classdef DataAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FunctionPathMenu              matlab.ui.container.Menu
        SelectpathDataProcMenu_2      matlab.ui.container.Menu
        SelectpathPlotIndivMenu_2     matlab.ui.container.Menu
        SelectpathPlotGroupMenu_2     matlab.ui.container.Menu
        SelectpathOtherFuncMenu_2     matlab.ui.container.Menu
        GridLayoutRoot                matlab.ui.container.GridLayout
        GridLayoutBottom              matlab.ui.container.GridLayout
        BasicPlotPanel                matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        PlotFunctionsIndividualPanel  matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        parameter5TextArea            matlab.ui.control.TextArea
        PlotFunc5DropDown             matlab.ui.control.DropDown
        parameter4TextArea            matlab.ui.control.TextArea
        parameter3TextArea            matlab.ui.control.TextArea
        parameter2TextArea            matlab.ui.control.TextArea
        parameter1TextArea            matlab.ui.control.TextArea
        PlotFunc4DropDown             matlab.ui.control.DropDown
        PlotFunc3DropDown             matlab.ui.control.DropDown
        PlotFunc2DropDown             matlab.ui.control.DropDown
        PlotFunc1DropDown             matlab.ui.control.DropDown
        GroupingPlotPanel             matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        PlotFunctionsGroupingPanel    matlab.ui.container.Panel
        GridLayout33                  matlab.ui.container.GridLayout
        GrpParameter5TextArea         matlab.ui.control.TextArea
        GrpParameter4TextArea         matlab.ui.control.TextArea
        GrpParameter3TextArea         matlab.ui.control.TextArea
        GrpParameter2TextArea         matlab.ui.control.TextArea
        GrpParameter1TextArea         matlab.ui.control.TextArea
        GrpPlotFunc5DropDown          matlab.ui.control.DropDown
        GrpPlotFunc4DropDown          matlab.ui.control.DropDown
        GrpPlotFunc3DropDown          matlab.ui.control.DropDown
        GrpPlotFunc2DropDown          matlab.ui.control.DropDown
        GrpPlotFunc1DropDown          matlab.ui.control.DropDown
        GridLayout24                  matlab.ui.container.GridLayout
        Group1subjectsPanel           matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        RefreshButton_2               matlab.ui.control.Button
        Group1subjectsListBox         matlab.ui.control.ListBox
        EnablegroupingplotCheckBox    matlab.ui.control.CheckBox
        GroupnamePanel                matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        Grp2EditField                 matlab.ui.control.EditField
        Grp2EditFieldLabel            matlab.ui.control.Label
        Grp1EditField                 matlab.ui.control.EditField
        Grp1EditFieldLabel            matlab.ui.control.Label
        GridLayoutTop                 matlab.ui.container.GridLayout
        SaveRunPanel                  matlab.ui.container.Panel
        GridLayout26                  matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout34                  matlab.ui.container.GridLayout
        IdleLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        RestoreoptionsButton          matlab.ui.control.Button
        BackupoptionsButton           matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        ArchivePathPanel              matlab.ui.container.Panel
        GridLayout18                  matlab.ui.container.GridLayout
        ArchivepathTextArea           matlab.ui.control.TextArea
        SelectArchivePathButton       matlab.ui.control.Button
        DataSelectionPanel            matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout22                  matlab.ui.container.GridLayout
        GridLayout35                  matlab.ui.container.GridLayout
        DataTypeDropDown              matlab.ui.control.DropDown
        DailyprogressplotCheckBox     matlab.ui.control.CheckBox
        TaskfilterPanel               matlab.ui.container.Panel
        GridLayout30                  matlab.ui.container.GridLayout
        GridLayout31                  matlab.ui.container.GridLayout
        FilterEditField               matlab.ui.control.EditField
        FilterEditFieldLabel          matlab.ui.control.Label
        EnabletaskfilterCheckBox      matlab.ui.control.CheckBox
        DataprocessingfunctionPanel   matlab.ui.container.Panel
        GridLayout32                  matlab.ui.container.GridLayout
        DataExtractFunctionDropDown   matlab.ui.control.DropDown
        DataMergeFunctionDropDown     matlab.ui.control.DropDown
        DatamergingmodePanel          matlab.ui.container.Panel
        GridLayout28                  matlab.ui.container.GridLayout
        SelectButton                  matlab.ui.control.StateButton
        MergeButton                   matlab.ui.control.StateButton
        RawButton                     matlab.ui.control.StateButton
        GridLayout21                  matlab.ui.container.GridLayout
        SubjectpathPanel              matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        SelectpathButton_2            matlab.ui.control.Button
        SubjectpathTextArea           matlab.ui.control.TextArea
        FilepathtypicalPanel          matlab.ui.container.Panel
        GridLayout15                  matlab.ui.container.GridLayout
        OnlyselectthedatainthispathwindowCheckBox  matlab.ui.control.CheckBox
        SelectpathButton              matlab.ui.control.Button
        TypicalFilepathTextArea       matlab.ui.control.TextArea
        TargetsubjectsPanel           matlab.ui.container.Panel
        GridLayout13                  matlab.ui.container.GridLayout
        TargetsubjectsListBox         matlab.ui.control.ListBox
        RefreshButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        oldPath = path;
        mfilePath = fileparts(mfilename('fullpath'));
        path_dataFun;
        path_plotIndFun;
        path_plotGrpFun;
        path_toolFun;
    end
    
    methods (Access = private)
        
        function bak = data_backup(app)
            bak = struct;
            bak.check_onlysbj = app.OnlyselectthedatainthispathwindowCheckBox.Value;
            bak.text_filepath = app.TypicalFilepathTextArea.Value;
            bak.text_sbjpath = app.SubjectpathTextArea.Value;
            %             bak.item_tarsbj = app.TargetsubjectsListBox.Items;
            bak.val_tarsbj = app.TargetsubjectsListBox.Value;
            bak.check_progplot = app.DailyprogressplotCheckBox.Value;
            bak.val_datatype = app.DataTypeDropDown.Value;
            bak.fun_dataExtr = app.DataExtractFunctionDropDown.Value;
            bak.fun_dataMerg = app.DataMergeFunctionDropDown.Value;
            bak.val_buttMerg = [app.RawButton.Value,app.MergeButton.Value,app.SelectButton.Value];
            bak.check_filter = app.EnabletaskfilterCheckBox.Value;
            bak.text_keyword = app.FilterEditField.Value;
            bak.text_archive = app.ArchivepathTextArea.Value;
            fun_plot1 = app.PlotFunc1DropDown.Value;
            fun_plot2 = app.PlotFunc2DropDown.Value;
            fun_plot3 = app.PlotFunc3DropDown.Value;
            fun_plot4 = app.PlotFunc4DropDown.Value;
            fun_plot5 = app.PlotFunc5DropDown.Value;
            bak.fun_plot = {fun_plot1,fun_plot2,fun_plot3,fun_plot4,fun_plot5};
            text_plot1 = app.parameter1TextArea.Value;
            text_plot2 = app.parameter2TextArea.Value;
            text_plot3 = app.parameter3TextArea.Value;
            text_plot4 = app.parameter4TextArea.Value;
            text_plot5 = app.parameter5TextArea.Value;
            bak.text_plot = {text_plot1,text_plot2,text_plot3,text_plot4,text_plot5};
            bak.check_group = app.EnablegroupingplotCheckBox.Value;
            bak.text_grp1 = app.Grp1EditField.Value;
            bak.text_grp2 = app.Grp2EditField.Value;
            %             bak.item_grpsbj = app.Group1subjectsListBox.Items;
            bak.val_grpsbj = app.Group1subjectsListBox.Value;
            fun_grpplot1 = app.GrpPlotFunc1DropDown.Value;
            fun_grpplot2 = app.GrpPlotFunc2DropDown.Value;
            fun_grpplot3 = app.GrpPlotFunc3DropDown.Value;
            fun_grpplot4 = app.GrpPlotFunc4DropDown.Value;
            fun_grpplot5 = app.GrpPlotFunc5DropDown.Value;
            bak.fun_grpplot = {fun_grpplot1,fun_grpplot2,fun_grpplot3,fun_grpplot4,fun_grpplot5};
            text_grpplot1 = app.GrpParameter1TextArea.Value;
            text_grpplot2 = app.GrpParameter2TextArea.Value;
            text_grpplot3 = app.GrpParameter3TextArea.Value;
            text_grpplot4 = app.GrpParameter4TextArea.Value;
            text_grpplot5 = app.GrpParameter5TextArea.Value;
            bak.text_grpplot = {text_grpplot1,text_grpplot2,text_grpplot3,text_grpplot4,text_grpplot5};
            bak.path_dataFun = app.path_dataFun;
            bak.path_plotIndFun = app.path_plotIndFun;
            bak.path_plotGrpFun = app.path_plotGrpFun;
            bak.path_toolFun = app.path_toolFun;
        end
        
        function data_restore(app,bak)
            app.path_dataFun = bak.path_dataFun;
            app.path_plotIndFun = bak.path_plotIndFun;
            app.path_plotGrpFun = bak.path_plotGrpFun;
            app.path_toolFun = bak.path_toolFun;
            checkFunPath(app,'check');
            addpath(app.path_dataFun,app.path_plotIndFun,app.path_plotGrpFun,genpath(app.path_toolFun));
            refreshFun(app);
            
            app.OnlyselectthedatainthispathwindowCheckBox.Value = bak.check_onlysbj;
            app.TypicalFilepathTextArea.Value = bak.text_filepath;
            app.SubjectpathTextArea.Value = bak.text_sbjpath;
            refreshTarSbj(app);
            errMsg = {};
            if all(ismember(bak.val_tarsbj,app.TargetsubjectsListBox.Items))
                app.TargetsubjectsListBox.Value = bak.val_tarsbj;
            else
                app.TargetsubjectsListBox.Items = bak.val_tarsbj;
                app.TargetsubjectsListBox.Value = bak.val_tarsbj;
                errMsg{end+1} = 'Some Subjects are not found but pretendedly loaded';
            end
            app.DailyprogressplotCheckBox.Value = bak.check_progplot;
            app.DataTypeDropDown.Value = bak.val_datatype;
            if any(ismember(app.DataExtractFunctionDropDown.Items,bak.fun_dataExtr))
                app.DataExtractFunctionDropDown.Value = bak.fun_dataExtr;
            else
                errMsg{end+1} = 'Cannot find Data-processing function';
            end
            if any(ismember(app.DataMergeFunctionDropDown.Items,bak.fun_dataMerg))
                app.DataMergeFunctionDropDown.Value = bak.fun_dataMerg;
            else
                errMsg{end+1} = 'Cannot find Data-merging function';
            end
            app.RawButton.Value = bak.val_buttMerg(1);
            app.MergeButton.Value = bak.val_buttMerg(2);
            app.SelectButton.Value = bak.val_buttMerg(3);
            app.EnabletaskfilterCheckBox.Value = bak.check_filter;
            app.FilterEditField.Value = bak.text_keyword;
            app.ArchivepathTextArea.Value = bak.text_archive;
            idx_plotInd = ismember(bak.fun_plot,app.PlotFunc1DropDown.Items);
            if(idx_plotInd(1))
                app.PlotFunc1DropDown.Value = bak.fun_plot{1};
            end
            if(idx_plotInd(2))
                app.PlotFunc2DropDown.Value = bak.fun_plot{2};
            end
            if(idx_plotInd(3))
                app.PlotFunc3DropDown.Value = bak.fun_plot{3};
            end
            if(idx_plotInd(4))
                app.PlotFunc4DropDown.Value = bak.fun_plot{4};
            end
            if(idx_plotInd(5))
                app.PlotFunc5DropDown.Value = bak.fun_plot{5};
            end
            if ~all(idx_plotInd)
                errMsg{end+1} = 'Some individual-plot functions are not found';
            end
            app.parameter1TextArea.Value = bak.text_plot{1};
            app.parameter2TextArea.Value = bak.text_plot{2};
            app.parameter3TextArea.Value = bak.text_plot{3};
            app.parameter4TextArea.Value = bak.text_plot{4};
            app.parameter5TextArea.Value = bak.text_plot{5};
            app.EnablegroupingplotCheckBox.Value = bak.check_group;
            app.Grp1EditField.Value = bak.text_grp1;
            app.Grp2EditField.Value = bak.text_grp2;
            refreshGrpSbj(app);
            if any(ismember(app.Group1subjectsListBox.Items,bak.val_grpsbj))
                app.Group1subjectsListBox.Value = bak.val_grpsbj;
            end
            idx_plotGrp = ismember(bak.fun_grpplot,app.GrpPlotFunc1DropDown.Items);
            if(idx_plotGrp(1))
                app.GrpPlotFunc1DropDown.Value = bak.fun_grpplot{1};
            end
            if(idx_plotGrp(2))
                app.GrpPlotFunc2DropDown.Value = bak.fun_grpplot{2};
            end
            if(idx_plotGrp(3))
                app.GrpPlotFunc3DropDown.Value = bak.fun_grpplot{3};
            end
            if(idx_plotGrp(4))
                app.GrpPlotFunc4DropDown.Value = bak.fun_grpplot{4};
            end
            if(idx_plotGrp(5))
                app.GrpPlotFunc5DropDown.Value = bak.fun_grpplot{5};
            end
            if ~all(idx_plotGrp)
                errMsg{end+1} = 'Some group-plot functions are not found';
            end
            app.GrpParameter1TextArea.Value = bak.text_grpplot{1};
            app.GrpParameter2TextArea.Value = bak.text_grpplot{2};
            app.GrpParameter3TextArea.Value = bak.text_grpplot{3};
            app.GrpParameter4TextArea.Value = bak.text_grpplot{4};
            app.GrpParameter5TextArea.Value = bak.text_grpplot{5};
            if ~isempty(errMsg)
                uialert(app.UIFigure,errMsg,'Options Restoration Error');
            end
        end
        
        function [orderName,task_suffix,sign] = genTarPath(~,expPath,sbjPath)
            orderName = {};
            if length(sbjPath)>=1 && length(expPath)>=1 && isfolder(sbjPath{1}) && isfolder(expPath{1})
                sbjPath = sbjPath{1}; expPath = expPath{1};
                sbj_suffix = erase(expPath,sbjPath);
                ind_sep = find(sbj_suffix==filesep);
                if length(ind_sep)>1
                    task_suffix = sbj_suffix(ind_sep(2):end); % erase subject name
                else
                    task_suffix = '';
                end
                sbjDir = dir(sbjPath);
                for i=1:length(sbjDir)
                    if isequal(sbjDir(i).name,'.') || isequal(sbjDir(i).name, '..') || ~sbjDir(i).isdir
                        continue;
                    end
                    tmp_folder = [sbjPath, filesep, sbjDir(i).name, task_suffix];
                    if isfolder(tmp_folder) && ~strcmp(tmp_folder,fullfile(sbjPath,'1_FigArchive'))
                        orderName{end+1} = sbjDir(i).name;
                    end
                end
                if isempty(orderName)
                    sign = false;
                else
                    sign = true;
                end
            else
                task_suffix = '';
                sign = false;
            end
        end
        
        function checkFunPath(app,method)
            switch method
                case lower('reset')
                    toReset = true;
                case lower('check')
                    toReset = false;
                otherwise
                    toReset = false;
            end
            if toReset || ~isfield(app,'path_dataFun') || ~isfolder(app.path_dataFun)
                app.path_dataFun = fullfile(app.mfilePath,['..',filesep,'..',filesep,'Function_Data']);
            end
            if toReset || ~isfield(app,'path_plotIndFun') || ~isfolder(app.path_plotIndFun)
                app.path_plotIndFun = fullfile(app.mfilePath,['..',filesep,'..',filesep,'Function_Plot',filesep,'Individual']);
            end
            if toReset || ~isfield(app,'path_plotGrpFun') || ~isfolder(app.path_plotGrpFun)
                app.path_plotGrpFun = fullfile(app.mfilePath,['..',filesep,'..',filesep,'Function_Plot',filesep,'Group']);
            end
            if toReset || ~isfield(app,'path_toolFun') || ~isfolder(app.path_toolFun)
                app.path_toolFun = fullfile(app.mfilePath,['..',filesep,'..',filesep,'Function_Tool']);
            end
        end
        
        function refreshTarSbj(app)
            sbjPath = app.SubjectpathTextArea.Value;
            expPath = app.TypicalFilepathTextArea.Value;
            [orderName,~,sign] = genTarPath(app,expPath,sbjPath);
            if sign
                app.TargetsubjectsListBox.Items = orderName;
            else
                app.TargetsubjectsListBox.Items = {};
            end
            app.TargetsubjectsListBox.Value = {};
        end
        
        function refreshGrpSbj(app)
            tarSbj = app.TargetsubjectsListBox.Value;
            if ~isempty(tarSbj)
                app.Group1subjectsListBox.Items = tarSbj;
            else
                app.Group1subjectsListBox.Items = {};
            end
            app.Group1subjectsListBox.Value = {};
        end
        
        function bak = data_default(app)
            bak = struct;
            bak.check_onlysbj = false;
            bak.text_filepath = {app.mfilePath};
            bak.text_sbjpath = {app.mfilePath};
            %             bak.item_tarsbj = app.TargetsubjectsListBox.Items;
            bak.val_tarsbj = {};
            bak.check_progplot = true;
            bak.val_datatype = app.DataTypeDropDown.Items{1};
            bak.fun_dataExtr = app.DataExtractFunctionDropDown.Items{1};
            bak.fun_dataMerg = app.DataMergeFunctionDropDown.Items{1};
            bak.val_buttMerg = [false,false,true];
            bak.check_filter = false;
            bak.text_keyword = '';
            bak.text_archive = {pwd};
            fun_plot1 = app.PlotFunc1DropDown.Items{1};
            fun_plot2 = app.PlotFunc2DropDown.Items{1};
            fun_plot3 = app.PlotFunc3DropDown.Items{1};
            fun_plot4 = app.PlotFunc4DropDown.Items{1};
            fun_plot5 = app.PlotFunc5DropDown.Items{1};
            bak.fun_plot = {fun_plot1,fun_plot2,fun_plot3,fun_plot4,fun_plot5};
            text_plot1 = '';
            text_plot2 = '';
            text_plot3 = '';
            text_plot4 = '';
            text_plot5 = '';
            bak.text_plot = {text_plot1,text_plot2,text_plot3,text_plot4,text_plot5};
            bak.check_group = false;
            bak.text_grp1 = '';
            bak.text_grp2 = '';
            %             bak.item_grpsbj = app.Group1subjectsListBox.Items;
            bak.val_grpsbj = {};
            fun_grpplot1 = app.GrpPlotFunc1DropDown.Items{1};
            fun_grpplot2 = app.GrpPlotFunc2DropDown.Items{1};
            fun_grpplot3 = app.GrpPlotFunc3DropDown.Items{1};
            fun_grpplot4 = app.GrpPlotFunc4DropDown.Items{1};
            fun_grpplot5 = app.GrpPlotFunc5DropDown.Items{1};
            bak.fun_grpplot = {fun_grpplot1,fun_grpplot2,fun_grpplot3,fun_grpplot4,fun_grpplot5};
            text_grpplot1 = '';
            text_grpplot2 = '';
            text_grpplot3 = '';
            text_grpplot4 = '';
            text_grpplot5 = '';
            bak.text_grpplot = {text_grpplot1,text_grpplot2,text_grpplot3,text_grpplot4,text_grpplot5};
            checkFunPath(app,'reset');
            bak.path_dataFun = app.path_dataFun;
            bak.path_plotIndFun = app.path_plotIndFun;
            bak.path_plotGrpFun = app.path_plotGrpFun;
            bak.path_toolFun = app.path_toolFun;
        end
        
        function refreshFun(app)
            app.DataExtractFunctionDropDown.Items = {'-- Select DataExtract Function'};
            app.DataMergeFunctionDropDown.Items = {'-- Select DataMerge Function'};
            app.PlotFunc1DropDown.Items = {'-- Select function'};
            app.PlotFunc2DropDown.Items = {'-- Select function'};
            app.PlotFunc3DropDown.Items = {'-- Select function'};
            app.PlotFunc4DropDown.Items = {'-- Select function'};
            app.PlotFunc5DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc1DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc2DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc3DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc4DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc5DropDown.Items = {'-- Select function'};
            
            fun_dataProc = dir(fullfile(app.path_dataFun,'*.m'));
            fun_plotInd = dir(fullfile(app.path_plotIndFun,'*.m'));
            fun_plotGrp = dir(fullfile(app.path_plotGrpFun,'*.m'));
            if ~isempty(fun_dataProc)
                for i=1:length(fun_dataProc)
                    if ~any(ismember(app.DataExtractFunctionDropDown.Items,fun_dataProc(i).name))
                        app.DataExtractFunctionDropDown.Items{end+1} = fun_dataProc(i).name;
                    end
                    if ~any(ismember(app.DataMergeFunctionDropDown.Items,fun_dataProc(i).name))
                        app.DataMergeFunctionDropDown.Items{end+1} = fun_dataProc(i).name;
                    end
                end
            end
            if ~isempty(fun_plotInd)
                for i=1:length(fun_plotInd)
                    if ~any(ismember(app.PlotFunc1DropDown.Items,fun_plotInd(i).name))
                        app.PlotFunc1DropDown.Items{end+1} = fun_plotInd(i).name;
                    end
                    if ~any(ismember(app.PlotFunc2DropDown.Items,fun_plotInd(i).name))
                        app.PlotFunc2DropDown.Items{end+1} = fun_plotInd(i).name;
                    end
                    if ~any(ismember(app.PlotFunc3DropDown.Items,fun_plotInd(i).name))
                        app.PlotFunc3DropDown.Items{end+1} = fun_plotInd(i).name;
                    end
                    if ~any(ismember(app.PlotFunc4DropDown.Items,fun_plotInd(i).name))
                        app.PlotFunc4DropDown.Items{end+1} = fun_plotInd(i).name;
                    end
                    if ~any(ismember(app.PlotFunc5DropDown.Items,fun_plotInd(i).name))
                        app.PlotFunc5DropDown.Items{end+1} = fun_plotInd(i).name;
                    end
                end
            end
            if ~isempty(fun_plotGrp)
                for i=1:length(fun_plotGrp)
                    if ~any(ismember(app.GrpPlotFunc1DropDown.Items,fun_plotGrp(i).name))
                        app.GrpPlotFunc1DropDown.Items{end+1} = fun_plotGrp(i).name;
                    end
                    if ~any(ismember(app.GrpPlotFunc2DropDown.Items,fun_plotGrp(i).name))
                        app.GrpPlotFunc2DropDown.Items{end+1} = fun_plotGrp(i).name;
                    end
                    if ~any(ismember(app.GrpPlotFunc3DropDown.Items,fun_plotGrp(i).name))
                        app.GrpPlotFunc3DropDown.Items{end+1} = fun_plotGrp(i).name;
                    end
                    if ~any(ismember(app.GrpPlotFunc4DropDown.Items,fun_plotGrp(i).name))
                        app.GrpPlotFunc4DropDown.Items{end+1} = fun_plotGrp(i).name;
                    end
                    if ~any(ismember(app.GrpPlotFunc5DropDown.Items,fun_plotGrp(i).name))
                        app.GrpPlotFunc5DropDown.Items{end+1} = fun_plotGrp(i).name;
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             set(app.UIFigure,'renderer','opengl'); % 'painter'
            
            bak_auto = fullfile(app.mfilePath,[mfilename,'_DefaultSave','.mat']);
            if isfile(bak_auto)
                load(bak_auto,'bak');
                data_restore(app,bak)
            else
                bak = data_default(app);
                data_restore(app,bak);
            end
            
            app.IdleLabel.Text = 'Idle';
            app.Lamp.Color = [0.8,0.8,0.8];
            drawnow;
        end

        % Value changed function: RawButton
        function RawButtonValueChanged(app, event)
            value = app.RawButton.Value;
            if value
                app.MergeButton.Value = false;
                app.SelectButton.Value = false;
            elseif ~app.MergeButton.Value && ~app.SelectButton.Value
                app.RawButton.Value = true;
            end
        end

        % Value changed function: MergeButton
        function MergeButtonValueChanged(app, event)
            value = app.MergeButton.Value;
            if value
                app.RawButton.Value = false;
                app.SelectButton.Value = false;
            elseif ~app.RawButton.Value && ~app.SelectButton.Value
                app.MergeButton.Value = true;
            end
        end

        % Value changed function: SelectButton
        function SelectButtonValueChanged(app, event)
            value = app.SelectButton.Value;
            if value
                app.RawButton.Value = false;
                app.MergeButton.Value = false;
            elseif ~app.RawButton.Value && ~app.MergeButton.Value
                app.SelectButton.Value = true;
            end
        end

        % Button pushed function: SelectpathButton
        function SelectpathButtonPushed(app, event)
            value = cellstr(app.TypicalFilepathTextArea.Value);
            if length(value)==1 && isfolder(value{1})
                path = value{1};
            else
                path = [];
            end
            result = uigetdir(path,'Select the directory containing data file');
            if result~=0
                app.TypicalFilepathTextArea.Value = result;
            end
        end

        % Button pushed function: SelectpathButton_2
        function SelectpathButton_2Pushed(app, event)
            value = cellstr(app.SubjectpathTextArea.Value);
            if length(value)==1 && isfolder(value{1})
                path = value{1};
            else
                path = [];
            end
            result = uigetdir(path,'Select the directory containing the data of different subjects');
            if result~=0
                app.SubjectpathTextArea.Value = result;
            end
        end

        % Button pushed function: SelectArchivePathButton
        function SelectArchivePathButtonPushed(app, event)
            value = cellstr(app.ArchivepathTextArea.Value);
            if length(value)==1 && isfolder(value{1})
                path = value{1};
            else
                path = [];
            end
            result = uigetdir(path,'Select the directory to archive results');
            if result~=0
                app.ArchivepathTextArea.Value = result;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshTarSbj(app);
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            refreshGrpSbj(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            bak = data_default(app);
            data_restore(app,bak);
        end

        % Button pushed function: BackupoptionsButton
        function BackupoptionsButtonPushed(app, event)
            bak = data_backup(app);
            
            [file,path,indx] = uiputfile(fullfile(app.mfilePath,[mfilename,'_Preset','.mat']),'Please select the path to save options');
            if indx
                save(fullfile(path,file),'bak');
            end
        end

        % Button pushed function: RestoreoptionsButton
        function RestoreoptionsButtonPushed(app, event)
            [file,path,indx] = uigetfile(fullfile(app.mfilePath,'*.mat'),'Please select the saved preset');
            if indx
                load(fullfile(path,file),'bak');
                data_restore(app,bak);
            end
        end

        % Menu selected function: SelectpathDataProcMenu_2
        function SelectpathDataProcMenu_2Selected(app, event)
            oldpath = app.path_dataFun;
            result = uigetdir(oldpath,'Select the directory containing data-processing functions');
            if result~=0
                app.path_dataFun = result;
            end
            refreshFun(app);
        end

        % Menu selected function: SelectpathPlotIndivMenu_2
        function SelectpathPlotIndivMenu_2Selected(app, event)
            oldpath = app.path_plotIndFun;
            result = uigetdir(oldpath,'Select the directory containing individual-plot functions');
            if result~=0
                app.path_plotIndFun = result;
            end
            refreshFun(app);
        end

        % Menu selected function: SelectpathPlotGroupMenu_2
        function SelectpathPlotGroupMenu_2Selected(app, event)
            oldpath = app.path_plotGrpFun;
            result = uigetdir(oldpath,'Select the directory containing group-plot functions');
            if result~=0
                app.path_plotGrpFun = result;
            end
            refreshFun(app);
        end

        % Menu selected function: SelectpathOtherFuncMenu_2
        function SelectpathOtherFuncMenu_2Selected(app, event)
            oldpath = app.path_toolFun;
            result = uigetdir(oldpath,'Select the directory containing other required functions');
            if result~=0
                app.path_toolFun = result;
            end
            refreshFun(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.IdleLabel.Text = 'Busy';
            app.Lamp.Color = [0.92,0.47,0.27];
            drawnow;
            try
                oriPath = pwd;
                mfile = mfilename('fullpath');
                bak = data_backup(app);
                % Obtain target path containing data
                tarPath = {};
                if bak.check_onlysbj
                    for i=1:length(bak.text_filepath)
                        if isfolder(bak.text_filepath{i})
                            tarPath{end+1} = bak.text_filepath{i};
                        end
                    end
                else
                    [~,suffix,~] = genTarPath(app,bak.text_filepath,bak.text_sbjpath);
                    for i=1:length(bak.val_tarsbj)
                        tarPath{end+1} = [bak.text_sbjpath{1},filesep,bak.val_tarsbj{i},suffix];
                    end
                end
                
                % Data Processing & Plot
                btAll2d = {};
                if bak.check_filter
                    taskfilter = [',','bak.text_keyword'];
                else
                    taskfilter = '';
                end
                text_archive = fullfile(bak.text_archive{1},datestr(datetime('now'),'yyyymmdd-HHMMSS'));
                if ~exist(text_archive,'dir')
                    mkdir(text_archive);
                end
                arc_data = {};
                arc_func = {[mfile,'.mlapp'],fullfile(app.path_dataFun,bak.fun_dataExtr)};
                for i=1:length(tarPath)
                    dataPath = tarPath{i};
                    cd(dataPath);
                    % Data Extract
                    switch bak.val_datatype
                        case '.txt'
                            FileNames = arrayfun(@(x)x.name, dir('*.txt'), 'UniformOutput', false);
                        case '.mat'
                            FileNames = arrayfun(@(x)x.name, dir('*DSRT*.mat'), 'UniformOutput', false);
                        otherwise
                            uialert(app.UIFigure,'Please choose suitable data type','Warning','Icon','Warning');
                            return;
                    end
                    btAll = cell(1,length(FileNames));
                    for j=1:length(FileNames)
                        [~,fun_dataExtr,~] = fileparts(bak.fun_dataExtr);
                        btAll{j} = eval([fun_dataExtr,'(FileNames{j},bak.check_progplot,text_archive)']);
                    end
                    if ~strcmp(bak.fun_dataMerg,app.DataMergeFunctionDropDown.Items{1}) %'-- Select DataMerge Function'
                        [~,fun_dataMerg,~] = fileparts(bak.fun_dataMerg);
                        btAll = eval([fun_dataMerg,'(btAll,',num2str(find(bak.val_buttMerg,1)),')']);
                        if i==1
                            arc_func{end+1} = fullfile(app.path_dataFun,bak.fun_dataMerg);
                        end
                    end
                    % Save Data
                    savename = 'bmixedAll_' + upper(btAll{end}.Subject(1));
                    save(savename, 'btAll')
                    
                    curCol = size(btAll,2);
                    btAll2d(end+1,1:curCol) = btAll;
                    arcCol = length(FileNames);
                    if bak.check_onlysbj
                        arc_data(end+1:end+arcCol) = fullfile(dataPath,FileNames)';
                    else
                        arc_data(end+1,1:arcCol) = fullfile(dataPath,FileNames)';
                    end
                    % Plot for each subject
                    idx_funPlot = find(~ismember(bak.fun_plot,'-- Select function'));
                    h_plot = cell(1,length(idx_funPlot));
                    cd(text_archive);
                    for j=1:length(idx_funPlot)
                        arc_func{end+1} = fullfile(app.path_plotIndFun,bak.fun_plot{idx_funPlot(j)});
                        [~,fun_plot,~] = fileparts(bak.fun_plot{idx_funPlot(j)});
                        text_plot = bak.text_plot{idx_funPlot(j)};
                        vargin = [];
                        for k=1:length(text_plot)
                            if ~isempty(text_plot{k})
                                vargin = [vargin,',',text_plot{k}];
                            end
                        end
                        h_plot{j} = eval([fun_plot,'(btAll',taskfilter,vargin,')']);
                    end
                    cd(tarPath{i});
                end
                tarSbj = bak.val_tarsbj;
                % Group plot
                if bak.check_group && ~bak.check_onlysbj
                    grpVar = string(zeros(length(tarSbj),1));
                    grpVar(:) = "";
                    for i=1:length(tarSbj)
                        if ismember(tarSbj{i},bak.val_grpsbj)
                            grpVar(i) = bak.text_grp1;
                        else
                            grpVar(i) = bak.text_grp2;
                        end
                    end
                    cd(text_archive);
                    save('bmixedAllsbj','btAll2d','grpVar');
                    
                    idx_fungrpPlot = find(~ismember(bak.fun_grpplot,'-- Select function'));
                    for i=1:length(idx_fungrpPlot)
                        arc_func{end+1} = fullfile(app.path_plotGrpFun,bak.fun_grpplot{idx_fungrpPlot(i)});
                        [~,fun_grpplot,~] = fileparts(bak.fun_grpplot{idx_fungrpPlot(i)});
                        text_grpplot = bak.text_grpplot{idx_fungrpPlot(i)};
                        vargin = [];
                        for j=1:length(text_grpplot)
                            if ~isempty(text_grpplot{j})
                                vargin = [vargin,',',text_grpplot{j}];
                            end
                        end
                        eval([fun_grpplot,'(btAll2d,grpVar',taskfilter,vargin,')']);
                    end
                else
                    cd(text_archive);
                    save('bmixedAllsbj','btAll2d');
                end
                % Backup
                
                save(fullfile(text_archive,[mfilename,'_OptionsBackup','.mat']),'bak');
                
                zip(fullfile(text_archive,'BAK_Func'),arc_func(isfile(arc_func)));
                if ~bak.check_onlysbj
                    path_temp = fullfile(text_archive,'BAK_Data');
                    mkdir(path_temp);
                    for i=1:length(tarSbj)
                        path_sbj = fullfile(path_temp,tarSbj{i});
                        mkdir(path_sbj);
                        sbj_data = arc_data(i,:);
                        sbj_data = sbj_data(isfile(sbj_data(~cellfun(@isempty,sbj_data))));
                        for j=1:length(sbj_data)
                            copyfile(sbj_data{j},path_sbj);
                        end
                    end
                    zip(fullfile(text_archive,'BAK_Data'),path_temp);
                    rmdir(path_temp,'s');
                else
                    zip(fullfile(text_archive,'BAK_Data'),arc_data(isfile(arc_data)));
                end
                cd(oriPath);
                app.IdleLabel.Text = 'Idle';
                app.Lamp.Color = [0.8,0.8,0.8];
                drawnow;
            catch ME
                app.IdleLabel.Text = 'Idle';
                app.Lamp.Color = [0.8,0.8,0.8];
                drawnow;
                uialert(app.UIFigure,getReport(ME,'basic','hyperlinks','off'),'Error');
                rethrow(ME);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Do you want to save current options?',...
                'Confirm Close','Options',{'Save','Discard','Cancel'},...
                'DefaultOption',2,'CancelOption',3);
            switch selection
                case 'Save'
                    bak = data_backup(app);
                    save(fullfile(app.mfilePath,[mfilename,'_DefaultSave','.mat']),'bak');
                    
                    path(app.oldPath);
                    
                    delete(app);
                case 'Discard'
                    path(app.oldPath);
                    
                    delete(app);
                case 'Cancel'
                    % pass
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FunctionPathMenu = uimenu(app.UIFigure);
            app.FunctionPathMenu.Text = 'Function Path';
            app.SelectpathDataProcMenu_2 = uimenu(app.FunctionPathMenu);
            app.SelectpathDataProcMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectpathDataProcMenu_2Selected, true);
            app.SelectpathDataProcMenu_2.Text = 'Select path (Data Proc)';
            app.SelectpathPlotIndivMenu_2 = uimenu(app.FunctionPathMenu);
            app.SelectpathPlotIndivMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectpathPlotIndivMenu_2Selected, true);
            app.SelectpathPlotIndivMenu_2.Text = 'Select path (Plot Indiv)';
            app.SelectpathPlotGroupMenu_2 = uimenu(app.FunctionPathMenu);
            app.SelectpathPlotGroupMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectpathPlotGroupMenu_2Selected, true);
            app.SelectpathPlotGroupMenu_2.Text = 'Select path (Plot Group)';
            app.SelectpathOtherFuncMenu_2 = uimenu(app.FunctionPathMenu);
            app.SelectpathOtherFuncMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectpathOtherFuncMenu_2Selected, true);
            app.SelectpathOtherFuncMenu_2.Text = 'Select path (Other Func)';
            app.GridLayoutRoot = uigridlayout(app.UIFigure);
            app.GridLayoutTop = uigridlayout(app.GridLayoutRoot);
            app.DataSelectionPanel = uipanel(app.GridLayoutTop);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.GridLayout2 = uigridlayout(app.DataSelectionPanel);
            app.TargetsubjectsPanel = uipanel(app.GridLayout2);
            app.TargetsubjectsPanel.Title = 'Target subjects';
            app.GridLayout13 = uigridlayout(app.TargetsubjectsPanel);
            app.RefreshButton = uibutton(app.GridLayout13, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TargetsubjectsListBox = uilistbox(app.GridLayout13);
            app.TargetsubjectsListBox.Items = {'Draco', 'Gregory', 'Harry', 'Hermione', 'Ron', 'Vincent'};
            app.TargetsubjectsListBox.Multiselect = 'on';
            app.TargetsubjectsListBox.Value = {};
            app.GridLayout21 = uigridlayout(app.GridLayout2);
            app.FilepathtypicalPanel = uipanel(app.GridLayout21);
            app.FilepathtypicalPanel.Title = 'File path (typical)';
            app.GridLayout15 = uigridlayout(app.FilepathtypicalPanel);
            app.TypicalFilepathTextArea = uitextarea(app.GridLayout15);
            app.TypicalFilepathTextArea.Value = {'C:\Users\RitaSkeeter\Desktop\AbusDumbledore\Hogwarts\Roster\HarryPotter\Potions'};
            app.SelectpathButton = uibutton(app.GridLayout15, 'push');
            app.SelectpathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathButtonPushed, true);
            app.SelectpathButton.Text = 'Select path';
            app.OnlyselectthedatainthispathwindowCheckBox = uicheckbox(app.GridLayout15);
            app.OnlyselectthedatainthispathwindowCheckBox.Text = 'Only select the data in this path window';
            app.SubjectpathPanel = uipanel(app.GridLayout21);
            app.SubjectpathPanel.Title = 'Subject path';
            app.GridLayout14 = uigridlayout(app.SubjectpathPanel);
            app.SubjectpathTextArea = uitextarea(app.GridLayout14);
            app.SubjectpathTextArea.Value = {'C:\Users\RitaSkeeter\Desktop\AbusDumbledore\Hogwarts\Roster'};
            app.SelectpathButton_2 = uibutton(app.GridLayout14, 'push');
            app.SelectpathButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectpathButton_2Pushed, true);
            app.SelectpathButton_2.Text = 'Select path';
            app.GridLayout22 = uigridlayout(app.GridLayout2);
            app.DatamergingmodePanel = uipanel(app.GridLayout22);
            app.DatamergingmodePanel.Title = 'Data merging mode';
            app.GridLayout28 = uigridlayout(app.DatamergingmodePanel);
            app.RawButton = uibutton(app.GridLayout28, 'state');
            app.RawButton.ValueChangedFcn = createCallbackFcn(app, @RawButtonValueChanged, true);
            app.RawButton.Text = 'Raw';
            app.MergeButton = uibutton(app.GridLayout28, 'state');
            app.MergeButton.ValueChangedFcn = createCallbackFcn(app, @MergeButtonValueChanged, true);
            app.MergeButton.Text = 'Merge';
            app.MergeButton.Value = true;
            app.SelectButton = uibutton(app.GridLayout28, 'state');
            app.SelectButton.ValueChangedFcn = createCallbackFcn(app, @SelectButtonValueChanged, true);
            app.SelectButton.Text = 'Select';
            app.DataprocessingfunctionPanel = uipanel(app.GridLayout22);
            app.DataprocessingfunctionPanel.Title = 'Data processing function';
            app.GridLayout32 = uigridlayout(app.DataprocessingfunctionPanel);
            app.DataMergeFunctionDropDown = uidropdown(app.GridLayout32);
            app.DataMergeFunctionDropDown.Items = {'-- Select DataMerge Function'};
            app.DataMergeFunctionDropDown.Value = '-- Select DataMerge Function';
            app.DataExtractFunctionDropDown = uidropdown(app.GridLayout32);
            app.DataExtractFunctionDropDown.Items = {'-- Select DataExtract Function'};
            app.DataExtractFunctionDropDown.Value = '-- Select DataExtract Function';
            app.TaskfilterPanel = uipanel(app.GridLayout22);
            app.TaskfilterPanel.Title = 'Task filter';
            app.GridLayout30 = uigridlayout(app.TaskfilterPanel);
            app.EnabletaskfilterCheckBox = uicheckbox(app.GridLayout30);
            app.EnabletaskfilterCheckBox.Text = 'Enable task filter';
            app.GridLayout31 = uigridlayout(app.GridLayout30);
            app.FilterEditFieldLabel = uilabel(app.GridLayout31);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.GridLayout31, 'text');
            app.GridLayout35 = uigridlayout(app.GridLayout22);
            app.DailyprogressplotCheckBox = uicheckbox(app.GridLayout35);
            app.DailyprogressplotCheckBox.Text = 'Daily progress plot';
            app.DailyprogressplotCheckBox.Value = true;
            app.DataTypeDropDown = uidropdown(app.GridLayout35);
            app.DataTypeDropDown.Items = {'-- Select data type', '.txt', '.mat'};
            app.DataTypeDropDown.Value = '-- Select data type';
            app.SaveRunPanel = uipanel(app.GridLayoutTop);
            app.SaveRunPanel.Title = 'Save & Run';
            app.GridLayout26 = uigridlayout(app.SaveRunPanel);
            app.ArchivePathPanel = uipanel(app.GridLayout26);
            app.ArchivePathPanel.Title = 'Archive Path';
            app.GridLayout18 = uigridlayout(app.ArchivePathPanel);
            app.SelectArchivePathButton = uibutton(app.GridLayout18, 'push');
            app.SelectArchivePathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectArchivePathButtonPushed, true);
            app.SelectArchivePathButton.Text = {'Select'; 'path'};
            app.ArchivepathTextArea = uitextarea(app.GridLayout18);
            app.Panel = uipanel(app.GridLayout26);
            app.GridLayout34 = uigridlayout(app.Panel);
            app.RunButton = uibutton(app.GridLayout34, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.ResetButton = uibutton(app.GridLayout34, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BackupoptionsButton = uibutton(app.GridLayout34, 'push');
            app.BackupoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @BackupoptionsButtonPushed, true);
            app.BackupoptionsButton.Text = {'Backup'; 'options'};
            app.RestoreoptionsButton = uibutton(app.GridLayout34, 'push');
            app.RestoreoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreoptionsButtonPushed, true);
            app.RestoreoptionsButton.Text = {'Restore'; 'options'};
            app.Lamp = uilamp(app.GridLayout34);
            app.IdleLabel = uilabel(app.GridLayout34);
            app.IdleLabel.Text = 'Idle';
            app.GridLayoutBottom = uigridlayout(app.GridLayoutRoot);
            app.GroupingPlotPanel = uipanel(app.GridLayoutBottom);
            app.GroupingPlotPanel.Title = 'Grouping Plot';
            app.GridLayout8 = uigridlayout(app.GroupingPlotPanel);
            app.GridLayout24 = uigridlayout(app.GridLayout8);
            app.GroupnamePanel = uipanel(app.GridLayout24);
            app.GroupnamePanel.Title = 'Group name';
            app.GridLayout5 = uigridlayout(app.GroupnamePanel);
            app.Grp1EditFieldLabel = uilabel(app.GridLayout5);
            app.Grp1EditFieldLabel.Text = 'Grp 1';
            app.Grp1EditField = uieditfield(app.GridLayout5, 'text');
            app.Grp1EditField.Value = 'Slytherin';
            app.Grp2EditFieldLabel = uilabel(app.GridLayout5);
            app.Grp2EditFieldLabel.Text = 'Grp 2';
            app.Grp2EditField = uieditfield(app.GridLayout5, 'text');
            app.Grp2EditField.Value = 'Gryffindor';
            app.EnablegroupingplotCheckBox = uicheckbox(app.GridLayout24);
            app.EnablegroupingplotCheckBox.Text = 'Enable grouping plot';
            app.Group1subjectsPanel = uipanel(app.GridLayout24);
            app.Group1subjectsPanel.Title = 'Group1 subjects';
            app.GridLayout9 = uigridlayout(app.Group1subjectsPanel);
            app.Group1subjectsListBox = uilistbox(app.GridLayout9);
            app.Group1subjectsListBox.Items = {'Draco', 'Gregory', 'Vincent'};
            app.Group1subjectsListBox.Multiselect = 'on';
            app.Group1subjectsListBox.Value = {};
            app.RefreshButton_2 = uibutton(app.GridLayout9, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.PlotFunctionsGroupingPanel = uipanel(app.GridLayout8);
            app.PlotFunctionsGroupingPanel.Title = 'Plot Functions - Grouping';
            app.GridLayout33 = uigridlayout(app.PlotFunctionsGroupingPanel);
            app.GrpPlotFunc1DropDown = uidropdown(app.GridLayout33);
            app.GrpPlotFunc1DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc1DropDown.Value = '-- Select function';
            app.GrpPlotFunc2DropDown = uidropdown(app.GridLayout33);
            app.GrpPlotFunc2DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc2DropDown.Value = '-- Select function';
            app.GrpPlotFunc3DropDown = uidropdown(app.GridLayout33);
            app.GrpPlotFunc3DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc3DropDown.Value = '-- Select function';
            app.GrpPlotFunc4DropDown = uidropdown(app.GridLayout33);
            app.GrpPlotFunc4DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc4DropDown.Value = '-- Select function';
            app.GrpPlotFunc5DropDown = uidropdown(app.GridLayout33);
            app.GrpPlotFunc5DropDown.Items = {'-- Select function'};
            app.GrpPlotFunc5DropDown.Value = '-- Select function';
            app.GrpParameter1TextArea = uitextarea(app.GridLayout33);
            app.GrpParameter2TextArea = uitextarea(app.GridLayout33);
            app.GrpParameter3TextArea = uitextarea(app.GridLayout33);
            app.GrpParameter4TextArea = uitextarea(app.GridLayout33);
            app.GrpParameter5TextArea = uitextarea(app.GridLayout33);
            app.BasicPlotPanel = uipanel(app.GridLayoutBottom);
            app.BasicPlotPanel.Title = 'Basic Plot';
            app.GridLayout4 = uigridlayout(app.BasicPlotPanel);
            app.PlotFunctionsIndividualPanel = uipanel(app.GridLayout4);
            app.PlotFunctionsIndividualPanel.Title = 'Plot Functions - Individual';
            app.GridLayout17 = uigridlayout(app.PlotFunctionsIndividualPanel);
            app.PlotFunc1DropDown = uidropdown(app.GridLayout17);
            app.PlotFunc1DropDown.Items = {'-- Select function'};
            app.PlotFunc1DropDown.Value = '-- Select function';
            app.PlotFunc2DropDown = uidropdown(app.GridLayout17);
            app.PlotFunc2DropDown.Items = {'-- Select function'};
            app.PlotFunc2DropDown.Value = '-- Select function';
            app.PlotFunc3DropDown = uidropdown(app.GridLayout17);
            app.PlotFunc3DropDown.Items = {'-- Select function'};
            app.PlotFunc3DropDown.Value = '-- Select function';
            app.PlotFunc4DropDown = uidropdown(app.GridLayout17);
            app.PlotFunc4DropDown.Items = {'-- Select function'};
            app.PlotFunc4DropDown.Value = '-- Select function';
            app.parameter1TextArea = uitextarea(app.GridLayout17);
            app.parameter2TextArea = uitextarea(app.GridLayout17);
            app.parameter3TextArea = uitextarea(app.GridLayout17);
            app.parameter4TextArea = uitextarea(app.GridLayout17);
            app.PlotFunc5DropDown = uidropdown(app.GridLayout17);
            app.PlotFunc5DropDown.Items = {'-- Select function'};
            app.PlotFunc5DropDown.Value = '-- Select function';
            app.parameter5TextArea = uitextarea(app.GridLayout17);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end