classdef BuildR2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Panel                     matlab.ui.container.Panel
        BpodfileLabel             matlab.ui.control.Label
        MEDfileLabel              matlab.ui.control.Label
        BuildRversion202023Label  matlab.ui.control.Label
        BuildrarrayButton         matlab.ui.control.Button
        UnitsButtonGroup          matlab.ui.container.ButtonGroup
        AutofillButton            matlab.ui.control.Button
        EditField_32              matlab.ui.control.EditField
        EditField_31              matlab.ui.control.EditField
        EditField_30              matlab.ui.control.EditField
        EditField_29              matlab.ui.control.EditField
        EditField_28              matlab.ui.control.EditField
        EditField_27              matlab.ui.control.EditField
        EditField_26              matlab.ui.control.EditField
        EditField_25              matlab.ui.control.EditField
        EditField_24              matlab.ui.control.EditField
        EditField_23              matlab.ui.control.EditField
        EditField_22              matlab.ui.control.EditField
        EditField_21              matlab.ui.control.EditField
        EditField_20              matlab.ui.control.EditField
        EditField_19              matlab.ui.control.EditField
        EditField_18              matlab.ui.control.EditField
        EditField_17              matlab.ui.control.EditField
        EditField_16              matlab.ui.control.EditField
        EditField_15              matlab.ui.control.EditField
        EditField_14              matlab.ui.control.EditField
        EditField_13              matlab.ui.control.EditField
        EditField_12              matlab.ui.control.EditField
        EditField_11              matlab.ui.control.EditField
        EditField_10              matlab.ui.control.EditField
        EditField_9               matlab.ui.control.EditField
        EditField_8               matlab.ui.control.EditField
        EditField_7               matlab.ui.control.EditField
        EditField_6               matlab.ui.control.EditField
        EditField_5               matlab.ui.control.EditField
        EditField_4               matlab.ui.control.EditField
        EditField_3               matlab.ui.control.EditField
        EditField_2               matlab.ui.control.EditField
        EditField_1               matlab.ui.control.EditField
        Ch32Button                matlab.ui.control.RadioButton
        Ch31Button                matlab.ui.control.RadioButton
        Ch30Button                matlab.ui.control.RadioButton
        Ch29Button                matlab.ui.control.RadioButton
        Ch28Button                matlab.ui.control.RadioButton
        Ch27Button                matlab.ui.control.RadioButton
        Ch26Button                matlab.ui.control.RadioButton
        Ch25Button                matlab.ui.control.RadioButton
        Ch24Button                matlab.ui.control.RadioButton
        Ch23Button                matlab.ui.control.RadioButton
        Ch22Button                matlab.ui.control.RadioButton
        Ch21Button                matlab.ui.control.RadioButton
        Ch20Button                matlab.ui.control.RadioButton
        Ch19Button                matlab.ui.control.RadioButton
        Ch18Button                matlab.ui.control.RadioButton
        Ch17Button                matlab.ui.control.RadioButton
        Ch16Button                matlab.ui.control.RadioButton
        Ch15Button                matlab.ui.control.RadioButton
        Ch14Button                matlab.ui.control.RadioButton
        Ch13Button                matlab.ui.control.RadioButton
        Ch12Button                matlab.ui.control.RadioButton
        Ch11Button                matlab.ui.control.RadioButton
        Ch10Button                matlab.ui.control.RadioButton
        Ch9Button                 matlab.ui.control.RadioButton
        Ch8Button                 matlab.ui.control.RadioButton
        Ch7Button                 matlab.ui.control.RadioButton
        Ch6Button                 matlab.ui.control.RadioButton
        Ch5Button                 matlab.ui.control.RadioButton
        Ch4Button                 matlab.ui.control.RadioButton
        Ch3Button                 matlab.ui.control.RadioButton
        Ch2Button                 matlab.ui.control.RadioButton
        Ch1Button                 matlab.ui.control.RadioButton
        BehaviordataPanel         matlab.ui.container.Panel
        AutofillButton_2          matlab.ui.control.Button
        BuildButton               matlab.ui.control.Button
        BlankoutEditField         matlab.ui.control.EditField
        BlankoutLabel             matlab.ui.control.Label
        AddlaserCheckBox          matlab.ui.control.CheckBox
        AddforceCheckBox          matlab.ui.control.CheckBox
        LoadBpodfileButton        matlab.ui.control.StateButton
        LoadMEDfileButton         matlab.ui.control.StateButton
        BpodProtocolDropDown      matlab.ui.control.DropDown
        BpodProtocolLabel         matlab.ui.control.Label
        DIversionDropDown         matlab.ui.control.DropDown
        DIversionLabel            matlab.ui.control.Label
        ProtocolDropDown          matlab.ui.control.DropDown
        ProtocolLabel             matlab.ui.control.Label
        KornblumCheckBox          matlab.ui.control.CheckBox
        SegmentsDropDown          matlab.ui.control.DropDown
        SegmentsLabel             matlab.ui.control.Label
        ExperimenterEditField     matlab.ui.control.EditField
        ExperimenterLabel         matlab.ui.control.Label
        ANMnameEditField          matlab.ui.control.EditField
        ANMLabel                  matlab.ui.control.Label
        UIAxesBehav               matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadMEDfileButton
        function LoadMEDfileButtonValueChanged(app, event)
            value = app.LoadMEDfileButton.Value;

            % Look for MED file
            MEDFile = dir('*Subject*.txt');

            if isempty(MEDFile)
                display('Check folder')
            end;

            % Compute behavioral data from MED file
            if app.KornblumCheckBox.Value == 1
                % Note: my (JY's) codes are stored in packages, e.g.,
                % track_training_progress_advanced_KornblumStyle can be
                % found in Behavior.MED.track_training_progress_advanced_KornblumStyle
                b = Behavior.MED.track_training_progress_advanced_KornblumStyle(MEDFile.name);
                myclass = Behavior.Timing.KornblumClass(MEDFile.name);
                myclass.Plot();
                myclass.Save()
                myclass.Print()
            else
                [b, myclass] = Behavior.MED.track_training_progress_advanced(MEDFile.name);
                myclass.Plot();
            end;

            % place b and kc (if there is any)
            app.MEDfileLabel.UserData = b; 
            app.LoadMEDfileButton.UserData = myclass;
            app.MEDfileLabel.Text = MEDFile.name;
            app.ANMnameEditField.Value = myclass.Subject;
            
        end

        % Drop down opening function: SegmentsDropDown
        function SegmentsDropDownOpening(app, event)
            x = dir('datafile*.nev');
            AllSegments = arrayfun(@(x)x.name, x, 'UniformOutput', false);
            app.SegmentsDropDown.Items = AllSegments; 
        end

        % Value changed function: LoadBpodfileButton
        function LoadBpodfileButtonValueChanged(app, event)

            value = app.LoadBpodfileButton.Value;
            name = app.ANMnameEditField.Value;
            BpodFile = dir([name '*.mat']);

            if ~isempty(BpodFile) && length(BpodFile)==1
                app.BpodfileLabel.Text = BpodFile.name;
                load(BpodFile.name);
                app.BpodfileLabel.UserData = SessionData;
            else
                % load
                % select Bpod file
                % load sdf from chemo folder
                [file,path] = uigetfile('*.mat', 'Pick Bpod file');
                load(fullfile(path, file));
                app.BpodfileLabel.Text = file;
                load(fullfile(path, file));
                app.BpodfileLabel.UserData = SessionData;

            end;

        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)

            % Check spks
            units = {};
            for i = 1:32
                ivalue = strrep(eval(['app.EditField_' num2str(i) '.Value']), ' ', '');
                if ~isempty(ivalue)
                    IndNew=size(units, 1)+1;
                    units{IndNew, 1} = i;
                    units{IndNew, 2} = ivalue;
                    units{IndNew, 3} = [];
                end;
            end;
      
            BlankOut = [];
            if ~isempty(app.BlankoutEditField.Value)
                BlankOut = str2num(app.BlankoutEditField.Value);
                BlankOut = reshape(BlankOut, 2, []);
            end;

            % here we build r array based on available information
            b                      =        app.MEDfileLabel.UserData;
            BehaviorClass =        app.LoadMEDfileButton.UserData;
            SessionData    =       app.BpodfileLabel.UserData;
            blocks              =       app.SegmentsDropDown.Items;
            EventOutCombined = []; % this one combines all event times and difference in time between blocks.

            % a name for saving the r array
            aGoodName   = ['RTarray_', b.Metadata.SubjectName, '_' b.Metadata.Date '.mat'];
            app.BuildButton.UserData = aGoodName;
            dBlockOnset = 0;
            % calculate time difference between different blocks

            for ib=1:length(blocks)
                nevfile = blocks{ib};
                openNEV(nevfile, 'report', 'read')  % open ‘datafile###.nev’, create “datafile###.mat”
                load([nevfile(1:11) '.mat'])
                switch app.DIversionDropDown.Value
                    case 'Version4'
                        EventOut = DIO_Events4(NEV); % create
                    case 'Version5'
                        EventOut = DIO_Events5(NEV); % create
                        % Poke signals are incorrect. Update poke from bpod.  10/4/2022
                        EventOut.Onset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                        EventOut.Offset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                end;

                if ib ==1;
                    RecordingOnset = EventOut.Meta.DateTimeRaw;
                end;

                if ib>1
                    dt_i = EventOut.Meta.DateTimeRaw-RecordingOnset;
                    dBlockOnset(ib)=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % in ms
                end;
                %  update poke information in EventOut with bpod events

                EventOut.Meta.Subject = app.ANMnameEditField.Value;
                EventOut.Meta.Experimenter = app.ExperimenterEditField.Value;

                if isfield(EventOut, 'Subject')
                    EventOut = rmfield(EventOut, 'Subject');
                end;
                if isfield(EventOut, 'Experimenter')
                    EventOut = rmfield(EventOut, 'Experimenter');
                end;

                if ib ==1
                    EventOutCombined=EventOut;
                    EventOutCombined = rmfield(EventOutCombined, 'TimeEvents');
                else
                    EventOutCombined.Meta(ib) = EventOut.Meta;
                    for k = 1:length(EventOutCombined.EventsLabels)
                        EventOutCombined.Onset{k} = [EventOutCombined.Onset{k}; EventOut.Onset{k}+dBlockOnset(ib)];
                        EventOutCombined.Offset{k} = [EventOutCombined.Offset{k}; EventOut.Offset{k}+dBlockOnset(ib)];
                    end;
                end;
            end;
           
            % Align Events
            switch app.BpodProtocolDropDown.Value
                case 'OptoRecording'
                    %  read bpod events
                    BpodEvents = Spikes.Array.Bpod_Events_MedOptoRecording(SessionData);
                    % Update poke based on data from Bpod events
                    EventOutCombined = Spikes.Array.UpdatePokeFromBpodEvents(EventOutCombined, BpodEvents);
                case 'OptoRecordingMix'  % optogenetic stimulation was applied at the onset of different events, we need to extract those times, and align them to blackrock's time
                    %  read bpod events
                    % we also read the approach signal from bpod states.
                    BpodEvents = Spikes.Array.Bpod_Events_MedOptoRecMix(SessionData);
                    EventOutCombined = Spikes.Array.UpdatePokeFromBpodEvents(EventOutCombined, BpodEvents);
                    EventOutCombined = Spikes.Array.UpdateApproachFromBpodEvents(EventOutCombined, BpodEvents);
                    EventOutCombined = Spikes.Array.UpdateOptoStimFromBpodEvents(EventOutCombined, BpodEvents);
            end
            EventOutCombined = Spikes.Array.AlignBehaviorClassToBR(EventOutCombined, BehaviorClass);
            %% construct an array (r) with aligned behavior, spikes and LFP data.
            % name is r

            % turn everything in minutes
            % single unit: 1; multiunit: 2
            r                         =             [];
            r.BehaviorClass =             BehaviorClass; % added 2023
            r.Meta                =             EventOutCombined.Meta;

            r.Behavior.Labels={
                'FrameOn',...
                'FrameOff', ...
                'LeverPress', ...
                'Trigger',...
                'LeverRelease',...
                'ValveOnset', ...
                'ValveOffset',...
                'PokeOnset',...
                'Approach',...  % this is important for opto-related 
                'OptoStimOn',...
                'OptoStimOff'
                };

            r.Behavior.LabelMarkers = [1:length(r.Behavior.Labels)];

            r.Behavior.Outcome                        = EventOutCombined.OutcomeEphys;
            % the followings are redundant but are listed so that other
            % programs still work. 
            r.Behavior.CorrectIndex                 =      find(strcmp(r.Behavior.Outcome, 'Correct'));
            r.Behavior.PrematureIndex            =      find(strcmp(r.Behavior.Outcome, 'Premature'));
            r.Behavior.LateIndex                      =      find(strcmp(r.Behavior.Outcome, 'Late'));
            r.Behavior.DarkIndex                     =      find(strcmp(r.Behavior.Outcome, 'Dark'));
            r.Behavior.Foreperiods                  =      EventOutCombined.FP_Ephys;
            r.Behavior.CueIndex                      =      EventOutCombined.CueEphys;

            r.Behavior.EventTimings = [];
            r.Behavior.EventMarkers = [];
            % add frame signal: 1 on, 2 off
            Ind_FrameOn = find(strcmp(r.Behavior.Labels, 'FrameOn'));
            Ind_FrameOff = find(strcmp(r.Behavior.Labels, 'FrameOff'));            
            indframe = find(strcmp(EventOutCombined.EventsLabels, 'Frame'));
            eventonset = EventOutCombined.Onset{indframe};
            eventoffset = EventOutCombined.Offset{indframe};
            eventmix = [eventonset; eventoffset];
            indeventmix = [ones(length(eventonset), 1)*Ind_FrameOn; ones(length(eventoffset), 1)*Ind_FrameOff]; % frame onset  1; frame offset 2
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add leverpress onset and offset signal: 3 and 5
            indleverpress= find(strcmp(EventOutCombined.EventsLabels, 'LeverPress'));
            eventonset = EventOutCombined.Onset{indleverpress};
            eventoffset = EventOutCombined.Offset{indleverpress};
            eventmix = [eventonset; eventoffset];
            Ind_Press =  find(strcmp(r.Behavior.Labels, 'LeverPress'));
            Ind_Release =  find(strcmp(r.Behavior.Labels, 'LeverRelease'));
            indeventmix = [ones(length(eventonset), 1)*Ind_Press; ones(length(eventoffset),1)*Ind_Release];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add trigger stimulus signal: 4
            indtriggers= find(strcmp(EventOutCombined.EventsLabels, 'Trigger'));
            eventonset = EventOutCombined.Onset{indtriggers};
            triggeronset =EventOutCombined.Onset{indtriggers};
            if size(eventonset, 1)<size(eventonset, 2)
                eventonset = eventonset';
            end;
            Ind_Trigger = find(strcmp(r.Behavior.Labels, 'Trigger'));
            indevent = [ones(length(eventonset), 1)*Ind_Trigger];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventonset];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indevent];

            % add valve onset and offset signals: 6 and 7
            indvalve= find(strcmp(EventOutCombined.EventsLabels, 'Valve'));
            eventonset = EventOutCombined.Onset{indvalve};
            eventoffset = EventOutCombined.Offset{indvalve};
            eventmix = [eventonset; eventoffset];
            Ind_ValveOn = find(strcmp(r.Behavior.Labels, 'ValveOnset'));
            Ind_ValveOff = find(strcmp(r.Behavior.Labels, 'ValveOffset'));

            indeventmix = [ones(length(eventonset), 1)*Ind_ValveOn; ones(length(eventoffset), 1)*Ind_ValveOff]; % frame onset  1; frame offset 2
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add poke onset signals: 8
            indpoke= find(strcmp(EventOutCombined.EventsLabels, 'Poke'));
            eventonset = EventOutCombined.Onset{indpoke};
            eventmix = [eventonset];
            Ind_Poke =  find(strcmp(r.Behavior.Labels, 'PokeOnset'));
            indeventmix = [ones(length(eventonset), 1)*Ind_Poke]; 
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add optostim, 
            indopto = find(strcmp(EventOutCombined.EventsLabels, 'OptoStim'));
            if ~isempty(indopto)
                eventonset = EventOutCombined.Onset{indopto};
                eventoffset = EventOutCombined.Offset{indopto}+BpodEvents.OptoStimDur;
                eventmix = [eventonset; eventoffset];
                Ind_OptoOn = find(strcmp(r.Behavior.Labels, 'OptoStimOn'));
                Ind_OptoOff = find(strcmp(r.Behavior.Labels, 'OptoStimOff'));
                indeventmix = [Ind_OptoOn*ones(length(eventonset), 1); Ind_OptoOff*ones(length(eventoffset), 1)]; %
                r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
                r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];
            end;

            % add approach signal, if there is any, if there is
            % any.11
            indapproach = find(strcmp(EventOutCombined.EventsLabels, 'Approach'));
            if ~isempty(indapproach)
                eventonset = EventOutCombined.Onset{indapproach};
                eventmix = [eventonset];
                Ind_Approach = find(strcmp(r.Behavior.Labels, 'Approach'));
                indeventmix = [Ind_Approach*ones(length(eventonset), 1)]; %
                r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
                r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];
            end;

            % sort timing
            [r.Behavior.EventTimings, index_timing] = sort(r.Behavior.EventTimings);
            r.Behavior.EventMarkers = r.Behavior.EventMarkers(index_timing);
            % write down periods of time that should be blanked out (e.g.,
            % wire connection becomes loose)
            r.Behavior.BlankOut = BlankOut;

%%      plot behavior data
            app.UIAxesBehav.NextPlot = 'add';
            app.UIAxesBehav.XLim = [0 max(r.Behavior.EventTimings/(1000))]; % in second
            app.UIAxesBehav.YTick = [1:length(r.Behavior.Labels)];
            app.UIAxesBehav.YTickLabel = r.Behavior.Labels;

            plot(app.UIAxesBehav, [r.Behavior.EventTimings/(1000)], [r.Behavior.EventMarkers],'o', 'color', 'k','markersize', 3, 'linewidth', 1)
            line(app.UIAxesBehav, [0 max(r.Behavior.EventTimings/(1000))], [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', 'k')

            n_behavior_events = length(r.Behavior.Labels);
            %% Add spikes
            r.Units.Channels                                = [1:16 17:32];
            r.Units.Profile                                    = units;
            r.Units.Definition                               = {'channel_id cluster_id unit_type polytrode', '1: single unit', '2: multi unit'};
            r.Units.SpikeNotes                             = [];

            for i                                              = 1:size(units, 1)
                ich = units{i, 1};
                sorting_code                             = units{i, 2};
                for k                                              = 1:length(sorting_code)
                    switch sorting_code(k)
                        case 'm'
                            r.Units.SpikeNotes                                   = [r.Units.SpikeNotes; units{i, 1} k 2 0];
                        case 's'
                            r.Units.SpikeNotes                                   = [r.Units.SpikeNotes; units{i, 1} k 1 0];
                    end;
                end
            end;

            spkchs = unique(r.Units.SpikeNotes(:, 1));
            allcolors                                          = varycolor(length(spkchs));

            app.UIAxesBehav.YLim = [0 size(r.Units.SpikeNotes , 1)+1+n_behavior_events];

            % put spikes
            if exist(['chdat1.mat'])>0
                raw = load('chdat1.mat');
            else
                raw                  = load(['chdat' num2str(r.Units.SpikeNotes(1, 1)) '.mat']);
            end;

            torg = raw.index; % also in ms 
            tnew = [1:length(raw.index)]*1000/30000; % tnew in ms

            for i                                             = 1:size(r.Units.SpikeNotes, 1)
                channel_id                              = r.Units.SpikeNotes(i, 1);  % channel id
                cluster_id                                = r.Units.SpikeNotes(i, 2);  % cluster id
                r.Units.SpikeTimes(i)              =   struct('timings',  [], 'wave', [], 'spk_id', []);

                % load spike time:
                if exist(['times_chdat' num2str(channel_id) '.mat'],'file') == 2
                    spk_id                                                 =     load(['times_chdat' num2str(channel_id) '.mat']);
                elseif exist(['times_chdat_meansub' num2str(channel_id) '.mat'],'file') == 2
                    spk_id                                                 =     load(['times_chdat_meansub' num2str(channel_id) '.mat']);
                else
                    error(['check file: ' 'times_chdat' num2str(channel_id) '.mat'])
                end
                spk_in_ms                                           =    round((spk_id.cluster_class(spk_id.cluster_class(:, 1)==cluster_id, 2))); % this is not mapped to time in recording

                [~, spkindx]                                          =     intersect(tnew, spk_in_ms);
                spk_in_ms_new                                   =    round(torg(spkindx));


                r.Units.SpikeTimes(i).timings = [r.Units.SpikeTimes(i).timings;  spk_in_ms_new]; % in ms
                r.Units.SpikeTimes(i).wave=[r.Units.SpikeTimes(i).wave;  spk_id.spikes(spk_id.cluster_class(:, 1)==cluster_id, :)];

                % remove  90% of spike waveforms to reduce file size
                % index spk train
                r.Units.SpikeTimes(i).spk_id            = 1:length(r.Units.SpikeTimes(i).timings);
                remove_percentage                         = 1-1000/length(r.Units.SpikeTimes(i).timings);

                if remove_percentage >0
                    ind_to_remove = randperm(length(r.Units.SpikeTimes(i).timings), round(length(r.Units.SpikeTimes(i).timings)*remove_percentage));
                    r.Units.SpikeTimes(i).spk_id(ind_to_remove) = [];
                    r.Units.SpikeTimes(i).wave(ind_to_remove, :) = [];
                end
                x_plot                                             = r.Units.SpikeTimes(i).timings;
                x_plot                                             = [x_plot]/(1000);
                y_plot                                             =  i  + 0.8*rand(1, length(x_plot))+n_behavior_events;
                if ~isempty(x_plot)
                    plot(app.UIAxesBehav, x_plot, y_plot,'.', 'color', allcolors(spkchs ==channel_id, :),'markersize', 4);
                end
            end

             %% check if analog signal should be added to r
            if app.AddforceCheckBox.Value == 1
                x1 = dir('Force*.mat');
                if ~isempty(x1)
                    fdata = load(x1.name);
                    r.Analog.Force = [torg; fdata.data]';
                    % resample data to 1000/sec
                    downsample_ratio = 30000/1000;                    
                    r.Analog.Force = downsample(r.Analog.Force, downsample_ratio);
                end;
            end;

            if app.AddlaserCheckBox.Value == 1
                x2 = dir('Laser*.mat');
                if ~isempty(x2)
                    odata = load(x2.name);
                    r.Analog.Opto = [torg; odata.data]';
                    % resample data to 1000/sec
                    downsample_ratio = 30000/1000;
                    r.Analog.Opto = downsample(r.Analog.Opto, downsample_ratio);
                end;
            end;

            % make sure UIAxesBehav and UIAxesRaster have the same width
            % Final touch double check the alignment. 
            % Check if it is kornblum class
            if app.KornblumCheckBox.Value == 1
                CorrectBehaviorEphysMapping(r,  r.BehaviorClass); % this also save r in the current directory
            end;

            tic
            save (aGoodName,'r', '-v7.3');
            toc

            % print this gui
            SaveGUIfigButtonPushed(app, event)
            clc
            disp('~~~~~~~~~~~~~~~~~~')
            disp('~~~~~R is ready~~~~~')
            disp('~~~~~~~~~~~~~~~~~~') 

            app.UIFigure.Color = 'g';


        end

        % Callback function
        function SaveGUIfigButtonPushed(app, event)
            % save current figure

            exportapp(app.UIFigure,'RTarray.png')
            exportapp(app.UIFigure,'RTarray.pdf')

        end

        % Callback function
        function CheckunitsButtonPushed(app, event)
            % Making PSTHs
            % search for ratio button that is on 
            % Check spks
            iCh = [];

            for i = 1:32
                ivalue = eval(['app.Ch' num2str(i) 'Button' '.Value']);
                if ivalue

                iCh = i;  % channel index
                type_units          =       eval(['app.EditField_' num2str(i) '.Value']);
                type_units          =       cellstr(type_units');
                n_units               =       length(type_units);

                allUnits = {};
                for k =1:n_units
                    allUnits{k} = ['unit' num2str(k) '_' type_units{k}];
                end;

                app.UnitsInCh.Items = allUnits;
                app.UnitsInCh.Value = app.UnitsInCh.Items{1};
                app.UnitsInCh.UserData =  i;

                end;
            end;

            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesRaster.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);

            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);

            session_length = app.UIAxesRaster.XLim;

            if isempty(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            else
                delete(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            end;
        
        end

        % Callback function
        function UnitsInChValueChanged(app, event)
            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesRaster.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);

            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);

            session_length = app.UIAxesRaster.XLim;

            if isempty(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            else
                delete(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            end;

        end

        % Callback function
        function PlotPSTHButtonPushed(app, event)
            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesRaster.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);
            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);
            session_length = app.UIAxesRaster.XLim;

            PrePress = app.PreEditField.Value;
            PostPress = app.PostEditField.Value;

            % plot PSTH
            switch app.ProtocolDropDown.Value
                case 'TwoFPs'
                    SRTSpikesV5(r, [ich, ik], 'FRrange',[0 FR_range], 'PressTimeDomain', [PrePress PostPress]);
                case {'Wait1', 'Wait2'}
                    SRTSpikesWait(r,[ich, ik], 'FRrange',[0 FR_range], 'PressTimeDomain', [PrePress PostPress]);
            end;

        end

        % Callback function
        function PlotAllPSTHsButtonPushed(app, event)
            %plot population responses
            r = app.UIAxesRaster.UserData;

            FR_range = max([app.SpkmaxEditField.Value 20]);
            PrePress = app.PreEditField.Value;
            PostPress = app.PostEditField.Value;

            switch app.ProtocolDropDown.Value
                case 'TwoFPs'
                    for i =1: length(r.Units.SpikeTimes)
                        SRTSpikesV5(r, i, 'FRrange',[0 FR_range], 'Name', '', 'PressTimeDomain', [PrePress PostPress]);
                        close all;
                    end;
                case {'Wait1', 'Wait2'}
                    for i =1: length(r.Units.SpikeTimes)
                        SRTSpikesWait(r, i, 'FRrange',[0 FR_range], 'Name', '', 'PressTimeDomain', [PrePress PostPress]);
                        close all;
                    end;
            end;

% 
%             PSTHPop = SRTSpikesPopulation(r);
        end

        % Callback function
        function LoadButtonPushed(app, event)

            [filename, pathname, ~] = uigetfile({'RT*.mat','MAT-files (*.mat)'}, ...
                'Pick r array');
            load(filename)
            app.UIAxesRaster.UserData                =      r;
            app.ANMnameEditField.Value                      =      r.Meta(1).Subject;
            app.ExperimenterEditField.Value         =      r.Meta(1).Experimenter;
            % fill in spike information
             for i = 1:size(r.Units.Profile, 1)
                ich = r.Units.Profile{i, 1}; 
                iunits = strrep(r.Units.Profile{i, 2}, ' ', ''); 
                eval(['app.EditField_' num2str(ich) '.Value' '=' 'iunits']);
             end;
            app.UIAxesBehav.NextPlot = 'add';
            app.UIAxesBehav.XLim = [0 max(r.Behavior.EventTimings/(1000))]; % in second
            app.UIAxesBehav.YTick = [1:length(r.Behavior.Labels)];
            app.UIAxesBehav.YTickLabel = r.Behavior.Labels;

            plot(app.UIAxesBehav, [r.Behavior.EventTimings/(1000)], [r.Behavior.EventMarkers],'o', 'color', 'k','markersize', 3, 'linewidth', 1)
            line(app.UIAxesBehav, [0 max(r.Behavior.EventTimings/(1000))], [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', [0.8 0.8 0.8])

            spkchs = unique(r.Units.SpikeNotes(:, 1));
            allcolors                                          = varycolor(length(spkchs));

            app.UIAxesRaster.XLim = app.UIAxesBehav.XLim;
            app.UIAxesRaster.YLim = [0 size(r.Units.SpikeNotes , 1)+1];
            app.UIAxesRaster.NextPlot = 'Add';

            % put spikes
            for i                                              = 1:size(r.Units.SpikeNotes, 1)
                channel_id                                         = r.Units.SpikeNotes(i, 1);  % channel id
    
                x_plot                                             = r.Units.SpikeTimes(i).timings;
                x_plot                                             = [x_plot]/(1000);
                y_plot                                             =  i -1 + 0.8*rand(1, length(x_plot));

                if ~isempty(x_plot)
                    plot(app.UIAxesRaster, x_plot, y_plot,'.', 'color', allcolors(spkchs ==channel_id, :),'markersize', 4);
                end;

            end;

            % make sure UIAxesBehav and UIAxesRaster have the same width
            app.UIAxesBehav.Position=[450 50 660 352];
            app.UIAxesRaster.Position=[530 380 580 352];
            app.UIAxesRaster.UserData = r;

        end

        % Callback function
        function PlotpopulationButton_2Pushed(app, event)
            %plot population responses
            r = app.UIAxesRaster.UserData;
            % check if it is two-FP protocol or wait protocol
            switch app.ProtocolDropDown.Value
                case 'TwoFPs'
                    PSTHPop = SRTSpikesPopulation(r);
                case 'Wait1'
                    PSTHPop = SRTSpikesPopulationWait(r);
                case 'Wait2'
                    PSTHPop = SRTSpikesPopulationWait(r);
            end;
        end

        % Value changed function: DIversionDropDown
        function DIversionDropDownValueChanged(app, event)
            value = app.DIversionDropDown.Value;
            
        end

        % Button pushed function: AutofillButton
        function AutofillButtonPushed(app, event)
            % Read unit table

            % 'autofill' will look for 'ListUnitsSorted.csv'
            filename = 'ListUnitsSorted.csv';

            if isempty(dir(filename))
                disp('You do not have ListUnitsSorted.csv')
            else
                tab = readtable(filename);
                chs = unique(tab.Channels);

                for i =1:length(chs)
                    ich = chs(i);
                    iunits = find(tab.Channels == ich);
                    sym_units = '';
                    for j =1:length(iunits)
                        sym_units = [sym_units tab.UnitTypes{iunits(j)}];
                    end;
                    eval(['app.EditField_' num2str(ich) '.Value = sym_units;']);
                end;
            end


        end

        % Value changed function: SegmentsDropDown
        function SegmentsDropDownValueChanged(app, event)
            value = app.SegmentsDropDown.Value;
            
        end

        % Button pushed function: AutofillButton_2
        function AutofillButton_2Pushed(app, event)
            SegmentsDropDownOpening(app, event);
            LoadMEDfileButtonValueChanged(app, event);
            LoadBpodfileButtonValueChanged(app, event);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.UIAxesBehav = uiaxes(app.Panel);
            title(app.UIAxesBehav, {'Behavior'; ''; ''})
            xlabel(app.UIAxesBehav, 'seconds')
            zlabel(app.UIAxesBehav, 'Z')
            app.UIAxesBehav.Tag = 'raster';
            app.BehaviordataPanel = uipanel(app.Panel);
            app.BehaviordataPanel.Title = 'Behavior data';
            app.ANMLabel = uilabel(app.BehaviordataPanel);
            app.ANMLabel.Text = '1|ANM ';
            app.ANMnameEditField = uieditfield(app.BehaviordataPanel, 'text');
            app.ExperimenterLabel = uilabel(app.BehaviordataPanel);
            app.ExperimenterLabel.Text = '2|Experimenter';
            app.ExperimenterEditField = uieditfield(app.BehaviordataPanel, 'text');
            app.SegmentsLabel = uilabel(app.BehaviordataPanel);
            app.SegmentsLabel.Text = '3|Segments';
            app.SegmentsDropDown = uidropdown(app.BehaviordataPanel);
            app.SegmentsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SegmentsDropDownOpening, true);
            app.SegmentsDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentsDropDownValueChanged, true);
            app.KornblumCheckBox = uicheckbox(app.BehaviordataPanel);
            app.KornblumCheckBox.Text = '4|Kornblum';
            app.ProtocolLabel = uilabel(app.BehaviordataPanel);
            app.ProtocolLabel.Text = '5|Protocol';
            app.ProtocolDropDown = uidropdown(app.BehaviordataPanel);
            app.ProtocolDropDown.Items = {'TwoFPs', 'Wait1', 'Wait2', 'Kornblum', ''};
            app.ProtocolDropDown.Value = 'TwoFPs';
            app.DIversionLabel = uilabel(app.BehaviordataPanel);
            app.DIversionLabel.Text = '6|DI version';
            app.DIversionDropDown = uidropdown(app.BehaviordataPanel);
            app.DIversionDropDown.Items = {'Version4', 'Version5'};
            app.DIversionDropDown.ValueChangedFcn = createCallbackFcn(app, @DIversionDropDownValueChanged, true);
            app.DIversionDropDown.Value = 'Version5';
            app.BpodProtocolLabel = uilabel(app.BehaviordataPanel);
            app.BpodProtocolLabel.Text = '7|Bpod Protocol';
            app.BpodProtocolDropDown = uidropdown(app.BehaviordataPanel);
            app.BpodProtocolDropDown.Items = {'OptoRecording', 'OptoRecordingMix'};
            app.BpodProtocolDropDown.Value = 'OptoRecording';
            app.LoadMEDfileButton = uibutton(app.BehaviordataPanel, 'state');
            app.LoadMEDfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadMEDfileButtonValueChanged, true);
            app.LoadMEDfileButton.Text = {'8|Load MED file'; ''};
            app.LoadBpodfileButton = uibutton(app.BehaviordataPanel, 'state');
            app.LoadBpodfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadBpodfileButtonValueChanged, true);
            app.LoadBpodfileButton.Text = {'9|Load Bpod file'; ''};
            app.AddforceCheckBox = uicheckbox(app.BehaviordataPanel);
            app.AddforceCheckBox.Text = '11|Add force';
            app.AddlaserCheckBox = uicheckbox(app.BehaviordataPanel);
            app.AddlaserCheckBox.Text = '12|Add laser';
            app.BlankoutLabel = uilabel(app.BehaviordataPanel);
            app.BlankoutLabel.Text = '13|Blank-out';
            app.BlankoutEditField = uieditfield(app.BehaviordataPanel, 'text');
            app.BuildButton = uibutton(app.BehaviordataPanel, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = '14|Build';
            app.AutofillButton_2 = uibutton(app.BehaviordataPanel, 'push');
            app.AutofillButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutofillButton_2Pushed, true);
            app.AutofillButton_2.Text = 'Autofill';
            app.UnitsButtonGroup = uibuttongroup(app.Panel);
            app.UnitsButtonGroup.Title = '10|Units';
            app.Ch1Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch1Button.Text = {'Ch1'; ''};
            app.Ch1Button.Value = true;
            app.Ch2Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch2Button.Text = {'Ch2'; ''};
            app.Ch3Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch3Button.Text = {'Ch3'; ''};
            app.Ch4Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch4Button.Text = 'Ch4';
            app.Ch5Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch5Button.Text = 'Ch5';
            app.Ch6Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch6Button.Text = 'Ch6';
            app.Ch7Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch7Button.Text = 'Ch7';
            app.Ch8Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch8Button.Text = 'Ch8';
            app.Ch9Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch9Button.Text = 'Ch9';
            app.Ch10Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch10Button.Text = 'Ch10';
            app.Ch11Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch11Button.Text = 'Ch11';
            app.Ch12Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch12Button.Text = 'Ch12';
            app.Ch13Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch13Button.Text = 'Ch13';
            app.Ch14Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch14Button.Text = 'Ch14';
            app.Ch15Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch15Button.Text = 'Ch15';
            app.Ch16Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch16Button.Text = 'Ch16';
            app.Ch17Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch17Button.Text = {'Ch17'; ''};
            app.Ch18Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch18Button.Text = {'Ch18'; ''};
            app.Ch19Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch19Button.Text = {'Ch19'; ''};
            app.Ch20Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch20Button.Text = 'Ch20';
            app.Ch21Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch21Button.Text = 'Ch21';
            app.Ch22Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch22Button.Text = 'Ch22';
            app.Ch23Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch23Button.Text = 'Ch23';
            app.Ch24Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch24Button.Text = 'Ch24';
            app.Ch25Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch25Button.Text = 'Ch25';
            app.Ch26Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch26Button.Text = 'Ch26';
            app.Ch27Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch27Button.Text = 'Ch27';
            app.Ch28Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch28Button.Text = 'Ch28';
            app.Ch29Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch29Button.Text = 'Ch29';
            app.Ch30Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch30Button.Text = 'Ch30';
            app.Ch31Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch31Button.Text = 'Ch31';
            app.Ch32Button = uiradiobutton(app.UnitsButtonGroup);
            app.Ch32Button.Text = 'Ch32';
            app.EditField_1 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_2 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_3 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_4 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_5 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_6 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_7 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_8 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_8.Value = ' ';
            app.EditField_9 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_10 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_11 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_12 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_13 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_14 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_15 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_16 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_17 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_18 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_19 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_20 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_21 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_22 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_23 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_24 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_25 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_26 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_27 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_28 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_29 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_30 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_31 = uieditfield(app.UnitsButtonGroup, 'text');
            app.EditField_32 = uieditfield(app.UnitsButtonGroup, 'text');
            app.AutofillButton = uibutton(app.UnitsButtonGroup, 'push');
            app.AutofillButton.ButtonPushedFcn = createCallbackFcn(app, @AutofillButtonPushed, true);
            app.AutofillButton.Text = 'Autofill';
            app.BuildrarrayButton = uibutton(app.Panel, 'push');
            app.BuildrarrayButton.Text = {'Build r array'; ''};
            app.BuildRversion202023Label = uilabel(app.Panel);
            app.BuildRversion202023Label.Text = 'BuildR version 2.0 (2023)';
            app.MEDfileLabel = uilabel(app.Panel);
            app.MEDfileLabel.Text = {'MED file'; ''};
            app.BpodfileLabel = uilabel(app.Panel);
            app.BpodfileLabel.Text = {'Bpod file'; ''};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SegmentsDropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BuildR2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end