classdef BuildR4Tetrodes2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        BuildR4Tetrodeversion202023Panel  matlab.ui.container.Panel
        Panel                  matlab.ui.container.Panel
        DIversionDropDown      matlab.ui.control.DropDown
        DIversionLabel         matlab.ui.control.Label
        BpodProtocolDropDown   matlab.ui.control.DropDown
        BpodProtocolLabel      matlab.ui.control.Label
        LoadBpodfileButton     matlab.ui.control.StateButton
        LoadMEDfileButton      matlab.ui.control.StateButton
        KornblumCheckBox       matlab.ui.control.CheckBox
        SegmentsDropDown       matlab.ui.control.DropDown
        SegmentsLabel          matlab.ui.control.Label
        ExperimenterEditField  matlab.ui.control.EditField
        ExperimenterLabel      matlab.ui.control.Label
        ANMnameEditField       matlab.ui.control.EditField
        ANMnameLabel           matlab.ui.control.Label
        EphysSegsEditField     matlab.ui.control.EditField
        EphysSegsLabel         matlab.ui.control.Label
        BlankoutEditField      matlab.ui.control.EditField
        BlankoutLabel          matlab.ui.control.Label
        AddlaserCheckBox       matlab.ui.control.CheckBox
        AddforceCheckBox       matlab.ui.control.CheckBox
        BuildButton            matlab.ui.control.Button
        BpodfileLabel          matlab.ui.control.Label
        MEDfileLabel           matlab.ui.control.Label
        PolytrodesButtonGroup  matlab.ui.container.ButtonGroup
        AutofillButton         matlab.ui.control.Button
        ChsEditField_8         matlab.ui.control.EditField
        ChsEditField_8Label    matlab.ui.control.Label
        ChsEditField_7         matlab.ui.control.EditField
        ChsEditField_7Label    matlab.ui.control.Label
        ChsEditField_6         matlab.ui.control.EditField
        ChsEditField_6Label    matlab.ui.control.Label
        ChsEditField_5         matlab.ui.control.EditField
        ChsEditField_5Label    matlab.ui.control.Label
        ChsEditField_4         matlab.ui.control.EditField
        ChsEditField_4Label    matlab.ui.control.Label
        ChsEditField_3         matlab.ui.control.EditField
        ChsEditField_3Label    matlab.ui.control.Label
        ChsEditField_2         matlab.ui.control.EditField
        ChsEditField_2Label    matlab.ui.control.Label
        ChsEditField_1         matlab.ui.control.EditField
        ChsEditFieldLabel      matlab.ui.control.Label
        egsssssmoremptyLabel   matlab.ui.control.Label
        EditField_8            matlab.ui.control.EditField
        EditField_7            matlab.ui.control.EditField
        EditField_6            matlab.ui.control.EditField
        EditField_5            matlab.ui.control.EditField
        EditField_4            matlab.ui.control.EditField
        EditField_3            matlab.ui.control.EditField
        EditField_2            matlab.ui.control.EditField
        EditField_1            matlab.ui.control.EditField
        Tetrode8Button         matlab.ui.control.RadioButton
        Tetrode7Button         matlab.ui.control.RadioButton
        Tetrode6Button         matlab.ui.control.RadioButton
        Tetrode5Button         matlab.ui.control.RadioButton
        Tetrode4Button         matlab.ui.control.RadioButton
        Tetrode3Button         matlab.ui.control.RadioButton
        Tetrode2Button         matlab.ui.control.RadioButton
        Tetrode1Button         matlab.ui.control.RadioButton
        UIAxesBehav            matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadMEDfileButton
        function LoadMEDfileButtonValueChanged(app, event)
            value = app.LoadMEDfileButton.Value;

            % Look for MED file 
            MEDFile = dir('*Subject*.txt');

            if isempty(MEDFile)
                display('Check folder')
            end;

            % Compute behavioral data from MED file
            if app.KornblumCheckBox.Value == 1
                % Note: my (JY's) codes are stored in packages, e.g.,
                % track_training_progress_advanced_KornblumStyle can be
                % found in Behavior.MED.track_training_progress_advanced_KornblumStyle
                b = Behavior.MED.track_training_progress_advanced_KornblumStyle(MEDFile.name);
                myclass = Behavior.Timing.KornblumClass(MEDFile.name);
                myclass.Plot();
                myclass.Save()
                myclass.Print()
            else
                [b, myclass] = Behavior.MED.track_training_progress_advanced(MEDFile.name);
                myclass.Plot();
            end;

            % place b and kc (if there is any)
            app.MEDfileLabel.UserData = b; 
            app.LoadMEDfileButton.UserData = myclass;
            app.MEDfileLabel.Text = MEDFile.name;
            
        end

        % Drop down opening function: SegmentsDropDown
        function SegmentsDropDownOpening(app, event)
            x = dir('datafile*.nev');
            AllSegments = arrayfun(@(x)x.name, x, 'UniformOutput', false);
            app.SegmentsDropDown.Items = AllSegments; 
        end

        % Value changed function: LoadBpodfileButton
        function LoadBpodfileButtonValueChanged(app, event)
            value = app.LoadBpodfileButton.Value;
            name = app.ANMnameEditField.Value;
            BpodFile = dir([name '_*_*_*.mat'])

            if ~isempty(BpodFile) && length(BpodFile)==1
                app.BpodfileLabel.Text = BpodFile.name;
                load(BpodFile.name);
                app.BpodfileLabel.UserData = SessionData;
            else
                % load
                % select Bpod file
                % load sdf from chemo folder
                [file,path] = uigetfile('*.mat', 'Pick Bpod file');
                load(fullfile(path, file));
                app.BpodfileLabel.Text = file;
                load(fullfile(path, file));
                app.BpodfileLabel.UserData = SessionData;

            end;

        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)

            % Check spks
            units = {};
            for i = 1:8
                ivalue = strrep(eval(['app.EditField_' num2str(i) '.Value']), ' ', '');
                if ~isempty(ivalue)
                    IndNew=size(units, 1)+1;
                    units{IndNew, 1} = i;
                    units{IndNew, 2} = ivalue;
                end;
            end;

            BlankOut = [];
            if ~isempty(app.BlankoutEditField.Value)
                BlankOut = str2num(app.BlankoutEditField.Value);
                BlankOut = reshape(BlankOut, 2, []);
            end;

            % here we build r array based on available information
            b                      =        app.MEDfileLabel.UserData;
            BehaviorClass =        app.LoadMEDfileButton.UserData;
            SessionData    =       app.BpodfileLabel.UserData;
            blocks              =       app.SegmentsDropDown.Items;
            EventOutCombined = []; % this one combines all event times and difference in time between blocks.

            % a name for saving the r array
            aGoodName   = ['RTarray_', b.Metadata.SubjectName, '_' b.Metadata.Date '.mat'];
            app.BuildButton.UserData = aGoodName;
            dBlockOnset = 0;
            % calculate time difference between different blocks

            for ib=1:length(blocks)
                nevfile = blocks{ib};
                openNEV(nevfile, 'report', 'read')  % open ‘datafile###.nev’, create “datafile###.mat”
                load([nevfile(1:11) '.mat'])
                switch app.DIversionDropDown.Value
                    case 'Version4'
                        EventOut = DIO_Events4(NEV); % create
                    case 'Version5'
                        EventOut = DIO_Events5(NEV); % create
                        % Poke signals are incorrect. Update poke from bpod.  10/4/2022
                        EventOut.Onset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                        EventOut.Offset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                end;

                if ib ==1;
                    RecordingOnset = EventOut.Meta.DateTimeRaw;
                end;

                if ib>1
                    dt_i = EventOut.Meta.DateTimeRaw-RecordingOnset;
                    dBlockOnset(ib)=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % in ms
                end;
                %  update poke information in EventOut with bpod events

                EventOut.Meta.Subject = app.ANMnameEditField.Value;
                EventOut.Meta.Experimenter = app.ExperimenterEditField.Value;

                if isfield(EventOut, 'Subject')
                    EventOut = rmfield(EventOut, 'Subject');
                end;
                if isfield(EventOut, 'Experimenter')
                    EventOut = rmfield(EventOut, 'Experimenter');
                end;

                if ib ==1
                    EventOutCombined=EventOut;
                    EventOutCombined = rmfield(EventOutCombined, 'TimeEvents');
                else
                    EventOutCombined.Meta(ib) = EventOut.Meta;
                    for k = 1:length(EventOutCombined.EventsLabels)
                        EventOutCombined.Onset{k} = [EventOutCombined.Onset{k}; EventOut.Onset{k}+dBlockOnset(ib)];
                        EventOutCombined.Offset{k} = [EventOutCombined.Offset{k}; EventOut.Offset{k}+dBlockOnset(ib)];
                    end;
                end;
            end;
           
            % Align Events
            switch app.BpodProtocolDropDown.Value
                case 'OptoRecording'
                    %  read bpod events
                    BpodEvents = Spikes.Array.Bpod_Events_MedOptoRecording(SessionData);
                    % Update poke based on data from Bpod events
                    EventOutCombined = Spikes.Array.UpdatePokeFromBpodEvents(EventOutCombined, BpodEvents);
                case 'OptoRecordingMix'  % optogenetic stimulation was applied at the onset of different events, we need to extract those times, and align them to blackrock's time
                    %  read bpod events
                    % we also read the approach signal from bpod states.
                    BpodEvents = Spikes.Array.Bpod_Events_MedOptoRecMix(SessionData);
                    EventOutCombined = Spikes.Array.UpdatePokeFromBpodEvents(EventOutCombined, BpodEvents);
                    EventOutCombined = Spikes.Array.UpdateApproachFromBpodEvents(EventOutCombined, BpodEvents);
                    EventOutCombined = Spikes.Array.UpdateOptoStimFromBpodEvents(EventOutCombined, BpodEvents);

            end;

            EventOutCombined = Spikes.Array.AlignBehaviorClassToBR(EventOutCombined, BehaviorClass);

            %% construct an array (r) with aligned behavior, spikes and LFP data.
            % name is r
            % turn everything in minutes
            % single unit: 1; multiunit: 2
            r                         =             [];
            r.BehaviorClass =             BehaviorClass; % added 2023
            r.Meta                =             EventOutCombined.Meta;

            r.Behavior.Labels={
                'FrameOn',...
                'FrameOff', ...
                'LeverPress', ...
                'Trigger',...
                'LeverRelease',...
                'ValveOnset', ...
                'ValveOffset',...
                'PokeOnset',...
                'Approach',...  % this is important for opto-related 
                'OptoStimOn',...
                'OptoStimOff'
                };

            r.Behavior.LabelMarkers = [1:length(r.Behavior.Labels)];

            r.Behavior.Outcome                        = EventOutCombined.OutcomeEphys;
            % the followings are redundant but are listed so that other
            % programs still work. 
            r.Behavior.CorrectIndex                 =      find(strcmp(r.Behavior.Outcome, 'Correct'));
            r.Behavior.PrematureIndex            =      find(strcmp(r.Behavior.Outcome, 'Premature'));
            r.Behavior.LateIndex                      =      find(strcmp(r.Behavior.Outcome, 'Late'));
            r.Behavior.DarkIndex                     =      find(strcmp(r.Behavior.Outcome, 'Dark'));
            r.Behavior.Foreperiods                  =      EventOutCombined.FP_Ephys;
            r.Behavior.CueIndex                      =      EventOutCombined.CueEphys;

            r.Behavior.EventTimings = [];
            r.Behavior.EventMarkers = [];
            % add frame signal: 1 on, 2 off
            Ind_FrameOn = find(strcmp(r.Behavior.Labels, 'FrameOn'));
            Ind_FrameOff = find(strcmp(r.Behavior.Labels, 'FrameOff'));            
            indframe = find(strcmp(EventOutCombined.EventsLabels, 'Frame'));
            eventonset = EventOutCombined.Onset{indframe};
            eventoffset = EventOutCombined.Offset{indframe};
            eventmix = [eventonset; eventoffset];
            indeventmix = [ones(length(eventonset), 1)*Ind_FrameOn; ones(length(eventoffset), 1)*Ind_FrameOff]; % frame onset  1; frame offset 2
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add leverpress onset and offset signal: 3 and 5
            indleverpress= find(strcmp(EventOutCombined.EventsLabels, 'LeverPress'));
            eventonset = EventOutCombined.Onset{indleverpress};
            eventoffset = EventOutCombined.Offset{indleverpress};
            eventmix = [eventonset; eventoffset];
            Ind_Press =  find(strcmp(r.Behavior.Labels, 'LeverPress'));
            Ind_Release =  find(strcmp(r.Behavior.Labels, 'LeverRelease'));
            indeventmix = [ones(length(eventonset), 1)*Ind_Press; ones(length(eventoffset),1)*Ind_Release];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add trigger stimulus signal: 4
            indtriggers= find(strcmp(EventOutCombined.EventsLabels, 'Trigger'));
            eventonset = EventOutCombined.Onset{indtriggers};
            triggeronset =EventOutCombined.Onset{indtriggers};
            if size(eventonset, 1)<size(eventonset, 2)
                eventonset = eventonset';
            end;
            Ind_Trigger = find(strcmp(r.Behavior.Labels, 'Trigger'));
            indevent = [ones(length(eventonset), 1)*Ind_Trigger];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventonset];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indevent];

            % add valve onset and offset signals: 6 and 7
            indvalve= find(strcmp(EventOutCombined.EventsLabels, 'Valve'));
            eventonset = EventOutCombined.Onset{indvalve};
            eventoffset = EventOutCombined.Offset{indvalve};
            eventmix = [eventonset; eventoffset];
            Ind_ValveOn = find(strcmp(r.Behavior.Labels, 'ValveOnset'));
            Ind_ValveOff = find(strcmp(r.Behavior.Labels, 'ValveOffset'));

            indeventmix = [ones(length(eventonset), 1)*Ind_ValveOn; ones(length(eventoffset), 1)*Ind_ValveOff]; % frame onset  1; frame offset 2
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add poke onset signals: 8
            indpoke= find(strcmp(EventOutCombined.EventsLabels, 'Poke'));
            eventonset = EventOutCombined.Onset{indpoke};
            eventmix = [eventonset];
            Ind_Poke =  find(strcmp(r.Behavior.Labels, 'PokeOnset'));
            indeventmix = [ones(length(eventonset), 1)*Ind_Poke]; 
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add optostim, 
            indopto = find(strcmp(EventOutCombined.EventsLabels, 'OptoStim'));
            if ~isempty(indopto)
                eventonset = EventOutCombined.Onset{indopto};
                eventoffset = EventOutCombined.Offset{indopto}+BpodEvents.OptoStimDur;
                eventmix = [eventonset; eventoffset];
                Ind_OptoOn = find(strcmp(r.Behavior.Labels, 'OptoStimOn'));
                Ind_OptoOff = find(strcmp(r.Behavior.Labels, 'OptoStimOff'));
                indeventmix = [Ind_OptoOn*ones(length(eventonset), 1); Ind_OptoOff*ones(length(eventoffset), 1)]; %
                r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
                r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];
            end;

            % add approach signal, if there is any, if there is
            % any.11
            indapproach = find(strcmp(EventOutCombined.EventsLabels, 'Approach'));
            if ~isempty(indapproach)
                eventonset = EventOutCombined.Onset{indapproach};
                eventmix = [eventonset];
                Ind_Approach = find(strcmp(r.Behavior.Labels, 'Approach'));
                indeventmix = [Ind_Approach*ones(length(eventonset), 1)]; %
                r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
                r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];
            end;

            % sort timing
            [r.Behavior.EventTimings, index_timing] = sort(r.Behavior.EventTimings);
            r.Behavior.EventMarkers = r.Behavior.EventMarkers(index_timing);
            % write down periods of time that should be blanked out (e.g.,
            % wire connection becomes loose)
            r.Behavior.BlankOut = BlankOut;

%%      plot behavior data
            app.UIAxesBehav.NextPlot = 'add';
            app.UIAxesBehav.XLim = [0 max(r.Behavior.EventTimings/(1000))]; % in second
            app.UIAxesBehav.YTick = [1:length(r.Behavior.Labels)];
            app.UIAxesBehav.YTickLabel = r.Behavior.Labels;

            plot(app.UIAxesBehav, [r.Behavior.EventTimings/(1000)], [r.Behavior.EventMarkers],'o', 'color', 'k','markersize', 3, 'linewidth', 1)
            line(app.UIAxesBehav, [0 max(r.Behavior.EventTimings/(1000))], [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', 'k')

            %% add spikes

            r.Units.Tetrodes                                = [1:8];
            for i =1:length(r.Units.Tetrodes)
                r.Units.TetrodeMapping{i} = eval(['app.ChsEditField_' num2str(i) '.Value']);
            end;
            r.Units.Profile                                    = units;
            r.Units.Definition                               = {'tetrode_id cluster_id', '1: single unit', '2: multi unit'};
            r.Units.SpikeNotes                             = [];
            for i                                              = 1:size(units, 1)
                ich = units{i, 1};
                sorting_code                             = units{i, 2};
                for k                                              = 1:length(sorting_code)
                    switch sorting_code(k)
                        case 'm'
                            r.Units.SpikeNotes                                   = [r.Units.SpikeNotes; units{i, 1} k 2 0];
                        case 's'
                            r.Units.SpikeNotes                                   = [r.Units.SpikeNotes; units{i, 1} k 1 0];
                    end;
                end
            end;

            NBehaveMax = max(r.Behavior.EventMarkers);
            app.UIAxesBehav.YLim = [0 NBehaveMax+size(r.Units.SpikeNotes, 1)+3];

            spkchs = unique(r.Units.SpikeNotes(:, 1));
            allcolors                                          = varycolor(length(spkchs));

            % load raw so we can have time
            if exist(['chdat1.mat'])>0
                raw = load('chdat1.mat');
            else
                raw                  = load(['chdat' num2str(r.Units.SpikeNotes(1, 1)) '.mat']);
            end;
            torg = raw.index; % also in ms
            tnew = [1:length(raw.index)]*1000/30000; % tnew in ms
            % Sometimes, we only include the later sessions. Check
            % EphysSegsEditField for this information
            EphysSegs = [];
            if ~isempty(app.EphysSegsEditField.Value)
                EphysSegs = str2num(app.EphysSegsEditField.Value);
            end;
            % Determine the beginning and ending of ephys segments. 
            SegBegs         =           [1 1+find(diff(torg)>100)];
            SegEnds         =           [find(diff(torg)>100) length(torg)];

            if length(SegBegs)~=length(r.Meta)
                disp('Check data! Ephys and Behavior are not aligned. Different segment number.')
            end;

            if ~isempty(EphysSegs)
                for ks = 1:length(EphysSegs)
                    if EphysSegs(ks)==0
                        tnew([SegBegs(ks):SegEnds(ks)]) = NaN;
                    end;
                end;
            end;

            torg = torg - torg(find(~isnan(tnew), 1, 'first'));

            % put spikes
            for i  = 1:size(r.Units.SpikeNotes, 1)
                tetrode_id                               =   r.Units.SpikeNotes(i, 1);  % channel id
                cluster_id                                =  r.Units.SpikeNotes(i, 2);  % cluster id
                r.Units.SpikeTimes(i)              =   struct('timings',  [], 'wave', [], 'spk_id', []);
                channels                                 =   str2num(eval(['app.ChsEditField_' num2str(tetrode_id) '.Value']));
%                 raw                                          =   load(['chdat' num2str(channels(1)) '.mat']);                
%                 tnew                                        =    [1:length(raw.index)]*1000/30000;
                % load spike time:
                spk_id                                                 = load(['times_polytrode' num2str(tetrode_id) '.mat']);
                spk_in_ms                                           = round((spk_id.cluster_class(spk_id.cluster_class(:, 1)==cluster_id, 2))); % this is not mapped to time in recording
                [~, spkindx]                                          =     intersect(tnew, spk_in_ms);
                spk_in_ms_new                                   =    round(torg(spkindx));
                r.Units.SpikeTimes(i).timings                   = [r.Units.SpikeTimes(i).timings;  spk_in_ms_new]; % in ms
                these_waves                                            = spk_id.spikes(spk_id.cluster_class(:, 1)==cluster_id, :); 
                r.Units.SpikeTimes(i).wave                      = [r.Units.SpikeTimes(i).wave;  these_waves];

                % remove 80% of spike waveforms to reduce file size
                % index spk train
                r.Units.SpikeTimes(i).spk_id            = 1:length(r.Units.SpikeTimes(i).timings);
                if length(r.Units.SpikeTimes(i).timings)>10000
                    remove_percentage                         = 0.9;
                else
                    remove_percentage                         = 1-1000/length(r.Units.SpikeTimes(i).timings);
                end;

                if remove_percentage >0
                    ind_to_remove = randperm(length(r.Units.SpikeTimes(i).timings), round(length(r.Units.SpikeTimes(i).timings)*remove_percentage));
                    r.Units.SpikeTimes(i).spk_id(ind_to_remove) = [];
                    r.Units.SpikeTimes(i).wave(ind_to_remove, :) = [];
                end;

                x_plot                                             = r.Units.SpikeTimes(i).timings;
                x_plot                                             = [x_plot]/(1000);
                y_plot                                             =  i +2 + 0.8*rand(1, length(x_plot)) + NBehaveMax;
                if ~isempty(x_plot)
                    plot(app.UIAxesBehav, x_plot, y_plot,'.', 'color', allcolors(spkchs ==tetrode_id, :),'markersize', 4);
                end;
            end;

            % make sure UIAxesBehav and UIAxesRaster have the same width
            % make sure UIAxesBehav and UIAxesRaster have the same width
            app.UIAxesBehav.Position=[520 10 580 20*NBehaveMax+20*size(r.Units.SpikeNotes, 1)];
            app.UIAxesBehav.UserData = r;

            %% check if analog signal should be added to r
            if app.AddforceCheckBox.Value == 1
                x1 = dir('Force*.mat');
                if ~isempty(x1)
                    fdata = load(x1.name);
%                     tnew = [1:length(fdata.index)]*1000/30000;
                    r.Analog.Force = [torg; fdata.data]';
                    % resample data to 1000/sec
                    downsample_ratio = 30000/1000;                    
                    r.Analog.Force = downsample(r.Analog.Force, downsample_ratio);
                end;
            end;

            if app.AddlaserCheckBox.Value == 1
                x2 = dir('Laser*.mat');
                if ~isempty(x2)
                    odata = load(x2.name);
%                     tnew = [1:length(odata.index)]*1000/30000;
                    r.Analog.Opto = [torg; odata.data]';
                    % resample data to 1000/sec
                    downsample_ratio = 30000/1000;
                    r.Analog.Opto = downsample(r.Analog.Opto, downsample_ratio);
                end;
            end;

            tic
            save (aGoodName,'r', '-v7.3');
            toc

            % print this gui
            SaveGUIfigButtonPushed(app, event)
            clc
            disp('~~~~~~~~~~~~~~~~~~')
            disp('~~~~~R is ready~~~~~')
            disp('~~~~~~~~~~~~~~~~~~') 
        end

        % Callback function
        function SaveGUIfigButtonPushed(app, event)
            % save current figure

            if ~isempty(app.BuildButton.UserData)
                %             aGoodName   = ['RTarray_', b.Metadata.SubjectName, '_' b.Metadata.Date '.mat'];
                aGoodName = extractBefore(app.BuildButton.UserData, '.mat');
                exportapp(app.UIFigure,[aGoodName '.png'])
                exportapp(app.UIFigure,[aGoodName '.pdf'])
            else
                exportapp(app.UIFigure,'RTarray.png')
                exportapp(app.UIFigure,'RTarray.pdf')
            end;

        end

        % Callback function
        function CheckunitsButtonPushed(app, event)
            % Making PSTHs
            % search for ratio button that is on 
            % Check spks
            iCh = [];

            for i = 1:8
                ivalue = eval(['app.Tetrode' num2str(i) 'Button' '.Value']);
                if ivalue

                iCh = i;  % channel index
                type_units          =       eval(['app.EditField_' num2str(i) '.Value']);
                type_units          =       cellstr(type_units');
                n_units               =       length(type_units);

                allUnits = {};
                for k =1:n_units
                    allUnits{k} = ['unit' num2str(k) '_' type_units{k}];
                end;

                app.UnitsInCh.Items = allUnits;
                app.UnitsInCh.Value = app.UnitsInCh.Items{1};
                app.UnitsInCh.UserData =  i;

                end;
            end;

            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesBehav.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);

            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);

            session_length = app.UIAxesBehav.XLim;

%             if isempty(app.PlotPSTHButton.UserData)
%                 app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
%             else
%                 delete(app.PlotPSTHButton.UserData)
%                 app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
%             end;
        
        end

        % Callback function
        function UnitsInChValueChanged(app, event)
            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesBehav.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);

            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);

            session_length = app.UIAxesBehav.XLim;

%             if isempty(app.PlotPSTHButton.UserData)
%                 app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
%             else
%                 delete(app.PlotPSTHButton.UserData)
%                 app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
%             end;

        end

        % Callback function
        function PlotPSTHButtonPushed(app, event)
            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesBehav.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);
            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);
            session_length = app.UIAxesBehav.XLim;

            % plot PSTH
            SRTSpikesV5(r, [ich, ik], 'FRrange',[0 FR_range], 'Type', 'Tetrode');

        end

        % Callback function
        function PlotAllPSTHsButtonPushed(app, event)
            %plot population responses
            r = app.UIAxesBehav.UserData;

            FR_range = max([app.SpkmaxEditField.Value 20]);

            for i =1: length(r.Units.SpikeTimes)
                %     SRTSpikes(r, i, 'FRrange', FRrange);
                SRTSpikesV5(r, i, 'FRrange',[0 FR_range], 'Name', '', 'Type', 'Tetrode');
                close all;
            end;
% 
%             PSTHPop = SRTSpikesPopulation(r);
        end

        % Callback function
        function LoadButtonPushed(app, event)

            [filename, pathname, ~] = uigetfile({'RT*.mat','MAT-files (*.mat)'}, ...
                'Pick r array');

            load(filename)
            
            app.UIAxesBehav.UserData                 =      r;
            app.ANMnameEditField.Value              =      r.Meta(1).Subject;
            app.ExperimenterEditField.Value         =       r.Meta(1).Experimenter;
            % fill in spike information

             for i = 1:size(r.Units.Profile, 1)
                ich = r.Units.Profile{i, 1}; 
                iunits = strrep(r.Units.Profile{i, 2}, ' ', ''); 
                eval(['app.EditField_' num2str(ich) '.Value' '=' 'iunits']);
             end;
 
            app.UIAxesBehav.NextPlot = 'add';
            app.UIAxesBehav.XLim = [0 max(r.Behavior.EventTimings/(1000))]; % in second
            app.UIAxesBehav.YTick = [1:length(r.Behavior.Labels)];
            app.UIAxesBehav.YTickLabel = r.Behavior.Labels;

            plot(app.UIAxesBehav, [r.Behavior.EventTimings/(1000)], [r.Behavior.EventMarkers],'o', 'color', 'k','markersize', 3, 'linewidth', 1)
            line(app.UIAxesBehav, [0 max(r.Behavior.EventTimings/(1000))], [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', [0.8 0.8 0.8])
         
            NBehaveMax = max(r.Behavior.EventMarkers);
            app.UIAxesBehav.YLim = [0 NBehaveMax+size(r.Units.SpikeNotes, 1)+3];

            spkchs = unique(r.Units.SpikeNotes(:, 1));
            allcolors                                          = varycolor(length(spkchs));
            % put spikes
            for i                                              = 1:size(r.Units.SpikeNotes, 1)
                channel_id                                         = r.Units.SpikeNotes(i, 1);  % channel id
    
                x_plot                                             = r.Units.SpikeTimes(i).timings;
                x_plot                                             = [x_plot]/(1000);
                y_plot                                             =  i +2 + 0.8*rand(1, length(x_plot)) + NBehaveMax;

                if ~isempty(x_plot)
                    plot(app.UIAxesBehav, x_plot, y_plot,'.', 'color', allcolors(spkchs ==channel_id, :),'markersize', 4);
                end;

            end;

            % make sure UIAxesBehav and UIAxesRaster have the same width
            app.UIAxesBehav.Position=[520 10 580 20*NBehaveMax+20*size(r.Units.SpikeNotes, 1)];

        end

        % Callback function
        function PlotpopulationButton_2Pushed(app, event)
            %plot population responses
            r = app.UIAxesBehav.UserData;
            % check if it is two-FP protocol or wait protocol
            switch app.MEDProtocolDropDown.Value
                case 'TwoFPs'
                    PSTHPop = SRTSpikesPopulation(r);
                case 'Wait1'
                    PSTHPop = SRTSpikesPopulationWait(r);
                case 'Wait2'
                    PSTHPop = SRTSpikesPopulationWait(r);
            end;
        end

        % Value changed function: SegmentsDropDown
        function SegmentsDropDownValueChanged(app, event)
            value = app.SegmentsDropDown.Value;
            
        end

        % Button pushed function: AutofillButton
        function AutofillButtonPushed(app, event)
            % 'autofill' will look for 'ListUnitsSorted.csv'
            filename = 'ListUnitsSorted.csv';

            if isempty(dir(filename))
                disp('You do not have ListUnitsSorted.csv')
            else
                tab = readtable(filename);
                chs = unique(tab.Channels);

                for i =1:length(chs)
                    ich = chs(i);
                    iunits = find(tab.Channels == ich);
                    sym_units = '';
                    for j =1:length(iunits)
                        sym_units = [sym_units tab.UnitTypes{iunits(j)}];
                    end;
                    eval(['app.EditField_' num2str(ich) '.Value = sym_units;']);
                end;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BuildR4Tetrodeversion202023Panel = uipanel(app.GridLayout);
            app.BuildR4Tetrodeversion202023Panel.Title = 'BuildR4Tetrode version 2.0 (2023)';
            app.UIAxesBehav = uiaxes(app.BuildR4Tetrodeversion202023Panel);
            title(app.UIAxesBehav, {'Behavior and spikes'; ''; ''; ''})
            xlabel(app.UIAxesBehav, 'seconds')
            zlabel(app.UIAxesBehav, 'Z')
            app.UIAxesBehav.Tag = 'raster';
            app.PolytrodesButtonGroup = uibuttongroup(app.BuildR4Tetrodeversion202023Panel);
            app.PolytrodesButtonGroup.Title = '13|Polytrodes';
            app.Tetrode1Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode1Button.Text = 'Tetrode1';
            app.Tetrode1Button.Value = true;
            app.Tetrode2Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode2Button.Text = 'Tetrode2';
            app.Tetrode3Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode3Button.Text = 'Tetrode3';
            app.Tetrode4Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode4Button.Text = 'Tetrode4';
            app.Tetrode5Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode5Button.Text = 'Tetrode5';
            app.Tetrode6Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode6Button.Text = 'Tetrode6';
            app.Tetrode7Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode7Button.Text = 'Tetrode7';
            app.Tetrode8Button = uiradiobutton(app.PolytrodesButtonGroup);
            app.Tetrode8Button.Text = 'Tetrode8';
            app.EditField_1 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_2 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_3 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_4 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_5 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_6 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_7 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_8 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.EditField_8.Value = ' ';
            app.egsssssmoremptyLabel = uilabel(app.PolytrodesButtonGroup);
            app.egsssssmoremptyLabel.Text = 'e.g., s, ss, ssm, or empty';
            app.ChsEditFieldLabel = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditFieldLabel.Text = 'Chs';
            app.ChsEditField_1 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_1.Value = '1, 3, 5, 7';
            app.ChsEditField_2Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_2Label.Text = 'Chs';
            app.ChsEditField_2 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_2.Value = '2, 4, 6, 8';
            app.ChsEditField_3Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_3Label.Text = 'Chs';
            app.ChsEditField_3 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_3.Value = '9, 11, 13, 15';
            app.ChsEditField_4Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_4Label.Text = 'Chs';
            app.ChsEditField_4 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_4.Value = '10, 12, 14, 16';
            app.ChsEditField_5Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_5Label.Text = 'Chs';
            app.ChsEditField_5 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_5.Value = '17,19,21,23';
            app.ChsEditField_6Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_6Label.Text = 'Chs';
            app.ChsEditField_6 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_6.Value = '18,20,22,24 ';
            app.ChsEditField_7Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_7Label.Text = 'Chs';
            app.ChsEditField_7 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_7.Value = '25,27,29,31 ';
            app.ChsEditField_8Label = uilabel(app.PolytrodesButtonGroup);
            app.ChsEditField_8Label.Text = 'Chs';
            app.ChsEditField_8 = uieditfield(app.PolytrodesButtonGroup, 'text');
            app.ChsEditField_8.Value = '26,28,30,32';
            app.AutofillButton = uibutton(app.PolytrodesButtonGroup, 'push');
            app.AutofillButton.ButtonPushedFcn = createCallbackFcn(app, @AutofillButtonPushed, true);
            app.AutofillButton.Tooltip = {'Need ''ListUnitsSorted.csv'''};
            app.AutofillButton.Text = 'Autofill';
            app.MEDfileLabel = uilabel(app.BuildR4Tetrodeversion202023Panel);
            app.MEDfileLabel.Text = {'MED file'; ''};
            app.BpodfileLabel = uilabel(app.BuildR4Tetrodeversion202023Panel);
            app.BpodfileLabel.Text = {'Bpod file'; ''};
            app.BuildButton = uibutton(app.BuildR4Tetrodeversion202023Panel, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = '14|Build';
            app.AddforceCheckBox = uicheckbox(app.BuildR4Tetrodeversion202023Panel);
            app.AddforceCheckBox.Text = '9|Add force';
            app.AddlaserCheckBox = uicheckbox(app.BuildR4Tetrodeversion202023Panel);
            app.AddlaserCheckBox.Text = '10|Add laser';
            app.BlankoutLabel = uilabel(app.BuildR4Tetrodeversion202023Panel);
            app.BlankoutLabel.Text = '11|Blank-out';
            app.BlankoutEditField = uieditfield(app.BuildR4Tetrodeversion202023Panel, 'text');
            app.EphysSegsLabel = uilabel(app.BuildR4Tetrodeversion202023Panel);
            app.EphysSegsLabel.Text = '12|Ephys Segs';
            app.EphysSegsEditField = uieditfield(app.BuildR4Tetrodeversion202023Panel, 'text');
            app.EphysSegsEditField.Tooltip = {'if present, keep ephys segments . eg.. [0, 0, 1, 1] means keep data 003, and 004 '; ''};
            app.Panel = uipanel(app.BuildR4Tetrodeversion202023Panel);
            app.Panel.Title = 'Panel';
            app.ANMnameLabel = uilabel(app.Panel);
            app.ANMnameLabel.Text = '1|ANM name';
            app.ANMnameEditField = uieditfield(app.Panel, 'text');
            app.ExperimenterLabel = uilabel(app.Panel);
            app.ExperimenterLabel.Text = '2|Experimenter';
            app.ExperimenterEditField = uieditfield(app.Panel, 'text');
            app.SegmentsLabel = uilabel(app.Panel);
            app.SegmentsLabel.Text = {'3|Segments'; ''; ''};
            app.SegmentsDropDown = uidropdown(app.Panel);
            app.SegmentsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SegmentsDropDownOpening, true);
            app.SegmentsDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentsDropDownValueChanged, true);
            app.KornblumCheckBox = uicheckbox(app.Panel);
            app.KornblumCheckBox.Text = '4|Kornblum';
            app.LoadMEDfileButton = uibutton(app.Panel, 'state');
            app.LoadMEDfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadMEDfileButtonValueChanged, true);
            app.LoadMEDfileButton.Text = {'5|Load MED file'; ''};
            app.LoadBpodfileButton = uibutton(app.Panel, 'state');
            app.LoadBpodfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadBpodfileButtonValueChanged, true);
            app.LoadBpodfileButton.Text = {'6|Load Bpod file'; ''};
            app.BpodProtocolLabel = uilabel(app.Panel);
            app.BpodProtocolLabel.Text = '7|Bpod Protocol';
            app.BpodProtocolDropDown = uidropdown(app.Panel);
            app.BpodProtocolDropDown.Items = {'OptoRecording', 'OptoRecordingMix'};
            app.BpodProtocolDropDown.Value = 'OptoRecording';
            app.DIversionLabel = uilabel(app.Panel);
            app.DIversionLabel.Text = '6|DI version';
            app.DIversionDropDown = uidropdown(app.Panel);
            app.DIversionDropDown.Items = {'Version4', 'Version5'};
            app.DIversionDropDown.Value = 'Version4';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SegmentsDropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BuildR4Tetrodes2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end