classdef BuildRKilosortMS2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        OpenCheckerappButton         matlab.ui.control.Button
        ShowrankingButton            matlab.ui.control.Button
        OpenAnalysisappButton        matlab.ui.control.Button
        UIAxesBehav                  matlab.ui.control.UIAxes
        UIAxesWaveSimilarity         matlab.ui.control.UIAxes
        UIAxesISISimilarity          matlab.ui.control.UIAxes
        Panel_2                      matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        SaveGUIfigButton             matlab.ui.control.Button
        BuildButton                  matlab.ui.control.Button
        BlankoutEditField            matlab.ui.control.EditField
        BlankoutLabel                matlab.ui.control.Label
        BuildRversion202023Label     matlab.ui.control.Label
        AddlaserCheckBox             matlab.ui.control.CheckBox
        AddforceCheckBox             matlab.ui.control.CheckBox
        BehaviorandmetadataPanel     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        MEDfilenamesDropDown         matlab.ui.control.DropDown
        MEDfilenamesLabel            matlab.ui.control.Label
        BpodProtocolDropDown         matlab.ui.control.DropDown
        BpodProtocolLabel            matlab.ui.control.Label
        DIversionDropDown            matlab.ui.control.DropDown
        DIversionLabel               matlab.ui.control.Label
        ProtocolDropDown             matlab.ui.control.DropDown
        ProtocolLabel                matlab.ui.control.Label
        BpodfilenamesDropDown        matlab.ui.control.DropDown
        BpodfilenamesDropDownLabel   matlab.ui.control.Label
        SegmentsDropDown             matlab.ui.control.DropDown
        SegmentsLabel                matlab.ui.control.Label
        ProbeCheckBox                matlab.ui.control.CheckBox
        BpodfileLabel                matlab.ui.control.Label
        MEDfileLabel                 matlab.ui.control.Label
        LoadBpodfileButton           matlab.ui.control.StateButton
        LoadMEDfileButton            matlab.ui.control.StateButton
        ANMnameEditField             matlab.ui.control.EditField
        ANMLabel                     matlab.ui.control.Label
        ExperimenterEditField        matlab.ui.control.EditField
        ExperimenterLabel            matlab.ui.control.Label
        KornblumCheckBox             matlab.ui.control.CheckBox
        SpikeTablefromKilosortPanel  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        cCrossCorretionButton        matlab.ui.control.Button
        bLoadKilosortOutputButton    matlab.ui.control.Button
        waitingLabel                 matlab.ui.control.Label
        aBuildspiketableButton       matlab.ui.control.Button
        UIAxesISI                    matlab.ui.control.UIAxes
        UIAxesAuto                   matlab.ui.control.UIAxes
        UIAxesWave                   matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadMEDfileButton
        function LoadMEDfileButtonValueChanged(app, event)
            value = app.LoadMEDfileButton.Value;
            % Look for MED file
            MEDFile = dir('*Subject*.txt');
            if isempty(MEDFile)
                display('Check folder')
            end
            % Compute behavioral data from MED file
            if app.KornblumCheckBox.Value == 1
                % Note: my (JY's) codes are stored in packages, e.g.,
                % track_training_progress_advanced_KornblumStyle can be
                % found in Behavior.MED.track_training_progress_advanced_KornblumStyle
                myclass_all = cell(1, length(MEDFile));
                MED_items = cell(1, length(MEDFile));
                for i =1:length(MEDFile)
                    MED_items{i} = MEDFile(i).name;
                    b = Behavior.MED.track_training_progress_advanced_KornblumStyle(MEDFile(i).name);
                    myclass = Behavior.Timing.KornblumClass(MEDFile(i).name);
                    myclass.Plot();
                    myclass.Save()
                    myclass.Print()
                    myclass_all{i} = myclass;
                end
            elseif app.ProbeCheckBox.Value==1
                myclass_all = cell(1,length(MEDFile));
                MED_items = cell(1,length(MEDFile));
                for i=1:length(MEDFile)
                    MED_items{i} = MEDFile(i).name;
                    [b,myclass] = Behavior.MED.track_training_probe(MEDFile(i).name);
                    myclass.Plot();
                    myclass.Save()
                    myclass.Print()
                    myclass_all{i} = myclass;
                end
            else
                myclass_all = cell(1, length(MEDFile));
                MED_items = cell(1, length(MEDFile));
                for i =1:length(MEDFile)
                    MED_items{i} = MEDFile(i).name;
                    [b, myclass] =Behavior.MED.track_training_progress_advanced(MEDFile(i).name);
                    myclass.Plot();
                    myclass.Save()
                    myclass.Print()
                    myclass_all{i} = myclass;
                end
            end

            % place b and kc (if there is any)
            app.MEDfilenamesDropDown.Items = MED_items;            
            app.LoadMEDfileButton.UserData = myclass_all;
            
            
        end

        % Drop down opening function: SegmentsDropDown
        function SegmentsDropDownOpening(app, event)
            x = dir('datafile*.nev');
            AllSegments = arrayfun(@(x)x.name, x, 'UniformOutput', false);
            app.SegmentsDropDown.Items = AllSegments; 
        end

        % Value changed function: LoadBpodfileButton
        function LoadBpodfileButtonValueChanged(app, event)
      value = app.LoadBpodfileButton.Value;
            name = app.ANMnameEditField.Value;
            BpodFile = dir([name '_*.mat']);

            if ~isempty(BpodFile)
                if length(BpodFile) == 1
                    app.BpodfilenamesDropDown.Items = {BpodFile.name};
                    load(BpodFile.name,'SessionData');
                    app.LoadBpodfileButton.UserData = {SessionData};
                else
                    BpodFileNames = cell(1, length(BpodFile));
                    SessionDataFiles = cell(1, length(BpodFile));
                    for i = 1:length(BpodFile)
                        BpodFileNames{i} = BpodFile(i).name;
                        load(BpodFile(i).name,'SessionData');
                        SessionDataFiles{i} = SessionData;
                    end
                    app.BpodfilenamesDropDown.Items = BpodFileNames;
                    app.LoadBpodfileButton.UserData = SessionDataFiles;
                end
            end

        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            % These are from non-kilosort versions
             % These are from kilosort spike table
            ko = app.bLoadKilosortOutputButton.UserData; % this is KilosortOutput.mat
            SpikeTable = app.bLoadKilosortOutputButton.UserData.SpikeTable;
            units = {};
            k = 1;
            while k<=height(SpikeTable)
              
                channel = SpikeTable(k,:).ch{1};
                j = k+1;
                while j<=height(SpikeTable) && SpikeTable(j,:).ch{1} == channel
                    j = j+1;
                end
                IndNew=size(units, 1)+1;
                type = '';
                phy_id = [];
                for i = k:j-1
                    phy_id = [phy_id SpikeTable(i, :).cluster_id];
                    if strcmp(SpikeTable(i,:).group{1},'good')
                        type = [type, 's'];
                    elseif strcmp(SpikeTable(i,:).group{1},'mua')
                        type = [type, 'm'];
                    else
                        continue
                    end
                end
                units{IndNew, 1} = channel;
                units{IndNew, 2} = type;
                units{IndNew, 3} = [];
                units{IndNew, 4} = phy_id;
                k = j;
            end
      
            %% Behavior relevant


            BlankOut = [];
            if ~isempty(app.BlankoutEditField.Value)
                BlankOut = str2num(app.BlankoutEditField.Value);
                BlankOut = reshape(BlankOut, 2, []);
            end;

            % here we build r array based on available information
%             b                      =        app.MEDfileLabel.UserData;

            BehaviorClass =        app.LoadMEDfileButton.UserData;
            SessionData    =       app.LoadBpodfileButton.UserData;
            blocks              =       app.SegmentsDropDown.Items;
            EventOutCombined = []; % this one combines all event times and difference in time between blocks.

            % a name for saving the r array
            aGoodName   = ['RTarray_', BehaviorClass{1}.Subject, '_' BehaviorClass{1}.Date '.mat'];
            dBlockOnset = 0;
            % calculate time difference between different blocks

            for ib=1:length(blocks)
                nevfile = blocks{ib};
                openNEV(nevfile, 'report', 'read')  % open ‘datafile###.nev’, create “datafile###.mat”
                load([nevfile(1:11) '.mat'])
                switch app.DIversionDropDown.Value
                    case 'Version4'
                        EventOut = DIO_Events4(NEV); % create
                    case 'Version5'
                        EventOut = DIO_Events5(NEV); % create
                        % Poke signals are incorrect. Update poke from bpod.  10/4/2022
                        EventOut.Onset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                        EventOut.Offset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                end;
                
                if ib == 1
                    openNSx([blocks{ib}(1:end-3),'ns6'], 'read', 'report')
                    NS6all = NS6;
                else
                    openNSx([blocks{ib}(1:end-3),'ns6'], 'read', 'report')
                    NS6all(ib) = NS6;
                end

                if ib == 1
%                     RecordingOnset = EventOut.Meta.DateTimeRaw;
                    RecordingOnset = NS6all(ib).MetaTags.DateTimeRaw;
                end

                if ib>1
%                     dt_i = EventOut.Meta.DateTimeRaw-RecordingOnset;
                    dt_i = NS6all(ib).MetaTags.DateTimeRaw - RecordingOnset;
                    dBlockOnset(ib)=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % in ms
                end;
                %  update poke information in EventOut with bpod events

                EventOut.Meta.Subject = app.ANMnameEditField.Value;
                EventOut.Meta.Experimenter = app.ExperimenterEditField.Value;

                if isfield(EventOut, 'Subject')
                    EventOut = rmfield(EventOut, 'Subject');
                end;
                if isfield(EventOut, 'Experimenter')
                    EventOut = rmfield(EventOut, 'Experimenter');
                end;

                if ib ==1
                    EventOutCombined=EventOut;
                    EventOutCombined = rmfield(EventOutCombined, 'TimeEvents');
                else
                    EventOutCombined.Meta(ib) = EventOut.Meta;
                    for k = 1:length(EventOutCombined.EventsLabels)
                        EventOutCombined.Onset{k} = [EventOutCombined.Onset{k}; EventOut.Onset{k}+dBlockOnset(ib)];
                        EventOutCombined.Offset{k} = [EventOutCombined.Offset{k}; EventOut.Offset{k}+dBlockOnset(ib)];
                    end;
                end;
            end;
           
            % Align Events
            switch app.BpodProtocolDropDown.Value
                case 'OptoRecording'
                    %  read bpod events
                    BpodEvents = cell(1, length(SessionData));
                    for i =1:length(BpodEvents)
                        BpodEvents{i} = Spikes.Array.Bpod_Events_MedOptoRecording(SessionData{i});
                    end
                    % Update poke based on data from Bpod events
                    EventOutCombined = Spikes.Array.UpdatePokeFromBpodEventsMS(EventOutCombined, BpodEvents);
                case 'OptoRecordingMix'  % optogenetic stimulation was applied at the onset of different events, we need to extract those times, and align them to blackrock's time
                    %  read bpod events
                    error('Not yet completed')
%                     BpodEvents = Bpod_Events_MedOptoRecMix(SessionData);
%                     EventOutCombined = UpdateDIOMedOptoRecMix(EventOutCombined, BpodEvents);
            end

                EventOutCombined = Spikes.Array.AlignBehaviorClassToBR_MS(EventOutCombined, BehaviorClass);

            %% construct an array (r) with aligned behavior, spikes and LFP data.
            % name is r

            % turn everything in minutes
            % single unit: 1; multiunit: 2
            r=[];
            r.BehaviorClass = BehaviorClass; % added 2023
            r.Meta                =  EventOutCombined.Meta;

            r.Behavior.Labels={
                'FrameOn',...
                'FrameOff', ...
                'LeverPress', ...
                'Trigger',...
                'LeverRelease',...
                'ValveOnset', ...
                'ValveOffset',...
                'PokeOnset',...
                'OptoStimOn',...
                'OptoStimOff'};
            
            r.Behavior.LabelMarkers = [1:length(r.Behavior.Labels)];

            r.Behavior.Outcome                        = EventOutCombined.OutcomeEphys;
            % the followings are redundant but are listed so that other
            % programs still work. 
            r.Behavior.CorrectIndex                 =      find(strcmp(r.Behavior.Outcome, 'Correct'));
            r.Behavior.PrematureIndex            =      find(strcmp(r.Behavior.Outcome, 'Premature'));
            r.Behavior.LateIndex                      =      find(strcmp(r.Behavior.Outcome, 'Late'));
            r.Behavior.DarkIndex                     =      find(strcmp(r.Behavior.Outcome, 'Dark'));
            r.Behavior.Foreperiods                  =      EventOutCombined.FP_Ephys;
            r.Behavior.CueIndex                      =      EventOutCombined.CueEphys;

            r.Behavior.EventTimings = [];
            r.Behavior.EventMarkers = [];
            % add frame signal: 1 on, 2 off
            indframe = find(strcmp(EventOutCombined.EventsLabels, 'Frame'));
            eventonset = EventOutCombined.Onset{indframe};
            eventoffset = EventOutCombined.Offset{indframe};
            eventmix = [eventonset; eventoffset];
            indeventmix = [ones(length(eventonset), 1); ones(length(eventoffset), 1)*2]; % frame onset  1; frame offset 2
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add leverpress onset and offset signal: 3 and 5
            indleverpress= find(strcmp(EventOutCombined.EventsLabels, 'LeverPress'));
            eventonset = EventOutCombined.Onset{indleverpress};
            eventoffset = EventOutCombined.Offset{indleverpress};
            eventmix = [eventonset; eventoffset];
            indeventmix = [ones(length(eventonset), 1)*3; ones(length(eventoffset),1)*5];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add trigger stimulus signal: 4
            indtriggers= find(strcmp(EventOutCombined.EventsLabels, 'Trigger'));
            eventonset = EventOutCombined.Onset{indtriggers};
            triggeronset =EventOutCombined.Onset{indtriggers};
            if size(eventonset, 1)<size(eventonset, 2)
                eventonset = eventonset';
            end;
            indevent = [ones(length(eventonset), 1)*4];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventonset];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indevent];

            figure(11); clf
            axes('nextplot', 'add', 'ylim', [0 10])

            plot(triggeronset, 4, 'go')
            text(triggeronset(1), 4.2, 'trigger')

            % add valve onset and offset signals: 6 and 7
            indvalve= find(strcmp(EventOutCombined.EventsLabels, 'Valve'));
            eventonset = EventOutCombined.Onset{indvalve};
            eventoffset = EventOutCombined.Offset{indvalve};
            eventmix = [eventonset; eventoffset];
            indeventmix = [ones(length(eventonset), 1)*6; ones(length(eventoffset), 1)*7]; % frame onset  1; frame offset 2
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            plot(eventoffset, 8, 'm^')
            text(eventoffset(1), 8.2, 'valve')

            % add poke onset signals: 8
            indpoke= find(strcmp(EventOutCombined.EventsLabels, 'Poke'));
            eventonset = EventOutCombined.Onset{indpoke};
            eventmix = [eventonset];
            indeventmix = [ones(length(eventonset), 1)*8]; 
            r.Behavior.EventTimings = [r.Behavior.EventTimings; eventmix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; indeventmix];

            % add optostim, if there is any. 9 on, 10 off
            indopto = find(strcmp(EventOutCombined.EventsLabels, 'OptoStim'));
            if ~isempty(indopto)
                eventonset                          =                       EventOutCombined.Onset{indopto};
                eventoffset                          =    EventOutCombined.Offset{indopto}+BpodEvents.OptoStimDur;
                eventmix                             =    [eventonset; eventoffset];
                indeventmix                        =     [9*ones(length(eventonset), 1); 10*ones(length(eventoffset), 1)]; %
                r.Behavior.EventTimings     =    [r.Behavior.EventTimings; eventmix];
                r.Behavior.EventMarkers     =    [r.Behavior.EventMarkers; indeventmix];
            end;

            % sort timing
            [r.Behavior.EventTimings, index_timing] = sort(r.Behavior.EventTimings);
            r.Behavior.EventMarkers = r.Behavior.EventMarkers(index_timing);

            % write down periods of time that should be blanked out (e.g.,
            % wire connection becomes loose)
            r.Behavior.BlankOut = BlankOut;

%%      plot behavior data
            app.UIAxesBehav.NextPlot = 'add';
            app.UIAxesBehav.XLim = [0 max(r.Behavior.EventTimings/(1000))]; % in second
            app.UIAxesBehav.YTick = [1:length(r.Behavior.Labels)];
            app.UIAxesBehav.YTickLabel = r.Behavior.Labels;

            plot(app.UIAxesBehav, [r.Behavior.EventTimings/(1000)], [r.Behavior.EventMarkers],'o', 'color', 'k','markersize', 3, 'linewidth', 1)
            line(app.UIAxesBehav, [0 max(r.Behavior.EventTimings/(1000))], [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', 'k')

            n_behavior_events = length(r.Behavior.Labels);
            %% Add spikes
            r.Units.Channels                                = 1:ko.ParamsKilosort.Nchan;
            r.Units.Profile                                    = units;
            r.Units.Definition                               = {'channel_id | cluster_id | unit_type | polytrode | phy cluster_id', '1: single unit', '2: multi unit'};
            r.Units.SpikeNotes                             = [];
            for i                                              = 1:size(units, 1)
                sorting_code                             = units{i, 2};
                for k                                              = 1:length(sorting_code)                    
                    switch sorting_code(k)
                        case 'm'
                            r.Units.SpikeNotes                                   = [r.Units.SpikeNotes; units{i, 1} k 2   units{i, 4}(k)];
                        case 's'
                            r.Units.SpikeNotes                                   = [r.Units.SpikeNotes; units{i, 1} k 1 units{i, 4}(k)];
                    end
                end
            end

            spkchs = unique(r.Units.SpikeNotes(:, 1));
            allcolors                                          = varycolor(length(spkchs));
            % re-scale y axis of this axis so that we can put spikes in 
            app.UIAxesBehav.YLim = [0 size(r.Units.SpikeNotes , 1)+1+n_behavior_events];
                    % put spikes
            for i = 1:size(r.Units.SpikeNotes, 1)
                    %  units{IndNew, 4} = phy_id;
                    ind_row = SpikeTable.cluster_id==r.Units.SpikeNotes(i, 4);
                    channel_id                              = r.Units.SpikeNotes(i, 1);  % channel id
%                 cluster_id                                = r.Units.SpikeNotes(i, 2);  % cluster id

                r.Units.SpikeTimes(i)                          =           struct('timings',  [], 'wave', [], 'spk_id', [], 'wave_mean',[]);
                r.Units.SpikeTimes(i).timings              =           SpikeTable(ind_row,:).spike_times_r{1};
                r.Units.SpikeTimes(i).wave                 =           SpikeTable(ind_row,:).waveforms{1};
                r.Units.SpikeTimes(i).wave_mean      =           SpikeTable(ind_row,:).waveforms_mean{1};
                % remove  90% of spike waveforms to reduce file size
                % index spk train
                r.Units.SpikeTimes(i).spk_id            = 1:length(r.Units.SpikeTimes(i).timings);
                if length(r.Units.SpikeTimes(i).timings)<1000
                    remove_percentage                         = 0;
                else
                    remove_percentage                         = 1-1000/length(r.Units.SpikeTimes(i).timings);
                end;

                if remove_percentage >0
                    ind_to_remove = randperm(length(r.Units.SpikeTimes(i).timings), round(length(r.Units.SpikeTimes(i).timings)*remove_percentage));
                    r.Units.SpikeTimes(i).spk_id(ind_to_remove) = [];
                    r.Units.SpikeTimes(i).wave(ind_to_remove, :) = [];
                end;
                x_plot                                             = r.Units.SpikeTimes(i).timings;
                x_plot                                             = [x_plot]/(1000);
                y_plot                                             =  i  + 0.8*rand(1, length(x_plot))+n_behavior_events;
                if ~isempty(x_plot)
                    plot(app.UIAxesBehav, x_plot, y_plot,'.', 'color', allcolors(spkchs ==channel_id, :),'markersize', 4);
                end;
            end;

             %% check if analog signal should be added to r
            if app.AddforceCheckBox.Value == 1
                x1 = dir('Force*.mat');
                if ~isempty(x1)
                    fdata = load(x1.name);
                    tnew = [1:length(fdata.index)]*1000/30000;
                    r.Analog.Force = [tnew; fdata.data]';
                    % resample data to 1000/sec
                    downsample_ratio = 30000/1000;                    
                    r.Analog.Force = downsample(r.Analog.Force, downsample_ratio);
                end;
            end;

            if app.AddlaserCheckBox.Value == 1
                x2 = dir('Laser*.mat');
                if ~isempty(x2)
                    odata = load(x2.name);
                    tnew = [1:length(odata.index)]*1000/30000;
                    r.Analog.Opto = [tnew; odata.data]';
                    % resample data to 1000/sec
                    downsample_ratio = 30000/1000;
                    r.Analog.Opto = downsample(r.Analog.Opto, downsample_ratio);
                end;
            end;
% 
%             % make sure UIAxesBehav and UIAxesRaster have the same width
%             % Final touch double check the alignment. 
%             % Check if it is kornblum class
%             if app.KornblumCheckBox.Value == 1
%                 CorrectBehaviorEphysMapping(r,  r.BehaviorClass); % this also save r in the current directory
%             end;

            tic
            save (aGoodName,'r', '-v7.3');
            toc

            % print this gui
            SaveGUIfigButtonPushed(app, event)
            clc
            disp('~~~~~~~~~~~~~~~~~~')
            disp('~~~~~R is ready~~~~~')
            disp('~~~~~~~~~~~~~~~~~~') 


        end

        % Button pushed function: SaveGUIfigButton
        function SaveGUIfigButtonPushed(app, event)
            % save current figure

            exportapp(app.UIFigure,'RTarray.png')
            exportapp(app.UIFigure,'RTarray.pdf')

        end

        % Callback function
        function CheckunitsButtonPushed(app, event)
            % Making PSTHs
            % search for ratio button that is on 
            % Check spks
            iCh = [];

            for i = 1:32
                ivalue = eval(['app.Ch' num2str(i) 'Button' '.Value']);
                if ivalue

                iCh = i;  % channel index
                type_units          =       eval(['app.EditField_' num2str(i) '.Value']);
                type_units          =       cellstr(type_units');
                n_units               =       length(type_units);

                allUnits = {};
                for k =1:n_units
                    allUnits{k} = ['unit' num2str(k) '_' type_units{k}];
                end;

                app.UnitsInCh.Items = allUnits;
                app.UnitsInCh.Value = app.UnitsInCh.Items{1};
                app.UnitsInCh.UserData =  i;

                end;
            end;

            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesRaster.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);

            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);

            session_length = app.UIAxesRaster.XLim;

            if isempty(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            else
                delete(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            end;
        
        end

        % Callback function
        function UnitsInChValueChanged(app, event)
            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesRaster.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);

            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);

            session_length = app.UIAxesRaster.XLim;

            if isempty(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            else
                delete(app.PlotPSTHButton.UserData)
                app.PlotPSTHButton.UserData = rectangle(app.UIAxesRaster, 'Position', [session_length(1) ind_Unit-1 diff(session_length) 1], 'linewidth', 2)
            end;

        end

        % Callback function
        function PlotPSTHButtonPushed(app, event)
            value = app.UnitsInCh.Value;
            all_Items = app.UnitsInCh.Items;
            r = app.UIAxesRaster.UserData;
            ich = app.UnitsInCh.UserData;
            ik = find(strcmp(all_Items, value));

            FR_range = max([app.SpkmaxEditField.Value 20]);
            % find out which one:
            ind_Unit = find(r.Units.SpikeNotes(:, 1)==ich & r.Units.SpikeNotes(:, 2)==ik);
            session_length = app.UIAxesRaster.XLim;

            PrePress = app.PreEditField.Value;
            PostPress = app.PostEditField.Value;

            % plot PSTH
            switch app.ProtocolDropDown.Value
                case 'TwoFPs'
                    SRTSpikesV5(r, [ich, ik], 'FRrange',[0 FR_range], 'PressTimeDomain', [PrePress PostPress]);
                case {'Wait1', 'Wait2'}
                    SRTSpikesWait(r,[ich, ik], 'FRrange',[0 FR_range], 'PressTimeDomain', [PrePress PostPress]);
            end;

        end

        % Callback function
        function PlotAllPSTHsButtonPushed(app, event)
            %plot population responses
            r = app.UIAxesRaster.UserData;

            FR_range = max([app.SpkmaxEditField.Value 20]);
            PrePress = app.PreEditField.Value;
            PostPress = app.PostEditField.Value;

            switch app.ProtocolDropDown.Value
                case 'TwoFPs'
                    for i =1: length(r.Units.SpikeTimes)
                        SRTSpikesV5(r, i, 'FRrange',[0 FR_range], 'Name', '', 'PressTimeDomain', [PrePress PostPress]);
                        close all;
                    end;
                case {'Wait1', 'Wait2'}
                    for i =1: length(r.Units.SpikeTimes)
                        SRTSpikesWait(r, i, 'FRrange',[0 FR_range], 'Name', '', 'PressTimeDomain', [PrePress PostPress]);
                        close all;
                    end;
            end;

% 
%             PSTHPop = SRTSpikesPopulation(r);
        end

        % Callback function
        function LoadButtonPushed(app, event)

            [filename, pathname, ~] = uigetfile({'RT*.mat','MAT-files (*.mat)'}, ...
                'Pick r array');
            load(filename)
            app.UIAxesRaster.UserData                =      r;
            app.ANMnameEditField.Value                      =      r.Meta(1).Subject;
            app.ExperimenterEditField.Value         =      r.Meta(1).Experimenter;
            % fill in spike information
             for i = 1:size(r.Units.Profile, 1)
                ich = r.Units.Profile{i, 1}; 
                iunits = strrep(r.Units.Profile{i, 2}, ' ', ''); 
                eval(['app.EditField_' num2str(ich) '.Value' '=' 'iunits']);
             end;
            app.UIAxesBehav.NextPlot = 'add';
            app.UIAxesBehav.XLim = [0 max(r.Behavior.EventTimings/(1000))]; % in second
            app.UIAxesBehav.YTick = [1:length(r.Behavior.Labels)];
            app.UIAxesBehav.YTickLabel = r.Behavior.Labels;

            plot(app.UIAxesBehav, [r.Behavior.EventTimings/(1000)], [r.Behavior.EventMarkers],'o', 'color', 'k','markersize', 3, 'linewidth', 1)
            line(app.UIAxesBehav, [0 max(r.Behavior.EventTimings/(1000))], [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', [0.8 0.8 0.8])

            spkchs = unique(r.Units.SpikeNotes(:, 1));
            allcolors                                          = varycolor(length(spkchs));

            app.UIAxesRaster.XLim = app.UIAxesBehav.XLim;
            app.UIAxesRaster.YLim = [0 size(r.Units.SpikeNotes , 1)+1];
            app.UIAxesRaster.NextPlot = 'Add';

            % put spikes
            for i                                              = 1:size(r.Units.SpikeNotes, 1)
                channel_id                                         = r.Units.SpikeNotes(i, 1);  % channel id    
                x_plot                                             = r.Units.SpikeTimes(i).timings;
                x_plot                                             = [x_plot]/(1000);
                y_plot                                             =  i -1 + 0.8*rand(1, length(x_plot));

                if ~isempty(x_plot)
                    plot(app.UIAxesRaster, x_plot, y_plot,'.', 'color', allcolors(spkchs ==channel_id, :),'markersize', 4);
                end;

            end;

            % make sure UIAxesBehav and UIAxesRaster have the same width
            app.UIAxesBehav.Position=[450 50 660 352];
            app.UIAxesRaster.Position=[530 380 580 352];
            app.UIAxesRaster.UserData = r;

        end

        % Callback function
        function PlotpopulationButton_2Pushed(app, event)
            %plot population responses
            r = app.UIAxesRaster.UserData;
            % check if it is two-FP protocol or wait protocol
            switch app.ProtocolDropDown.Value
                case 'TwoFPs'
                    PSTHPop = SRTSpikesPopulation(r);
                case 'Wait1'
                    PSTHPop = SRTSpikesPopulationWait(r);
                case 'Wait2'
                    PSTHPop = SRTSpikesPopulationWait(r);
            end;
        end

        % Value changed function: DIversionDropDown
        function DIversionDropDownValueChanged(app, event)
            value = app.DIversionDropDown.Value;
            
        end

        % Button pushed function: bLoadKilosortOutputButton
        function bLoadKilosortOutputButtonPushed(app, event)
            % check if there is 
            file_name = 'KilosortOutput.mat';
            if ~isempty(dir(file_name))
                load(file_name);
                app.bLoadKilosortOutputButton.UserData = KilosortOutput;
                kotab = KilosortOutput.SpikeTable;
                % Show spike data in spike table
                kotab=removevars(kotab, {'Amplitude','ContamPct', 'KSLabel' , 'depth', 'fr', 'sh', 'spike_times_r', 'waveforms', 'waveforms_mean', 'spike_times', 'spike_times_r'});
                kotab.Violation = zeros(length(kotab.cluster_id), 1);
                kotab.Index = (1:length(kotab.cluster_id))';
                % compute violation ratio
                for i =1:length(kotab.cluster_id)
                    spike_times = KilosortOutput.SpikeTable.spike_times_r{i};                   
                    isi = diff(spike_times);
                    n_violation = sum(isi<=3);
                    kotab.Violation(i) = 100*n_violation/length(spike_times);
                    disp(['Computing unit #', num2str(i)])
                end
                
                app.UITable.Data = kotab;
                app.UITable.ColumnName = {'ClusterID', 'Amplitude', 'Channel', 'Group', 'n_spikes', 'ISI<3ms(%)', 'Index'};

            end;

        end

        % Button pushed function: aBuildspiketableButton
        function aBuildspiketableButtonPushed(app, event)
            % this adopts 'BuildSpikeTable'
            % 1. determine which folder the sorting data are stored
            dir_name = uigetdir(pwd, 'select the spike sorting folder ');
            addpath(dir_name)
            spikeTable = phy2mat(dir_name);
            load ops.mat

            %% update spike table
            % channel index start from 1
            spikeTable.ch = spikeTable.ch+1;
            % Only save labeled clusters and clear noise
            k = 1;
            while k <= height(spikeTable)
                if isempty(spikeTable(k,:).group{1}) || strcmp(spikeTable(k,:).group{1},'noise')
                    spikeTable(k,:) = [];
                else
                    k=k+1;
                end
            end
            spikeTable = sortrows(spikeTable,{'ch','group'});

            % read and add spike times
            spike_times = readNPY('spike_times.npy');
            spike_clusters = readNPY('spike_clusters.npy');

            tbl_spike_times = cell(height(spikeTable),1);
            for k = 1:height(spikeTable)
                tbl_spike_times{k} = spike_times(spike_clusters==spikeTable(k,:).cluster_id);
            end
            spikeTable.spike_times = tbl_spike_times;


            %% extract waveform from temp_wh.dat
            waveforms_tbl = cell(height(spikeTable),1);
            waveforms_mean_tbl = cell(height(spikeTable),1);
            ch_tbl = cell(height(spikeTable),1);
            for k = 1:height(spikeTable)
                tic
                opsfproc = ops.fproc;
                opsfproc = strsplit(opsfproc,{'/','\'});
                opsfproc = fullfile(opsfproc{:});
                [filepath,name,ext] = fileparts(opsfproc);
                % added by JY
                filepath = dir_name; % in case you move data around. the old filepathy won't apply.

                gwfparams.dataDir = filepath;    % KiloSort/Phy output folder
                gwfparams.fileName = [name, ext];         % .dat file containing the raw
                gwfparams.dataType = 'int16';            % Data type of .dat file (this should be BP filtered)
                gwfparams.nCh = ops.Nchan;                      % Number of channels that were streamed to disk in .dat file
                gwfparams.wfWin = [-31 32];              % Number of samples before and after spiketime to include in waveform
                gwfparams.nWf = length(spikeTable(k,:).spike_times{1});                    % Number of waveforms per unit to pull out
                gwfparams.spikeTimes =    spikeTable(k,:).spike_times{1}; % Vector of cluster spike times (in samples) same length as .spikeClusters
                gwfparams.spikeClusters = ones(length(spikeTable(k,:).spike_times{1}),1); % Vector of cluster IDs (Phy nomenclature)   same length as .spikeTimes

                wf = getWaveforms(gwfparams);

                amp_ch = max(squeeze(wf.waveFormsMean),[],2)-min(squeeze(wf.waveFormsMean),[],2);
                [~, ch_amp_largest] = max(amp_ch);
                ch_tbl{k} = ch_amp_largest;

                waveforms_tbl{k} = squeeze(wf.waveForms(:,:,ch_amp_largest,:));
                waveforms_mean_tbl{k} = squeeze(wf.waveFormsMean);

                toc
            end

            spikeTable.waveforms = waveforms_tbl;
            spikeTable.waveforms_mean = waveforms_mean_tbl;
            spikeTable.ch = ch_tbl;
            spikeTable = sortrows(spikeTable,{'ch','group'});

            % save spikeTable
            writetable(spikeTable, 'KilosortSpikeTable.csv')

            %% Get real spike time
            % Read NS6 file.
            ns6_files = dir('*.ns6');
            if ~isempty(ns6_files)
                for i =1:length(ns6_files)
                    if i == 1
                        openNSx(ns6_files(i).name, 'read', 'report')
                        NS6all= NS6;
                    else
                        openNSx(ns6_files(i).name, 'read', 'report')
                        NS6all(i)= NS6;
                    end
                end
            else
                error('No .ns6 files!')
            end
            % Define channels
            EphysChs = 1:ops.Nchan;
            Fs = ops.fs; % this is the sampling rate
            % Extract ephys data
            index =[];
            for k = 1:length(NS6all)
                NS6 = NS6all(k);
                if k>1
                    dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                    dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                else
                    dBlockOnset=0; % define the starting time of the first session as 0
                end
                if iscell(NS6.Data)
                    for j =1:length(NS6.Data)
                        % this is time in ms
                        index_k = (0:length(double(NS6.Data{j}(1, :)))-1)*1000/Fs+NS6.MetaTags.Timestamp(j)*1000/Fs+dBlockOnset;
                        % skip first 102 frame (zeropad by blackrock?)
                        index_k(1:102) = [];
                        if k==1
                            index = [index index_k];
                        else
                            % this is to take care of an old issue
                            index_k = index_k(index_k> index(end)+0.03);
                            index = [index index_k];
                        end
                    end
                else
                    index_k = (0:length(double(NS6.Data(1, :)))-1)*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset;
                    % skip first 102 frame (zeropad by blackrock?)
                    index_k(1:102) = [];
                    index = [index index_k];
                end
            end
            savefile = 'index.mat'; % name of raw data files
            save(savefile, 'index');
            % first 102 frame in Kilosort is skipped (Blackrock zeropad the data)
            % kilosort add 0 at the end of data

            spike_times_r = cell(height(spikeTable),1);
            for k = 1:height(spikeTable)
                spike_times_r{k} = index(spikeTable(k,:).spike_times{1});
            end
            spikeTable.spike_times_r = spike_times_r;
            disp(spikeTable)

            %%
            chanMap = load('chanMap.mat');
            KilosortOutput = Spikes.Array.KilosortOutputClass(spikeTable, chanMap, ops);
            KilosortOutput.save();
            app.waitingLabel.Text = 'Done';
             bLoadKilosortOutputButtonPushed(app, event);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if size(indices, 1) ==1
                % Plot this unit:
                cla(app.UIAxesISI);
                cla(app.UIAxesAuto)
                kotab = app.bLoadKilosortOutputButton.UserData.SpikeTable;
                % mean waveforms across channels
                wave_form = kotab.waveforms_mean{indices(1)}/4;
                st = round(kotab.spike_times_r{indices(1)}); % in ms
                n_chs = size(wave_form, 1); % number of channels
                n_sample = size(wave_form, 2); % sample size per spike
                colors = [25, 167, 206]/255;
                % each row will be 8 channels
                n_cols = 8;
                n_rows = n_chs/n_cols;
                axes(app.UIAxesWave); cla(app.UIAxesWave)
                set(app.UIAxesWave, 'nextplot', 'add')
                app.UIAxesISI.XLim = [0 25];
                app.UIAxesISI.Position = [254 10 220 125];
                app.UIAxesISI.XLabel.String = 'ISI (ms)';
                max_x = 0;
                if n_rows<1
                    n_rows=1;
                end
                v_sep = 100;
                for i =1:n_rows
                    for j=1:n_cols
                        k = j+(i-1)*n_cols;
                        wave_k = wave_form(k, :)+v_sep*(i-1);
                        t_wave = [1:n_sample]+n_sample*(j-1)+4;
                        plot(app.UIAxesWave, t_wave, wave_k, 'linewidth', 1, 'color', colors);
                        max_x = max([max_x, max(t_wave)]);
                    end
                end
                axis(app.UIAxesWave,'tight');
                set(app.UIAxesWave, 'xlim', [0 max_x], 'ylim', [-400  v_sep*(n_rows-1)+200])
                % compute autocorrelation
                max_lags = 50;
                kutime = zeros(1, max(st));
                kutime(st)=1;
                [r, lags] =  xcorr(kutime, max_lags);
                r(lags==0) =0;
                hbar = bar(app.UIAxesAuto, lags, r, 0.9);
                set(hbar, 'facecolor', 'k')
%                 xlabel('lag (ms)')
                app.UIAxesAuto.Position = [254 100 220 125];
                app.UIAxesAuto.XLim = [-25 25];
                app.UIAxesAuto.XLabel.String = 'Lag (ms)';
                % compute isi
                isi = diff(st);
                bin_edges = [0:1:50];
                bin_centers = bin_edges(1:end-1)+0.5;
                [n_bin_edges] = histcounts(isi, bin_edges);
                hbar2 = bar(app.UIAxesISI, bin_centers, n_bin_edges);
                set(hbar2, 'facecolor', 'k')
                app.UIAxesISI.XLim = [0 25];
                app.UIAxesISI.Position = [254 10 220 125];
                app.UIAxesISI.XLabel.String = 'ISI (ms)';
%                 xlabel('ISI (ms)')
            else
                unit = [];
                unit(1) = indices(1, 1);
                unit(2) = indices(2, 1);
                colors{1} = [25, 167, 206]/255;
                colors{2} = [252, 79, 0]/255;
                kotab = app.bLoadKilosortOutputButton.UserData.SpikeTable;
                axes(app.UIAxesWave); cla(app.UIAxesWave)
                set(app.UIAxesWave, 'nextplot', 'add')
                max_x = 0;
                axes(app.UIAxesISI); cla(app.UIAxesISI)
                set(app.UIAxesISI, 'nextplot', 'add','xlim', [0 25])
                axes(app.UIAxesAuto); cla(app.UIAxesAuto)
                set(app.UIAxesAuto, 'nextplot', 'add', 'xlim', [-25 25])
                app.UIAxesAuto.Position = [254 100 220 125];
                hbar = zeros(1, length(unit));
                hbar2 =zeros(1, length(unit));
                % Plot this unit:
                for m = 1:length(unit)
                    % mean waveforms across channels
                    wave_form = kotab.waveforms_mean{unit(m)}/4;
                    st = round(kotab.spike_times_r{unit(m)}); % in ms
                    n_chs = size(wave_form, 1); % number of channels
                    n_sample = size(wave_form, 2); % sample size per spike
                    n_cols = 8;
                    n_rows = n_chs/n_cols;
                    if n_rows<1
                        n_rows=1;
                    end
                    v_sep = 100;
                    for i =1:n_rows
                        for j=1:n_cols
                            k = j+(i-1)*n_cols;
                            wave_k = wave_form(k, :)+v_sep*(i-1);
                            t_wave = [1:n_sample]+n_sample*(j-1)+4;
                            plot(app.UIAxesWave, t_wave, wave_k, 'linewidth', 1, 'color', colors{m});
                            max_x = max([max_x, max(t_wave)]);
                        end
                    end
                    axis(app.UIAxesWave,'tight');
                    % compute autocorrelation
                    max_lags = 50;
                    kutime = zeros(1, max(st));
                    kutime(st)=1;
                    [r, lags] =  xcorr(kutime, max_lags);
                    r(lags==0) =0;
                    hbar(m) = bar(app.UIAxesAuto, lags, r, 0.9);
                    set(hbar(m), 'facecolor', colors{m}, 'FaceAlpha', 0.25)
                    app.UIAxesAuto.XLabel.String = 'Lag (ms)';
                    % compute isi
                    isi = diff(st);
                    bin_edges = [0:1:50];
                    bin_centers = bin_edges(1:end-1)+0.5;
                    [n_bin_edges] = histcounts(isi, bin_edges);
                    hbar2(m) = bar(app.UIAxesISI, bin_centers, n_bin_edges);
                    set(hbar2(m), 'facecolor', colors{m}, 'FaceAlpha', 0.25)
                end
                set(app.UIAxesWave, 'xlim', [0 max_x], 'ylim', [-400  v_sep*(n_rows-1)+200])

                 if ~isempty(app.UIAxesWaveSimilarity.UserData) 
                     delete(findobj(app.UIAxesWaveSimilarity, 'type', 'Rectangle'))
                     % circle up 
                     disp([unit(1), unit(2)])
                     hr = rectangle(app.UIAxesWaveSimilarity, 'Position', [unit(1)-0.5, unit(2)-0.5, 1 1]);
                     hr.EdgeColor = 'c';
                     hr.LineWidth = 1; 
                 end
            end

        end

        % Button pushed function: cCrossCorretionButton
        function cCrossCorretionButtonPushed(app, event)
            % compute cross correlation of all units
            kotab = app.bLoadKilosortOutputButton.UserData.SpikeTable;

            % mean waveforms across channels
            wave_form = kotab.waveforms_mean;
            st = kotab.spike_times_r; % in ms
            n_units = length(st);

            correlation_wave = zeros(n_units, n_units);
            correlation_isi      = zeros(n_units, n_units);

            bin_edges = [0:1:50];

            for i=1:n_units
                for j =1:n_units
                    wave_i = reshape(wave_form{i}, 1, []);
                    wave_j = reshape(wave_form{j}, 1, []);
                    if i~=j
                        cc=corrcoef(wave_i, wave_j);
                        correlation_wave(i, j) =  atanh(cc(1, 2));
                    else
                        correlation_wave(i, j) =  0;
                    end
                    % compute isi
                    isi_i = diff(st{i});
                    isi_j = diff(st{j});
                    [n_bin_i] = histcounts(isi_i, bin_edges);
                    [n_bin_j] = histcounts(isi_j, bin_edges);
                    if i~=j
                        cc=corrcoef(n_bin_i, n_bin_j);
                        correlation_isi(i, j) =  atanh(cc(1, 2));
                    else
                        correlation_isi(i, j) =  0;
                    end
                end
            end

            mycolormap = customcolormap([0 0.5 1], [1 1 1; 1 0 0; 0 0 0]);
            colormap(app.UIAxesWaveSimilarity, 'Hot');
            colormap(app.UIAxesISISimilarity, "Hot");
            colorbar(app.UIAxesWaveSimilarity);

            axes(app.UIAxesWaveSimilarity);
            imagesc(app.UIAxesWaveSimilarity, correlation_wave, [0 2.5])

            axes(app.UIAxesISISimilarity);
            imagesc(app.UIAxesISISimilarity, correlation_isi, [0 2])

            app.UIAxesWaveSimilarity.XLim = [0.5 n_units+0.5];
            app.UIAxesWaveSimilarity.YLim = [0.5 n_units+0.5];

            app.UIAxesISISimilarity.XLim = [0.5 n_units+0.5];
            app.UIAxesISISimilarity.YLim = [0.5 n_units+0.5];


            app.UIAxesWaveSimilarity.Position = [550 20 250 250];
            app.UIAxesISISimilarity.Position = [840 20 250 250];

            app.UIAxesWaveSimilarity.UserData = correlation_wave;

        end

        % Callback function
        function UIAxesWaveSimilarityButtonDown(app, event)
            
        end

        % Button pushed function: ShowrankingButton
        function ShowrankingButtonPushed(app, event)
            data =  app.UIAxesWaveSimilarity.UserData;
            n_units = size(data, 1);

            corr_mat = [];

            for i =1:n_units
                for j =1:n_units
                    if i>j
                        corr_mat = [corr_mat; i, j, data(i, j)];
                    end;
                end;
            end;

            [~, sort_index] = sort(corr_mat(:, 3), 'descend');
            clc
            corr_mat = corr_mat(sort_index, :);
            this_table = table(corr_mat(:, 1), corr_mat(:, 2), corr_mat(:, 3), 'VariableNames',{'unit 1', 'unit 2', 'waveform similarity'});
                        app.ShowrankingButton.UserData = this_table;
            this_table(1:10, :)
        end

        % Button pushed function: OpenAnalysisappButton
        function OpenAnalysisappButtonPushed(app, event)
            if app.KornblumCheckBox.Value == 1
                  Spikes.Timing.Analysis;
            else
                  Spikes.SRT.Analysis;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: BpodProtocolDropDown
        function BpodProtocolDropDownValueChanged(app, event)
            value = app.BpodProtocolDropDown.Value;
            
        end

        % Button pushed function: OpenCheckerappButton
        function OpenCheckerappButtonPushed(app, event)
            App.SpikeChecker;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.SpikeTablefromKilosortPanel = uipanel(app.Panel);
            app.SpikeTablefromKilosortPanel.Title = '10|SpikeTable from Kilosort';
            app.GridLayout3 = uigridlayout(app.SpikeTablefromKilosortPanel);
            app.UIAxesWave = uiaxes(app.GridLayout3);
            title(app.UIAxesWave, {''; ''})
            xlabel(app.UIAxesWave, 'X')
            ylabel(app.UIAxesWave, 'Y')
            zlabel(app.UIAxesWave, 'Z')
            app.UIAxesAuto = uiaxes(app.GridLayout3);
            title(app.UIAxesAuto, {''; ''})
            xlabel(app.UIAxesAuto, {''; ''})
            ylabel(app.UIAxesAuto, 'Count')
            zlabel(app.UIAxesAuto, 'Z')
            app.UIAxesISI = uiaxes(app.GridLayout3);
            title(app.UIAxesISI, {''; ''})
            xlabel(app.UIAxesISI, {''; ''})
            ylabel(app.UIAxesISI, 'Count')
            zlabel(app.UIAxesISI, 'Z')
            app.UIAxesISI.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.aBuildspiketableButton = uibutton(app.GridLayout3, 'push');
            app.aBuildspiketableButton.ButtonPushedFcn = createCallbackFcn(app, @aBuildspiketableButtonPushed, true);
            app.aBuildspiketableButton.Text = 'a. Build spike table';
            app.waitingLabel = uilabel(app.GridLayout3);
            app.waitingLabel.Text = {'waiting'; ''};
            app.bLoadKilosortOutputButton = uibutton(app.GridLayout3, 'push');
            app.bLoadKilosortOutputButton.ButtonPushedFcn = createCallbackFcn(app, @bLoadKilosortOutputButtonPushed, true);
            app.bLoadKilosortOutputButton.Text = 'b. Load KilosortOutput';
            app.cCrossCorretionButton = uibutton(app.GridLayout3, 'push');
            app.cCrossCorretionButton.ButtonPushedFcn = createCallbackFcn(app, @cCrossCorretionButtonPushed, true);
            app.cCrossCorretionButton.Text = 'c. Cross-Corretion';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.BehaviorandmetadataPanel = uipanel(app.Panel);
            app.BehaviorandmetadataPanel.Title = 'Behavior and meta data';
            app.GridLayout2 = uigridlayout(app.BehaviorandmetadataPanel);
            app.KornblumCheckBox = uicheckbox(app.GridLayout2);
            app.KornblumCheckBox.Text = '4|Kornblum';
            app.ExperimenterLabel = uilabel(app.GridLayout2);
            app.ExperimenterLabel.Text = '2|Experimenter';
            app.ExperimenterEditField = uieditfield(app.GridLayout2, 'text');
            app.ANMLabel = uilabel(app.GridLayout2);
            app.ANMLabel.Text = '1|ANM ';
            app.ANMnameEditField = uieditfield(app.GridLayout2, 'text');
            app.LoadMEDfileButton = uibutton(app.GridLayout2, 'state');
            app.LoadMEDfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadMEDfileButtonValueChanged, true);
            app.LoadMEDfileButton.Text = {'8|Load MED file'; ''};
            app.LoadBpodfileButton = uibutton(app.GridLayout2, 'state');
            app.LoadBpodfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadBpodfileButtonValueChanged, true);
            app.LoadBpodfileButton.Text = {'9|Load Bpod file'; ''};
            app.MEDfileLabel = uilabel(app.GridLayout2);
            app.MEDfileLabel.Text = {'MED file'; ''};
            app.BpodfileLabel = uilabel(app.GridLayout2);
            app.BpodfileLabel.Text = {'Bpod file'; ''};
            app.ProbeCheckBox = uicheckbox(app.GridLayout2);
            app.ProbeCheckBox.Text = '4''|Probe';
            app.SegmentsLabel = uilabel(app.GridLayout2);
            app.SegmentsLabel.Text = '3|Segments';
            app.SegmentsDropDown = uidropdown(app.GridLayout2);
            app.SegmentsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SegmentsDropDownOpening, true);
            app.BpodfilenamesDropDownLabel = uilabel(app.GridLayout2);
            app.BpodfilenamesDropDownLabel.Text = 'Bpod file names';
            app.BpodfilenamesDropDown = uidropdown(app.GridLayout2);
            app.ProtocolLabel = uilabel(app.GridLayout2);
            app.ProtocolLabel.Text = '5|Protocol';
            app.ProtocolDropDown = uidropdown(app.GridLayout2);
            app.ProtocolDropDown.Items = {'TwoFPs', '3FPs', 'Wait1', 'Wait2', 'Kornblum'};
            app.ProtocolDropDown.Value = 'TwoFPs';
            app.DIversionLabel = uilabel(app.GridLayout2);
            app.DIversionLabel.Text = '6|DI version';
            app.DIversionDropDown = uidropdown(app.GridLayout2);
            app.DIversionDropDown.Items = {'Version4', 'Version5'};
            app.DIversionDropDown.ValueChangedFcn = createCallbackFcn(app, @DIversionDropDownValueChanged, true);
            app.DIversionDropDown.Value = 'Version5';
            app.BpodProtocolLabel = uilabel(app.GridLayout2);
            app.BpodProtocolLabel.Text = '7|Bpod Protocol';
            app.BpodProtocolDropDown = uidropdown(app.GridLayout2);
            app.BpodProtocolDropDown.Items = {'OptoRecording', 'OptoRecordingMix', 'OptoRecordingSelfTimed', 'MedOptoRecMixKB', 'MedLick'};
            app.BpodProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @BpodProtocolDropDownValueChanged, true);
            app.BpodProtocolDropDown.Value = 'OptoRecording';
            app.MEDfilenamesLabel = uilabel(app.GridLayout2);
            app.MEDfilenamesLabel.Text = 'MED file names';
            app.MEDfilenamesDropDown = uidropdown(app.GridLayout2);
            app.Panel_2 = uipanel(app.Panel);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.AddforceCheckBox = uicheckbox(app.GridLayout4);
            app.AddforceCheckBox.Text = '11|Add force';
            app.AddlaserCheckBox = uicheckbox(app.GridLayout4);
            app.AddlaserCheckBox.Text = '12|Add laser';
            app.BuildRversion202023Label = uilabel(app.GridLayout4);
            app.BuildRversion202023Label.Text = 'BuildR version 2.0 (2023)';
            app.BlankoutLabel = uilabel(app.GridLayout4);
            app.BlankoutLabel.Text = '13|Blank-out';
            app.BlankoutEditField = uieditfield(app.GridLayout4, 'text');
            app.BuildButton = uibutton(app.GridLayout4, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = '15|Build';
            app.SaveGUIfigButton = uibutton(app.GridLayout4, 'push');
            app.SaveGUIfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGUIfigButtonPushed, true);
            app.SaveGUIfigButton.Text = {'Save GUI fig'; ''};
            app.Panel_3 = uipanel(app.Panel);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.UIAxesISISimilarity = uiaxes(app.GridLayout5);
            title(app.UIAxesISISimilarity, 'ISI')
            xlabel(app.UIAxesISISimilarity, 'X')
            ylabel(app.UIAxesISISimilarity, 'Y')
            zlabel(app.UIAxesISISimilarity, 'Z')
            app.UIAxesWaveSimilarity = uiaxes(app.GridLayout5);
            title(app.UIAxesWaveSimilarity, 'waveform')
            xlabel(app.UIAxesWaveSimilarity, 'X')
            ylabel(app.UIAxesWaveSimilarity, 'Y')
            zlabel(app.UIAxesWaveSimilarity, 'Z')
            app.UIAxesBehav = uiaxes(app.GridLayout5);
            title(app.UIAxesBehav, {''; ''})
            xlabel(app.UIAxesBehav, 'seconds')
            zlabel(app.UIAxesBehav, 'Z')
            app.UIAxesBehav.Tag = 'raster';
            app.OpenAnalysisappButton = uibutton(app.GridLayout5, 'push');
            app.OpenAnalysisappButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAnalysisappButtonPushed, true);
            app.OpenAnalysisappButton.Text = 'Open Analysis app';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ShowrankingButton = uibutton(app.GridLayout6, 'push');
            app.ShowrankingButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrankingButtonPushed, true);
            app.ShowrankingButton.Text = 'Show ranking';
            app.OpenCheckerappButton = uibutton(app.GridLayout6, 'push');
            app.OpenCheckerappButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCheckerappButtonPushed, true);
            app.OpenCheckerappButton.Text = 'Open Checker app';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SegmentsDropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BuildRKilosortMS2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end