classdef EphysVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ReadframetimesandROIPanel    matlab.ui.container.Panel
        LoadButton                   matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        ReadframeinfoandROIsButton   matlab.ui.control.Button
        tMaxEditField                matlab.ui.control.NumericEditField
        tMinEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel           matlab.ui.control.Label
        removeButton_2               matlab.ui.control.Button
        removeButton                 matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        addButton_2                  matlab.ui.control.Button
        addButton                    matlab.ui.control.Button
        SideviewListBox              matlab.ui.control.ListBox
        SideviewListBoxLabel         matlab.ui.control.Label
        TopviewListBox               matlab.ui.control.ListBox
        TopviewListBoxLabel          matlab.ui.control.Label
        AllfilesListBox              matlab.ui.control.ListBox
        AllfilesListBoxLabel         matlab.ui.control.Label
        CheckvideofilesButton        matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        RemakeDropDown               matlab.ui.control.DropDown
        RemakeDropDownLabel          matlab.ui.control.Label
        PosttimemsEditField          matlab.ui.control.NumericEditField
        PosttimemsEditFieldLabel     matlab.ui.control.Label
        PretimemsEditField           matlab.ui.control.NumericEditField
        PretimemsEditFieldLabel      matlab.ui.control.Label
        EventnameEditField           matlab.ui.control.EditField
        EventnameEditFieldLabel      matlab.ui.control.Label
        EventtimeextractioncodeEditField  matlab.ui.control.EditField
        EventtimeextractioncodeEditFieldLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        MakingVideosButton           matlab.ui.control.Button
        rsfieldListBox               matlab.ui.control.ListBox
        rsfieldListBoxLabel          matlab.ui.control.Label
        WaveInfoLamp                 matlab.ui.control.Lamp
        WaveInfoLampLabel            matlab.ui.control.Label
        WaveListBox                  matlab.ui.control.ListBox
        FrameInfoLamp                matlab.ui.control.Lamp
        FrameInfoLampLabel           matlab.ui.control.Label
        AlignwavedataButton          matlab.ui.control.Button
        rfileLabel                   matlab.ui.control.Label
        LoadrButton                  matlab.ui.control.Button
        AlignLEDOnwithtriggertimeButton  matlab.ui.control.Button
        TwoviewephysvideoanalysisappLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        RemoveButton                 matlab.ui.control.Button
        UITable2                     matlab.ui.control.Table
        ExtractioncodeTextArea       matlab.ui.control.TextArea
        ExtractioncodeTextAreaLabel  matlab.ui.control.Label
        BehaviortableLabel           matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckvideofilesButton
        function CheckvideofilesButtonPushed(app, event)
            m=dir('*.seq');
            all_names = arrayfun(@(x)x.name, m, 'UniformOutput',false);
            app.AllfilesListBox.Items = all_names;

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            this_file = app.AllfilesListBox.Value;
            PlaySeqFile(this_file)

        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            % check what file is selected
            this_file = app.AllfilesListBox.Value;
            current_files = app.TopviewListBox.Items;
            if ~isempty(current_files)
                current_files = [current_files this_file];
            else
                current_files{1} = this_file;
            end
            app.TopviewListBox.Items = current_files;
            
        end

        % Button pushed function: addButton_2
        function addButton_2Pushed(app, event)
            % check what file is selected
            this_file = app.AllfilesListBox.Value;
            current_files = app.SideviewListBox.Items;
            if ~isempty(current_files)
                current_files = [current_files this_file];
            else
                current_files{1} = this_file;
            end
            app.SideviewListBox.Items = current_files;
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            app.TopviewListBox.Items = app.TopviewListBox.Items(~strcmp(app.TopviewListBox.Items, app.TopviewListBox.Value));
        end

        % Button pushed function: removeButton_2
        function removeButton_2Pushed(app, event)
            app.SideviewListBox.Items = app.SideviewListBox.Items(~strcmp(app.SideviewListBox.Items, app.SideviewListBox.Value));
        end

        % Button pushed function: ReadframeinfoandROIsButton
        function ReadframeinfoandROIsButtonPushed(app, event)
            
            t_check = [app.tMinEditField.Value app.tMaxEditField.Value];
            sideviews = app.SideviewListBox.Items;
            topviews = app.TopviewListBox.Items;
            FrameInfo.SideView  = GetSeqFrameInfo(sideviews, t_check*100, 'SideView', 1);
            FrameInfo.TopView   = GetSeqFrameInfo(topviews, t_check*100, 'TopView', 0);

            app.ReadframeinfoandROIsButton.UserData = FrameInfo;
            app.Lamp.Color = [0 1 0];

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Look for FrameInfoSideView and FrameInfoTopView

            file1 = dir('FrameInfoSideView.mat');
            if ~isempty(file1)
                frameinfodata = load('FrameInfoSideView.mat');
                FrameInfo.SideView = frameinfodata.FrameInfo;
            end

            file2 = dir('FrameInfoTopView.mat');
            if ~isempty(file2)
                frameinfodata = load('FrameInfoTopView.mat');
                FrameInfo.TopView = frameinfodata.FrameInfo;
            end

            app.ReadframeinfoandROIsButton.UserData = FrameInfo;
            app.Lamp.Color = [0 1 0];

            clc
            disp('~~~~~~~~ Loaded ~~~~~~~~')

        end

        % Button pushed function: LoadrButton
        function LoadrButtonPushed(app, event)
            % load r
            filename = dir('RTarray_*.mat');
            if isempty(filename) || length(filename)>1
                % prompt
                [filename,  ~] = uigetfile('RT*.mat', 'Choose r array');
            else
                filename = filename.name;
            end
            data = load(filename);
            app.rfileLabel.Text = filename;
            app.rfileLabel.UserData = data.r;

            if isfield(data.r, 'FrameInfo')
                app.FrameInfoLamp.Color = 'g';
            end

            if isfield(data.r, 'Wave')
                app.WaveInfoLamp.Color = 'g';
            end

            app.rsfieldListBox.Items = fieldnames(data.r);

            % make behavior table
            if ~isfield(data.r, 'BehaviorTable')
                MakeBehaviortableButtonPushed(app, event)
            else
                BehTable = data.r.BehaviorTable;
                app.UITable.Data = BehTable;
                app.UITable.ColumnName = BehTable.Properties.VariableNames;
            end

        end

        % Button pushed function: AlignLEDOnwithtriggertimeButton
        function AlignLEDOnwithtriggertimeButtonPushed(app, event)
            if isempty(app.ReadframeinfoandROIsButton.UserData)
                 LoadButtonPushed(app, event)
            end
            FrameInfo = app.ReadframeinfoandROIsButton.UserData;
            tLEDon          =       FindLEDon(FrameInfo.SideView.tframe, FrameInfo.SideView.ROI);
            r = app.rfileLabel.UserData;
            tTriggerR = r.Behavior.EventTimings(r.Behavior.EventMarkers == find(strcmp(r.Behavior.Labels, 'Trigger'))); % in ms

            % Align r and LED on time
            IndOut = findseqmatchrev(tTriggerR, tLEDon, 0, 1);

            % Now tLEDon is mapped to MED time domain
            tLEDonVideo         =       tLEDon(~isnan(IndOut));                   % This is LED on time in video time domain, in millisecond
            tLEDonR                 =       tTriggerR(IndOut(~isnan(IndOut)));         % This is the corresponding time in r , in millisecond

            FrameInfo.LED.tLEDon    =       tLEDonVideo;
            FrameInfo.LED.tLEDonR   =       tLEDonR;

            % Map video frames to MED time domain
            tFramesOut                                         =       MapVidFrameTimeToAnotherDomain(tLEDonVideo,tLEDonR, FrameInfo.SideView.tframe);
            FrameInfo.SideView.tFrameR           =          tFramesOut;

            % Check
            TimeDiff = tLEDonVideo(:) - tLEDonR(:);
            disp('This demonstrate the drift (it is mitigated by re-alignment to each LED events)')
            figure;
            plot((tLEDonR - tLEDonR(1))/1000, TimeDiff - TimeDiff(1), 'ko');
            xlabel('Time since the first event (s)'); ylabel('Time difference')

            % Double check if LED time is well aligned

            % Empirically, some events are still not well aligned. In particually, in a small subset of trials, 
            % LED starts to light up at trigger time we need to revise these trials.
            % some tLEDon need to be revised.

            imhappy = 0;
            while ~imhappy
                %   function tLEDnew = CorrectLEDtimeMED(TriggerTimeMED, FrameInfo)
                tLEDon          =       CorrectLEDtimeR(tLEDonR', FrameInfo.SideView); % update tLEDon
                % find index of tLEDon in MED domain:
                IndOut = findseqmatchrev(tTriggerR, tLEDon, 0, 1);
                tLEDonVideo         =       tLEDon(~isnan(IndOut));                   % This is LED on time in video time domain, in millisecond
                tLEDonR             =       tTriggerR(IndOut(~isnan(IndOut)));         % This is the corresponding time in r , in millisecond

                FrameInfo.LED.tLEDon = tLEDonVideo;
                FrameInfo.LED.tLEDonR = tLEDonR;

                % Map video frames to MED time domain
                tFramesOut = MapVidFrameTimeToAnotherDomain(tLEDonVideo, tLEDonR, FrameInfo.SideView.tframe);
                FrameInfo.SideView.tFrameR = tFramesOut;

                clc
                reply = input('Are you happy? Y/N [Y]', 's');
                if isempty(reply)
                    reply = 'Y';
                end;
                if strcmp(reply, 'Y')  ||  strcmp(reply, 'y')
                    imhappy = 1;
                else
                    imhappy =0;
                end
            end

            % Now map all topview frame time to R (that is, find the time
            % in R that corresponds to each frame from the topview. Note
            % that Topview and side view cams are from the same computer. 
            %  the unit shown in FrameInfo.TopView.tFrameOrg are in
            %  microseconds

            tFrameOrg_Side = FrameInfo.SideView.tframeOrg/1000; % turned into ms
            tFrameOrg_Top = FrameInfo.SideView.tframeOrg/1000; % turned into ms
            tFrameR_Side = FrameInfo.SideView.tFrameR; %  we have mapped the frame time in side view cam to time in R
            tFrameR_Top = interp1(tFrameOrg_Side, tFrameR_Side, tFrameOrg_Top);

            FrameInfo.TopView.tFrameR = tFrameR_Top;
            % Update
            app.ReadframeinfoandROIsButton.UserData = FrameInfo;
            % Save
            save('FrameInfo.mat', 'FrameInfo')

            % Update r
            r.FrameInfo = FrameInfo;
            save(app.rfileLabel.Text, 'r')
            LoadrButtonPushed(app, event)
        end

        % Button pushed function: AlignwavedataButton
        function AlignwavedataButtonPushed(app, event)
            % here we look at the wave data and map its time to r
            r = app.rfileLabel.UserData;
            tTriggerR = r.Behavior.EventTimings(r.Behavior.EventMarkers == find(strcmp(r.Behavior.Labels, 'Trigger'))); % in ms
            h5file = dir('*.h5');
            h5fileNames = arrayfun(@(x)x.name, h5file, 'UniformOutput',false);
            app.WaveListBox.Items = h5fileNames;

            WSOut = [];
            % These signals need to be downsampled to 1000/sec

            for i =1:length(h5fileNames)
                iWSOut                      =       ReadH5Opto(h5fileNames{i});
                iWSOut                      =       MapWave2R(iWSOut, tTriggerR);

                % Downsample to 1000 Hz
                iWSOut.Time         = downsample(iWSOut.Time, 20); % downsample from 20 kHz to 1 kHz
                iWSOut.Signals      = downsample(iWSOut.Signals, 20); 
                iWSOut.TimeInR    = downsample(iWSOut.TimeInR, 20);

                if isempty(WSOut)
                    WSOut                   =       iWSOut;
                else
                    WSOut.Time              =       [WSOut.Time iWSOut.Time];
                    WSOut.Signals           =       [WSOut.Signals; iWSOut.Signals];
                    WSOut.TriggerTimeWS     =       [WSOut.TriggerTimeWS iWSOut.TriggerTimeWS];
                    WSOut.TriggerTimeWS2R   =       [WSOut.TriggerTimeWS2R; iWSOut.TriggerTimeWS2R];
                    WSOut.TimeInR           =       [WSOut.TimeInR iWSOut.TimeInR];
                end;
            end
            save('WSOut.mat', 'WSOut', '-v7.3' )
            % also save to r
            r.Wave = WSOut;
            save(app.rfileLabel.Text, 'r');
            LoadrButtonPushed(app, event);

        end

        % Callback function
        function MakeBehaviortableButtonPushed(app, event)
            r = app.rfileLabel.UserData;
            BehTable = MakeBehaviorTableFromR(r);
            r = app.rfileLabel.UserData;
            r.BehaviorTable = BehTable;
            save(app.rfileLabel.Text, 'r');
            LoadrButtonPushed(app, event);
           
        end

        % Button pushed function: MakingVideosButton
        function MakingVideosButtonPushed(app, event)
            exportapp(app.UIFigure,'VideoClippingInfo.png') 

            r = app.rfileLabel.UserData;
            extraction_code = app.EventtimeextractioncodeEditField.Value;
            if ~isempty(extraction_code) && ~strcmp(extraction_code, 'code')
                eval(extraction_code)
            else
                filename = 'extraction_code';
                fid = fopen(filename, 'wt');
                code_lines = app.ExtractioncodeTextArea.Value;
                for i =1:length(code_lines)
                    disp(code_lines{i})
                    eval(code_lines{i})
                    fprintf('%s\n', code_lines{i});
                end
                fclose(fid);
            end
            tPreEvent = app.PretimemsEditField.Value;
            tPostEvent = app.PosttimemsEditField.Value;
            tRange = [tPreEvent tPostEvent];
            event_name = 'Press';
            remake = str2num(app.RemakeDropDown.Value);
            MakeEphysVideoDualView2023(r, bTable, r.Wave, tEvents, 'eventName', event_name, ...
                'timeRange', tRange, 'reMake', remake);

        end

        % Callback function
        function ExampleButtonPushed(app, event)
%             fid = fopen(filename, 'wt');
%             if fid ~= -1
%                 fprintf('This is line 1.  Backslash n makes a new line.\n');
%                 fprintf('My result is %f\n, result);
%                 fprintf('My string = %s\n', myString);
%                 fclose(fid);
%             else
%                 warningMessage = sprintf('Cannot open file %s', filename);
%                 uiwait(warndlg(warningMessage));
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x', 239};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CheckvideofilesButton = uibutton(app.LeftPanel, 'push');
            app.CheckvideofilesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckvideofilesButtonPushed, true);
            app.CheckvideofilesButton.Text = {'1 | Check video files'; ''};
            app.AllfilesListBoxLabel = uilabel(app.LeftPanel);
            app.AllfilesListBoxLabel.Text = 'All files';
            app.AllfilesListBox = uilistbox(app.LeftPanel);
            app.TopviewListBoxLabel = uilabel(app.LeftPanel);
            app.TopviewListBoxLabel.Text = 'Top view';
            app.TopviewListBox = uilistbox(app.LeftPanel);
            app.TopviewListBox.Items = {};
            app.TopviewListBox.Value = {};
            app.SideviewListBoxLabel = uilabel(app.LeftPanel);
            app.SideviewListBoxLabel.Text = 'Side view';
            app.SideviewListBox = uilistbox(app.LeftPanel);
            app.SideviewListBox.Items = {};
            app.SideviewListBox.Value = {};
            app.addButton = uibutton(app.LeftPanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '3 | add';
            app.addButton_2 = uibutton(app.LeftPanel, 'push');
            app.addButton_2.ButtonPushedFcn = createCallbackFcn(app, @addButton_2Pushed, true);
            app.addButton_2.Text = '3 | add';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '2 | Play';
            app.removeButton = uibutton(app.LeftPanel, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.removeButton_2 = uibutton(app.LeftPanel, 'push');
            app.removeButton_2.ButtonPushedFcn = createCallbackFcn(app, @removeButton_2Pushed, true);
            app.removeButton_2.Text = 'remove';
            app.ReadframetimesandROIPanel = uipanel(app.LeftPanel);
            app.ReadframetimesandROIPanel.Title = '4 | Read frame times and ROI';
            app.TimeEditFieldLabel = uilabel(app.ReadframetimesandROIPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.tMinEditField = uieditfield(app.ReadframetimesandROIPanel, 'numeric');
            app.tMaxEditField = uieditfield(app.ReadframetimesandROIPanel, 'numeric');
            app.ReadframeinfoandROIsButton = uibutton(app.ReadframetimesandROIPanel, 'push');
            app.ReadframeinfoandROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadframeinfoandROIsButtonPushed, true);
            app.ReadframeinfoandROIsButton.Text = 'Read frame info and ROIs';
            app.Lamp = uilamp(app.ReadframetimesandROIPanel);
            app.LoadButton = uibutton(app.ReadframetimesandROIPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TwoviewephysvideoanalysisappLabel = uilabel(app.CenterPanel);
            app.TwoviewephysvideoanalysisappLabel.Text = 'Two-view ephys-video analysis app';
            app.AlignLEDOnwithtriggertimeButton = uibutton(app.CenterPanel, 'push');
            app.AlignLEDOnwithtriggertimeButton.ButtonPushedFcn = createCallbackFcn(app, @AlignLEDOnwithtriggertimeButtonPushed, true);
            app.AlignLEDOnwithtriggertimeButton.Tooltip = {'Here we calculate the time LED was turned on and align these time with trigger time recorded in r. This block of procedures also add a new field ''FrameInfo'' to r and save r. We don''t need to re-run step ''6'' once it has been done once. '};
            app.AlignLEDOnwithtriggertimeButton.Text = '6 | Align LED-On with trigger time';
            app.LoadrButton = uibutton(app.CenterPanel, 'push');
            app.LoadrButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrButtonPushed, true);
            app.LoadrButton.Text = '5 | Load ''r''';
            app.rfileLabel = uilabel(app.CenterPanel);
            app.rfileLabel.Text = 'r file';
            app.AlignwavedataButton = uibutton(app.CenterPanel, 'push');
            app.AlignwavedataButton.ButtonPushedFcn = createCallbackFcn(app, @AlignwavedataButtonPushed, true);
            app.AlignwavedataButton.Text = '7 |  Align wave data';
            app.FrameInfoLampLabel = uilabel(app.CenterPanel);
            app.FrameInfoLampLabel.Text = 'FrameInfo';
            app.FrameInfoLamp = uilamp(app.CenterPanel);
            app.WaveListBox = uilistbox(app.CenterPanel);
            app.WaveInfoLampLabel = uilabel(app.CenterPanel);
            app.WaveInfoLampLabel.Text = 'WaveInfo';
            app.WaveInfoLamp = uilamp(app.CenterPanel);
            app.rsfieldListBoxLabel = uilabel(app.CenterPanel);
            app.rsfieldListBoxLabel.Text = 'r''s field';
            app.rsfieldListBox = uilistbox(app.CenterPanel);
            app.MakingVideosButton = uibutton(app.CenterPanel, 'push');
            app.MakingVideosButton.ButtonPushedFcn = createCallbackFcn(app, @MakingVideosButtonPushed, true);
            app.MakingVideosButton.Text = '8 | Making Videos';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '2023.9';
            app.EventtimeextractioncodeEditFieldLabel = uilabel(app.CenterPanel);
            app.EventtimeextractioncodeEditFieldLabel.Text = 'Event time extraction code';
            app.EventtimeextractioncodeEditField = uieditfield(app.CenterPanel, 'text');
            app.EventtimeextractioncodeEditField.Tooltip = {'Input the code''s name'; 'Once we run it, we can get the time of interests'};
            app.EventtimeextractioncodeEditField.Value = 'code';
            app.EventnameEditFieldLabel = uilabel(app.CenterPanel);
            app.EventnameEditFieldLabel.Text = 'Event name';
            app.EventnameEditField = uieditfield(app.CenterPanel, 'text');
            app.EventnameEditField.Value = 'Press';
            app.PretimemsEditFieldLabel = uilabel(app.CenterPanel);
            app.PretimemsEditFieldLabel.Text = 'Pre time (ms)';
            app.PretimemsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PretimemsEditField.Value = -2000;
            app.PosttimemsEditFieldLabel = uilabel(app.CenterPanel);
            app.PosttimemsEditFieldLabel.Text = 'Post time (ms)';
            app.PosttimemsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PosttimemsEditField.Value = 2000;
            app.RemakeDropDownLabel = uilabel(app.CenterPanel);
            app.RemakeDropDownLabel.Text = 'Remake';
            app.RemakeDropDown = uidropdown(app.CenterPanel);
            app.RemakeDropDown.Items = {'0', '1'};
            app.RemakeDropDown.Value = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.BehaviortableLabel = uilabel(app.RightPanel);
            app.BehaviortableLabel.Text = 'Behavior table';
            app.ExtractioncodeTextAreaLabel = uilabel(app.RightPanel);
            app.ExtractioncodeTextAreaLabel.Text = 'Extraction code';
            app.ExtractioncodeTextArea = uitextarea(app.RightPanel);
            app.ExtractioncodeTextArea.Value = {'filename = dir(''RTarray_*.mat'');'; 'load(filename.name);'; 'bTable                  =       r.BehaviorTable;'; 'indPress               =       find(strcmp(bTable.Outcome, ''Correct''));'; 'tEvents                 =       bTable.tPress(indPress);'};
            app.UITable2 = uitable(app.RightPanel);
            app.UITable2.ColumnName = {'Channel'; 'Unit'};
            app.UITable2.RowName = {};
            app.RemoveButton = uibutton(app.RightPanel, 'push');
            app.RemoveButton.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EphysVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end