classdef SeqVideoClippingMED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ReadframetimesandROIPanel       matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ReadframeinfoandROIsButton      matlab.ui.control.Button
        tMaxEditField                   matlab.ui.control.NumericEditField
        tMinEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        removeButton_2                  matlab.ui.control.Button
        removeButton                    matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        addButton_2                     matlab.ui.control.Button
        addButton                       matlab.ui.control.Button
        SideviewListBox                 matlab.ui.control.ListBox
        SideviewListBoxLabel            matlab.ui.control.Label
        TopviewListBox                  matlab.ui.control.ListBox
        TopviewListBoxLabel             matlab.ui.control.Label
        AllfilesListBox                 matlab.ui.control.ListBox
        AllfilesListBoxLabel            matlab.ui.control.Label
        CheckvideofilesButton           matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        SeedrandomnumDropDown           matlab.ui.control.DropDown
        SeedrandomnumDropDownLabel      matlab.ui.control.Label
        MEDDataName                     matlab.ui.control.Label
        KornblumCheckBox                matlab.ui.control.CheckBox
        RemakeDropDown                  matlab.ui.control.DropDown
        RemakeDropDownLabel             matlab.ui.control.Label
        PosttimemsEditField             matlab.ui.control.NumericEditField
        PosttimemsEditFieldLabel        matlab.ui.control.Label
        PretimemsEditField              matlab.ui.control.NumericEditField
        PretimemsEditFieldLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MakingVideosButton              matlab.ui.control.Button
        WaveListBox                     matlab.ui.control.ListBox
        AlignwavedataButton             matlab.ui.control.Button
        LoadMEDButton                   matlab.ui.control.Button
        AlignLEDOnwithtriggertimeButton  matlab.ui.control.Button
        TwoviewvideoextractionappLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        eventNameglossaryTextArea       matlab.ui.control.TextArea
        eventNameglossaryTextAreaLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ExtractioncodeTextArea          matlab.ui.control.TextArea
        ExtractioncodeTextAreaLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckvideofilesButton
        function CheckvideofilesButtonPushed(app, event)
            m=dir('*.seq');
            all_names = arrayfun(@(x)x.name, m, 'UniformOutput',false);
            app.AllfilesListBox.Items = all_names;

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            this_file = app.AllfilesListBox.Value;
            PlaySeqFile(this_file)

        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            % check what file is selected
            this_file = app.AllfilesListBox.Value;
            current_files = app.TopviewListBox.Items;
            if ~isempty(current_files)
                current_files = [current_files this_file];
            else
                current_files{1} = this_file;
            end
            app.TopviewListBox.Items = current_files;
            
        end

        % Button pushed function: addButton_2
        function addButton_2Pushed(app, event)
            % check what file is selected
            this_file = app.AllfilesListBox.Value;
            current_files = app.SideviewListBox.Items;
            if ~isempty(current_files)
                current_files = [current_files this_file];
            else
                current_files{1} = this_file;
            end
            app.SideviewListBox.Items = current_files;
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            app.TopviewListBox.Items = app.TopviewListBox.Items(~strcmp(app.TopviewListBox.Items, app.TopviewListBox.Value));
        end

        % Button pushed function: removeButton_2
        function removeButton_2Pushed(app, event)
            app.SideviewListBox.Items = app.SideviewListBox.Items(~strcmp(app.SideviewListBox.Items, app.SideviewListBox.Value));
        end

        % Button pushed function: ReadframeinfoandROIsButton
        function ReadframeinfoandROIsButtonPushed(app, event)
            
            t_check = [app.tMinEditField.Value app.tMaxEditField.Value];
            sideviews = app.SideviewListBox.Items;
            topviews = app.TopviewListBox.Items;
            FrameInfo.SideView  = GetSeqFrameInfo(sideviews, t_check*100, 'SideView', 1);
            FrameInfo.TopView   = GetSeqFrameInfo(topviews, t_check*100, 'TopView', 0);

            app.ReadframeinfoandROIsButton.UserData = FrameInfo;
            app.Lamp.Color = [0 1 0];

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Look for FrameInfoSideView and FrameInfoTopView

            file1 = dir('FrameInfoSideView.mat');
            if ~isempty(file1)
                frameinfodata = load('FrameInfoSideView.mat');
                FrameInfo.SideView = frameinfodata.FrameInfo;
            end

            file2 = dir('FrameInfoTopView.mat');
            if ~isempty(file2)
                frameinfodata = load('FrameInfoTopView.mat');
                FrameInfo.TopView = frameinfodata.FrameInfo;
            end

            app.ReadframeinfoandROIsButton.UserData = FrameInfo;
            app.Lamp.Color = [0 1 0];

            clc
            disp('~~~~~~~~ Loaded ~~~~~~~~')

        end

        % Button pushed function: LoadMEDButton
        function LoadMEDButtonPushed(app, event)
            % load and process MED data
            MEDFile = dir('*Subject*.txt');
            if isempty(MEDFile)
                display('Check folder')
            end;
            % Compute behavioral data from MED file
            if app.KornblumCheckBox.Value == 1
                % Note: my (JY's) codes are stored in packages, e.g.,
                % track_training_progress_advanced_KornblumStyle can be
                % found in Behavior.MED.track_training_progress_advanced_KornblumStyle
                b = Behavior.MED.track_training_progress_advanced_KornblumStyle(MEDFile.name);
                myclass = Behavior.Timing.KornblumClass(MEDFile.name);
                myclass.Plot();
                myclass.Save()
                myclass.Print()
            else
                [b, myclass] = Behavior.MED.track_training_progress_advanced(MEDFile.name);
                myclass.Plot();
            end;

            % place b and kc (if there is any)
            app.MEDDataName.Text = MEDFile.name;
            app.LoadMEDButton.UserData = myclass;

        end

        % Button pushed function: AlignLEDOnwithtriggertimeButton
        function AlignLEDOnwithtriggertimeButtonPushed(app, event)
            if isempty(app.ReadframeinfoandROIsButton.UserData)
                 LoadButtonPushed(app, event)
            end
            FrameInfo = app.ReadframeinfoandROIsButton.UserData;
            tLEDon          =       FindLEDon(FrameInfo.SideView.tframe, FrameInfo.SideView.ROI);
            myclass = app.LoadMEDButton.UserData;

            % read trigger time in class
            tTriggerMED = myclass.ToneTime(myclass.ToneTime>0); % to ms
            tlag = 0.01; % there is a 10 ms delay
            if app.KornblumCheckBox.Value == 1
                tGoodReleaseUncue       =   myclass.ReleaseTime(myclass.Cue == 0 & strcmp(myclass.Outcome, 'Correct')) + tlag;
                tTriggerMED                     =   sort([tTriggerMED tGoodReleaseUncue]);
            end;

            figure; plot(tTriggerMED, 'ko')
            xlabel('Index')
            ylabel('Time (sec)')

            % turn trigger time to ms
            tTriggerMED = tTriggerMED*1000;

            % Align r and LED on time
            IndOut = findseqmatchrev(tTriggerMED, tLEDon, 0, 1);

            % Now tLEDon is mapped to MED time domain
            tLEDonVideo                             =       tLEDon(~isnan(IndOut));                   % This is LED on time in video time domain, in millisecond
            tLEDonMED                               =       tTriggerMED(IndOut(~isnan(IndOut)));         % This is the corresponding time in r , in millisecond

            FrameInfo.LED.tLEDon            =       tLEDonVideo;
            FrameInfo.LED.tLEDonMED   =       tLEDonMED;

            % Map video frames to MED time domain
            tFramesOut                                         =       MapVidFrameTimeToAnotherDomain(tLEDonVideo,tLEDonMED, FrameInfo.SideView.tframe);
            FrameInfo.SideView.tFrameMED           =          tFramesOut;

            % Check
            TimeDiff = tLEDonVideo(:) - tLEDonMED(:);
            figure;
            plot((tLEDonMED - tLEDonMED(1))/1000, TimeDiff - TimeDiff(1), 'ko');
            xlabel('Time since the first event (s)'); ylabel('Time difference')
             title('This demonstrate the drift (it is mitigated by re-alignment to each LED events)')

            % Double check if LED time is well aligned

            % Empirically, some events are still not well aligned. In particually, in a small subset of trials, 
            % LED starts to light up at trigger time we need to revise these trials.
            % some tLEDon need to be revised.

            imhappy = 0;
            while ~imhappy
                %   function tLEDnew = CorrectLEDtimeMED(TriggerTimeMED, FrameInfo)
                tLEDon                          =       CorrectLEDtimeMED(tLEDonMED', FrameInfo.SideView); % update tLEDon
                % find index of tLEDon in MED domain:
                IndOut                          = findseqmatchrev(tTriggerMED, tLEDon, 0, 1);
                tLEDonVideo               =       tLEDon(~isnan(IndOut));                   % This is LED on time in video time domain, in millisecond
                tLEDonMED                       =       tTriggerMED(IndOut(~isnan(IndOut)));         % This is the corresponding time in r , in millisecond

                FrameInfo.LED.tLEDon = tLEDonVideo;
                FrameInfo.LED.tLEDonMED = tLEDonMED;

                % Map video frames to MED time domain
                tFramesOut = MapVidFrameTimeToAnotherDomain(tLEDonVideo, tLEDonMED, FrameInfo.SideView.tframe);
                FrameInfo.SideView.tFrameMED = tFramesOut;

                clc
                reply = input('Are you happy? Y/N [Y]', 's');
                if isempty(reply)
                    reply = 'Y';
                end;
                if strcmp(reply, 'Y')  ||  strcmp(reply, 'y')
                    imhappy = 1;
                else
                    imhappy =0;
                end
            end

            % Now map all topview frame time to MED (that is, find the time
            % in MED that corresponds to each frame from the topview. Note
            % that Topview and side view cams are from the same computer. 
            %  the unit shown in FrameInfo.TopView.tFrameOrg are in
            %  microseconds

            tFrameOrg_Side = FrameInfo.SideView.tframeOrg/1000; % turned into ms
            tFrameOrg_Top = FrameInfo.SideView.tframeOrg/1000; % turned into ms
            tFrameMED_Side = FrameInfo.SideView.tFrameMED; %  we have mapped the frame time in side view cam to time in R
            tFrameMED_Top = interp1(tFrameOrg_Side, tFrameMED_Side, tFrameOrg_Top);

            FrameInfo.TopView.tFrameMED = tFrameMED_Top;
            % Update
            app.ReadframeinfoandROIsButton.UserData = FrameInfo;
            % Save
            save('FrameInfo.mat', 'FrameInfo')

        end

        % Button pushed function: AlignwavedataButton
        function AlignwavedataButtonPushed(app, event)
            % here we look at the wave data and map its time to r
            myclass = app.LoadMEDButton.UserData;
            % read trigger time in class
            tTriggerMED = myclass.ToneTime(myclass.ToneTime>0); % to ms
            tlag = 0.01; % there is a 10 ms delay
            if app.KornblumCheckBox.Value == 1
                tGoodReleaseUncue       =   myclass.ReleaseTime(myclass.Cue == 0 & strcmp(myclass.Outcome, 'Correct')) + tlag;
                tTriggerMED                     =   sort([tTriggerMED tGoodReleaseUncue]);
            end
            h5file = dir('*.h5');
            h5fileNames = arrayfun(@(x)x.name, h5file, 'UniformOutput',false);
            app.WaveListBox.Items = h5fileNames;
            WSOut = [];
            % These signals need to be downsampled to 1000/sec
            for i =1:length(h5fileNames)
                iWSOut                                  =       ReadH5Opto(h5fileNames{i});
                iWSOut                                  =       MapWave2MED(iWSOut, tTriggerMED);
                % Downsample to 1000 Hz
                iWSOut.Time                         =           downsample(iWSOut.Time, 20); % downsample from 20 kHz to 1 kHz
                iWSOut.Signals                      =           downsample(iWSOut.Signals, 20);
                iWSOut.TimeInMED                  =           downsample(iWSOut.TimeInMED, 20);
                if isempty(WSOut)
                    WSOut                               =       iWSOut;
                else
                    WSOut.Time                      =       [WSOut.Time iWSOut.Time];
                    WSOut.Signals                   =       [WSOut.Signals; iWSOut.Signals];
                    WSOut.TriggerTimeWS     =       [WSOut.TriggerTimeWS iWSOut.TriggerTimeWS];
                    WSOut.TriggerTimeWS2MED   =       [WSOut.TriggerTimeWS2MED iWSOut.TriggerTimeWS2MED];
                    WSOut.TimeInMED                   =       [WSOut.TimeInMED iWSOut.TimeInMED];
                end
            end
            save('WSOut.mat', 'WSOut', '-v7.3' )
            
        end

        % Callback function
        function MakeBehaviortableButtonPushed(app, event)
            r = app.rfileLabel.UserData;
            BehTable = MakeBehaviorTableFromR(r);
            r = app.rfileLabel.UserData;
            r.BehaviorTable = BehTable;
            save(app.rfileLabel.Text, 'r');
            LoadMEDButtonPushed(app, event);
           
        end

        % Button pushed function: MakingVideosButton
        function MakingVideosButtonPushed(app, event)
            exportapp(app.UIFigure,'VideoClippingInfo.png') 
            event_name = 'Unknown';
            % load frameinfo
            load('FrameInfo.mat')

            % load obj
            if   app.KornblumCheckBox.Value == 1
                filename = dir('KbClass_*.mat');
                load(filename.name);
            else
                filename = dir('BClass_*.mat');
                load(filename.name);
            end

            remake = str2num(app.RemakeDropDown.Value);
            if isempty(dir('WSOut.mat'))
                WSOut = [];
            else
                load('WSOut.mat')
            end
            random_s = str2num(app.SeedrandomnumDropDown.Value);
            rng(random_s);
            extraction_code = app.ExtractioncodeTextArea.Value;
            tPreEvent = app.PretimemsEditField.Value;
            tPostEvent = app.PosttimemsEditField.Value;
            tRange = [tPreEvent tPostEvent];
            if endsWith(extraction_code{1} , '.m')
                ncode = length(extraction_code);
                for i = 1:ncode
                    eval(extractBefore(extraction_code{1}, '.m'));
                    if max(tEvents)<1000*60*5
                        tEvents = tEvents*1000; % turn seconds into ms
                    end
                    MakeSeqVideoDualView2023(obj, FrameInfo, WSOut, tEvents, 'eventName', eventName, ...
                        'timeRange', tRange, 'reMake', remake);
                end
            else
                code_lines = app.ExtractioncodeTextArea.Value;
                for i =1:length(code_lines)
                    disp(code_lines{i})
                    eval(code_lines{i})
                end
                if max(tEvents)<1000*60*5
                    tEvents = tEvents*1000; % turn seconds into ms
                end
                MakeSeqVideoDualView2023(obj, FrameInfo, WSOut, tEvents, 'eventName', eventName, ...
                    'timeRange', tRange, 'reMake', remake);
            end


        end

        % Callback function
        function ExampleButtonPushed(app, event)
%             fid = fopen(filename, 'wt');
%             if fid ~= -1
%                 fprintf('This is line 1.  Backslash n makes a new line.\n');
%                 fprintf('My result is %f\n, result);
%                 fprintf('My string = %s\n', myString);
%                 fclose(fid);
%             else
%                 warningMessage = sprintf('Cannot open file %s', filename);
%                 uiwait(warndlg(warningMessage));
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x', 239};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CheckvideofilesButton = uibutton(app.LeftPanel, 'push');
            app.CheckvideofilesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckvideofilesButtonPushed, true);
            app.CheckvideofilesButton.Text = {'1 | Check video files'; ''};
            app.AllfilesListBoxLabel = uilabel(app.LeftPanel);
            app.AllfilesListBoxLabel.Text = 'All files';
            app.AllfilesListBox = uilistbox(app.LeftPanel);
            app.TopviewListBoxLabel = uilabel(app.LeftPanel);
            app.TopviewListBoxLabel.Text = 'Top view';
            app.TopviewListBox = uilistbox(app.LeftPanel);
            app.TopviewListBox.Items = {};
            app.TopviewListBox.Value = {};
            app.SideviewListBoxLabel = uilabel(app.LeftPanel);
            app.SideviewListBoxLabel.Text = 'Side view';
            app.SideviewListBox = uilistbox(app.LeftPanel);
            app.SideviewListBox.Items = {};
            app.SideviewListBox.Value = {};
            app.addButton = uibutton(app.LeftPanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '3 | add';
            app.addButton_2 = uibutton(app.LeftPanel, 'push');
            app.addButton_2.ButtonPushedFcn = createCallbackFcn(app, @addButton_2Pushed, true);
            app.addButton_2.Text = '3 | add';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '2 | Play';
            app.removeButton = uibutton(app.LeftPanel, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.removeButton_2 = uibutton(app.LeftPanel, 'push');
            app.removeButton_2.ButtonPushedFcn = createCallbackFcn(app, @removeButton_2Pushed, true);
            app.removeButton_2.Text = 'remove';
            app.ReadframetimesandROIPanel = uipanel(app.LeftPanel);
            app.ReadframetimesandROIPanel.Title = '4 | Read frame times and ROI';
            app.TimeEditFieldLabel = uilabel(app.ReadframetimesandROIPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.tMinEditField = uieditfield(app.ReadframetimesandROIPanel, 'numeric');
            app.tMaxEditField = uieditfield(app.ReadframetimesandROIPanel, 'numeric');
            app.ReadframeinfoandROIsButton = uibutton(app.ReadframetimesandROIPanel, 'push');
            app.ReadframeinfoandROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadframeinfoandROIsButtonPushed, true);
            app.ReadframeinfoandROIsButton.Text = 'Read frame info and ROIs';
            app.Lamp = uilamp(app.ReadframetimesandROIPanel);
            app.LoadButton = uibutton(app.ReadframetimesandROIPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TwoviewvideoextractionappLabel = uilabel(app.CenterPanel);
            app.TwoviewvideoextractionappLabel.Text = 'Two-view video extraction app';
            app.AlignLEDOnwithtriggertimeButton = uibutton(app.CenterPanel, 'push');
            app.AlignLEDOnwithtriggertimeButton.ButtonPushedFcn = createCallbackFcn(app, @AlignLEDOnwithtriggertimeButtonPushed, true);
            app.AlignLEDOnwithtriggertimeButton.Tooltip = {'Here we calculate the time LED was turned on and align these time with trigger time recorded in r. This block of procedures also add a new field ''FrameInfo'' to r and save r. We don''t need to re-run step ''6'' once it has been done once. '};
            app.AlignLEDOnwithtriggertimeButton.Text = '6 | Align LED-On with trigger time';
            app.LoadMEDButton = uibutton(app.CenterPanel, 'push');
            app.LoadMEDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMEDButtonPushed, true);
            app.LoadMEDButton.Text = '5 | Load MED';
            app.AlignwavedataButton = uibutton(app.CenterPanel, 'push');
            app.AlignwavedataButton.ButtonPushedFcn = createCallbackFcn(app, @AlignwavedataButtonPushed, true);
            app.AlignwavedataButton.Text = '7 |  Align wave data';
            app.WaveListBox = uilistbox(app.CenterPanel);
            app.MakingVideosButton = uibutton(app.CenterPanel, 'push');
            app.MakingVideosButton.ButtonPushedFcn = createCallbackFcn(app, @MakingVideosButtonPushed, true);
            app.MakingVideosButton.Text = '8 | Making Videos';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '2023.10';
            app.PretimemsEditFieldLabel = uilabel(app.CenterPanel);
            app.PretimemsEditFieldLabel.Text = 'Pre time (ms)';
            app.PretimemsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PretimemsEditField.Value = -2000;
            app.PosttimemsEditFieldLabel = uilabel(app.CenterPanel);
            app.PosttimemsEditFieldLabel.Text = 'Post time (ms)';
            app.PosttimemsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PosttimemsEditField.Value = 2000;
            app.RemakeDropDownLabel = uilabel(app.CenterPanel);
            app.RemakeDropDownLabel.Text = 'Remake';
            app.RemakeDropDown = uidropdown(app.CenterPanel);
            app.RemakeDropDown.Items = {'0', '1'};
            app.RemakeDropDown.Value = '0';
            app.KornblumCheckBox = uicheckbox(app.CenterPanel);
            app.KornblumCheckBox.Text = 'Kornblum';
            app.MEDDataName = uilabel(app.CenterPanel);
            app.SeedrandomnumDropDownLabel = uilabel(app.CenterPanel);
            app.SeedrandomnumDropDownLabel.Text = 'Seed (random num)';
            app.SeedrandomnumDropDown = uidropdown(app.CenterPanel);
            app.SeedrandomnumDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SeedrandomnumDropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.ExtractioncodeTextAreaLabel = uilabel(app.RightPanel);
            app.ExtractioncodeTextAreaLabel.Text = 'Extraction code';
            app.ExtractioncodeTextArea = uitextarea(app.RightPanel);
            app.ExtractioncodeTextArea.Value = {'filename = dir(''BClass_*.mat'');'; 'load(filename.name);'; 'tEvents                 = obj.PressTime(strcmp(obj.Outcome, ''Correct''))'; 'eventName = ''Press'';'};
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'Steps:'; '1. Check and Sort Video.'; '    Use ''Play'' to identify relevant videos. Put the viddeo names under ''Top view'' or ''Side view''. '; ''; '2. Read frame times and ROI'; ''; '3. Load MED data. '; ''; '4. Align LED on and MED''s trigger time. '; ''; '5. Optional: load and align wave data'; ''; '6. Make video clips. '; ''; 'You can make video by just clicking ''Making Videos'' if there are ''FrameInfo''(aligned), behavior class, and (optional) WSOut'};
            app.eventNameglossaryTextAreaLabel = uilabel(app.RightPanel);
            app.eventNameglossaryTextAreaLabel.Text = 'eventName glossary';
            app.eventNameglossaryTextArea = uitextarea(app.RightPanel);
            app.eventNameglossaryTextArea.Value = {'Press'; 'PressStim'; 'PressNoStim'; 'CuedPress'; 'UncuedPress'; 'Trigger'; 'TriggerStim'; 'TriggerNoStim'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeqVideoClippingMED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end