classdef SpikeChecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UpdateKilosortOutputButton   matlab.ui.control.Button
        SaveKilosortOutputmatButton  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        SpikeNumberLabel             matlab.ui.control.Label
        UnittypeDropDown             matlab.ui.control.DropDown
        UnittypeDropDownLabel        matlab.ui.control.Label
        SpknumberEditField           matlab.ui.control.NumericEditField
        SpknumberEditFieldLabel      matlab.ui.control.Label
        UnitsSpinner                 matlab.ui.control.Spinner
        UnitsSpinnerLabel            matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        PlotButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        KeepButton_2                 matlab.ui.control.Button
        SelectButton_2               matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        NewButton                    matlab.ui.control.Button
        UndoButton                   matlab.ui.control.Button
        KeepButton                   matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        UIAxesPCA                    matlab.ui.control.UIAxes
        UIAxesAutoCorr               matlab.ui.control.UIAxes
        UIAxesAmp                    matlab.ui.control.UIAxes
        UIAxesColormap               matlab.ui.control.UIAxes
        UIAxesSpikes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Look for KilosortOutput.mat
            this_file = dir('KilosortOutput.mat');
            if ~isempty(this_file)
                load('KilosortOutput.mat');
            end
            app.LoadButton.UserData = KilosortOutput;
            % Turn on lamp
            app.Lamp.Color = [0.1 1 0.2];
            PlotButtonPushed(app, event)
                        % All units
            app.UnitsSpinner.Value = 1;
            app.UnitsSpinner.Limits = [1  size(KilosortOutput.SpikeTable, 1)];

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Select PCA plot
            % remove spikes by drawing a perimeter

            % in = inpolygon(xq,yq,xv,yv)
            % Find out points that are inside the polygon.
            KO = app.LoadButton.UserData ;
            all_spikes = KO.SpikeTable.waveforms{app.UnitsSpinner.Value};
            score = app.UIAxesPCA.UserData{4};
            ind_spikes = 1:size(all_spikes, 1);

            ind_plot=  app.UIAxesPCA.UserData{1};
            x_plot = app.UIAxesPCA.UserData{2};
            y_plot = app.UIAxesPCA.UserData{3};

            axes(app.UIAxesPCA);
            cla(app.UIAxesPCA);
            scatter(app.UIAxesPCA, score(ind_plot, 1), score(ind_plot, 2), 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)

            figure(77); clf(77)
            ha=axes;
            set(ha, 'next', 'add', 'hittest', 'off')
            scatter(score(ind_plot, 1), score(ind_plot, 2), 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)
            title('Choose 20 points to mark your boundary')
            htext = uicontrol('Parent', 77, 'Style', 'text', 'string', num2str(20), ...
                'units', 'normalized', 'position', [0.1 0.95 0.2 0.04], ...
                'fontname', 'dejavu sans', 'fontsize', 12, 'fontweight', 'bold');
            k = 1;
            m=20;
            while k<=m
                [xv(k) yv(k)] = ginput(1);
                if k>1
                    plot(ha, xv(k+[-1 0]),yv(k+[-1 0]),'-r','linewidth', 1);
                else
                    plot(ha, xv(1),yv(1),'.r','linewidth', 1);
                end
                htext.String = num2str(m-k);
                k = k+1;
            end
            plot(xv([end 1]),yv([end 1]),'-r','linewidth', 1);

            pause(1)
            close(77)

            ind_inside = insidepoly(score(:, 1), score(:, 2), xv, yv);
            ind_inside2 = inpolygon(x_plot, y_plot, xv, yv);
            plot(app.UIAxesPCA, x_plot(ind_inside2), y_plot(ind_inside2), 'rx')
            % plot these spikes in spike axis
            spikes_selected = all_spikes(ind_plot(ind_inside2), :);
            spikes_unselected = all_spikes(ind_plot(~ind_inside2), :);
            tspikes = [1:size(spikes_selected, 2)];
            plot(app.UIAxesSpikes, tspikes, spikes_selected, 'r');
            ylim = app.UIAxesSpikes.YLim;
            % mark spikes in amplitude plot
            x_plot = app.UIAxesAmp.UserData{3};
            y_plot = app.UIAxesAmp.UserData{4};
            
            plot(app.UIAxesAmp, x_plot(ind_inside2), y_plot(ind_inside2), 'rx')
        
            % Make a plot showing auto-correlogram 
            figure(78); clf(78)
            set(78, 'units', 'centimeters', 'position', [5 5, 15, 9]);

            subplot(2, 3, 1)
            % original auto correlogram
            spk_times =  KO.SpikeTable.spike_times_r{app.UnitsSpinner.Value};
            spk_mat = zeros(1, ceil(spk_times(end)));
            spk_mat(round(spk_times)) = 1;
            [c, lags] = xcorr(spk_mat, 50); % max lag 25 ms
            c(lags==0)=0;
            hbar1=bar(lags, c); 
            hbar1.FaceColor = [0.3010 0.7450 0.9330];
            set(gca, 'xlim', [-25 25]);
            violation1 = 100*sum(diff(spk_times)<3)/(length(spk_times)-1);
            title(['ISI<3ms: ' num2str(violation1) '%'])
            ylim2 = get(gca, 'ylim');

            subplot(2, 3, 2)
            % auto correlogram of selected points
            spk_times_selected = spk_times(ind_inside);
            spk_mat = zeros(1, ceil(spk_times(end)));
            spk_mat(round(spk_times_selected)) = 1;
            spk_mat_selected = spk_mat;
            [c, lags] = xcorr(spk_mat, 100); % max lag 25 ms
            c(lags==0)=0;
            hbar2=bar( lags, c);
            hbar2.FaceColor = 'r';
            set(gca, 'xlim', [-25 25]);
            violation2 = 100*sum(diff(spk_times_selected)<3)/(length(spk_times_selected)-1);
            n_spikes = sum(ind_inside);
            title([num2str(n_spikes) ': ' num2str(violation2) '%'])
            set(gca, 'ylim', ylim2)

            subplot(2, 3, 5)
            n_spikes = size(spikes_selected, 1);
            spikes_chosen = spikes_selected(randperm(n_spikes, min(n_spikes, 50)), :);
            plot(tspikes, spikes_chosen, 'color', [1 0.5 0 0.5]);
            hold on
            spike_chosen_avg =  mean(spikes_selected, 1);
            plot(tspikes, spike_chosen_avg, 'r', 'linewidth', 2);
            set(gca, 'ylim', ylim)

            subplot(2, 3, 3)
            %  auto correlogram of other poins
            spk_times_unselected = setdiff(spk_times, spk_times_selected);
            spk_mat = zeros(1, ceil(spk_times(end)));
            spk_mat(round(spk_times_unselected)) = 1;
            spk_mat_unselected = spk_mat;
            [c, lags] = xcorr(spk_mat, 100); % max lag 25 ms
            c(lags==0)=0;
            hbar3=bar( lags, c);
            hbar3.FaceColor = 'k';
            set(gca, 'xlim', [-25 25]);
            violation3 = 100*sum(diff(spk_times_unselected)<3)/(length(spk_times_unselected)-1);
            n_spikes = sum(~ind_inside);
            title([num2str(n_spikes) ': ' num2str(violation3) '%']) 
            set(gca, 'ylim', ylim2)

            subplot(2, 3, 6)
            n_spikes = size(spikes_unselected, 1);
            plot(tspikes, spikes_unselected(randperm(n_spikes, min(n_spikes, 50)), :), 'color', [0.5 0.5 0.5 0.5]);
            hold on
            plot(tspikes, spike_chosen_avg, 'r', 'linewidth', 2);
            plot(tspikes, mean(spikes_unselected, 1), 'color','k', 'linewidth', 2);
            set(gca, 'ylim', ylim)

            subplot(2, 3, 4)
            % compute cross correlation
            [c, lags] = xcorr(spk_mat_unselected, spk_mat_selected, 100); % max lag 25 ms
            c(lags==0)=0;
            hbar3=bar( lags, c);
            hbar3.FaceColor = 'c';
            set(gca, 'xlim', [-25 25]);
            title(['cross correlation'])
            axis 'auto y'

            app.SelectButton.UserData = {ind_inside};

        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            % Keep selected points
            disp('~~~~~~~~~updating unit~~~~~~~~~~~')
            unit_ind         =           app.UnitsSpinner.Value;
            ko                  =           app.LoadButton.UserData;            
            % save a temporary copy of kilosortmat so we can go back
            app.KeepButton.UserData = ko;
            ind_selected  =           app.SelectButton.UserData{1}; 
            ko.SpikeTable.n_spikes(unit_ind) = sum(ind_selected);
            spktimes = ko.SpikeTable.spike_times(unit_ind);
            spktimes = spktimes{1}(ind_selected);
            ko.SpikeTable.spike_times(unit_ind) = {spktimes};

            % update spk waves
            spkwaves = ko.SpikeTable.waveforms(unit_ind);
            spkwaves = spkwaves{1};
            spkwaves = spkwaves(ind_selected, :);
            ko.SpikeTable.waveforms(unit_ind) = {spkwaves};

            % update spike_times_r
            spike_times_r = ko.SpikeTable.spike_times_r(unit_ind);
            spike_times_r = spike_times_r{1};
            spike_times_r = spike_times_r(ind_selected);
            ko.SpikeTable.spike_times_r(unit_ind)  = {spike_times_r};

            % add a column 
            new_col_name = 'ind_selected';
            ko.SpikeTable.ind_selected(unit_ind) = {ind_selected};
            app.LoadButton.UserData = ko;

            disp('~~~~~~~~~updating plots~~~~~~~~~~~')
            % replot
            PlotButtonPushed(app, event)
            close(78)


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

        
            app.Lamp.Color = [0.9 0 0.2];
            KilosortOutput = app.LoadButton.UserData;
            kotab = KilosortOutput.SpikeTable;
            % Show spike data in spike table
            kotab=removevars(kotab, {'Amplitude','ContamPct', 'KSLabel' , 'depth', 'fr', 'sh', 'spike_times_r', 'waveforms', 'waveforms_mean', 'spike_times', 'spike_times_r'});
            kotab.Violation = zeros(length(kotab.cluster_id), 1);
            kotab.Index = (1:length(kotab.cluster_id))';
%             % compute violation ratio
%             for i =1:length(kotab.cluster_id)
%                 spike_times = KilosortOutput.SpikeTable.spike_times_r{i};
%                 isi = diff(spike_times);
%                 n_violation = sum(isi<3);
%                 kotab.Violation(i) = 100*n_violation/length(spike_times);
%                 disp(['Computing unit #', num2str(i)])
%             end
            app.UITable.Data = kotab;

            if sum(strcmp(kotab.Properties.VariableNames, 'cluster_id'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'cluster_id'))} = 'ClusterID';
            end;
            if sum(strcmp(kotab.Properties.VariableNames, 'amp'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'amp'))} = 'Amplitude';
            end;
            if sum(strcmp(kotab.Properties.VariableNames, 'ch'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'ch'))} = 'Channel';
            end;
            if sum(strcmp(kotab.Properties.VariableNames, 'group'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'group'))} = 'Group';
            end;

            if sum(strcmp(kotab.Properties.VariableNames, 'n_spikes'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'n_spikes'))} = 'n_spikes';
            end;
            if sum(strcmp(kotab.Properties.VariableNames, 'ind_selected'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'ind_selected'))} = 'ind_selected';
            end;
            if sum(strcmp(kotab.Properties.VariableNames, 'Index'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'Index'))} = 'Index';
            end;
            if sum(strcmp(kotab.Properties.VariableNames, 'Violation'))
                app.UITable.ColumnName{find(strcmp(kotab.Properties.VariableNames, 'Violation'))} = 'ISI<3ms(%)';
            end;
            
            all_spikes = KilosortOutput.SpikeTable.waveforms{app.UnitsSpinner.Value};
            n_spikes = KilosortOutput.SpikeTable.n_spikes(app.UnitsSpinner.Value);
            n_plot = min([app.SpknumberEditField.Value, n_spikes]);
            ind_plot = randperm(n_spikes, n_plot);
            toplot_spikes = all_spikes(ind_plot, :);
            tspike = [1:size(toplot_spikes, 2)];
            cla(app.UIAxesSpikes)
            plot(app.UIAxesSpikes, tspike, toplot_spikes, 'color', 'b')
            app.UIAxesSpikes.XLabel.String            = '';
            app.UIAxesSpikes.XLim = [tspike(1) tspike(end)];
            app.UIAxesSpikes.YLim = [1.2*min(toplot_spikes(:)) 1.2*max(toplot_spikes(:))];
            app.UIAxesSpikes.NextPlot = 'add';
            app.UIAxesSpikes.Title.String = ['Unit #' num2str(app.UnitsSpinner.Value)];

            % Plot color
            cla(app.UIAxesColormap)
            yedges = [min(all_spikes(:)):40:max(all_spikes(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);
            for i =1:length(tspike)
                ncountsall(i, :) = histcounts(all_spikes(:, i), yedges);
            end;
            imagesc(app.UIAxesColormap, tspike, yedges(1:end)+5, ncountsall');
            app.UIAxesColormap.UserData = {tspike, yedges(1:end)+5, ncountsall'};
            app.UIAxesColormap.XLim =[tspike(1) tspike(end)];
            app.UIAxesColormap.YLim = [yedges(1) yedges(end)];
            app.UIAxesColormap.YDir = 'normal';
            app.SpikeNumberLabel.Text = num2str(size(all_spikes, 1));

            % Compute autocorrelation
            cla(app.UIAxesAutoCorr)
            spk_times =  round(KilosortOutput.SpikeTable.spike_times_r{app.UnitsSpinner.Value});
            spk_mat = zeros(1, spk_times(end));
            spk_mat(spk_times) = 1;
            [c, lags] = xcorr(spk_mat, 50); % max lag 25 ms
            c(lags==0)=0;

            hbar=bar(app.UIAxesAutoCorr, lags, c);
            app.UIAxesAutoCorr.XLim = [-25 25];
            % compute autocorrelation

            % Compute PCA
            [coeffs, score] = pca(all_spikes);
            % plot scores
            cla(app.UIAxesPCA)
            scatter(app.UIAxesPCA, score(ind_plot, 1), score(ind_plot, 2), 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)
            app.UIAxesPCA.XLabel.String = 'PC 1';
            app.UIAxesPCA.YLabel.String = 'PC 2';
            app.UIAxesPCA.NextPlot = 'add';
            app.UIAxesPCA.UserData = {ind_plot, score(ind_plot, 1), score(ind_plot, 2), score};

            % plot size vs time
            cla(app.UIAxesAmp)
            spk_amp_toplot = score(ind_plot, 1);
            spk_times_toplot = spk_times(ind_plot);
            scatter(app.UIAxesAmp, spk_times_toplot, spk_amp_toplot, 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5);
            app.UIAxesAmp.XLabel.String = 'Time (ms)';
            app.UIAxesAmp.YLabel.String = 'PC1';
            app.UIAxesAmp.NextPlot = 'add';
            app.UIAxesAmp.UserData = {spk_times, ind_plot,spk_times_toplot, spk_amp_toplot};
            app.Lamp.Color = [0.1 0.9 0.2];

            % update unit type
            unit_type = KilosortOutput.SpikeTable.group{app.UnitsSpinner.Value};
            app.UnittypeDropDown.Value = unit_type;
        
       
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            % this button still saves data before last 'Keep'
            ko_old =  app.KeepButton.UserData;
            app.LoadButton.UserData = ko_old;

        end

        % Value changed function: UnitsSpinner
        function UnitsSpinnerValueChanged(app, event)
            value = app.UnitsSpinner.Value;
           
        end

        % Value changing function: UnitsSpinner
        function UnitsSpinnerValueChanging(app, event)
%              SaveKilosortOutputmatButtonPushed(app, event)
            clc
            disp('~~~~~~~~~~~~~~~~~~~~~~~~')
            disp('~~~~~~~Please wait~~~~~~~')
            changingValue = event.Value;
            disp(['Plotting Unit # ' num2str(changingValue)])
            app.UnitsSpinner.Value = changingValue;
            PlotButtonPushed(app, event)
        end

        % Button pushed function: SaveKilosortOutputmatButton
        function SaveKilosortOutputmatButtonPushed(app, event)
            disp('~~~~~~~~~~~~~~~~~~~~~~~~')

            disp('~~~~~~~ save KilosortOutput.mat ~~~~~')
            KilosortOutput = app.LoadButton.UserData;
            save('KilosortOutput.mat', 'KilosortOutput', '-v7.3')
            disp('~~~~~~~~~done~~~~~~~~~~~')
        end

        % Value changed function: UnittypeDropDown
        function UnittypeDropDownValueChanged(app, event)
            value = app.UnittypeDropDown.Value;
            % change unit type
             KilosortOutput = app.LoadButton.UserData;
             KilosortOutput.SpikeTable.group{app.UnitsSpinner.Value} = value;
             app.LoadButton.UserData = KilosortOutput;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            
%             % plot size vs time
%             cla(app.UIAxesAmp)
%             spk_amp_toplot = score(ind_plot, 1);
%             spk_times_toplot = spk_times(ind_plot);
%             scatter(app.UIAxesAmp, spk_times_toplot, spk_amp_toplot, 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5);
%             app.UIAxesAmp.XLabel.String = 'Time (ms)';
%             app.UIAxesAmp.YLabel.String = 'PC1';
%             app.UIAxesAmp.NextPlot = 'add';
%             app.UIAxesAmp.UserData = {spk_times, ind_plot,spk_times_toplot, spk_amp_toplot};
%             app.Lamp.Color = [0.1 0.9 0.2];

            % in = inpolygon(xq,yq,xv,yv)
            % Find out points that are inside the polygon.
            KO = app.LoadButton.UserData ;

            all_spikes = KO.SpikeTable.waveforms{app.UnitsSpinner.Value};
            spk_times = app.UIAxesAmp.UserData{1};

            score = app.UIAxesPCA.UserData{4};
            ind_spikes = 1:size(all_spikes, 1);

            ind_plot=  app.UIAxesAmp.UserData{2};
            x_plot = app.UIAxesAmp.UserData{3};
            y_plot = app.UIAxesAmp.UserData{4};

            axes(app.UIAxesAmp);
            cla(app.UIAxesAmp);
            scatter(app.UIAxesAmp, x_plot, y_plot, 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)

            figure(77); clf(77)
            ha=axes;
            set(ha, 'next', 'add')
            scatter(x_plot, y_plot, 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)
            title('Choose 20 points to mark your boundary')
            htext = uicontrol('Parent', 77, 'Style', 'text', 'string', num2str(20), ...
                'units', 'normalized', 'position', [0.1 0.95 0.2 0.04], ...
                'fontname', 'dejavu sans', 'fontsize', 12, 'fontweight', 'bold');
            k = 1;
            m=20;
            while k<=m
                [xv(k) yv(k)] = ginput(1);
                if k>1
                    plot(ha, xv(k+[-1 0]),yv(k+[-1 0]),'-r','linewidth', 1);
                else
                    plot(ha, xv(1),yv(1),'.r','linewidth', 1);
                end
                htext.String = num2str(m-k);
                k = k+1;
            end
            plot(xv([end 1]),yv([end 1]),'-r','linewidth', 1);

            ind_inside = insidepoly(spk_times, score(:, 1), xv, yv);
            ind_inside2 = inpolygon(x_plot, y_plot, xv, yv);
            plot(app.UIAxesAmp, x_plot(ind_inside2), y_plot(ind_inside2), 'rx')
            % plot these spikes in spike axis
            spikes_selected = all_spikes(ind_plot(ind_inside2), :);
            tspikes = [1:size(spikes_selected, 2)];
            plot(app.UIAxesSpikes, tspikes, spikes_selected, 'r');

            % mark spikes in PCA plot
            x_plot = app.UIAxesPCA.UserData{2};
            y_plot = app.UIAxesPCA.UserData{3};
            
            plot(app.UIAxesPCA, x_plot(ind_inside2), y_plot(ind_inside2), 'rx')

        
            % Make a plot showing auto-correlogram 
            figure(78); clf(78)
            set(78, 'units', 'centimeters', 'position', [5 10, 18, 5]);
            subplot(1, 3, 1)
            % original auto correlogram
            spk_times =  KO.SpikeTable.spike_times_r{app.UnitsSpinner.Value};
            spk_mat = zeros(1, ceil(spk_times(end)));
            spk_mat(round(spk_times)) = 1;
            [c, lags] = xcorr(spk_mat, 50); % max lag 25 ms
            c(lags==0)=0;
            hbar1=bar(lags, c); 
            hbar1.FaceColor = [0.3010 0.7450 0.9330];
            set(gca, 'xlim', [-25 25]);
            violation1 = 100*sum(diff(spk_times)<3)/(length(spk_times)-1);
            title(['ISI<3ms: ' num2str(violation1) '%'])

            subplot(1, 3, 2)
            % auto correlogram of selected points
            spk_times_selected = spk_times(ind_inside);
            spk_mat = zeros(1, ceil(spk_times_selected(end)));
            spk_mat(round(spk_times_selected)) = 1;
            [c, lags] = xcorr(spk_mat, 50); % max lag 25 ms
            c(lags==0)=0;
            hbar2=bar( lags, c);
            hbar2.FaceColor = 'r';
            set(gca, 'xlim', [-25 25]);
            violation2 = 100*sum(diff(spk_times_selected)<3)/(length(spk_times_selected)-1);
            title(['ISI<3ms: ' num2str(violation2) '%'])

            subplot(1, 3, 3)
            %  auto correlogram of other poins
            spk_times_unselected = setdiff(spk_times, spk_times_selected);
            spk_mat = zeros(1, ceil(spk_times_unselected(end)));
            spk_mat(round(spk_times_unselected)) = 1;
            [c, lags] = xcorr(spk_mat, 50); % max lag 25 ms
            c(lags==0)=0;
            hbar3=bar( lags, c);
            hbar3.FaceColor = 'k';
            set(gca, 'xlim', [-25 25]);
            violation3 = 100*sum(diff(spk_times_unselected)<3)/(length(spk_times_unselected)-1);
            title(['ISI<3ms: ' num2str(violation3) '%'])
            app.SelectButton_2.UserData = {ind_inside};


        end

        % Button pushed function: KeepButton_2
        function KeepButton_2Pushed(app, event)
            % Keep selected points
            disp('~~~~~~~~~updating unit~~~~~~~~~~~')
            unit_ind         =           app.UnitsSpinner.Value;
            ko                  =           app.LoadButton.UserData;            
            % save a temporary copy of kilosortmat so we can go back
            app.KeepButton_2.UserData = ko;
            ind_selected  =           app.SelectButton_2.UserData{1}; 
            ko.SpikeTable.n_spikes(unit_ind) = sum(ind_selected);
            spktimes = ko.SpikeTable.spike_times(unit_ind);
            spktimes = spktimes{1}(ind_selected);
            ko.SpikeTable.spike_times(unit_ind) = {spktimes};

            % update spk waves
            spkwaves = ko.SpikeTable.waveforms(unit_ind);
            spkwaves = spkwaves{1};
            spkwaves = spkwaves(ind_selected, :);
            ko.SpikeTable.waveforms(unit_ind) = {spkwaves};

            % update spike_times_r
            spike_times_r = ko.SpikeTable.spike_times_r(unit_ind);
            spike_times_r = spike_times_r{1};
            spike_times_r = spike_times_r(ind_selected);
            ko.SpikeTable.spike_times_r(unit_ind)  = {spike_times_r};

            % add a column 
            new_col_name = 'ind_selected';
            ko.SpikeTable.ind_selected(unit_ind) = {ind_selected};
            app.LoadButton.UserData = ko;

            disp('~~~~~~~~~updating plots~~~~~~~~~~~')
            % replot
            PlotButtonPushed(app, event)

        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
             % Keep selected points
            disp('~~~~~~~~~generating a new unit~~~~~~~~~~~')

            unit_ind         =           app.UnitsSpinner.Value;
            ko                  =           app.LoadButton.UserData;            

            % save a temporary copy of kilosortmat so we can go back
            app.NewButton.UserData = ko;
            ind_selected  =           app.SelectButton.UserData{1}; 
            org_row = ko.SpikeTable(unit_ind, :);

            % establish a new cluster_id, a really big one so we know it is
            % not produced by a normal kilosort pipeline
            max_id = max(ko.SpikeTable.cluster_id);

            if max_id<1000
                new_id = 1001;
            else
                new_id = max_id + 1;
            end
            org_row.cluster_id = new_id;

            % new entry:
             n_spikes_new = sum(ind_selected);
             org_row.n_spikes= n_spikes_new;

             ko.SpikeTable.n_spikes(unit_ind) = sum(~ind_selected); % update the original cluster entry
            % original spike times
            spktimes = ko.SpikeTable.spike_times(unit_ind);
            spktimes_new = spktimes{1}(ind_selected); % new cluster
            org_row.spike_times = {spktimes_new};
            spktimes_original_cluster = spktimes{1}(~ind_selected);
            ko.SpikeTable.spike_times(unit_ind) = {spktimes_original_cluster}; % update original cluster

            % update spk waves
            spkwaves = ko.SpikeTable.waveforms(unit_ind);
            spkwaves = spkwaves{1};
            spkwaves_new = spkwaves(ind_selected, :);
            org_row.waveforms = {spkwaves_new};
            spkwaves_original_cluster = spkwaves(~ind_selected, :);

            ko.SpikeTable.waveforms(unit_ind) = {spkwaves_original_cluster};

            % update spike_times_r
            spike_times_r = ko.SpikeTable.spike_times_r(unit_ind);
            spike_times_r = spike_times_r{1};
            spike_times_r_new = spike_times_r(ind_selected);
            org_row.spike_times_r =  {spike_times_r_new};
            spike_times_r_original_cluster = spike_times_r(~ind_selected);
            ko.SpikeTable.spike_times_r(unit_ind)  = {spike_times_r_original_cluster};
            % add a column 
            new_col_name = 'ind_selected';
            ko.SpikeTable.ind_selected(unit_ind) = {~ind_selected};            
            org_row.ind_selected ={ind_selected};
            ko.SpikeTable = [ko.SpikeTable; org_row];
         
            app.UnitsSpinner.Limits = [1  size(ko.SpikeTable, 1)];
            app.LoadButton.UserData = ko;

            disp('~~~~~~~~~updating plots~~~~~~~~~~~')
            % replot
            PlotButtonPushed(app, event)
            close(78)
        end

        % Button pushed function: UpdateKilosortOutputButton
        function UpdateKilosortOutputButtonPushed(app, event)
            this_file = dir('KilosortOutput.mat');
            if ~isempty(this_file)
                load('KilosortOutput.mat');
            end
            Spikes.Array.UpdateKilosortOutput(KilosortOutput)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Lamp = uilamp(app.GridLayout2);
            app.UnitsSpinnerLabel = uilabel(app.GridLayout2);
            app.UnitsSpinnerLabel.Text = 'Units';
            app.UnitsSpinner = uispinner(app.GridLayout2);
            app.UnitsSpinner.ValueChangingFcn = createCallbackFcn(app, @UnitsSpinnerValueChanging, true);
            app.UnitsSpinner.ValueChangedFcn = createCallbackFcn(app, @UnitsSpinnerValueChanged, true);
            app.UnitsSpinner.Value = 1;
            app.SpknumberEditFieldLabel = uilabel(app.GridLayout2);
            app.SpknumberEditFieldLabel.Text = 'Spk number';
            app.SpknumberEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpknumberEditField.Value = 1000;
            app.UnittypeDropDownLabel = uilabel(app.GridLayout2);
            app.UnittypeDropDownLabel.Text = 'Unit type';
            app.UnittypeDropDown = uidropdown(app.GridLayout2);
            app.UnittypeDropDown.Items = {'mua', 'good', 'noise'};
            app.UnittypeDropDown.ValueChangedFcn = createCallbackFcn(app, @UnittypeDropDownValueChanged, true);
            app.UnittypeDropDown.Value = 'mua';
            app.SpikeNumberLabel = uilabel(app.GridLayout2);
            app.SpikeNumberLabel.Text = 'SpikeNumber';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveKilosortOutputmatButton = uibutton(app.GridLayout2, 'push');
            app.SaveKilosortOutputmatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKilosortOutputmatButtonPushed, true);
            app.SaveKilosortOutputmatButton.Text = 'Save KilosortOutput.mat';
            app.UpdateKilosortOutputButton = uibutton(app.GridLayout2, 'push');
            app.UpdateKilosortOutputButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKilosortOutputButtonPushed, true);
            app.UpdateKilosortOutputButton.Text = 'Update KilosortOutput';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.UIAxesSpikes = uiaxes(app.GridLayout3);
            title(app.UIAxesSpikes, 'Title')
            xlabel(app.UIAxesSpikes, 'X')
            ylabel(app.UIAxesSpikes, 'Y')
            zlabel(app.UIAxesSpikes, 'Z')
            app.UIAxesColormap = uiaxes(app.GridLayout3);
            title(app.UIAxesColormap, 'Title')
            xlabel(app.UIAxesColormap, 'X')
            ylabel(app.UIAxesColormap, 'Y')
            zlabel(app.UIAxesColormap, 'Z')
            app.UIAxesAmp = uiaxes(app.GridLayout3);
            title(app.UIAxesAmp, 'Title')
            xlabel(app.UIAxesAmp, 'X')
            ylabel(app.UIAxesAmp, 'Y')
            zlabel(app.UIAxesAmp, 'Z')
            app.UIAxesAutoCorr = uiaxes(app.GridLayout3);
            title(app.UIAxesAutoCorr, 'Title')
            xlabel(app.UIAxesAutoCorr, 'X')
            ylabel(app.UIAxesAutoCorr, 'Y')
            zlabel(app.UIAxesAutoCorr, 'Z')
            app.UIAxesPCA = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesPCA, 'X')
            ylabel(app.UIAxesPCA, 'Y')
            zlabel(app.UIAxesPCA, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SelectButton = uibutton(app.GridLayout4, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.KeepButton = uibutton(app.GridLayout4, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            app.UndoButton = uibutton(app.GridLayout4, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.NewButton = uibutton(app.GridLayout4, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Tooltip = {'Place the selected points to a new cluster'};
            app.NewButton.Text = 'New';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.SelectButton_2 = uibutton(app.GridLayout5, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.KeepButton_2 = uibutton(app.GridLayout5, 'push');
            app.KeepButton_2.ButtonPushedFcn = createCallbackFcn(app, @KeepButton_2Pushed, true);
            app.KeepButton_2.Text = 'Keep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeChecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end