classdef SpikeCuration2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UnittypeDropDown              matlab.ui.control.DropDown
        UnittypeDropDownLabel         matlab.ui.control.Label
        RatioEditField                matlab.ui.control.NumericEditField
        RatioEditFieldLabel           matlab.ui.control.Label
        PrintallButton                matlab.ui.control.Button
        PlotClustersButton            matlab.ui.control.Button
        Spikecuration2023Label        matlab.ui.control.Label
        meansubCheckBox               matlab.ui.control.CheckBox
        ButtonGroup                   matlab.ui.container.ButtonGroup
        SingleButton                  matlab.ui.control.RadioButton
        TetrodeButton                 matlab.ui.control.RadioButton
        Panel                         matlab.ui.container.Panel
        NewclusterButton              matlab.ui.control.Button
        MergeButton                   matlab.ui.control.Button
        EditMergeField2               matlab.ui.control.NumericEditField
        EditMergeField1               matlab.ui.control.NumericEditField
        ClearbadButton                matlab.ui.control.Button
        SelectgoodspikesButton        matlab.ui.control.Button
        RemovebadspikesButton         matlab.ui.control.Button
        numberLabel                   matlab.ui.control.Label
        AddtobadButton                matlab.ui.control.Button
        SelectbadspikesButton         matlab.ui.control.Button
        SelectoutliersButton          matlab.ui.control.Button
        LowerlimitEditField           matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel      matlab.ui.control.Label
        UpperlimitEditField           matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel      matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        TotalspikesLabel              matlab.ui.control.Label
        NumofspikesEditField          matlab.ui.control.NumericEditField
        NumofspikesEditFieldLabel     matlab.ui.control.Label
        PlotspikesButton              matlab.ui.control.Button
        SpikeclusterDropDown          matlab.ui.control.DropDown
        SpikeclusterDropDownLabel     matlab.ui.control.Label
        LoadspikesButton              matlab.ui.control.Button
        TetrodeDropDown               matlab.ui.control.DropDown
        RightPanel                    matlab.ui.container.Panel
        UnitsofthischanneltrodeLabel  matlab.ui.control.Label
        UnitsofthissessionLabel       matlab.ui.control.Label
        RemoveButton                  matlab.ui.control.Button
        UITableList                   matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        SelectspikesButton            matlab.ui.control.Button
        PlottypeDropDown              matlab.ui.control.DropDown
        PlottypeDropDownLabel         matlab.ui.control.Label
        SelectspikesButton_2          matlab.ui.control.Button
        SpktimesButton                matlab.ui.control.Button
        YminSlider                    matlab.ui.control.Slider
        YminSliderLabel               matlab.ui.control.Label
        YmaxSlider                    matlab.ui.control.Slider
        YmaxSliderLabel               matlab.ui.control.Label
        XmaxSlider                    matlab.ui.control.Slider
        XmaxSliderLabel               matlab.ui.control.Label
        XminSlider                    matlab.ui.control.Slider
        XminSliderLabel               matlab.ui.control.Label
        UIAxesAuto                    matlab.ui.control.UIAxes
        UIAxesPCA                     matlab.ui.control.UIAxes
        UIAxesSpkTimes                matlab.ui.control.UIAxes
        UIAxesSpikes                  matlab.ui.control.UIAxes
        UIAxesColormap                matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadspikesButton
        function LoadspikesButtonPushed(app, event)

            % Load this spike cluster
            indch = app.TetrodeDropDown.Value;
            %             cluster_name = ['polytrode' num2str(indcluster) '_spikes.mat'];
            %             polyspikes = load(cluster_name);
            %             No spike sorting information
            %             par: [1×1 struct]
            %             index: [5.3000 35.8333 57.5333 72.4667 92.1333 119.1667 … ]
            %             thr: [13×4 double]
            %             spikes: [320929×256 double]

            switch app.ButtonGroup.SelectedObject.Text
                case 'Single'
                    if app.meansubCheckBox.Value == 1
                        cluster_time =  ['times_chdat_meansub' num2str(indch) '.mat'];
                    else
                        cluster_time =  ['times_chdat' num2str(indch) '.mat'];
                    end;
                case 'Tetrode'
                    cluster_time =  ['times_polytrode' num2str(indch) '.mat'];
            end;

            polyspiketimes = load(cluster_time);

            %               par: [1×1 struct]
            %            spikes: [320929×256 double]
            %             inspk: [320929×35 double]
            %              Temp: 1
            %            forced: [1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 … ]
            %        gui_status: [1×1 struct]
            %     cluster_class: [320929×2 double]
            %           ipermut: [104739 142493 273052 87127 77425 300537 … ]

            % plot sorted spikes
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);

            app.SpikeclusterDropDown.Items = string(clusters);
            app.LoadspikesButton.UserData = polyspiketimes;
            app.AddtobadButton.UserData = [];
            %             CheckautocorrelogramButtonPushed(app, event)

            % put information on the table
            unit_types = ['noise'; repmat({'m'}, nclusters-1, 1)];
            this_table = table(str2num(indch)*ones(nclusters, 1), clusters, unit_types, 'VariableNames',{'Channels', 'Units', 'UnitTypes'});
            app.UITable.Data = this_table;
            app.UITable.ColumnName =  this_table.Properties.VariableNames;
            this_cluster = str2num(app.SpikeclusterDropDown.Value);
            index = find(this_table.Units == this_cluster);
            app.UnittypeDropDown.Value = this_table.UnitTypes{index};

            % load unit table for this session
            table_name = 'ListUnitsSorted.csv';
            find_file = dir(table_name);

            if ~isempty(find_file)
                tab = readtable('ListUnitsSorted.csv');

                app.UITableList.Data = tab;
                app.UITableList.ColumnName =  tab.Properties.VariableNames;
            end

        end

        % Button pushed function: PlotspikesButton
        function PlotspikesButtonPushed(app, event)

            % plot spikes
            ratio = app.RatioEditField.Value;
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);

            % plot no more than # spikes
            plotnum = min([size(spikeswave_thiscluster, 1) app.NumofspikesEditField.Value]);
            spikewave_toplot = spikeswave_thiscluster(randperm(size(spikeswave_thiscluster, 1), plotnum), :);
            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxesSpikes, tspike, spikewave_toplot, 'color', thiscolor)
            app.UIAxesSpikes.XLabel.String            = 'Time (ms)';
            app.UIAxesSpikes.XLim = [tspike(1) tspike(end)];
            app.UIAxesSpikes.YLim = [1.2*min(spikewave_toplot(:)) 1.2*max(spikewave_toplot(:))];

            % Plot spike in color maps
            % histogram2(X,Y,Xedges,Yedges)

            yedges = [min(spikeswave_thiscluster(:)):10:max(spikeswave_thiscluster(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);

            for i =1:length(tspike)
                ncountsall(i, :) = histcounts(spikeswave_thiscluster(:, i), yedges);
            end;

            imagesc(app.UIAxesColormap, tspike, yedges(1:end)+5, ncountsall');
            app.UIAxesColormap.UserData = {tspike, yedges(1:end)+5, ncountsall'};

            app.UIAxesColormap.XLim =[tspike(1) tspike(end)];
            app.UIAxesColormap.YLim = [yedges(1) yedges(end)];
            app.UIAxesColormap.YDir = 'normal';

            app.TotalspikesLabel.Text = num2str(size(spikeswave_thiscluster, 1));
            app.XminSlider.Value = tspike(1);
            app.XminSlider.Limits = [tspike(1) tspike(end)];


            app.XmaxSlider.Limits = [tspike(1) tspike(end)];
            app.XmaxSlider.Value = tspike(end);

            app.YminSlider.Limits = round([min(app.UIAxesColormap.YLim) 0]);
            app.YminSlider.Value = round(min(app.UIAxesColormap.YLim));

            app.YmaxSlider.Limits = round([0 max(app.UIAxesColormap.YLim)]);
            app.YmaxSlider.Value = round(max(app.UIAxesColormap.YLim));

            %% plot spike times
            % plot spike timings
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);

            indcluster = str2num(app.SpikeclusterDropDown.Value); % this is the current cluster
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);

            % Compute PCA
            [~, score]   =     pca(spikeswave_thiscluster);
            spkpeak     =    max(spikeswave_thiscluster, [], 2);
            spktrough   =    min(spikeswave_thiscluster, [], 2);

            if abs(median(spkpeak))>abs(median(spktrough))
                spkplot = spkpeak;
                yrange = [0.25*min(spkplot) max(spkplot)*1.5];
            else
                spkplot = spktrough;
                yrange = [min(spkplot)*1.5, 0.25*max(spkplot)];
            end            

            tspikeinsession = polyspiketimes.cluster_class(polyspiketimes.cluster_class(:, 1)==indcluster, 2); % this is spike time
            % choose number of spikes to plot
            n_plot = min([round(ratio*length(tspikeinsession)), app.NumofspikesEditField.Value]);
            ind_plot = randperm(length(tspikeinsession), n_plot);
            cla(app.UIAxesSpkTimes);
            app.UIAxesSpkTimes.NextPlot = 'add';
            switch app.PlottypeDropDown.Value
                case 'Amplitude'
                    scatter(app.UIAxesSpkTimes, tspikeinsession(ind_plot)/1000, spkplot(ind_plot),  30,'filled', 'MarkerFaceColor',thiscolor, 'MarkerFaceAlpha', 0.5)
                    app.UIAxesSpkTimes.XLim = [tspikeinsession(1) tspikeinsession(end)]/1000;
                    app.UIAxesSpkTimes.YLim = yrange;
                    app.UIAxesSpkTimes.XLabel.String = 'Time (s)';
                     app.UIAxesSpkTimes.YLabel.String = 'Amplitude';
                    app.UIAxesSpkTimes.UserData = {tspikeinsession/1000, spkplot,  tspikeinsession(ind_plot)/1000, spkplot(ind_plot)};
                case 'PC1'
                    scatter(app.UIAxesSpkTimes, tspikeinsession(ind_plot)/1000, score(ind_plot, 1),  30,'filled', 'MarkerFaceColor',thiscolor, 'MarkerFaceAlpha', 0.5)
                    app.UIAxesSpkTimes.XLim = [tspikeinsession(1) tspikeinsession(end)]/1000;
                    sd = std(score(:, 1));
                     app.UIAxesSpkTimes.YLim = [min(score(:, 1))-sd max(score(:, 1))+sd];
                    app.UIAxesSpkTimes.XLabel.String = 'Time (s)';
                    app.UIAxesSpkTimes.YLabel.String = 'PC1';
                           app.UIAxesSpkTimes.UserData = {tspikeinsession/1000, score(:, 1),  tspikeinsession(ind_plot)/1000, score(ind_plot, 1)};
                case 'PC2'
                    scatter(app.UIAxesSpkTimes, tspikeinsession(ind_plot)/1000, score(ind_plot, 2),  30,'filled', 'MarkerFaceColor',thiscolor, 'MarkerFaceAlpha', 0.5)
                    app.UIAxesSpkTimes.XLim = [tspikeinsession(1) tspikeinsession(end)]/1000;
                    sd = std(score(:, 2));
                     app.UIAxesSpkTimes.YLim = [min(score(:, 2))-sd max(score(:, 2))+sd];
                    app.UIAxesSpkTimes.XLabel.String = 'Time (s)';
                    app.UIAxesSpkTimes.YLabel.String = 'PC2';
                    app.UIAxesSpkTimes.UserData = {tspikeinsession/1000, score(:, 2), tspikeinsession(ind_plot)/1000, score(ind_plot, 2)};
            end;
            cla(app.UIAxesPCA)
            scatter(app.UIAxesPCA,  score(ind_plot, 1), score(ind_plot, 2), 30,'filled', 'MarkerFaceColor',thiscolor, 'MarkerFaceAlpha', 0.5)
            app.UIAxesPCA.NextPlot = 'add';
            app.UIAxesPCA.UserData = {score, ind_plot};
            disp('Done')

            % plot autocorrelation
            cla(app.UIAxesAuto)
            kutime = round(tspikeinsession);
            kutime2 = zeros(1, max(kutime));
            kutime2(kutime)=1;

            [c, lags] = xcorr(kutime2, 100); % max lag 25 ms
            c(lags==0)=0;

            app.UIAxesAuto.NextPlot = 'add';
            app.UIAxesAuto.XLim = [-100 100];
            app.UIAxesAuto.YTick = [0 max([1, median(c)])];
            app.UIAxesAuto.XLabel.String = 'Lag (ms)';
            app.UIAxesAuto.YLabel.String = 'Count';
            hbar = bar(app.UIAxesAuto, lags, c, 0.8);
            set(hbar, 'facecolor', thiscolor, 'edgecolor', 'k', 'linewidth', 1, 'BarWidth', 1, 'facealpha', 0.75)

            N_short = length(find(diff(kutime)<3));
            if isempty(N_short)
                N_short = 0;
            end;
            app.UIAxesAuto.Title.String = ['#' num2str(indcluster) ': ' sprintf('%2.1d / %2.1d < 3ms (%2.4f%%)', N_short, length(kutime), 100*N_short/length(kutime))];
 
            % Check unit  type
            app.UnittypeDropDown.Value = app.UITable.Data.UnitTypes(find(app.UITable.Data.Units == indcluster));

        end

        % Value changed function: SpikeclusterDropDown
        function SpikeclusterDropDownValueChanged(app, event)
            value = app.SpikeclusterDropDown.Value;
            % plot spikes
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            numofspikes = length(find(polyspiketimes.cluster_class(:, 1) == indcluster));

            app.TotalspikesLabel.Text = num2str(numofspikes);

            PlotspikesButtonPushed(app, event);

        end

        % Button pushed function: SelectbadspikesButton
        function SelectbadspikesButtonPushed(app, event)
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];

            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];

            MapData =  app.UIAxesColormap.UserData;
            % make new, smaller figure. otherwise the program might get really slow

            hf = figure(25); clf(hf)
            set(hf, 'units', 'centimeters', 'Position', [5 5 15 8])
            hanew = axes;

            set(hanew, 'ydir', 'normal', 'xlim', [app.XminSlider.Value  app.XmaxSlider.Value], ...
                'ylim', [ app.YminSlider.Value  app.YmaxSlider.Value], 'nextplot', 'add')

            imagesc(hanew, MapData{1}, MapData{2}, MapData{3});
            rect = getrect(hanew);

            xrange = [rect(1) rect(1)+rect(3)];
            yrange = [rect(2) rect(2)+rect(4)];

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];

            spk_tocheck = spikeswave_thiscluster(:, tspike>=xrange(1) & tspike<=xrange(2));
            [ia, ~] = find(spk_tocheck>=yrange(1) & spk_tocheck<=yrange(2));

            if ~isempty(ia)
                indbad = unique(ia);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));

                figure(34); clf(34)

                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectbadspikesButton.UserData = indbad;
                text(1, 0, num2str(length(indbad)))
            end;

        end

        % Button pushed function: AddtobadButton
        function AddtobadButtonPushed(app, event)

            newbadindex = app.SelectbadspikesButton.UserData;

            if size(newbadindex, 1)>size(newbadindex, 2)
                newbadindex = newbadindex';
                app.SelectbadspikesButton.UserData = [];
            end;

            newbadindex2 = app.SelectoutliersButton.UserData;
            if size(newbadindex2, 1)>size(newbadindex2, 2)
                newbadindex2 = newbadindex2';
                app.SelectoutliersButton.UserData = [];
            end;

            newbadindex3 = app.SelectspikesButton_2.UserData;
            if size(newbadindex3, 1)>size(newbadindex3, 2)
                newbadindex3 = newbadindex3';
                app.SelectspikesButton_2.UserData = [];
            end;

            if isempty(app.AddtobadButton.UserData)
                app.AddtobadButton.UserData = unique([newbadindex newbadindex2 newbadindex3]);
            else
                app.AddtobadButton.UserData = unique([app.AddtobadButton.UserData newbadindex newbadindex2 newbadindex3]);
            end;

            app.numberLabel.Text = num2str(length(app.AddtobadButton.UserData));
            RemovebadspikesButtonPushed(app, event)
        end

        % Button pushed function: RemovebadspikesButton
        function RemovebadspikesButtonPushed(app, event)
            % remove bad spikes from the current cluster
            indbad =  app.AddtobadButton.UserData;

            polyspiketimes = app.LoadspikesButton.UserData;

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            spkind = find(polyspiketimes.cluster_class(:, 1) == indcluster);  % all index

            ind2delete = spkind(indbad);
            polyspiketimes.cluster_class(ind2delete, 1) = 0; % turn them into 0
            app.AddtobadButton.UserData = [];
            app.LoadspikesButton.UserData = polyspiketimes;  % save the data
            app.numberLabel.Text = '';
            % update Map data


            %               par: [1×1 struct]
            %            spikes: [320929×256 double]
            %             inspk: [320929×35 double]
            %              Temp: 1
            %            forced: [1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 … ]
            %        gui_status: [1×1 struct]
            %     cluster_class: [320929×2 double]
            %           ipermut: [104739 142493 273052 87127 77425 300537 … ]

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            app.TotalspikesLabel.Text = num2str(size(spikeswave_thiscluster, 1));

            plotnum = min([size(spikeswave_thiscluster, 1) app.NumofspikesEditField.Value]);

            spikewave_toplot = spikeswave_thiscluster(randperm(size(spikeswave_thiscluster, 1), plotnum), :);

            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxesSpikes, tspike, spikewave_toplot, 'color', thiscolor)
            app.UIAxesSpikes.XLabel.String            = 'Time (ms)';
            app.UIAxesSpikes.XLim = [tspike(1) tspike(end)];
            app.UIAxesSpikes.YLim = [1.2*min(spikewave_toplot(:)) 1.2*max(spikewave_toplot(:))];
            axes(app.UIAxesSpikes)
            axis tight

            % Plot spike in color maps
            % histogram2(X,Y,Xedges,Yedges)

            yedges = [min(spikeswave_thiscluster(:)):10:max(spikeswave_thiscluster(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);
            for i =1:length(tspike)
                ncountsall(i, :) = histcounts(spikeswave_thiscluster(:, i), yedges);
            end;
            imagesc(app.UIAxesColormap, tspike, yedges(1:end)+5, ncountsall');
            app.UIAxesColormap.UserData = {tspike, yedges(1:end)+5, ncountsall'};
            app.UIAxesColormap.XLim =[tspike(1) tspike(end)];
            app.UIAxesColormap.YLim = [yedges(1) yedges(end)];
            app.UIAxesColormap.YDir = 'normal';

            % ADJUST scale
            app.XminSlider.Value = tspike(1);
            app.XminSlider.Limits = [tspike(1) tspike(end)];
            app.XmaxSlider.Limits = [tspike(1) tspike(end)];
            app.XmaxSlider.Value = tspike(end);
            app.YminSlider.Limits = round([min(app.UIAxesColormap.YLim) 0]);
            app.YminSlider.Value = round(min(app.UIAxesColormap.YLim));

            app.YmaxSlider.Limits = round([0 max(app.UIAxesColormap.YLim)]);
            app.YmaxSlider.Value = round(max(app.UIAxesColormap.YLim));

            % plot spike peak/trough with time
            PlotspikesButtonPushed(app, event)

        end

        % Callback function
        function ClearbadButtonPushed(app, event)

            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];
            app.SelectspikesButton_2.UserData = [];

        end

        % Button pushed function: SelectoutliersButton
        function SelectoutliersButtonPushed(app, event)
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];

            upper_limit = app.UpperlimitEditField.Value;
            lower_limit = app.LowerlimitEditField.Value;

            polyspiketimes = app.LoadspikesButton.UserData;

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];

            [ia, ~] = find(spikeswave_thiscluster> upper_limit | spikeswave_thiscluster<lower_limit);
            if ~isempty(ia)

                indoutofbound = unique(ia);
                indbadplot = indoutofbound(randperm(length(indoutofbound), min(100, length(indoutofbound))));
                figure(34); clf(34)

                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectoutliersButton.UserData = indoutofbound;
                text(1, 0, num2str(length(indoutofbound)), 'color', 'r')
            end;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            % this will overwrite the old data use with caution
            indch = app.TetrodeDropDown.Value;

            switch app.ButtonGroup.SelectedObject.Text
                case 'Single'
                    if app.meansubCheckBox.Value == 1
                        cluster_time =  ['times_chdat_meansub' num2str(indch) '.mat'];
                    else
                        cluster_time =  ['times_chdat' num2str(indch) '.mat'];
                    end;
                case 'Tetrode'
                    cluster_time =  ['times_polytrode' num2str(indch) '.mat'];
            end;

            polyspiketimes = app.LoadspikesButton.UserData;

            par = polyspiketimes.par;
            spikes = polyspiketimes.spikes;
            inspk = polyspiketimes.inspk;
            Temp = polyspiketimes.Temp;
            forced = polyspiketimes.forced;
            gui_status = polyspiketimes.gui_status;
            cluster_class = polyspiketimes.cluster_class;
            ipermut = polyspiketimes.ipermut;
            save(cluster_time, 'spikes', 'cluster_class', 'par', 'gui_status', 'Temp', 'forced', 'inspk', 'ipermut');

            disp('Done saving')

            % save the data into a table
            % first, look for the table
            table_name = 'ListUnitsSorted.csv';
            find_file = dir(table_name);
            if isempty(find_file)
                this_unit_table = app.UITable;
                ch                   =        this_unit_table.Data.Channels;
                units               =        this_unit_table.Data.Units;
                unit_types      =        this_unit_table.Data.UnitTypes;   
                ind_exclude = units~=0 & ~strcmp(unit_types, 'noise');
                ch                  =        ch(ind_exclude);
                unit_types     =        unit_types(ind_exclude);
                units              =        units(ind_exclude);
                tab = table(ch, units, unit_types, 'VariableNames',{'Channels', 'Units', 'UnitTypes'});
                aGoodName = 'ListUnitsSorted.csv';
                writetable(tab, aGoodName)
                disp('Done making table')
            else
                this_unit_table = app.UITable;
                ch                   =        this_unit_table.Data.Channels;
                units               =        this_unit_table.Data.Units;
                unit_types      =        this_unit_table.Data.UnitTypes;   
                ind_exclude = units~=0 & ~strcmp(unit_types, 'noise');
                ch                  =        ch(ind_exclude);
                unit_types     =        unit_types(ind_exclude);
                units              =        units(ind_exclude);
                tab = readtable('ListUnitsSorted.csv');

                % check if the current channel/tetrode is listed
                ind= find(tab.Channels == unique(ch));
                if ~isempty(ind)
                    tab(ind, :) = [];
                end

                new_entries_tab = table(ch, units, unit_types, 'VariableNames',{'Channels', 'Units', 'UnitTypes'});
                % add current data
                tab = [tab; new_entries_tab];
                % sort rows
                [~, indsort]=sort(tab.Channels);
                tab = tab(indsort, :);

                aGoodName = 'ListUnitsSorted.csv';
                writetable(tab, aGoodName)
                disp('Done making table')

            end

            app.UITableList.Data = tab;
            app.UITableList.ColumnName =  tab.Properties.VariableNames;

        end

        % Callback function
        function CheckautocorrelogramButtonPushed(app, event)
            % plot auto-correlogram

            polyspiketimes = app.LoadspikesButton.UserData;

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikestime_thiscluster = polyspiketimes.cluster_class(find(polyspiketimes.cluster_class(:, 1) == indcluster), 2);

            % plot autocorrelation
            kutime = round(spikestime_thiscluster);
            kutime2 = zeros(1, max(kutime));
            kutime2(kutime)=1;

            [c, lags] = xcorr(kutime2, 50); % max lag 25 ms
            c(lags==0)=0;

            figure(35); clf(35)
            set(35, 'units', 'centimeters', 'position', [5 5 10 8])

            ha00= axes('nextplot', 'add', 'xlim', [-50 50]);
            if median(c)>1
                set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 median(c)]);
            else
                set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 1], 'ylim', [0 1]);
            end;

            hbar = bar(lags, c);
            set(hbar, 'facecolor', thiscolor, 'edgecolor', 'none', 'linewidth', 1, 'BarWidth', 1);

            xlabel('Lag(ms)')

            N_short = length(find(diff(kutime)<3));
            if isempty(N_short)
                N_short = 0;
            end;
            title(['#' num2str(indcluster) ': ' sprintf('%2.1d / %2.1d < 3ms (%2.4f%%)', N_short, length(kutime), 100*N_short/length(kutime))], 'FontName', 'Dejavu Sans', 'fontsize', 8, 'FontWeight', 'bold')
            legend(num2str(length(kutime)), 'box', 'off')


            mkdir('Fig')
            savename = fullfile(pwd, 'Fig', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value 'Cluster'  app.SpikeclusterDropDown.Value 'AutoCorrelation.png'])
            export_fig(35, savename)

        end

        % Button pushed function: SelectgoodspikesButton
        function SelectgoodspikesButtonPushed(app, event)
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];

            MapData =  app.UIAxesColormap.UserData;
            % make new, smaller figure. otherwise the program might get really slow

            hf = figure(25); clf(hf)
            set(hf, 'units', 'centimeters', 'Position', [5 5 15 8])
            hanew = axes;

            set(hanew, 'ydir', 'normal', 'xlim', [app.XminSlider.Value  app.XmaxSlider.Value], ...
                'ylim', [ app.YminSlider.Value  app.YmaxSlider.Value], 'nextplot', 'add')

            imagesc(hanew, MapData{1}, MapData{2}, MapData{3});
            rect = getrect(hanew);

            xrange = [rect(1) rect(1)+rect(3)];
            yrange = [rect(2) rect(2)+rect(4)];

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];

            spk_tocheck = spikeswave_thiscluster(:, tspike>=xrange(1) & tspike<=xrange(2));

            [ia, ~] = find(spk_tocheck>=yrange(1) & spk_tocheck<=yrange(2)); % this actually selects good spikes

            ia = setdiff(1:size(spk_tocheck, 1), unique(ia));

            if ~isempty(ia)
                indbad = unique(ia);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));

                figure(34); clf(34)

                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectbadspikesButton.UserData = indbad;
                text(1, 0, num2str(length(indbad)))

            end;
        end

        % Button pushed function: ClearbadButton
        function ClearbadButtonPushed2(app, event)
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];
        end

        % Value changed function: XminSlider
        function XminSliderValueChanged(app, event)
            value = app.XminSlider.Value;
            value2 = app.XmaxSlider.Value;

            if value2 > value
                app.UIAxesColormap.XLim = [value value2];
                app.UIAxesSpikes.XLim = [value value2];
            end;

        end

        % Value changed function: XmaxSlider
        function XmaxSliderValueChanged(app, event)

            value = app.XminSlider.Value;
            value2 = app.XmaxSlider.Value;

            if value2 > value
                app.UIAxesColormap.XLim = [value value2];
                app.UIAxesSpikes.XLim = [value value2];
            end;
        end

        % Value changed function: YmaxSlider
        function YmaxSliderValueChanged(app, event)
            value = app.YmaxSlider.Value;
            value2 = app.YminSlider.Value;
            if value2 < value
                app.UIAxesColormap.YLim = [value2 value];
                app.UIAxesSpikes.YLim = [value2 value];
            end;
        end

        % Value changed function: YminSlider
        function YminSliderValueChanged(app, event)

            value = app.YmaxSlider.Value;

            value2 = app.YminSlider.Value;

            if value2 < value
                app.UIAxesColormap.YLim = [value2 value];
                app.UIAxesSpikes.YLim = [value2 value];
            end;
        end

        % Callback function
        function PrintButtonPushed(app, event)

            mkdir('Fig')
            savename = fullfile(pwd, 'Fig', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value 'Cluster'  app.SpikeclusterDropDown.Value 'Sorting.png'])
            exportapp(app.UIFigure, savename)
            % plot auto-correlogram
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikestime_thiscluster = polyspiketimes.cluster_class(find(polyspiketimes.cluster_class(:, 1) == indcluster), 2);
            % plot autocorrelation
            kutime = round(spikestime_thiscluster);
            kutime2 = zeros(1, max(kutime));
            kutime2(kutime)=1;
            [c, lags] = xcorr(kutime2, 50); % max lag 25 ms
            c(lags==0)=0;

            figure(35); clf(35)
            set(35, 'units', 'centimeters', 'position', [5 5 10 8])
            ha00= axes('nextplot', 'add', 'xlim', [-50 50])
            if median(c)>1
                set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 median(c)])
            else
                set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 1], 'ylim', [0 1])
            end;

            hbar = bar(lags, c);
            set(hbar, 'facecolor', thiscolor, 'edgecolor', 'none', 'linewidth', 1, 'BarWidth', 0.8)

            xlabel('Lag(ms)')

            N_short = length(find(diff(kutime)<3));
            if isempty(N_short)
                N_short = 0;
            end;
            title(['#' num2str(indcluster) ': ' sprintf('%2.1d / %2.1d < 3ms (%2.4f%%)', N_short, length(kutime), 100*N_short/length(kutime))], 'FontName', 'Dejavu Sans', 'fontsize', 8, 'FontWeight', 'bold')
            legend(num2str(length(kutime)), 'box', 'off')

            mkdir('Fig')
            savename = fullfile(pwd, 'Fig', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value 'Cluster'  app.SpikeclusterDropDown.Value 'AutoCorrelation.png'])
            export_fig(35, savename)


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
        end

        % Button pushed function: SpktimesButton
        function SpktimesButtonPushed(app, event)
            % plot spike timings

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);

            spkpeak = max(spikeswave_thiscluster, [], 2);
            spktrough = min(spikeswave_thiscluster, [], 2);

            if abs(median(spkpeak))>abs(median(spktrough))
                spkplot = spkpeak;
                yrange = [0.9*min(spkplot) max(spkplot)*1.1];
            else
                spkplot = spktrough;
                yrange = [min(spkplot)*1.1, 0.9*max(spkplot)];
            end;

            tspikeinsession = polyspiketimes.cluster_class(polyspiketimes.cluster_class(:, 1)==indcluster, 2);

             ind_plot = randperm(length(tspikeinsession), round(app.RatioEditField.Value*length(tspikeinsession)));

            plot(app.UIAxesSpkTimes, tspikeinsession(ind_plot)/1000, spkplot(ind_plot), '.', 'color', thiscolor)
            app.UIAxesSpkTimes.XLim = [tspikeinsession(1)/1000-60 tspikeinsession(end)/1000+60];
            app.UIAxesSpkTimes.YLim = yrange;
            app.UIAxesSpkTimes.XLabel.String = 'Time (s)';
            app.UIAxesSpkTimes.Title =[];
            app.UIAxesSpkTimes.UserData = spkplot;

        end

        % Button pushed function: PlotClustersButton
        function PlotClustersButtonPushed(app, event)

            % plot spikes
            indch = app.TetrodeDropDown.Value;
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);

            figure(39); clf(39)

            ha=axes;
            set(ha, 'nextplot', 'add');

            for i = 1:nclusters-1
                indcluster =  i;
                spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
                plot(mean(spikeswave_thiscluster, 1)-200*(i-1), 'color', allcolors(i+1, :), 'linewidth', 1);
                text(5, 50-200*(i-1), ['cluster', num2str(i)], 'color', allcolors(i+1, :));
            end;

            switch app.ButtonGroup.SelectedObject.Text
                case 'Single'
                    title(['Ch' num2str(indch)])
                case 'Tetrode'
                    title(['Tetrode' num2str(indch)])
            end;

            axis tight

            mkdir('Fig')
            savename = fullfile(pwd, 'Fig', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value 'AllCluster'  '.png'])
            export_fig(39, savename)


        end

        % Button pushed function: NewclusterButton
        function NewclusterButtonPushed(app, event)
            % remove bad spikes from the current cluster

            indbad =  app.AddtobadButton.UserData;
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            % now add this to a new cluster

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            newclusterid = clusters(end)+1;

            spkind = find(polyspiketimes.cluster_class(:, 1) == indcluster);  % all index
            ind2newcluster = spkind(indbad);

            polyspiketimes.cluster_class(ind2newcluster, 1) = newclusterid;

            app.AddtobadButton.UserData = [];
            app.LoadspikesButton.UserData = polyspiketimes;  % save the data
            app.numberLabel.Text = '';

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);


            app.SpikeclusterDropDown.Items = string(clusters);

            %               par: [1×1 struct]
            %            spikes: [320929×256 double]
            %             inspk: [320929×35 double]
            %              Temp: 1
            %            forced: [1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 … ]
            %        gui_status: [1×1 struct]
            %     cluster_class: [320929×2 double]
            %           ipermut: [104739 142493 273052 87127 77425 300537 … ]

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            app.TotalspikesLabel.Text = num2str(size(spikeswave_thiscluster, 1));

            plotnum = min([size(spikeswave_thiscluster, 1) app.NumofspikesEditField.Value]);

            spikewave_toplot = spikeswave_thiscluster(randperm(size(spikeswave_thiscluster, 1), plotnum), :);

            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxesSpikes, tspike, spikewave_toplot, 'color', thiscolor)
            app.UIAxesSpikes.XLabel.String            = 'Time (ms)';
            app.UIAxesSpikes.XLim = [tspike(1) tspike(end)];
            app.UIAxesSpikes.YLim = [1.2*min(spikewave_toplot(:)) 1.2*max(spikewave_toplot(:))];
            axes(app.UIAxesSpikes)
            axis tight

            % Plot spike in color maps
            % histogram2(X,Y,Xedges,Yedges)

            yedges = [min(spikeswave_thiscluster(:)):10:max(spikeswave_thiscluster(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);

            for i =1:length(tspike)
                ncountsall(i, :) = histcounts(spikeswave_thiscluster(:, i), yedges);
            end;

            imagesc(app.UIAxesColormap, tspike, yedges(1:end)+5, ncountsall');

            app.UIAxesColormap.XLim =[tspike(1) tspike(end)];
            app.UIAxesColormap.YLim = [yedges(1) yedges(end)];
            app.UIAxesColormap.YDir = 'normal';

            % ADJUST scale
            app.XminSlider.Value = tspike(1);
            app.XminSlider.Limits = [tspike(1) tspike(end)];

            app.XmaxSlider.Limits = [tspike(1) tspike(end)];
            app.XmaxSlider.Value = tspike(end);

            app.YminSlider.Limits = round([min(app.UIAxesColormap.YLim) 0]);
            app.YminSlider.Value = round(min(app.UIAxesColormap.YLim));

            app.YmaxSlider.Limits = round([0 max(app.UIAxesColormap.YLim)]);
            app.YmaxSlider.Value = round(max(app.UIAxesColormap.YLim));

        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)

            tomerge1 = app.EditMergeField1.Value;
            tomerge2 = app.EditMergeField2.Value;

            if tomerge1==tomerge2
                return
            end;

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            clusters(clusters==tomerge2) = tomerge1;

            newclusters = unique(clusters);
            [~, sortindex]=sort(newclusters);
            sortindex = sortindex - 1;

            for i =1:length(newclusters)
                spkind = find(polyspiketimes.cluster_class(:, 1) == newclusters(i));  % all index
                polyspiketimes.cluster_class(spkind, 1) = sortindex(i);
            end;

            app.AddtobadButton.UserData = [];
            app.LoadspikesButton.UserData = polyspiketimes;  % save the data
            app.numberLabel.Text = '';

            clusters = unique(polyspiketimes.cluster_class(:, 1));

            app.SpikeclusterDropDown.Items = string(clusters);


        end

        % Button pushed function: PrintallButton
        function PrintallButtonPushed(app, event)

            mkdir('Fig')

            fighand = 36;
            figure(fighand); clf(fighand)
            set(fighand, 'units', 'centimeters', 'position', [5 5 10 13])

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indplot = 0;

            for i =1:length(app.SpikeclusterDropDown.Items)

                if ~strcmp(app.SpikeclusterDropDown.Items{i}, '0')
                    app.SpikeclusterDropDown.Value = app.SpikeclusterDropDown.Items{i};
                    PlotspikesButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);

                    savename = fullfile(pwd, 'Fig', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value 'Cluster'  app.SpikeclusterDropDown.Items{i} 'Sorting.png'])
                    exportapp(app.UIFigure, savename)

                    % plot auto-correlogram
                    polyspiketimes = app.LoadspikesButton.UserData;
                    clusters = unique(polyspiketimes.cluster_class(:, 1));
                    nclusters = length(clusters);
                    allcolors = varycolor(nclusters*2);
                    allcolors = allcolors(1:2:2*nclusters-1, :);
                    indcluster = str2double(app.SpikeclusterDropDown.Items{i});
                    thiscolor = allcolors(clusters==indcluster, :);
                    spikestime_thiscluster = polyspiketimes.cluster_class(find(polyspiketimes.cluster_class(:, 1) == indcluster), 2);
                    % plot autocorrelation
                    kutime = round(spikestime_thiscluster);
                    kutime2 = zeros(1, max(kutime));
                    kutime2(kutime)=1;
                    [c, lags] = xcorr(kutime2, 50); % max lag 25 ms
                    c(lags==0)=0;

                    ha00= axes('nextplot', 'add', 'xlim', [-50 50], 'ylim', [0 max(c)*1.1],  'units', 'centimeters', 'position', [2+indplot 1.5 6 4], ...
                        'FontName', 'Dejavu Sans', 'fontsize', 7)

                    if median(c)>1
                        set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 median(c)])
                    else
                        set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 1], 'ylim', [0 1])
                    end;

                    hbar = bar(lags, c);
                    set(hbar, 'facecolor', thiscolor, 'edgecolor', 'none', 'linewidth', 1,  'BarWidth', 1)

                    if indplot == 0
                        xlabel('Lag (ms)', 'FontName', 'Dejavu Sans', 'fontsize', 8)
                        ylabel('Spike count')
                    end;

                    N_short = length(find(diff(kutime)<3));
                    if isempty(N_short)
                        N_short = 0;
                    end;
                    title(['#' num2str(indcluster) ': ' sprintf('%2.1d / %2.1d < 3ms (%2.4f%%)', N_short, length(kutime), 100*N_short/length(kutime))], 'FontName', 'Dejavu Sans', 'fontsize', 8, 'FontWeight', 'bold')
                    legend(num2str(length(kutime)), 'box', 'off')

                    % Plot spike waveforms
                    ha01= axes('nextplot', 'add', 'xlim', [-50 50], 'ylim', [0 max(c)*1.1],  'units', 'centimeters', 'position', [2+indplot 7 6 4], ...
                        'FontName', 'Dejavu Sans', 'fontsize', 7)
                    % plot spikes
                    thiscolor = allcolors(clusters==indcluster, :);
                    spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
                    plotnum = min([size(spikeswave_thiscluster, 1) app.NumofspikesEditField.Value]);
                    spikewave_toplot = spikeswave_thiscluster(randperm(size(spikeswave_thiscluster, 1), plotnum), :);
                    tspike = [1:size(spikewave_toplot, 2)];
                    plot(tspike, spikewave_toplot, 'color', thiscolor)
                    xlabel('Time (ms)');
                    ha01.XLim = [tspike(1) tspike(end)];
                    ha01.YLim = [1.2*min(spikewave_toplot(:)) 1.2*max(spikewave_toplot(:))];
                    indplot = indplot + 7.5;
                end;
            end;

            set(fighand, 'units', 'centimeters', 'position', [5 5 indplot+2 12.5]);
            uicontrol('Style', 'text', 'parent', 36,'units', 'normalized', 'position', [0.1 0.91 0.25 0.05],'HorizontalAlignment', 'left', ...
                'string', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value], 'fontweight', 'bold', 'fontsize', 10);


            savename = fullfile(pwd, 'Fig', [app.ButtonGroup.SelectedObject.Text app.TetrodeDropDown.Value  'AutoCorrelationUnits.png']);
            export_fig(fighand, savename)

        end

        % Button pushed function: SelectspikesButton_2
        function SelectspikesButton_2Pushed(app, event)

            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            tspikeinsession = app.UIAxesSpkTimes.UserData{1};
            spkplot = app.UIAxesSpkTimes.UserData{2};

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];
            % x is polyspiketimes, y is spkplot

            x_plot = app.UIAxesSpkTimes.Children.XData;
            y_plot =  app.UIAxesSpkTimes.Children.YData;

            figure(78); clf(78)
            set(78, 'units', 'centimeters', 'position', [2 2 15 10]);
            ha=axes;
            set(ha, 'next', 'add')
            scatter(x_plot, y_plot, 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)
            title('Choose 20 points to mark your boundary for removing spikes')
            htext = uicontrol('Parent', 78, 'Style', 'text', 'string', num2str(20), ...
                'units', 'normalized', 'position', [0.1 0.96 0.2 0.04], ...
                'fontname', 'dejavu sans', 'fontsize', 12, 'fontweight', 'bold');
            k = 1;
            m=20;
            while k<=m
                [xv(k) yv(k)] = ginput(1);
                if k>1
                    plot(ha, xv(k+[-1 0]),yv(k+[-1 0]),'-r','linewidth', 1);
                else
                    plot(ha, xv(1),yv(1),'.r','linewidth', 1);
                end
                htext.String = num2str(m-k);
                k = k+1;
            end
            plot(xv([end 1]),yv([end 1]),'-r','linewidth', 1);

            ind_inside = insidepoly(tspikeinsession, spkplot, xv, yv);
            ind_inside2 = inpolygon(x_plot, y_plot, xv, yv);
            if sum(ind_inside2)>0
            plot(app.UIAxesSpkTimes, x_plot(ind_inside2), y_plot(ind_inside2), 'rx')
            end;

            if sum(ind_inside)>0
                figure(34); clf(34)
                plot(tspike, spikeswave_thiscluster(ind_inside, :), 'color', [0.5 0.5 0.5]);

                indbad = find(ind_inside==0);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));
                figure(34); clf(34)
                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectspikesButton_2.UserData = indbad;
                text(1, 0, num2str(length(indbad)))
            end;

        end

        % Value changed function: PlottypeDropDown
        function PlottypeDropDownValueChanged(app, event)
            value = app.PlottypeDropDown.Value;
            disp('##### Please wait ######')
            PlotspikesButtonPushed(app, event)
        end

        % Button pushed function: SelectspikesButton
        function SelectspikesButtonPushed(app, event)
            % Select a bunch of points

            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.SelectbadspikesButton.UserData = [];
            app.SelectoutliersButton.UserData = [];

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            tspikeinsession = app.UIAxesSpkTimes.UserData{1};
            spkplot = app.UIAxesSpkTimes.UserData{2};

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];
            % x is polyspiketimes, y is spkplot

            score = app.UIAxesPCA.UserData{1};
            ind_plot = app.UIAxesPCA.UserData{2};

            x_plot = score(ind_plot, 1);
            y_plot = score(ind_plot, 2);

            figure(78); clf(78)
            set(78, 'units', 'centimeters', 'position', [2 2 15 10]);
            ha=axes;
            set(ha, 'next', 'add')
            scatter(x_plot, y_plot, 30,'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)
            title('Choose 20 points to mark your boundary for removing spikes')
            htext = uicontrol('Parent', 78, 'Style', 'text', 'string', num2str(20), ...
                'units', 'normalized', 'position', [0.1 0.96 0.2 0.04], ...
                'fontname', 'dejavu sans', 'fontsize', 12, 'fontweight', 'bold');
            k = 1;
            m=20;
            while k<=m
                [xv(k) yv(k)] = ginput(1);
                if k>1
                    plot(ha, xv(k+[-1 0]),yv(k+[-1 0]),'-r','linewidth', 1);
                else
                    plot(ha, xv(1),yv(1),'.r','linewidth', 1);
                end
                htext.String = num2str(m-k);
                k = k+1;
            end
            plot(xv([end 1]),yv([end 1]),'-r','linewidth', 1);

            ind_inside = insidepoly(score(:,1), score(:, 2), xv, yv);
            ind_inside2 = inpolygon(x_plot, y_plot, xv, yv);

            if sum(ind_inside2)>0
                plot(app.UIAxesPCA, x_plot(ind_inside2), y_plot(ind_inside2), 'rx')

                tspikeinsession_prime = app.UIAxesSpkTimes.UserData{3};
                spkplot_prime = app.UIAxesSpkTimes.UserData{4};
                plot(app.UIAxesSpkTimes, tspikeinsession_prime(ind_inside2),  spkplot_prime(ind_inside2), 'rx')
            end;

            if sum(ind_inside)>0
%                 figure(34); clf(34)
%                 plot(tspike, spikeswave_thiscluster(ind_inside, :), 'color', [0.5 0.5 0.5]);

                indbad = find(ind_inside==0);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));
                figure(34); clf(34)
                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectspikesButton_2.UserData = indbad;
                text(1, 0, num2str(length(indbad)))
                title('Other spikes')
            end;


        end

        % Value changed function: UnittypeDropDown
        function UnittypeDropDownValueChanged(app, event)
            value = app.UnittypeDropDown.Value;
            % to change unit type
            indcluster = str2num(app.SpikeclusterDropDown.Value); % this is the current cluster
            app.UITable.Data.UnitTypes(find(app.UITable.Data.Units == indcluster))={value};
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
                    % save the data into a table
            % first, look for the table
            table_name = 'ListUnitsSorted.csv';
            find_file = dir(table_name);
            if ~isempty(find_file)
                this_unit_table = app.UITable;
                ch                   =        this_unit_table.Data.Channels;
                units               =        this_unit_table.Data.Units;
                unit_types      =        this_unit_table.Data.UnitTypes;  

                ch                  =        ch(units~=0);
                unit_types     =        unit_types(units~=0);
                units              =        units(units~=0);
                tab = readtable('ListUnitsSorted.csv');
                % check if the current channel/tetrode is listed
                ind= find(tab.Channels == ch);
                if ~isempty(ind)
                    tab(ind, :) = [];
                end
                aGoodName = 'ListUnitsSorted.csv';
                writetable(tab, aGoodName)
                disp('Done making table')
            end

            app.UITableList.Data = tab;
            app.UITableList.ColumnName =  tab.Properties.VariableNames;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {200, 200};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TetrodeDropDown = uidropdown(app.LeftPanel);
            app.TetrodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32'};
            app.TetrodeDropDown.Value = '1';
            app.LoadspikesButton = uibutton(app.LeftPanel, 'push');
            app.LoadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspikesButtonPushed, true);
            app.LoadspikesButton.Text = 'Load spikes';
            app.SpikeclusterDropDownLabel = uilabel(app.LeftPanel);
            app.SpikeclusterDropDownLabel.Text = 'Spike cluster';
            app.SpikeclusterDropDown = uidropdown(app.LeftPanel);
            app.SpikeclusterDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeclusterDropDownValueChanged, true);
            app.PlotspikesButton = uibutton(app.LeftPanel, 'push');
            app.PlotspikesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotspikesButtonPushed, true);
            app.PlotspikesButton.Text = 'Plot spikes';
            app.NumofspikesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumofspikesEditFieldLabel.Text = 'Num of spikes';
            app.NumofspikesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumofspikesEditField.Value = 1000;
            app.TotalspikesLabel = uilabel(app.LeftPanel);
            app.TotalspikesLabel.Text = 'Total spikes';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.UpperlimitEditFieldLabel = uilabel(app.Panel);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.UpperlimitEditField = uieditfield(app.Panel, 'numeric');
            app.UpperlimitEditField.Value = 1000;
            app.LowerlimitEditFieldLabel = uilabel(app.Panel);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit';
            app.LowerlimitEditField = uieditfield(app.Panel, 'numeric');
            app.LowerlimitEditField.Value = -1000;
            app.SelectoutliersButton = uibutton(app.Panel, 'push');
            app.SelectoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutliersButtonPushed, true);
            app.SelectoutliersButton.Text = 'Select outliers';
            app.SelectbadspikesButton = uibutton(app.Panel, 'push');
            app.SelectbadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbadspikesButtonPushed, true);
            app.SelectbadspikesButton.Text = 'Select bad spikes';
            app.AddtobadButton = uibutton(app.Panel, 'push');
            app.AddtobadButton.ButtonPushedFcn = createCallbackFcn(app, @AddtobadButtonPushed, true);
            app.AddtobadButton.Text = 'Add to bad';
            app.numberLabel = uilabel(app.Panel);
            app.numberLabel.Text = 'number';
            app.RemovebadspikesButton = uibutton(app.Panel, 'push');
            app.RemovebadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovebadspikesButtonPushed, true);
            app.RemovebadspikesButton.Text = 'Remove bad spikes';
            app.SelectgoodspikesButton = uibutton(app.Panel, 'push');
            app.SelectgoodspikesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgoodspikesButtonPushed, true);
            app.SelectgoodspikesButton.Text = 'Select good spikes';
            app.ClearbadButton = uibutton(app.Panel, 'push');
            app.ClearbadButton.ButtonPushedFcn = createCallbackFcn(app, @ClearbadButtonPushed2, true);
            app.ClearbadButton.Text = 'Clear bad';
            app.EditMergeField1 = uieditfield(app.Panel, 'numeric');
            app.EditMergeField2 = uieditfield(app.Panel, 'numeric');
            app.MergeButton = uibutton(app.Panel, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.NewclusterButton = uibutton(app.Panel, 'push');
            app.NewclusterButton.ButtonPushedFcn = createCallbackFcn(app, @NewclusterButtonPushed, true);
            app.NewclusterButton.Text = 'New cluster';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TetrodeButton = uiradiobutton(app.ButtonGroup);
            app.TetrodeButton.Text = 'Tetrode';
            app.TetrodeButton.Value = true;
            app.SingleButton = uiradiobutton(app.ButtonGroup);
            app.SingleButton.Text = 'Single';
            app.meansubCheckBox = uicheckbox(app.LeftPanel);
            app.meansubCheckBox.Text = 'meansub';
            app.Spikecuration2023Label = uilabel(app.LeftPanel);
            app.Spikecuration2023Label.Text = {'Spike curation'; '2023'; ''};
            app.PlotClustersButton = uibutton(app.LeftPanel, 'push');
            app.PlotClustersButton.ButtonPushedFcn = createCallbackFcn(app, @PlotClustersButtonPushed, true);
            app.PlotClustersButton.Text = 'PlotClusters';
            app.PrintallButton = uibutton(app.LeftPanel, 'push');
            app.PrintallButton.ButtonPushedFcn = createCallbackFcn(app, @PrintallButtonPushed, true);
            app.PrintallButton.Text = 'Print all';
            app.RatioEditFieldLabel = uilabel(app.LeftPanel);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RatioEditField.Tooltip = {'Percentage of spikes to be plotted. '};
            app.RatioEditField.Value = 0.1;
            app.UnittypeDropDownLabel = uilabel(app.LeftPanel);
            app.UnittypeDropDownLabel.Text = 'Unit type';
            app.UnittypeDropDown = uidropdown(app.LeftPanel);
            app.UnittypeDropDown.Items = {'s', 'm', 'noise'};
            app.UnittypeDropDown.ValueChangedFcn = createCallbackFcn(app, @UnittypeDropDownValueChanged, true);
            app.UnittypeDropDown.Value = 's';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesColormap = uiaxes(app.RightPanel);
            xlabel(app.UIAxesColormap, 'X')
            ylabel(app.UIAxesColormap, 'Y')
            zlabel(app.UIAxesColormap, 'Z')
            app.UIAxesSpikes = uiaxes(app.RightPanel);
            xlabel(app.UIAxesSpikes, 'X')
            ylabel(app.UIAxesSpikes, 'Y')
            zlabel(app.UIAxesSpikes, 'Z')
            app.UIAxesSpkTimes = uiaxes(app.RightPanel);
            title(app.UIAxesSpkTimes, 'Feature vs time')
            xlabel(app.UIAxesSpkTimes, 'X')
            ylabel(app.UIAxesSpkTimes, 'Y')
            zlabel(app.UIAxesSpkTimes, 'Z')
            app.UIAxesPCA = uiaxes(app.RightPanel);
            title(app.UIAxesPCA, 'PCA')
            xlabel(app.UIAxesPCA, 'pc1')
            ylabel(app.UIAxesPCA, 'pc2')
            zlabel(app.UIAxesPCA, 'Z')
            app.UIAxesAuto = uiaxes(app.RightPanel);
            title(app.UIAxesAuto, 'Cross-correlation')
            xlabel(app.UIAxesAuto, 'X')
            ylabel(app.UIAxesAuto, 'Y')
            zlabel(app.UIAxesAuto, 'Z')
            app.XminSliderLabel = uilabel(app.RightPanel);
            app.XminSliderLabel.Text = 'Xmin';
            app.XminSlider = uislider(app.RightPanel);
            app.XminSlider.ValueChangedFcn = createCallbackFcn(app, @XminSliderValueChanged, true);
            app.XmaxSliderLabel = uilabel(app.RightPanel);
            app.XmaxSliderLabel.Text = 'Xmax';
            app.XmaxSlider = uislider(app.RightPanel);
            app.XmaxSlider.ValueChangedFcn = createCallbackFcn(app, @XmaxSliderValueChanged, true);
            app.YmaxSliderLabel = uilabel(app.RightPanel);
            app.YmaxSliderLabel.Text = 'Ymax';
            app.YmaxSlider = uislider(app.RightPanel);
            app.YmaxSlider.ValueChangedFcn = createCallbackFcn(app, @YmaxSliderValueChanged, true);
            app.YminSliderLabel = uilabel(app.RightPanel);
            app.YminSliderLabel.Text = 'Ymin';
            app.YminSlider = uislider(app.RightPanel);
            app.YminSlider.ValueChangedFcn = createCallbackFcn(app, @YminSliderValueChanged, true);
            app.SpktimesButton = uibutton(app.RightPanel, 'push');
            app.SpktimesButton.ButtonPushedFcn = createCallbackFcn(app, @SpktimesButtonPushed, true);
            app.SpktimesButton.Text = 'Spk times';
            app.SelectspikesButton_2 = uibutton(app.RightPanel, 'push');
            app.SelectspikesButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectspikesButton_2Pushed, true);
            app.SelectspikesButton_2.Tooltip = {'in the popup window, select spikes you want to keep'};
            app.SelectspikesButton_2.Text = 'Select spikes';
            app.PlottypeDropDownLabel = uilabel(app.RightPanel);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.RightPanel);
            app.PlottypeDropDown.Items = {'Amplitude', 'PC1', 'PC2'};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypeDropDownValueChanged, true);
            app.PlottypeDropDown.Value = 'Amplitude';
            app.SelectspikesButton = uibutton(app.RightPanel, 'push');
            app.SelectspikesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectspikesButtonPushed, true);
            app.SelectspikesButton.Tooltip = {'in the popup window, select spikes you want to keep'};
            app.SelectspikesButton.Text = 'Select spikes';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'this is units from current channel'};
            app.UITableList = uitable(app.RightPanel);
            app.UITableList.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableList.RowName = {};
            app.UITableList.Tooltip = {'this is the list of units sorted in this session'};
            app.RemoveButton = uibutton(app.RightPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tooltip = {'Remove current channel/tetrode form the list'};
            app.RemoveButton.Text = 'Remove';
            app.UnitsofthissessionLabel = uilabel(app.RightPanel);
            app.UnitsofthissessionLabel.Text = 'Units of this session';
            app.UnitsofthischanneltrodeLabel = uilabel(app.RightPanel);
            app.UnitsofthischanneltrodeLabel.Text = 'Units of this channel/trode';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SaveButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeCuration2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end