classdef TrajectoryAnalysisLesion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        timeresolutionmsEditField       matlab.ui.control.NumericEditField
        timeresolutionmsEditFieldLabel  matlab.ui.control.Label
        SpatialKernelwidthEditField     matlab.ui.control.NumericEditField
        SpatialKernelwidthEditFieldLabel  matlab.ui.control.Label
        MidpostlesiontrialsEditField    matlab.ui.control.NumericEditField
        MidpostlesiontrialsEditFieldLabel  matlab.ui.control.Label
        SimilarityanalysisButton        matlab.ui.control.Button
        trangeEditField                 matlab.ui.control.NumericEditField
        trangeEditFieldLabel            matlab.ui.control.Label
        trangeEditField_2               matlab.ui.control.NumericEditField
        LateprelesiontrialsEditField    matlab.ui.control.NumericEditField
        LateprelesiontrialsEditFieldLabel  matlab.ui.control.Label
        EarlyprelesiontrialsEditField   matlab.ui.control.NumericEditField
        EarlyprelesiontrialsEditFieldLabel  matlab.ui.control.Label
        LatepostlesiontrialsEditField   matlab.ui.control.NumericEditField
        LatepostlesiontrialsEditFieldLabel  matlab.ui.control.Label
        EarlypostlesiontrialsEditField  matlab.ui.control.NumericEditField
        EarlypostlesiontrialsEditFieldLabel  matlab.ui.control.Label
        yrangeEditField_2               matlab.ui.control.NumericEditField
        xrangeEditField_2               matlab.ui.control.NumericEditField
        yrangeEditField                 matlab.ui.control.NumericEditField
        yrangeEditFieldLabel            matlab.ui.control.Label
        xrangeEditField                 matlab.ui.control.NumericEditField
        xrangeEditFieldLabel            matlab.ui.control.Label
        PlottrajectoryandspeedButton    matlab.ui.control.Button
        AlignmentacrosssessionsButton   matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        DatatypesButtonGroup            matlab.ui.container.ButtonGroup
        UnclearButton                   matlab.ui.control.RadioButton
        UnderVideoDataButton            matlab.ui.control.RadioButton
        TraditionalButton               matlab.ui.control.RadioButton
        ProcesstrackingdataButton       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ExtensivetrainingEditField      matlab.ui.control.EditField
        ExtensivetrainingEditFieldLabel  matlab.ui.control.Label
        PrintCheckBox                   matlab.ui.control.CheckBox
        PlotButton_2                    matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ImageEditField                  matlab.ui.control.NumericEditField
        ImageEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.EditField
        WidthEditFieldLabel             matlab.ui.control.Label
        LoadsessionsButton              matlab.ui.control.Button
        ANMEditField                    matlab.ui.control.EditField
        ANMEditFieldLabel               matlab.ui.control.Label
        AddButton_2                     matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        ManipulationListBox             matlab.ui.control.ListBox
        ManipulationListBoxLabel        matlab.ui.control.Label
        ControlListBox                  matlab.ui.control.ListBox
        ControlListBoxLabel             matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
    end


    methods (Access = private)

        function [results, distance_sum] = cal_speed(app, t, x, y)
            results                 =       nan*ones(length(x), 1);
            distance              =       nan*ones(length(x), 1);
            ind_not_nan     =        find(~isnan(x) & ~isnan(y));
            x_                          =       x(ind_not_nan);
            y_                          =       y(ind_not_nan);
            t_                          =       t(ind_not_nan);
            for i =2:length(x_)
                results(ind_not_nan(i)) = sqrt((x_(i)-x_(i-1))^2+(y_(i)-y_(i-1))^2)/(t_(i)-t_(i-1));
                distance(ind_not_nan(i)) = sqrt((x_(i)-x_(i-1))^2+(y_(i)-y_(i-1))^2);
            end

            distance_sum = nan*ones(length(distance), 1);
            for i =1:length(distance)
                if ~isnan(distance(i))
                    distance_sum(i) = sum(distance(i:end), 'omitnan');
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadsessionsButton
        function LoadsessionsButtonPushed(app, event)
            % check session folders

            % find out name of the rat
            thisFolder = pwd;
            thisFolderSplitted = strsplit(thisFolder, '\');
            NameIndex =  find(strcmp(thisFolderSplitted, 'ANMs'))+1;
            ratName = thisFolderSplitted{NameIndex};


            disp(['this rat is: ', ratName])

            % Read folders
            rootFolder = pwd;
            files = dir(rootFolder);
            dirFlags = [files.isdir];
            subFolders                 =        files(dirFlags);
            subFolderNames      =        {subFolders(3:end).name};
            dataFolderNames    = {};
            for k =1:length(subFolderNames)
                if ~isempty(str2num(subFolderNames{k}))
                    dataFolderNames = [dataFolderNames  subFolderNames{k}];
                    fprintf('Subfolder #%d = %s \n', k,  subFolderNames{k})
                end;
            end
            dataFolderNames'
            app.ListBox.Items = dataFolderNames;


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % add data to 'prelesion' or 'saline' category
            value = app.ListBox.Value;
            app.ControlListBox.Items = [app.ControlListBox.Items {value}];
            app.ControlListBox.Items = unique(app.ControlListBox.Items);
            % remove the item from listbox
            app.ListBox.Items = setdiff(app.ListBox.Items, value);

        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            % add data to 'prelesion' or 'saline' category
            value = app.ListBox.Value;
            app.ManipulationListBox.Items = [app.ManipulationListBox.Items {value}];
            app.ManipulationListBox.Items = unique(app.ManipulationListBox.Items);
            % remove the item from listbox
            app.ListBox.Items = setdiff(app.ListBox.Items, value);
        end

        % Button pushed function: ProcesstrackingdataButton
        function ProcesstrackingdataButtonPushed(app, event)

            % Here we will compute the tracking data, including extraction and
            % alignment.

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 1| load data, build class%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Sessions_PreLesion = app.ControlListBox.Items;
            Sessions_PostLesion = app.ManipulationListBox.Items;
            this_folder = pwd; % this is current folder
            ANM_Name = app.ANMEditField.Value;
            NumImage = app.ImageEditField.Value;
            Width = str2double(app.WidthEditField.Value);
            AllSessionNames = {};
            ImageReal = {};
            obj_pre = {};
            obj_post = {};
            % Check pre-lesion session
            clc
            disp('############ Extracting prelesion sessions ############')

            for i = 1:length(Sessions_PreLesion)
                if app.TraditionalButton.Value
                    FileDir = fullfile(this_folder, Sessions_PreLesion{i});
                elseif app.UnderVideoDataButton.Value
                    FileDir = fullfile(this_folder, Sessions_PreLesion{i}, 'VideoData');
                end
                disp(['Processing session: ' Sessions_PreLesion{i}])
                load(fullfile(FileDir, 'DLCTrackingOut.mat'));
                obj          =  PawTraj(DLCTrackingOut, ANM_Name, 'Width', Width);

                % remove some images
                if length( size(obj.PoseTracking.Images{1}))==4 && NumImage <  size(obj.PoseTracking.Images{1},4)
                    if NumImage == 1
                        index = median((1:size(obj.PoseTracking.Images{1},4)));
                        for k =1:length(obj.PoseTracking.Images)
                            obj.PoseTracking.Images{k} = obj.PoseTracking.Images{k}(:,:,:,index);
                            obj.PoseTracking.ImagesIndex{k} =  obj.PoseTracking.ImagesIndex{k}(index);
                        end
                    else
                        index = randperm(size(obj.PoseTracking.Images{1},4), NumImage);
                        for k =1:length(obj.PoseTracking.Images)
                            obj.PoseTracking.Images{k} = obj.PoseTracking.Images{k}(:,:,:,index);
                            obj.PoseTracking.ImagesIndex{k} =  obj.PoseTracking.ImagesIndex{k}(index);
                        end
                    end
                end

                obj_pre{i} = obj;
                obj_pre{i}.nExample =  50;
                close
                %     obj_pre{i}.PlotTrajDistribution;
                disp(obj_pre{i}.EndPoints)
                ImageReal = [ImageReal {obj.PoseTracking.Images{randperm(length(obj.PoseTracking.Images), 1)}}];
                AllSessionNames = [AllSessionNames; Sessions_PreLesion{i}];
            end

            disp('############ Extracting postlesion sessions ############')
            for i = 1:length(Sessions_PostLesion)
                if app.TraditionalButton.Value
                    FileDir = fullfile(this_folder, Sessions_PostLesion{i});
                elseif app.UnderVideoDataButton.Value
                    FileDir = fullfile(this_folder, Sessions_PostLesion{i},'VideoData');
                end
                disp(['Processing session: ' Sessions_PostLesion{i}])
                load(fullfile(FileDir, 'DLCTrackingOut.mat'));
                obj =   PawTraj(DLCTrackingOut, ANM_Name, 'Width', Width);

                % remove some images
                if length( size(obj.PoseTracking.Images{1}))==4 && NumImage <  size(obj.PoseTracking.Images{1},4)
                    if NumImage == 1
                        index = median((1:size(obj.PoseTracking.Images{1},4)));
                        for k =1:length(obj.PoseTracking.Images)
                            obj.PoseTracking.Images{k} = obj.PoseTracking.Images{k}(:,:,:,index);
                            obj.PoseTracking.ImagesIndex{k} =  obj.PoseTracking.ImagesIndex{k}(index);
                        end
                    else
                        index = randperm(size(obj.PoseTracking.Images{1},4), NumImage);
                        for k =1:length(obj.PoseTracking.Images)
                            obj.PoseTracking.Images{k} = obj.PoseTracking.Images{k}(:,:,:,index);
                            obj.PoseTracking.ImagesIndex{k} =  obj.PoseTracking.ImagesIndex{k}(index);
                        end
                    end
                end
                obj_post{i}          = obj;
                obj_post{i}.nExample =  50;
                close
                %     obj_post{i}.PlotTrajDistribution;
                disp(obj_post{i}.EndPoints)
                ImageReal = [ImageReal {obj.PoseTracking.Images{randperm(length(obj.PoseTracking.Images), 1)}}];
                AllSessionNames = [AllSessionNames; Sessions_PostLesion{i}];
            end

            disp(ImageReal')
            disp(AllSessionNames)
            obj_prepost = {obj_pre, obj_post, ImageReal, AllSessionNames};

            app.ProcesstrackingdataButton.UserData = obj_prepost;
            save(['PawTrajObj_PrePost_' ANM_Name '.mat'], 'obj_prepost', '-v7.3')
            app.Lamp.Color = 'g';


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            if isempty(app.ProcesstrackingdataButton.UserData)
                error('Process or load data first')
            else
                obj_data = app.ProcesstrackingdataButton.UserData;
                obj_control = obj_data{1};
            end

            index_session = strcmp(app.ControlListBox.Items, app.ControlListBox.Value);
            this_obj = obj_control{index_session};
            this_obj.PlotTrajDistribution;
            this_obj.Plot;
            if app.PrintCheckBox.Value == 1
                this_obj.PrintTrajDistribution;
                this_obj.Print;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            ANM_Name = app.ANMEditField.Value;

            [filename, pathname, ~] = uigetfile(['PawTrajObj_PrePost_' ANM_Name '*.mat'], 'Choose a TrajObj');
            load(fullfile(pathname, filename));
            if contains(filename, 'Calibrated')
                app.Lamp.Color = 'b';
            else
                app.Lamp.Color = 'g';
            end
            app.ProcesstrackingdataButton.UserData = obj_prepost;

        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            if isempty(app.ProcesstrackingdataButton.UserData)
                error('Process or load data first')
            else
                obj_data = app.ProcesstrackingdataButton.UserData;
                obj_manip= obj_data{2};
            end

            index_session = strcmp(app.ManipulationListBox.Items, app.ManipulationListBox.Value);
            this_obj = obj_manip{index_session};
            this_obj.PlotTrajDistribution;
            this_obj.Plot;
            if app.PrintCheckBox.Value == 1
                this_obj.PrintTrajDistribution;
                this_obj.Print;
            end
        end

        % Button pushed function: AlignmentacrosssessionsButton
        function AlignmentacrosssessionsButtonPushed(app, event)
            obj_pre = app.ProcesstrackingdataButton.UserData{1};
            obj_post = app.ProcesstrackingdataButton.UserData{2};
            ImageReal= app.ProcesstrackingdataButton.UserData{3};
            AllSessionNames= app.ProcesstrackingdataButton.UserData{4}; 

            % check if the lamp is blue, if it is, skip 'alignment'
            % note that the lamp only turns blue when 
            % 1. we have run this step already just recently (because in
            % the end, of this callback, the lamp turns blue)
            % 2. loaded PawTrajObj_PrePost_ANM_Calibrated.mat file
            % in either case, we shouldn't perform alignment again. 

            if  isempty(find(app.Lamp.Color-[0 0 1]))
                f = msgbox('You have already done that! check command window');
                clc
                reply = input('Re-do alignment? (y/n)', 's');
                if ~strcmp(reply, 'y')
                return
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 2| alignment%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% run the following block of code if you don't understand how
            %%% shift is defined.
            %             XShifts = [0 10 30 -40];
            %             YShifts = [0 15 10 -35];
            %             % Check if the images overlap with each other (camera might get moved
            %             % around)
            %             XCheckLimit = [100 600];
            %             YCheckLimit = [100 400];
            %             XCheckRange = [XCheckLimit(1):XCheckLimit(2)];
            %             YCheckRange = [YCheckLimit(1):YCheckLimit(2)];
            %             ImagBaseline = ImageReal{3};
            %             ImagBaseline = ImagBaseline(XCheckRange, YCheckRange, 1);
            %             MaxCorrLocs = [];
            %             ImagCollections = [];
            %             for i =1:length(XShifts)
            %                 ImagTest = ImageReal{3};
            %                 ImagTest = ImagTest(XCheckRange+XShifts(i), YCheckRange+YShifts(i), 1);
            %                 ImagBaseline_sub = ImagBaseline - mean(ImagBaseline(:));
            %                 ImagTest_sub     = ImagTest - mean(ImagTest(:));
            %                 figure; clf
            %                 set(gcf, 'units', 'centimeters', 'position', [2 2 30 7], 'Visible', 'on')
            %                 subplot(1, 3, 1)
            %                 imagesc(ImagBaseline)
            %                 title('base')
            %                 subplot(1, 3, 2)
            %                 imagesc(ImagTest)
            %                 colormap(gray)
            %                 title(num2str(i))
            %                 crr = xcorr2(ImagBaseline_sub, ImagTest_sub);
            %                 subplot(1, 3, 3)
            %                 imagesc(crr)
            %                 colormap(gray)
            %                 [ssr, snd] = max(crr(:));
            %                 [indi, indj] = ind2sub(size(crr), snd);
            %                 hold on
            %                 plot(indj, indi, 'r+', 'markersize', 10, 'linewidth', 2)
            %                 MaxCorrLocs = [MaxCorrLocs; ssr, indi, indj];
            %                 disp([ssr, indi, indj])
            %                 disp('Difference with auto-correlation: ')
            %                 disp([ssr, indi, indj]-MaxCorrLocs(1, :))
            %                 disp('Shifts')
            %                 disp([XShifts(i), YShifts(i)])
            %                 disp('Positive difference: Upward or Leftward shift; Negative difference: Downward or Rightward shift')
            %             end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Check if images across sessions are shifted due to camera
            % moving around

            % Check if the images overlap with each other (camera might get moved
            % around)
            clc
            disp('~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~')
            disp('~~~~~~~~~Check image stability~~~~~~~~~~')
            disp('~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~')
            XCheckLimit = [1 700];
            YCheckLimit = [1 600];
            MaxX = 800;
            MaxY = 800;
            XCheckRange = [XCheckLimit(1):XCheckLimit(2)];
            YCheckRange = [YCheckLimit(1):YCheckLimit(2)];
            IndBase     = 1; % this is the image with which other images are compared to
            ImagBaseline = ImageReal{IndBase};
            ImagBaseline = ImagBaseline(XCheckRange, YCheckRange, 1);
            MaxCorrLocs = [];
            ImagCollections = [];
            figure(6); clf
            set(gcf, 'units', 'centimeters', 'position', [2 2 12 length(ImageReal)*3], 'Visible', 'on')
            for i =1:length(ImageReal)
                ImagTest = ImageReal{i};
                ImagTest = ImagTest(XCheckRange, YCheckRange, 1);
                ImagCollections = cat(3, ImagCollections, ImagTest);
                ImagBaseline_sub = ImagBaseline - mean(ImagBaseline(:));
                ImagTest_sub     = ImagTest - mean(ImagTest(:));
                subplot(length(ImageReal), 3, 1+3*(i-1))
                imagesc(ImagBaseline)
                text(XCheckRange(1), YCheckRange(1), sprintf('base=%2.0d', IndBase), 'color', 'y')
%                 set(gca, 'xticklabel', [], 'yticklabel', [])
                subplot(length(ImageReal), 3, 2+3*(i-1))
                imagesc(ImagTest)
                colormap(gray)
                text(XCheckRange(1), YCheckRange(1), sprintf('test=%2.0d', i), 'color', 'y')
%                 set(gca, 'xticklabel', [], 'yticklabel', [])
                crr = xcorr2(ImagBaseline_sub, ImagTest_sub);
                subplot(length(ImageReal), 3, 3+3*(i-1))
                imagesc(crr)
                colormap(gray)
                [ssr, snd] = max(crr(:));
                [indi, indj] = ind2sub(size(crr), snd);
                hold on
                plot(indj, indi, 'r+', 'markersize', 10, 'linewidth', 2)
%                 set(gca, 'xticklabel', [], 'yticklabel', [])

                MaxCorrLocs = [MaxCorrLocs; ssr, indi, indj];
                disp('~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~')
                disp(['Base is ' num2str(IndBase) ' | Session: ' AllSessionNames{IndBase}])
                disp(['This session is ',  AllSessionNames{i}])
                disp(['Image#' num2str(i)])
                disp([ssr, indi, indj])
                disp('Difference with base (base =1): ')
                disp([ssr, indi, indj]-MaxCorrLocs(1, :))
                disp('Positive difference: Upward or Leftward shift; Negative difference: Downward or Rightward shift')
            end
            disp(MaxCorrLocs)
            disp(MaxCorrLocs - MaxCorrLocs(IndBase, :))
            this_folder = pwd;
            if ~exist(fullfile(pwd, 'Figures'), 'dir')
                mkdir(fullfile(this_folder, 'Figures'))
            end
            tosavename=  fullfile(this_folder, 'Figures', 'Fig0_ImageStability');
            saveas(gcf, tosavename, 'epsc')
            saveas(gcf, tosavename, 'fig')
            print (gcf,'-dpdf', tosavename)
            print (gcf,'-dpng', tosavename)
            %%% Plot an example
            Coordinates_Adjustment =  MaxCorrLocs(:, [2 3]) - MaxCorrLocs(IndBase, [2 3]);
            disp(Coordinates_Adjustment)
            % first column is vertical adjustment, second column is horizontal
          %% adjustment
            figure(22); clf
            img_size = 3;
            set(gcf, 'units', 'centimeters', 'position', [2 2 25 6], 'Visible','on', 'paperpositionmode', 'auto')
            xplotrange = [100 800];
            yplotrange = [200 900];
            tIndex     = [-250 100];
            tMap = [tIndex(1):tIndex(end)];
            cmap = parula(length(tMap));
            % pre-lesion trials
            NewPreCoordinates = [];
            PrePlots        = linspace(1, length(obj_pre),length(obj_pre));
            for k =1:length(PrePlots)
                IndPre          =   PrePlots(k);
                IndExample        =   randperm(size(obj_pre{IndPre}.TrajMapped, 3), 10);
                ha_prelesion    =   axes('units', 'centimeters', 'nextplot', 'add', 'position', [1+(img_size + 0.5)*(k-1) 1 img_size img_size], 'xlim', xplotrange, 'ylim', yplotrange, 'ydir', 'reverse');
                axis off
                rng(3)
                ImagePlot       =   obj_pre{IndPre}.PoseTracking.Images{IndExample(3)};
                if length(size(ImagePlot))==4
                    ImagePlot = ImagePlot(:,:, :, 1);
                end
                NewOrigin = [Coordinates_Adjustment(IndPre, 2) Coordinates_Adjustment(IndPre, 1)];
                imagesc(NewOrigin(1), NewOrigin(2), ImagePlot)
                NewPreCoordinates = cat(1, NewPreCoordinates, NewOrigin);
                colormap gray
                TrajectoryToPlot = obj_pre{IndPre}.TrajMapped(:, :, IndExample);
                for i = 1:length(IndExample)
                    iIndx = find(TrajectoryToPlot(:, 1, i)>=tIndex(1) & TrajectoryToPlot(:, 1, i)<=tIndex(2));
                    tPlot = TrajectoryToPlot(iIndx, 1, i);
                    [~, ind_cmap] = intersect(tMap, tPlot);
                    Horizontal_Coordinates = TrajectoryToPlot(iIndx, 2, i);
                    Vertical_Coordinates   =  TrajectoryToPlot(iIndx, 3, i);
                    % Re-calibrate !!!!!!!!!!! This is important.
                    % Trajectory also got shiftted around                 
                    Horizontal_Coordinates  =  Horizontal_Coordinates + Coordinates_Adjustment(IndPre, 2);
                    Vertical_Coordinates    =  Vertical_Coordinates + Coordinates_Adjustment(IndPre, 1);
                    hs = scatter(ha_prelesion, downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                        5, downsample(cmap(ind_cmap, :), 10),'+');
                    title(['Pre lesion' num2str(PrePlots(k))])
                end;
                %     axis off
            end;
            % Post-Lesion trials
            NewPostCoordinates = [];
            PostPlots        = linspace(1, length(obj_post),length(obj_post))+length(obj_pre);
            for k =1:length(PostPlots)
                disp(k)
                IndPost          =   PostPlots(k)-length(obj_pre);
                IndExample        =   randperm(size(obj_post{IndPost}.TrajMapped, 3), min(10,size(obj_post{IndPost}.TrajMapped, 3)));
                ha_postlesion    =   axes('units', 'centimeters', 'nextplot', 'add', 'position',  [1+(img_size + 0.5)*length(PrePlots)+(img_size + 0.5)*(k-1) 1 img_size img_size], 'xlim', xplotrange, 'ylim', yplotrange, 'ydir', 'reverse');
                axis off
                rng(3)
                ImagePlot       =   obj_post{IndPost}.PoseTracking.Images{IndExample(1)};

                if length(size(ImagePlot))==4
                    ImagePlot = ImagePlot(:,:, :, 1);
                end

                NewOrigin = [Coordinates_Adjustment(PostPlots(k), 2)  Coordinates_Adjustment(PostPlots(k), 1)];
                imagesc(NewOrigin(1), NewOrigin(2), ImagePlot)
                NewPostCoordinates = cat(1, NewPostCoordinates, NewOrigin);

                colormap gray
                TrajectoryToPlot = obj_post{IndPost}.TrajMapped(:, :, IndExample);

                for i = 1:length(IndExample)
                    iIndx = find(TrajectoryToPlot(:, 1, i)>=tIndex(1) & TrajectoryToPlot(:, 1, i)<=tIndex(2));
                    tPlot = TrajectoryToPlot(iIndx, 1, i);
                    [~, ind_cmap] = intersect(tMap, tPlot);

                    Horizontal_Coordinates = TrajectoryToPlot(iIndx, 2, i);
                    Vertical_Coordinates   =  TrajectoryToPlot(iIndx, 3, i);

                    % Re-calibrate
                    Horizontal_Coordinates  =  Horizontal_Coordinates + Coordinates_Adjustment(PostPlots(k), 2);
                    Vertical_Coordinates    =  Vertical_Coordinates + Coordinates_Adjustment(PostPlots(k), 1);

                    hs = scatter(ha_postlesion, downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                        5, downsample(cmap(ind_cmap, :), 10), ...
                      '+');
                    title(['Post lesion' num2str(IndPost)])
                end;
                %     axis off
            end;

            % this plots the color scalebar
            xnow =  1+(img_size + 0.5)*length(PrePlots)+(img_size + 0.5)*length(PostPlots);
            ha_colorbar = axes('units', 'centimeters', 'position', [xnow 1 0.2 3], ...
                'nextplot', 'add', ...
                'ydir', 'normal', 'YAxisLocation', 'right', ...
                'ytick', [-200:100:50], 'ylim', tIndex, 'xlim', [0 1.5], 'xtick', [], 'TickLength', [0.015 0.1]);
            ss =  scatter(ones(1, length(tMap)), tMap, 20,  cmap, 'filled', 's');
            ylabel('Time from maximal height (ms)')
            tosavename=  fullfile(this_folder, 'Figures', 'Fig1_ReachingTrajectories(adjusted)');

            saveas(gcf, tosavename, 'epsc')
            saveas(gcf, tosavename, 'fig')
            print (gcf,'-dpdf', tosavename)
            print (gcf,'-dpng', tosavename)

            %% Critical: Update obj.OriginCoordinates
            % Redefin OriginCoordinates
            % This has several effect as some properties are dependent
            % properties
            % 1. TrajMapped will be re-calculated. 
            % 2.    % TrajDist, which is calculated based on TrajMapped
            %           will be re-calculated. 
            for i =1:length(obj_pre)
                obj_pre{i}.OriginCoordinates = obj_pre{i}.OriginCoordinates+NewPreCoordinates(i, :);
            end
            for i =1:length(obj_post)
                obj_post{i}.OriginCoordinates = obj_post{i}.OriginCoordinates+NewPostCoordinates(i, :);
            end
            obj_prepost = {obj_pre, obj_post, ImageReal, AllSessionNames};
            app.ProcesstrackingdataButton.UserData = obj_prepost; % here we update the userdata
            ANM_Name = app.ANMEditField.Value;
            % save to a new name is important so we dont update the updated
            % trajectories, etc. 
            save(['PawTrajObj_PrePost_' ANM_Name '_Calibrated.mat'], 'obj_prepost', '-v7.3')
             app.Lamp.Color = 'b';

            %% Using new coordinates to recreate the reaching trajectory figure
            figure(23); clf
            set(gcf, 'units', 'centimeters', 'position', [2 2 25 6], 'Visible','on', 'paperpositionmode', 'auto')
            xplotrange = [100 800];
            yplotrange = [200 900];
            tIndex     = [-250 100];
            tMap = [tIndex(1):tIndex(end)];
            cmap = parula(length(tMap));
            % pre-lesion trials
            PrePlots        = linspace(1, length(obj_pre),length(obj_pre));
            namePre=fliplr(1:length(PrePlots));
            for k =1:length(PrePlots)
                IndPre          =   PrePlots(k);
                IndExample        =   randperm(size(obj_pre{IndPre}.TrajMapped, 3), 10);
                ha_prelesion    =   axes('units', 'centimeters', 'nextplot', 'add', 'position', [1+(img_size + 0.5)*(k-1) 1 img_size img_size], 'xlim', xplotrange, 'ylim', yplotrange, 'ydir', 'reverse');
                
                rng(3)
                ImagePlot       =   obj_pre{IndPre}.PoseTracking.Images{IndExample(3)};
                if length(size(ImagePlot))==4
                    ImagePlot = ImagePlot(:,:, :, 1);
                end
                NewOrigin = obj_pre{IndPre}.OriginCoordinates;
                imagesc(NewOrigin(1), NewOrigin(2), ImagePlot)
                colormap gray
                TrajectoryToPlot = obj_pre{IndPre}.TrajMapped(:, :, IndExample);
                for i = 1:length(IndExample)
                    iIndx = find(TrajectoryToPlot(:, 1, i)>=tIndex(1) & TrajectoryToPlot(:, 1, i)<=tIndex(2));
                    tPlot = TrajectoryToPlot(iIndx, 1, i);
                    [~, ind_cmap] = intersect(tMap, tPlot);
                    Horizontal_Coordinates = TrajectoryToPlot(iIndx, 2, i);
                    Vertical_Coordinates   =  TrajectoryToPlot(iIndx, 3, i);
                    hs = scatter(ha_prelesion, downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                        10, downsample(cmap(ind_cmap, :), 10),'+');
                    title(['Pre lesion' num2str(namePre(k))])
                    if k==2
                        axis off
                    end
                end
            end
            % Post-Lesion trials
            PostPlots        = linspace(1, length(obj_post),length(obj_post))+length(obj_pre);
            for k =1:length(PostPlots)
                IndPost          =   PostPlots(k)-length(obj_pre);
                IndExample        =   randperm(size(obj_post{IndPost}.TrajMapped, 3), min(10,size(obj_post{IndPost}.TrajMapped, 3)) );
                ha_postlesion    =   axes('units', 'centimeters', 'nextplot', 'add', 'position', [[1+(img_size + 0.5)*length(PrePlots)+(img_size + 0.5)*(k-1) 1 img_size img_size]], 'xlim', xplotrange, 'ylim', yplotrange, 'ydir', 'reverse');
                axis off
                rng(3)
                ImagePlot       =   obj_post{IndPost}.PoseTracking.Images{IndExample(1)};
                if length(size(ImagePlot))==4
                    ImagePlot = ImagePlot(:,:, :, 1);
                end
                NewOrigin = [Coordinates_Adjustment(PostPlots(k), 2)  Coordinates_Adjustment(PostPlots(k), 1)];
                imagesc(NewOrigin(1), NewOrigin(2), ImagePlot)
                colormap gray
                TrajectoryToPlot = obj_post{IndPost}.TrajMapped(:, :, IndExample);
                for i = 1:length(IndExample)
                    iIndx = find(TrajectoryToPlot(:, 1, i)>=tIndex(1) & TrajectoryToPlot(:, 1, i)<=tIndex(2));
                    tPlot = TrajectoryToPlot(iIndx, 1, i);
                    [~, ind_cmap] = intersect(tMap, tPlot);

                    Horizontal_Coordinates = TrajectoryToPlot(iIndx, 2, i);
                    Vertical_Coordinates   =  TrajectoryToPlot(iIndx, 3, i);

                    hs = scatter(ha_postlesion, downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                        10, downsample(cmap(ind_cmap, :), 10),'+');
                    if PostPlots(k)-2==4
                        IndPost=5;
                    elseif PostPlots(k)-2==5
                        IndPost=16;
                    end
                    title(['Post lesion' num2str(IndPost)],'Color',[255, 178, 0]/255)
                end;

                axis off
            end;

            % this plots the color scalebar
            xnow =  1+(img_size + 0.5)*length(PrePlots)+(img_size + 0.5)*length(PostPlots);
            ha_colorbar = axes('units', 'centimeters', 'position', [xnow 1 0.2 3], ...
                'nextplot', 'add', ...
                'ydir', 'normal', 'YAxisLocation', 'right', ...
                'ytick', [-200:100:50], 'ylim', tIndex, 'xlim', [0 1.5], 'xtick', [], 'TickLength', [0.015 0.1]);
            ss =  scatter(ones(1, length(tMap)), tMap, 20,  cmap, 'filled', 's');
            h=ylabel('Time from maximal height (ms)','Rotation',270);
            h.Position(1)=10;
            tosavename=  fullfile(this_folder, 'Figures', 'Fig1b_ReachingTrajectories(NewPawTraj)');

            saveas(gcf, tosavename, 'epsc')
            saveas(gcf, tosavename, 'fig')
            print (gcf,'-dpdf', tosavename)
            print (gcf,'-dpng', tosavename)


        end

        % Button pushed function: PlottrajectoryandspeedButton
        function PlottrajectoryandspeedButtonPushed(app, event)
   
            clc
            disp('######### Please wait ###########')
            obj_pre = app.ProcesstrackingdataButton.UserData{1};
            obj_post = app.ProcesstrackingdataButton.UserData{2};
            
            ANM = obj_pre{1}.Subject;
            ar = 1.28 ; % apect ratio for some plots
            color_pre = {'#05BFDB', '#088395', '#0A4D68'};
            color_post = ({'#F78CA2', '#D80032',  '#3D0C11'});
            % only select a few
            pre_types = {'Pre1', 'Pre2'}; % 
            n_pre = length(obj_pre);
            post_types= {'Early', 'Late'};


            n_post = length(post_types);
            % each time we randomly sampled nselect trials from all pre-lesion trials. and
            % nselect trials from the first 50 post-lesion trials. (with repetition)
            DataOut= [];

            hf=24;
            figure(hf); clf
            set(hf, 'units', 'centimeters', 'position', [2 2 31 16], 'color', 'w', 'paperpositionmode', 'auto', 'Visible','on')
            xplotrange = [app.xrangeEditField.Value app.xrangeEditField_2.Value];
            yplotrange = [app.yrangeEditField.Value app.yrangeEditField_2.Value];
            t_onset = app.trangeEditField.Value;
            t_offset = app.trangeEditField_2.Value;
            tIndex     = [t_onset t_offset];
            tMap = (tIndex(1):tIndex(end));
            cmap = fake_parula(length(tMap));
            n_example = 7;
            % pre-lesion and post trials
            ynow = 1;
            width = 2.5;     
            height  =2.5;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            PrePlots        = linspace(1, length(obj_pre),length(obj_pre));
            PreTrajCollection = [];
            PreDistributionCollection = []; % this is the convolved distribution. 
            TrackingID = [];

            for k =1:length(PrePlots)
                n_trials = size(obj_pre{k}.TrajMapped, 3);
                PreTrajCollection = cat(3, PreTrajCollection, obj_pre{k}.TrajMapped);
                TrackingID = cat(2, TrackingID, [k*ones(1, n_trials); 1:n_trials]);
            end
 
            DataOut.PreLesion.Trajectory = PreTrajCollection;
            DataOut.PreLesion.TrackingID = TrackingID;

            early_num       =                  app.EarlyprelesiontrialsEditField.Value;
            late_num         =                   app.LateprelesiontrialsEditField.Value;
            % cut the pre-lesion trials into half 
            % select early and late number of trials randomly from these
            % two halves
            
            random_select = sort(randperm(size(PreTrajCollection, 3), early_num+late_num));

            EarlyTraj           =                   PreTrajCollection(:, :, random_select(1:early_num));
            LateTraj            =                   PreTrajCollection(:,:, random_select(end-late_num+1:end));

            EarlyTrajPre = EarlyTraj;  
            LateTrajPre = LateTraj;

            TrackingID_Early = TrackingID(:, random_select(1:early_num));
            TrackingID_Late = TrackingID(:, random_select(end-late_num+1:end));

            DataOut.PreLesion.TrajectorySelected = {EarlyTraj, LateTraj};
            DataOut.PreLesion.IDTrajectorySelected = {TrackingID_Early, TrackingID_Late};
            DataOut.PreLesion.IDTrajectorySelectedNoite = 'Trajectory selected, TrajectoryID: session, trial';

            disp('#############################################')
            disp('######### 1. Plot example frames (pre lesion) #######')
            disp('#############################################')

            xnow_start = 1;
            dy = 20;

            pre_trajectory_examples = {};
            for i =1:length(pre_types)
                switch pre_types{i}
                    case 'Pre1'
                        IndExample        =   sort(randperm(size(EarlyTraj, 3), n_example));
                        IndExampleTracking = TrackingID_Early(:, IndExample);
                        TrajectoryToPlot = EarlyTraj(:,:, IndExample);

                     case 'Pre2'
                        IndExample        =   sort(randperm(size(LateTraj, 3), n_example));
                        IndExampleTracking = TrackingID_Late(:, IndExample);
                        TrajectoryToPlot = LateTraj(:,:, IndExample);
                 end
                xnow =  xnow_start;
                pre_trajectory_examples{i} = TrajectoryToPlot;
                for j =1:length(IndExample)
                    ha_prelesion    =   axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                        'xlim', xplotrange, 'ylim', yplotrange, 'ydir', 'reverse');
                   line(xplotrange, [yplotrange(1) yplotrange(1)], 'color', color_pre{i}, 'linewidth', 4);
                   line(xplotrange, [yplotrange(2) yplotrange(2)], 'color', color_pre{i}, 'linewidth', 4);
                   line([xplotrange(1) xplotrange(1)], yplotrange, 'color', color_pre{i}, 'linewidth', 4);
                   line([xplotrange(2) xplotrange(2)], yplotrange, 'color', color_pre{i}, 'linewidth', 4);
                   xnow = xnow + width+0.2;
                    session_id = IndExampleTracking(1,j);
                    trial_id = IndExampleTracking(2, j);
                    ImagePlot = obj_pre{session_id}.PoseTracking.Images{trial_id};
                    if length(size(ImagePlot))==4
                        ImagePlot = ImagePlot(:,:, :, 1);
                    end
                    NewOrigin = obj_pre{1}.OriginCoordinates;
                    imagesc(NewOrigin(1), NewOrigin(2), ImagePlot)
                    colormap gray
                    t_Trajectory = TrajectoryToPlot(:, 1, j);                    
                    ind_notnan = ~isnan(t_Trajectory);
                    t_Trajectory = t_Trajectory(ind_notnan);
                    Horizontal_Coordinates = TrajectoryToPlot(ind_notnan, 2, j);
                    Vertical_Coordinates   =  TrajectoryToPlot(ind_notnan, 3 , j);

                    Verticle_min = min(Vertical_Coordinates);
                    y_threshold = Verticle_min + dy;
                    ind_threshold = find(Vertical_Coordinates<y_threshold, 1, 'first');
                    t_Trajectory = t_Trajectory - t_Trajectory(ind_threshold);

                    [~, ind_cmap, ind_this_traj] = intersect(tMap, t_Trajectory);
                    this_cmap = zeros(length(t_Trajectory), 3);
                    this_cmap(ind_this_traj, :) = cmap(ind_cmap, :);
                    this_cmap_sum = sum(this_cmap, 2);
                    if this_cmap_sum(1)==0
                        ind_blank = find(this_cmap_sum>0, 1, 'first');
                        this_cmap(1:ind_blank-1,:) = repmat(this_cmap(ind_blank, :), ind_blank-1, 1);
                    end
                    this_cmap_sum = sum(this_cmap, 2);
                    if this_cmap_sum(end)==0
                        ind_blank = find(this_cmap_sum==0, 1, 'first');
                        n_blank = sum(this_cmap_sum==0);
                        this_cmap(ind_blank:end,:) = repmat(this_cmap(ind_blank-1, :), n_blank, 1);
                    end

                    scatter(ha_prelesion, downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                        20, downsample(this_cmap, 10),'+', 'linewidth', 1);
                     if j ==1
                        title(sprintf('Pre: s%2.0d/tr%2.0d',session_id, trial_id))
                    else
                        title(sprintf('s%2.0d/tr%2.0d',session_id, trial_id))
                     end
                    if i==2 || j>1
                        axis off
                    end
                end
                ynow = ynow + height + 1;
            end
            % add a colorbar
            ha_colorbar = axes('units', 'centimeters', 'position', [xnow 1 0.3 height], ...
                'nextplot', 'add', ...
                'ydir', 'normal', 'YAxisLocation', 'right', ...
                'ytick', [-500:100:50], 'ylim', tIndex, 'xlim', [0 1.5], 'xtick', [], 'TickLength', [0.015 0.1]);
            ss =  scatter(ones(1, length(tMap)), tMap, 20,  cmap, 'filled', 's');
            h=ylabel('Time from maximal height (ms)','Rotation',270);
            h.Position(1) = h.Position(1)*1.3;
            xnow = xnow +3;

            %% Extract Post lesion data
            PostPlots        = linspace(1, length(obj_post),length(obj_post));
            PostTrajCollection = [];
            TrackingID = [];
            SessionsCollection = cell(1, length(PostPlots));
            for k =1:length(PostPlots)
                n_trials = size(obj_post{k}.TrajMapped, 3);
                PostTrajCollection = cat(3, PostTrajCollection, obj_post{k}.TrajMapped);
                TrackingID = cat(2, TrackingID, [k*ones(1, n_trials); 1:n_trials]);
                SessionsCollection{k} = strrep(obj_post{k}.Session(1:10), '-', '');
            end

            DataOut.PostLesion.Trajectory = PostTrajCollection;
            DataOut.PostLesion.TrackingID = TrackingID;

            early_num       =                  app.EarlypostlesiontrialsEditField.Value;
            late_num         =                   app.LatepostlesiontrialsEditField.Value;

            n_post_session_max = max(TrackingID(1,:));

            % ind where late trials are
            late_session = app.ExtensivetrainingEditField.Value;
            ind_late = find(strcmp(SessionsCollection, late_session));

            ind_early_post = find(TrackingID(1, :)<ind_late);
            ind_late_post = find(TrackingID(1, :)==ind_late);

            % we randomly select early_num trials from ind_early_post and
            % ind_late_post trials from ind_late_post

            if length(ind_early_post)>early_num
                ind_early_post_selected = ind_early_post(randperm(length(ind_early_post), early_num));
            else
                ind_early_post_selected = ind_early_post;
            end;

            if length(ind_late_post)>late_num
                ind_late_post_selected = ind_late_post(randperm(length(ind_late_post), late_num));
            else
                ind_late_post_selected = ind_late_post;
            end;


%             PostTrajCollection = PostTrajCollection(:,:,[ind_early_post_selected ind_late_post_selected]);
%             TrackingID = TrackingID(:,[ind_early_post_selected ind_late_post_selected]);

             EarlyTraj                  =                   PostTrajCollection(:, :, ind_early_post_selected);
             LateTraj                    =                   PostTrajCollection(:,:, ind_late_post_selected);

            EarlyTrajPost = EarlyTraj;
            LateTrajPost = LateTraj;
            TrackingID_Early = TrackingID(:, ind_early_post_selected);
            TrackingID_Late = TrackingID(:, ind_late_post_selected);

            DataOut.PostLesion.TrajectorySelected = {EarlyTraj, LateTraj};
            DataOut.PostLesion.IDTrajectorySelected = {TrackingID_Early, TrackingID_Late};
            DataOut.PostLesion.IDTrajectorySelectedNoite = 'Trajectory selected, TrajectoryID: session, trial';

            disp('#############################################')
            disp('######### 2. Plot example frames (pre lesion) #######')
            disp('#############################################')
            post_trajectory_examples = {};
            xnow_start = 1;
            for i =1:length(post_types)
                switch post_types{i}
                    case 'Early'
                        IndExample        =   sort(randperm(size(EarlyTraj, 3), n_example));
                        IndExampleTracking = TrackingID_Early(:, IndExample);
                        TrajectoryToPlot = EarlyTraj(:,:, IndExample);
                    case 'Late'
                        IndExample        =   sort(randperm(size(LateTraj, 3), n_example));
                        IndExampleTracking = TrackingID_Late(:, IndExample);
                        TrajectoryToPlot = LateTraj(:,:, IndExample);
                end
                xnow =  xnow_start;
                post_trajectory_examples{i} = TrajectoryToPlot;
                for j =1:length(IndExample)
                    ha_postlesion    =   axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                        'xlim', xplotrange, 'ylim', yplotrange, 'ydir', 'reverse');
                   line(xplotrange, [yplotrange(1) yplotrange(1)], 'color', color_post{i}, 'linewidth', 4);
                   line(xplotrange, [yplotrange(2) yplotrange(2)], 'color', color_post{i}, 'linewidth', 4);
                   line([xplotrange(1) xplotrange(1)], yplotrange, 'color', color_post{i}, 'linewidth', 4);
                   line([xplotrange(2) xplotrange(2)], yplotrange, 'color', color_post{i}, 'linewidth', 4);
                   xnow = xnow + width+0.2;
                    session_id = IndExampleTracking(1,j);
                    trial_id = IndExampleTracking(2, j);
                    ImagePlot = obj_post{session_id}.PoseTracking.Images{trial_id};
                    if length(size(ImagePlot))==4
                        ImagePlot = ImagePlot(:,:, :, 1);
                    end
                    NewOrigin = obj_post{1}.OriginCoordinates;
                    imagesc(NewOrigin(1), NewOrigin(2), ImagePlot)
                    colormap gray
                    t_Trajectory = TrajectoryToPlot(:, 1, j);                    
                    ind_notnan = ~isnan(t_Trajectory);
                    t_Trajectory = t_Trajectory(ind_notnan);
                    Horizontal_Coordinates = TrajectoryToPlot(ind_notnan, 2, j);
                    Vertical_Coordinates   =  TrajectoryToPlot(ind_notnan, 3 , j);
                    Verticle_min = min(Vertical_Coordinates);
                    y_threshold = Verticle_min + dy;
                    ind_threshold = find(Vertical_Coordinates<y_threshold, 1, 'first');
                    t_Trajectory = t_Trajectory - t_Trajectory(ind_threshold);
                    [~, ind_cmap, ind_this_traj] = intersect(tMap, t_Trajectory);
                    this_cmap = zeros(length(t_Trajectory), 3);
                    this_cmap(ind_this_traj, :) = cmap(ind_cmap, :);
                    this_cmap_sum = sum(this_cmap, 2);
                    if this_cmap_sum(1)==0
                        ind_blank = find(this_cmap_sum>0, 1, 'first');
                        this_cmap(1:ind_blank-1,:) = repmat(this_cmap(ind_blank, :), ind_blank-1, 1);
                    end
                    this_cmap_sum = sum(this_cmap, 2);
                    if this_cmap_sum(end)==0
                        ind_blank = find(this_cmap_sum==0, 1, 'first');
                        n_blank = sum(this_cmap_sum==0);
                        this_cmap(ind_blank:end,:) = repmat(this_cmap(ind_blank-1, :), n_blank, 1);
                    end
                    scatter(ha_postlesion, downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                        20, downsample(this_cmap, 10),'+', 'linewidth', 1);
                    if j ==1
                        title(sprintf('Post: s%2.0d/tr%2.0d',session_id, trial_id));
                    else
                        title(sprintf('s%2.0d/tr%2.0d',session_id, trial_id));
                    end
                    if i==2 || j>1
                        axis off
                    end
                end
                ynow = ynow + height + 1;
            end
            xnow = xnow +1.5;       

           %% Plot all traces
            xnow_trace = xnow;
            width_kinematics = 4;
            height_kinematics = 6;
            x_color = '#0802A3';
            y_color = '#C70039';
            tLimit = [-500 100];
            x_range_summary = [app.xrangeEditField.Value app.xrangeEditField_2.Value];
            y_range_summary = [app.yrangeEditField.Value app.yrangeEditField_2.Value];
            xlabel('t (ms)')  
            shift = 200;
            ynow = 1;
            dy = 20;
            % Plot pre-lesion traces
            for i = 1:length(pre_types)
                ha_pre_final = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow 1 width_kinematics height_kinematics], ...
                    'xlim', tLimit, 'ylim', [0 1200], 'ydir', 'reverse', 'ticklength', [0.025 0.5]);
                line([0 0], [200 1000], 'color', 'k', 'linestyle', ':','linewidth', 1)
                line([tLimit(1) tLimit(1)], [1000 1200], 'color','k', 'linewidth', 2)
                line([tLimit(1) tLimit(1)+100], [1200 1200], 'color','k', 'linewidth', 2)
                axis off
                xnow = xnow + width_kinematics+1;
                switch pre_types{i}
                    case 'Pre1'
                        TrajData =  EarlyTrajPre;
                    case 'Pre2'
                        TrajData =  LateTrajPre;
                end
                 n_trials = size(TrajData, 3);
                for j =1:n_trials
                    t_plot = TrajData(:, 1, j);
                    x_plot = TrajData(:, 2, j);
                    y_plot = TrajData(:, 3, j);
                    t_Index = find(t_plot>=tLimit(1) & t_plot<=tLimit(2));
                    t_extract = t_plot(t_Index);
                    x_extract = x_plot(t_Index);
                    y_extract = y_plot(t_Index);
                    y_min = min(y_extract);
                    y_threshold = y_min + dy;
                    ind_threshold = find(y_extract<y_threshold, 1, 'first');                    
                    t_extract_new = t_extract - t_extract(ind_threshold);
                    [speed_extract, distance_extract] = cal_speed(app, t_extract, x_extract, y_extract);
                    plot(ha_pre_final, t_extract_new, x_extract, 'color', x_color)
                    plot(ha_pre_final, t_extract_new,  y_extract+shift, 'color', y_color)

                    if j ==1
                        x_level = mean(x_extract(t_extract>0),'omitnan');
                        y_level = mean(y_extract(t_extract>0),'omitnan');
                        text(110, x_level, 'x', 'color', x_color,'fontname','dejavu sans', 'fontweight','bold');
                        text(100, y_level+shift, 'y', 'color', y_color,'fontname','dejavu sans', 'fontweight','bold');
                    end
                    title(sprintf('Pre%2.0d',i),'color', color_pre{i}, ...
                        'fontname', 'dejavu sans', 'fontweight', 'bold', 'fontsize', 9)
                end
            end
            ynow = ynow+height_kinematics+1.5;
            xnow = xnow_trace;

            % Plot post-lesion traces
            for i = 1:length(post_types)
                ha_post_final = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width_kinematics height_kinematics], ...
                    'xlim', tLimit, 'ylim', [0 1200], 'ydir', 'reverse', 'ticklength', [0.025 0.5]);
                line([0 0], [200 1000], 'color', 'k', 'linestyle', ':','linewidth', 1)
                line([tLimit(1) tLimit(1)], [1000 1200], 'color','k', 'linewidth', 2)
                line([tLimit(1) tLimit(1)+100], [1200 1200], 'color','k', 'linewidth', 2)
                axis off
                xnow = xnow + width_kinematics+1;
                switch post_types{i}
                    case 'Early'
                        TrajData =  EarlyTrajPost;
                    case 'Late'
                        TrajData = LateTrajPost;
                end
                n_trials = size(TrajData, 3);

                for j =1:n_trials
                    t_plot = TrajData(:, 1, j);
                    x_plot = TrajData(:, 2, j);
                    y_plot = TrajData(:, 3, j);
                    t_Index = find(t_plot>=tLimit(1) & t_plot<=tLimit(2));
                    t_extract = t_plot(t_Index);
                    x_extract = x_plot(t_Index);
                    y_extract = y_plot(t_Index);
                    y_min = min(y_extract);
                    y_threshold = y_min + dy;
                    ind_threshold = find(y_extract<y_threshold, 1, 'first');
                    t_extract_new = t_extract - t_extract(ind_threshold);
                    [speed_extract, distance_extract] = cal_speed(app, t_extract, x_extract, y_extract);
                    plot(ha_post_final, t_extract_new, x_extract, 'color', x_color)
                    plot(ha_post_final, t_extract_new,  y_extract+shift, 'color', y_color)

                    if j ==1
                        x_level = mean(x_extract(t_extract>0),'omitnan');
                        y_level = mean(y_extract(t_extract>0),'omitnan');
                        text(110, x_level, 'x', 'color', x_color,'fontname','dejavu sans', 'fontweight','bold');
                        text(110, y_level+shift, 'y', 'color', y_color,'fontname','dejavu sans', 'fontweight','bold');
                    end
                    title(sprintf('Post %s', post_types{i}),'color', color_post{i}, ...
                        'fontname', 'dejavu sans', 'fontweight', 'bold', 'fontsize', 9)
                end
            end

            % resize the figure
            scale = 0.85;
            set(hf, 'Units', 'normalized');
            pos = get(hf, 'Position');
            set(hf, 'position', pos*scale);
            everything = get(hf, 'Children');

            for i =1:length(everything)
                set(everything(i), 'Units', 'Normalized')
                pos = get(everything(i), 'Position');
                set(everything(i), 'Position', pos*scale);
            end

            set(hf, 'PaperOrientation', 'landscape')

            %% extract speed and perform averaging (with reference to the peak)
            % to get an intuition, plot a bunch of pre reaches
            hf0=25;
            figure(hf0); clf
            set(hf0, 'units', 'centimeters', 'position', [2 2 30 16], 'color', 'w', 'paperpositionmode', 'auto', 'Visible','on')
            % group analysis goes here.
            tRange = tIndex;  % we extract trajectory -200 to 50 ms with respect the peak time
            tmap = tMap;
            x_aligned = [];
            y_aligned = [];
            speed = [];
            width = 3;
            xnow = 1.5;
            ynow = 1;
            % check the x and y range you want to plot
            x_range_summary = [app.xrangeEditField.Value app.xrangeEditField_2.Value];
            y_range_summary = [app.yrangeEditField.Value app.yrangeEditField_2.Value];
            height = width*diff(y_range_summary)/diff(x_range_summary);

            ha_pre_final = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                'xlim', x_range_summary, 'ylim', y_range_summary, 'ydir', 'reverse', 'ticklength', [0.025 0.5]);
            ynow = 1+height+1.25;
            xlabel('x')
            ylabel('y')

            % distance measured in trajectory
            ha_pre_distance = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'xlim', [tRange(1)-10 tRange(2)+10], 'ylim', [-50 600], 'ticklength', [0.025 0.5],'ydir','reverse');
            ynow = ynow +width/ar + 1.25;
            xlabel('Time from peak (ms)')
            ylabel('Distance from the lever')

            % speed measured in trajectory
            ha_pre_speed = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'xlim', [tRange(1)-10 tRange(2)+10], 'ylim', [0 10], 'ticklength', [0.025 0.5]);
            ynow = ynow +width/ar + 1.25;
            xlabel('Time from peak (ms)')
            ylabel('Speed (pixels/ms)')

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%% Pre Lesion trials %%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            disp('#########################################################')
            disp('######### 3. Extract and comput trajectory and speed (pre-lesion)  #######')
            disp('#########################################################')
            x_accum = []; % collect all x values from all trajectory to build a PCA space
            y_accum = [];
            t_accum = [];
            trial_index = [];
            session_index = [];
            xpre_dynamics                   = cell(3, length(pre_types));
            ypre_dynamics                   = cell(3, length(pre_types));
            distance_pre_dynamics     = cell(3, length(pre_types));
            speed_pre_dynamics        = cell(3, length(pre_types));

            % Plot variance here
            for i = 1:length(pre_types)
                switch pre_types{i}
                    case 'Pre1'
                        TrajData = EarlyTrajPre;
                    case 'Pre2'
                        TrajData = LateTrajPre;
                end
                n_trials = size(TrajData, 3);
                t_Index = find(obj_pre{1}.tMap>=tRange(1) & obj_pre{1}.tMap<=tRange(2));
                x_aligned = [];
                y_aligned = [];
                speed = [];
                distance = [];
                for j =1:n_trials
                    t_plot = TrajData(:, 1, j);
                    x_plot = TrajData(:, 2, j);
                    y_plot = TrajData(:, 3, j);
 
                    t_extract = t_plot(t_Index);
                    x_extract = x_plot(t_Index);
                    y_extract = y_plot(t_Index);
                    y_min = min(y_extract);
                    y_threshold = y_min + dy;
                    ind_threshold = find(y_extract<y_threshold, 1, 'first');
                    t_extract = t_extract - t_extract(ind_threshold);

                    % downsample
                    t_extract = downsample(t_extract, 10);
                    x_extract = downsample(x_extract, 10);
                    y_extract = downsample(y_extract, 10);

                    [speed_extract, distance_extract] = cal_speed(app, t_extract, x_extract, y_extract);
                    x_accum = [x_accum; x_extract(~isnan(x_extract))];
                    y_accum = [ y_accum; y_extract(~isnan(y_extract))];
                    t_accum = [t_accum; t_extract(~isnan(x_extract))];
                    trial_index = [trial_index; j*ones(sum(~isnan(x_extract)), 1)];
                    session_index = [session_index; i*ones(sum(~isnan(x_extract)), 1)];
                    x_aligned = [x_aligned x_extract];
                    y_aligned = [y_aligned y_extract];
                    speed = [speed speed_extract];
                    distance = [distance distance_extract];
                    x_mean = zeros(1, length(tmap));
                    x_ci = zeros(2, length(tmap));
                    x_sd = zeros(1, length(tmap));
                    y_mean = zeros(1, length(tmap));
                    y_ci = zeros(2, length(tmap));
                    y_sd = zeros(1, length(tmap));
                end

                n_data = size(x_aligned, 1);
                x_mean = zeros(1, n_data);
                x_ci =    zeros(2, n_data);
                x_var =    zeros(1, n_data);
                x_var_ci =    zeros(2, n_data);
                y_mean = zeros(1, n_data);
                y_ci = zeros(2, n_data);
                y_var = zeros(1, n_data);
                y_var_ci =    zeros(2, n_data);

                xpre_dynamics{1, i} = x_aligned;
                ypre_dynamics{1, i} = y_aligned;
                speed_pre_dynamics{1, i} = speed;

                for it = 1:n_data
                    it_x = x_aligned(it, :);
                    it_x = it_x(~isnan(it_x));
                    x_mean(it) = mean(it_x);
                    x_ci(:, it) = bootci(1000, @mean, it_x);
                    x_var(it) = var(it_x);
                    x_var_ci(:, it) = bootci(1000, @var, it_x);
                    it_y = y_aligned(it, :);
                    it_y = it_y(~isnan(it_y));
                    y_mean(it) = mean(it_y);
                    y_ci(:, it) = bootci(1000, @mean, it_y);
                    y_var(it) = var(it_y);
                    y_var_ci(:, it) = bootci(1000, @var, it_y);

                end

                xpre_dynamics{2, i} = [x_mean; x_ci];
                ypre_dynamics{2, i} = [y_mean; y_ci];
                xpre_dynamics{3, i} = [x_var; x_var_ci];
                ypre_dynamics{3, i} = [y_var; y_var_ci];

                distance_mean = mean(distance, 2, 'omitnan');
                distance_ci = nan*ones(2, length(distance_mean));

                speed_mean = mean(speed, 2, 'omitnan');
                speed_ci = nan*ones(2, length(speed_mean));

                for k =1:length(distance_mean)
                    if sum(~isnan(distance(k, :)))>16
                        k_distance_ci       =  bootci(1000, @(x)mean(x, 'omitnan'), distance(k, :));
                        distance_ci(:, k) = k_distance_ci;
                        k_speed_ci       =  bootci(1000, @(x)mean(x, 'omitnan'), speed(k, :));
                       speed_ci(:, k) = k_speed_ci;
                    end
                end

                distance_pre_dynamics{1, i} = distance;
                distance_pre_dynamics{2, i} = distance_mean;
                distance_pre_dynamics{3, i} = distance_ci;
                speed_pre_dynamics{1, i} = speed;
                speed_pre_dynamics{2, i} = speed_mean;
                speed_pre_dynamics{3, i} = speed_ci;

                ind = (1:10:length(tmap));
                scatter(ha_pre_final, x_mean(ind), y_mean(ind),10, 'MarkerEdgeColor', color_post{i}, ...
                    'Marker', '.', 'MarkerFaceAlpha', 0,'linewidth', 1);
                line(ha_pre_final, [x_mean(ind); x_mean(ind)], y_ci(:, ind), 'color',color_pre{i});
                line(ha_pre_final, x_ci(:, ind), [y_mean(ind); y_mean(ind)], 'color',color_pre{i});
                line(ha_pre_distance, [tMap(ind); tMap(ind)], distance_ci(:, ind), 'color', color_pre{i});
                plot(ha_pre_distance, tMap(ind), distance_mean(ind),  'marker', '.', 'linestyle', '-', 'color', color_pre{i}, ...
                    'linewidth', 1, 'markersize', 4);
                line(ha_pre_speed, [tMap(ind); tMap(ind)], speed_ci(:, ind), 'color', color_pre{i});
                plot(ha_pre_speed, tMap(ind), speed_mean(ind),  'marker', '.', 'linestyle', '-', 'color', color_pre{i}, ...
                    'linewidth', 1, 'markersize', 4);

            end

            disp('#########################################################')
            disp('######### 4. Compute principal components from pre-lesion data  #######')
            disp('#########################################################')
            % analyze the co-variance of x and y
            coeff = pca([x_accum y_accum]);
            ha_pre_pca = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'xlim', [tRange(1)-10 tRange(2)+10], 'ylim', [-200 500], 'ticklength', [0.025 0.5]);
            ynow=ynow+width/ar+1.25;
            xlabel('Time from peak (ms)')
            ylabel('PC1')

            pc = cell(3, length(pre_types));
            for i =1:length(pre_types)
                pc_i =[];
                n_trials = size(xpre_dynamics{1, i}, 2);
                for j =1:n_trials
                    x_ij = xpre_dynamics{1, i}(:, j);
                    x_ij_nnan = x_ij(~isnan(x_ij));
                    y_ij = ypre_dynamics{1, i}(:, j);
                    y_ij_nnan = y_ij(~isnan(y_ij));
                    x_ij = x_ij - mean(x_ij_nnan);
                    y_ij =y_ij - mean(y_ij_nnan);
                    pc_ij = [x_ij y_ij]*coeff;
                    disp(j)
                    disp(size(pc_ij))
                    pc_i = [pc_i pc_ij(:, 1)];
                end
                pc{1, i} = pc_i;
                % compute mean and ci
                pci_mean = mean(pc_i, 2, 'omitnan');
                pci_ci = nan*ones(2, length(pc_i));
                for k =1:length(pci_mean)
                    if sum(~isnan(pc_i(k, :)))>16
                        k_pci_ci       =  bootci(1000, @(x)mean(x, 'omitnan'), pc_i(k, :));
                        pci_ci(:, k) = k_pci_ci;
                    end
                end
                pc{2, i} = pci_mean;
                pc{3, i}= pci_ci;
                ind = (1:10:length(tmap));
                line([tMap(ind); tMap(ind)], pci_ci(:, ind), 'color', color_pre{i});
                plot(ha_pre_pca, tMap(ind), pci_mean(ind), 'marker', '.', 'linestyle', '-', 'color', color_pre{i}, 'linewidth', 1, 'markersize', 4)
            end

            DataOut.PreLesion.DynamicsNote = {'tMap', 'x', 'y', 'distance', 'speed','pca'};
            DataOut.PreLesion.Dynamics = {tMap, xpre_dynamics, ypre_dynamics, distance_pre_dynamics, speed_pre_dynamics, pc};
            DataOut.PreLesion.PCACoeff = coeff;

            % ######################################################
            % ######################################################
            ynow = 1;
            xnow = xnow + width + 1.5;
            % Here goes the post data
            % distance measured in trajectory      

            ha_post_final = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                'xlim', x_range_summary, 'ylim', y_range_summary, 'ydir', 'reverse', 'ticklength', [0.025 0.5]);
            ynow = 1+height+1.25;

            ha_post_distance = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'xlim', [tRange(1)-10 tRange(2)+10], 'ylim', [-50 600], 'ticklength', [0.025 0.5],'ydir','reverse');
            ynow = ynow +width/ar+ 1.25;

            xlabel('Time from peak (ms)')
            ylabel('Distance from the lever')

            % speed measured in trajectory
            ha_post_speed = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'xlim', [tRange(1)-10 tRange(2)+10], 'ylim', [0 10], 'ticklength', [0.025 0.5]);
            ynow = ynow +width/ar+ 1.25;
            xlabel('Time from peak (ms)')
            ylabel('Speed (pixels/ms)')

            disp('#########################################################')
            disp('######### 5. Extract and compute trajectory and speed (post-lesion)  #######')
            disp('#########################################################')
            x_accum = []; % collect all x values from all trajectory to build a PCA space
            y_accum = [];
            t_accum = [];
            trial_index = [];
            session_index = [];
            xpost_dynamics                  =    cell(3, length(post_types));
            ypost_dynamics                  =    cell(3, length(post_types));
            distance_post_dynamics   =   cell(3, length(post_types));
            speed_post_dynamics        = cell(3, length(pre_types));
            % Plot variance here
            for i = 1:length(post_types)
                switch post_types{i}
                    case 'Early'
                        TrajData = EarlyTrajPost;
                    case 'Late'
                        TrajData = LateTrajPost;
                end

                n_trials = size(TrajData, 3);
                t_Index = find(obj_post{1}.tMap>=tRange(1) & obj_post{1}.tMap<=tRange(2));
                x_aligned = [];
                y_aligned = [];
                speed = [];
                distance = [];

                for j =1:n_trials
                    t_plot = TrajData(:, 1, j);
                    x_plot = TrajData(:, 2, j);
                    y_plot = TrajData(:, 3, j);
                    t_extract = t_plot(t_Index);
                    x_extract = x_plot(t_Index);
                    y_extract = y_plot(t_Index);
                    [speed_extract, distance_extract] = cal_speed(app, t_extract, x_extract, y_extract);
                    x_accum = [x_accum; x_extract(~isnan(x_extract))];
                    y_accum = [ y_accum; y_extract(~isnan(y_extract))];
                    t_accum = [t_accum; t_extract(~isnan(x_extract))];
                    trial_index = [trial_index; j*ones(sum(~isnan(x_extract)), 1)];
                    session_index = [session_index; i*ones(sum(~isnan(x_extract)), 1)];
                    x_aligned = [x_aligned x_extract];
                    y_aligned = [y_aligned y_extract];
                    speed = [speed speed_extract];
                    distance = [distance distance_extract];
                    x_mean = zeros(1, length(tmap));
                    x_ci = zeros(2, length(tmap));
                    x_sd = zeros(1, length(tmap));
                    y_mean = zeros(1, length(tmap));
                    y_ci = zeros(2, length(tmap));
                    y_sd = zeros(1, length(tmap));
                end

                xpost_dynamics{1, i} = x_aligned;
                ypost_dynamics{1, i} = y_aligned;

                for it = 1:length(tmap)
                    x_mean(it) = mean(x_aligned(it, :), 'omitnan');
                    x_ci(:, it) = bootci(1000, @nanmean, x_aligned(it, :));
                    x_sd(it) = std(x_aligned(it, :), 'omitnan');
                    y_mean(it) = mean(y_aligned(it, :), 'omitnan');
                    y_ci(:, it) = bootci(1000, @nanmean, y_aligned(it, :));
                    y_sd(it) = std(y_aligned(it, :), 'omitnan');
                end

                xpost_dynamics{2, i} = x_mean;
                ypost_dynamics{2, i} = y_mean;
                xpost_dynamics{3, i} = x_ci;
                ypost_dynamics{3, i} = y_ci;
                distance_mean = mean(distance, 2, 'omitnan');
                distance_ci = nan*ones(2, length(distance_mean));
                speed_mean = mean(speed, 2, 'omitnan');
                speed_ci = nan*ones(2, length(speed_mean));

                for k =1:length(distance_mean)
                    if sum(~isnan(distance(k, :)))>16
                        k_distance_ci       =  bootci(1000, @(x)mean(x, 'omitnan'), distance(k, :));
                        distance_ci(:, k) = k_distance_ci;
                                                k_speed_ci       =  bootci(1000, @(x)mean(x, 'omitnan'), speed(k, :));
                       speed_ci(:, k) = k_speed_ci;
                    end
                end

                distance_post_dynamics{1, i} = distance;
                distance_post_dynamics{2, i} = distance_mean;
                distance_post_dynamics{3, i} = distance_ci;
              
                speed_post_dynamics{1, i} = speed;
                speed_post_dynamics{2, i} = speed_mean;
                speed_post_dynamics{3, i} = speed_ci;

                ind = (1:10:length(tmap));
                scatter(ha_post_final, x_mean(ind), y_mean(ind),10, 'MarkerEdgeColor', color_post{i}, ...
                    'Marker', '.', 'MarkerFaceAlpha', 0,'linewidth', 1);
                line(ha_post_final, [x_mean(ind); x_mean(ind)], y_ci(:, ind), 'color',color_post{i});
                line(ha_post_final, x_ci(:, ind), [y_mean(ind); y_mean(ind)], 'color',color_post{i});
                line(ha_post_distance, [tMap(ind); tMap(ind)], distance_ci(:, ind), 'color', color_post{i});
                plot(ha_post_distance, tMap(ind), distance_mean(ind),  'marker', '.', 'linestyle', '-', 'color', color_post{i}, 'linewidth', 1, 'markersize', 4);

                scatter(ha_pre_final, x_mean(ind), y_mean(ind),10, 'MarkerEdgeColor', color_post{i}, ...
                    'Marker', '.', 'MarkerFaceAlpha', 0,'linewidth', 1);
                line(ha_pre_final, [x_mean(ind); x_mean(ind)], y_ci(:, ind), 'color',color_post{i});
                line(ha_pre_final, x_ci(:, ind), [y_mean(ind); y_mean(ind)], 'color',color_post{i});

                line(ha_pre_distance, [tMap(ind); tMap(ind)], distance_ci(:, ind), 'color', color_post{i});
                plot(ha_pre_distance, tMap(ind), distance_mean(ind),  'marker', '.', 'linestyle', '-', 'color', color_post{i}, ...
                    'linewidth', 1, 'markersize', 4);

                line(ha_post_speed, [tMap(ind); tMap(ind)], speed_ci(:, ind), 'color', color_post{i});
                plot(ha_post_speed, tMap(ind), speed_mean(ind),  'marker', '.', 'linestyle', '-', 'color', color_post{i}, ...
                    'linewidth', 1, 'markersize', 4);

                line(ha_pre_speed, [tMap(ind); tMap(ind)], speed_ci(:, ind), 'color', color_post{i});
                plot(ha_pre_speed, tMap(ind), speed_mean(ind),  'marker', '.', 'linestyle', '-', 'color', color_post{i}, ...
                    'linewidth', 1, 'markersize', 4);

            end

            % analyze the co-variance of x and y   
            ha_post_pca = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'xlim', [tRange(1)-10 tRange(2)+10], 'ylim', [-200 500], 'ticklength', [0.025 0.5]);
            ynow=ynow+width/ar+1.25;
            xlabel('Time from peak (ms)')
            ylabel('PC1')
            pc= cell(3, length(post_types));

            for i =1:length(post_types)
                pc_i =[];
                n_trials = size(xpost_dynamics{1, i}, 2);
                for j =1:n_trials
                    x_ij = xpost_dynamics{1, i}(:, j);
                    x_ij_nnan = x_ij(~isnan(x_ij));
                    y_ij = ypost_dynamics{1, i}(:, j);
                    y_ij_nnan = y_ij(~isnan(y_ij));
                    x_ij = x_ij - mean(x_ij_nnan);
                    y_ij =y_ij - mean(y_ij_nnan);
                    pc_ij = [x_ij y_ij]*coeff;
                    pc_i = [pc_i pc_ij(:, 1)];
                end
                pc{1, i} = pc_i;
                % compute mean and ci
                pci_mean = mean(pc_i, 2, 'omitnan');
                pci_ci = nan*ones(2, length(pc_i));
                for k =1:length(pci_mean)
                    if sum(~isnan(pc_i(k, :)))>16
                        k_pci_ci       =  bootci(1000, @(x)mean(x, 'omitnan'), pc_i(k, :));
                        pci_ci(:, k) = k_pci_ci;
                    end
                end
                pc{2, i} = pci_mean;
                pc{3, i}= pci_ci;
                ind = (1:10:length(tmap));
                line([tMap(ind); tMap(ind)], pci_ci(:, ind), 'color', color_post{i});
                plot(ha_post_pca, tMap(ind), pci_mean(ind), 'marker', '.', 'linestyle', '-', 'color', color_post{i}, 'linewidth', 1, 'markersize', 4)
                plot(ha_pre_pca, tMap(ind), pci_mean(ind), 'marker', '.', 'linestyle', '-', 'color', color_post{i}, 'linewidth', 1, 'markersize', 4)

            end

            uicontrol('Style','text','Units','normalized','Position',[0.7 0.95 0.3 0.04],...
                'string', [ANM ' (today: ' date ')'], ...
                'FontName','Dejavu Sans', 'fontweight', 'bold','fontsize', 10,'BackgroundColor',[1 1 1],...
                'HorizontalAlignment','Left');

            DataOut.PostLesion.DynamicsNote = {'tMap', 'x', 'y', 'distance', 'speed','pca', 'pca_mean', 'pca'};
            DataOut.PostLesion.Dynamics = {tMap, xpost_dynamics, ypost_dynamics, distance_post_dynamics, speed_post_dynamics, pc};
            DataOut.PostLesion.PCACoeff = coeff;

            DataOut.ANM = ANM;
            aGoodName = ['TrajectoryDataOut_' ANM '.mat'];
            save(aGoodName, 'DataOut')
            app.PlottrajectoryandspeedButton.UserData = DataOut;

            tosavename=  fullfile(pwd, 'Figures', 'Fig2_TrajectoryExamples');
            saveas(hf, tosavename, 'epsc')
            saveas(hf, [tosavename], 'fig')
            print (hf,'-dpdf', tosavename)
            print (hf,'-dpng', tosavename)

            tosavename=  fullfile(pwd, 'Figures', 'Fig2_TrajectoryDistanceSpeedPCA');
            saveas(hf0, tosavename, 'epsc')
            saveas(hf0, [tosavename], 'fig')
            print (hf0,'-dpdf', tosavename)
            print (hf0,'-dpng', tosavename)


            disp('#########################################################')
            disp(['6.  "Fig2_TrajectoryDistanceSpeedPCA" saved to /Figures'])
            disp('#########################################################')


        end

        % Button pushed function: SimilarityanalysisButton
        function SimilarityanalysisButtonPushed(app, event)

            obj_pre = app.ProcesstrackingdataButton.UserData{1};
            obj_post = app.ProcesstrackingdataButton.UserData{2};
            ANM = obj_pre{1}.Subject;
            DataOut = app.PlottrajectoryandspeedButton.UserData;
            t_onset = app.trangeEditField.Value;
            t_offset = app.trangeEditField_2.Value;
            tIndex     = [t_onset t_offset];
            markersize = 15;
            markeralpha= 0.75;
            n_early_post = app.EarlypostlesiontrialsEditField.Value;
            n_late_post = app.LatepostlesiontrialsEditField.Value;

            color_pre = {'#05BFDB', '#088395', '#0A4D68'};
            color_post = ({'#F78CA2', '#D80032',  '#3D0C11'});

            clc
            disp('#########################################################')
            disp(['###############1. Kernelize the trajectory data################'])
            disp('#########################################################')
           
            hf=23; 
            figure(hf);clf(hf)
            ha = axes('nextplot', 'add','ydir','reverse');
            % Step 1. Perform a gaussian kernel filtering of the distribution data
            % check how data look like in 3d space
            trj_pre = DataOut.PreLesion.Trajectory; % 3001           3         283
            nsample = size(DataOut.PreLesion.Trajectory, 3); % let's just check 25 trials
            session_id_pre = DataOut.PreLesion.TrackingID(1, :);
            session_id_pre(1:floor(0.5*nsample))=1;
            session_id_pre(floor(0.5*nsample)+1:end)=2;
            trj_pre_ = trj_pre;

            rng(23);
%             trj_pre_ = trj_pre(:,:,randperm(size(trj_pre, 3),nsample));
            colors = fake_parula(nsample);
            time_binsize = app.timeresolutionmsEditField.Value;            
            t_range = (tIndex(1):time_binsize:tIndex(end));
            x_range = [app.xrangeEditField.Value app.xrangeEditField_2.Value];
            y_range = [app.yrangeEditField.Value app.yrangeEditField_2.Value];
            spatial_binsize = app.SpatialKernelwidthEditField.Value;            
            x_edges = [app.xrangeEditField.Value: spatial_binsize: app.xrangeEditField_2.Value];
            y_edges = [app.yrangeEditField.Value: spatial_binsize: app.yrangeEditField_2.Value];
            psf = fspecial('gaussian', [spatial_binsize spatial_binsize], 1); % create a 2d gaussian kernel
            mat3d = zeros(length(x_edges)-1, length(y_edges)-1, length(t_range)-1, nsample);            
            mat2d = zeros(length(x_edges)-1, length(y_edges)-1, nsample);
            mat_high_d_spatial =[];
            mat_high_d_spatiotemporal =[];
            im ={};
            for i =1:nsample
                t_data = trj_pre_(:, 1, i);
                x_data = trj_pre_(:, 2, i);
                y_data = trj_pre_(:, 3, i);
                mat_traj = zeros(length(x_edges)-1, length(y_edges)-1, length(t_range)-1);
                for j =1:length(t_range)-1
                    indj = find(t_data>=t_range(j) & t_data<t_range(j+1));
                    if ~isempty(indj)
                        x_data_ = x_data(indj);
                        y_data_ = y_data(indj);
                        ndist2=histcounts2(x_data_, y_data_, x_edges, y_edges);
                        ndist2c = conv2(ndist2, psf, 'same');
                        mat_traj(:,:,j) = ndist2c;
                        if rem(i, 20) ==0
                            cla(ha)
                            imagesc(ndist2c')
                            title(sprintf('t=%2.2f', t_range(j)))
                            colormap(winter)
                            pause(0.01)
                        end
                        %%% if you want to make a gif %%%%%%%%%%% %%%%%%%%%%%
                        %                         frame = getframe(gcf);
                        %                         im =[im frame2im(frame)];
                        %%% if you want to make a gif %%%%%%%%%%% %%%%%%%%%%%
                    end
                end
                mat3d(:,:,:,i) = mat_traj;
                mat_high_d_spatiotemporal = [mat_high_d_spatiotemporal mat_traj(:)];

                mat2d(:,:,i) = sum(mat_traj, 3);
                mat_high_d_spatial = [mat_high_d_spatial reshape(mat2d(:,:,i), [], 1)];

                %%% if you want to make a gif %%%%%%%%%%% %%%%%%%%%%%
%                 filename = "testAnimated.gif"; % Specify the output file name
%                 for idx = 1:length(im)
%                     [A,map] = rgb2ind(im{idx},256);
%                     if idx == 1
%                         imwrite(A,map,filename,"gif","LoopCount",Inf,"DelayTime",1);
%                     else
%                         imwrite(A,map,filename,"gif","WriteMode","append","DelayTime",1);
%                     end
%                 end
                %%%%%%%%%%% %%%%%%%%%%% %%%%%%%%%%%
            end

            mat2d_pre = mat2d;
            mat2davg_pre = mean(mat2d, 3);
            mat_high_d_spatial = mat_high_d_spatial'; % rows: samples, columns: features
            mat_high_d_spatiotemporal = mat_high_d_spatiotemporal';

            figure; subplot(1, 2, 1)
            imagesc(mat_high_d_spatiotemporal)
            subplot(1, 2, 2)
            imagesc(mat_high_d_spatial)
            % mat3d now has all the trials in 3d. the dimension is  30(x)
            % 20(y)    12(time)    25(samples)

            % This is the data:   mat_high_d           6912x25                     1382400  double
            % Perform
            % PCA analysis
            disp('#########################################################')
            disp('###############2. PCA analysis #############################')
            disp('#########################################################')

            [PCA_st, score_st, ~, ~, ~, mu_st] = pca(mat_high_d_spatiotemporal);
            [PCA_s, score_s, ~, ~, ~, mu_s] = pca(mat_high_d_spatial);
            close all;

            hfd=25;
            figure(hfd); clf
            set(hfd, 'units', 'centimeters', 'position', [5 5 20 22], 'color', 'w', 'paperpositionmode', 'auto', 'Visible','on')
            precolor = color_pre{3};
            postcolor = color_post{3};
            % Plot trajectories in 3D
            % Plot a large number of examples in 3d
            xnow = 13;
            ynow = 2;
            height = 5;
            width = 5;

            ha_3d = axes('units', 'centimeters', 'nextplot', 'add', 'ydir','reverse', ...
                'zlim', tIndex, 'position', [xnow ynow width height], ...
                'ticklength', [0.02 0.02]);
            ynow = ynow +height+1;
            grid on
            view(ha_3d,140,30)
            xlabel('Horizontal position')
            ylabel('Vertical position')
            zlabel('Time (ms)')

            ha_2d = axes('units', 'centimeters', 'nextplot', 'add', 'ydir','reverse', 'position', [xnow ynow width height], ...
                'ticklength', [0.02 0.02]);
            grid on
            xlabel('Horizontal position')
            ylabel('Vertical position')

            for i =1:nsample
                if i <0.5*nsample
                    icolor = color_pre{1};
                else
                    icolor = color_pre{2};
                end
                t_data = trj_pre_(:, 1, i);
                indplot = find(t_data>= t_onset& t_data<=t_offset);
                t_data = t_data(indplot);
                x_data = trj_pre_(indplot, 2, i);
                y_data = trj_pre_(indplot, 3, i);
                plot3(ha_3d, downsample(x_data, 10), downsample(y_data, 10),downsample(t_data, 10), 'color', icolor);
                plot(ha_2d, downsample(x_data,10), downsample(y_data,10), 'color',icolor);
            end

            xnow = 2;
            ynow = 2;
            ar = 1;
            width = 4;
            % Plot random examples
            nexample = 6;
            rng(11)
            ind_example = randperm(nsample, nexample);
            ha1 = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                 'ticklength', [0.025 0.5]);
            xnow = xnow + width + 1.5;
            usession_id_pre = unique(session_id_pre);

            for i =1:nsample
                if i <0.5*nsample
                    icolor = color_pre{1};
                else
                    icolor = color_pre{2};
                end
                scatter(score_st(i, 1), score_st(i, 2), ...
                    'filled', 'o', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
            end

            text(ha1, score_st(ind_example, 1), score_st(ind_example, 2), num2cell(1:nexample), ...
                'color', precolor,'fontweight','bold');

            title('PCA (spatiotemporal)')
            xlabel('PC1')
            ylabel('PC2')

            ha2 = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'ticklength', [0.025 0.5]);
            xnow = xnow + width + 1.5;

            for i =1:nsample
                if i <0.5*nsample
                    icolor = color_pre{1};
                else
                    icolor = color_pre{2};
                end
                scatter(score_s(i, 1), score_s(i, 2), ...
                    'filled', 'o', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
            end
            title('PCA (spatial)')
            xlabel('PC1')
            ylabel('PC2')
%             scatter(ha2, score_s(ind_example, 1), score_s(ind_example, 2), ...
%                 'filled', '*', 'MarkerFaceColor', 'k','MarkerEdgeColor', 'r',  'SizeData',30, 'MarkerFaceAlpha', 0.5);
            text(ha2, score_s(ind_example, 1), score_s(ind_example, 2), num2cell(1:nexample), 'color', precolor ...
                ,'fontweight','bold');

            % t-SNE analysis
            yst_sne = tsne(mat_high_d_spatiotemporal);
            ys_sne = tsne(mat_high_d_spatial);
            xnow = 2;
            ynow = 7.5;
            ar = 1;
            width = 4;

            ha3 = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'ticklength', [0.025 0.5]);
            xnow = xnow + width + 1.5;
            usession_id_pre = unique(session_id_pre);
            for i =1:nsample
                if i <0.5*nsample
                    icolor = color_pre{1};
                else
                    icolor = color_pre{2};
                end
                scatter(yst_sne(i, 1), yst_sne(i, 2), ...
                    'filled', 'o', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
            end

%             scatter(ha3, yst_sne(ind_example, 1), yst_sne(ind_example, 2), ...
%                 'filled', '*', 'MarkerFaceColor', 'k', 'MarkerEdgeColor', 'r', 'SizeData',30, 'MarkerFaceAlpha', 0.5);
            text(ha3, yst_sne(ind_example, 1), yst_sne(ind_example, 2), num2cell(1:nexample), 'color',precolor, 'fontweight','bold');
            title('t-SNE (spatiotemporal)')
            xlabel('y1')
            ylabel('y2')

            ha4 = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width width/ar], ...
                'ticklength', [0.025 0.5]);
            ynow = ynow + width/ar +1;
            for i =1:nsample
                if i <0.5*nsample
                    icolor = color_pre{1};
                else
                    icolor = color_pre{2};
                end
                scatter(ys_sne(i, 1), ys_sne(i, 2), ...
                    'filled', 'o', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
            end
            title('t-SNE (spatial)')
            xlabel('y1')
            ylabel('y2')
            %             scatter(ha4, ys_sne(ind_example, 1), ys_sne(ind_example, 2), ...
            %                 'filled', '*', 'MarkerFaceColor', 'k','MarkerEdgeColor', 'r',  'SizeData',30, 'MarkerFaceAlpha', 0.5);
            text(ha4, ys_sne(ind_example, 1), ys_sne(ind_example, 2), num2cell(1:nexample), ...
                'color', precolor,'fontweight','bold');

            % write down some meta data
            uicontrol('Style','text','Units','centimeters','Position',[ 1 ynow, 10, 0.5],...
                'string', [ANM ' (today: ' date ')'], ...
                'FontName','Dejavu Sans', 'fontweight', 'bold','fontsize', 10,'BackgroundColor',[1 1 1],...
                'HorizontalAlignment','Left');

            % Plot examples
            xnow = 1;
            ynow = 14;
            width = 3;
            height = 3;
            xplotrange = [app.xrangeEditField.Value app.xrangeEditField_2.Value];
            yplotrange = [app.yrangeEditField.Value app.yrangeEditField_2.Value];
            t_onset = app.trangeEditField.Value;
            t_offset = app.trangeEditField_2.Value;
            tIndex     = [t_onset t_offset];
            tMap = (tIndex(1):tIndex(end));
            cmap = fake_parula(length(tMap));
            hae=zeros(1, nexample);
            [xlim, ylim, ~] = size(mat2d_pre);
            colormap(gray)
            for i = 1:nexample
                %                 hae(i) = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                %                     'ticklength', [0.025 0.5], 'xlim', xplotrange, 'ylim', yplotrange,'xtick',[],'ytick',[], 'ydir', 'reverse');

                hae(i) = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                    'ticklength', [0.025 0.5], 'xlim', [0 xlim], 'ylim', [0 ylim],'xtick',[],'ytick',[], 'ydir', 'reverse');
                imagesc([0:xlim],[0:ylim], mat2d_pre(:,:, ind_example(i))', [0 8]);

                haeraw(i) = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                    'ticklength', [0.025 0.5], 'xlim', [x_edges(1) x_edges(end)], 'ylim', [y_edges(1) y_edges(end)],'xtick',[],'ytick',[], 'ydir', 'reverse','color', 'none');

                xnow = xnow + width + 0.1;
                % trj_pre = DataOut.PreLesion.Trajectory; % 3001           3         283
                itrj = squeeze(trj_pre(:, :, ind_example(i)));
                tPlot = itrj(:, 1);
                [~, ind_cmap, ind_data] = intersect(tMap, tPlot);
                Horizontal_Coordinates = itrj(ind_data, 2);
                Vertical_Coordinates   =  itrj(ind_data, 3);
                hs = scatter(haeraw(i), downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                    20, downsample(cmap(ind_cmap, :), 10),'+', 'linewidth', 1);

                if i == 1
                    title(['Pre|' num2str(i) '|Step: ' num2str(median(diff(tPlot(ind_data)))*10) 'ms'], 'fontname','dejavu sans', 'fontsize', 10,'color', precolor)
                else
                    title([num2str(i)], 'fontname','dejavu sans', 'fontsize', 10,'color', precolor)
                end
            end

            % this plots the color scalebar
            xnow = xnow-width-0.1;
            ha_colorbar = axes('units', 'centimeters', 'position', [xnow ynow-0.5 3 0.1], ...
                'nextplot', 'add', ...
                'ydir', 'normal', ...
                'xtick', [-200:100:50], 'xlim', tIndex, 'ylim', [0 1.5], 'ytick', [], 'TickLength', [0.015 0.1]);
            ss =  scatter( tMap, ones(1, length(tMap)),20,  cmap, 'filled', 's');
            xlabel('Time from maximal height (ms)')

            % now, add post-lesion data
             % Step 2. Extract post-lesion data

             trj_post = DataOut.PostLesion.Trajectory; % 3001           3         283
             nsample = size(DataOut.PostLesion.Trajectory, 3); % let's just check 25 trials
             TrackingID = DataOut.PostLesion.TrackingID(1, :);

            early_num       =                  app.EarlypostlesiontrialsEditField.Value;
            late_num         =                   app.LatepostlesiontrialsEditField.Value;

            n_post_session_max = max(TrackingID);
            ind_early_post = find(TrackingID<n_post_session_max);
            ind_late_post = find(TrackingID==n_post_session_max);

            % we randomly select early_num trials from ind_early_post and
            % ind_late_post trials from ind_late_post

            if length(ind_early_post)>early_num
                ind_early_post_selected = ind_early_post(randperm(length(ind_early_post), early_num));
            else
                ind_early_post_selected = ind_early_post;
            end

            if length(ind_late_post)>late_num
                ind_late_post_selected = ind_late_post(randperm(length(ind_late_post), late_num));
            else
                ind_late_post_selected = ind_late_post;
            end

             % check how many segements are there

%              n_early_post = app.EarlypostlesiontrialsEditField.Value;
%              n_late_post = app.LatepostlesiontrialsEditField.Value;

             trj_post = trj_post(:, :, [ind_early_post_selected ind_late_post_selected]); % first and last 100 trials
             session_id_org_post = TrackingID([ind_early_post_selected ind_late_post_selected]);
             session_id_post = [3*ones(1, length(ind_early_post_selected)) 4*ones(1, length(ind_late_post_selected))];
            trj_post_ = trj_post;
            rng(23);
%             trj_pre_ = trj_pre(:,:,randperm(size(trj_pre, 3),nsample));
            colors = fake_parula(nsample);
            hf=26; 
            figure(hf);clf(hf)
            ha = axes('nextplot', 'add','ydir','reverse');
            mat3d = zeros(length(x_edges)-1, length(y_edges)-1, length(t_range)-1, nsample);            
            mat2d = zeros(length(x_edges)-1, length(y_edges)-1, nsample);
            matpost_high_d_spatial =[];
            matpost_high_d_spatiotemporal =[];
            im ={};
             nsample= size(trj_post, 3);

            for i =1:nsample
                t_data = trj_post_(:, 1, i);
                x_data = trj_post_(:, 2, i);
                y_data = trj_post_(:, 3, i);
                mat_traj = zeros(length(x_edges)-1, length(y_edges)-1, length(t_range)-1);
                for j =1:length(t_range)-1
                    indj = find(t_data>=t_range(j) & t_data<t_range(j+1));
                    if ~isempty(indj)
                        x_data_ = x_data(indj);
                        y_data_ = y_data(indj);
                        ndist2=histcounts2(x_data_, y_data_, x_edges, y_edges);
                        ndist2c = conv2(ndist2, psf, 'same');
                        mat_traj(:,:,j) = ndist2c;
                        if rem(i, 20) ==0
                            cla(ha)
                            imagesc(ndist2c')
                            title(sprintf('t=%2.2f', t_range(j)))
                            colormap(winter)
                            pause(0.01)
                        end
                    end
                end
                mat3d(:,:,:,i) = mat_traj;
                matpost_high_d_spatiotemporal = [matpost_high_d_spatiotemporal mat_traj(:)];
                mat2d(:,:,i) = sum(mat_traj, 3);
                matpost_high_d_spatial = [matpost_high_d_spatial reshape(mat2d(:,:,i), [], 1)];
            end
            mat2d_post = mat2d;
            mat2davg_post = mean(mat2d, 3);
            matpost_high_d_spatial = matpost_high_d_spatial'; % rows: samples, columns: features
            matpost_high_d_spatiotemporal = matpost_high_d_spatiotemporal';
            % calculating the projection of post-lesion data to the
            % principlal components constructed with pre-lesion data

            % center our data
            matpost_high_d_spatiotemporal_centered = matpost_high_d_spatiotemporal - mu_st;
            matpost_high_d_spatial_centered = matpost_high_d_spatial - mu_s;

            score_post_st = matpost_high_d_spatiotemporal_centered*PCA_st;
            score_post_s  =matpost_high_d_spatial_centered*PCA_s;

            usession_id_post = unique(session_id_post);
            for i =1:length(usession_id_post)
                icolor = color_post{i};
                scatter(ha1, score_post_st(session_id_post ==usession_id_post(i), 1), score_post_st(session_id_post ==usession_id_post(i), 2), ...
                    'filled', 's', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
                scatter(ha2, score_post_s(session_id_post ==usession_id_post(i), 1), score_post_s(session_id_post ==usession_id_post(i), 2), ...
                    'filled', 's', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
            end

            for i =1:nsample
                icolor = color_post{session_id_post(i)-max(session_id_pre)};
                t_data = trj_post_(:, 1, i);
                indplot = find(t_data>= t_onset& t_data<=t_offset);
                t_data = t_data(indplot);
                x_data = trj_post_(indplot, 2, i);
                y_data = trj_post_(indplot, 3, i);
                plot3(ha_3d, downsample(x_data, 10), downsample(y_data, 10),downsample(t_data, 10), 'color', icolor);
                plot(ha_2d, downsample(x_data,10), downsample(y_data,10), 'color',icolor);
            end

            % shuffle lines in ha_3d and ha_2d
            xlines = get(ha_2d, 'children');
            set(ha_2d, 'children', xlines(randperm(length(xlines), length(xlines))));
            xlines = get(ha_3d, 'children');
            set(ha_3d, 'children', xlines(randperm(length(xlines), length(xlines))));

            % extract post examples
            rng(7)
            indpost_example = randperm(nsample, nexample);
            xnow = 1;
            ynow = 18;
            figure(25)
            for i = 1:nexample
                hae2(i) = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                    'ticklength', [0.025 0.5], 'xlim', [0 xlim], 'ylim', [0 ylim],'xtick',[],'ytick',[], 'ydir', 'reverse');
                imagesc([0:xlim],[0:ylim], mat2d_post(:,:, indpost_example(i))', [0 8]);
                hae2raw(i) = axes('units', 'centimeters', 'nextplot', 'add', 'position', [xnow ynow width height], ...
                    'ticklength', [0.025 0.5], 'xlim', xplotrange, 'ylim', yplotrange,'xtick',[],'ytick',[], 'ydir', 'reverse','color', 'none');
                xnow = xnow + width + 0.1;
                % trj_pre = DataOut.PreLesion.Trajectory; % 3001           3         283
                itrj = squeeze(trj_post(:, :, indpost_example(i)));
                tPlot = itrj(:, 1);
                [~, ind_cmap, ind_data] = intersect(tMap, tPlot);
                Horizontal_Coordinates = itrj(ind_data, 2);
                Vertical_Coordinates   =  itrj(ind_data, 3);
                scatter(hae2raw(i), downsample(Horizontal_Coordinates, 10), downsample(Vertical_Coordinates, 10), ...
                    20, downsample(cmap(ind_cmap, :), 10),'+', 'linewidth', 1);
                if i == 1
                    title(['Post|' num2str(i)], 'fontname','dejavu sans', 'fontsize', 10,'color', postcolor)
                else
                    title([num2str(i)], 'fontname','dejavu sans', 'fontsize', 10,'color', postcolor)
                end
            end

            text(ha1, score_post_st(indpost_example, 1), score_post_st(indpost_example, 2), num2cell(1:nexample), ...
                'color', postcolor,'fontweight','bold');
            text(ha2, score_post_s(indpost_example, 1), score_post_s(indpost_example, 2), num2cell(1:nexample), ...
                'color', postcolor,'fontweight','bold');

            % put data pre- and post-lesion together for  tSNE
            disp('#########################################################')
            disp(['###############3. tSNE ################'])
            disp('#########################################################')

            mat_high_d_spatial_all=[];
            mat_high_d_spatiotemporal_all=[];
            mat_high_d_spatial_all                  =    [mat_high_d_spatial; matpost_high_d_spatial];
            mat_high_d_spatiotemporal_all   =    [mat_high_d_spatiotemporal; matpost_high_d_spatiotemporal];


            index_all = [session_id_pre session_id_post];
            yst_sne = tsne(mat_high_d_spatiotemporal_all);
            ys_sne = tsne(mat_high_d_spatial_all);
            % we need to remap the example index
            % ind_example stays the same
            indpost_example = indpost_example + size(mat_high_d_spatial, 1);

            cla(ha3)
            usession_id = unique(index_all);
            for i =1:length(usession_id)
                if i <= max(session_id_pre)
                    icolor = color_pre{i};
                    scatter(ha3, yst_sne(index_all ==usession_id(i), 1), yst_sne(index_all ==usession_id(i), 2), ...
                        'filled', 'o', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
                else
                    icolor = color_post{i-max(session_id_pre)};
                    scatter(ha3, yst_sne(index_all ==usession_id(i), 1), yst_sne(index_all ==usession_id(i), 2), ...
                        'filled', 's', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
                end
            end

            text(ha3, yst_sne(indpost_example, 1), yst_sne(indpost_example, 2), num2cell(1:nexample), ...
                'color', postcolor,'fontweight','bold');
            text(ha3, yst_sne(ind_example, 1), yst_sne(ind_example, 2), num2cell(1:nexample), ...
                'color', precolor,'fontweight','bold');

            cla(ha4)
            usession_id = unique(index_all);
            for i =1:length(usession_id)
                if i <= max(session_id_pre)
                    icolor = color_pre{i};
                    scatter(ha4, ys_sne(index_all ==usession_id(i), 1), ys_sne(index_all ==usession_id(i), 2), ...
                        'filled', 'o', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
                else
                    icolor = color_post{i-max(session_id_pre)};
                    scatter(ha4, ys_sne(index_all ==usession_id(i), 1), ys_sne(index_all ==usession_id(i), 2), ...
                        'filled', 's', 'MarkerFaceColor', icolor, 'SizeData',markersize, 'MarkerFaceAlpha', markeralpha);
                end
            end

            text(ha4, ys_sne(indpost_example, 1), ys_sne(indpost_example, 2), num2cell(1:nexample), ...
                'color', postcolor,'fontweight','bold');
            text(ha4, ys_sne(ind_example, 1), ys_sne(ind_example, 2), num2cell(1:nexample), ...
                'color', precolor,'fontweight','bold');

            % save a bunch of data
            disp('#########################################################')
            disp(['###############4. Save to TrajectoryDataOut_ANM.mat ################'])
            disp('#########################################################')


            thisFile = ['TrajectoryDataOut_' ANM '.mat'];
            % load this file
            load(thisFile);
            DataOut.Dimension.PreLesionSpatiotemporal = mat_high_d_spatiotemporal;
            DataOut.Dimension.PreLesionSpatial = mat_high_d_spatial;
            DataOut.Dimension.PreLesionSessionIndex = session_id_pre;
            DataOut.Dimension.PreLesionPCAScoreSpatiotemporal = score_st;
            DataOut.Dimension.PreLesionPCAScoreSpatial = score_s;
            DataOut.Dimension.PostLesionSpatiotemporal = matpost_high_d_spatiotemporal;
            DataOut.Dimension.PostLesionSpatial = matpost_high_d_spatial;
            DataOut.Dimension.PostLesionSessionIndex = session_id_post;
            DataOut.Dimension.PostLesionPCAScoreSpatiotemporal = score_post_st;
            DataOut.Dimension.PostLesionPCAScoreSpatial = score_post_s;
            DataOut.Dimension.PCACoeff_Spatiotemporal = PCA_st;
            DataOut.Dimension.PCACoeff_Spatial = PCA_s;
            DataOut.Dimension.SpatialBinSize = spatial_binsize;
            DataOut.Dimension.TimeBinSize = time_binsize;
            DataOut.Dimension.tSNE_Spatiotemporal = yst_sne;
            DataOut.Dimension.tSNE_Spatial = ys_sne;
            DataOut.Dimension.FigOutput = 'Fig3_DimensionalityReduction';
            DataOut.Dimension.Today = date;

            save(thisFile, 'DataOut');
            app.PlottrajectoryandspeedButton.UserData = DataOut;

            disp('#########################################################')
            disp(['#######5.Save this figure to "Fig3_DimensionalityReduction" to /Figures ########'])
            disp('#########################################################')

            tosavename=  fullfile(pwd, 'Figures', 'Fig3_DimensionalityReduction');
            saveas(hfd, tosavename, 'epsc')
            saveas(hfd, [tosavename], 'fig')
            print (hfd,'-dpdf', tosavename)
            print (hfd,'-dpng', tosavename)


        end

        % Callback function
        function AddButton_3Pushed(app, event)
            
            % add data to 'prelesion' or 'saline' category
            value = app.ManipulationListBox.Value;
            app.Extensivewritedownthedateeg20220804ListBox.Items = value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ListBox = uilistbox(app.Panel);
            app.ControlListBoxLabel = uilabel(app.Panel);
            app.ControlListBoxLabel.Text = 'Control';
            app.ControlListBox = uilistbox(app.Panel);
            app.ControlListBox.Items = {};
            app.ControlListBox.Value = {};
            app.ManipulationListBoxLabel = uilabel(app.Panel);
            app.ManipulationListBoxLabel.Text = 'Manipulation';
            app.ManipulationListBox = uilistbox(app.Panel);
            app.ManipulationListBox.Items = {};
            app.ManipulationListBox.Value = {};
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.Panel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = '2 | Add';
            app.ANMEditFieldLabel = uilabel(app.Panel);
            app.ANMEditFieldLabel.Text = 'ANM';
            app.ANMEditField = uieditfield(app.Panel, 'text');
            app.LoadsessionsButton = uibutton(app.Panel, 'push');
            app.LoadsessionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsessionsButtonPushed, true);
            app.LoadsessionsButton.Text = '1|Load sessions';
            app.WidthEditFieldLabel = uilabel(app.Panel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.Panel, 'text');
            app.WidthEditField.Value = '1200';
            app.ImageEditFieldLabel = uilabel(app.Panel);
            app.ImageEditFieldLabel.Text = 'Image#';
            app.ImageEditField = uieditfield(app.Panel, 'numeric');
            app.ImageEditField.Value = 1;
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.Panel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.PrintCheckBox = uicheckbox(app.Panel);
            app.PrintCheckBox.Text = 'Print';
            app.ExtensivetrainingEditFieldLabel = uilabel(app.Panel);
            app.ExtensivetrainingEditFieldLabel.Text = 'Extensive training';
            app.ExtensivetrainingEditField = uieditfield(app.Panel, 'text');
            app.ProcesstrackingdataButton = uibutton(app.UIFigure, 'push');
            app.ProcesstrackingdataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesstrackingdataButtonPushed, true);
            app.ProcesstrackingdataButton.Text = '3 | Process tracking data';
            app.DatatypesButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatypesButtonGroup.Title = 'Data types';
            app.TraditionalButton = uiradiobutton(app.DatatypesButtonGroup);
            app.TraditionalButton.Text = 'Traditional';
            app.TraditionalButton.Value = true;
            app.UnderVideoDataButton = uiradiobutton(app.DatatypesButtonGroup);
            app.UnderVideoDataButton.Text = 'Under /VideoData';
            app.UnclearButton = uiradiobutton(app.DatatypesButtonGroup);
            app.UnclearButton.Text = 'Unclear';
            app.Lamp = uilamp(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AlignmentacrosssessionsButton = uibutton(app.UIFigure, 'push');
            app.AlignmentacrosssessionsButton.ButtonPushedFcn = createCallbackFcn(app, @AlignmentacrosssessionsButtonPushed, true);
            app.AlignmentacrosssessionsButton.Text = '4 | Alignment across sessions';
            app.PlottrajectoryandspeedButton = uibutton(app.UIFigure, 'push');
            app.PlottrajectoryandspeedButton.ButtonPushedFcn = createCallbackFcn(app, @PlottrajectoryandspeedButtonPushed, true);
            app.PlottrajectoryandspeedButton.Text = '5 | Plot trajectory and speed';
            app.xrangeEditFieldLabel = uilabel(app.UIFigure);
            app.xrangeEditFieldLabel.Text = 'x range';
            app.xrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.xrangeEditField.Value = 200;
            app.yrangeEditFieldLabel = uilabel(app.UIFigure);
            app.yrangeEditFieldLabel.Text = 'y range';
            app.yrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.yrangeEditField.Value = 400;
            app.xrangeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.xrangeEditField_2.Value = 800;
            app.yrangeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.yrangeEditField_2.Value = 900;
            app.EarlypostlesiontrialsEditFieldLabel = uilabel(app.UIFigure);
            app.EarlypostlesiontrialsEditFieldLabel.Text = 'Early post-lesion trials';
            app.EarlypostlesiontrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EarlypostlesiontrialsEditField.Value = 50;
            app.LatepostlesiontrialsEditFieldLabel = uilabel(app.UIFigure);
            app.LatepostlesiontrialsEditFieldLabel.Text = 'Late post-lesion trials';
            app.LatepostlesiontrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatepostlesiontrialsEditField.Value = 50;
            app.EarlyprelesiontrialsEditFieldLabel = uilabel(app.UIFigure);
            app.EarlyprelesiontrialsEditFieldLabel.Text = 'Early pre-lesion trials';
            app.EarlyprelesiontrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EarlyprelesiontrialsEditField.Value = 80;
            app.LateprelesiontrialsEditFieldLabel = uilabel(app.UIFigure);
            app.LateprelesiontrialsEditFieldLabel.Text = 'Late pre-lesion trials';
            app.LateprelesiontrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LateprelesiontrialsEditField.Value = 80;
            app.trangeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.trangeEditField_2.Value = 100;
            app.trangeEditFieldLabel = uilabel(app.UIFigure);
            app.trangeEditFieldLabel.Text = 't range';
            app.trangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.trangeEditField.Value = -300;
            app.SimilarityanalysisButton = uibutton(app.UIFigure, 'push');
            app.SimilarityanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SimilarityanalysisButtonPushed, true);
            app.SimilarityanalysisButton.Tooltip = {'We will do a tSNE analysis on trajectory data (t, x, y)'};
            app.SimilarityanalysisButton.Text = '6 | Similarity analysis';
            app.MidpostlesiontrialsEditFieldLabel = uilabel(app.UIFigure);
            app.MidpostlesiontrialsEditFieldLabel.Text = 'Mid post-lesion trials';
            app.MidpostlesiontrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpatialKernelwidthEditFieldLabel = uilabel(app.UIFigure);
            app.SpatialKernelwidthEditFieldLabel.Text = 'Spatial Kernel width';
            app.SpatialKernelwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpatialKernelwidthEditField.Tooltip = {'Gaussian kernel width for convolving the trajectory'; ''};
            app.SpatialKernelwidthEditField.Value = 25;
            app.timeresolutionmsEditFieldLabel = uilabel(app.UIFigure);
            app.timeresolutionmsEditFieldLabel.Text = 'time resolution (ms)';
            app.timeresolutionmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeresolutionmsEditField.Tooltip = {'Gaussian kernel width for convolving the trajectory'; ''};
            app.timeresolutionmsEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrajectoryAnalysisLesion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end