classdef Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DateLabel                    matlab.ui.control.Label
        GUISnapshotButton            matlab.ui.control.Button
        PSTHPanel                    matlab.ui.container.Panel
        NdivDropDown                 matlab.ui.control.DropDown
        NdivDropDownLabel            matlab.ui.control.Label
        CombineCueUncueCheckBox      matlab.ui.control.CheckBox
        SendPopButton                matlab.ui.control.Button
        PSTHsingleButton             matlab.ui.control.Button
        UnitsChUnitsmDropDown        matlab.ui.control.DropDown
        UnitsChUnitsmDropDownLabel   matlab.ui.control.Label
        TimerangesEditFieldMax       matlab.ui.control.NumericEditField
        PlotpopulationPSTHButton     matlab.ui.control.Button
        PopulationPSTHButton         matlab.ui.control.Button
        TimerangesEditFieldMin       matlab.ui.control.NumericEditField
        TimerangesEditFieldLabel     matlab.ui.control.Label
        KornblumCheckBox             matlab.ui.control.CheckBox
        PSTHallButton                matlab.ui.control.Button
        CheckbehaviorButton          matlab.ui.control.Button
        SendRDone                    matlab.ui.control.Label
        SendrButton                  matlab.ui.control.Button
        TableDone                    matlab.ui.control.Label
        UnittableButton              matlab.ui.control.Button
        AnalysisroutinetimingparadigmLabel  matlab.ui.control.Label
        RTArrayFile                  matlab.ui.control.Label
        LoadrButton                  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        ChemorelatedPanel            matlab.ui.container.Panel
        Loadsdf_outButton            matlab.ui.control.Button
        RecoverySegEditField         matlab.ui.control.NumericEditField
        RecoverySegEditFieldLabel    matlab.ui.control.Label
        InjectionTimeEditField       matlab.ui.control.NumericEditField
        InjectionTimeEditFieldLabel  matlab.ui.control.Label
        InjectionSegEditField        matlab.ui.control.NumericEditField
        InjectionSegEditFieldLabel   matlab.ui.control.Label
        OptionalChemoSDFButton       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadrButton
        function LoadrButtonPushed(app, event)
            % load r
            r_name = Spikes.r_name;

            if isempty(r_name)
                [file,path] = uigetfile();
                data = load(fullfile(path, file));
                app.RTArrayFile.UserData = data.r;
                app.RTArrayFile.Text = file;
            else
                data = load(Spikes.r_name);
                app.RTArrayFile.UserData = data.r;
                app.RTArrayFile.Text = Spikes.r_name;
            end

            % Update 'Units' dropdown menu
            Units = data.r.Units.SpikeNotes;
            UnitItems = cell(1,size(Units, 1));
            for i =1:size(Units, 1)
                UnitItems{i} = num2str(data.r.Units.SpikeNotes(i, [1:3])); 
            end;
            app.UnitsChUnitsmDropDown.Items = UnitItems;
        end

        % Button pushed function: UnittableButton
        function UnittableButtonPushed(app, event)
            r = app.RTArrayFile.UserData;
            tab = Spikes.unit_table(r);
            app.TableDone.Text = 'Done';

            % Update table
            app.UITable.ColumnName =  tab.Properties.VariableNames;
            app.UITable.Data = tab;

        end

        % Button pushed function: SendrButton
        function SendrButtonPushed(app, event)
            r = app.RTArrayFile.UserData;
            Spikes.send_r;
            app.SendRDone.Text = 'Done';
        end

        % Button pushed function: OptionalChemoSDFButton
        function OptionalChemoSDFButtonPushed(app, event)
            r = app.RTArrayFile.UserData;
            InjectTime = app.InjectionTimeEditField.Value;
            InjectSeg  = app.InjectionSegEditField.Value;
            RecoverySeg  = app.RecoverySegEditField.Value;

            if InjectTime==0 && InjectSeg == 0
                clc
                disp('~~~~~~~~~~~~~~~~~~~~~')
                disp('Need injection information')
                disp('~~~~~~~~~~~~~~~~~~~~~')
                return;
            elseif InjectTime>0 && RecoverySeg==0
                Chemo.MakeChemoSDF(r, 'InjectionTime', InjectTime);
            elseif InjectTime>0 && RecoverySeg>0
                Chemo.MakeChemoSDF(r, 'InjectionTime', InjectTime,'RecoverySegment', RecoverySeg);
            elseif InjectSeg>0 && RecoverySeg>0
                Chemo.MakeChemoSDF(r, 'InjectionSegment', InjectSeg, 'RecoverySegment', RecoverySeg)
            elseif InjectSeg>0 && RecoverySeg==0
                sdfout = Chemo.MakeChemoSDF(r, 'InjectionSegment', InjectSeg);
                app.InjectionTimeEditField.Value=round(sdfout.t_injection);
            else
                disp('What else?!')
                return
            end;
        end

        % Button pushed function: CheckbehaviorButton
        function CheckbehaviorButtonPushed(app, event)
            r = app.RTArrayFile.UserData;
            if isfield(r, 'BehaviorClass')
                r.BehaviorClass.Plot();
                r.BehaviorClass.Print();
            else
                clc
                disp('~~~~~~~~~~~~~~~')
                disp('No class found in r')
                disp('~~~~~~~~~~~~~~~')
            end;
        end

        % Button pushed function: PSTHallButton
        function PSTHallButtonPushed(app, event)
            ndiv = str2num(app.NdivDropDown.Value);
            if app.KornblumCheckBox.Value == 1
                combine_cue_uncue = app.CombineCueUncueCheckBox.Value;
                r = app.RTArrayFile.UserData;
                compute_range_min = app.TimerangesEditFieldMin.Value;
                compute_range_max = app.TimerangesEditFieldMax.Value;
                if compute_range_min == 0 && compute_range_max == 0
                    for ind = 1:length(r.Units.SpikeTimes)
                        Spikes.Timing.KornblumSpikes(r, ind, 'CombineCueUncue', combine_cue_uncue);
                        Spikes.Timing.KornblumRamping(r, ind,  'CombinedCueUncue', combine_cue_uncue,  'Division', ndiv)
                    end
                else
                    compute_range = [compute_range_min compute_range_max];
                    for ind = 1:length(r.Units.SpikeTimes)
                        Spikes.Timing.KornblumSpikes(r, ind, 'CombineCueUncue', combine_cue_uncue, 'ComputeRange', compute_range);
                        Spikes.Timing.KornblumRamping(r, ind,  'CombinedCueUncue', combine_cue_uncue, ...
                            'ComputeTimeRange', compute_range,  'Division', ndiv)
                    end
                end
            else
                disp('Work ongoing')
            end
        end

        % Button pushed function: PSTHsingleButton
        function PSTHsingleButtonPushed(app, event)
            ndiv = str2num(app.NdivDropDown.Value);
            if app.KornblumCheckBox.Value == 1
                r = app.RTArrayFile.UserData;
                combine_cue_uncue = app.CombineCueUncueCheckBox.Value;
                i=find(strcmp(app.UnitsChUnitsmDropDown.Items, app.UnitsChUnitsmDropDown.Value));
                compute_range_min = app.TimerangesEditFieldMin.Value;
                compute_range_max = app.TimerangesEditFieldMax.Value;
                if compute_range_min == 0 && compute_range_max == 0
                    Spikes.Timing.KornblumSpikes(r, i);
                    Spikes.Timing.KornblumRamping(r, i,  'CombinedCueUncue', combine_cue_uncue,  'Division', ndiv);
                else
                    compute_range = [compute_range_min compute_range_max];
                    Spikes.Timing.KornblumSpikes(r, i, 'CombineCueUncue', combine_cue_uncue, ...
                        'ComputeRange', compute_range);
                    Spikes.Timing.KornblumRamping(r, i,  'ComputeTimeRange', compute_range, ...
                        'CombinedCueUncue', combine_cue_uncue,  'Division', ndiv);
                end;
            end;

        end

        % Button pushed function: PopulationPSTHButton
        function PopulationPSTHButtonPushed(app, event)
            if app.KornblumCheckBox.Value == 1
                r = app.RTArrayFile.UserData;
                compute_range_min = app.TimerangesEditFieldMin.Value;
                compute_range_max = app.TimerangesEditFieldMax.Value;
                if compute_range_min == 0 && compute_range_max == 0
                    pop = Spikes.Timing.KornblumSpikesPopulation(r);
                    app.PopulationPSTHButton.UserData = pop;
                else
                    compute_range = [compute_range_min compute_range_max];
                    pop = Spikes.Timing.KornblumSpikesPopulation(r, compute_range)            
                    app.PopulationPSTHButton.UserData = pop;
                end;
            else
                disp('Work ongoing')
            end;
        end

        % Button pushed function: PlotpopulationPSTHButton
        function PlotpopulationPSTHButtonPushed(app, event)
            if ~isempty(app.PopulationPSTHButton.UserData)
                pop = app.PopulationPSTHButton.UserData;
                Spikes.Timing.VisualizePSTHPopulation(pop);
            else
                % check local directory
                thisfolder = pwd;
                if ismac
                    folder_names = split(thisfolder, '/');
                else
                    folder_names = split(thisfolder, '\');
                end;

                ANM_name = folder_names{end-1};

                session = folder_names{end};
                pop_file = dir(['PopulationPSTH_' ANM_name '_' session '.mat']);

                if ~isempty(pop_file)
                    load(pop_file.name)
                else
                  
                    clc
                    disp('~~~~~~~~~~~~~~~')
                    disp('Compute PSTH pop first')
                    disp('~~~~~~~~~~~~~~~')
                    return;
                end;
                pop = PSTHOut;
                app.PopulationPSTHButton.UserData = pop;
                Spikes.Timing.VisualizePSTHPopulation(pop);
            end
        end

        % Button pushed function: SendPopButton
        function SendPopButtonPushed(app, event)
             Spikes.send_psth_pop;
        end

        % Button pushed function: GUISnapshotButton
        function GUISnapshotButtonPushed(app, event)
            c=date;
            app.DateLabel.Text = c;
            % save current figure
            this_folder          =      pwd;
            folder_split          =     split(this_folder, '\');
            rat_name            =       folder_split{end-1};
            session_name    =       folder_split{end};

            aGoodName = ['AnalysisGUI_' rat_name, '_', session_name];
            exportapp(app.UIFigure,[aGoodName '.png'])
            exportapp(app.UIFigure,[aGoodName '.pdf'])
        end

        % Value changed function: KornblumCheckBox
        function KornblumCheckBoxValueChanged(app, event)
            value = app.KornblumCheckBox.Value;
            
        end

        % Button pushed function: Loadsdf_outButton
        function Loadsdf_outButtonPushed(app, event)
            chemo_folder = fullfile(findonedrive, '00_Work', '03_Projects', '09_Chemogenetics', 'Data', 'sdf_out');
            % check if r has been loaded
            filename = [];
            if ~isempty(app.RTArrayFile.UserData)
                r_name = app.RTArrayFile.Text;
                r_name2 = extractAfter(r_name, 'RTarray_');
                sdf_name = ['sdf_' r_name2];
                if ~isempty(dir(fullfile(chemo_folder, sdf_name)))
                    load(fullfile(chemo_folder, sdf_name));
                else
                    % load sdf from chemo folder
                    [file,path] = uigetfile(chemo_folder);
                    load(fullfile(path, file));
                end;
            else
                % load sdf from chemo folder
                [file,path] = uigetfile(chemo_folder);
                load(fullfile(path, file));
            end

            t_inject = sdf_out.t_injection;
            app.InjectionSegEditField.Value = t_inject;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {450, 450};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {324, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadrButton = uibutton(app.LeftPanel, 'push');
            app.LoadrButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrButtonPushed, true);
            app.LoadrButton.Text = '1 | Load ''r''';
            app.RTArrayFile = uilabel(app.LeftPanel);
            app.RTArrayFile.Text = 'Waiting for r';
            app.AnalysisroutinetimingparadigmLabel = uilabel(app.LeftPanel);
            app.AnalysisroutinetimingparadigmLabel.Text = 'Analysis routine timing paradigm ';
            app.UnittableButton = uibutton(app.LeftPanel, 'push');
            app.UnittableButton.ButtonPushedFcn = createCallbackFcn(app, @UnittableButtonPushed, true);
            app.UnittableButton.Text = '2 | Unit table';
            app.TableDone = uilabel(app.LeftPanel);
            app.TableDone.Text = 'None';
            app.SendrButton = uibutton(app.LeftPanel, 'push');
            app.SendrButton.ButtonPushedFcn = createCallbackFcn(app, @SendrButtonPushed, true);
            app.SendrButton.Text = '3 | Send ''r''';
            app.SendRDone = uilabel(app.LeftPanel);
            app.SendRDone.Text = 'None';
            app.CheckbehaviorButton = uibutton(app.LeftPanel, 'push');
            app.CheckbehaviorButton.ButtonPushedFcn = createCallbackFcn(app, @CheckbehaviorButtonPushed, true);
            app.CheckbehaviorButton.Text = '4 | Check behavior';
            app.PSTHPanel = uipanel(app.LeftPanel);
            app.PSTHPanel.Title = 'PSTH';
            app.PSTHallButton = uibutton(app.PSTHPanel, 'push');
            app.PSTHallButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHallButtonPushed, true);
            app.PSTHallButton.Text = '5 | PSTH (all)';
            app.KornblumCheckBox = uicheckbox(app.PSTHPanel);
            app.KornblumCheckBox.ValueChangedFcn = createCallbackFcn(app, @KornblumCheckBoxValueChanged, true);
            app.KornblumCheckBox.Text = 'Kornblum';
            app.KornblumCheckBox.Value = true;
            app.TimerangesEditFieldLabel = uilabel(app.PSTHPanel);
            app.TimerangesEditFieldLabel.Text = 'Time range (s)';
            app.TimerangesEditFieldMin = uieditfield(app.PSTHPanel, 'numeric');
            app.PopulationPSTHButton = uibutton(app.PSTHPanel, 'push');
            app.PopulationPSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PopulationPSTHButtonPushed, true);
            app.PopulationPSTHButton.Text = '6 | Population PSTH';
            app.PlotpopulationPSTHButton = uibutton(app.PSTHPanel, 'push');
            app.PlotpopulationPSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpopulationPSTHButtonPushed, true);
            app.PlotpopulationPSTHButton.Text = '7 | Plot population PSTH';
            app.TimerangesEditFieldMax = uieditfield(app.PSTHPanel, 'numeric');
            app.UnitsChUnitsmDropDownLabel = uilabel(app.PSTHPanel);
            app.UnitsChUnitsmDropDownLabel.Text = 'Units (Ch, Unit, s/m)';
            app.UnitsChUnitsmDropDown = uidropdown(app.PSTHPanel);
            app.PSTHsingleButton = uibutton(app.PSTHPanel, 'push');
            app.PSTHsingleButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHsingleButtonPushed, true);
            app.PSTHsingleButton.Text = 'PSTH (single)';
            app.SendPopButton = uibutton(app.PSTHPanel, 'push');
            app.SendPopButton.ButtonPushedFcn = createCallbackFcn(app, @SendPopButtonPushed, true);
            app.SendPopButton.Text = '8 | Send Pop';
            app.CombineCueUncueCheckBox = uicheckbox(app.PSTHPanel);
            app.CombineCueUncueCheckBox.Text = 'Combine Cue/Uncue';
            app.NdivDropDownLabel = uilabel(app.PSTHPanel);
            app.NdivDropDownLabel.Text = 'Ndiv';
            app.NdivDropDown = uidropdown(app.PSTHPanel);
            app.NdivDropDown.Items = {'2', '3', '4', '5', '6', '7'};
            app.NdivDropDown.Value = '4';
            app.GUISnapshotButton = uibutton(app.LeftPanel, 'push');
            app.GUISnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @GUISnapshotButtonPushed, true);
            app.GUISnapshotButton.Text = 'GUI Snapshot';
            app.DateLabel = uilabel(app.LeftPanel);
            app.DateLabel.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.ChemorelatedPanel = uipanel(app.RightPanel);
            app.ChemorelatedPanel.Title = 'Chemo related';
            app.OptionalChemoSDFButton = uibutton(app.ChemorelatedPanel, 'push');
            app.OptionalChemoSDFButton.ButtonPushedFcn = createCallbackFcn(app, @OptionalChemoSDFButtonPushed, true);
            app.OptionalChemoSDFButton.Text = 'Optional | Chemo SDF';
            app.InjectionSegEditFieldLabel = uilabel(app.ChemorelatedPanel);
            app.InjectionSegEditFieldLabel.Text = 'InjectionSeg';
            app.InjectionSegEditField = uieditfield(app.ChemorelatedPanel, 'numeric');
            app.InjectionTimeEditFieldLabel = uilabel(app.ChemorelatedPanel);
            app.InjectionTimeEditFieldLabel.Text = 'InjectionTime';
            app.InjectionTimeEditField = uieditfield(app.ChemorelatedPanel, 'numeric');
            app.RecoverySegEditFieldLabel = uilabel(app.ChemorelatedPanel);
            app.RecoverySegEditFieldLabel.Text = 'RecoverySeg';
            app.RecoverySegEditField = uieditfield(app.ChemorelatedPanel, 'numeric');
            app.Loadsdf_outButton = uibutton(app.ChemorelatedPanel, 'push');
            app.Loadsdf_outButton.ButtonPushedFcn = createCallbackFcn(app, @Loadsdf_outButtonPushed, true);
            app.Loadsdf_outButton.Text = 'Load sdf_out';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end