classdef hbBuildR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MWATab                        matlab.ui.container.Tab
        ClearButton                   matlab.ui.control.Button
        Ch32EditField                 matlab.ui.control.EditField
        Ch32EditFieldLabel            matlab.ui.control.Label
        Ch31EditField                 matlab.ui.control.EditField
        Ch31EditFieldLabel            matlab.ui.control.Label
        Ch30EditField                 matlab.ui.control.EditField
        Ch30EditFieldLabel            matlab.ui.control.Label
        Ch29EditField                 matlab.ui.control.EditField
        Ch29EditFieldLabel            matlab.ui.control.Label
        Ch28EditField                 matlab.ui.control.EditField
        Ch28EditFieldLabel            matlab.ui.control.Label
        Ch27EditField                 matlab.ui.control.EditField
        Ch27EditFieldLabel            matlab.ui.control.Label
        Ch26EditField                 matlab.ui.control.EditField
        Ch26EditFieldLabel            matlab.ui.control.Label
        Ch25EditField                 matlab.ui.control.EditField
        Ch25EditFieldLabel            matlab.ui.control.Label
        Ch24EditField                 matlab.ui.control.EditField
        Ch24EditFieldLabel            matlab.ui.control.Label
        Ch23EditField                 matlab.ui.control.EditField
        Ch23EditFieldLabel            matlab.ui.control.Label
        Ch22EditField                 matlab.ui.control.EditField
        Ch22EditFieldLabel            matlab.ui.control.Label
        Ch21EditField                 matlab.ui.control.EditField
        Ch21EditFieldLabel            matlab.ui.control.Label
        Ch20EditField                 matlab.ui.control.EditField
        Ch20EditFieldLabel            matlab.ui.control.Label
        Ch19EditField                 matlab.ui.control.EditField
        Ch19EditFieldLabel            matlab.ui.control.Label
        Ch18EditField                 matlab.ui.control.EditField
        Ch18EditFieldLabel            matlab.ui.control.Label
        Ch17EditField                 matlab.ui.control.EditField
        Ch17EditFieldLabel            matlab.ui.control.Label
        Ch16EditField                 matlab.ui.control.EditField
        Ch16EditFieldLabel            matlab.ui.control.Label
        Ch15EditField                 matlab.ui.control.EditField
        Ch15EditFieldLabel            matlab.ui.control.Label
        Ch14EditField                 matlab.ui.control.EditField
        Ch14EditFieldLabel            matlab.ui.control.Label
        Ch13EditField                 matlab.ui.control.EditField
        Ch13EditFieldLabel            matlab.ui.control.Label
        Ch12EditField                 matlab.ui.control.EditField
        Ch12EditFieldLabel            matlab.ui.control.Label
        Ch11EditField                 matlab.ui.control.EditField
        Ch11EditFieldLabel            matlab.ui.control.Label
        Ch10EditField                 matlab.ui.control.EditField
        Ch10EditFieldLabel            matlab.ui.control.Label
        Ch9EditField                  matlab.ui.control.EditField
        Ch9Label                      matlab.ui.control.Label
        Ch8EditField                  matlab.ui.control.EditField
        Ch8EditFieldLabel             matlab.ui.control.Label
        Ch7EditField                  matlab.ui.control.EditField
        Ch7EditFieldLabel             matlab.ui.control.Label
        Ch6EditField                  matlab.ui.control.EditField
        Ch6EditFieldLabel             matlab.ui.control.Label
        Ch5EditField                  matlab.ui.control.EditField
        Ch5EditFieldLabel             matlab.ui.control.Label
        Ch4EditField                  matlab.ui.control.EditField
        Ch4EditFieldLabel             matlab.ui.control.Label
        Ch3EditField                  matlab.ui.control.EditField
        Ch3EditFieldLabel             matlab.ui.control.Label
        Ch2EditField                  matlab.ui.control.EditField
        Ch2EditFieldLabel             matlab.ui.control.Label
        Ch1EditField                  matlab.ui.control.EditField
        Ch1EditFieldLabel             matlab.ui.control.Label
        TetrodeTab                    matlab.ui.container.Tab
        ChsEditField_8                matlab.ui.control.EditField
        ChsEditField_8Label           matlab.ui.control.Label
        ChsEditField_7                matlab.ui.control.EditField
        ChsEditField_7Label           matlab.ui.control.Label
        ChsEditField_6                matlab.ui.control.EditField
        ChsEditField_6Label           matlab.ui.control.Label
        ChsEditField_5                matlab.ui.control.EditField
        ChsEditField_5Label           matlab.ui.control.Label
        ChsEditField_4                matlab.ui.control.EditField
        ChsEditField_4Label           matlab.ui.control.Label
        ChsEditField_3                matlab.ui.control.EditField
        ChsEditField_3Label           matlab.ui.control.Label
        ChsEditField_2                matlab.ui.control.EditField
        ChsEditField_2Label           matlab.ui.control.Label
        ChsEditField_1                matlab.ui.control.EditField
        ChsEditFieldLabel             matlab.ui.control.Label
        Tetrode8EditField             matlab.ui.control.EditField
        Tetrode8EditFieldLabel        matlab.ui.control.Label
        Tetrode7EditField             matlab.ui.control.EditField
        Tetrode7EditFieldLabel        matlab.ui.control.Label
        Tetrode6EditField             matlab.ui.control.EditField
        Tetrode6EditFieldLabel        matlab.ui.control.Label
        Tetrode5EditField             matlab.ui.control.EditField
        Tetrode5EditFieldLabel        matlab.ui.control.Label
        Tetrode4EditField             matlab.ui.control.EditField
        Tetrode4EditFieldLabel        matlab.ui.control.Label
        Tetrode3EditField             matlab.ui.control.EditField
        Tetrode3EditFieldLabel        matlab.ui.control.Label
        Tetrode2EditField             matlab.ui.control.EditField
        Tetrode2EditFieldLabel        matlab.ui.control.Label
        Tetrode1EditField             matlab.ui.control.EditField
        Tetrode1EditFieldLabel        matlab.ui.control.Label
        BuildRClassandplotPSTHsPanel  matlab.ui.container.Panel
        RoutputdirLabel               matlab.ui.control.Label
        RoutdirButton                 matlab.ui.control.Button
        SortrasterCheckBox            matlab.ui.control.CheckBox
        CopyRButton                   matlab.ui.control.Button
        UnitIDEditField               matlab.ui.control.EditField
        UnitIDEditFieldLabel          matlab.ui.control.Label
        PlotPSTHButton                matlab.ui.control.Button
        PlotPopulationButton          matlab.ui.control.Button
        Lamp_PlotPoppulation          matlab.ui.control.Lamp
        InitiateRButton               matlab.ui.control.Button
        PlotallPSTHsButton            matlab.ui.control.Button
        Lamp_PlotPSTHs                matlab.ui.control.Lamp
        Lamp_BuildR                   matlab.ui.control.Lamp
        LoadRButton                   matlab.ui.control.Button
        BuildRButton                  matlab.ui.control.Button
        MetaPanel                     matlab.ui.container.Panel
        DebugModeCheckBox             matlab.ui.control.CheckBox
        ExperTextArea                 matlab.ui.control.TextArea
        ExperTextAreaLabel            matlab.ui.control.Label
        ElectrodesButtonGroup         matlab.ui.container.ButtonGroup
        TetrodesButton                matlab.ui.control.RadioButton
        MWAButton                     matlab.ui.control.RadioButton
        ShowresultsinFinderButton     matlab.ui.control.Button
        DIOVersionDropDown            matlab.ui.control.DropDown
        DIOVersionDropDownLabel       matlab.ui.control.Label
        ProtocolDropDown              matlab.ui.control.DropDown
        ProtocolDropDownLabel         matlab.ui.control.Label
        ChanNumButtonGroup            matlab.ui.container.ButtonGroup
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        BlocksEditField               matlab.ui.control.EditField
        BlocksEditFieldLabel          matlab.ui.control.Label
        BpodProtocolDropDown          matlab.ui.control.DropDown
        BpodProtocolLabel             matlab.ui.control.Label
        DateDatePicker                matlab.ui.control.DatePicker
        DateDatePickerLabel           matlab.ui.control.Label
        SubjectTextArea               matlab.ui.control.TextArea
        SubjectTextAreaLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        kClass
        rClass
        Subject
        MEDProtocol
%         BpodProtocol
%         Blocks
        Date
        dirRCopy    = "/Volumes/Procyon/EphysSorting/Pavlov/SameNeuronCheck"
    end
    
    methods (Access = private)
        function flag = checkMeta(app)
            rpath = pwd;
            app.Date = string(datetime(app.DateDatePicker.Value, "Format", "uuuuMMdd"));
            app.Subject = string(app.SubjectTextArea.Value);
            app.MEDProtocol = string(app.ProtocolDropDown.Value);
            if ~any(ismember(split(rpath, '/'), app.Subject))
                errordlg("Please check current PATH or Subject NAME", "Path | NAME Error");
                flag = 0; return
            elseif app.Date ~= rpath(end-7:end)
                errordlg("Please check current PATH or DATE settings", "Path | Date Error");
                flag = 0; return
            else
                flag = 1;
            end
        end

        function switchBuildR(app, status)
            switch status
                case "on"
                    app.switchRprocess("off");
                    app.BuildRButton.Enable = "on";
                    app.LoadRButton.Enable = "on";
                    app.Lamp_BuildR.Visible = "on";
                    app.Lamp_BuildR.Color = "red";
                case "off"
                    app.BuildRButton.Enable = "off";
                    app.LoadRButton.Enable = "off";
                case "disable"
                    app.switchRprocess("off");
                    app.LoadRButton.Enable = "on";
                    app.BuildRButton.Enable = "on";
                    app.Lamp_BuildR.Visible = "off";
            end

        end
                
        function switchRprocess(app, status)
            switch status
                case "on"
                    app.switchBuildR("off");
                    app.Lamp_BuildR.Visible = "on";
                    app.Lamp_BuildR.Color = "green";

                    app.PlotallPSTHsButton.Enable = "on";
                    app.Lamp_PlotPSTHs.Color   = "red";
                    app.Lamp_PlotPSTHs.Visible = "on";
                    app.Lamp_PlotPoppulation.Color = "red";
                    app.Lamp_PlotPoppulation.Visible = "on";
                    app.PlotallPSTHsButton.Enable = "on";
                    app.PlotPopulationButton.Enable = "on";
                    app.PlotPSTHButton.Enable = "on";
                    app.CopyRButton.Enable = "on";
                    app.CopyRButton.FontColor = "k";
                case "off"
                    app.PlotallPSTHsButton.Enable = "off";
                    app.PlotPopulationButton.Enable = "off";
                    app.PlotPSTHButton.Enable = "off";
                    app.CopyRButton.Enable = "off";
                    app.CopyRButton.FontColor = "k";
                    app.Lamp_PlotPSTHs.Color = "red";
                    app.Lamp_PlotPSTHs.Visible = "off";
                    app.Lamp_PlotPoppulation.Color = "red";
                    app.Lamp_PlotPoppulation.Visible = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowresultsinFinderButton
        function ShowresultsinFinderButtonPushed(app, event)
            finderPath = fullfile(pwd, "Fig");
            [~,~] = mkdir(finderPath);
            finderCmd = 'open "'+ string(finderPath)+ '" -R';
            system(finderCmd, '-echo');
        end

        % Button pushed function: BuildRButton
        function BuildRButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end

            fname = arrayfun(@(x)x.name, dir("datafile00*.nev"), 'UniformOutput', false);
            blocks = cellfun(@(x) str2double(x(end-4)), fname, "UniformOutput", true);
            if ~isnan(app.BlocksEditField.Value)
                blocksin = str2double(split(app.BlocksEditField.Value, [" ", ",", "/"]));
                if any(~ismember(blocksin, blocks))
                    errordlg("Wrong 'Blocks' settings"); return
                else
                    blocks = blocksin;
                end
            end
            app.switchBuildR("off");
            app.Lamp_BuildR.Color = "yellow";
            drawnow;

            %% Check spks
            units = {};
            nChan = str2double(app.ChanNumButtonGroup.SelectedObject.Text);
            Electrodes = string(app.ElectrodesButtonGroup.SelectedObject.Text);
            switch Electrodes
                case "MWA"
                    for i = 1:nChan
                        ivalue = app.("Ch"+num2str(i)+"EditField").Value;
                        if ~isempty(ivalue)
                            idxNew = size(units, 1)+1;
                            units{idxNew, 1} = i; %#ok<*AGROW>
                            units{idxNew, 2} = ivalue;
                            units{idxNew, 3} = [];
                        end
                    end
                case "Tetrodes"
                    for i = 1:nChan/4
                        ivalue = app.("Tetrode"+num2str(i)+"EditField").Value;
                        if ~isempty(ivalue)
                            idxNew = size(units, 1)+1;
                            units{idxNew, 1} = i;
                            units{idxNew, 2} = ivalue;
                        end
                    end
            end


            %% Track and combine DIOEVENT from BR
            EventOutCombined = [];
            dBlockOnset = zeros(length(blocks), 1);
            for iBlock = 1:length(blocks)
                % open ‘datafile###.nev’, create “datafile###.mat”
                openNEV(char("datafile00"+blocks(iBlock)+".nev"), 'report', 'read')
                load("datafile00"+blocks(iBlock)+".mat", "NEV");
                if app.DebugModeCheckBox.Value
                    app.DIOVersionDropDown.Value = "V5"; drawnow;
                end
                switch app.DIOVersionDropDown.Value
                    case "V4"
                        EventOut = DIO_Events4(NEV); % create
                    case "V5"
                        EventOut = DIO_Events5(NEV); % create
                        % Poke signals are incorrect. Update poke from bpod.  10/4/2022
                        EventOut.Onset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                        EventOut.Offset{strcmp(EventOut.EventsLabels, 'Poke')} = [];
                end
                if iBlock == 1
                    RecordingOnset = EventOut.Meta.DateTimeRaw;
                else
                    idt = EventOut.Meta.DateTimeRaw - RecordingOnset;
                    dBlockOnset(iBlock) = idt(end)+idt(end-1)*1000+...
                        idt(end-2)*1000*60+idt(end-3)*1000*60*60;  %+idt(end-4)*1000*60*60*24
                end

                EventOut.Meta.Subject = app.Subject;
                EventOut.Meta.Experimenter = app.ExperTextArea.Value;
                EventOut.Meta.Protocol = app.ProtocolDropDown.Value;
                if isfield(EventOut, 'Subject')
                    EventOut = rmfield(EventOut, 'Subject');
                end
                if isfield(EventOut, 'Experimenter')
                    EventOut = rmfield(EventOut, 'Experimenter');
                end
                if iBlock == 1
                    EventOutCombined = EventOut;
                    EventOutCombined.EventsLabels = string(EventOutCombined.EventsLabels);
                    EventOutCombined = rmfield(EventOutCombined, 'TimeEvents');
                else
                    EventOutCombined.Meta(iBlock) = EventOut.Meta;
                    for k = 1:length(EventOutCombined.EventsLabels)
                        EventOutCombined.Onset{k}  = [EventOutCombined.Onset{k}; ...
                            EventOut.Onset{k}  + dBlockOnset(iBlock)];
                        EventOutCombined.Offset{k} = [EventOutCombined.Offset{k};...
                            EventOut.Offset{k} + dBlockOnset(iBlock)];
                    end
                end
            end

            %% Track Bpod data and align to BR
            BpodFile = dir(app.Subject+"*.mat");
            load(BpodFile.name, "SessionData");
            BpodEvents = TrackBpodBehavior(SessionData, app.BpodProtocolDropDown.Value);
            disp('**** Aligning Bpod to BlackRock ****');
            EventOutCombined = hbAlignBpod2BR(EventOutCombined, BpodEvents, ...
                app.BpodProtocolDropDown.Value, app.DebugModeCheckBox.Value);
            
            %% Track MED data and align to BR
            MEDFile = dir('*Subject*.txt');
            [bMED, BehaviorClass]= TrackMEDBehavior(MEDFile.name, app.ProtocolDropDown.Value);
            disp('**** Aligning MED to BlackRock ****');
            if app.DebugModeCheckBox.Value
                EventOutCombined = hbAlignMED2BRbyTrigger(EventOutCombined, bMED);
            end
            EventOutCombined = hbAlignMED2BR(EventOutCombined, bMED, app.ProtocolDropDown.Value);

            %% Construct R array with aligned behavior, spikes and LFP data
            % turn everything in minutes
            % single unit: 1; multiunit: 2
            r = struct;
            r.Meta = EventOutCombined.Meta;
            r.BehaviorClass = BehaviorClass;
            
            r.Behavior.Labels = ["FrameOn", "FrameOff", ...
                "LeverPress", "Trigger", "LeverRelease", ...
                "ValveOnset", "ValveOffset", "PokeOnset", "BadPokeOnset"];
            r.Behavior.LabelMarkers = 1:length(r.Behavior.Labels);
            
            r.Behavior.Outcome        = EventOutCombined.OutcomeEphys;
            r.Behavior.CorrectIndex   = find(r.Behavior.Outcome == "Correct");
            r.Behavior.PrematureIndex = find(r.Behavior.Outcome == "Premature");
            r.Behavior.LateIndex      = find(r.Behavior.Outcome == "Late");
            r.Behavior.DarkIndex      = find(r.Behavior.Outcome == "Dark");
            r.Behavior.Foreperiods    = EventOutCombined.FPEphys;
            r.Behavior.EventTimings   = [];
            r.Behavior.EventMarkers   = [];
            
            % add frame signal: 1 on, 2 off
            idxFrame = EventOutCombined.EventsLabels == 'Frame';
            EventOnset = EventOutCombined.Onset{idxFrame};
            EventOffset = EventOutCombined.Offset{idxFrame};
            EventMix = [EventOnset; EventOffset];
            idxEventMix = [ones(length(EventOnset), 1); ones(length(EventOffset), 1)*2];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; EventMix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; idxEventMix];

            % add leverpress onset and offset signal: 3 and 5
            idxLeverPress = EventOutCombined.EventsLabels == "LeverPress";
            EventOnset  = EventOutCombined.Onset{idxLeverPress};
            EventOffset = EventOutCombined.Offset{idxLeverPress};
            EventMix = [EventOnset; EventOffset];
            idxEventMix = [ones(length(EventOnset), 1)*3; ones(length(EventOffset),1)*5];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; EventMix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; idxEventMix];

            % add trigger stimulus signal: 4
            idxTrigger = EventOutCombined.EventsLabels == "Trigger";
            EventOnset = EventOutCombined.Onset{idxTrigger};
            EventMix = EventOnset;
            idxEventMix = ones(length(EventOnset), 1)*4;
            r.Behavior.EventTimings = [r.Behavior.EventTimings; EventMix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; idxEventMix];

            % add valve onset and offset signals: 6 and 7
            idxValve = EventOutCombined.EventsLabels == "Valve";
            EventOnset  = EventOutCombined.Onset{idxValve};
            EventOffset = EventOutCombined.Offset{idxValve};
            EventMix = [EventOnset; EventOffset];
            idxEventMix = [ones(length(EventOnset), 1)*6; ones(length(EventOffset), 1)*7];
            r.Behavior.EventTimings = [r.Behavior.EventTimings; EventMix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; idxEventMix];
            
            % add poke onset signals: 8
            idxPoke = EventOutCombined.EventsLabels == "Poke";
            EventOnset = EventOutCombined.Onset{idxPoke};
            EventMix = EventOnset;
            idxEventMix = ones(length(EventOnset), 1)*8;
            r.Behavior.EventTimings = [r.Behavior.EventTimings; EventMix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; idxEventMix];

            % add badpoke onset signals: 9
            idxBadPoke = EventOutCombined.EventsLabels == "BadPoke";
            EventOnset = EventOutCombined.Onset{idxBadPoke};
            EventMix = EventOnset;
            idxEventMix = ones(length(EventOnset), 1)*9;
            r.Behavior.EventTimings = [r.Behavior.EventTimings; EventMix];
            r.Behavior.EventMarkers = [r.Behavior.EventMarkers; idxEventMix];

            % sort timing
            [r.Behavior.EventTimings, idxTiming] = sort(r.Behavior.EventTimings);
            r.Behavior.EventMarkers = r.Behavior.EventMarkers(idxTiming);

            %% plot behavior data
            close all;

            SumFig = figure(2); clf(2, "reset");
            set(gcf, 'name', 'Summary of current R array', 'color', 'w', ...
                'unit', 'centimeters', 'position', [2 2 30 20], 'paperpositionmode', 'auto' );
            ha21 = axes;
            set(ha21, 'units', 'centimeters', 'position', [2.5 12 27 6], ...
                'nextplot', 'add', 'fontsize', 8,...
                'xlim', [0 max(r.Behavior.EventTimings/(1000))],...
                'ytick', 1:length(r.Behavior.Labels), 'yticklabel', r.Behavior.Labels);
            plot(r.Behavior.EventTimings/(1000), r.Behavior.EventMarkers, 'o',...
                'color', 'k','markersize', 4, 'linewidth', 1.5)
            line([0 max(r.Behavior.EventTimings/(1000))],...
                [1:length(r.Behavior.Labels); 1:length(r.Behavior.Labels)], 'color', [0.8 0.8 0.8]);
            ha21.XAxis.Visible = 'off';

            r.Units.Channels = nChan;
            r.Units.Profile    = units;
            r.Units.Definition = {'channel_id cluster_id unit_type polytrode',...
                '1: single unit', '2: multi unit'};
            r.Units.SpikeNotes = [];
            for i = 1:size(units, 1)
                for k = 1:length(units{i, 2})
                    switch units{i, 2}(k)
                        case 'm'
                            r.Units.SpikeNotes = [r.Units.SpikeNotes; units{i, 1} k 2 0];
                        case 's'
                            r.Units.SpikeNotes = [r.Units.SpikeNotes; units{i, 1} k 1 0];
                    end
                end
            end

            switch Electrodes
                case "MWA"
                    xmap = 1:nChan;
                    n = double(nChan/8);
                    xcoor = ones(1,n).*(1:8)'*64;
                    ycoor = (1:n).*ones(1,8)'*400;
                case "Tetrodes"
                    xmap = zeros(nChan, 1);
                    n = double(nChan/4);
                    for i = 1:nChan
                        if ismember(mod(i,8), [1 3 5 7])
                            xmap(i) = floor((double(i)-1)/8)*2+1;
                        else
                            xmap(i) = floor((double(i)-1)/8)*2+2;
                        end
                    end
                    xcoor = ones(1,n/2).*[1 1 2 2 3 3 4 4]'*64;
                    ycoor = xmap*400;
            end
            chanMap.xtrodeMap  = reshape(xmap,  [], nChan);
            chanMap.xcoor2plot = reshape(xcoor, [], nChan);
            chanMap.ycoor2plot = reshape(ycoor, [], nChan);
            r.Units.ChannelMap = chanMap;

            SpkChs = unique(r.Units.SpikeNotes(:, 1));
            allColors = varycolor(length(SpkChs));

            ha22 = axes;
            set(ha22, 'units', 'centimeters', 'position', [2.5 1 27 10.5], ...
                'nextplot', 'add', 'fontsize', 8,...
                'xlim', get(ha21, 'xlim'), ...
                'ylim', [0 size(r.Units.SpikeNotes , 1)+0.1]);
            linkaxes([ha21, ha22], 'x');
            xlabel('Time (sec)', 'FontSize', 10, 'FontName', 'Arial');
            ylabel('# Units', 'FontSize', 10, 'FontName', 'Arial');

            ha0 = axes;
            set(ha0, 'units', 'centimeters', 'position', [1.5 18 27 0.5]); axis off;
            title("Summary of "+app.Subject+"-"+string(bMED.Metadata.Date)+"-"+app.MEDProtocol, ...
                "FontSize", 12, "FontWeight", "bold", "FontName", "Arial");

            % load raw so we can have time
            raw = load(['chdat' num2str(r.Units.SpikeNotes(1, 1)) '.mat']);
            torg = raw.index; % also in ms
            torg = torg - torg(1);
            % put spikes
            for i = 1:size(r.Units.SpikeNotes, 1)

                r.Units.SpikeTimes(i) = struct('timings', [], 'wave', [], 'wave_mean', [], 'spk_id', []);

                iChannel = r.Units.SpikeNotes(i, 1);  % channel id (for tetrodes, it's the temporary tetrode id)
                iCluster = r.Units.SpikeNotes(i, 2);  % cluster id

                % load spike time:
                switch Electrodes
                    case "Tetrodes"
                        spk_id = load(['times_polytrode' num2str(iChannel) '.mat']);
                        channels = strrep(app.("ChsEditField_"+num2str(iChannel)).Value, ' ', '');
                        raw      = load(['chdat' channels(1) '.mat']);
                    case "MWA"
                        % if app.mean_subCheckBox.Value == 1
                        %     spk_id = load(['times_chdat_meansub' num2str(iChannel) '.mat']);
                        % else
                        spk_id = load(['times_chdat_meansub' num2str(iChannel) '.mat']);
                        raw    = load(['chdat' num2str(iChannel) '.mat']);
                        % end
                end
                tnew = (1:length(raw.index))*1000/30000;
                spk_in_ms = round((spk_id.cluster_class(spk_id.cluster_class(:, 1)==iCluster, 2)));
                [~, spkindx] = intersect(tnew, spk_in_ms);
                r.Units.SpikeTimes(i).timings = round(torg(spkindx)); % in ms
                r.Units.SpikeTimes(i).wave    = spk_id.spikes(spk_id.cluster_class(:, 1)==iCluster, :);
                meanWave = mean(r.Units.SpikeTimes(i).wave);
                sameProbe = find(r.Units.ChannelMap.xtrodeMap == iChannel);
                meanWave = reshape(meanWave, [], length(sameProbe));
                peakvalue = max(abs(meanWave));
                [~,idxpeak] = max(peakvalue);
                r.Units.SpikeNotes(i, 1) = sameProbe(idxpeak);
                mean_wave = nan(nChan, 64);
                for j = 1:length(sameProbe)
                    mean_wave(sameProbe(j),:) = meanWave(:,j);
                end
                r.Units.SpikeTimes(i).wave_mean = mean_wave;
                % r.Units.SpikeTimes(i).timings = [r.Units.SpikeTimes(i).timings;  spk_in_ms_new]; % in ms
                % these_waves                   = spk_id.spikes(spk_id.cluster_class(:, 1)==iCluster, :); 
                % r.Units.SpikeTimes(i).wave    = [r.Units.SpikeTimes(i).wave;  these_waves];

                r.Units.SpikeTimes(i).spk_id = 1:length(r.Units.SpikeTimes(i).timings);
                if length(r.Units.SpikeTimes(i).timings) >= 10000
                    prctRemove = 0.9;
                else
                    prctRemove = 1-1000/length(r.Units.SpikeTimes(i).timings);
                end

                if prctRemove > 0
                    idxRemove = randperm(length(r.Units.SpikeTimes(i).timings),...
                        round(length(r.Units.SpikeTimes(i).timings)*prctRemove));
                    r.Units.SpikeTimes(i).spk_id(idxRemove) = [];
                    r.Units.SpikeTimes(i).wave(idxRemove, :) = [];
                end

                x_plot = r.Units.SpikeTimes(i).timings/1000;
                y_plot =  i - 1 + 0.8*rand(1, length(x_plot));
                if ~isempty(x_plot)
                    plot(ha22, x_plot, y_plot, '.', ...
                        'color', allColors(SpkChs == iChannel, :), 'markersize', 4);
                end
            end
            set(ha22, 'xlim', [0 max(r.Behavior.EventTimings/(1000))]);

            tic
            app.rClass = hbRClass(r);
            app.rClass.save();
%             save(savename, 'r', '-v7.3');

            [~,~] = mkdir(pwd,"Fig");
            savename = fullfile(pwd,'Fig', ...
                "RTarrayAll_"+app.Subject+"_"+string(bMED.Metadata.Date)+"_"+app.ProtocolDropDown.Value);
            
            saveas(SumFig,savename,'fig');
            print(SumFig,'-dpng',savename);
            print(SumFig,'-depsc2',savename);
            toc

            disp('********************');
            disp('**** COME R! :) ****');
            disp('********************');

            app.switchRprocess("on");
        end

        % Button pushed function: LoadRButton
        function LoadRButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end
            fname = uigetfile("RClass*.mat", "MultiSelect", "off");
            if any(fname)
                rc = load(fname, "rClass");
            else
                return
            end
            app.rClass = rc.rClass;
            app.switchRprocess("on");
        end

        % Button pushed function: InitiateRButton
        function InitiateRButtonPushed(app, event)
            app.rClass = [];
            if isempty(app.kClass)
                app.switchBuildR("disable");
            else
                app.switchBuildR("on");
            end
        end

        % Button pushed function: PlotallPSTHsButton
        function PlotallPSTHsButtonPushed(app, event)
            app.PlotallPSTHsButton.Enable = "off";
            app.Lamp_PlotPSTHs.Color = "yellow";
            drawnow;
            delete(fullfile(pwd, "/Fig/PSTH", "*.png"));
            app.rClass.plotPSTH4All("sortRaster", app.SortrasterCheckBox.Value);
            app.Lamp_PlotPSTHs.Color = "green";
        end

        % Button pushed function: PlotPSTHButton
        function PlotPSTHButtonPushed(app, event)
            if ~isempty(app.UnitIDEditField.Value)
                charin = split(app.UnitIDEditField.Value, {'[', ' ', ',', ']'});
                idxUnit = charin(cellfun(@(x) ~isempty(x), charin));
                idxUnit = cellfun(@(x) str2double(x), idxUnit');
                app.rClass.plotPSTH(idxUnit);
            else
                errordlg("Set UnitID before @plotPSTH");
                return
            end
        end

        % Button pushed function: PlotPopulationButton
        function PlotPopulationButtonPushed(app, event)
            app.PlotPopulationButton.Enable = "off";
            app.Lamp_PlotPoppulation.Color = "yellow"; drawnow;
            app.rClass.plotPopulationPSTH();
            app.Lamp_PlotPoppulation.Color = "green";
            app.CopyRButton.Enable = "on";
        end

        % Button pushed function: CopyRButton
        function CopyRButtonPushed(app, event)
            newobj = app.rClass;
            newobj.prepare4SameNeuronCheck("targetPath", app.dirRCopy);
            app.CopyRButton.Enable = "off";
            app.CopyRButton.FontColor = "green";
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            app.Date = string(datetime(value, "Format", "uuuuMMdd"));
        end

        % Button pushed function: RoutdirButton
        function RoutdirButtonPushed(app, event)
            newPath = uigetdir(pwd);
            if any(newPath)
                app.dirRCopy = newPath;
                app.RoutputdirLabel.Text = newPath;
            else
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetaPanel = uipanel(app.UIFigure);
            app.MetaPanel.Title = '1. Meta';
            app.SubjectTextAreaLabel = uilabel(app.MetaPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.MetaPanel);
            app.SubjectTextArea.Value = {'Orion'};
            app.DateDatePickerLabel = uilabel(app.MetaPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.MetaPanel);
            app.DateDatePicker.DisplayFormat = 'uuuu/MM/dd';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Value = datetime([2023 1 11]);
            app.BpodProtocolLabel = uilabel(app.MetaPanel);
            app.BpodProtocolLabel.Text = 'Bpod Protocol';
            app.BpodProtocolDropDown = uidropdown(app.MetaPanel);
            app.BpodProtocolDropDown.Items = {'MedOptoRecording', 'MedLick'};
            app.BpodProtocolDropDown.Value = 'MedOptoRecording';
            app.BlocksEditFieldLabel = uilabel(app.MetaPanel);
            app.BlocksEditFieldLabel.Text = 'Blocks';
            app.BlocksEditField = uieditfield(app.MetaPanel, 'text');
            app.ChanNumButtonGroup = uibuttongroup(app.MetaPanel);
            app.ChanNumButtonGroup.Title = 'ChanNum';
            app.Button = uiradiobutton(app.ChanNumButtonGroup);
            app.Button.Text = '16';
            app.Button_2 = uiradiobutton(app.ChanNumButtonGroup);
            app.Button_2.Text = '32';
            app.Button_2.Value = true;
            app.ProtocolDropDownLabel = uilabel(app.MetaPanel);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.MetaPanel);
            app.ProtocolDropDown.Items = {'Wait1', 'Wait2', '2FPs', '3FPs'};
            app.ProtocolDropDown.Value = 'Wait2';
            app.DIOVersionDropDownLabel = uilabel(app.MetaPanel);
            app.DIOVersionDropDownLabel.Text = 'DIO Version';
            app.DIOVersionDropDown = uidropdown(app.MetaPanel);
            app.DIOVersionDropDown.Items = {'V4', 'V5'};
            app.DIOVersionDropDown.Value = 'V4';
            app.ShowresultsinFinderButton = uibutton(app.MetaPanel, 'push');
            app.ShowresultsinFinderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowresultsinFinderButtonPushed, true);
            app.ShowresultsinFinderButton.Text = {'Show results'; 'in Finder'};
            app.ElectrodesButtonGroup = uibuttongroup(app.MetaPanel);
            app.ElectrodesButtonGroup.Title = 'Electrodes';
            app.MWAButton = uiradiobutton(app.ElectrodesButtonGroup);
            app.MWAButton.Text = 'MWA';
            app.MWAButton.Value = true;
            app.TetrodesButton = uiradiobutton(app.ElectrodesButtonGroup);
            app.TetrodesButton.Text = 'Tetrodes';
            app.ExperTextAreaLabel = uilabel(app.MetaPanel);
            app.ExperTextAreaLabel.Text = 'Exp.er';
            app.ExperTextArea = uitextarea(app.MetaPanel);
            app.ExperTextArea.Value = {'hbWang'};
            app.DebugModeCheckBox = uicheckbox(app.MetaPanel);
            app.DebugModeCheckBox.Text = 'DebugMode';
            app.BuildRClassandplotPSTHsPanel = uipanel(app.UIFigure);
            app.BuildRClassandplotPSTHsPanel.Title = '3. Build RClass and plot PSTHs';
            app.BuildRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.BuildRButton.ButtonPushedFcn = createCallbackFcn(app, @BuildRButtonPushed, true);
            app.BuildRButton.Text = 'Build R';
            app.LoadRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.LoadRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRButtonPushed, true);
            app.LoadRButton.Text = 'Load R';
            app.Lamp_BuildR = uilamp(app.BuildRClassandplotPSTHsPanel);
            app.Lamp_BuildR.Visible = 'off';
            app.Lamp_PlotPSTHs = uilamp(app.BuildRClassandplotPSTHsPanel);
            app.Lamp_PlotPSTHs.Visible = 'off';
            app.PlotallPSTHsButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.PlotallPSTHsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallPSTHsButtonPushed, true);
            app.PlotallPSTHsButton.Enable = 'off';
            app.PlotallPSTHsButton.Text = {'Plot all'; 'PSTHs'};
            app.InitiateRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.InitiateRButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateRButtonPushed, true);
            app.InitiateRButton.Text = 'Initiate R';
            app.Lamp_PlotPoppulation = uilamp(app.BuildRClassandplotPSTHsPanel);
            app.Lamp_PlotPoppulation.Visible = 'off';
            app.PlotPopulationButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.PlotPopulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPopulationButtonPushed, true);
            app.PlotPopulationButton.Enable = 'off';
            app.PlotPopulationButton.Text = {'Plot'; 'Population'};
            app.PlotPSTHButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.PlotPSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSTHButtonPushed, true);
            app.PlotPSTHButton.Enable = 'off';
            app.PlotPSTHButton.Text = 'Plot PSTH';
            app.UnitIDEditFieldLabel = uilabel(app.BuildRClassandplotPSTHsPanel);
            app.UnitIDEditFieldLabel.Text = 'UnitID';
            app.UnitIDEditField = uieditfield(app.BuildRClassandplotPSTHsPanel, 'text');
            app.CopyRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.CopyRButton.ButtonPushedFcn = createCallbackFcn(app, @CopyRButtonPushed, true);
            app.CopyRButton.Enable = 'off';
            app.CopyRButton.Text = 'Copy R';
            app.SortrasterCheckBox = uicheckbox(app.BuildRClassandplotPSTHsPanel);
            app.SortrasterCheckBox.Text = 'Sort raster';
            app.SortrasterCheckBox.Value = true;
            app.RoutdirButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.RoutdirButton.ButtonPushedFcn = createCallbackFcn(app, @RoutdirButtonPushed, true);
            app.RoutdirButton.Text = 'R out dir';
            app.RoutputdirLabel = uilabel(app.BuildRClassandplotPSTHsPanel);
            app.RoutputdirLabel.Text = '/Volumes/Procyon/EphysSorting/Pavlov/SameNeuronCheck';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MWATab = uitab(app.TabGroup);
            app.MWATab.Title = 'MWA';
            app.Ch1EditFieldLabel = uilabel(app.MWATab);
            app.Ch1EditFieldLabel.Text = 'Ch1';
            app.Ch1EditField = uieditfield(app.MWATab, 'text');
            app.Ch2EditFieldLabel = uilabel(app.MWATab);
            app.Ch2EditFieldLabel.Text = 'Ch2';
            app.Ch2EditField = uieditfield(app.MWATab, 'text');
            app.Ch3EditFieldLabel = uilabel(app.MWATab);
            app.Ch3EditFieldLabel.Text = 'Ch3';
            app.Ch3EditField = uieditfield(app.MWATab, 'text');
            app.Ch4EditFieldLabel = uilabel(app.MWATab);
            app.Ch4EditFieldLabel.Text = 'Ch4';
            app.Ch4EditField = uieditfield(app.MWATab, 'text');
            app.Ch5EditFieldLabel = uilabel(app.MWATab);
            app.Ch5EditFieldLabel.Text = 'Ch5';
            app.Ch5EditField = uieditfield(app.MWATab, 'text');
            app.Ch6EditFieldLabel = uilabel(app.MWATab);
            app.Ch6EditFieldLabel.Text = 'Ch6';
            app.Ch6EditField = uieditfield(app.MWATab, 'text');
            app.Ch7EditFieldLabel = uilabel(app.MWATab);
            app.Ch7EditFieldLabel.Text = 'Ch7';
            app.Ch7EditField = uieditfield(app.MWATab, 'text');
            app.Ch8EditFieldLabel = uilabel(app.MWATab);
            app.Ch8EditFieldLabel.Text = 'Ch8';
            app.Ch8EditField = uieditfield(app.MWATab, 'text');
            app.Ch9Label = uilabel(app.MWATab);
            app.Ch9Label.Text = 'Ch9';
            app.Ch9EditField = uieditfield(app.MWATab, 'text');
            app.Ch10EditFieldLabel = uilabel(app.MWATab);
            app.Ch10EditFieldLabel.Text = 'Ch10';
            app.Ch10EditField = uieditfield(app.MWATab, 'text');
            app.Ch11EditFieldLabel = uilabel(app.MWATab);
            app.Ch11EditFieldLabel.Text = 'Ch11';
            app.Ch11EditField = uieditfield(app.MWATab, 'text');
            app.Ch12EditFieldLabel = uilabel(app.MWATab);
            app.Ch12EditFieldLabel.Text = 'Ch12';
            app.Ch12EditField = uieditfield(app.MWATab, 'text');
            app.Ch13EditFieldLabel = uilabel(app.MWATab);
            app.Ch13EditFieldLabel.Text = 'Ch13';
            app.Ch13EditField = uieditfield(app.MWATab, 'text');
            app.Ch14EditFieldLabel = uilabel(app.MWATab);
            app.Ch14EditFieldLabel.Text = 'Ch14';
            app.Ch14EditField = uieditfield(app.MWATab, 'text');
            app.Ch15EditFieldLabel = uilabel(app.MWATab);
            app.Ch15EditFieldLabel.Text = 'Ch15';
            app.Ch15EditField = uieditfield(app.MWATab, 'text');
            app.Ch16EditFieldLabel = uilabel(app.MWATab);
            app.Ch16EditFieldLabel.Text = 'Ch16';
            app.Ch16EditField = uieditfield(app.MWATab, 'text');
            app.Ch17EditFieldLabel = uilabel(app.MWATab);
            app.Ch17EditFieldLabel.Text = 'Ch17';
            app.Ch17EditField = uieditfield(app.MWATab, 'text');
            app.Ch18EditFieldLabel = uilabel(app.MWATab);
            app.Ch18EditFieldLabel.Text = 'Ch18';
            app.Ch18EditField = uieditfield(app.MWATab, 'text');
            app.Ch19EditFieldLabel = uilabel(app.MWATab);
            app.Ch19EditFieldLabel.Text = 'Ch19';
            app.Ch19EditField = uieditfield(app.MWATab, 'text');
            app.Ch20EditFieldLabel = uilabel(app.MWATab);
            app.Ch20EditFieldLabel.Text = 'Ch20';
            app.Ch20EditField = uieditfield(app.MWATab, 'text');
            app.Ch21EditFieldLabel = uilabel(app.MWATab);
            app.Ch21EditFieldLabel.Text = 'Ch21';
            app.Ch21EditField = uieditfield(app.MWATab, 'text');
            app.Ch22EditFieldLabel = uilabel(app.MWATab);
            app.Ch22EditFieldLabel.Text = 'Ch22';
            app.Ch22EditField = uieditfield(app.MWATab, 'text');
            app.Ch23EditFieldLabel = uilabel(app.MWATab);
            app.Ch23EditFieldLabel.Text = 'Ch23';
            app.Ch23EditField = uieditfield(app.MWATab, 'text');
            app.Ch24EditFieldLabel = uilabel(app.MWATab);
            app.Ch24EditFieldLabel.Text = 'Ch24';
            app.Ch24EditField = uieditfield(app.MWATab, 'text');
            app.Ch25EditFieldLabel = uilabel(app.MWATab);
            app.Ch25EditFieldLabel.Text = 'Ch25';
            app.Ch25EditField = uieditfield(app.MWATab, 'text');
            app.Ch26EditFieldLabel = uilabel(app.MWATab);
            app.Ch26EditFieldLabel.Text = 'Ch26';
            app.Ch26EditField = uieditfield(app.MWATab, 'text');
            app.Ch27EditFieldLabel = uilabel(app.MWATab);
            app.Ch27EditFieldLabel.Text = 'Ch27';
            app.Ch27EditField = uieditfield(app.MWATab, 'text');
            app.Ch28EditFieldLabel = uilabel(app.MWATab);
            app.Ch28EditFieldLabel.Text = 'Ch28';
            app.Ch28EditField = uieditfield(app.MWATab, 'text');
            app.Ch29EditFieldLabel = uilabel(app.MWATab);
            app.Ch29EditFieldLabel.Text = 'Ch29';
            app.Ch29EditField = uieditfield(app.MWATab, 'text');
            app.Ch30EditFieldLabel = uilabel(app.MWATab);
            app.Ch30EditFieldLabel.Text = 'Ch30';
            app.Ch30EditField = uieditfield(app.MWATab, 'text');
            app.Ch31EditFieldLabel = uilabel(app.MWATab);
            app.Ch31EditFieldLabel.Text = 'Ch31';
            app.Ch31EditField = uieditfield(app.MWATab, 'text');
            app.Ch32EditFieldLabel = uilabel(app.MWATab);
            app.Ch32EditFieldLabel.Text = 'Ch32';
            app.Ch32EditField = uieditfield(app.MWATab, 'text');
            app.ClearButton = uibutton(app.MWATab, 'push');
            app.ClearButton.Text = 'Clear';
            app.TetrodeTab = uitab(app.TabGroup);
            app.TetrodeTab.Title = 'Tetrode';
            app.Tetrode1EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode1EditFieldLabel.Text = 'Tetrode1';
            app.Tetrode1EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode2EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode2EditFieldLabel.Text = 'Tetrode2';
            app.Tetrode2EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode3EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode3EditFieldLabel.Text = 'Tetrode3';
            app.Tetrode3EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode4EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode4EditFieldLabel.Text = 'Tetrode4';
            app.Tetrode4EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode5EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode5EditFieldLabel.Text = 'Tetrode5';
            app.Tetrode5EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode6EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode6EditFieldLabel.Text = 'Tetrode6';
            app.Tetrode6EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode7EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode7EditFieldLabel.Text = 'Tetrode7';
            app.Tetrode7EditField = uieditfield(app.TetrodeTab, 'text');
            app.Tetrode8EditFieldLabel = uilabel(app.TetrodeTab);
            app.Tetrode8EditFieldLabel.Text = 'Tetrode8';
            app.Tetrode8EditField = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditFieldLabel = uilabel(app.TetrodeTab);
            app.ChsEditFieldLabel.Text = 'Chs';
            app.ChsEditField_1 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_1.Value = '1 3 5 7';
            app.ChsEditField_2Label = uilabel(app.TetrodeTab);
            app.ChsEditField_2Label.Text = 'Chs';
            app.ChsEditField_2 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_2.Value = '2 4 6 8';
            app.ChsEditField_3Label = uilabel(app.TetrodeTab);
            app.ChsEditField_3Label.Text = 'Chs';
            app.ChsEditField_3 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_3.Value = '9 11 13 15';
            app.ChsEditField_4Label = uilabel(app.TetrodeTab);
            app.ChsEditField_4Label.Text = 'Chs';
            app.ChsEditField_4 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_4.Value = '10 12 14 16';
            app.ChsEditField_5Label = uilabel(app.TetrodeTab);
            app.ChsEditField_5Label.Text = 'Chs';
            app.ChsEditField_5 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_5.Value = '17 19 21 23';
            app.ChsEditField_6Label = uilabel(app.TetrodeTab);
            app.ChsEditField_6Label.Text = 'Chs';
            app.ChsEditField_6 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_6.Value = '18 20 22 24';
            app.ChsEditField_7Label = uilabel(app.TetrodeTab);
            app.ChsEditField_7Label.Text = 'Chs';
            app.ChsEditField_7 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_7.Value = '25 27 29 31';
            app.ChsEditField_8Label = uilabel(app.TetrodeTab);
            app.ChsEditField_8Label.Text = 'Chs';
            app.ChsEditField_8 = uieditfield(app.TetrodeTab, 'text');
            app.ChsEditField_8.Value = '26 28 30 32';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hbBuildR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end