classdef hbSpikeSortingPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SpikeCheckerPanel             matlab.ui.container.Panel
        XButtonGroup                  matlab.ui.container.ButtonGroup
        XLabel                        matlab.ui.control.Label
        TimeButton                    matlab.ui.control.ToggleButton
        XPC1Button                    matlab.ui.control.ToggleButton
        XPC2Button                    matlab.ui.control.ToggleButton
        HeatmapButton                 matlab.ui.control.StateButton
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        NewButton                     matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ResetSpikeCheckerButton       matlab.ui.control.Button
        Lamp_SpikeChecker             matlab.ui.control.Lamp
        SpkNumberEditField            matlab.ui.control.NumericEditField
        SpkNumberEditFieldLabel       matlab.ui.control.Label
        UndoButton                    matlab.ui.control.Button
        KeepButton                    matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        USpinner                      matlab.ui.control.Spinner
        ULabel                        matlab.ui.control.Label
        UnitList                      matlab.ui.control.Table
        YButtonGroup                  matlab.ui.container.ButtonGroup
        YLabel                        matlab.ui.control.Label
        AmpButton                     matlab.ui.control.ToggleButton
        YPC1Button                    matlab.ui.control.ToggleButton
        YPC2Button                    matlab.ui.control.ToggleButton
        UIAxesAutoCorr                matlab.ui.control.UIAxes
        UIAxesScatter                 matlab.ui.control.UIAxes
        UIAxesSpikes                  matlab.ui.control.UIAxes
        UIAxesISI                     matlab.ui.control.UIAxes
        BuildRClassandplotPSTHsPanel  matlab.ui.container.Panel
        RoutputdirLabel               matlab.ui.control.Label
        RoutputdirButton              matlab.ui.control.Button
        SortrasterCheckBox            matlab.ui.control.CheckBox
        CopyRButton                   matlab.ui.control.Button
        UnitIDEditField               matlab.ui.control.EditField
        UnitIDEditFieldLabel          matlab.ui.control.Label
        PlotPSTHButton                matlab.ui.control.Button
        PlotPopulationButton          matlab.ui.control.Button
        Lamp_PlotPoppulation          matlab.ui.control.Lamp
        InitiateRButton               matlab.ui.control.Button
        PlotallPSTHsButton            matlab.ui.control.Button
        Lamp_PlotPSTHs                matlab.ui.control.Lamp
        Lamp_BuildR                   matlab.ui.control.Lamp
        LoadRButton                   matlab.ui.control.Button
        BuildRButton                  matlab.ui.control.Button
        MetaPanel                     matlab.ui.container.Panel
        DebugModeCheckBox             matlab.ui.control.CheckBox
        BlocksEditField               matlab.ui.control.EditField
        BlocksEditFieldLabel          matlab.ui.control.Label
        DIODropDown                   matlab.ui.control.DropDown
        DIODropDownLabel              matlab.ui.control.Label
        ShowresultsinFinderButton     matlab.ui.control.Button
        BpodProtocolDropDown          matlab.ui.control.DropDown
        BpodProtocolLabel             matlab.ui.control.Label
        ProtocolDropDown              matlab.ui.control.DropDown
        ProtocolDropDownLabel         matlab.ui.control.Label
        ElectrodesDropDown            matlab.ui.control.DropDown
        ElectrodesDropDownLabel       matlab.ui.control.Label
        DateDatePicker                matlab.ui.control.DatePicker
        DateDatePickerLabel           matlab.ui.control.Label
        SubjectTextArea               matlab.ui.control.TextArea
        SubjectTextAreaLabel          matlab.ui.control.Label
        BuildKClassandchecksortresultsPanel  matlab.ui.container.Panel
        KSoutputdirButton             matlab.ui.control.Button
        KSoutputdirLabel              matlab.ui.control.Label
        RestartphyforkilosortButton   matlab.ui.control.Button
        ThresholdSpinner              matlab.ui.control.Spinner
        ThresholdSpinnerLabel         matlab.ui.control.Label
        InitiateKButton               matlab.ui.control.Button
        Lamp_CheckSimilarity          matlab.ui.control.Lamp
        Lamp_PlotSortResults          matlab.ui.control.Lamp
        Lamp_BuildK                   matlab.ui.control.Lamp
        PlotsortresultsButton         matlab.ui.control.Button
        LoadKButton                   matlab.ui.control.Button
        ChecksimilarityButton         matlab.ui.control.Button
        BuildKButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        kClass
        rClass
        Subject
%         MEDProtocol
%         BpodProtocol
%         Blocks
        Date
        kClassName
        dirKSOutput = "/kilosort2_5_output/sorter_output"
        dirRCopy    = "/Volumes/Procyon/EphysSorting/Pavlov/SameNeuronCheck"
    end
    
    methods (Access = private)
        function flag = checkMeta(app)
            rpath = pwd;
            app.Date = string(datetime(app.DateDatePicker.Value, "Format", "uuuuMMdd"));
            app.Subject = string(app.SubjectTextArea.Value);
            if ~any(ismember(split(rpath, filesep), app.Subject))
                errordlg("Please check current PATH or Subject NAME", "Path | NAME Error");
                flag = 0; return
            elseif app.Date ~= rpath(end-7:end)
                errordlg("Please check current PATH or DATE settings", "Path | Date Error");
                flag = 0; return
            else
                flag = 1;
            end
        end

        function plotInApp(app)

            kTable = app.kClass.SpikeTable;
            allWaves = kTable.waveforms{app.USpinner.Value};
            tSpikes = round(kTable.spike_times_r{app.USpinner.Value});

            nSpikes = kTable.n_spikes(app.USpinner.Value);
            nPlot = min([app.SpkNumberEditField.Value, nSpikes]);
            idxPlot = randperm(nSpikes, nPlot);

            % Raw spikes
            app.UIAxesSpikes.UserData = {allWaves, idxPlot};
            app.plotSpikes();
            
            %% plot spk properties
            tSpk2 = zeros(1, tSpikes(end));
            tSpk2(tSpikes) = 1;
            [c, lags] = xcorr(tSpk2, 50); % max lag 25 ms
            c(lags==0) = 0;

            cla(app.UIAxesAutoCorr);
            bar(app.UIAxesAutoCorr, lags, c);

            ISI = diff(tSpikes);
            rViolation = 100*sum(ISI<3)/length(tSpikes);

            cla(app.UIAxesISI)
            histogram(app.UIAxesISI, ISI, 0:1:50);
            app.UIAxesISI.Title.String = "ISI<3ms: "+num2str(rViolation)+"%";

            %% plot scatter of Amp/PCs vs. Time/PCs
            [~, score] = pca(allWaves);
            spikePeak = min(allWaves, [], 2);
            spikeTrough = max(allWaves, [], 2);
            if abs(median(spikePeak)) > abs(median(spikeTrough))
                spikeAmp = spikePeak;
            else
                spikeAmp = spikeTrough;
            end
            app.UIAxesScatter.UserData = {score, tSpikes, spikeAmp, idxPlot, []};
            app.plotScatter();

            % update unit type
            unitType = kTable.group{app.USpinner.Value};
            switch unitType
                case "good"
                    app.TypeDropDown.Value = "Single";
                case "mua"
                    app.TypeDropDown.Value = "Mua";
                case "noise"
                    app.TypeDropDown.Value = "Noise";
            end

            app.SelectButton.Enable = "on";
            app.KeepButton.Enable = "off";
            app.NewButton.Enable = "off";
            app.UndoButton.Enable = "off";

        end

        function plotSpikes(app)

            allWaves = app.UIAxesSpikes.UserData{1};
            idxPlot = app.UIAxesSpikes.UserData{2};
            smplSpikes = allWaves(idxPlot, :);
            spkLength = size(smplSpikes, 2);

            cla(app.UIAxesSpikes);
            app.UIAxesSpikes.NextPlot = 'add';

            if ~app.HeatmapButton.Value
                plot(app.UIAxesSpikes, 1:spkLength, smplSpikes, 'color', 'b');
                app.UIAxesSpikes.YLim = [1.2*min(smplSpikes(:)) 1.2*max(smplSpikes(:))];
                app.UIAxesSpikes.XLim = [1 spkLength];
                app.UIAxesSpikes.Title.String = "Unit #"+num2str(app.USpinner.Value)+...
                    " | Spk number "+num2str(size(allWaves, 1));
            else % Heatmap
                yEdges = (1.3*min(allWaves,[],2)):40:(1.3*max(allWaves,[],2)); % 10 uV/bin
                spkWaveCount = zeros(length(yEdges)-1, spkLength);
                for k = 1:spkLength
                    spkWaveCount(:,k) = histcounts(allWaves(:,k), yEdges);
                end
                imagesc(app.UIAxesSpikes, 1:spkLength, yEdges(1:end)+5, spkWaveCount);
                app.UIAxesSpikes.YLim = [yEdges(1) yEdges(end)];
                app.UIAxesSpikes.YDir = 'normal';
                app.UIAxesSpikes.XLim = [1 spkLength];
                app.UIAxesSpikes.Title.String = "Unit #"+num2str(app.USpinner.Value)+...
                    " | Spk number "+num2str(size(allWaves, 1));
            end
        end

        function plotScatter(app)
            score    = app.UIAxesScatter.UserData{1};
            tSpikes  = app.UIAxesScatter.UserData{2};
            spikeAmp = app.UIAxesScatter.UserData{3};
            idxPlot  = app.UIAxesScatter.UserData{4};
            idxInsidePlot = app.UIAxesScatter.UserData{5};

            cla(app.UIAxesScatter); app.UIAxesScatter.NextPlot = 'add';

            % Get x value to plot
            switch app.XButtonGroup.SelectedObject.Text
                case "Time"
                    smplXvalue = tSpikes(idxPlot);
                case "PC1"
                    smplXvalue = score(idxPlot, 1);
                case "PC2"
                    smplXvalue = score(idxPlot, 2);
            end
            % Get y value to plot
            switch app.YButtonGroup.SelectedObject.Text
                case "Amp."
                    smplYvalue = spikeAmp(idxPlot);
                case "PC1"
                    smplYvalue = score(idxPlot, 1);
                case "PC2"
                    smplYvalue = score(idxPlot, 2);
            end
            scatter(app.UIAxesScatter, smplXvalue, smplYvalue, 30, 'filled', 'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5);
            plot(app.UIAxesScatter, smplXvalue(idxInsidePlot), smplYvalue(idxInsidePlot), 'rx');
            % app.UIAxesScatter.XLim = [-100000 max(smplXvalue)+100000];
        end

        function updateTable(app)

            kTable = app.kClass.SpikeTable;
            newTable = removevars(kTable, {'Amplitude', 'ContamPct', 'KSLabel', 'depth', 'fr', 'amp',...
                'sh', 'spike_times_r', 'waveforms', 'waveforms_mean', 'spike_times', 'spike_times_r'});
            newTable.index = (1:length(newTable.cluster_id))';
            app.UnitList.Data = newTable(:, ["index","ch","group","cluster_id","n_spikes"]);
            app.USpinner.Limits = [1 size(app.UnitList.Data, 1)];

        end

        function spikeTable = updateSpikeTable(~, spikeTable, ops)

            nCluster = height(spikeTable);
            waveforms_tbl = cell(nCluster, 1);
            waveforms_mean_tbl = cell(nCluster, 1);
            ch_tbl = cell(nCluster, 1);
            for k = 1:nCluster
                tic
                [filepath,name,ext]     = fileparts(ops.fproc);
                gwfparams.dataDir       = filepath;    % KiloSort/Phy output folder
                gwfparams.fileName      = [name, ext]; % .dat file containing the raw 
                gwfparams.dataType      = 'int16';     % Data type of .dat file (this should be BP filtered)
                gwfparams.nCh           = ops.Nchan;   % Number of channels that were streamed to disk in .dat file
                gwfparams.wfWin         = [-31 32];    % Number of samples before and after spiketime to include in waveform
                % Number of waveforms per unit to pull out
                gwfparams.nWf           = length(spikeTable(k,:).spike_times{1});
                % Vector of cluster spike times (in samples), same length as .spikeClusters
                gwfparams.spikeTimes    = spikeTable(k,:).spike_times{1};
                % Vector of cluster IDs (Phy nomenclature), same length as .spikeTimes
                gwfparams.spikeClusters = ones(length(spikeTable(k,:).spike_times{1}),1); 
            
                wf = getWaveforms(gwfparams);
            
                amp_ch = max(squeeze(wf.waveFormsMean),[],2)-min(squeeze(wf.waveFormsMean),[],2);
                [~, ch_amp_largest] = max(amp_ch);
                ch_tbl{k} = ch_amp_largest;
                
                waveforms_tbl{k} = squeeze(wf.waveForms(:,:,ch_amp_largest,:));
                waveforms_mean_tbl{k} = squeeze(wf.waveFormsMean);
                toc
            end
            
            spikeTable.waveforms = waveforms_tbl;
            spikeTable.waveforms_mean = waveforms_mean_tbl;
            spikeTable.ch = ch_tbl;
            spikeTable = sortrows(spikeTable,{'ch','group'});

        end

        function switchBuildK(app, status)
            switch status
                case "on"
                    app.BuildKButton.Enable = "on";
                    app.LoadKButton.Enable = "on";
                    app.Lamp_BuildK.Color = "red";
                    app.Lamp_BuildK.Visible = "on";
                    app.switchKprocess("off");
                case "off"
                    app.BuildKButton.Enable = "off";
                    app.LoadKButton.Enable = "off";
            end
        end

        function switchKprocess(app, status)
            switch status
                case "on"
                    % Lamps
                    app.switchBuildK("off");
                    app.Lamp_BuildK.Color = "green";

                    app.PlotsortresultsButton.Enable = "on";
                    app.Lamp_PlotSortResults.Color   = "red";
                    app.Lamp_PlotSortResults.Visible = "on";

                    app.ChecksimilarityButton.Enable = "on";
                    app.Lamp_CheckSimilarity.Color   = "red";
                    app.Lamp_CheckSimilarity.Visible = "on";

                    app.switchSpikeChecker("on");
                    app.ResetSpikeCheckerButtonPushed();

                case "off"
                    app.PlotsortresultsButton.Enable = "off";
                    app.Lamp_PlotSortResults.Color   = "red";
                    app.Lamp_PlotSortResults.Visible = "off";

                    app.ChecksimilarityButton.Enable = "off";
                    app.Lamp_CheckSimilarity.Color   = "red";
                    app.Lamp_CheckSimilarity.Visible = "off";
%                     app.RestartphyforkilosortButton.Enable = "off";
                    app.switchSpikeChecker("off");
            end  
        end

        function switchSpikeChecker(app, status)
            switch status
                case "on"
                    app.USpinner.Enable = "on";
                    app.ResetSpikeCheckerButton.Enable = "on";
                    app.Lamp_SpikeChecker.Visible = "on";
                    app.SelectButton.Enable = "on";
                    app.SaveButton.Enable = "off";
                case "off"
                    app.USpinner.Enable = "off";
                    app.ResetSpikeCheckerButton.Enable = "off";
                    app.Lamp_SpikeChecker.Visible = "off";
                    app.SelectButton.Enable = "off";
                    app.KeepButton.Enable = "off";
                    app.NewButton.Enable = "off";
                    app.UndoButton.Enable = "off";
                    app.SaveButton.Enable = "off";
            end
        end

        function switchBuildR(app, status)
            switch status
                case "on"
                    app.switchRprocess("off");
                    app.BuildRButton.Enable = "on";
                    app.LoadRButton.Enable = "on";
                    app.Lamp_BuildR.Visible = "on";
                    app.Lamp_BuildR.Color = "red";
                    app.switchSpikeChecker("on");
                case "off"
                    app.BuildRButton.Enable = "off";
                    app.LoadRButton.Enable = "off";
                    app.InitiateKButton.Enable = "off";
                    app.switchSpikeChecker("off");
                case "disable"
                    app.switchRprocess("off");
                    app.LoadRButton.Enable = "on";
                    app.BuildRButton.Enable = "off";
                    app.Lamp_BuildR.Visible = "off";
            end

        end

        function switchRprocess(app, status)
            switch status
                case "on"
                    app.switchBuildR("off");
                    app.Lamp_BuildR.Visible = "on";
                    app.Lamp_BuildR.Color = "green";
                    if isempty(app.kClass)
                        app.Lamp_BuildK.Visible = "off";
                    else
                        app.Lamp_BuildK.Color = "green";
                        app.Lamp_BuildK.Visible = "on";
                    end
                    app.PlotallPSTHsButton.Enable = "on";
                    app.Lamp_PlotPSTHs.Color   = "red";
                    app.Lamp_PlotPSTHs.Visible = "on";
                    app.Lamp_PlotPoppulation.Color = "red";
                    app.Lamp_PlotPoppulation.Visible = "on";
                    app.PlotallPSTHsButton.Enable = "on";
                    app.PlotPopulationButton.Enable = "on";
                    app.PlotPSTHButton.Enable = "on";
                    app.CopyRButton.Enable = "on";
                    app.CopyRButton.FontColor = "k";
                case "off"
                    app.PlotallPSTHsButton.Enable = "off";
                    app.PlotPopulationButton.Enable = "off";
                    app.PlotPSTHButton.Enable = "off";
                    app.CopyRButton.Enable = "off";
                    app.CopyRButton.FontColor = "k";
                    app.Lamp_PlotPSTHs.Color = "red";
                    app.Lamp_PlotPSTHs.Visible = "off";
                    app.Lamp_PlotPoppulation.Color = "red";
                    app.Lamp_PlotPoppulation.Visible = "off";
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuildKButton
        function BuildKButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end

            app.switchBuildK("off");
            app.Lamp_BuildK.Color = "yellow";
            drawnow;

            absDirKSOutput = fullfile(pwd, app.dirKSOutput);
            addpath(absDirKSOutput);
            load(fullfile(absDirKSOutput, 'ops.mat'), "ops");
            chanMap = load('chanMap.mat');
            spikeTable = phy2mat(absDirKSOutput);
            spikeTable = getRealSpikeTime(spikeTable, ops);
            spikeTable = app.updateSpikeTable(spikeTable, ops);
            
            app.kClass = hbKilosort(spikeTable, chanMap, ops);
            app.kClass.setXtrodeMap("xtrode", app.ElectrodesDropDown.Value);
            app.kClassName = "KClass_" + app.SubjectTextArea.Value + "_" + ...
                app.Date + "_" + app.ProtocolDropDown.Value + ".mat";
            app.kClass.save(pwd, app.kClassName);
            rmpath(absDirKSOutput);
            app.switchKprocess("on");
            app.switchBuildR("on");
            disp("Done :)");

            function spikeTable = getRealSpikeTime(spikeTable, ops)

                % Only save labeled clusters and clear noise
                spikeTable.ch = spikeTable.ch + 1; % channel index start from 1
                k = 1;
                while k <= height(spikeTable)
                    if isempty(spikeTable(k,:).group{1}) || strcmp(spikeTable(k,:).group{1},'noise')
                        spikeTable(k,:) = [];
                    else
                        k = k+1;
                    end
                end
                spikeTable = sortrows(spikeTable,{'ch','group'});
                
                % read and add spike times
                spike_times = readNPY('spike_times.npy');
                spike_clusters = readNPY('spike_clusters.npy');
                
                tbl_spike_times = cell(height(spikeTable),1);
                for k = 1:height(spikeTable)
                    tbl_spike_times{k} = spike_times(spike_clusters==spikeTable(k,:).cluster_id);
                end
                spikeTable.spike_times = tbl_spike_times;

                %% getRealSpikeTime
                ns6_files = dir('*.ns6'); % Read NS6 file.
                if ~isempty(ns6_files)
                    for i =1:length(ns6_files)
                        if i == 1
                            openNSx(ns6_files(i).name, 'read', 'report');
                            NS6all = NS6;
                        else
                            openNSx(ns6_files(i).name, 'read', 'report');
                            NS6all(i) = NS6;
                        end
                    end
                else
                    error('No .ns6 files!');
                end
    
                disp("Extracting ephys data ......");
                % Extract ephys data
                Fs = ops.fs; % sampling rate
                index = [];
                for k = 1:length(NS6all)
                    NS6 = NS6all(k);
                    if k > 1
                        % start time of this session relative to the first session
                        dt_i = NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw;
                        dBlockOnset = dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60+dt_i(end-4)*1000*60*60*24;
                    else
                        dBlockOnset = 0; % define the starting time of the first session as 0
                    end
                    if iscell(NS6.Data)
                        for j = 1:length(NS6.Data)
                            % this is time in ms
                            index_k = (0:length(double(NS6.Data{j}(1, :)))-1)*1000/Fs + ...
                                NS6.MetaTags.Timestamp(j)*1000/Fs + dBlockOnset;
                            % skip first 102 frame (zeropad by blackrock?)
                            index_k(1:102) = [];
                            if k == 1
                                index = [index index_k];
                            else % this is to take care of an old issue
                                index_k = index_k(index_k> index(end)+0.03);
                                index = [index index_k];
                            end
                        end
                    else
                        index_k = (0:length(double(NS6.Data(1, :)))-1)*1000/Fs + ...
                            NS6.MetaTags.Timestamp*1000/Fs + dBlockOnset;
                        index_k(1:102) = []; % skip first 102 frame (zeropad by blackrock?)
                        index = [index index_k];
                    end
                end
                savefile = 'index.mat'; % name of raw data files
                save(savefile, 'index');
                
                spike_times_r = cell(height(spikeTable),1);
                for k = 1:height(spikeTable)
                    spike_times_r{k} = index(spikeTable(k,:).spike_times{1});
                end
                spikeTable.spike_times_r = spike_times_r;
            end
        end

        % Button pushed function: PlotsortresultsButton
        function PlotsortresultsButtonPushed(app, event)
            app.Lamp_PlotSortResults.Color = 'yellow';
            app.PlotsortresultsButton.Enable = "off";
            if ~isfield(app.kClass.ChanMap, "xtrodeMap")
                app.kClass.setXtrodeMap("xtrode", app.ElectrodesDropDown.Value);
            end
            delete(fullfile(pwd, "/Fig/Sorting", "*_SortingResults.png"));
            app.kClass.plotSortResultAll();
            app.Lamp_PlotSortResults.Color = 'green';
        end

        % Button pushed function: ChecksimilarityButton
        function ChecksimilarityButtonPushed(app, event)
            app.Lamp_CheckSimilarity.Color = 'yellow';
            app.ChecksimilarityButton.Enable = "off";
            delete(fullfile(pwd, "/Fig/Sorting", "CheckSimilarity*.png"));
            delete(fullfile(pwd, "/Fig/Sorting", "CrossCorrelation*.png"));
            app.kClass.checkSimilarity("Threshold", app.ThresholdSpinner.Value);
            app.Lamp_CheckSimilarity.Color = 'green';
        end

        % Button pushed function: LoadKButton
        function LoadKButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end
            fname = uigetfile("*KClass*.mat", "MultiSelect", "off");
            if any(fname)
                kc = load(fname, "kClass");
            else
                return
            end
            app.kClass = kc.kClass;
            app.kClassName = fname;
            app.switchKprocess("on");
            app.switchBuildR("on");
        end

        % Button pushed function: ShowresultsinFinderButton
        function ShowresultsinFinderButtonPushed(app, event)
            finderPath = fullfile(pwd, "Fig");
            [~,~] = mkdir(finderPath);
            if ismac
                finderCmd = 'open "'+ string(finderPath)+ '" -R';
            elseif ispc
                finderCmd = append('explorer ',finderPath);
            else
                error('Unspecified system');
            end
            system(finderCmd, '-echo');
        end

        % Button pushed function: InitiateKButton
        function InitiateKButtonPushed(app, event)
            app.kClass = [];
            app.switchBuildK("on");
            if isempty(app.rClass)
                app.switchBuildR("disable");
            end
        end

        % Button pushed function: RestartphyforkilosortButton
        function RestartphyforkilosortButtonPushed(app, event)
            phyPath = fullfile(pwd, app.dirKSOutput);
            phyCmd = "cd "+ string(phyPath)+"; phy template-gui params.py";
            clipboard("copy", phyCmd);
            system('open -a Terminal.app', '-echo');
        end

        % Button pushed function: BuildRButton
        function BuildRButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end

            fname = arrayfun(@(x)x.name, dir("datafile00*.nev"), 'UniformOutput', false);
            blocks = cellfun(@(x) str2double(x(end-4)), fname, "UniformOutput", true);
            if ~isnan(app.BlocksEditField.Value)
                blocksin = str2double(split(app.BlocksEditField.Value, [" ", ",", "/"]));
                if any(~ismember(blocksin, blocks))
                    errordlg("Wrong 'Blocks' settings"); return
                else
                    blocks = blocksin;
                end
            end
            app.switchBuildR("off");
            app.Lamp_BuildR.Color = "yellow";
            if app.DebugModeCheckBox.Value
                app.DIODropDown.Value = "Version5";
            end
            drawnow;
            try
            app.rClass = app.kClass.buildR(...
                'Subject', app.Subject, ...
                'Blocks', blocks, ...
                'DIOVersion', app.DIODropDown.Value, ...
                'MEDProtocol', app.ProtocolDropDown.Value, ...
                'BpodProtocol', app.BpodProtocolDropDown.Value, ...
                'DebugMode', app.DebugModeCheckBox.Value);
            app.switchBuildK("off");
            app.switchKprocess("off");
            app.switchRprocess("on");
            catch ME
                app.switchBuildK("off");
                app.switchKprocess("off");
                app.switchRprocess("on");
                rethrow(ME);
            end
        end

        % Button pushed function: LoadRButton
        function LoadRButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end
            fname = uigetfile("RClass*.mat", "MultiSelect", "off");
            if any(fname)
                rc = load(fname, "rClass");
            else
                return
            end
            app.rClass = rc.rClass;
            app.switchBuildK("off");
            app.switchKprocess("off");
            app.switchRprocess("on");
        end

        % Button pushed function: InitiateRButton
        function InitiateRButtonPushed(app, event)
            app.rClass = [];
            if isempty(app.kClass)
                app.switchBuildR("disable");
            else
                app.switchBuildR("on");
            end
            app.InitiateKButton.Enable = "on";
        end

        % Button pushed function: PlotallPSTHsButton
        function PlotallPSTHsButtonPushed(app, event)
            app.PlotallPSTHsButton.Enable = "off";
            app.Lamp_PlotPSTHs.Color = "yellow";
            drawnow;
            delete(fullfile(pwd, "/Fig/PSTH", "*.png"));
            app.rClass.plotPSTH4All("sortRaster", app.SortrasterCheckBox.Value);
            app.Lamp_PlotPSTHs.Color = "green";
        end

        % Button pushed function: PlotPSTHButton
        function PlotPSTHButtonPushed(app, event)
            if ~isempty(app.UnitIDEditField.Value)
                charin = split(app.UnitIDEditField.Value, {'[', ' ', ',', ']'});
                idxUnit = charin(cellfun(@(x) ~isempty(x), charin));
                idxUnit = cellfun(@(x) str2double(x), idxUnit');
                app.rClass.plotPSTH(idxUnit);
            else
                errordlg("Set UnitID before @plotPSTH");
                return
            end
        end

        % Button pushed function: PlotPopulationButton
        function PlotPopulationButtonPushed(app, event)
            app.PlotPopulationButton.Enable = "off";
            app.Lamp_PlotPoppulation.Color = "yellow"; drawnow;
            if app.ProtocolDropDown.Value == "AutoShaping"
                app.rClass.plotPopulationPSTH_AutoShaping();
            else
                app.rClass.plotPopulationPSTH();
            end
            app.Lamp_PlotPoppulation.Color = "green";
            app.CopyRButton.Enable = "on";
        end

        % Button pushed function: CopyRButton
        function CopyRButtonPushed(app, event)
            newobj = app.rClass;
            newobj.prepare4SameNeuronCheck("targetPath", app.dirRCopy);
            app.CopyRButton.Enable = "off";
            app.CopyRButton.FontColor = "green";
        end

        % Button pushed function: KSoutputdirButton
        function KSoutputdirButtonPushed(app, event)
            flag = app.checkMeta(); if ~flag; return; end
            rPath = pwd;
            newPath = uigetdir(rPath);
            if any(newPath)
                relativeNewPath = erase(newPath, rPath);
                if isempty(relativeNewPath)
                    return
                end
                if length(relativeNewPath) > length(erase(rPath, newPath))
                    errordlg("New KSoutput dir must be subfolder of current session!");
                    return
                end
                if ~exist(fullfile(newPath, "ops.mat"), "file")
                    errordlg("No ops.mat in selected directory!");
                    return
                end
                app.dirKSOutput = relativeNewPath;
                app.KSoutputdirLabel.Text = "."+relativeNewPath;
            else
                return
            end
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            app.Date = string(datetime(value, "Format", "uuuuMMdd"));
        end

        % Button pushed function: RoutputdirButton
        function RoutputdirButtonPushed(app, event)
            newPath = uigetdir(pwd);
            if any(newPath)
                app.dirRCopy = newPath;
                app.RoutputdirLabel.Text = newPath;
            else
                return
            end
        end

        % Value changed function: HeatmapButton
        function HeatmapButtonValueChanged(app, event)
            if isempty(app.UIAxesSpikes.UserData)
                return;
            else
                app.plotSpikes();
            end
        end

        % Value changing function: USpinner
        function USpinnerValueChanging(app, event)
            app.USpinner.Enable = "off";
            app.Lamp_SpikeChecker.Color = "yellow";
            drawnow;
            
            app.USpinner.Value = event.Value;
            app.plotInApp();
            app.USpinner.Enable = "on";
            app.Lamp_SpikeChecker.Color = "green";

            if app.USpinner.Value == app.USpinner.Limits(2)
                app.SaveButton.Enable = "on";
            else
                app.SaveButton.Enable = "off";
            end
        end

        % Button pushed function: ResetSpikeCheckerButton
        function ResetSpikeCheckerButtonPushed(app, event)
            app.updateTable();
            app.USpinner.Value = 1;
            % app.USpinner.Limits = [1 size(app.UnitList.Data, 1)];
            app.plotInApp();
            app.switchSpikeChecker("on");
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Select Temporal plot
            % remove spikes by drawing a perimeter

            % in = inpolygon(xq,yq,xv,yv)
            % Find out points that are inside the polygon.
            allWaves = app.kClass.SpikeTable.waveforms{app.USpinner.Value};
            score    = app.UIAxesScatter.UserData{1};
            tSpikes  = app.UIAxesScatter.UserData{2};
            spikeAmp = app.UIAxesScatter.UserData{3};
            idxPlot  = app.UIAxesScatter.UserData{4};

            % Get x value to plot
            switch app.XButtonGroup.SelectedObject.Text
                case "Time"
                    allXvalue = tSpikes;
                case "PC1"
                    allXvalue = score(:, 1);
                case "PC2"
                    allXvalue = score(:, 2);
            end
            % Get y value to plot
            switch app.YButtonGroup.SelectedObject.Text
                case "Amp."
                    allYvalue = spikeAmp;
                case "PC1"
                    allYvalue = score(:, 1);
                case "PC2"
                    allYvalue = score(:, 2);
            end
            plotXvalue = allXvalue(idxPlot);
            plotYvalue = allYvalue(idxPlot);

            m = 20; k = 1; xv = zeros(1,m); yv = zeros(1,m);
            
            FigTemporal = figure(99); clf(FigTemporal, "reset");
            haTemporal = axes; set(haTemporal, 'next', 'add');
            scatter(plotXvalue, plotYvalue, 30, 'filled',...
                'MarkerFaceColor', [0.3010 0.7450 0.9330], 'MarkerFaceAlpha', 0.5)
            title("Choose 0/"+num2str(m)+" points to mark your boundary", ...
                "FontName", "Dejavu Sans", "FontSize", 12, "FontWeight", "bold");

            while k <= m
                [xv(k), yv(k)] = ginput(1);
                if k > 1
                    plot(haTemporal, xv(k+[-1 0]), yv(k+[-1 0]), '-r', 'linewidth', 1);
                else
                    plot(haTemporal, xv(1),yv(1),'.r','linewidth', 1);
                end
                haTemporal.Title.String = "Choose "+num2str(k)+"/"+num2str(m)+" points to mark your boundary";
                k = k + 1;
            end
            plot(xv([end 1]),yv([end 1]),'-r','linewidth', 1);

            idxInsideAll  = insidepoly(allXvalue, allYvalue, xv, yv);
            idxInsidePlot = inpolygon(plotXvalue, plotYvalue, xv, yv);
            app.UIAxesScatter.UserData{5} = idxInsidePlot;
            app.plotScatter();         

            % plot these spikes in spike axis
            selectedWaves = allWaves(idxPlot(idxInsidePlot), :);
            spkLength = 1:size(selectedWaves, 2);
            plot(app.UIAxesSpikes, spkLength, selectedWaves, 'r');

            %% Make a plot showing auto-correlogram 
            FigAuto = figure(98); clf(FigAuto, "reset");
            set(FigAuto, 'units', 'centimeters', 'position', [5 10 18 5]);
            
            % original auto correlogram
            subplot(1,3,1);
            tSpk2 = zeros(1, ceil(tSpikes(end)));
            tSpk2(round(tSpikes)) = 1;
            [c, lags] = xcorr(tSpk2, 50); % max lag 25 ms
            c(lags==0) = 0;
            hbar1 = bar(lags, c); 
            hbar1.FaceColor = [0.3010 0.7450 0.9330];
            set(gca, 'xlim', [-25 25]);
            violation1 = 100*sum(diff(tSpikes)<3)/(length(tSpikes)-1);
            title(['ISI<3ms: ' num2str(violation1) '%'])

            % auto correlogram of selected points
            subplot(1,3,2);
            tSpikesIn = tSpikes(idxInsideAll);
            tSpk2 = zeros(1, ceil(tSpikesIn(end)));
            tSpk2(round(tSpikesIn)) = 1;
            [c, lags] = xcorr(tSpk2, 50); % max lag 25 ms
            c(lags==0) = 0;
            hbar2=bar( lags, c);
            hbar2.FaceColor = 'r';
            set(gca, 'xlim', [-25 25]);
            violation2 = 100*sum(diff(tSpikesIn)<3)/(length(tSpikesIn)-1);
            title(['ISI<3ms: ' num2str(violation2) '%'])

            %  auto correlogram of other points
            subplot(1,3,3);
            tSpikesOut = setdiff(tSpikes, tSpikesIn);
            tSpk2 = zeros(1, ceil(tSpikesOut(end)));
            tSpk2(round(tSpikesOut)) = 1;
            [c, lags] = xcorr(tSpk2, 50); % max lag 25 ms
            c(lags==0) = 0;
            hbar3 = bar(lags, c);
            hbar3.FaceColor = 'k';
            set(gca, 'xlim', [-25 25]);
            violation3 = 100*sum(diff(tSpikesOut)<3)/(length(tSpikesOut)-1);
            title(['ISI<3ms: ' num2str(violation3) '%'])
            app.SelectButton.UserData = {idxInsideAll};

            %% Update GUI
            app.KeepButton.Enable = "on";
            app.NewButton.Enable = "on";
            app.UndoButton.Enable = "off";

        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            idxUnit = app.USpinner.Value;
            kTable = app.kClass.SpikeTable;
            % save a temporary copy of kilosortmat so we can go back
            app.KeepButton.UserData = kTable;

            idxInsideAll = app.SelectButton.UserData{1};
            kTable.n_spikes(idxUnit) = sum(idxInsideAll);
            kTable.spike_times{idxUnit} = kTable.spike_times{idxUnit}(idxInsideAll);
            kTable.waveforms{idxUnit} = kTable.waveforms{idxUnit}(idxInsideAll, :);
            kTable.spike_times_r{idxUnit} = kTable.spike_times_r{idxUnit}(idxInsideAll);
            kTable.ind_selected{idxUnit} = idxInsideAll;
            app.kClass.SpikeTable = kTable;
            
            %% Update GUI
            close all;
            app.plotInApp();
            app.updateTable();
            app.UndoButton.Enable = "on";
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            idxUnit = app.USpinner.Value;
            kTable = app.kClass.SpikeTable;
            newUnit = app.USpinner.Limits(2)+1;
            % save a temporary copy of kilosortmat so we can go back
            app.KeepButton.UserData = kTable;
            idxInsideAll = app.SelectButton.UserData{1};
            
            %% Add idxOutside as new cluster
            kTable.n_spikes(newUnit) = sum(~idxInsideAll);
            kTable.spike_times{newUnit} = kTable.spike_times{idxUnit}(~idxInsideAll);
            kTable.waveforms{newUnit} = kTable.waveforms{idxUnit}(~idxInsideAll, :);
            kTable.spike_times_r{newUnit} = kTable.spike_times_r{idxUnit}(~idxInsideAll);
            kTable.ind_selected{newUnit} = ~idxInsideAll;

            % Update unit info
            kTable.index(newUnit) = newUnit;
            kTable.ch(newUnit) = kTable.ch(idxUnit);
            kTable.group(newUnit) = kTable.group(idxUnit);
            if max(kTable.cluster_id) > 10000
                kTable.cluster_id(newUnit) = max(kTable.cluster_id)+1;
            else
                kTable.cluster_id(newUnit) = 10001;
            end
            kTable.ContamPct(newUnit) = kTable.ContamPct(idxUnit);
            kTable.depth(newUnit) = kTable.depth(idxUnit);
            kTable.fr(newUnit) = kTable.fr(idxUnit)*kTable.n_spikes(newUnit)/kTable.n_spikes(idxUnit);
            
            %% Remove outsiders
            kTable.n_spikes(idxUnit) = sum(idxInsideAll);
            kTable.spike_times{idxUnit} = kTable.spike_times{idxUnit}(idxInsideAll);
            kTable.waveforms{idxUnit} = kTable.waveforms{idxUnit}(idxInsideAll, :);
            kTable.spike_times_r{idxUnit} = kTable.spike_times_r{idxUnit}(idxInsideAll);
            kTable.ind_selected{idxUnit} = idxInsideAll;

            app.kClass.SpikeTable = kTable;            
            %% Update GUI
            close all;
            app.plotInApp();
            app.updateTable();
            app.UndoButton.Enable = "on";
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.kClass.SpikeTable = app.KeepButton.UserData;
            app.plotInApp();
            app.UndoButton.Enable = "off";
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            dispType = app.TypeDropDown.Value;
            idxUnit = app.USpinner.Value;
            switch dispType
                case "Single"
                    app.kClass.SpikeTable.group{idxUnit} = 'good';
                case "Mua"
                    app.kClass.SpikeTable.group{idxUnit} = 'mua';
                case "Noise"
                    app.kClass.SpikeTable.group{idxUnit} = 'noise';
            end
            app.updateTable();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Lamp_SpikeChecker.Color = "yellow";
            app.SaveButton.Enable = "off"; drawnow;
            backupName = "Backup_"+app.kClassName;
            movefile(app.kClassName, backupName); % rename old kClass file (for backup)

            spikeTable = app.kClass.SpikeTable;
            idxNoise = strcmp(spikeTable.group,'noise');
            spikeTable = spikeTable(~idxNoise,:);
            spikeTable = sortrows(spikeTable,{'ch','group'});
            app.kClass.SpikeTable = app.updateSpikeTable(spikeTable, app.kClass.ParamsKilosort);
            app.ResetSpikeCheckerButton();

            writetable(app.kClass.SpikeTable, 'KilosortSpikeTable.csv');
            app.kClass.save(pwd, app.kClassName);
            app.Lamp_SpikeChecker.Color = "green";
        end

        % Selection changed function: XButtonGroup
        function XButtonGroupSelectionChanged(app, event)
            app.plotScatter();
        end

        % Selection changed function: YButtonGroup
        function YButtonGroupSelectionChanged(app, event)
            app.plotScatter();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BuildKClassandchecksortresultsPanel = uipanel(app.UIFigure);
            app.BuildKClassandchecksortresultsPanel.Title = '2. Build KClass and check sort results';
            app.BuildKButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.BuildKButton.ButtonPushedFcn = createCallbackFcn(app, @BuildKButtonPushed, true);
            app.BuildKButton.Text = 'Build K';
            app.ChecksimilarityButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.ChecksimilarityButton.ButtonPushedFcn = createCallbackFcn(app, @ChecksimilarityButtonPushed, true);
            app.ChecksimilarityButton.Enable = 'off';
            app.ChecksimilarityButton.Text = {'Check'; 'similarity'};
            app.LoadKButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.LoadKButton.ButtonPushedFcn = createCallbackFcn(app, @LoadKButtonPushed, true);
            app.LoadKButton.Text = 'Load K';
            app.PlotsortresultsButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.PlotsortresultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsortresultsButtonPushed, true);
            app.PlotsortresultsButton.Enable = 'off';
            app.PlotsortresultsButton.Text = {'Plot sort'; 'results'};
            app.Lamp_BuildK = uilamp(app.BuildKClassandchecksortresultsPanel);
            app.Lamp_PlotSortResults = uilamp(app.BuildKClassandchecksortresultsPanel);
            app.Lamp_PlotSortResults.Visible = 'off';
            app.Lamp_CheckSimilarity = uilamp(app.BuildKClassandchecksortresultsPanel);
            app.Lamp_CheckSimilarity.Visible = 'off';
            app.InitiateKButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.InitiateKButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateKButtonPushed, true);
            app.InitiateKButton.Text = 'Initiate K';
            app.ThresholdSpinnerLabel = uilabel(app.BuildKClassandchecksortresultsPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.BuildKClassandchecksortresultsPanel);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [0 Inf];
            app.ThresholdSpinner.Value = 1.5;
            app.RestartphyforkilosortButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.RestartphyforkilosortButton.ButtonPushedFcn = createCallbackFcn(app, @RestartphyforkilosortButtonPushed, true);
            app.RestartphyforkilosortButton.Text = 'Restart phy for kilosort';
            app.KSoutputdirLabel = uilabel(app.BuildKClassandchecksortresultsPanel);
            app.KSoutputdirLabel.Text = './kilosort2_5_output/sorter_output';
            app.KSoutputdirButton = uibutton(app.BuildKClassandchecksortresultsPanel, 'push');
            app.KSoutputdirButton.ButtonPushedFcn = createCallbackFcn(app, @KSoutputdirButtonPushed, true);
            app.KSoutputdirButton.Text = 'KS output dir';
            app.MetaPanel = uipanel(app.UIFigure);
            app.MetaPanel.Title = '1. Meta';
            app.SubjectTextAreaLabel = uilabel(app.MetaPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.MetaPanel);
            app.SubjectTextArea.Value = {'Doge'};
            app.DateDatePickerLabel = uilabel(app.MetaPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.MetaPanel);
            app.DateDatePicker.DisplayFormat = 'uuuu/MM/dd';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Value = datetime([2024 1 24]);
            app.ElectrodesDropDownLabel = uilabel(app.MetaPanel);
            app.ElectrodesDropDownLabel.Text = 'Electrodes';
            app.ElectrodesDropDown = uidropdown(app.MetaPanel);
            app.ElectrodesDropDown.Items = {'Silicon', 'Tetrode', 'MWA'};
            app.ElectrodesDropDown.Value = 'Silicon';
            app.ProtocolDropDownLabel = uilabel(app.MetaPanel);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.MetaPanel);
            app.ProtocolDropDown.Items = {'AutoShaping', 'LeverPress', 'LeverRelease', 'Wait1', 'Wait2', '2FPs', '3FPs'};
            app.ProtocolDropDown.Value = 'AutoShaping';
            app.BpodProtocolLabel = uilabel(app.MetaPanel);
            app.BpodProtocolLabel.Text = 'Bpod Protocol';
            app.BpodProtocolDropDown = uidropdown(app.MetaPanel);
            app.BpodProtocolDropDown.Items = {'MedOptoRecording', 'MedLick'};
            app.BpodProtocolDropDown.Value = 'MedOptoRecording';
            app.ShowresultsinFinderButton = uibutton(app.MetaPanel, 'push');
            app.ShowresultsinFinderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowresultsinFinderButtonPushed, true);
            app.ShowresultsinFinderButton.Text = {'Show results'; 'in Finder'};
            app.DIODropDownLabel = uilabel(app.MetaPanel);
            app.DIODropDownLabel.Text = 'DIO';
            app.DIODropDown = uidropdown(app.MetaPanel);
            app.DIODropDown.Items = {'Version4', 'Version5'};
            app.DIODropDown.Value = 'Version5';
            app.BlocksEditFieldLabel = uilabel(app.MetaPanel);
            app.BlocksEditFieldLabel.Text = 'Blocks';
            app.BlocksEditField = uieditfield(app.MetaPanel, 'text');
            app.DebugModeCheckBox = uicheckbox(app.MetaPanel);
            app.DebugModeCheckBox.Text = 'DebugMode';
            app.BuildRClassandplotPSTHsPanel = uipanel(app.UIFigure);
            app.BuildRClassandplotPSTHsPanel.Title = '4. Build RClass and plot PSTHs';
            app.BuildRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.BuildRButton.ButtonPushedFcn = createCallbackFcn(app, @BuildRButtonPushed, true);
            app.BuildRButton.Enable = 'off';
            app.BuildRButton.Text = 'Build R';
            app.LoadRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.LoadRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRButtonPushed, true);
            app.LoadRButton.Text = 'Load R';
            app.Lamp_BuildR = uilamp(app.BuildRClassandplotPSTHsPanel);
            app.Lamp_BuildR.Visible = 'off';
            app.Lamp_PlotPSTHs = uilamp(app.BuildRClassandplotPSTHsPanel);
            app.Lamp_PlotPSTHs.Visible = 'off';
            app.PlotallPSTHsButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.PlotallPSTHsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallPSTHsButtonPushed, true);
            app.PlotallPSTHsButton.Enable = 'off';
            app.PlotallPSTHsButton.Text = {'Plot all'; 'PSTHs'};
            app.InitiateRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.InitiateRButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateRButtonPushed, true);
            app.InitiateRButton.Text = 'Initiate R';
            app.Lamp_PlotPoppulation = uilamp(app.BuildRClassandplotPSTHsPanel);
            app.Lamp_PlotPoppulation.Visible = 'off';
            app.PlotPopulationButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.PlotPopulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPopulationButtonPushed, true);
            app.PlotPopulationButton.Enable = 'off';
            app.PlotPopulationButton.Text = {'Plot'; 'Population'};
            app.PlotPSTHButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.PlotPSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSTHButtonPushed, true);
            app.PlotPSTHButton.Enable = 'off';
            app.PlotPSTHButton.Text = 'Plot PSTH';
            app.UnitIDEditFieldLabel = uilabel(app.BuildRClassandplotPSTHsPanel);
            app.UnitIDEditFieldLabel.Text = 'UnitID';
            app.UnitIDEditField = uieditfield(app.BuildRClassandplotPSTHsPanel, 'text');
            app.CopyRButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.CopyRButton.ButtonPushedFcn = createCallbackFcn(app, @CopyRButtonPushed, true);
            app.CopyRButton.Enable = 'off';
            app.CopyRButton.Text = 'Copy R';
            app.SortrasterCheckBox = uicheckbox(app.BuildRClassandplotPSTHsPanel);
            app.SortrasterCheckBox.Text = 'Sort raster';
            app.SortrasterCheckBox.Value = true;
            app.RoutputdirButton = uibutton(app.BuildRClassandplotPSTHsPanel, 'push');
            app.RoutputdirButton.ButtonPushedFcn = createCallbackFcn(app, @RoutputdirButtonPushed, true);
            app.RoutputdirButton.Text = 'R output dir';
            app.RoutputdirLabel = uilabel(app.BuildRClassandplotPSTHsPanel);
            app.RoutputdirLabel.Text = '/Volumes/Procyon/EphysSorting/Pavlov/SameNeuronCheck';
            app.SpikeCheckerPanel = uipanel(app.UIFigure);
            app.SpikeCheckerPanel.Title = '3. SpikeChecker';
            app.UIAxesISI = uiaxes(app.SpikeCheckerPanel);
            title(app.UIAxesISI, 'ISI')
            xlabel(app.UIAxesISI, 'ISI (ms)')
            zlabel(app.UIAxesISI, 'Z')
            app.UIAxesISI.XLim = [0 50];
            app.UIAxesISI.XTick = [0 10 20 30 40 50];
            app.UIAxesSpikes = uiaxes(app.SpikeCheckerPanel);
            app.UIAxesSpikes.XTick = [];
            app.UIAxesSpikes.YTick = [];
            app.UIAxesScatter = uiaxes(app.SpikeCheckerPanel);
            app.UIAxesScatter.XTick = [];
            app.UIAxesScatter.YTick = [];
            app.UIAxesAutoCorr = uiaxes(app.SpikeCheckerPanel);
            title(app.UIAxesAutoCorr, 'AutoCorrelation')
            xlabel(app.UIAxesAutoCorr, 'lags (ms)')
            zlabel(app.UIAxesAutoCorr, 'Z')
            app.UIAxesAutoCorr.XLim = [-25 25];
            app.UIAxesAutoCorr.XTick = [-20 -10 0 10 20];
            app.UIAxesAutoCorr.ZTick = [];
            app.YButtonGroup = uibuttongroup(app.SpikeCheckerPanel);
            app.YButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YButtonGroupSelectionChanged, true);
            app.YPC2Button = uitogglebutton(app.YButtonGroup);
            app.YPC2Button.Text = 'PC2';
            app.YPC1Button = uitogglebutton(app.YButtonGroup);
            app.YPC1Button.Text = 'PC1';
            app.AmpButton = uitogglebutton(app.YButtonGroup);
            app.AmpButton.Text = 'Amp.';
            app.AmpButton.Value = true;
            app.YLabel = uilabel(app.YButtonGroup);
            app.YLabel.Text = 'Y';
            app.UnitList = uitable(app.SpikeCheckerPanel);
            app.UnitList.ColumnName = {'#'; 'Ch'; 'Grp'; 'KS'; 'nSpk'};
            app.UnitList.RowName = {};
            app.UnitList.SelectionType = 'row';
            app.ULabel = uilabel(app.SpikeCheckerPanel);
            app.ULabel.Enable = 'off';
            app.ULabel.Text = '#U';
            app.USpinner = uispinner(app.SpikeCheckerPanel);
            app.USpinner.ValueChangingFcn = createCallbackFcn(app, @USpinnerValueChanging, true);
            app.USpinner.Limits = [1 Inf];
            app.USpinner.Enable = 'off';
            app.USpinner.Value = 1;
            app.SelectButton = uibutton(app.SpikeCheckerPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.KeepButton = uibutton(app.SpikeCheckerPanel, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Enable = 'off';
            app.KeepButton.Text = 'Keep';
            app.UndoButton = uibutton(app.SpikeCheckerPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.SpkNumberEditFieldLabel = uilabel(app.SpikeCheckerPanel);
            app.SpkNumberEditFieldLabel.Text = 'SpkNumber';
            app.SpkNumberEditField = uieditfield(app.SpikeCheckerPanel, 'numeric');
            app.SpkNumberEditField.Value = 1000;
            app.Lamp_SpikeChecker = uilamp(app.SpikeCheckerPanel);
            app.Lamp_SpikeChecker.Visible = 'off';
            app.ResetSpikeCheckerButton = uibutton(app.SpikeCheckerPanel, 'push');
            app.ResetSpikeCheckerButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSpikeCheckerButtonPushed, true);
            app.ResetSpikeCheckerButton.Enable = 'off';
            app.ResetSpikeCheckerButton.Text = 'Reset';
            app.SaveButton = uibutton(app.SpikeCheckerPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.NewButton = uibutton(app.SpikeCheckerPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Enable = 'off';
            app.NewButton.Text = 'New';
            app.TypeDropDownLabel = uilabel(app.SpikeCheckerPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.SpikeCheckerPanel);
            app.TypeDropDown.Items = {'Single', 'Mua', 'Noise'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Single';
            app.HeatmapButton = uibutton(app.SpikeCheckerPanel, 'state');
            app.HeatmapButton.ValueChangedFcn = createCallbackFcn(app, @HeatmapButtonValueChanged, true);
            app.HeatmapButton.Text = 'Heatmap';
            app.XButtonGroup = uibuttongroup(app.SpikeCheckerPanel);
            app.XButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XButtonGroupSelectionChanged, true);
            app.XPC2Button = uitogglebutton(app.XButtonGroup);
            app.XPC2Button.Text = 'PC2';
            app.XPC1Button = uitogglebutton(app.XButtonGroup);
            app.XPC1Button.Text = 'PC1';
            app.TimeButton = uitogglebutton(app.XButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.XLabel = uilabel(app.XButtonGroup);
            app.XLabel.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hbSpikeSortingPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end