classdef track_side_kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedkinematicsanalysissideviewUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CheckalltrialsButton         matlab.ui.control.Button
        TrialsTrackedLabel           matlab.ui.control.Label
        TrialstrackedListBox         matlab.ui.control.ListBox
        TrialstrackedListBoxLabel    matlab.ui.control.Label
        TrajListDropDown             matlab.ui.control.DropDown
        FilterButton                 matlab.ui.control.Button
        pressindex                   matlab.ui.control.EditField
        DiffButton                   matlab.ui.control.Button
        currentBodyPart              matlab.ui.control.Label
        addTraj                      matlab.ui.control.CheckBox
        LoadButton                   matlab.ui.control.Button
        trialNum                     matlab.ui.control.Label
        FEB2026JYLabel               matlab.ui.control.Label
        tagEditField                 matlab.ui.control.EditField
        tagEditFieldLabel            matlab.ui.control.Label
        GoodtrajectoriesLabel        matlab.ui.control.Label
        RemoveFromFinalButton        matlab.ui.control.Button
        AddToFinalButton             matlab.ui.control.Button
        goodTrajTable                matlab.ui.control.Table
        SelectedTrial                matlab.ui.control.Label
        CheckatrialButton            matlab.ui.control.Button
        trajSummary                  matlab.ui.control.Table
        LoadrarrayButton             matlab.ui.control.Button
        TrajExtractedfolderButton    matlab.ui.control.Button
        BodytrackedDLCDropDown       matlab.ui.control.DropDown
        BodytrackedDLCDropDownLabel  matlab.ui.control.Label
        VideocliplistDropDown        matlab.ui.control.DropDown
        VideocliplistDropDownLabel   matlab.ui.control.Label
        videoPath                    matlab.ui.control.Label
        SelectvideopathButton        matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        SavedatafornicefigureButton  matlab.ui.control.Button
        ApplyallCheckBox             matlab.ui.control.CheckBox
        ManualfixButton              matlab.ui.control.Button
        SeeallButton                 matlab.ui.control.Button
        AutofigsaveCheckBox          matlab.ui.control.CheckBox
        BodypartsremainedLabel       matlab.ui.control.Label
        UnTrackedListBox             matlab.ui.control.ListBox
        PostEditField                matlab.ui.control.NumericEditField
        PostEditFieldLabel           matlab.ui.control.Label
        PreEditField                 matlab.ui.control.NumericEditField
        PreEditFieldLabel            matlab.ui.control.Label
        ForwardButton                matlab.ui.control.Button
        BackwardButton               matlab.ui.control.Button
        AdjustBrightnessKnob         matlab.ui.control.Knob
        AdjustBrightnessKnobLabel    matlab.ui.control.Label
        HoldCheckBox                 matlab.ui.control.CheckBox
        LiftButton                   matlab.ui.control.Button
        AddCheckBox                  matlab.ui.control.CheckBox
        SelectrangeButton            matlab.ui.control.Button
        ComputeButton                matlab.ui.control.Button
        VisualizeButton              matlab.ui.control.Button
        RankunitsButton              matlab.ui.control.Button
        PSTHButton                   matlab.ui.control.Button
        PRINTButton                  matlab.ui.control.Button
        EndTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditField           matlab.ui.control.NumericEditField
        endButton                    matlab.ui.control.Button
        startButton                  matlab.ui.control.Button
        SpeedKnob                    matlab.ui.control.DiscreteKnob
        KinematicDropDown            matlab.ui.control.DropDown
        KinematicDropDownLabel       matlab.ui.control.Label
        UnitlistDropDown             matlab.ui.control.DropDown
        UnitlistDropDownLabel        matlab.ui.control.Label
        VideoSlider                  matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        removeButton                 matlab.ui.control.Button
        addButton                    matlab.ui.control.Button
        ChosenUITable                matlab.ui.control.Table
        PlaySwitch                   matlab.ui.control.Switch
        PlayButton                   matlab.ui.control.Button
        FrameLabel                   matlab.ui.control.Label
        FrameTimeLabel               matlab.ui.control.Label
        colorMapSDF                  matlab.ui.control.UIAxes
        poseAxis                     matlab.ui.control.UIAxes
        barAxis                      matlab.ui.control.UIAxes
        kinematicsAxis               matlab.ui.control.UIAxes
        spikesAxis                   matlab.ui.control.UIAxes
        VideoClipAxis                matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        Menu_2                       matlab.ui.container.Menu
        Menu2_2                      matlab.ui.container.Menu
        ContextMenu3                 matlab.ui.container.ContextMenu
        Menu_3                       matlab.ui.container.Menu
        Menu2_3                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        currentFolder; % where you started the app.
        folderPath; % where videos and csv files are
        fileName; % current video file
        rData % Ephys data
        hbar matlab.graphics.illustration.ColorBar    
        trajTable; % tracked trajectory table, from a script like TrajExtraction.m
        trajTableSummary; % a summary of tracked trajectory table
        trajTableGood; % only selected refined trials for next-level kinematic analysis. 
        currentTrial; % selected trial in the trajTableSummary
        allTrials;
        poseRangeROI   % handles for the two vertical lines
        startTimeMs; % reading from the block as above
        endTimeMs; % reading from the block as above
        currentVideo; % current video
        VideoMeta; % meta data of this video
        currentFrameTime;
        currentFrameLine; % a line that mark current frame in the spike raster
        currentFrameLineSpikes; % same line plotted in the spike raster
        xLever; % lever position
        yLever;
        currentPose;
        currentPoseScatter; % this is in the x vs t plot
        currentFrameLinePose;
        videoTimeRange (1,2) int32 = int32([-5000 5000]);
        startTimeChosen; % selected start time of this trial
        startTimeChosenLine;
        startTimeChosenLinePose;
        endTimeChosen; % selected end time of this trial
        endTimeChosenLine;
        endTimeChosenLinePose;
        tableKinematics; % kinematics table from selected trials and episode, this will be saved. 
        tableKinematicsSummary;
        approachStartingTime

        isManualFixPicking logical = false
        manualFixMarker matlab.graphics.chart.primitive.Scatter = matlab.graphics.chart.primitive.Scatter.empty
        manualFixPoint double = [NaN NaN]   % [x y] of last click

        showUnitLine

        TrajExtractedFolder; % this is the traj extracted folder.
        TrajExtractedFolder_; % this is the old traj extracted folder (from rVideoViewer app).

        selectedUnits; % selected units for plotting
        selectedUnitsGroup;
        selectedUnitsSDFs;
        spkRasterLine; % this are spike raster lines
        currentTrialBehavior; % behavior table of current trial
        RawFrame
        hasSavedInitialRefinedTable logical = false

        headPositionKinematics;

        isPlaying % Description
        BodyScatter % the scatter plot of body tracking results

        toLeverHeadScatter; % to lever scatter
        fromLeverHeadScatter; % from lever scatter
        currentBodyPoint; % position corresponding to current time point
        currentHeadPoint; % position corresponding to current time point
        currentHeadAngle; % current head angle
        currentKinematicFeature;

        goodTrajSelectedRows double = []

        PSTH_folder

        FrameImg % the frame image
        FrameImgSide
        FrameImgSideOrg  % in case a ROI is chosen
        dlcResults; % Description
        CurrentFrameTracking;
        thisVideo;
        thisVideoSide; % side video name
        extractSegment; % time segment to extract for current trial
        extractSegmentIndex; % index of extracted segment
        bodyTriangle; % a body triangle connecting ears and tail
        tPresses; % press times from r
        tReleases; % release times from r
        tTriggers; % trigger times from r
        tPokes; % poke times from r
        SideVideoPath; % path to side videos
        TrajSavedPath; % where we save the TrajExtracted data
    end

    methods (Access = private)

        function populateTrajSummaryTable(app, Tshort)

            T = Tshort;

            % --- make strings display nicely (avoid 1x1 cell display) ---
            if iscell(T.index);    T.index   = string(T.index);   end
            if iscell(T.outcome);  T.outcome = string(T.outcome); end

            % --- ensure time columns show <missing> instead of "NaN" ---
            timeVars = {'tpress','ttrigger','trelease','tpoke'};
            for k = 1:numel(timeVars)
                v = timeVars{k};

                if isnumeric(T.(v))
                    x = round(T.(v));
                    s = strings(size(x));
                    s(:) = missing;
                    ok = ~isnan(x);
                    s(ok) = string(x(ok));
                    T.(v) = s;
                else
                    s = string(T.(v));
                    s(ismissing(s) | s=="" | lower(s)=="nan") = missing;
                    T.(v) = s;
                end
            end

            % --- freeze "index" by putting it into RowName (left fixed area) ---
            idx = string(T.index);
            T.index = [];

            % --- push to UITable ---
            app.trajSummary.Data = T;
            app.trajSummary.RowName = cellstr(idx);   % <-- keep this (do NOT overwrite)

            app.trajSummary.FontSize   = 10;        % try 8 or 7
            app.trajSummary.FontWeight = 'normal'; % removes bold look (usually)
            app.trajSummary.ColumnName = T.Properties.VariableNames;

            % ColumnFormat must match number of columns
            % app.trajSummary.ColumnFormat = { ...
            %     'char', ...    % tpress
            %     'char', ...    % ttrigger
            %     'char', ...    % trelease
            %     'char', ...    % tpoke
            %     'numeric', ... % fp
            %     'char', ...    % outcome
            %     'numeric', ... % durIn
            %     'numeric'  ... % durOut
            %     };

            app.trajSummary.ColumnWidth = {80, 80, 80, 80, 55, 90, 65, 65};
            app.trajSummary.ColumnEditable = false(1, width(T));
          
        end

        function updateText(app, row)

            % If you're using RowName = your frozen index labels:
            if iscell(app.trajSummary.RowName) && numel(app.trajSummary.RowName) >= row
                selectedIndex = app.trajSummary.RowName{row};  % e.g., 'Top_Press_229539'
            else
                selectedIndex = ""; % fallback
            end

            % The actual row of data in the table (with index removed)
            T = app.trajSummary.Data;
            selectedRowData = T(row,:);

            str = [row + " |" + string(selectedIndex) + " | " + selectedRowData.outcome];
            app.SelectedTrial.Text = str;
            app.SelectedTrial.FontSize = 12;
            app.SelectedTrial.FontWeight = 'bold';
            app.currentTrial = selectedIndex;

            % Example: show it somewhere / debug
            disp("Selected row = " + row + ", index = " + string(selectedIndex) + selectedRowData.outcome);
            % or store it:
            % app.extractSegmentIndex = row;  % if that’s what you want
            app.pressindex.Value = string(selectedIndex);

        end

        function updateToFrame(app, frameIndex)

            % ---- guards ----
            if isempty(app.currentVideo)
                return;
            end
            if isempty(app.VideoMeta) || ~isfield(app.VideoMeta,'VideoInfo')
                return;
            end

            % VideoReader isn't a handle object, so isvalid(vr) isn't meaningful.
            vr = app.currentVideo;

            % ---- clamp using timestamps (more reliable than Duration*FrameRate) ----
            vt = app.VideoMeta.VideoInfo.VideoTimeStamps;
            nF = numel(vt);
            if nF < 1
                return
            end
            frameIndex = max(1, min(nF, round(frameIndex)));

            % ---- update current frame time (in ephys) ----
            if isfield(app.VideoMeta.VideoInfo,'EphysTimeStamps')
                et = app.VideoMeta.VideoInfo.EphysTimeStamps;
                if numel(et) >= frameIndex
                    app.currentFrameTime = et(frameIndex);
                else
                    app.currentFrameTime = NaN;
                end
            else
                app.currentFrameTime = NaN;
            end

            % ---- read the frame ----
            % Random access: read(vr, k) is the key line. You don't need CurrentTime here.
            try
                frame = read(vr, frameIndex);
            catch
                return
            end

            % store raw + render with knob
            app.RawFrame = frame;
            app.refreshFrameBrightness();   % <-- this applies AdjustKnob gain and updates FrameImg

            % ---- labels ----
            FrameTime = vt(frameIndex) - vt(1);
            if isprop(app,'FrameLabel') && ~isempty(app.FrameLabel) && isvalid(app.FrameLabel)
                app.FrameLabel.Text = sprintf('Frame: %4.0d', frameIndex);
            end
            if isprop(app,'FrameTimeLabel') && ~isempty(app.FrameTimeLabel) && isvalid(app.FrameTimeLabel)
                app.FrameTimeLabel.Text = sprintf('t (ms) = %4.0f', FrameTime);
            end

            % ---- slider ----
            if isprop(app,'VideoSlider') && ~isempty(app.VideoSlider) && isvalid(app.VideoSlider)
                % optionally keep limits sane
                % app.VideoSlider.Limits = [1 nF];
                app.VideoSlider.Value = frameIndex;
            end

            % ---- current-frame lines ----
            if isfield(app.VideoMeta.VideoInfo,'EphysTimeStamps') && isprop(app,'currentTrialBehavior') && ~isempty(app.currentTrialBehavior)
                et = app.VideoMeta.VideoInfo.EphysTimeStamps;
                if numel(et) >= frameIndex
                    v = et(frameIndex) - app.currentTrialBehavior.t_press;

                    if isprop(app,'currentFrameLine') && ~isempty(app.currentFrameLine) && isvalid(app.currentFrameLine)
                        app.currentFrameLine.Value = v;
                    end
                    if isprop(app,'currentFrameLinePose') && ~isempty(app.currentFrameLinePose) && isvalid(app.currentFrameLinePose)
                        app.currentFrameLinePose.Value = v;
                    end
                    if isprop(app,'currentFrameLineSpikes') && ~isempty(app.currentFrameLineSpikes) && isvalid(app.currentFrameLineSpikes)
                        app.currentFrameLineSpikes.Value = v;
                    end
                end
            end

            % ---- update current body marker (no replot of clouds) ----
            if isprop(app,'ComputeButton') && ~isempty(app.ComputeButton) ...
                    && ~isempty(app.ComputeButton.UserData)
                app.mark_current_head();
            end

            drawnow limitrate;   % good for scrubbing
        end

        function showKinematicsTable(app, T)
            %SHOWKINEMATICSTABLE Display a kinematics table nicely in app.goodTrajTable.
            %
            % Works for both:
            %   - old "wide/head" tables (with Source, filled_mask, etc.)
            %   - new "long/body_part" tables (body_part, keep_run_mask, run_id, etc.)
            %
            % Key behaviors:
            %   - converts cell/cellstr/string-like cols to string
            %   - forces mask columns to logical
            %   - reorders columns to a readable layout
            %   - auto-builds ColumnFormat + ColumnWidth

            if isempty(T) || ~istable(T)
                app.goodTrajTable.Data = table();
                app.goodTrajTable.RowName = {};
                return
            end

            Tout = T;

            % ---------------------------
            % 1) normalize common text columns
            % ---------------------------
            textCols = {'Source','lever_phase','trial','anm_session','body_part','Tag','Name'};
            for i = 1:numel(textCols)
                vname = textCols{i};
                if ismember(vname, Tout.Properties.VariableNames)
                    Tout.(vname) = local_to_string(Tout.(vname));
                end
            end

            % ---------------------------
            % 2) force common mask columns to logical
            % ---------------------------
            maskCols = {'filled_mask','kept_mask','keep_run_mask'};
            for i = 1:numel(maskCols)
                nm = maskCols{i};
                if ismember(nm, Tout.Properties.VariableNames)
                    Tout.(nm) = logical(Tout.(nm));
                end
            end

            % ---------------------------
            % 3) reorder columns (works for long + wide)
            % ---------------------------
            % For long table, this is a nice front block:
            preferredFront = {'trial','anm_session','lever_phase','body_part','time'};
            front = preferredFront(ismember(preferredFront, Tout.Properties.VariableNames));

            % Then push the rest, but keep "core kinematics" early if present
            core = {'x','y','lh','x_s','y_s','vx','vy','speed','v_forward','v_up',...
                'kept_mask','keep_run_mask','run_id'};
            core = core(ismember(core, Tout.Properties.VariableNames));

            rest = setdiff(Tout.Properties.VariableNames, [front, core], 'stable');

            Tout = Tout(:, [front, core, rest]);

            % ---------------------------
            % 4) push to uitable
            % ---------------------------
            app.goodTrajTable.Data = Tout;
            app.goodTrajTable.ColumnName = Tout.Properties.VariableNames;
            app.goodTrajTable.FontSize = 12;

            % ---------------------------
            % 5) ColumnFormat + ColumnWidth (auto)
            % ---------------------------
            fmts   = cell(1, width(Tout));
            widths = cell(1, width(Tout));

            for j = 1:width(Tout)
                v  = Tout.(j);
                nm = Tout.Properties.VariableNames{j};

                if islogical(v)
                    fmts{j} = 'logical';
                    widths{j} = 70;

                elseif isnumeric(v)
                    fmts{j} = 'numeric';

                    if strcmpi(nm,'time')
                        widths{j} = 80;
                    elseif any(strcmpi(nm, {'x','y','x_s','y_s'}))
                        widths{j} = 70;
                    elseif any(strcmpi(nm, {'vx','vy','speed','v_forward','v_up'}))
                        widths{j} = 85;
                    elseif contains(lower(nm), 'theta') || contains(lower(nm), 'angle')
                        widths{j} = 85;
                    elseif contains(lower(nm), 'omega') || contains(lower(nm), 'accel') || contains(lower(nm), 'run_id')
                        widths{j} = 80;
                    else
                        widths{j} = 80;
                    end

                else
                    % treat as text
                    fmts{j} = 'char';

                    if any(strcmp(nm, {'trial','anm_session'}))
                        widths{j} = 150;
                    elseif any(strcmp(nm, {'lever_phase','body_part'}))
                        widths{j} = 95;
                    else
                        widths{j} = 120;
                    end
                end
            end

            app.goodTrajTable.ColumnFormat = fmts;
            app.goodTrajTable.ColumnWidth  = widths;
            app.goodTrajTable.ColumnEditable = false(1, width(Tout));

            % Update Data again (in case type conversion happened)
            app.goodTrajTable.Data = Tout;

            % ---------------------------
            % 6) Update dropdown summary safely
            % ---------------------------
            % Your existing summarizeTableKinematicsTopPressToFromLever() filters Top_Press_*.
            % With Side_Press_* present, that function will return empty.
            %
            % Minimal safe behavior here: build Ts directly from Tout without hard name filters.
            try
                Ts = local_summarize_trial_phase(Tout);
                app.tableKinematicsSummary = Ts;
                if istable(Ts) && height(Ts)>0 && ismember('Trials', Ts.Properties.VariableNames)
                    app.TrajListDropDown.Items = cellstr(string(Ts.Trials));
                end
            catch
                % don't kill UI if summary fails
            end

            % ---- local helper funcs (nested) ----
            function s = local_to_string(v)
                % robust convert: cell/cellstr/string/numeric -> string vector
                if isstring(v)
                    s = v;
                elseif ischar(v)
                    s = string(v);
                elseif iscell(v)
                    % cell can contain char, string, or 1x1 string array
                    try
                        s = string(v);
                    catch
                        s = strings(size(v));
                        for ii = 1:numel(v)
                            try
                                s(ii) = string(v{ii});
                            catch
                                s(ii) = "";
                            end
                        end
                    end
                else
                    s = string(v);
                end
                s = s(:);
            end

            function Ts = local_summarize_trial_phase(Tk)
                % group by (trial, lever_phase) if available, else just trial
                trial = "";
                phase = "";
                if ismember('trial', Tk.Properties.VariableNames), trial = string(Tk.trial); end
                if ismember('lever_phase', Tk.Properties.VariableNames), phase = string(Tk.lever_phase); end

                if trial == ""
                    Ts = table();
                    return
                end

                if phase ~= ""
                    [G, trialG, phaseG] = findgroups(trial, phase);
                    n_samples = splitapply(@numel, Tk.time, G);
                    t_start   = splitapply(@min,   Tk.time, G);
                    t_end     = splitapply(@max,   Tk.time, G);
                    dur_ms    = t_end - t_start;
                    Trials = trialG + "_" + phaseG;

                    sessG = repmat("", numel(trialG), 1);
                    if ismember('anm_session', Tk.Properties.VariableNames)
                        sess = string(Tk.anm_session);
                        sessG = splitapply(@(x)x(1), sess, G);
                    end

                    Ts = table(Trials, trialG, phaseG, n_samples, t_start, t_end, dur_ms, sessG, ...
                        'VariableNames', {'Trials','trial','lever_phase','n_samples','t_start','t_end','dur_ms','anm_session'});
                    Ts = sortrows(Ts, {'trial','lever_phase'});
                else
                    [G, trialG] = findgroups(trial);
                    n_samples = splitapply(@numel, Tk.time, G);
                    t_start   = splitapply(@min,   Tk.time, G);
                    t_end     = splitapply(@max,   Tk.time, G);
                    dur_ms    = t_end - t_start;
                    Trials = trialG;

                    sessG = repmat("", numel(trialG), 1);
                    if ismember('anm_session', Tk.Properties.VariableNames)
                        sess = string(Tk.anm_session);
                        sessG = splitapply(@(x)x(1), sess, G);
                    end

                    Ts = table(Trials, trialG, n_samples, t_start, t_end, dur_ms, sessG, ...
                        'VariableNames', {'Trials','trial','n_samples','t_start','t_end','dur_ms','anm_session'});
                    Ts = sortrows(Ts, {'trial'});
                end
            end
        end

        function saveKinematicsTable(app)

            if isempty(app.tableKinematics) || ~istable(app.tableKinematics) || height(app.tableKinematics) == 0
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'app.tableKinematics is empty — nothing to save.', 'Nothing to save');
                return
            end

            T = app.tableKinematics;

            % --- tag ---
            tag = strtrim(string(app.tagEditField.Value));

            % --- session: prefer from table, fallback to currentTrialBehavior ---
            sess = "";

            if any(strcmp(T.Properties.VariableNames, 'anm_session'))
                sess = T.anm_session(1);

                % handle common cases: cell -> string, or string already
                if iscell(sess); sess = sess{1}; end
                sess = string(sess);

                % if it looks like ["Nasha_20250405"], clean it
                sess = erase(sess, ["[","]",""""]);
                sess = strtrim(sess);
            end

            if sess == "" && isprop(app, 'currentTrialBehavior') && ~isempty(app.currentTrialBehavior) && ...
                    isfield(app.currentTrialBehavior, 'anm_session') && ~isempty(app.currentTrialBehavior.anm_session)
                s2 = app.currentTrialBehavior.anm_session{1};
                sess = string(s2);
                sess = erase(sess, ["[","]",""""]);
                sess = strtrim(sess);
            end

            if sess == ""
                sess = "unknown_session";
            end

            % --- build filename ---
            base = "RefinedTrajectorySideView_Long_" + sess;
            if tag ~= ""
                base = base + "_" + tag;
            end

            % make filename safe
            base = regexprep(base, '\s+', '_');           % spaces -> _
            base = regexprep(base, '[^\w\-]+', '');       % remove odd chars
            file_name = base + ".csv";

            % --- folders ---
            outDir = fullfile(pwd, 'Data', 'Processed');
            if ~isfolder(outDir), mkdir(outDir); end

            histDir = fullfile(outDir, 'RefinedTrajectoryHistory');
            if ~isfolder(histDir), mkdir(histDir); end

            outPath = fullfile(outDir, file_name);

            % ===== overwrite protection: move old canonical into history =====
            if ~app.hasSavedInitialRefinedTable
                if isfile(outPath)
                    stamp = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
                    histName = base + "_" + stamp + ".csv";
                    histPath = fullfile(histDir, histName);

                    % ensure unique
                    k = 1;
                    while isfile(histPath)
                        histName = base + "_" + stamp + "_" + k + ".csv";
                        histPath = fullfile(histDir, histName);
                        k = k + 1;
                    end

                    try
                        movefile(outPath, histPath, 'f');
                        app.hasSavedInitialRefinedTable = true;
                    catch ME
                        uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf("Failed to archive existing file:\n%s\n\n%s", outPath, ME.message), ...
                            'Save failed');
                        return
                    end
                end
            end
            % ===== end =====

            % --- save new canonical ---
            try
                writetable(T, outPath);
            catch ME
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf("Failed to save:\n%s\n\n%s", outPath, ME.message), 'Save failed');
                return
            end

            % --- meta ---
            write_meta( ...
                char(file_name), ...
                'MetaFolder', outDir, ...
                'Description', 'Kinematics of selected trials and duration', ...
                'Purpose', 'for kinematics analysis (side view)', ...
                'GeneratorFunction', 'opto_paper.track_side_kinematics.mlapp', ...
                'GeneratorScript', 'None', ...
                'Inputs', {'VideoClip', 'r'} );

            app.updateProgressCounts();
        end

        function syncTrajSummaryWithGood(app)

            % --- get tables from UI ---
            Tg = app.goodTrajTable.Data;
            Tg = Tg(strcmp(Tg.body_part, app.BodytrackedDLCDropDown.Value), :);

            Ts = app.trajSummary.Data;

            if isempty(Tg) || ~istable(Tg) || height(Tg)==0
                % nothing selected yet; still update the counter
                app.updateProgressCounts();
                return;
            end
            if isempty(Ts) || ~istable(Ts) || height(Ts)==0
                app.updateProgressCounts();
                return;
            end

            % --- extract trial names from goodTrajTable ---
            if ~ismember('trial', Tg.Properties.VariableNames)
                warning('goodTrajTable has no column named "trial".');
                return;
            end
            doneTrials = unique(string(Tg.trial));
            doneTrials = doneTrials(~ismissing(doneTrials) & doneTrials ~= "");

            if isempty(doneTrials)
                app.updateProgressCounts();
                return;
            end

            % --- remove matching trials from trajSummary ---
            if true
                if ismember('trial', Ts.Properties.VariableNames)
                    keep = ~ismember(string(Ts.trial), doneTrials);
                    Ts2  = Ts(keep, :);

                    % preserve RowName if it's a custom cell array
                    rn = app.trajSummary.RowName;
                    if iscell(rn) && numel(rn) == height(Ts)
                        app.trajSummary.RowName = rn(keep);
                    end

                    app.trajSummary.Data = Ts2;
                else
                    % fallback: if trajSummary uses RowName to store trial ids
                    rn = app.trajSummary.RowName;
                    if iscell(rn) && numel(rn) == height(Ts)
                        keep = ~ismember(string(rn), doneTrials);
                        app.trajSummary.Data = Ts(keep,:);
                        app.trajSummary.RowName = rn(keep);
                    else
                        warning('trajSummary has no "trial" column (and RowName is not usable).');
                    end
                end
            end

            app.resetTrajSummarySelection();
            % --- update n=x / n=y ---
            app.updateProgressCounts();
        end

        function updateProgressCounts(app)

            x = 0;  % trials in goodTrajTable
            Tg = app.goodTrajTable.Data;
            if istable(Tg) && height(Tg)>0 && ismember('trial', Tg.Properties.VariableNames)
                x = numel(unique(string(Tg.trial)));
            end

            y = 0;  % trials left in trajSummary
            Ts = app.trajSummary.Data;
            if istable(Ts) && height(Ts)>0
                if ismember('trial', Ts.Properties.VariableNames)
                    y = numel(unique(string(Ts.trial)));
                else
                    rn = app.trajSummary.RowName;
                    if iscell(rn) && numel(rn)==height(Ts)
                        y = numel(unique(string(rn)));
                    else
                        y = height(Ts);
                    end
                end
            end

            % change trialNum to your label name
            if isprop(app,'trialNum') && ~isempty(app.trialNum) && isvalid(app.trialNum)
                app.trialNum.Text = sprintf('n=%d / n=%d', x, y);
            end
        end

        function saveSelectedUnits(app)

            % ---- guards ----
            if ~isprop(app,'trajSummary') || isempty(app.trajSummary) || ...
                     ~ismember('anm_session', app.trajSummary.Data.Properties.VariableNames)
                return;
            end
            if ~isprop(app,'selectedUnitsGroup') || isempty(app.selectedUnitsGroup)
                return;
            end

            anm_session = string(app.trajSummary.Data.anm_session{1});

            outDir = fullfile(pwd, 'Data', 'Processed');
            if ~isfolder(outDir)
                mkdir(outDir);
            end

            labels = string(app.selectedUnitsGroup(:));
            labels = labels(~ismissing(labels) & labels ~= "");
            labels = unique(labels, 'stable');   % optional, but nice

            % ---- parse channel/unit from two possible formats ----
            n = numel(labels);
            Tunits = table(app.selectedUnits(:, 1), app.selectedUnits(:, 2), 'VariableNames', {'ch','unit'});

            % ---- write ----
            fileName = sprintf('UnitsSelected_%s.csv', anm_session);
            fullPath = fullfile(outDir, fileName);
            writetable(Tunits, fullPath);
            disp(Tunits)

            disp("Saved: " + string(fullPath));

            % ---- meta (optional) ----

            write_meta( ...
                fileName, ...
                'MetaFolder', outDir, ...
                'Description', 'Selected units when examining kinematics', ...
                'Purpose', 'for kinematics analysis (top view)', ...
                'GeneratorFunction', '+opto_paper.track_kinematics.mlapp', ...
                'GeneratorScript', 'None', ...
                'Inputs', {'TrajectoryTopView_ANM_Session.csv', 'VideoClip', 'r'} );


            if ismethod(app,'updateProgressCounts')
                app.updateProgressCounts();
            end
        end

        function loadSelectedUnits(app)

            % need anm_session
            if isempty(app.trajSummary.Data) 
                return;
            end

            anm_session = string(app.trajSummary.Data.anm_session{1});

            % use same folder as your saveSelectedUnits() (you used pwd)
            outDir = fullfile(pwd, 'Data', 'Processed');
            fileName = sprintf('UnitsSelected_%s.csv', anm_session);
            fpath = fullfile(outDir, fileName);

            if ~isfile(fpath)
                % nothing saved yet; just leave selection as-is
                return;
            end

            Tunits = readtable(fpath);

            % ---- extract ch/unit robustly ----
            if all(ismember({'ch','unit'}, Tunits.Properties.VariableNames))
                ch = Tunits.ch;
                un = Tunits.unit;
            else
                % fallback: assume first two columns are ch and unit
                ch = Tunits{:,1};
                un = Tunits{:,2};
            end

            ch = double(ch);
            un = double(un);


            if isempty(ch)
                return;
            end

            % ---- update app properties ----
            app.selectedUnits = [ch(:), un(:)];
            app.selectedUnitsGroup = cellstr(compose('%d | %d', ch(:), un(:)));  % e.g. {'280_1'}

            % ---- update UI table ----
            if isprop(app,'ChosenUITable') && ~isempty(app.ChosenUITable) && isvalid(app.ChosenUITable)
                Tui = table(ch(:), un(:), 'VariableNames', {'ch','unit'});
                app.ChosenUITable.Data = Tui;
                app.ChosenUITable.ColumnName = {'ch','unit'};
                app.ChosenUITable.RowName = 'numbered';
                app.ChosenUITable.ColumnEditable = [false false];
                try
                    app.ChosenUITable.ColumnWidth = {40, 40};
                catch
                end
            end

            disp("Loaded selected units: " + fpath);

            % check if there is also a sdf file
            fileName_SDF = sprintf('SDFs_of_UnitsSelected_%s.mat', anm_session);
            fpath = fullfile(outDir, fileName_SDF);
            if isfile(fpath)
                % nothing saved yet; just leave selection as-is
                app.selectedUnitsSDFs = load(fpath);
            end
        end


        % ---- local helper (put at end of the same file / methods block as a private method) ----
        function key = local_extractTrailingNumber(app, s)
            s = string(s);
            m = regexp(s, '\d+$', 'match', 'once');   % returns string array (or empty strings)
            key = str2double(m);                     % empty -> NaN automatically
        end

        function fig = getAppFigure(app)
            fig = [];
            props = properties(app);
            for i = 1:numel(props)
                try
                    v = app.(props{i});
                    if isa(v,'matlab.ui.Figure') && isvalid(v)
                        fig = v;
                        return;
                    end
                catch
                end
            end
        end
        
        function localizePSTHfolder(app)
            [~, folderPath] = uigetfile({'*.png'}, 'Select a PSTH'); 
            app.PSTH_folder = folderPath;
        end
        
        function visualizePopActivity(app)

            if isempty(app.selectedUnitsSDFs)
                warndlg('Rank units first')
            end

            if isfield(app.selectedUnitsSDFs, 'out')
                data = app.selectedUnitsSDFs.out;
            else
                data = app.selectedUnitsSDFs;
            end

            sdf = data.sdfs;
            t = data.t_single;

            cla(app.colorMapSDF)
            app.colorMapSDF.NextPlot = 'add';
            imagesc(app.colorMapSDF, t, (1:size(sdf, 1)), sdf, [-0.2 0.8]);
            app.colorMapSDF.XLim = [1 t(end)];
            app.colorMapSDF.YLim = [0.5 size(sdf, 1)+0.5];

            for i =1:numel(data.event_markers)
                xline(app.colorMapSDF, data.event_markers(i), 'Color','w',...
                    'LineWidth', 2, 'LineStyle',':');
            end
            app.colorMapSDF.XLabel.String = '(ms)';
            app.colorMapSDF.YLabel.String = 'units';
            app.colorMapSDF.Title.String = 'SDFs';
            app.colorMapSDF.Position(3) = 160;
            app.colorMapSDF.Position(2) = 16;
            app.colorMapSDF.Position(1) = 415;
            app.colorMapSDF.YDir = 'reverse';
            % delete old colorbar if it exists
            if ~isempty(app.hbar) && isvalid(app.hbar)
                delete(app.hbar);
            end

            % create new colorbar
            app.hbar = colorbar(app.colorMapSDF);
            app.hbar.Units = 'pixels';
            app.hbar.Label.String = 'norm. activity';
            app.hbar.Position(3) = 6;
            app.hbar.Position(1) = 576;

        end

        function resetTrajSummarySelection(app)
            % clear your cached selection
            if isprop(app,'trajSummarySelectedRow')
                app.trajSummarySelectedRow = [];
            end

            % clear table selection (not always settable, so be defensive)
            try
                app.trajSummary.Selection = [];   % some versions support this
            catch
            end

            % force UI to settle focus
            drawnow;
        end


        function ok = loadAndShowTrialVideo(app, trialName)
            cla(app.VideoClipAxis)
            % loadAndShowTrialVideo Load video + meta for a trial and display first frame.
            %
            % Inputs
            %   trialName : char/string, e.g. "Top_Press_229539"
            %
            % Returns
            %   ok : true if loaded successfully, false otherwise

            ok = false;

            if nargin < 2 || isempty(trialName)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'trialName is empty.', 'Video');
                return
            end

            if isempty(app.folderPath) || ~isfolder(app.folderPath)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'folderPath is empty or invalid. Please select a folder first.', 'Video');
                return
            end

            trialName = string(trialName);

            % filenames
            video_name      = sprintf('%s.mp4', trialName);
            video_meta_name = sprintf('%s.mat', trialName);

            % update dropdown if item exists
            if isprop(app, 'VideocliplistDropDown') && ~isempty(app.VideocliplistDropDown.Items)
                ind = strcmp(app.VideocliplistDropDown.Items, video_name);
                if any(ind)
                    app.VideocliplistDropDown.Value = app.VideocliplistDropDown.Items{find(ind,1,'first')};
                end
            end

            % locate video
            vfile_path = fullfile(app.folderPath, video_name);
            if ~isfile(vfile_path)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf('Cannot find video:\n%s', vfile_path), 'Video');
                return
            end

            % locate meta
            meta_path = fullfile(app.folderPath, video_meta_name);
            if ~isfile(meta_path)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf('Cannot find meta file:\n%s', meta_path), 'Video');
                return
            end

            % load meta
            try
                app.VideoMeta = load(meta_path);
            catch ME
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf('Failed to load meta:\n%s', ME.message), 'Video');
                return
            end

            % compute time range of the video clip
            app.videoTimeRange = [min( app.VideoMeta.VideoInfo.EphysTimeStamps-app.VideoMeta.VideoInfo.Time) max( app.VideoMeta.VideoInfo.EphysTimeStamps-app.VideoMeta.VideoInfo.Time)];

            % open video
            try
                app.currentVideo = VideoReader(vfile_path);
            catch ME
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf('Failed to open video:\n%s', ME.message), 'Video');
                return
            end

            % title
            if isprop(app, 'VideoClipAxis') && isvalid(app.VideoClipAxis)
                app.VideoClipAxis.Title.String =  sprintf('%s_%s', app.trajSummary.Data.anm_session{1}, video_name);
                app.VideoClipAxis.Title.FontSize = 15;
                app.VideoClipAxis.Title.Interpreter = 'none';
            end

            % total frames (guard against weird metadata)
            totalFrames = max(1, floor(app.currentVideo.Duration * app.currentVideo.FrameRate));

            % slider update
            if isprop(app, 'VideoSlider') && isvalid(app.VideoSlider)
                app.VideoSlider.Limits = [1, totalFrames];
                step = max(1, round(totalFrames/10));
                app.VideoSlider.MajorTicks = 1:step:totalFrames;
                app.VideoSlider.Value = 1;
            end

            % labels
            if isprop(app, 'FrameLabel') && isvalid(app.FrameLabel)
                app.FrameLabel.Text = 'Frame: 1';
            end
            if isprop(app, 'FrameTimeLabel') && isvalid(app.FrameTimeLabel)
                app.FrameTimeLabel.Text = 't (ms) = 0';
            end

            % current time in ephys (if present)
            try
                app.currentFrameTime = app.VideoMeta.VideoInfo.EphysTimeStamps(1);
            catch
                app.currentFrameTime = NaN;
            end

            % read + show first frame
            try
                % ensure we start from the beginning
                app.currentVideo.CurrentTime = 0;
                firstFrame = readFrame(app.currentVideo);
            catch ME
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf('Failed to read first frame:\n%s', ME.message), 'Video');
                return
            end

            if isprop(app, 'VideoClipAxis') && isvalid(app.VideoClipAxis)
                app.RawFrame = firstFrame;        % store raw
                app.refreshFrameBrightness();     % show using knob gain
            end

            ok = true;
        end

        function plotComputedKinematicsToKinematicsAxis(app, varargin)
            % Plot computed kinematics from app.ComputeButton.UserData.TrajTable
            % onto app.poseAxis.
            %
            % Optional name-value:
            %   'Kinematic'  : column name (default = app.KinematicDropDown.Value)
            %   'TrialName'  : e.g. "Top_Press_229539" (if not available in table)

            % ---- defaults ----
            kin = "";
            trialName = "";

            if ~isempty(varargin)
                p = inputParser;
                p.addParameter('Kinematic', "", @(s) ischar(s) || isstring(s));
                p.addParameter('TrialName', "", @(s) ischar(s) || isstring(s));
                p.parse(varargin{:});
                kin = string(p.Results.Kinematic);
                trialName = string(p.Results.TrialName);
            end

            if kin == ""
                if isprop(app, 'KinematicDropDown') && ~isempty(app.KinematicDropDown.Value)
                    kin = string(app.KinematicDropDown.Value);
                else
                    kin = "y_s";
                end
            end

            % ---- fetch table from UserData ----
            if ~isprop(app, 'ComputeButton') || isempty(app.ComputeButton.UserData) || ...
                    ~isfield(app.ComputeButton.UserData, 'TrajTable') || isempty(app.ComputeButton.UserData.TrajTable)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'No TrajTable found in app.ComputeButton.UserData.', 'Plot kinematics');
                return
            end

            TT = app.ComputeButton.UserData.TrajTable;

            if ~istable(TT) || height(TT) < 2
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'TrajTable is empty or invalid.', 'Plot kinematics');
                return
            end

            % ---- determine t0 from trial name digits ----
            % priority: TrialName argument > TT.Source{1} > app.currentTrial > TT.time(1)
            if trialName == ""
                if any(strcmp(TT.Properties.VariableNames, 'Source'))
                    s = TT.Source(1);
                    if iscell(s); s = s{1}; end
                    trialName = string(s);
                elseif isprop(app, 'currentTrial') && ~isempty(app.currentTrial)
                    trialName = string(app.currentTrial);
                end
            end

            tok = regexp(trialName, '\d+$', 'match', 'once');
            if isempty(tok)
                t0_ms = TT.time(1);
            else
                t0_ms = str2double(tok);  % assume ms
            end

            % ---- time relative (sec) ----
            if ~any(strcmp(TT.Properties.VariableNames, 'time'))
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'TrajTable has no "time" column.', 'Plot kinematics');
                return
            end

            % ---- make sure requested column exists ----
            if ~any(strcmp(TT.Properties.VariableNames, kin))
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, sprintf('TrajTable has no column "%s".', kin), 'Plot kinematics');
                return
            end

            % Find the selected body parts
            body = app.BodytrackedDLCDropDown.Value;
            ind = strcmp(TT.body_part, body);

            y = TT.(kin)(ind);
            t_rel = (TT.time(ind) - t0_ms);  % seconds

            % ---- plot ----
            % t_start = app.startTimeChosenLine.Value;
            % t_end = app.endTimeChosenLine.Value;
            
            ax = app.kinematicsAxis;
            if ~app.HoldCheckBox.Value
                cla(ax);
            end
            hold(ax, 'on');

            % add behavioral variables

            m = isfinite(t_rel) & isfinite(y);
            plot(ax, t_rel(m), y(m), '.', 'LineWidth',2, 'MarkerSize',10);

            % % add start-end boundary
            % app.startTimeChosenLine = xline(app.kinematicsAxis, t_start, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');
            % app.endTimeChosenLine = xline(app.kinematicsAxis, t_end, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');
            % smoothed overlay (red)
            % window in samples (frames); tweak as desired
            win = 7;
            if nnz(m) >= 3
                y_s = movmean(double(y(m)), min(win, nnz(m)), 'omitnan');
                plot(ax, t_rel(m), y_s, '-', 'Color', 'r', 'LineWidth', 2);
            end
            grid(ax, 'on');
            xlabel(ax, 't - trial# (s)');
            ylabel(ax, strrep(kin, '_', '\_'));
            hold(ax, 'off');

            if strcmp(kin, "y_s")
                app.kinematicsAxis.YDir = 'reverse';
            end

            app.kinematicsAxis.YLim = [min(y)-0.2*range(y) max(y)+0.2*range(y)];
            to_show_sr = sprintf('%s_%s', TT.trial{1}, TT.lever_phase{1});
            text(ax, ax.XLim(2)-1000, ax.YLim(1)+0.1*diff(ax.YLim), to_show_sr, 'FontSize', 12, 'Interpreter','none')

            % add a red line marking current frame
            app.currentFrameLine = xline(app.kinematicsAxis, app.currentFrameTime-t0_ms, 'Color', 'r', 'LineWidth', 2);
            app.kinematicsAxis.XLim = [app.PreEditField.Value app.PostEditField.Value];
            % if ~isempty(t_rel)
            %     dt = max(t_rel)-min(t_rel);
            %     if t_rel(end)+.5*dt>t_rel(1)-.5*dt
            %         app.kinematicsAxis.XLim = [t_rel(1)-.5*dt t_rel(end)+.5*dt];
            %     end
            % end
            % plot the head position trajectory on the image
            colors = {'r', 'r'};
            marker_size = 40;
            marker_alpha = 0.25;
 
            if strcmp(app.ComputeButton.UserData.Lever, 'toLever')
                if ~isempty(app.toLeverHeadScatter) && isvalid(app.toLeverHeadScatter)
                    delete(app.toLeverHeadScatter);
                end

                app.toLeverHeadScatter = scatter(app.VideoClipAxis, TT.x, TT.y, ...
                    'MarkerEdgeColor',colors{1}, ...
                    'SizeData',marker_size, 'Marker', '+', 'MarkerFaceAlpha', marker_alpha, 'LineWidth',1);
            elseif strcmp(app.ComputeButton.UserData.Lever, 'fromLever')
                if ~isempty(app.fromLeverHeadScatter) && isvalid(app.fromLeverHeadScatter)
                    delete(app.fromLeverHeadScatter);
                end
                app.fromLeverHeadScatter = scatter(app.VideoClipAxis, TT.x, TT.y, ...
                    'MarkerEdgeColor',colors{2}, ...
                    'SizeData',marker_size, 'Marker', '+', 'MarkerFaceAlpha', marker_alpha, 'LineWidth',1);
            end
            app.mark_current_head;
        end

        function mark_current_head(app)
            % ---- fetch table from UserData ----
            if ~isprop(app, 'ComputeButton') || isempty(app.ComputeButton.UserData) || ...
                    ~isfield(app.ComputeButton.UserData, 'TrajTable') || isempty(app.ComputeButton.UserData.TrajTable)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'No TrajTable found in app.ComputeButton.UserData.', 'Plot kinematics');
                return
            end

            TT = app.ComputeButton.UserData.TrajTable;

            % mark current spot with a big *
            % ---- update current head marker (no replot of clouds) ----
            if isprop(app,'currentBodyPoint') && ~isempty(app.currentBodyPoint)
                delete(app.currentBodyPoint);
            end

            % current_time should be in the SAME timebase as app.currentKinematics.time
            % Here we use FrameTime computed above from VideoTimeStamps.
            current_time = app.currentFrameTime;   % seconds (if your vt is seconds)

            tk = TT.time;

            if ~isempty(tk) && current_time >= min(tk) && current_time <= max(tk)

                [~, ind] = min(abs(tk - current_time));
                x_current = TT.x(ind);
                y_current = TT.y(ind);   % <-- fix bug
  
                % delete old point if it exists
                if ~isempty(app.currentBodyPoint) && isvalid(app.currentBodyPoint)
                    delete(app.currentBodyPoint);
                end
                % recreate
                app.currentBodyPoint = scatter(app.VideoClipAxis, x_current, y_current, ...
                    60, 'c', '*', 'LineWidth', 2);
               
            end
        end

        function y_u = unwrapAngleBySegments_(app, t_rel, y, TT)
            % Unwrap angle within continuous valid segments.
            y_u = y;

            n = numel(y);
            if n < 2
                return
            end

            % Use kept_mask if present, else require finite time+angle
            if any(strcmp(TT.Properties.VariableNames, 'kept_mask'))
                segMask = TT.kept_mask(:) & isfinite(t_rel) & isfinite(y);
            else
                segMask = isfinite(t_rel) & isfinite(y);
            end

            d = diff([false; segMask; false]);
            starts = find(d == 1);
            ends   = find(d == -1) - 1;

            for s = 1:numel(starts)
                idx = starts(s):ends(s);
                y_u(idx) = unwrap(y_u(idx));
            end
        end


        function Ts = summarizeTableKinematicsTopPressToFromLever(app)
            %SUMMARIZETABLEKINEMATICSTOPPRESSTOFROMLEVER
            % Create a summary table from app.tableKinematics keeping only:
            %   - trials matching "Top_Press_<digits>"
            %   - lever_phase in {"toLever","fromLever"}
            %
            % Output Ts columns:
            %   Trials, trial, lever_phase, n_samples, t_start, t_end, dur_ms, anm_session

            T = app.tableKinematics;

            if isempty(T) || ~istable(T) || height(T)==0
                Ts = table();
                return
            end

            % ---- required columns ----
            req = {'trial','lever_phase','time'};
            for i = 1:numel(req)
                if ~ismember(req{i}, T.Properties.VariableNames)
                    error('app.tableKinematics missing required column "%s".', req{i});
                end
            end

            trial = string(T.trial);
            phase = string(T.lever_phase);

            % ---- filter: Top_Press_<digits> only ----
            isTopPress = ~cellfun('isempty', regexp(cellstr(trial), '^Top_Press_\d+$', 'once'));

            % ---- filter: toLever/fromLever only ----
            isToFrom = (phase == "toLever") | (phase == "fromLever");

            keep = isTopPress & isToFrom;

            if ~any(keep)
                Ts = table();
                return
            end

            Tk = T(keep, :);
            Tk.trial = string(Tk.trial);
            Tk.lever_phase = string(Tk.lever_phase);

            % optional: keep session if present
            hasSess = ismember('anm_session', Tk.Properties.VariableNames);
            if hasSess
                Tk.anm_session = string(Tk.anm_session);
            end

            % ---- group by (trial, lever_phase) ----
            [G, trialG, phaseG] = findgroups(Tk.trial, Tk.lever_phase);

            n_samples = splitapply(@numel, Tk.time, G);
            t_start   = splitapply(@min,   Tk.time, G);
            t_end     = splitapply(@max,   Tk.time, G);
            dur_ms    = t_end - t_start;

            if hasSess
                sessG = splitapply(@(x) x(1), Tk.anm_session, G);
            else
                sessG = repmat("", numel(trialG), 1);
            end

            Trials = trialG + "_" + phaseG;

            Ts = table(Trials, trialG, phaseG, n_samples, t_start, t_end, dur_ms, sessG, ...
                'VariableNames', {'Trials','trial','lever_phase','n_samples','t_start','t_end','dur_ms','anm_session'});

            % sort for readability
            Ts = sortrows(Ts, {'trial','lever_phase'});

        end


        function gain = getVideoGain(app)
            gain = 1;
            if isprop(app,'AdjustBrightnessKnob') && ~isempty(app.AdjustBrightnessKnob) && isvalid(app.AdjustBrightnessKnob)
                g = double(app.AdjustBrightnessKnob.Value);
                if isfinite(g)
                    gain = max(0, g);
                end
            end
        end

        function refreshFrameBrightness(app)
            % Update displayed frame based on RawFrame and AdjustKnob.
            if ~isprop(app,'VideoClipAxis') || isempty(app.VideoClipAxis) || ~isvalid(app.VideoClipAxis)
                return
            end
            if isempty(app.RawFrame)
                return
            end

            gain = app.getVideoGain();

            % Apply gain for RGB uint8
            brightFrame = uint8(min(double(app.RawFrame) * gain, 255));

            % Create or update image object
            if isprop(app,'FrameImg') && ~isempty(app.FrameImg) && isvalid(app.FrameImg)
                app.FrameImg.CData = brightFrame;
            else
                app.FrameImg = imshow(brightFrame, 'Parent', app.VideoClipAxis);
            end

            % Keep axes consistent
            app.VideoClipAxis.XLim = [0 size(brightFrame,2)];
            app.VideoClipAxis.YLim = [0 size(brightFrame,1)];

            % Keep aspect ratio using your original logic (guard for App Designer layout)
            try
                app.VideoClipAxis.Position(4) = (app.VideoClipAxis.Position(3)/size(brightFrame,2)) * size(brightFrame,1);
            catch
                % ignore if layout manager prevents direct resizing
            end
        end

        function updateTrackedTrialsListBox(app)
            % ListBox items like: Side_Press_1005084_toLever (812)
            % Label text: Trials tracked: ##

            if ~isprop(app,'TrialstrackedListBox') || isempty(app.TrialstrackedListBox) || ~isvalid(app.TrialstrackedListBox)
                return
            end
            hasLabel = isprop(app,'TrialsTrackedLabel') && ~isempty(app.TrialsTrackedLabel) && isvalid(app.TrialsTrackedLabel);

            if isempty(app.tableKinematics) || ~istable(app.tableKinematics) || height(app.tableKinematics)==0 || ...
                    ~ismember('trial', app.tableKinematics.Properties.VariableNames) || ...
                    ~ismember('lever_phase', app.tableKinematics.Properties.VariableNames)

                app.TrialstrackedListBox.Items = {};
                app.TrialstrackedListBox.Value = {};
                if hasLabel, app.TrialsTrackedLabel.Text = 'Trials tracked: 0'; end
                return
            end

            tr = string(app.tableKinematics.trial);
            ph = string(app.tableKinematics.lever_phase);
            good = (tr ~= "") & (ph ~= "") & isfinite(strlength(tr)) & isfinite(strlength(ph));
            tr = tr(good); ph = ph(good);

            key = tr + "_" + ph; % e.g. Side_Press_1005084_toLever

            % unique keys + counts
            [uKey, ~, gi] = unique(key, 'stable');
            counts = accumarray(gi, 1);

            % Optional: nice sort by trial numeric suffix, then phase
            try
                % split key into trial + phase
                parts = split(uKey, "_");
                % last token is phase, the rest join back as trial (in case trial contains underscores)
                phase = parts(:, end);
                trialOnly = join(parts(:,1:end-1), "_");

                tok = regexp(trialOnly, '(\d+)$', 'tokens', 'once');
                tn = nan(size(trialOnly));
                hasTok = ~cellfun(@isempty, tok);
                tn(hasTok) = cellfun(@(c) str2double(c{1}), tok(hasTok));

                % sort by tn then phase
                [~, ord] = sortrows(table(tn, phase), {'tn','phase'});
                uKey = uKey(ord);
                counts = counts(ord);
            catch
            end

            items = arrayfun(@(k,c) sprintf('%s (%d)', k, c), uKey, counts, 'UniformOutput', false);

            % preserve selection by key (ignoring "(count)")
            oldVal = string(app.TrialstrackedListBox.Value);
            oldKey = regexprep(oldVal, '\s+\(\d+\)$', '');

            app.TrialstrackedListBox.Items = items;

            keep = oldKey(ismember(oldKey, string(uKey)));
            if isempty(keep)
                app.TrialstrackedListBox.Value = {};
            else
                % map keep keys back to items
                keepItems = cell(0,1);
                for kk = 1:numel(keep)
                    idx = find(string(uKey) == keep(kk), 1, 'first');
                    if ~isempty(idx)
                        keepItems{end+1,1} = items{idx}; %#ok<AGROW>
                    end
                end
                app.TrialstrackedListBox.Value = keepItems;
            end

            if hasLabel
                app.TrialsTrackedLabel.Text = sprintf('Trials tracked: %d', numel(uKey));
            end
        end

        function stopPickPoint(app)
            app.isPickingPoint = false;
            app.VideoClipAxis.Title.String = '';
            app.VideoClipAxis.Color = 'w';

            % detach callbacks (optional)
            app.VideoClipAxis.ButtonDownFcn = [];
            if ~isempty(app.FrameImg) && isvalid(app.FrameImg)
                app.FrameImg.ButtonDownFcn = [];
            end
        end


        function updateUnTrackedListBox(app, leverPhase)
            %UPDATEUNTRACKEDLISTBOX Update UnTrackedListBox with untracked BODY PARTS for current trial.
            %
            % Logic:
            %   - If current trial has no tracked rows (in app.tableKinematics) -> list ALL body parts.
            %   - Else -> list body parts that are NOT yet present for this trial (optionally within leverPhase).
            %
            % Inputs
            %   leverPhase (optional): "toLever" / "fromLever". If provided, only consider that phase.

            % ---- guards ----
            if ~isprop(app,'UnTrackedListBox') || isempty(app.UnTrackedListBox) || ~isvalid(app.UnTrackedListBox)
                return
            end
            if ~isprop(app,'BodytrackedDLCDropDown') || isempty(app.BodytrackedDLCDropDown) || ~isvalid(app.BodytrackedDLCDropDown)
                app.UnTrackedListBox.Items = {};
                app.UnTrackedListBox.Value = {};
                return
            end
            if ~isprop(app,'currentTrial') || isempty(app.currentTrial) || strlength(string(app.currentTrial))==0
                app.UnTrackedListBox.Items = {};
                app.UnTrackedListBox.Value = {};
                return
            end

            trial = string(app.currentTrial);
            allBP = string(app.BodytrackedDLCDropDown.Items);
            allBP = allBP(allBP ~= "");

            % ---- if no table or missing columns -> nothing tracked yet -> show all ----
            if isempty(app.tableKinematics) || ~istable(app.tableKinematics) || height(app.tableKinematics)==0 || ...
                    ~ismember('trial', app.tableKinematics.Properties.VariableNames) || ...
                    ~ismember('body_part', app.tableKinematics.Properties.VariableNames)
                setList(allBP);
                return
            end

            T = app.goodTrajTable.Data;

            % ---- rows for this trial ----
            indTrial = string(T.trial) == trial;

            % optional: filter by leverPhase if provided AND column exists
            usePhase = (nargin >= 2) && ~isempty(leverPhase) && strlength(string(leverPhase))>0 && ...
                ismember('lever_phase', T.Properties.VariableNames);
            if usePhase
                indTrial = indTrial & (string(T.lever_phase) == string(leverPhase));
            end

            if ~any(indTrial)
                % trial not present (or not present for this phase) -> nothing tracked yet
                setList(allBP);
                return
            end

            % ---- tracked body parts for this trial (and phase if used) ----
            trackedBP = unique(string(T.body_part(indTrial)), 'stable');
            trackedBP = trackedBP(trackedBP ~= "");

            % ---- remaining body parts ----
            remaining = allBP(~ismember(allBP, trackedBP));

            setList(remaining);
            return

            % ---- nested helper to set list while preserving selection if possible ----
            function setList(itemsStr)
                itemsStr = string(itemsStr);
                itemsStr = itemsStr(itemsStr ~= "");

                oldVal = string(app.UnTrackedListBox.Value);

                app.UnTrackedListBox.Items = cellstr(itemsStr);

                keepVal = oldVal(ismember(oldVal, itemsStr));
                if isempty(keepVal)
                    app.UnTrackedListBox.Value = {};
                else
                    app.UnTrackedListBox.Value = cellstr(keepVal);
                end
            end
        end

    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AdvancedkinematicsanalysissideviewUIFigure.AutoResizeChildren = 'off';
        end

        % Button pushed function: SelectvideopathButton
        function SelectvideopathButtonPushed(app, event)
            app.hasSavedInitialRefinedTable = false;
            % 1) Decide whether to reuse saved folder
            useSaved = false;

            if isempty(app.folderPath) || isempty(app.fileName) || ~isfolder(app.folderPath)
                % no saved folder (or invalid) -> must browse
                useSaved = false;
            else
                % ask user
                choice = uiconfirm(app.AdvancedkinematicsanalysissideviewUIFigure, ...
                    sprintf("Use saved folder?\n%s", app.folderPath), ...
                    "Select folder", ...
                    "Options", ["Yes","No","Cancel"], ...
                    "DefaultOption", "Yes", ...
                    "CancelOption", "Cancel");

                switch choice
                    case "Yes"
                        useSaved = true;
                    case "No"
                        useSaved = false;
                    otherwise % "Cancel" or window closed
                        return;
                end
            end

            % 2) If not using saved, open file dialog and save the chosen folder
            if ~useSaved
                [fileName, folderPath] = uigetfile( ...
                    {'*.mp4;*.avi;*.mov', 'Video Files (*.mp4, *.avi, *.mov)'; '*.*', 'All Files (*.*)'}, ...
                    'Select a Video');

                if isequal(fileName, 0)
                    return; % user canceled
                end

                % save for next time
                app.folderPath = folderPath;
                app.fileName = fileName;
            else
                % using saved folder; up to you what "no need to select again" means:
                % - if you also saved last file name, reuse it
                % - otherwise you still need to choose a file inside that folder

                if exist(app.folderPath, 'dir')
                    folderPath = app.folderPath;
                else
                    % If you haven't saved a file name, you can either:
                    % (A) do nothing here and let later UI pick a file from the folder, OR
                    % (B) prompt for file but start in that folder:
                    [fileName, folderPath] = uigetfile( ...
                        {'*.mp4;*.avi;*.mov', 'Video Files (*.mp4, *.avi, *.mov)'; '*.*', 'All Files (*.*)'}, ...
                        'Select a Video', ...
                        app.folderPath);

                    if isequal(fileName, 0)
                        return;
                    end

                    app.folderPath = folderPath;
                    app.fileName = fileName;
                end
            end

            folderPath = app.folderPath;
            fileName = app.fileName;

            % Get a list of all videos in the same folder
            videoFiles = dir(fullfile(folderPath, 'Side*.mp4'));
            videoFiles = [videoFiles; dir(fullfile(folderPath, 'Side*.avi'))];
            videoFiles = [videoFiles; dir(fullfile(folderPath, 'Side*.mov'))];

            videoMetaFileName = [extractBefore(fileName, '.mp4') '.mat'];
            app.VideoMeta  = load(fullfile(folderPath, videoMetaFileName));
            app.currentFolder = pwd;
            app.folderPath = folderPath;
            rel = extractAfter(folderPath, app.currentFolder);
            rel = char(rel);                  % if you want a char, not string
            rel = regexprep(rel, '^[/\\]', '');  % drop leading / or \ if present
            app.videoPath.Text = rel;
            app.videoPath.FontName = 'Helvetica';
            app.videoPath.FontSize = 9;

            % Extract the names of the video files
            videoNames = {videoFiles.name};
            % Update the ListBox with video names
            % sort stuff
            % Example list of files in the folder (replace this with actual files list)
            % Extract numeric part of each file name and store in an array
            nums = cellfun(@(x) str2double(regexp(x, '(?<=_)\d+(?=\.mp4)', 'match')), videoNames);           % Sort the files based on the numeric part
            [~, idx] = sort(nums);
            % Reorder the file list according to the sorted order
            sorted_files = videoNames(idx);
            % Update the ListBox with the sorted file list (assumed ListBox name is ListBox)
            app.VideocliplistDropDown.Items = sorted_files;
            app.allTrials = sorted_files;

            % --- Use this to update the app.trajSummary table ---
            if isempty(app.rData)
                LoadrarrayButtonPushed(app)
            end

            all_video_names     =   sorted_files';
            all_trials_names    =   cell(numel(all_video_names), 1);
            all_event_table     =   table();

            event_times = zeros(numel(all_video_names), 1);
            event = '';

            for i =1:numel(all_video_names)
                i_trial_name            =       extractBefore(all_video_names{i}, '.mp4');
                tok                     =       regexp(i_trial_name, '\d+$', 'match', 'once');   % trailing digits

                % --- find corresponding trial in the event table ---
                t_event = str2double(tok);

                if isempty(event)
                    parts = split(string(i_trial_name), "_");
                    event = parts(2);   % "Press"
                end

                event_time_type = sprintf('t_%s', lower(event));

                [min_dist, ind] = min(abs(app.rData.EventTable.(event_time_type)-t_event));
                if min_dist<10
                    iET = app.rData.EventTable(ind, :);
                    all_event_table = [all_event_table; iET];
                end

                event_times(i)          =       str2double(tok);
                all_trials_names{i}     =       i_trial_name;

            end

            ind_empty = find(event_times == 0);
            if any(ind_empty)
                all_trials_names(ind_empty) = [];
                event_times(ind_empty) = [];
                all_video_names(ind_empty) = [];
            end

            all_DLC_names = cell(numel(all_trials_names), 1);
            event_marker = sprintf('event_time_%s', event);

            anm_session = sprintf('%s_%s',app.rData.BehaviorClass.Subject, app.rData.BehaviorClass.Date);
            anm_session_col = repmat({anm_session}, numel(all_trials_names), 1);
            trajTableSummary = table(all_trials_names, event_times, all_video_names, all_DLC_names, ...
                'VariableNames', {'Trials', event_marker, 'VideoClips', 'DLCResults'});

            trajTableSummary = [trajTableSummary all_event_table];
            app.trajSummary.Data = trajTableSummary;
            app.trajSummary.ColumnName = trajTableSummary.Properties.VariableNames;

            % Make row names
            T = app.trajSummary.Data;      % your 170x19 table

            % 1) extract the first column (assuming it is named 'Trials')
            rn = T.Trials;                 % could be string/cell/char/numeric

            % 2) convert to row names (string array is fine)
            rn = string(rn);

            % 3) set RowName on the uitable
            app.trajSummary.RowName = rn;

            % 4) optional: hide the Trials column now that it's the row header

            app.trajSummary.Data = T;

            app.SelectvideopathButton.BackgroundColor = '#A2CB8B';
            app.SelectvideopathButton.FontColor = 'k';

            % -- show this video --
            app.currentTrial = extractBefore(fileName, '.mp4');   % however you set it
            ok = app.loadAndShowTrialVideo(app.currentTrial);
            if ~ok
                return
            end

        end

        % Button pushed function: TrajExtractedfolderButton
        function TrajExtractedfolderButtonPushed(app, event)

            folderPath = uigetdir(pwd, 'Select the "TrajExtraced" folder from your rVideoViewer app');
            if isequal(folderPath,0)
                return; % user canceled
            end

            app.TrajExtractedFolder_ = folderPath;
  
        end

        % Button pushed function: LoadrarrayButton
        function LoadrarrayButtonPushed(app, event)

            rFile = dir('RTarray*.mat');
            if isempty(rFile) || length(rFile)>1
                % Open a dialog box to select a .mat file
                [fileName, folderPath_] = uigetfile('RTarray*.mat', 'Select the rArray file');
            else
                fileName = rFile.name;
                folderPath_ = rFile.folder;
            end

            % Check if the user selected a file
            if isequal(fileName, 0)
                disp('No file selected.');
                return;
            end

            % Construct the full path of the selected file
            matFilePath = fullfile(folderPath_, fileName);
            % Load the .mat file and save its contents to app.rData
            load(matFilePath);
            app.rData = r;
            disp(['MAT file loaded successfully: ', matFilePath]);
            app.LoadrarrayButton.BackgroundColor = '#A2CB8B';

            % --- read unit list and list units in app.UnitlistDropDown
            unit_list = cell(size(r.Units.SpikeNotes, 1), 1);
            for i =1:size(r.Units.SpikeNotes, 1)
                if r.Units.SpikeNotes(i, 3) == 1
                    type = 's';
                else
                    type = 'm';
                end
                unit_list{i} = sprintf('ch%d_unit%d_%s', r.Units.SpikeNotes(i, 1), r.Units.SpikeNotes(i, 2), type);
            end
            app.UnitlistDropDown.Items = unit_list;

            % --- load selected unit table ---
            loadSelectedUnits(app)
        end

        % Button pushed function: CheckatrialButton
        function CheckatrialButtonPushed(app, event)
            
            app.ComputeButton.UserData = [];
            app.ApplyallCheckBox.Value = false;

            % The following data are plotted:
            % 1. Video clips corresponding to current trial
            % 2. If there is DLC file, plot the selected body parts over
            % the video
            % 3. Check if ephys data are available, if so, plot those too.
            % 4. Head position
            app.currentTrial = app.pressindex.Value;

            % -- show this video --
            ok = app.loadAndShowTrialVideo(app.currentTrial);
            if ~ok
                return
            end

            % check if trajectory should be plotted
            if app.addTraj.Value
                addTrajValueChanged(app, event);
            end

            app.UnTrackedListBox.Items = app.BodytrackedDLCDropDown.Items;

            % --- label the behavior data ---
            behavior_this_trial = app.trajSummary.Data(strcmp(app.trajSummary.Data.Trials, app.currentTrial), :);
            app.currentTrialBehavior = behavior_this_trial;
            % 
            t_press = behavior_this_trial.t_press;
            t_release = behavior_this_trial.t_release-t_press;
            t_poke = behavior_this_trial.t_poke-t_press;
            fp = behavior_this_trial.FP;
               
            % --- add kinematic feature ---
            cla(app.kinematicsAxis)
            app.kinematicsAxis.XLim = app.videoTimeRange;
            app.kinematicsAxis.NextPlot = 'add';
            app.kinematicsAxis.XTickLabel = [];

            % add a red line marking current frame
            app.currentFrameLine = xline(app.kinematicsAxis, app.currentFrameTime-t_press, 'Color', 'r', 'LineWidth', 2);
            
            % --- add spikes if units were selected ---
            cla(app.spikesAxis)
            app.spikesAxis.XLim = [app.PreEditField.Value app.PostEditField.Value];
            app.spikesAxis.NextPlot = 'add';
            app.spikesAxis.YDir = 'reverse';
            app.spikesAxis.FontSize = 9;
            app.spikesAxis.YGrid = "on";
            app.spikesAxis.XLabel.String = 'time (ms)';
            app.currentFrameLineSpikes = xline(app.spikesAxis, app.currentFrameTime-t_press, 'Color', 'r', 'LineWidth', 2);
            plotshaded([0 fp], [0 0; 1+size(app.selectedUnits, 1) 1+size(app.selectedUnits, 1)], '#FFB33F', 0.25, app.spikesAxis);

            if ~isempty(app.selectedUnits)
                r = app.rData;
                tickL = 0.5;
                xAll = [];
                yAll = [];
                for i = 1:size(app.selectedUnits, 1)
                    ind = find(r.Units.SpikeNotes(:, 1)==app.selectedUnits(i, 1) & r.Units.SpikeNotes(:, 2)==app.selectedUnits(i, 2));
                    spk_times = r.Units.SpikeTimes(ind).timings;
                    ind = find(spk_times>t_press+app.kinematicsAxis.XLim(1) & spk_times<t_press+app.kinematicsAxis.XLim(2));
                    if ~isempty(ind)
                        spk_times_rel = spk_times(ind) - t_press;
                        xx = [spk_times_rel(:)'; spk_times_rel(:)'];      % 2 x N
                        yy = [(i-tickL)*ones(1,numel(spk_times_rel));
                            (i+tickL)*ones(1,numel(spk_times_rel))];
                        % add NaN separator column so different spikes don't connect
                        xAll = [xAll, xx, nan(2,1)];
                        yAll = [yAll, yy, nan(2,1)];
                    end
                end

                % plot once
                app.spkRasterLine = line(app.spikesAxis, xAll, yAll, ...
                    'Color', 'k', 'LineWidth', 1);
                % add release and poke time
                xline(app.spikesAxis, t_release, 'Color', '#134E8E', 'LineWidth', 2, 'LineStyle',':');
                if ~isnan(t_poke)
                    xline(app.spikesAxis, t_poke, 'Color', '#237227', 'LineWidth', 2, 'LineStyle',':');
                end
                % optional: nice y-lims
                app.spikesAxis.YLim = [0, size(app.selectedUnits, 1)+1];
                app.spikesAxis.YTick = (1:size(app.selectedUnits, 1));

                if size(app.selectedUnits, 1)>20
                    app.spikesAxis.FontSize = 6;
                else
                    app.spikesAxis.FontSize = 9;
                end
                set(app.spikesAxis, 'TickLabelInterpreter', 'none');
                app.spikesAxis.YTickLabel = app.selectedUnitsGroup;

            end
            app.StartTimeEditField.Value = 0;
            app.EndTimeEditField.Value = 0;

            if isempty(app.ChosenUITable.Data)
                anm_session = app.trajSummary.Data.anm_session{1};
                % use same folder as your saveSelectedUnits() (you used pwd)
                outDir = fullfile(pwd, 'Data', 'Processed');
                fileName = sprintf('UnitsSelected_%s.csv', anm_session);
                fpath = fullfile(outDir, fileName);
                if isfile(fpath)
                    loadSelectedUnits(app)
                end
            end
            app.HoldCheckBox.Value = false;
            updateUnTrackedListBox(app, "toLever")

            updateTrackedTrialsListBox(app)

            if any(strcmp(app.UnTrackedListBox.Items, app.BodytrackedDLCDropDown.Value))
                app.UnTrackedListBox.Value = app.BodytrackedDLCDropDown.Value;
            end

            app.ManualfixButton.UserData = [];
        end

        % Cell selection callback: trajSummary
        function trajSummaryCellSelection(app, event)
            
            indices = event.Indices;
            % event.Indices is N×2: [row, col] for each selected cell
            row = event.Indices(1,1);

            app.currentTrial = event.Source.Data.Trials{row};
            updateText(app, row)
            
            CheckatrialButtonPushed(app, event)
           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            if isempty(app.currentVideo)
                disp('No video loaded.');
                return;
            end

            app.PlaySwitch.Value = 'On';

            totalFrames = floor(app.currentVideo.Duration * app.currentVideo.FrameRate);
            currentFrame = round(app.VideoSlider.Value);

            if currentFrame == totalFrames
                app.VideoSlider.Value = 1;
                currentFrame = 1;
            end

            dt = 1 / app.currentVideo.FrameRate;
            
            for frameIndex = currentFrame:totalFrames
                if strcmp(app.PlaySwitch.Value, 'Off')
                    break;
                end
                
                t0 = tic;
                app.updateToFrame(frameIndex);
                elapsed = toc(t0);
                
                % ---- slow down if knob not off ----
                k = app.SpeedKnob.Value;   % could be 'off' or 2/3/4/5 or '2'...
                if ischar(k) || isstring(k)
                    k = string(k);
                    if lower(k) == "off"
                        slowFactor = 1;
                    else
                        slowFactor = str2double(k);  % '2' -> 2
                    end
                else
                    slowFactor = k;  % numeric
                end

                if ~isfinite(slowFactor) || slowFactor < 1
                    slowFactor = 1;
                end

                targetDt = dt * slowFactor;

                % only wait if we're faster than target rate
                if slowFactor>1
                    pause(max(0, targetDt - elapsed));
                end
            end
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.isPlaying = false;
        end

        % Callback function
        function MakesummarytableButtonPushed(app, event)
            if isempty(app.rData)
                LoadrarrayButtonPushed(app)
            end
            all_trials = app.allTrials;
            n_trials = numel(all_trials);
            r = app.rData;
            traj_table = table();

        end

        % Value changed function: addTraj
        function addTrajValueChanged(app, event)
            value = app.addTraj.Value;
            if value
                current_trial_name = extractBefore(app.VideocliplistDropDown.Value, '.mp4');
                % --- locate the DLC file ---
                selectedDLCFile = dir(fullfile(app.folderPath, [current_trial_name, '*.csv']));

                % --- check if deeplabcut results exist, if so, update the body
                % parts
                % make sure the correct item is selected
                if ~isempty(selectedDLCFile)  
                    if isempty(app.rData)
                        LoadrarrayButtonPushed(app)
                    end
                    selectedDLCFilePath = fullfile(selectedDLCFile(1).folder, selectedDLCFile(1).name);
                    dlcResults = read_dlc_table(selectedDLCFilePath);
                    % --- update dlcResults ---
                    app.dlcResults = dlcResults;
                    % Assuming dlcResults is already loaded or created
                    % Extract field names from dlcResults
                    fieldNames = fieldnames(dlcResults);
                    % Assign field names to the list box in your app
                    app.BodytrackedDLCDropDown.Items = fieldNames;
                    % if any(strcmp(fieldNames, 'LeftPaw'))
                    %     app.BodytrackedDLCDropDown.Value = 'LeftPaw';
                    % end
                    % Plot the first body part
                    % Extract x, y, and likelihood from dlcResults.EarLTop

                    % Use this to update the app.trajSummary table
                    all_trials_names    =   app.trajSummary.Data.Trials;
                    for i =1:numel(all_trials_names)
                        i_trial_name            =       all_trials_names{i};
                        % --- locate the DLC file ---
                        selectedDLCFile = dir(fullfile(app.folderPath, [i_trial_name, '*.csv']));
                        if isempty(selectedDLCFile)
                            app.trajSummary.Data.DLCResults{i} = 'NaN';
                        else
                            app.trajSummary.Data.DLCResults{i} = fullfile(selectedDLCFile.folder, selectedDLCFile.name);
                        end
                    end
                                     
                else
                    warndlg('Cannot find DLC tracking file')
                    return
                end

                % --- this is the body parts to plot ---
                body =  app.BodytrackedDLCDropDown.Value;
                app.currentBodyPart.Text = body;

                body_x = app.dlcResults.(body).x;
                body_y = app.dlcResults.(body).y;
                body_likelihood = app.dlcResults.(body).likelihood;

                app.VideoClipAxis.NextPlot = 'add';
                marker_size = 10;
                marker_alpha = 0.65;

                % delete old if it exists
                if ~isempty(app.BodyScatter) && isvalid(app.BodyScatter)
                    delete(app.BodyScatter);
                end

                % recreate
                app.BodyScatter = scatter(app.VideoClipAxis, body_x, body_y, ...
                    marker_size, body_likelihood, 'filled', 'MarkerEdgeColor', 'k', ...
                    'LineWidth',0.1, 'MarkerEdgeAlpha',0.5);

                colormap(app.VideoClipAxis, 'parula'); % Set colormap to 'hot'
                
                % make pseudo colorbar
                xcolor = ones(1, 100);
                ycolor = linspace(0, 1, 100);
                scatter(app.barAxis, xcolor, ycolor, 85, ycolor, 'filled', 's');
                app.barAxis.YLim = [0 1];
                app.barAxis.YAxisLocation='right';
                app.barAxis.XLim = [0.9 1.1];
                app.barAxis.Color='none';
                app.barAxis.Position(3) =25;
                app.barAxis.Position(1) = app.VideoClipAxis.Position(1)+app.VideoClipAxis.Position(3);
                colormap(app.barAxis, 'parula'); % Set colormap to 'hot'
             
                % Plot the data to poseAxis
                cla(app.poseAxis);
                t = app.VideoMeta.VideoInfo.EphysTimeStamps-app.VideoMeta.VideoInfo.Time;

                % add behavior data
                ind_row = strcmp(app.trajSummary.Data.Trials, app.currentTrial);
                app.poseAxis.NextPlot = 'add';
                marker_size = 10;
                % --- draw the FP ---
                fp = app.trajSummary.Data.FP(ind_row);
                app.currentPoseScatter = scatter(app.poseAxis, t, body_x, ...
                    marker_size, body_likelihood, 'filled', 'MarkerEdgeColor', 'k', ...
                    'LineWidth',0.1, 'MarkerEdgeAlpha',0.5);
                app.poseAxis.XLim = [app.PreEditField.Value app.PostEditField.Value];
                y_range = app.poseAxis.YLim;
                if any(ind_row)
                    plotshaded([0 fp], [y_range(1) y_range(1); y_range(2) y_range(2)], '#FFB33F', 0.25, app.poseAxis);
                    t_release = app.trajSummary.Data.t_release(ind_row)-app.trajSummary.Data.t_press(ind_row);
                    xline(app.poseAxis, t_release, 'Color', '#134E8E', 'LineWidth', 2, 'LineStyle',':');
                    t_poke = app.trajSummary.Data.t_poke(ind_row)-app.trajSummary.Data.t_press(ind_row);
                    if ~isnan(t_poke)
                        xline(app.poseAxis, t_poke, 'Color', '#237227', 'LineWidth', 2, 'LineStyle',':');
                    end
                end
                app.poseAxis.XTickLabel = [];
                app.poseAxis.YDir = 'reverse';
                % add a red line marking current frame
                app.currentFrameLinePose = xline(app.poseAxis, app.currentFrameTime-app.trajSummary.Data.t_press(ind_row), 'Color', 'r', 'LineWidth', 2);

            end

        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            unit_selected = app.UnitlistDropDown.Value;

            tok = regexp(unit_selected, 'ch(\d+)_unit(\d+)', 'tokens', 'once');
            ch   = str2double(tok{1});
            unit = str2double(tok{2});
            this_row = table(ch, unit, 'VariableNames',{'ch', 'unit'});

            if isempty(app.ChosenUITable.Data)
                app.ChosenUITable.Data = this_row;
            else
                existing_table = app.ChosenUITable.Data;
                if any(existing_table.ch == ch & existing_table.unit == unit)
                    return
                end
                existing_table = [existing_table; this_row];
                app.ChosenUITable.Data = existing_table;
            end
            app.ChosenUITable.ColumnWidth = {40, 40};

            app.selectedUnits = zeros(height(app.ChosenUITable.Data), 2);
            app.selectedUnitsGroup = cell(height(app.ChosenUITable.Data), 1);
            r = app.rData;
            for i =1:height(app.ChosenUITable.Data)
                app.selectedUnits(i, :) = [app.ChosenUITable.Data.ch(i) app.ChosenUITable.Data.unit(i)];
                app.selectedUnitsGroup{i} = sprintf('%d | %d', app.ChosenUITable.Data.ch(i), app.ChosenUITable.Data.unit(i));
            end
            app.saveSelectedUnits();
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)

            row_selected = app.ChosenUITable.Selection(1);
            app.ChosenUITable.Data(row_selected, :) = [];
            app.selectedUnits = zeros(1, height(app.ChosenUITable.Data));
            app.selectedUnitsGroup = cell(height(app.ChosenUITable.Data), 1);
            r = app.rData;
            for i =1:height(app.ChosenUITable.Data)
                app.selectedUnits(i) = find(r.Units.SpikeNotes(:, 1) == app.ChosenUITable.Data.ch(i) & r.Units.SpikeNotes(:, 2) == app.ChosenUITable.Data.unit(i));
                app.selectedUnitsGroup{i} = sprintf('%d | %d', app.ChosenUITable.Data.ch(i), app.ChosenUITable.Data.unit(i));
            end
            app.saveSelectedUnits();
        end

        % Value changed function: KinematicDropDown
        function KinematicDropDownValueChanged(app, event)
            value = app.KinematicDropDown.Value;
            % plot the part of kinematics we just computed
            T = app.ComputeButton.UserData.TrajTable;
            app.plotComputedKinematicsToKinematicsAxis();  % uses app.KinematicDropDown.Value by default

        end

        % Value changed function: VideoSlider
        function VideoSliderValueChanged(app, event)
            value = app.VideoSlider.Value;
            app.updateToFrame(app.VideoSlider.Value);
        end

        % Button pushed function: AddToFinalButton
        function AddToFinalButtonPushed(app, event)

            if isempty(app.ComputeButton.UserData)
                warndlg('Compute first!')
                return
            end
            T_tosave = app.ComputeButton.UserData.TrajTable;
 
            trial = T_tosave.trial{1};
            to_from = T_tosave.lever_phase{1};

            if isempty(app.goodTrajTable.Data)
                app.tableKinematics = [];
            end

            if isempty(app.tableKinematics)
                app.tableKinematics = T_tosave;
            else
                ind_same = strcmp(app.tableKinematics.trial, trial) & strcmp(app.tableKinematics.body_part, app.BodytrackedDLCDropDown.Value) & strcmp(app.tableKinematics.lever_phase, to_from);
                if any(ind_same)
                    app.tableKinematics(ind_same, :) = [];
                end
                app.tableKinematics = [app.tableKinematics; T_tosave];
            end

            % ---- sort ----
            T = app.tableKinematics;

            % make sure trial is sortable
            trialStr = string(T.trial);
            trialNum = cellfun(@(x)str2double(extractAfter(x, 'Side_Press_')), T.trial);

            if ismember('time', T.Properties.VariableNames)
                [~, ord] = sortrows(table(trialNum, T.time), {'trialNum','Var2'}); % Var2 is time
            elseif ismember('time_relative', T.Properties.VariableNames)
                [~, ord] = sortrows(table(trialStr, T.time_relative), {'trialStr','Var2'});
            else
                ord = 1:height(T);
            end

            app.tableKinematics = T(ord, :);
            showKinematicsTable(app, flipud(app.tableKinematics));

            % --- save this table ---
            tic
            disp('#########Saving kinematicsTable#########')
            app.saveKinematicsTable();
            toc
            % --- sync trials between two tables ---

            % app.syncTrajSummaryWithGood();
            % --- save this fig for further reference ---
            tic
            if app.AutofigsaveCheckBox.Value
                disp('#########Saving this figure as a snapshot#########')
                PRINTButtonPushed(app, event)
            end
            toc
            app.AddToFinalButton.BackgroundColor = '#A2CB8B';
            updateUnTrackedListBox(app, "toLever")

            if isempty(app.UnTrackedListBox.Items)
                SeeallButtonPushed(app, event)
            end

            app.updateTrackedTrialsListBox();

        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.StartTimeEditField.Value = app.currentFrameTime;
            app.startTimeChosen = app.currentFrameTime;

            if ~isempty(app.startTimeChosenLine)
                delete(app.startTimeChosenLine)
            end
            if ~isempty(app.startTimeChosenLinePose)
                delete(app.startTimeChosenLinePose)
            end

            t_start = app.currentFrameTime-app.currentTrialBehavior.t_press;
            app.startTimeChosenLinePose = xline(app.poseAxis, t_start, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');
            app.startTimeChosenLine = xline(app.kinematicsAxis, t_start, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');
        end

        % Button pushed function: endButton
        function endButtonPushed(app, event)
            app.EndTimeEditField.Value = app.currentFrameTime;
            app.endTimeChosen = app.currentFrameTime;
            if ~isempty(app.endTimeChosenLine)
                delete(app.endTimeChosenLine)
            end
            if ~isempty(app.endTimeChosenLinePose)
                delete(app.endTimeChosenLinePose)
            end
            t_end = app.currentFrameTime-app.currentTrialBehavior.t_press;
            app.endTimeChosenLinePose = xline(app.poseAxis, t_end, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');
            app.endTimeChosenLine = xline(app.kinematicsAxis, t_end, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % --- find this table ---
            tag = app.tagEditField.Value;
            anm_session = app.trajSummary.Data.anm_session{1};
            if isempty(tag)
                file_name = sprintf('RefinedTrajectorySideView_Long_%s.csv', anm_session);
            else
                file_name = sprintf('RefinedTrajectorySideView_Long_%s_%s.csv', anm_session, tag);
            end

            file_path = fullfile(pwd, 'Data', 'Processed');
            full_name = fullfile(file_path, file_name);

            if ~isfile(full_name)
                warndlg(sprintf('File does not exist: %s', full_name));
                return;
            end

            T = readtable(full_name);

            app.tableKinematics = T;
            showKinematicsTable(app, app.tableKinematics);
            app.updateTrackedTrialsListBox();
        end

        % Button pushed function: RemoveFromFinalButton
        function RemoveFromFinalButtonPushed(app, event)

            % ---- get selection from tracked listbox ----
            if ~isprop(app,'TrialstrackedListBox') || isempty(app.TrialstrackedListBox) || ~isvalid(app.TrialstrackedListBox) || ...
                    isempty(app.TrialstrackedListBox.Value)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'Select an item in Trials tracked list first.', 'No selection');
                return
            end

            sel = string(app.TrialstrackedListBox.Value); % can be char or cellstr depending on multiselect
            sel = sel(:);

            % strip the " (164)" suffix
            key = regexprep(sel, '\s+\(\d+\)$', '');  % e.g. Side_Press_1091769_fromLever

            % parse into trial + lever_phase (phase is last token; trial may contain underscores)


            parts = split(key, "_");
            lever_phase = parts(end);                 % "fromLever"
            trial = join(parts(1:end-1), "_");        % "Side_Press_1091769"
            trial = trial{1};                         % (optional) make it a string scalar

            % if size(parts,2) < 2
            %     uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'Tracked item format unexpected (needs trial_phase).', 'Error');
            %     return
            % end

            % lever_phase = parts(:, end);
            % trial = join(parts(:,1:end-1), "_");

            % ---- choose body part to delete (current dropdown) ----
            if ~isprop(app,'BodytrackedDLCDropDown') || isempty(app.BodytrackedDLCDropDown) || ~isvalid(app.BodytrackedDLCDropDown) || ...
                    isempty(app.BodytrackedDLCDropDown.Value)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'Body part dropdown is empty.', 'Error');
                return
            end
            body_part = string(app.BodytrackedDLCDropDown.Value);

            % If you want to delete ALL body parts for selected trial+phase instead, set:
            % body_part = string.empty;

            % ---- delete from goodTrajTable.Data (view) ----
            if ~isempty(app.goodTrajTable.Data) && istable(app.goodTrajTable.Data) && height(app.goodTrajTable.Data) > 0
                Tv = app.goodTrajTable.Data;
                Tv = local_remove_rows(Tv, trial, lever_phase, body_part);
                app.goodTrajTable.Data = Tv;
            end

            % ---- delete from master app.tableKinematics ----
            if ~isempty(app.tableKinematics) && istable(app.tableKinematics) && height(app.tableKinematics) > 0
                Tm = app.tableKinematics;
                Tm = local_remove_rows(Tm, trial, lever_phase, body_part);
                app.tableKinematics = Tm;
            end

            % ---- refresh UI ----
            if isprop(app,'goodTrajSelectedRows'); app.goodTrajSelectedRows = []; end

            % update the displayed table (your helper)
            showKinematicsTable(app, app.tableKinematics);

            % save + update lists
            app.saveKinematicsTable();
            app.updateTrackedTrialsListBox();

            % update body-part "to-do" list for the *current* trial/phase (optional)
            if ~isempty(trial)
                % if only one selected, update using that; otherwise just use current trial if you prefer
                try
                    app.currentTrial = char(trial(1));
                catch
                end
            end
            updateUnTrackedListBox(app, lever_phase(1));  % uses your new body-part-based function

            % ---------------- local helper ----------------
            function T = local_remove_rows(T, trialKey, phaseKey, bpKey)
                % Robust removal matching columns if present.
                if isempty(T) || ~istable(T) || height(T)==0
                    return
                end

                if ~ismember('trial', T.Properties.VariableNames)
                    return
                end

                % columns as strings
                tr = string(T.trial);

                % phase column (assuming lever_phase exists)
                ph = string(T.lever_phase);

                % body part column (assuming body_part exists)
                bp = string(T.body_part);

                % build removal mask (single selection)
                rem = (tr == trialKey) & (ph == phaseKey);

                % if you want body-part-specific delete:
                if ~isempty(bpKey) && strlength(bpKey) > 0
                    rem = rem & (bp == bpKey);
                end

                T(rem,:) = [];
            end

        end

        % Cell selection callback: goodTrajTable
        function goodTrajTableCellSelection(app, event)
            if isempty(event.Indices); return; end
            app.goodTrajSelectedRows = unique(event.Indices(:,1)); % supports multi-select
        end

        % Button pushed function: DiffButton
        function DiffButtonPushed(app, event)
                        % --- sync trials between two tables ---
            app.syncTrajSummaryWithGood();
        end

        % Button pushed function: PRINTButton
        function PRINTButtonPushed(app, event)
            fig = app.getAppFigure();

            if isempty(fig)
                warning('Could not find a matlab.ui.Figure handle in this app.');
                return;
            end

            if isempty(app.currentTrial) || strlength(string(app.currentTrial))==0
                % no fig handle needed for warndlg
                warndlg('currentTrial is empty.');
                return;
            end

            if isempty(app.trajSummary) || ~istable(app.trajSummary.Data) || ...
                    ~ismember('anm_session', app.trajSummary.Data.Properties.VariableNames) || ...
                    isempty(app.trajSummary.Data.anm_session)
                warndlg('anm_session not found in app.trajSummary.');
                return;
            end

            anm_session = string(app.trajSummary.Data.anm_session{1});
            trial = string(app.currentTrial);   % e.g. "Top_Press_318217"

            outDir = fullfile(pwd, 'Data', 'Processed', 'Traj_Figs');
            if ~isfolder(outDir)
                mkdir(outDir);
            end

            body = app.BodytrackedDLCDropDown.Value;
            baseName = anm_session + "_" + trial;
            baseName = regexprep(baseName, '[^\w\-]', '_');   % safe filename

            if isempty(app.ComputeButton.UserData)
                warndlg('Track a body part first!')
                return;
            end

            leverPhase = app.ComputeButton.UserData.Lever;
            filePath = fullfile(outDir, baseName + "_" + leverPhase + "_" + body +"_SideView.png");

            t = tic;
            drawnow limitrate;
            t_drawnow = toc(t);

            % t = tic;
            % fr = getframe(fig);
            % t_getframe = toc(t);
            % 
            % t = tic;
            % imwrite(fr.cdata, filePath);
            % t_imwrite = toc(t);
            %
            fig = app.AdvancedkinematicsanalysissideviewUIFigure;

            % t = tic;
            fr = getframe(fig);
            imwrite(fr.cdata, filePath);

            % =========================================================
            % NEW: if figure 26 exists, save it too (with Kinematics_ prefix)
            % =========================================================
            fig26 = findall(0, 'Type', 'figure', 'Number', 26);
            if ~isempty(fig26) && isvalid(fig26)
                try
                    drawnow limitrate; % let it finish rendering
                    fileName = baseName + "_" + leverPhase + "_" + "_SideView.png"
                    kinFileName = "Kinematics_" + fileName;   % same name, prefixed
                    kinPath = fullfile(outDir, kinFileName);

                    fr2 = getframe(fig26);
                    imwrite(fr2.cdata, kinPath);
                catch ME
                    warning('Failed to save figure 26: %s', ME.message);
                end
            end

        end

        % Cell edit callback: trajSummary
        function trajSummaryCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)

            Ts = app.trajSummary.Data;
            if isempty(Ts) || ~istable(Ts) || height(Ts)==0
                return
            end

            % ---- cutoff from current trial ----
            cur = string(app.currentTrial);  % e.g. "Side_Press_67030"
            cutoff = app.local_extractTrailingNumber(cur);
            if ~isfinite(cutoff)
                uialert(app.getAppFigure(), sprintf('Cannot parse time from currentTrial: %s', cur), 'Cannot filter');
                return
            end

            % ---- choose trial labels in trajSummary ----
            if ismember('Trials', Ts.Properties.VariableNames)
                sTrials = string(Ts.Trials);
            elseif ismember('trial', Ts.Properties.VariableNames)
                sTrials = string(Ts.trial);
            else
                rn = app.trajSummary.RowName;
                if iscell(rn) && numel(rn)==height(Ts)
                    sTrials = string(rn);
                else
                    uialert(app.getAppFigure(), 'trajSummary has no Trials/trial column and RowName is not usable.', 'Cannot filter');
                    return
                end
            end

            sKey = app.local_extractTrailingNumber(sTrials);
            if all(isnan(sKey))
                uialert(app.getAppFigure(), 'Cannot parse trial times from trajSummary.', 'Cannot filter');
                return
            end

            % ---- keep only trials that are NOT earlier than current trial ----
            keep = sKey > cutoff;          % keep current trial and later
            Ts_removed = Ts(~keep, :);

            clc;
            disp('######### removed trials (earlier than current trial) #########')
            disp(Ts_removed)
            disp('######### ######### ######### #########')

            % preserve RowName if it is custom (cell array)
            rn = app.trajSummary.RowName;
            if iscell(rn) && numel(rn)==height(Ts)
                app.trajSummary.RowName = rn(keep);
            end

            app.trajSummary.Data = Ts(keep, :);

            if ismethod(app,'updateProgressCounts')
                app.updateProgressCounts();
            end

            app.FilterButton.BackgroundColor = '#A2CB8B';

            % ---- select the first row after filtering ----
            TsNew = app.trajSummary.Data;
            if istable(TsNew) && height(TsNew) >= 1
                row = 1;

                % try to visually select it
                try
                    app.trajSummary.Selection = [row 1];   % first row, first column
                catch
                end

                % also update app state + load the trial (reuses your existing logic)
                try
                    updateText(app, row);
                    CheckatrialButtonPushed(app, []);
                catch
                    % fallback: at least set currentTrial from Trials column if present
                    if ismember('Trials', TsNew.Properties.VariableNames)
                        app.currentTrial = string(TsNew.Trials{row});
                    elseif iscell(app.trajSummary.RowName) && numel(app.trajSummary.RowName) >= row
                        app.currentTrial = string(app.trajSummary.RowName{row});
                    end
                end
            end

        end

        % Button pushed function: PSTHButton
        function PSTHButtonPushed(app, event)

            % --- make sure PSTH folder exists ---
            if isempty(app.PSTH_folder) || ~isfolder(app.PSTH_folder)
                newDir = uigetdir(pwd, 'Select PSTH folder');
                if isequal(newDir,0), return; end
                app.PSTH_folder = newDir;
            end

            % --- get selected row (ch/unit) from ChosenUITable ---
            sel = [];
            try
                sel = app.ChosenUITable.Selection;
            catch
                sel = [];
            end

            if isempty(sel)
                warndlg('Select a unit row in ChosenUITable first.');
                return;
            end

            % If Selection is numeric indices (common): [row col]
            if isnumeric(sel)
                rowIdx = sel(1,1);
                D = app.ChosenUITable.Data;
                if istable(D)
                    row = D(rowIdx,:);
                    ch = row.ch(1);
                    unit = row.unit(1);
                else
                    % cell-array Data case
                    ch = D{rowIdx, 1};
                    unit = D{rowIdx, 2};
                end

                % If Selection returns the selected row as a table (your current code assumes this)
            elseif istable(sel)
                ch = sel.ch(1);
                unit = sel.unit(1);

            else
                warndlg('ChosenUITable.Selection format not recognized.');
                return;
            end

            % --- look for PSTHs ---
            anm_session = string(app.currentTrialBehavior.anm_session{1}); % e.g. Nasha_20250405
            pattern = sprintf('%s_Ch%d_Unit%d*.png', anm_session, ch, unit);
            psth_figs = dir(fullfile(app.PSTH_folder, pattern));

            % If none, allow user to redefine folder once
            if isempty(psth_figs)
                newDir = uigetdir(app.PSTH_folder, ...
                    sprintf('No PSTH found. Pick PSTH folder for %s', anm_session));
                if ~isequal(newDir,0)
                    app.PSTH_folder = newDir;
                    psth_figs = dir(fullfile(app.PSTH_folder, pattern));
                end
            end

            if isempty(psth_figs)
                warndlg(sprintf(['No PSTH PNG found for %s Ch%d Unit%d.\n' ...
                    'Pattern: %s\nFolder: %s'], ...
                    anm_session, ch, unit, pattern, app.PSTH_folder));
                return;
            end

            % Sort by name (or you can sort by datenum)
            [~, ix] = sort({psth_figs.name});
            psth_figs = psth_figs(ix);

            % If multiple, let user pick which to open
            names = {psth_figs.name};
            if numel(names) > 1
                [pick, ok] = listdlg('PromptString','Select PSTH image(s) to open:', ...
                    'ListString', names, 'SelectionMode','multiple', 'ListSize',[520 260]);
                if ~ok || isempty(pick); return; end
            else
                pick = 1;
            end

            % Open
            fig_num = 10;
            for ii = pick
                fpath = fullfile(psth_figs(ii).folder, psth_figs(ii).name);

                try
                    I = imread(fpath);
                    figure(ii+fig_num, 'Name', psth_figs(ii).name, 'Color','w');
                    imshow(I);
                    axis image off;
                    title(psth_figs(ii).name, 'Interpreter','none');
                catch ME
                    % Fallback: open in system viewer
                    try
                        if ispc
                            winopen(fpath);
                        elseif ismac
                            system(sprintf('open "%s"', fpath));
                        else
                            system(sprintf('xdg-open "%s"', fpath));
                        end
                    catch
                        warning('Failed to open %s (%s)', fpath, ME.message);
                    end
                end
            end
        end

        % Button pushed function: RankunitsButton
        function RankunitsButtonPushed(app, event)

            app.RankunitsButton.BackgroundColor = 'b';
            % rank units according to their activity patterns
            units_selected = app.selectedUnits;
            r = app.rData;
            [units_selected, out] = Spikes.SRT.rank_units_selected(app.rData, units_selected);
            app.RankunitsButton.BackgroundColor = 'g';
            app.RankunitsButton.FontColor = 'w';
            app.ChosenUITable.Data = [];
            app.selectedUnits = units_selected;
            app.selectedUnitsGroup = [];

            for i =1:size(units_selected, 1)
                ch   = units_selected(i, 1);
                unit = units_selected(i, 2);
                this_row = table(ch, unit, 'VariableNames',{'ch', 'unit'});
                app.selectedUnitsGroup{i} = sprintf('%d | %d', ch, unit);

                if isempty(app.ChosenUITable.Data)
                    app.ChosenUITable.Data = this_row;
                else
                    existing_table = app.ChosenUITable.Data;
                    if any(existing_table.ch == ch & existing_table.unit == unit)
                        return
                    end
                    existing_table = [existing_table; this_row];
                    app.ChosenUITable.Data = existing_table;
                end
            end

            fprintf('SDF computation completed: %s\n', datetime)
            app.ChosenUITable.ColumnWidth = {40, 40};
            app.saveSelectedUnits();

            % --- save sdf ---
            anm_session = string(app.trajSummary.Data.anm_session{1});

            outDir = fullfile(pwd, 'Data', 'Processed');
            if ~isfolder(outDir)
                mkdir(outDir);
            end

            app.selectedUnitsSDFs = out;
            % ---- write ----
            fileName = sprintf('SDFs_of_UnitsSelected_%s.mat', anm_session);
            fullPath = fullfile(outDir, fileName);
            save(fullPath, "out", '-v7.3');
            disp("Saved: " + string(fullPath));
            % ---- meta (optional) ----

            write_meta( ...
                fileName, ...
                'MetaFolder', outDir, ...
                'Description', 'SDFs of selected units when examining kinematics', ...
                'Purpose', 'for kinematics analysis (top view)', ...
                'GeneratorFunction', '+opto_paper.track_kinematics.mlapp', ...
                'GeneratorScript', 'None', ...
                'Inputs', {'TrajectoryTopView_ANM_Session.csv', 'VideoClip', 'r'});

        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            visualizePopActivity(app)
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.FilterButton.BackgroundColor = app.AdvancedkinematicsanalysissideviewUIFigure.Color;

            % --- set the additional add check box to 0 ---
            app.AddCheckBox.Value = 0;

            % Recompute this part of trajectory
            app.AddToFinalButton.BackgroundColor = app.AdvancedkinematicsanalysissideviewUIFigure.Color;

            dlc_folder = app.folderPath;
            trial_name = app.currentTrial;
            dlc_file_name = sprintf('%sDLC*.csv', trial_name);
            dlc_file_location = dir(fullfile(dlc_folder, dlc_file_name));
            if isempty(dlc_file_location)
                warndlg(sprintf('There is no DLC file associated with %s in %s', dlc_file_name, dlc_folder));
                return;
            end
            dlc_file_path = fullfile(dlc_file_location.folder, dlc_file_location.name);
            app.dlcResults = read_dlc_table(dlc_file_path);

            t_start = app.StartTimeEditField.Value;
            t_end   = app.EndTimeEditField.Value;
            dur_min = 100; % there has to be 0.1 second of data

            if ~(t_start~=0 && t_end~=0 && t_end-t_start>dur_min)
                warndlg('Too short!');
                return;
            end

            % --- this is the video info (meta data)---
            videoMetaFileName = [trial_name '.mat'];
            app.VideoMeta = load(fullfile(app.folderPath, videoMetaFileName));

            % Find out the index
            IndexTimeSelected = find(app.VideoMeta.VideoInfo.EphysTimeStamps>=t_start & ...
                app.VideoMeta.VideoInfo.EphysTimeStamps<=t_end);

            TrajExtracted = app.dlcResults;
            TrajExtracted.Name = trial_name;
            TrajExtracted.IndexSelected = IndexTimeSelected;
            TrajExtracted.TimeSelected = app.VideoMeta.VideoInfo.EphysTimeStamps(IndexTimeSelected);

            % determine if this segment is before or after press
            if TrajExtracted.TimeSelected(1) < app.currentTrialBehavior.t_press
                tag = 'toLever';
            else
                tag = 'fromLever';
            end
            TrajExtracted.Tag = tag;

            % If no folder path is saved, ask user to select one
            if isempty(app.TrajSavedPath)
                selectedFolder = fullfile(pwd, 'Data', 'Processed', 'TrajExtractedSide');
                if ~exist(selectedFolder, 'dir')
                    mkdir(selectedFolder);
                    disp(['Created folder: ', selectedFolder]);
                end
                app.TrajSavedPath = selectedFolder; % Save the new folder path
            end
            savedFolderPath = app.TrajSavedPath;
            metaDir = savedFolderPath;

            % Save TrajExtracted (same for all parts)
            new_traj_name = sprintf('SideTrajExtracted_%s_%s.mat', trial_name, tag);
            save(fullfile(savedFolderPath, new_traj_name), 'TrajExtracted');
            write_meta(...
                new_traj_name, ...
                'MetaFolder', metaDir,...
                'Description', 'Trajectory extracted of side-view movement (single, continuous)', ...
                'Purpose', 'for analyzing spike-kinematics relationship', ...
                'GeneratorFunction', 'opto_paper.track_side_kinematics.mlapp', ...
                'GeneratorScript', 'NaN', ...
                'Inputs', {'NaN'});

            disp(['Data will be saved to: ', fullfile(savedFolderPath, new_traj_name)]);

            % ==========================================================
            % Decide body parts to compute
            % ==========================================================
            doAll = logical(app.ApplyallCheckBox.Value);

            if doAll
                parts = string(app.UnTrackedListBox.Items(:));
                parts = parts(parts ~= "" & ~ismissing(parts));
                if isempty(parts)
                    parts = string(app.BodytrackedDLCDropDown.Value);
                end
            else
                parts = string(app.BodytrackedDLCDropDown.Value);
            end

            % ==========================================================
            % Loop over parts (1 part if not apply-all)
            % ==========================================================
            for ip = 1:numel(parts)
                body = char(parts(ip));

                % keep UI consistent
                try
                    if any(strcmp(app.BodytrackedDLCDropDown.Items, body))
                        app.BodytrackedDLCDropDown.Value = body;
                        addTrajValueChanged(app, event);
                    end
                catch
                end

                traj_table = Spikes.Videos.compute_pawtraj_usingDLC(TrajExtracted, ...
                    'BodyParts', {body}, ...
                    'Thresh', 0.8, ...
                    'KeepLongestRun', true, ...
                    'MinKeepFrames', 5, ...
                    'SmoothWin', 5, ...
                    'TimeUnit', 'ms');

                % meta columns
                traj_table.lever_phase = repmat({tag}, height(traj_table), 1);
                traj_table.trial       = repmat({app.currentTrial}, height(traj_table), 1);
                sess = string(app.currentTrialBehavior.anm_session{1});
                traj_table.anm_session = repmat({sess}, height(traj_table), 1);

                % Save this table (optional: make per-body file to avoid overwriting)
                new_traj_name = sprintf('SideKinematicsExtracted_%s_%s_%s.csv', trial_name, tag, body);
                new_traj_name = regexprep(new_traj_name, '[^\w\.\-]', '_'); % safe
                writetable(traj_table, fullfile(savedFolderPath, new_traj_name));

                write_meta(...
                    new_traj_name, ...
                    'MetaFolder', metaDir,...
                    'Description', 'Kinematics of side-view movement (single, continuous)', ...
                    'Purpose', 'for analyzing spike-kinematics relationship', ...
                    'GeneratorFunction', 'opto_paper.track_side_kinematics.mlapp', ...
                    'GeneratorScript', 'NaN', ...
                    'Inputs', {'NaN'});

                % Update UserData (so plotting / manual-fix uses latest)
                app.ComputeButton.UserData = struct('TrajTable', traj_table, 'Lever', tag);

                % Update dropdown + plot
                app.KinematicDropDown.Items = {'x_s', 'y_s', 'speed', 'v_forward', 'v_up'};
                app.KinematicDropDown.Value = 'y_s';
                app.plotComputedKinematicsToKinematicsAxis();

                % Auto-add after each part if apply-all
                if doAll
                    app.AddCheckBox.Value = true;
                    AddCheckBoxValueChanged(app, event);
                    app.AddCheckBox.Value = false; % keep UI tidy
                end
            end

            app.SelectrangeButton.BackgroundColor = app.AdvancedkinematicsanalysissideviewUIFigure.Color;
            % re-focus on the UIFigure
            figure(app.AdvancedkinematicsanalysissideviewUIFigure);
            drawnow;
            if isempty(app.UnTrackedListBox.Items)
                SeeallButtonPushed(app, event)
            end
            app.ApplyallCheckBox.Value = false;
        end

        % Value changed function: BodytrackedDLCDropDown
        function BodytrackedDLCDropDownValueChanged(app, event)
            value = app.BodytrackedDLCDropDown.Value;
            try
                CheckatrialButtonPushed(app, event)
            end
        end

        % Selection changed function: trajSummary
        function trajSummarySelectionChanged(app, event)
            selection = app.trajSummary.Selection;
            
        end

        % Clicked callback: KinematicDropDown
        function KinematicDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: TrajListDropDown
        function TrajListDropDownValueChanged(app, event)
            value = app.TrajListDropDown.Value;

            % let's plot this trial
            %value = 'Top_Press_229539_fromLever';
            parts = split(string(value), "_");
            trial = strjoin(parts(1:3), "_");   % "Top_Press_229539"
            phase = parts(4);                   % "fromLever"

            % select corresponding row in app.trajSummary
            Ts = app.trajSummary.Data;

            % Trials column is cell -> string
            trials = string(Ts.Trials);

            % find matching row(s)
            idx = find(trials == trial);

            % idx is the row index you want
            % e.g., select the first match:
            if isempty(idx)
                warning('Trial not found in trajSummary: %s', trial);
            else
                row = idx(1);
                Ts_row = Ts(row, :);   % the corresponding row as a table
                app.trajSummary.Selection = [row 1];  % select row, first column
                updateText(app, row)
                CheckatrialButtonPushed(app, event);
            end

            rows_good = strcmp(app.goodTrajTable.Data.trial, trial) & strcmp(app.goodTrajTable.Data.lever_phase, phase) & strcmp(app.goodTrajTable.Data.body_part, app.BodytrackedDLCDropDown.Value);

            traj_table = app.goodTrajTable.Data(rows_good, :);

            app.ComputeButton.UserData  = struct('TrajTable', [], 'Lever', []);
            app.ComputeButton.UserData.TrajTable = traj_table;
            app.ComputeButton.UserData.Lever = phase;
            app.ComputeButton.UserData
            app.KinematicDropDown.Items = {'x_s', 'y_s', 'speed', 'v_forward', 'v_up'};
            app.KinematicDropDown.Value = 'y_s';
            app.plotComputedKinematicsToKinematicsAxis();  % uses app.KinematicDropDown.Value by default

        end

        % Value changed function: VideocliplistDropDown
        function VideocliplistDropDownValueChanged(app, event)
            value = app.VideocliplistDropDown.Value;

            trial = extractBefore(value, '.mp4');
            % Trials column is cell -> string
            Ts = app.trajSummary.Data;
            trials = string(Ts.Trials);

            % find matching row(s)
            idx = find(trials == trial);

            % idx is the row index you want
            % e.g., select the first match:
            if isempty(idx)
                warning('Trial not found in trajSummary: %s', trial);
            else
                row = idx(1);
             end

            app.trajSummary.Selection = [row 1];  % select row, first column
            updateText(app, row)
           
            
        end

        % Button pushed function: SelectrangeButton
        function SelectrangeButtonPushed(app, event)
            % select a range
            % Let user draw a rectangle on app.poseAxis and set start/end times.
            %
            % Assumptions:
            % - app.poseAxis X axis is time (ms) OR you can convert from seconds to ms.
            % - app has fields/properties to store selection:
            %     app.startTimeMs, app.endTimeMs  (you can rename)
            % - app also has UI components you currently use for start/end:
            %     e.g., app.StartTimeEditField, app.EndTimeEditField (optional)

            ax = app.poseAxis;

            % ---- cues on ----
            oldBtnColor = app.AdvancedkinematicsanalysissideviewUIFigure.Color;
            app.SelectrangeButton.BackgroundColor = '#A2CB8B';
            ax.Color = '#E8F5BD';  % light green

            ax.Title.String = 'Drag a rectangle over the time range';
            drawnow;

            % ---- remove old ROI ----
            if ~isempty(app.poseRangeROI) && isvalid(app.poseRangeROI)
                delete(app.poseRangeROI);
            end

            % ---- draw ROI ----
            app.poseRangeROI = drawrectangle(ax, ...
                'Color', [0.2 0.6 1.0], ...
                'FaceAlpha', 0.08, ...
                'LineWidth', 1.5);

            % If user cancels / nothing created
            if isempty(app.poseRangeROI) || ~isvalid(app.poseRangeROI)
                % cues off
                % ax.Color = 'w';
                % ax.Title.String = app.oldPoseTitle;
                % app.SelectrangeButton.BackgroundColor = oldBtnColor;
                return
            end

            % ---- read x-range ----
            pos = app.poseRangeROI.Position;   % [x y w h] in data units
            xMin = pos(1);
            xMax = pos(1) + pos(3);

            % sort + clamp
            xMin = min(xMin, xMax);
            xMax = max(xMin, xMax);
            xl = ax.XLim;
            xMin = max(xMin, xl(1));
            xMax = min(xMax, xl(2));

            if ~isfinite(xMin) || ~isfinite(xMax) || xMax <= xMin
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'Invalid rectangle (zero width). Try again.', 'Select range');
                % cues off
                ax.Color = 'w';
                app.SelectrangeButton.BackgroundColor = oldBtnColor;
                return
            end
            % For now, assume axis is ms directly:
            tStartMs = xMin;
            tEndMs   = xMax;

            % These are now only relative values, let's compute time in
            % ephys space
            tStartMs = tStartMs + app.currentTrialBehavior.t_press;
            tEndMs = tEndMs + app.currentTrialBehavior.t_press;

            % Ensure start < end and has some width
            if ~isfinite(tStartMs) || ~isfinite(tEndMs) || (tEndMs <= tStartMs)
                uialert(app.AdvancedkinematicsanalysissideviewUIFigure, 'Invalid selection. Please drag a rectangle with non-zero width.', 'Select range');
                return
            end

            % Store them (add these app properties, or replace with your own)
            app.startTimeMs = tStartMs;
            app.endTimeMs   = tEndMs;

            % Update UI fields if they exist
            if isprop(app, 'StartTimeEditField') && isvalid(app.StartTimeEditField)
                app.StartTimeEditField.Value = double(tStartMs);
            end
            if isprop(app, 'EndTimeEditField') && isvalid(app.EndTimeEditField)
                app.EndTimeEditField.Value = double(tEndMs);
            end
            app.poseAxis.Color = 'w';   % light red / pink
            StartTimeEditFieldValueChanged(app, event)
            ComputeButtonPushed(app, event)
        end

        % Value changed function: AddCheckBox
        function AddCheckBoxValueChanged(app, event)
            value = app.AddCheckBox.Value;
            if value
                AddToFinalButtonPushed(app, event)
            end
            app.AddCheckBox.Value = false;
            
        end

        % Callback function
        function RetrievalButtonPushed(app, event)

            % add behavior data
            ind_row = strcmp(app.trajSummary.Data.Trials, app.currentTrial);
            fp = app.trajSummary.Data.FP(ind_row);
            t_trigger = app.trajSummary.Data.t_trigger(ind_row);
            t_poke = app.trajSummary.Data.t_poke(ind_row);

            if isnan(t_poke)
                warndlg('Hey, there is no poke event! Wake up')
            end

            app.StartTimeEditField.Value = t_trigger;
            app.EndTimeEditField.Value = t_poke;
            StartTimeEditFieldValueChanged(app, event);

            ComputeButtonPushed(app, event)

        end

        % Button pushed function: LiftButton
        function LiftButtonPushed(app, event)

            % automatically searching for the final not-nan point before
            % the press time

            % add behavior data
            ind_row = strcmp(app.trajSummary.Data.Trials, app.currentTrial);
            fp = app.trajSummary.Data.FP(ind_row);
            t_press = app.trajSummary.Data.t_press(ind_row);
            t_trigger = app.trajSummary.Data.t_trigger(ind_row);
            t_poke = app.trajSummary.Data.t_poke(ind_row);
            data = app.dlcResults.(string(app.BodytrackedDLCDropDown.Value));
            t_frames = app.VideoMeta.VideoInfo.EphysTimeStamps - app.VideoMeta.VideoInfo.Time;

            thr = 0.8;
            K   = 3;

            m = data.likelihood(:) > thr;                 % logical mask
            idx0 = find(conv(double(m), ones(K,1), 'valid') == K, 1, 'first');

            if isempty(idx0)
                idx_start = [];                           % no such run
            else
                idx_start = idx0;                         % starting index of first run
            end

            app.StartTimeEditField.Value = app.VideoMeta.VideoInfo.EphysTimeStamps(idx_start);
            app.EndTimeEditField.Value = t_press;
            StartTimeEditFieldValueChanged(app, event);
            ComputeButtonPushed(app, event)
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            if ~isempty(app.startTimeChosenLine)
                delete(app.startTimeChosenLine)
            end
            if ~isempty(app.startTimeChosenLinePose)
                delete(app.startTimeChosenLinePose)
            end

            t_start = value-app.currentTrialBehavior.t_press;
            % add a red line marking current frame
            app.currentFrameTime = value;
            app.currentFrameLine.Value = t_start;
            app.currentFrameLinePose.Value = t_start;
            app.currentFrameLineSpikes.Value = t_start;

            % update the video slide bar
            ind = find(app.VideoMeta.VideoInfo.EphysTimeStamps>= value, 1, 'first');
            app.VideoSlider.Value = ind;
            app.updateToFrame(app.VideoSlider.Value);

        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            value = app.EndTimeEditField.Value;
            if ~isempty(app.endTimeChosenLine)
                delete(app.endTimeChosenLine)
            end
            t_end= value-app.currentTrialBehavior.t_press;
            app.startTimeChosenLinePose = xline(app.poseAxis, t_start, 'Color','b', 'LineWidth',1.5, 'LineStyle','-.');

        end

        % Cell edit callback: ChosenUITable
        function ChosenUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: ChosenUITable
        function ChosenUITableCellSelection(app, event)
            indices = event.Indices;

            r = app.rData;
            tickL = 0.75;
            xAll = [];
            yAll = [];
            t_press = app.currentTrialBehavior.t_press;
            for i = indices(1)
                ind = find(r.Units.SpikeNotes(:, 1)==app.selectedUnits(i, 1) & r.Units.SpikeNotes(:, 2)==app.selectedUnits(i, 2));
                spk_times = r.Units.SpikeTimes(ind).timings;
                ind = find(spk_times>t_press+app.kinematicsAxis.XLim(1) & spk_times<t_press+app.kinematicsAxis.XLim(2));
                if ~isempty(ind)
                    spk_times_rel = spk_times(ind) - t_press;
                    xx = [spk_times_rel(:)'; spk_times_rel(:)'];      % 2 x N
                    yy = [(i-tickL)*ones(1,numel(spk_times_rel));
                        (i+tickL)*ones(1,numel(spk_times_rel))];
                    % add NaN separator column so different spikes don't connect
                    xAll = [xAll, xx, nan(2,1)];
                    yAll = [yAll, yy, nan(2,1)];

                end
            end

            if isempty(yAll)
                warndlg('No spikes!!!')
                return
            end
            if isempty(app.showUnitLine)
                app.showUnitLine = line(app.spikesAxis, xAll, yAll, ...
                    'Color', 'r', 'LineWidth', 2);
            else
                delete(app.showUnitLine)
                app.showUnitLine = line(app.spikesAxis, xAll, yAll, ...
                    'Color', 'r', 'LineWidth', 2);
            end
        end

        % Value changed function: AdjustBrightnessKnob
        function AdjustBrightnessKnobValueChanged(app, event)
            app.refreshFrameBrightness();
        end

        % Value changing function: AdjustBrightnessKnob
        function AdjustBrightnessKnobValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            app.VideoSlider.Value = app.VideoSlider.Value - 1;
            VideoSliderValueChanged(app, event)
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.VideoSlider.Value = app.VideoSlider.Value + 1;
            VideoSliderValueChanged(app, event)
        end

        % Value changed function: PreEditField
        function PreEditFieldValueChanged(app, event)
            value = app.PreEditField.Value;
            
        end

        % Value changed function: PostEditField
        function PostEditFieldValueChanged(app, event)
            value = app.PostEditField.Value;
            
        end

        % Value changed function: UnTrackedListBox
        function UnTrackedListBoxValueChanged(app, event)
            value = app.UnTrackedListBox.Value;
            app.BodytrackedDLCDropDown.Value = value;
            try
                CheckatrialButtonPushed(app, event)
            end
        end

        % Clicked callback: UnTrackedListBox
        function UnTrackedListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            value = app.UnTrackedListBox.Value;
            app.BodytrackedDLCDropDown.Value = value;
            try
                CheckatrialButtonPushed(app, event)
            end
        end

        % Button pushed function: SeeallButton
        function SeeallButtonPushed(app, event)
            % plot all tracked activity

            this_trial = app.currentTrial;

            % --- pull this trial data ---
            Tg = app.goodTrajTable.Data;
            ind_this_trial = strcmp(string(Tg.trial), string(this_trial));
            data = Tg(ind_this_trial, :);

            t_press   = app.currentTrialBehavior.t_press;
            t_release = app.currentTrialBehavior.t_release - t_press;
            fp        = app.currentTrialBehavior.FP;
            t_poke    = app.currentTrialBehavior.t_poke - t_press;   % needed for spikes

            % =========================
            % USER SELECTIONS
            % =========================
            parts_to_plot   = ["RightPaw","LeftPaw","LeftEar","LeverEnd"];
            plot_leverend   = true;

            pos_signals     = ["y","x"];
            vel_signals     = ["v_up","v_forward"];
            % =========================

            % --- decide final body parts list ---
            all_parts = string(app.BodytrackedDLCDropDown.Items(:));
            if ~plot_leverend
                all_parts = all_parts(all_parts ~= "LeverEnd");
            end
            if ~isempty(parts_to_plot)
                body_parts = intersect(all_parts, string(parts_to_plot), 'stable');
            else
                body_parts = all_parts;
            end

            n_body_parts = numel(body_parts);
            if n_body_parts == 0
                warning('No body parts selected to plot.');
                return
            end

            figure(26); clf(26)

            % ---- time window for xlim ----
            x_min = min(data.time) - t_press;
            x_max = max(data.time) - t_press;

            % (optional) if you prefer UI pre/post range instead:
            % x_min = app.PreEditField.Value;
            % x_max = app.PostEditField.Value;

            % ---- median window ----
            win = 7;
            if mod(win,2)==0, win = win+1; end

            % ---- total rows (extra row for spikes) ----
            n_rows = n_body_parts + 1;

            % =========================
            % BODY PART SUBPLOTS
            % =========================
            marker_size = 8;

            for i = 1:n_body_parts
                bp = body_parts(i);
                ind = (string(data.body_part) == bp);
                if ~any(ind), continue; end

                t = data.time(ind) - t_press;

                % ---- subplot 1: position (left col) ----
                ax1 = subplot(n_rows, 2, 1+(i-1)*2); %#ok<LAXES>
                cla(ax1);
                hold(ax1, 'on')

                h = gobjects(0); labels = {};

                if any(pos_signals == "y")
                    y = data.y_s(ind);
                    h(end+1) = scatter(ax1, t, y, marker_size, 'r', 'filled', 'MarkerEdgeColor','w'); %#ok<AGROW>
                    h(end+1) = plot(ax1, t, movmedian(y, win, 'omitnan'), '-', 'LineWidth', 1.5); %#ok<AGROW>
                    labels = [labels, {'y (raw)','y (med)'}]; %#ok<AGROW>
                end

                if any(pos_signals == "x") && bp ~= "LeverEnd"
                    x = data.x_s(ind);
                    h(end+1) = scatter(ax1, t, x, marker_size, 'k', 'filled', 'MarkerEdgeColor','w'); %#ok<AGROW>
                    h(end+1) = plot(ax1, t, movmedian(x, win, 'omitnan'), '-', 'LineWidth', 1.5); %#ok<AGROW>
                    labels = [labels, {'x (raw)','x (med)'}]; %#ok<AGROW>
                end

                title(ax1, bp, 'Interpreter','none')
                xlim(ax1, [x_min x_max])
                yl = ylim(ax1);
                plotshaded([0 fp], [yl(1) yl(1); yl(2) yl(2)], '#FFB33F', 0.25);
                xline(ax1, t_release, 'Color','b', 'LineWidth',1)

                if ~isempty(h)
                    lgd = legend(ax1, h, labels, 'Interpreter','none', 'Location','best');
                    lgd.Box = 'off';          % no border
                    lgd.Color = 'none';       % transparent background
                end

                if i == n_body_parts
                    xlabel(ax1, 'time (ms)')
                end

                % ---- subplot 2: velocity (right col) ----
                ax2 = subplot(n_rows, 2, i*2); %#ok<LAXES>
                cla(ax2);
                hold(ax2, 'on')

                yline(ax2, 0, 'color', [.2 .2 .2], 'LineStyle','-.', 'LineWidth',1)
                h = gobjects(0); labels = {};

                if any(vel_signals == "v_up")
                    v_up = data.v_up(ind);
                    h(end+1) = scatter(ax2, t, v_up, marker_size, 'r', 'filled', 'MarkerEdgeColor','w'); %#ok<AGROW>
                    h(end+1) = plot(ax2, t, movmedian(v_up, win, 'omitnan'), '-', 'LineWidth', 1.5); %#ok<AGROW>
                    labels = [labels, {'v\_up (raw)','v\_up (med)'}]; %#ok<AGROW>
                end

                if any(vel_signals == "v_forward") && bp ~= "LeverEnd"
                    v_forward = data.v_forward(ind);
                    h(end+1) = scatter(ax2, t, v_forward, marker_size, 'k', 'filled', 'MarkerEdgeColor','w'); %#ok<AGROW>
                    h(end+1) = plot(ax2, t, movmedian(v_forward, win, 'omitnan'), '-', 'LineWidth', 1.5); %#ok<AGROW>
                    labels = [labels, {'v\_forward (raw)','v\_forward (med)'}]; %#ok<AGROW>
                end

                title(ax2, bp, 'Interpreter','none')
                xlim(ax2, [x_min x_max])
                yl = ylim(ax2);
                plotshaded([0 fp], [yl(1) yl(1); yl(2) yl(2)], '#FFB33F', 0.25);
                xline(ax2, t_release, 'Color','b', 'LineWidth',1)

                if ~isempty(h)
                    lgd = legend(ax2, h, labels, 'Interpreter','none', 'Location','best');
                    lgd.Box = 'off';
                    lgd.Color = 'none';
                end

                if i == n_body_parts
                    xlabel(ax2, 'time (ms)')
                end
            end

            % =========================
            % SPIKES ROW (two panels at bottom)
            % =========================
            nU = size(app.selectedUnits, 1);
            xAll = [];
            yAll = [];

            if ~isempty(app.selectedUnits)
                r = app.rData;
                tickL = 0.5;

                for uu = 1:nU
                    indU = find(r.Units.SpikeNotes(:,1)==app.selectedUnits(uu,1) & ...
                        r.Units.SpikeNotes(:,2)==app.selectedUnits(uu,2), 1, 'first');
                    if isempty(indU), continue; end

                    spk_times = r.Units.SpikeTimes(indU).timings;
                    keep = (spk_times > t_press + x_min) & (spk_times < t_press + x_max);

                    if any(keep)
                        spk_rel = spk_times(keep) - t_press;

                        xx = [spk_rel(:)'; spk_rel(:)'];
                        yy = [(uu-tickL)*ones(1,numel(spk_rel));
                            (uu+tickL)*ones(1,numel(spk_rel))];

                        xAll = [xAll, xx, nan(2,1)];
                        yAll = [yAll, yy, nan(2,1)];
                    end
                end
            end

            % two axes in the last row, col 1 and col 2
            for kk = 1:2
                axS = subplot(n_rows, 2, 2*n_rows-1 + (kk-1)); %#ok<LAXES>
                cla(axS); hold(axS, 'on');
                xlim(axS, [x_min x_max]);

                axS.YDir = 'reverse';
                axS.YGrid = 'on';
                axS.FontSize = 9;
                axS.YTickLabel = [];
                xlabel(axS, 'time (ms)');
                title(axS, 'Spikes', 'Interpreter','none');

                % FP shading
                plotshaded([0 fp], [0 0; 1+max(nU,1) 1+max(nU,1)], '#FFB33F', 0.25, axS);

                % raster (if any)
                if ~isempty(xAll)
                    line(axS, xAll, yAll, 'Color','k', 'LineWidth', 1);
                    axS.YLim = [0, nU+1];
                    axS.YTick = 1:nU;
                else
                    axS.YLim = [0 2];
                end

                % behavior markers
                xline(axS, t_release, 'Color', '#134E8E', 'LineWidth', 2, 'LineStyle', ':');
                if isfinite(t_poke)
                    xline(axS, t_poke, 'Color', '#237227', 'LineWidth', 2, 'LineStyle', ':');
                end
            end

            % =========================
            % Column labels (same as before)
            % =========================
            fig = gcf;
            delete(findall(fig, 'Tag', 'ColHeaderText'));

            annotation(fig, 'textbox', [0.05 0.95 0.55 0.03], ...
                'String', sprintf('Position signal_%s', app.currentTrial), ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle', ...
                'Interpreter','none', ...
                'LineStyle', 'none', ...
                'FontWeight', 'bold', ...
                'FontSize', 10, ...
                'Tag', 'ColHeaderText');

            annotation(fig, 'textbox', [0.60 0.95 0.25 0.03], ...
                'String', 'Action signal', ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'middle', ...
                'LineStyle', 'none', ...
                'FontWeight', 'bold', ...
                'FontSize', 10, ...
                'Tag', 'ColHeaderText');
        end

        % Button pushed function: ManualfixButton
        function ManualfixButtonPushed(app, event)
            fig = app.getAppFigure();
            if isempty(fig) || ~isvalid(fig) || isempty(app.VideoClipAxis) || ~isvalid(app.VideoClipAxis)
                return
            end

            % --- UI cue ---
            oldTitle = app.VideoClipAxis.Title.String;
            oldColor = app.VideoClipAxis.Color;
            app.VideoClipAxis.Title.String = 'Manual fix: click a point on the video (ESC to cancel)';
            app.VideoClipAxis.Color = [1 0.97 0.9];
            drawnow;

            % --- stash old callbacks so we restore them ---
            oldMouseFcn = fig.WindowButtonDownFcn;
            oldKeyFcn   = fig.WindowKeyPressFcn;

            % --- state (nested scope) ---
            clicked = false;
            canceled = false;
            pt = [NaN NaN];

            % --- arm one-shot handlers ---
            fig.WindowButtonDownFcn = @onClickOnce;
            fig.WindowKeyPressFcn   = @onKeyPress;

            % --- wait until click or cancel ---
            uiwait(fig);

            % --- restore callbacks + UI cue ---
            fig.WindowButtonDownFcn = oldMouseFcn;
            fig.WindowKeyPressFcn   = oldKeyFcn;
            app.VideoClipAxis.Title.String = oldTitle;
            app.VideoClipAxis.Color = oldColor;

            % --- if canceled / no click, stop here ---
            if canceled || ~clicked || any(~isfinite(pt))
                return
            end

            % ==========================================================
            % CONTINUE HERE: you now have the clicked point in pt = [x y]
            % ==========================================================
            x = pt(1);
            y = pt(2);

            % show marker (optional)
            hold(app.VideoClipAxis,'on');
            scatter(app.VideoClipAxis, x, y, 150, 'cyan', '.', 'LineWidth', 1);
            % Next step: apply correction to tracking (we’ll do this next)
            % e.g. overwrite DLC x/y at current frame for selected body part

            index = app.VideoSlider.Value;
            current_time = app.VideoMeta.VideoInfo.EphysTimeStamps(index);
            body = app.BodytrackedDLCDropDown.Value;
            this_row = table(current_time, {body}, x, y, 'VariableNames',{'time', 'body_part', 'x_corrected', 'y_corrected'});

            T = app.ComputeButton.UserData.TrajTable;  % long table
            % this_row: time, body_part, x_corrected, y_corrected

            % make sure body_part types match
            this_row.body_part = string(this_row.body_part);

            % attach correction columns to T (create if missing)
            if ~ismember('x_corrected', T.Properties.VariableNames)
                T.x_corrected = nan(height(T),1);
                T.y_corrected = nan(height(T),1);
            end

            m = (T.time == this_row.time) & (string(T.body_part) == string(this_row.body_part));
            T.x_corrected(m) = this_row.x_corrected;
            T.y_corrected(m) = this_row.y_corrected;

            % recompute
            T = Spikes.Videos.correct_side_traj(T, 'SmoothWin', 7, 'TimeUnit','ms', 'Thresh', [], 'MinKeepFrames', 5);

            % session name (prefer table, fallback to behavior)
            sess = "";
            if istable(T) && height(T)>0 && ismember('anm_session', T.Properties.VariableNames)
                sess = string(T.anm_session(1));
            elseif ~isempty(app.currentTrialBehavior) && isfield(app.currentTrialBehavior,'anm_session') && ~isempty(app.currentTrialBehavior.anm_session)
                sess = string(app.currentTrialBehavior.anm_session{1});
            end
            sess = erase(sess, ["[","]",""""]);
            sess = strtrim(sess);
            if sess == "", sess = "unknown_session"; end

            trial = string(app.currentTrial);
            body  = string(body);   % you said it's ready already

            % output folder
            outDir = fullfile(pwd, 'Data', 'Processed', 'TrajCorrected');
            if ~isfolder(outDir), mkdir(outDir); end

            % filename
            base = "CorrectedTrajectorySideView_" + sess + "_" + trial + "_" + body;
            base = regexprep(base, '\s+', '_');
            base = regexprep(base, '[^\w\-]+', '_');   % keep it safe
            outPath = fullfile(outDir, base + ".csv");

            % write
            try
                writetable(T, outPath);
                disp("Saved corrected table: " + string(outPath));
            catch ME
                uialert(app.getAppFigure(), sprintf("Failed to save corrected table:\n%s\n\n%s", outPath, ME.message), 'Save failed');
            end

            % remove temporary columns so schema stays unchanged
            T.x_corrected = [];
            T.y_corrected = [];

            app.ComputeButton.UserData.TrajTable = T;
            app.plotComputedKinematicsToKinematicsAxis();  % uses app.KinematicDropDown.Value by default

            % -------- nested callbacks ----------
            function onClickOnce(~, ~)
                % accept click only inside VideoClipAxis
                cpFig = fig.CurrentPoint; % figure pixel coords
                axPos = getpixelposition(app.VideoClipAxis, true);

                inAx = cpFig(1) >= axPos(1) && cpFig(1) <= axPos(1)+axPos(3) && ...
                    cpFig(2) >= axPos(2) && cpFig(2) <= axPos(2)+axPos(4);

                if ~inAx
                    return
                end

                p = app.VideoClipAxis.CurrentPoint(1,1:2); % data coords
                pt = p;
                clicked = true;

                uiresume(fig);
            end

            function onKeyPress(~, e)
                if isfield(e,'Key') && strcmpi(e.Key,'escape')
                    canceled = true;
                    uiresume(fig);
                end
            end
        
        end

        % Size changed function: RightPanel
        function RightPanelSizeChanged(app, event)
            position = app.RightPanel.Position;

        end

        % Key press function: AdvancedkinematicsanalysissideviewUIFigure
        function AdvancedkinematicsanalysissideviewUIFigureKeyPress(app, event)
            % Ignore key events when user is typing in an edit field
            if isa(app.AdvancedkinematicsanalysissideviewUIFigure.CurrentObject, 'matlab.ui.control.EditField') || ...
                    isa(app.AdvancedkinematicsanalysissideviewUIFigure.CurrentObject, 'matlab.ui.control.TextArea')
                return
            end

            switch event.Key
                case 'leftarrow'
                    BackwardButtonPushed(app, event);

                case 'rightarrow'
                    ForwardButtonPushed(app, event);
                case 'space'
                    ManualfixButtonPushed(app, event);
            end
        end

        % Callback function
        function ApplyallButtonPushed(app, event)
            % this computes all body parts, including the lever
            
        end

        % Value changed function: ApplyallCheckBox
        function ApplyallCheckBoxValueChanged(app, event)
            value = app.ApplyallCheckBox.Value;
            
        end

        % Clicked callback: TrajListDropDown
        function TrajListDropDownClicked(app, event)
            TrajListDropDownValueChanged(app, event)
            
        end

        % Clicked callback: TrialstrackedListBox
        function TrialstrackedListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            value = app.TrialstrackedListBox.Items{item};

            % selected item like "Side_Press_1005084_toLever (812)"
            sel = string(value);
            key = regexprep(sel, '\s+\(\d+\)$', '');  % strip the count

            % set dropdown to that key
            if isprop(app,'TrajListDropDown') && ~isempty(app.TrajListDropDown) && isvalid(app.TrajListDropDown)

                % only set if exists (avoid errors)
                if any(strcmp(app.TrajListDropDown.Items, key))
                    app.TrajListDropDown.Value = key;

                    % IMPORTANT: setting Value programmatically might not always trigger ValueChangedFcn
                    % depending on how your app is wired. So call your plot routine explicitly.

                    % Option A: call the dropdown's ValueChanged handler directly (best if you have one)
                    % app.TrajListDropDownValueChanged([]);

                    % Option B (recommended): call the function you already use to plot a selected item
                    TrajListDropDownValueChanged(app, event);  % you implement / point to your existing plotting function
                else
                    uialert(app.AdvancedkinematicsanalysissideviewUIFigure, ...
                        sprintf('Key not found in dropdown:\n%s', key), 'Not found');
                end
            end
        end

        % Value changed function: TrialstrackedListBox
        function TrialstrackedListBoxValueChanged(app, event)
            value = app.TrialstrackedListBox.Value;
            if isempty(app.TrialstrackedListBox.Value)
                return
            end

            % selected item like "Side_Press_1005084_toLever (812)"
            sel = string(value);
            key = regexprep(sel, '\s+\(\d+\)$', '');  % strip the count

            % set dropdown to that key
            if isprop(app,'TrajListDropDown') && ~isempty(app.TrajListDropDown) && isvalid(app.TrajListDropDown)

                % only set if exists (avoid errors)
                if any(strcmp(app.TrajListDropDown.Items, key))
                    app.TrajListDropDown.Value = key;

                    % IMPORTANT: setting Value programmatically might not always trigger ValueChangedFcn
                    % depending on how your app is wired. So call your plot routine explicitly.

                    % Option A: call the dropdown's ValueChanged handler directly (best if you have one)
                    % app.TrajListDropDownValueChanged([]);

                    % Option B (recommended): call the function you already use to plot a selected item
                    TrajListDropDownValueChanged(app, event);  % you implement / point to your existing plotting function
                else
                    uialert(app.AdvancedkinematicsanalysissideviewUIFigure, ...
                        sprintf('Key not found in dropdown:\n%s', key), 'Not found');
                end
            end
        end

        % Button pushed function: CheckalltrialsButton
        function CheckalltrialsButtonPushed(app, event)
            
            T = app.tableKinematics;
            body = app.BodytrackedDLCDropDown.Value;
            data_check = Kin.screen_tracking(T, body);

        end

        % Button pushed function: SavedatafornicefigureButton
        function SavedatafornicefigureButtonPushed(app, event)
            % Want to produce a nice figure that is suitable for
            % publication

            % find the image of current frame

            if ~isprop(app,'VideoClipAxis') || isempty(app.VideoClipAxis) || ~isvalid(app.VideoClipAxis)
                return
            end
            if isempty(app.RawFrame)
                return
            end

            gain = app.getVideoGain();

            % Apply gain for RGB uint8
            brightFrame = uint8(min(double(app.RawFrame) * gain, 255));
            % Keep axes consistent
            trialName = string(app.currentTrial); 
            T = app.goodTrajTable.Data;
            TT = T(T.trial == trialName, :);

            index = app.VideoSlider.Value;
            et = app.VideoMeta.VideoInfo.EphysTimeStamps;
            t_current = et(index);

            anm_session = string(TT.anm_session(1));
            trial = string(TT.trial(1));

            % --- package struct ---
            S = struct();
            S.frame = brightFrame;
            S.TT = TT;
            S.index = index;
            S.time = t_current;
            S.anm_session = anm_session;
            S.trial = trial;
            S.gain = gain;
            S.created_at = string(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));

            outFolder = fullfile(pwd, 'Data', 'Processed', 'ExampleFrames');
            % --- save ---
            if ~exist(outFolder, 'dir')
                mkdir(outFolder);
            end

            fileName = sprintf('example_trajectory_%s_%s_%d.mat', ...
                anm_session, trial, index);
            filePath = fullfile(outFolder, fileName);

            save(filePath, 'S');

            % --- write meta ---
            write_meta( ...
                fileName, ...
                'MetaFolder', outFolder, ...
                'Description', 'Example frame data for trajectory overlay plotting', ...
                'Purpose', 'Store raw frame and tracked body-part coordinates for later figure generation in script', ...
                'GeneratorFunction', 'save_example_frame_data', ...
                'GeneratorScript', '', ...
                'Inputs', { ...
                sprintf('anm_session: %s', anm_session), ...
                sprintf('trial: %s', trial), ...
                sprintf('gain: %.6g', gain), ...
                sprintf('n_rows_TT: %d', height(TT)) ...
                });

            fprintf('Saved example frame data to:\n%s\n', filePath);
        end

        % Callback function
        function AddbodyButtonPushed(app, event)
            if isempty(app.forPlotDropDown.Items)
                app.forPlotDropDown.Items = {app.BodytrackedDLCDropDown.Value};
            else
                app.forPlotDropDown.Items = [app.forPlotDropDown.Items app.BodytrackedDLCDropDown.Value];
                app.forPlotDropDown.Items = unique(app.forPlotDropDown.Items);
            end
        end

        % Callback function
        function ClearButtonPushed(app, event)
            app.forPlotDropDown.Items = {};
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AdvancedkinematicsanalysissideviewUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {802, 802};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedkinematicsanalysissideviewUIFigure = uifigure('Visible', 'off');
            app.AdvancedkinematicsanalysissideviewUIFigure.Name = 'Advanced kinematics analysis (side view)';
            app.AdvancedkinematicsanalysissideviewUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AdvancedkinematicsanalysissideviewUIFigure.KeyPressFcn = createCallbackFcn(app, @AdvancedkinematicsanalysissideviewUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.AdvancedkinematicsanalysissideviewUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectvideopathButton = uibutton(app.LeftPanel, 'push');
            app.SelectvideopathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectvideopathButtonPushed, true);
            app.SelectvideopathButton.Text = '1|Select video path';
            app.videoPath = uilabel(app.LeftPanel);
            app.VideocliplistDropDownLabel = uilabel(app.LeftPanel);
            app.VideocliplistDropDownLabel.Text = 'Video clip list';
            app.VideocliplistDropDown = uidropdown(app.LeftPanel);
            app.VideocliplistDropDown.ValueChangedFcn = createCallbackFcn(app, @VideocliplistDropDownValueChanged, true);
            app.BodytrackedDLCDropDownLabel = uilabel(app.LeftPanel);
            app.BodytrackedDLCDropDownLabel.Text = 'Body tracked (DLC)';
            app.BodytrackedDLCDropDown = uidropdown(app.LeftPanel);
            app.BodytrackedDLCDropDown.ValueChangedFcn = createCallbackFcn(app, @BodytrackedDLCDropDownValueChanged, true);
            app.TrajExtractedfolderButton = uibutton(app.LeftPanel, 'push');
            app.TrajExtractedfolderButton.ButtonPushedFcn = createCallbackFcn(app, @TrajExtractedfolderButtonPushed, true);
            app.TrajExtractedfolderButton.Tooltip = {'The table has a name like "TrajectoryTopView_Nasha_20250405.csv". It is created by a script "TrajExtraction.m"'};
            app.TrajExtractedfolderButton.Text = '3|TrajExtracted folder';
            app.LoadrarrayButton = uibutton(app.LeftPanel, 'push');
            app.LoadrarrayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrarrayButtonPushed, true);
            app.LoadrarrayButton.Text = '2|Load r array';
            app.trajSummary = uitable(app.LeftPanel);
            app.trajSummary.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.trajSummary.RowName = {};
            app.trajSummary.CellEditCallback = createCallbackFcn(app, @trajSummaryCellEdit, true);
            app.trajSummary.CellSelectionCallback = createCallbackFcn(app, @trajSummaryCellSelection, true);
            app.trajSummary.SelectionChangedFcn = createCallbackFcn(app, @trajSummarySelectionChanged, true);
            app.CheckatrialButton = uibutton(app.LeftPanel, 'push');
            app.CheckatrialButton.ButtonPushedFcn = createCallbackFcn(app, @CheckatrialButtonPushed, true);
            app.CheckatrialButton.Tooltip = {'This load the video clips and tracked trajectory'};
            app.CheckatrialButton.Text = '4|Check a trial';
            app.SelectedTrial = uilabel(app.LeftPanel);
            app.SelectedTrial.Text = 'Label2';
            app.goodTrajTable = uitable(app.LeftPanel);
            app.goodTrajTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.goodTrajTable.RowName = {};
            app.goodTrajTable.CellSelectionCallback = createCallbackFcn(app, @goodTrajTableCellSelection, true);
            app.AddToFinalButton = uibutton(app.LeftPanel, 'push');
            app.AddToFinalButton.ButtonPushedFcn = createCallbackFcn(app, @AddToFinalButtonPushed, true);
            app.AddToFinalButton.Text = '5|Add ';
            app.RemoveFromFinalButton = uibutton(app.LeftPanel, 'push');
            app.RemoveFromFinalButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromFinalButtonPushed, true);
            app.RemoveFromFinalButton.Text = 'Remove';
            app.GoodtrajectoriesLabel = uilabel(app.LeftPanel);
            app.GoodtrajectoriesLabel.Text = 'Good trajectories';
            app.tagEditFieldLabel = uilabel(app.LeftPanel);
            app.tagEditFieldLabel.Tooltip = {'Add the tag to target specific type of activity'};
            app.tagEditFieldLabel.Text = 'tag';
            app.tagEditField = uieditfield(app.LeftPanel, 'text');
            app.FEB2026JYLabel = uilabel(app.LeftPanel);
            app.FEB2026JYLabel.Text = 'FEB 2026 JY';
            app.trialNum = uilabel(app.LeftPanel);
            app.trialNum.Text = 'Label7';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.addTraj = uicheckbox(app.LeftPanel);
            app.addTraj.ValueChangedFcn = createCallbackFcn(app, @addTrajValueChanged, true);
            app.addTraj.Text = '5|Add trajectory';
            app.currentBodyPart = uilabel(app.LeftPanel);
            app.currentBodyPart.Text = 'Label5';
            app.DiffButton = uibutton(app.LeftPanel, 'push');
            app.DiffButton.ButtonPushedFcn = createCallbackFcn(app, @DiffButtonPushed, true);
            app.DiffButton.Tooltip = {'Remove trials in TrajSummary table if these trials are in goodTrajTable'};
            app.DiffButton.Text = 'Diff';
            app.pressindex = uieditfield(app.LeftPanel, 'text');
            app.FilterButton = uibutton(app.LeftPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Tooltip = {'Remove all trials in trajSummary that are earlier than the latest trials in Good Trajectories table. '};
            app.FilterButton.Text = 'Filter';
            app.TrajListDropDown = uidropdown(app.LeftPanel);
            app.TrajListDropDown.ValueChangedFcn = createCallbackFcn(app, @TrajListDropDownValueChanged, true);
            app.TrajListDropDown.ClickedFcn = createCallbackFcn(app, @TrajListDropDownClicked, true);
            app.TrialstrackedListBoxLabel = uilabel(app.LeftPanel);
            app.TrialstrackedListBoxLabel.Text = 'Trials tracked';
            app.TrialstrackedListBox = uilistbox(app.LeftPanel);
            app.TrialstrackedListBox.ValueChangedFcn = createCallbackFcn(app, @TrialstrackedListBoxValueChanged, true);
            app.TrialstrackedListBox.ClickedFcn = createCallbackFcn(app, @TrialstrackedListBoxClicked, true);
            app.TrialsTrackedLabel = uilabel(app.LeftPanel);
            app.TrialsTrackedLabel.Text = 'Label8';
            app.CheckalltrialsButton = uibutton(app.LeftPanel, 'push');
            app.CheckalltrialsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckalltrialsButtonPushed, true);
            app.CheckalltrialsButton.Text = 'Check all trials';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.SizeChangedFcn = createCallbackFcn(app, @RightPanelSizeChanged, true);
            app.VideoClipAxis = uiaxes(app.RightPanel);
            app.VideoClipAxis.XTick = [];
            app.VideoClipAxis.XTickLabel = '';
            app.VideoClipAxis.YTick = [];
            app.spikesAxis = uiaxes(app.RightPanel);
            title(app.spikesAxis, 'Spikes')
            xlabel(app.spikesAxis, 'time (ms)')
            zlabel(app.spikesAxis, 'Z')
            app.kinematicsAxis = uiaxes(app.RightPanel);
            zlabel(app.kinematicsAxis, 'Z')
            app.barAxis = uiaxes(app.RightPanel);
            zlabel(app.barAxis, 'Z')
            app.poseAxis = uiaxes(app.RightPanel);
            zlabel(app.poseAxis, 'Z')
            app.colorMapSDF = uiaxes(app.RightPanel);
            title(app.colorMapSDF, 'Title')
            xlabel(app.colorMapSDF, 'X')
            ylabel(app.colorMapSDF, 'Y')
            zlabel(app.colorMapSDF, 'Z')
            app.FrameTimeLabel = uilabel(app.RightPanel);
            app.FrameTimeLabel.Text = 'Label3';
            app.FrameLabel = uilabel(app.RightPanel);
            app.FrameLabel.Text = 'Label4';
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PlaySwitch = uiswitch(app.RightPanel, 'slider');
            app.PlaySwitch.Value = 'On';
            app.ChosenUITable = uitable(app.RightPanel);
            app.ChosenUITable.ColumnName = {'Ch'; 'Unit'};
            app.ChosenUITable.RowName = {};
            app.ChosenUITable.CellEditCallback = createCallbackFcn(app, @ChosenUITableCellEdit, true);
            app.ChosenUITable.CellSelectionCallback = createCallbackFcn(app, @ChosenUITableCellSelection, true);
            app.addButton = uibutton(app.RightPanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '2.1|add';
            app.removeButton = uibutton(app.RightPanel, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.SliderLabel = uilabel(app.RightPanel);
            app.SliderLabel.Text = 'Slider';
            app.VideoSlider = uislider(app.RightPanel);
            app.VideoSlider.ValueChangedFcn = createCallbackFcn(app, @VideoSliderValueChanged, true);
            app.UnitlistDropDownLabel = uilabel(app.RightPanel);
            app.UnitlistDropDownLabel.Text = 'Unit list';
            app.UnitlistDropDown = uidropdown(app.RightPanel);
            app.KinematicDropDownLabel = uilabel(app.RightPanel);
            app.KinematicDropDownLabel.Text = 'Kinematic:';
            app.KinematicDropDown = uidropdown(app.RightPanel);
            app.KinematicDropDown.Items = {'x', 'y', 'v', 'angle', 'v_angle'};
            app.KinematicDropDown.ValueChangedFcn = createCallbackFcn(app, @KinematicDropDownValueChanged, true);
            app.KinematicDropDown.Tooltip = {'This selects what feature will be plotted with behavior. '};
            app.KinematicDropDown.ClickedFcn = createCallbackFcn(app, @KinematicDropDownClicked, true);
            app.KinematicDropDown.Value = 'x';
            app.SpeedKnob = uiknob(app.RightPanel, 'discrete');
            app.SpeedKnob.Items = {'Off', '2', '4', '6', '8', '10', '12', '14', '16', '18', '20'};
            app.startButton = uibutton(app.RightPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.endButton = uibutton(app.RightPanel, 'push');
            app.endButton.ButtonPushedFcn = createCallbackFcn(app, @endButtonPushed, true);
            app.endButton.Text = 'end';
            app.StartTimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.EndTimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.PRINTButton = uibutton(app.RightPanel, 'push');
            app.PRINTButton.ButtonPushedFcn = createCallbackFcn(app, @PRINTButtonPushed, true);
            app.PRINTButton.Text = 'PRINT';
            app.PSTHButton = uibutton(app.RightPanel, 'push');
            app.PSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHButtonPushed, true);
            app.PSTHButton.Tooltip = {'To visualize PSTHs'};
            app.PSTHButton.Text = 'PSTH';
            app.RankunitsButton = uibutton(app.RightPanel, 'push');
            app.RankunitsButton.ButtonPushedFcn = createCallbackFcn(app, @RankunitsButtonPushed, true);
            app.RankunitsButton.Text = 'Rank units';
            app.VisualizeButton = uibutton(app.RightPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.ComputeButton = uibutton(app.RightPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.SelectrangeButton = uibutton(app.RightPanel, 'push');
            app.SelectrangeButton.ButtonPushedFcn = createCallbackFcn(app, @SelectrangeButtonPushed, true);
            app.SelectrangeButton.Tooltip = {'This allows you to select a range in the poseAxis. '};
            app.SelectrangeButton.Text = 'Select range';
            app.AddCheckBox = uicheckbox(app.RightPanel);
            app.AddCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddCheckBoxValueChanged, true);
            app.AddCheckBox.Text = 'Add';
            app.LiftButton = uibutton(app.RightPanel, 'push');
            app.LiftButton.ButtonPushedFcn = createCallbackFcn(app, @LiftButtonPushed, true);
            app.LiftButton.Tooltip = {'auto-select'};
            app.LiftButton.Text = 'Lift';
            app.HoldCheckBox = uicheckbox(app.RightPanel);
            app.HoldCheckBox.Text = 'Hold';
            app.AdjustBrightnessKnobLabel = uilabel(app.RightPanel);
            app.AdjustBrightnessKnobLabel.Text = 'Adjust Brightness';
            app.AdjustBrightnessKnob = uiknob(app.RightPanel, 'continuous');
            app.AdjustBrightnessKnob.Limits = [1 4];
            app.AdjustBrightnessKnob.ValueChangedFcn = createCallbackFcn(app, @AdjustBrightnessKnobValueChanged, true);
            app.AdjustBrightnessKnob.ValueChangingFcn = createCallbackFcn(app, @AdjustBrightnessKnobValueChanging, true);
            app.AdjustBrightnessKnob.Value = 1;
            app.BackwardButton = uibutton(app.RightPanel, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = 'Backward';
            app.ForwardButton = uibutton(app.RightPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.PreEditFieldLabel = uilabel(app.RightPanel);
            app.PreEditFieldLabel.Text = 'Pre';
            app.PreEditField = uieditfield(app.RightPanel, 'numeric');
            app.PreEditField.ValueChangedFcn = createCallbackFcn(app, @PreEditFieldValueChanged, true);
            app.PreEditField.Value = -2000;
            app.PostEditFieldLabel = uilabel(app.RightPanel);
            app.PostEditFieldLabel.Text = 'Post';
            app.PostEditField = uieditfield(app.RightPanel, 'numeric');
            app.PostEditField.ValueChangedFcn = createCallbackFcn(app, @PostEditFieldValueChanged, true);
            app.PostEditField.Value = 3000;
            app.UnTrackedListBox = uilistbox(app.RightPanel);
            app.UnTrackedListBox.ValueChangedFcn = createCallbackFcn(app, @UnTrackedListBoxValueChanged, true);
            app.UnTrackedListBox.ClickedFcn = createCallbackFcn(app, @UnTrackedListBoxClicked, true);
            app.BodypartsremainedLabel = uilabel(app.RightPanel);
            app.BodypartsremainedLabel.Text = 'Body parts remained';
            app.AutofigsaveCheckBox = uicheckbox(app.RightPanel);
            app.AutofigsaveCheckBox.Text = 'Auto fig save';
            app.SeeallButton = uibutton(app.RightPanel, 'push');
            app.SeeallButton.ButtonPushedFcn = createCallbackFcn(app, @SeeallButtonPushed, true);
            app.SeeallButton.Text = 'See all';
            app.ManualfixButton = uibutton(app.RightPanel, 'push');
            app.ManualfixButton.ButtonPushedFcn = createCallbackFcn(app, @ManualfixButtonPushed, true);
            app.ManualfixButton.Tag = 'Manual fix a few points (after compute)';
            app.ManualfixButton.Tooltip = {'auto-select'};
            app.ManualfixButton.Text = 'Manual fix';
            app.ApplyallCheckBox = uicheckbox(app.RightPanel);
            app.ApplyallCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyallCheckBoxValueChanged, true);
            app.ApplyallCheckBox.Text = 'Apply all';
            app.SavedatafornicefigureButton = uibutton(app.RightPanel, 'push');
            app.SavedatafornicefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatafornicefigureButtonPushed, true);
            app.SavedatafornicefigureButton.Text = 'Save data for nice figure';
            app.ContextMenu = uicontextmenu(app.AdvancedkinematicsanalysissideviewUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CheckatrialButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.AdvancedkinematicsanalysissideviewUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.AddToFinalButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.AdvancedkinematicsanalysissideviewUIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu3);
            app.Menu2_3.Text = 'Menu2';
            % Assign app.ContextMenu3
            app.BackwardButton.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.AdvancedkinematicsanalysissideviewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_side_kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedkinematicsanalysissideviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedkinematicsanalysissideviewUIFigure)
        end
    end
end