classdef DLCApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TrialTrackedLabel           matlab.ui.control.Label
        NextvideoButton             matlab.ui.control.Button
        SavedatatomatButton         matlab.ui.control.Button
        ManualtrackButton           matlab.ui.control.Button
        PlottrajectoryButton        matlab.ui.control.Button
        FillEndButton               matlab.ui.control.Button
        FillStartButton             matlab.ui.control.Button
        EndEditField                matlab.ui.control.EditField
        EndEditFieldLabel           matlab.ui.control.Label
        StartEditField              matlab.ui.control.EditField
        StartEditFieldLabel         matlab.ui.control.Label
        FramenumEditField           matlab.ui.control.EditField
        FramenumEditFieldLabel      matlab.ui.control.Label
        MetafileEditField           matlab.ui.control.EditField
        MetafileEditFieldLabel      matlab.ui.control.Label
        DLCfileEditField            matlab.ui.control.EditField
        DLCfileEditFieldLabel       matlab.ui.control.Label
        VidnameEditField            matlab.ui.control.EditField
        VidnameEditFieldLabel       matlab.ui.control.Label
        LoadvideoButton             matlab.ui.control.Button
        BodypartsDropDown           matlab.ui.control.DropDown
        BodypartsDropDownLabel      matlab.ui.control.Label
        UIAxes7                     matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        GoodtrackingratioEditField  matlab.ui.control.EditField
        GoodtrackingratioEditFieldLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)
            
            % open selection
            [vfilename, vpath] = uigetfile('*.avi', 'Select a file') ;
            % e.g., Lamar_20210218_Press001.avi
            meta_filename = [vfilename(1:end-4) '.mat'];
            dlcTable_name = dir([vfilename(1:end-4) '*.csv']);
            dlcTable_name = dlcTable_name.name;
            
            [D, S] = xlsread(dlcTable_name);
            
            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;
            app.LoadvideoButton.UserData.dlcTable = dlcTable_name;
            
            app.VidnameEditField.Value = vfilename(1:end-4);
            app.DLCfileEditField.Value = dlcTable_name(1:end-4);
            app.MetafileEditField.Value = meta_filename;
            
            % start to update video file
            ind_body = find(strcmp(S(2, :), app.BodypartsDropDown.Value));
            nframes   =       D(:, 1);
            x_pos       =       D(:, ind_body(1));
            y_pos       =       D(:, ind_body(2));
            lh              =       D(:, ind_body(3));
            
            load(app.LoadvideoButton.UserData.metaname);
            t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            
            app.BodypartsDropDown.UserData = [x_pos, y_pos, lh, t_frames'];
            
            n_goodframes = length(find(lh>0.8));
            n_allframes = length(lh);
            
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)
            
            cla(app.UIAxes2)
            app.UIAxes2.UserData{1} = plot(app.UIAxes2,nframes+1, lh, 'ko-');
            set(app.UIAxes2, 'xlim', [0 nframes(end)+1], 'ylim', [-0.2 1.2])
            line(app.UIAxes2, [0 nframes(end)+1], [0.8 0.8], 'color', 'g', 'linestyle', ':')
            set(app.UIAxes2, 'nextplot', 'add')
            
            app.UIAxes2.XLabel.String = 'Frame #';
            app.UIAxes2.YLabel.String = 'Likelihood';
            
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);
            
            % plot first frame on the video axis
            cla(app.UIAxes)
            ind_this = 1;
            
            this_video = vfilename;
            vidObj = VideoReader(this_video);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames;
            toc
            
            this_frame = AllFrames(:, :, :, ind_this);
            app.FramenumEditField.Value =  num2str(ind_this);
            
            
            vidpos = app.UIAxes.Position;
            width = vidpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            vidpos(4) = newheight;
            
            set(app.UIAxes,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 1)], 'ylim', [0 size(this_frame, 2)], 'ydir','reverse')
            
            app.UIAxes.UserData{3} = image(app.UIAxes, this_frame);
            
            if lh(ind_this) < 0.8
                app.UIAxes.UserData{2} = plot(app.UIAxes, x_pos(ind_this), y_pos(ind_this), 'ro', 'markersize', 6, 'linewidth', 1)
                app.UIAxes2.UserData{2} = plot(app.UIAxes2, ind_this, lh(ind_this), 'mo', 'markersize', 6, 'markerfacecolor', 'm', 'linewidth', 1);
            else
                app.UIAxes.UserData{2} =plot(app.UIAxes, x_pos(ind_this), y_pos(ind_this), 'co', 'markersize', 6, 'linewidth', 1)
                app.UIAxes2.UserData{2} = plot(app.UIAxes2, ind_this, lh(ind_this), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            end;
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
            
            % plot pose in ha_traj
            
            cla(app.UIAxes3)
            set(app.UIAxes3, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxes3, x_pos(i), y_pos(i), 'c+', 'linewidth', 1)
                else
                    plot(app.UIAxes3, x_pos(i), y_pos(i), 'k+', 'linewidth', 1)
                end;
            end;
            
            app.UIAxes3.UserData{1} = plot(app.UIAxes3, x_pos(ind_this), y_pos(ind_this), 'm+', 'markersize', 15,'markerfacecolor', 'm', 'linewidth', 2);
            
            trajpos = app.UIAxes3.Position;
            
            width = trajpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            trajpos(4) = newheight;
            
            app.UIAxes3.Position = trajpos;
            app.UIAxes3.XLim = [100 900];
            app.UIAxes3.YLim = [100 800];
            
            % plot pose  x vs t
            cla(app.UIAxes4)
             app.UIAxes4.UserData = [];
            set(app.UIAxes4, 'nextplot', 'add')
            app.UIAxes4.UserData{1} = plot(app.UIAxes4, t_frames, x_pos, 'ko')
            app.UIAxes4.YLabel.String = 'X Position';
            app.UIAxes4.XLabel.String = 'Frames';
            app.UIAxes4.UserData{2} = plot(app.UIAxes4, t_frames(ind_this), x_pos(ind_this), 'm+', 'markersize', 15, 'linewidth', 2);
            app.UIAxes4.XLim    = [t_frames(1) t_frames(end)];
            
            
            % plot pose  y vs t
            cla(app.UIAxes5)
            app.UIAxes5.UserData = [];
            set(app.UIAxes5, 'nextplot', 'add')
            app.UIAxes5.UserData{1} = plot(app.UIAxes5, t_frames, y_pos, 'ko')
            app.UIAxes5.YLabel.String = 'Y Position';
            app.UIAxes5.XLabel.String = 'Frames';
            app.UIAxes5.UserData{2} = plot(app.UIAxes5, t_frames(ind_this), y_pos(ind_this),  'm+', 'markersize', 15, 'linewidth', 2);
            app.UIAxes5.XLim    = [t_frames(1) t_frames(end)];
            
            cla(app.UIAxes6)
            app.UIAxes6.XLim = [100 900];
            app.UIAxes6.YLim = [100 800];
            app.UIAxes6.NextPlot = 'Add';
            
            app.SavedatatomatButton.Text = {'Save data to mat'};
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            tic
            app.UIAxes.UserData{3}.CData = this_frame;
            toc

            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            t_frames =  posdata(:, 4);


            if lh(ind_this) < 0.8
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
            else
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
            end;


            set(app.UIAxes3.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 15, 'linewidth', 2)
            set(app.UIAxes4.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
            set(app.UIAxes5.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            verticalScrollCount = event.VerticalScrollCount;
            
            value = app.Slider.Value;
            newvalue = value + verticalScrollCount;
            newvalue = max([1 newvalue]);
            app.Slider.Value = newvalue;
            value = newvalue;
            
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')
            
            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            tic
            app.UIAxes.UserData{3}.CData = this_frame;
            toc
            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            t_frames =  posdata(:, 4);
            
            if lh(ind_this) < 0.8
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
            else
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
            end;

            set(app.UIAxes3.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 15, 'linewidth', 2)
            set(app.UIAxes4.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
            set(app.UIAxes5.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))

            
        end

        % Callback function
        function SelectrangeButtonPushed(app, event)
            % select the rannge
            rect = getrect(app.UIAxes2);
            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';
            ind_chosen = find(frs>=rect(1) & frs<=sum(rect([1 3])) & lh>=rect(2) & lh <= sum(rect([2 4])));
            
            if isempty(app.SelectrangeButton.UserData)
                app.SelectrangeButton.UserData{1} = ind_chosen;
                app.SelectrangeButton.UserData{2} = plot(app.UIAxes2, frs(ind_chosen), lh(ind_chosen), 'g.', 'markersize', 5);
                app.UIAxes3.UserData{2} = plot(app.UIAxes3, x_pos(ind_chosen), y_pos(ind_chosen), 'go', 'markersize', 4);
            else
                app.SelectrangeButton.UserData{1} = ind_chosen;
                set(app.SelectrangeButton.UserData{2}, 'XData', frs(ind_chosen), 'YData', lh(ind_chosen));
                set(app.UIAxes3.UserData{2}, 'XData',  x_pos(ind_chosen), 'YData', y_pos(ind_chosen))
            end;
            
            %             %% plot trajectory
            %
            %             t_chosen = 1:length(ind_chosen);
            %             x_chosen = x_pos(ind_chosen);
            %             y_chosen = y_pos(ind_chosen);
            %
            %             tnew = resample(t_chosen, 10, 1);
            %             x_chosennew = interp1(t_chosen, x_chosen, tnew);
            %             y_chosennew = interp1(t_chosen, y_chosen, tnew);
            %             app.UIAxes3.UserData{3} = plot(app.UIAxes3, x_chosennew, y_chosennew, 'color', [1 0.8 0.2]);
            
        end

        % Callback function
        function UndoButtonPushed(app, event)

            posdata = app.BodypartsDropDown.UserData;

            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';

            ind_chosen = [];

            app.SelectrangeButton.UserData{1} = ind_chosen;
            set(app.SelectrangeButton.UserData{2}, 'XData', [], 'YData', []);
            set(app.UIAxes3.UserData{2}, 'XData',  [], 'YData', [])


        end

        % Button pushed function: ManualtrackButton
        function ManualtrackButtonPushed(app, event)
            % change the location of body part
            value = app.Slider.Value;
            ind_this = round(value);
            
            % draw point on UIAxes
            h = drawpoint(app.UIAxes,  'LabelVisible', 'off'); % user-defined body location

            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            t_frames =  posdata(:, 4);
            x_pos(ind_this) = h.Position(1);
            y_pos(ind_this) = h.Position(2);
            lh(ind_this)    = 1;
            
            posdata = [x_pos y_pos lh t_frames];
            
            app.BodypartsDropDown.UserData = posdata;
            
            if lh(ind_this) < 0.8
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
            else
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
            end;            
            
            set(app.UIAxes3.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 12, 'linewidth', 2)
            set(app.UIAxes4.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
            set(app.UIAxes5.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))
            
            % update
            set(app.UIAxes2.UserData{1}, 'XData', [1:length(lh)], 'YData', lh);
            
            set(app.UIAxes4.UserData{1}, 'XData', t_frames, 'YData', x_pos)
            set(app.UIAxes5.UserData{1}, 'XData', t_frames, 'YData', y_pos)
            
                       
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);
            
            x_pos_selected = x_pos(FrameBeg:FrameEnd);
            y_pos_selected = y_pos(FrameBeg:FrameEnd);
            t_frames_selected = t_frames(FrameBeg:FrameEnd);
            
            %             cla(app.UIAxes4)
            
            if length(app.UIAxes4.UserData)>2
                set(app.UIAxes4.UserData{3}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                set(app.UIAxes4.UserData{4}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                set(app.UIAxes5.UserData{3}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                set(app.UIAxes5.UserData{4}, 'XData', t_frames_selected, 'YData', y_pos_selected)
            end;
            
            AllChildren = get(app.UIAxes, 'Children');
            delete(AllChildren(1));
        end

        % Button pushed function: FillStartButton
        function FillStartButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.StartEditField.Value = num2str(ind_this);
        end

        % Button pushed function: FillEndButton
        function FillEndButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.EndEditField.Value = num2str(ind_this);
        end

        % Button pushed function: PlottrajectoryButton
        function PlottrajectoryButtonPushed(app, event)
            
            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);
            
            x_pos = posdata(FrameBeg:FrameEnd, 1);
            y_pos = posdata(FrameBeg:FrameEnd, 2);
            
            load(app.LoadvideoButton.UserData.metaname);
            t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            t_frames_selected = t_frames(FrameBeg:FrameEnd);
            this_frame = app.UIAxes.UserData{1}(:, :, :, FrameEnd);
            %
            cla(app.UIAxes6)
            
            app.UIAxes6.NextPlot = 'add';
            
            % plot the image first
            
            app.UIAxes6.UserData{1} = image(app.UIAxes6, this_frame);
            app.UIAxes6.XLim = [0 800];
            app.UIAxes6.YLim = [0 800];
            app.UIAxes6.YDir     = 'reverse';
            
            app.UIAxes6.UserData{2} = plot(app.UIAxes6, x_pos, y_pos, '.', 'color', 'g', 'linewidth', 1, 'markersize', 6, 'markerfacecolor', 'g');
            app.UIAxes6.UserData{3} = plot(app.UIAxes6, x_pos, y_pos, '-', 'linewidth', 2, 'color', [1 0.8 0]);

            %             cla(app.UIAxes4)
            app.UIAxes4.UserData{3} =  plot(app.UIAxes4, t_frames_selected, x_pos,'*', 'color', 'g', 'linewidth', 1, 'markersize', 4, 'markerfacecolor', 'g');
            app.UIAxes4.UserData{4} =  plot(app.UIAxes4, t_frames_selected, x_pos,  "Color", [1 0.8 0], 'linewidth', 2);
            app.UIAxes4.XLabel.String = 'Time from press (ms)';
            app.UIAxes4.YLabel.String = 'X position';
            app.UIAxes4.XLim    = [t_frames_selected(1) t_frames_selected(end)];
            app.UIAxes4.YDir     = 'reverse';

            %             cla(app.UIAxes5)
            app.UIAxes5.UserData{3} =  plot(app.UIAxes5, t_frames_selected, y_pos,'*', 'color', 'g', 'linewidth', 1,  'markersize', 4, 'markerfacecolor', 'g');
            app.UIAxes5.UserData{4} =  plot(app.UIAxes5, t_frames_selected, y_pos, "Color", [1 0.8 0], 'linewidth', 2);
            app.UIAxes5.XLabel.String = 'Time from press (ms)';
            app.UIAxes5.YLabel.String = 'Y position';
            app.UIAxes5.YDir     = 'reverse';
            app.UIAxes5.XLim    = [t_frames_selected(1) t_frames_selected(end)];
 

            chH = get(app.UIAxes4,'Children')
            set(app.UIAxes4,'Children',[chH(end-1); setdiff(chH, chH(end-1))])

            chH = get(app.UIAxes5,'Children')
            set(app.UIAxes5,'Children',[chH(end-1); setdiff(chH, chH(end-1))])

        end

        % Button pushed function: SavedatatomatButton
        function SavedatatomatButtonPushed(app, event)
            %             Session: '2021-02-18-14h19m-Subject Lamar'
            %             Event: 'Press'
            %             EventIndex: 129
            %             Performance: 'Correct'
            %             EventTime: 1787750
            %             FrameTimesB: [1×249 double]
            %             VideoOrg: 'Cam_00D41932971+2021_2_18_14_20_34.5797602++.avi'
            %             FrameIndx: [1×249 double]
            %             Code: 'G:\ZqWork\Code\YUcode\Functions\ExportVideoFiles'
            %             CreatedOn: '01-Jul-2021'
            %             DLCTrackingOut = [];
            
            meta_filename =   app.LoadvideoButton.UserData.metaname;
            body_part_name = app.BodypartsDropDown.Value;
            load(meta_filename);  % this will launch VidMeta
            
            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);
            
            IndxSelected = round(linspace(FrameBeg, FrameEnd, 5));
            %             this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            
            if  ~isempty(dir('DLCTrackingOut.mat'))
                
                load('DLCTrackingOut.mat')
                
                % load DLCTracingOut
                % find index of body parts
                ind_body =find(strcmp(DLCTrackingOut.BodyParts, body_part_name));
                
                % new index
                if isempty(find(DLCTrackingOut.PoseTracking(ind_body).EventIndex(1, :)==VidMeta.EventIndex))
                    
                    DLCTrackingOut.PoseTracking(ind_body).PosData{end+1}        = posdata(FrameBeg:FrameEnd, :);
                    DLCTrackingOut.PoseTracking(ind_body).EventIndex(:, end+1)  = [VidMeta.EventIndex; VidMeta.EventTime];
                    DLCTrackingOut.PoseTracking(ind_body).Performance{end+1}  =  VidMeta.Performance;
                    DLCTrackingOut.PoseTracking(ind_body).Images{end+1} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                    DLCTrackingOut.PoseTracking(ind_body).ImagesIndex{end+1} = IndxSelected-FrameBeg+1;
                    
                else
                    ind_old = find(DLCTrackingOut.PoseTracking(ind_body).EventIndex(1, :)==VidMeta.EventIndex);
                    DLCTrackingOut.PoseTracking(ind_body).PosData{ind_old}        = posdata(FrameBeg:FrameEnd, :);
                    DLCTrackingOut.PoseTracking(ind_body).EventIndex(:, ind_old)  = [VidMeta.EventIndex; VidMeta.EventTime];
                    DLCTrackingOut.PoseTracking(ind_body).Performance{ind_old}  =  VidMeta.Performance;
                    DLCTrackingOut.PoseTracking(ind_body).Images{ind_old} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                    DLCTrackingOut.PoseTracking(ind_body).ImagesIndex{ind_old} = IndxSelected-FrameBeg+1;
                end;
                
                save DLCTrackingOut DLCTrackingOut
                
                %% Trials tracked
                app.TrialTrackedLabel.Text = sprintf('tracked: %2.0d', size(DLCTrackingOut.PoseTracking(ind_body).EventIndex, 2));
                                
            else
                
                DLCTrackingOut = [];
                DLCTrackingOut.Session = VidMeta.Session;
                DLCTrackingOut.Event = VidMeta.Event;
                DLCTrackingOut.BodyParts{1} = body_part_name;
                ind_body = 1;
                DLCTrackingOut.PoseTracking(1).PosInfo = {'x', 'y', 'time(ms)', 'likelihood'};

                DLCTrackingOut.PoseTracking(1).PosData{1} = posdata(FrameBeg:FrameEnd, :);
                DLCTrackingOut.PoseTracking(1).EventIndex(:, 1) = [VidMeta.EventIndex; VidMeta.EventTime];
                DLCTrackingOut.PoseTracking(1).Performance{1} = VidMeta.Performance;
                DLCTrackingOut.PoseTracking(1).Images{1} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                DLCTrackingOut.PoseTracking(1).ImagesIndex{1} = IndxSelected-FrameBeg+1;
                save DLCTrackingOut DLCTrackingOut

                %% Trials tracked
                app.TrialTrackedLabel.Text = sprintf('tracked: %2.0d', size(DLCTrackingOut.PoseTracking(1).EventIndex, 2));
            end;
            

            app.SavedatatomatButton.Text = {'Saved'}


            app.UIAxes7.XLim = [200 900];
            app.UIAxes7.YLim = [200 800];
            app.UIAxes7.NextPlot = 'Add';
            app.UIAxes7.YDir     = 'reverse';
            delete(get(app.UIAxes7, 'children'));

            % plot an image

            image(app.UIAxes7, DLCTrackingOut.PoseTracking(ind_body).Images{end}(:, :, :, 3));

            % plot all tracked trials
            for i = 1:length(DLCTrackingOut.PoseTracking.PosData)
                tframes = DLCTrackingOut.PoseTracking.PosData{i}(:, 3);
                indframes = find(tframes<=500);
                plot(app.UIAxes7, DLCTrackingOut.PoseTracking.PosData{i}(indframes, 1), DLCTrackingOut.PoseTracking.PosData{i}(indframes, 2), 'color', [0.8 0.8 0.8]);
            end
            plot(app.UIAxes7, DLCTrackingOut.PoseTracking.PosData{end}(indframes, 1), DLCTrackingOut.PoseTracking.PosData{end}(indframes, 2), 'color', [1 0.8 0], 'linewidth', 2);

            
        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)
            
            AllFiles = dir('*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,app.LoadvideoButton.UserData.vfilename));
            
            if current_indx == length(AllVidFiles)
                return
            end
            
            next_indx = current_indx + 1;
            
            vfilename = AllVidFiles{next_indx};
            meta_filename = [vfilename(1:end-4) '.mat'];
            dlcTable_name = dir([vfilename(1:end-4) '*.csv']);
            dlcTable_name = dlcTable_name.name;
            
            [D, S] = xlsread(dlcTable_name);
            
            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;
            app.LoadvideoButton.UserData.dlcTable = dlcTable_name;
            
            app.VidnameEditField.Value = vfilename(1:end-4);
            app.DLCfileEditField.Value = dlcTable_name(1:end-4);
            app.MetafileEditField.Value = meta_filename;
            
            % start to update video file
            ind_body = find(strcmp(S(2, :), app.BodypartsDropDown.Value));
            nframes   =       D(:, 1);
            x_pos       =       D(:, ind_body(1));
            y_pos       =       D(:, ind_body(2));
            lh              =       D(:, ind_body(3));
            
            load(app.LoadvideoButton.UserData.metaname);
            t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            
            app.BodypartsDropDown.UserData = [x_pos, y_pos, lh, t_frames'];
            
            n_goodframes = length(find(lh>0.8));
            n_allframes = length(lh);
            
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)
            
            cla(app.UIAxes2)
            app.UIAxes2.UserData{1} = plot(app.UIAxes2,nframes+1, lh, 'ko-');
            set(app.UIAxes2, 'xlim', [0 nframes(end)+1], 'ylim', [-0.2 1.2])
            line(app.UIAxes2, [0 nframes(end)+1], [0.8 0.8], 'color', 'g', 'linestyle', ':')
            set(app.UIAxes2, 'nextplot', 'add')
            
            app.UIAxes2.XLabel.String = 'Frame #';
            app.UIAxes2.YLabel.String = 'Likelihood';
            
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);
            
            % plot first frame on the video axis
            cla(app.UIAxes)
            ind_this = 1;
            
            this_video = vfilename;
            vidObj = VideoReader(this_video);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames;
            toc
            
            this_frame = AllFrames(:, :, :, ind_this);
            app.FramenumEditField.Value =  num2str(ind_this);
            
            
            vidpos = app.UIAxes.Position;
            width = vidpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            vidpos(4) = newheight;
            
            set(app.UIAxes,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 1)], 'ylim', [0 size(this_frame, 2)], 'ydir','reverse')
            
            app.UIAxes.UserData{3} = image(app.UIAxes, this_frame);
            
            if lh(ind_this) < 0.8
                app.UIAxes.UserData{2} = plot(app.UIAxes, x_pos(ind_this), y_pos(ind_this), 'ro', 'markersize', 6, 'linewidth', 1)
                app.UIAxes2.UserData{2} = plot(app.UIAxes2, ind_this, lh(ind_this), 'mo', 'markersize', 6, 'markerfacecolor', 'm', 'linewidth', 1);
            else
                app.UIAxes.UserData{2} =plot(app.UIAxes, x_pos(ind_this), y_pos(ind_this), 'co', 'markersize', 6, 'linewidth', 1)
                app.UIAxes2.UserData{2} = plot(app.UIAxes2, ind_this, lh(ind_this), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            end;
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
            
            % plot pose in ha_traj
            
            cla(app.UIAxes3)
            set(app.UIAxes3, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxes3, x_pos(i), y_pos(i), 'c+', 'linewidth', 1)
                else
                    plot(app.UIAxes3, x_pos(i), y_pos(i), 'k+', 'linewidth', 1)
                end;
            end;
            
            app.UIAxes3.UserData{1} = plot(app.UIAxes3, x_pos(ind_this), y_pos(ind_this), 'm+', 'markersize', 12,'markerfacecolor', 'm', 'linewidth', 2);
            
            trajpos = app.UIAxes3.Position;
            
            width = trajpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            trajpos(4) = newheight;
            
            app.UIAxes3.Position = trajpos;
            app.UIAxes3.XLim = [100 900];
            app.UIAxes3.YLim = [100 800];
            
            % plot pose  x vs t
            cla(app.UIAxes4)
            set(app.UIAxes4, 'nextplot', 'add')
            app.UIAxes4.UserData = [];
            app.UIAxes4.UserData{1} = plot(app.UIAxes4, t_frames, x_pos, 'ko')
            app.UIAxes4.YLabel.String = 'X Position';
            app.UIAxes4.XLabel.String = 'Frames';
            app.UIAxes4.UserData{2} = plot(app.UIAxes4, t_frames(ind_this), x_pos(ind_this), 'm+', 'markersize', 12, 'linewidth', 2);
                        app.UIAxes4.XLim    = [t_frames(1) t_frames(end)];

            
            % plot pose  y vs t
            cla(app.UIAxes5)
            set(app.UIAxes5, 'nextplot', 'add')
            app.UIAxes5.UserData = [];
            app.UIAxes5.UserData{1} = plot(app.UIAxes5, t_frames, y_pos, 'ko')
            app.UIAxes5.YLabel.String = 'Y Position';
            app.UIAxes5.XLabel.String = 'Frames';
            app.UIAxes5.UserData{2} = plot(app.UIAxes5, t_frames(ind_this), y_pos(ind_this),  'm+', 'markersize', 12, 'linewidth', 2);
                        app.UIAxes5.XLim    = [t_frames(1) t_frames(end)];

            
            cla(app.UIAxes6)
            app.UIAxes6.XLim = [200 1000];
            app.UIAxes6.YLim = [100 800];
            app.UIAxes6.NextPlot = 'Add';
            
            app.SavedatatomatButton.Text = {'Save data to mat'};

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
           
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
             switch key
                case 'control'
                    % change the location of body part
                    value = app.Slider.Value;
                    ind_this = round(value);

                    % draw point on UIAxes
                    h = drawpoint(app.UIAxes,  'LabelVisible', 'off'); % user-defined body location


                    posdata = app.BodypartsDropDown.UserData;

                    x_pos   =   posdata(:, 1);
                    y_pos   =   posdata(:, 2);
                    lh      =   posdata(:, 3);
                    t_frames =  posdata(:, 4);
                    x_pos(ind_this) = h.Position(1);
                    y_pos(ind_this) = h.Position(2);
                    lh(ind_this)    = 1;

                    posdata = [x_pos y_pos lh t_frames];

                    app.BodypartsDropDown.UserData = posdata;

                    if lh(ind_this) < 0.8
                        set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                        set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
                    else
                        set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                        set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
                    end;

                    set(app.UIAxes3.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 12, 'linewidth', 2)
                    set(app.UIAxes4.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
                    set(app.UIAxes5.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))

                    % update
                    set(app.UIAxes2.UserData{1}, 'XData', [1:length(lh)], 'YData', lh);

                    set(app.UIAxes4.UserData{1}, 'XData', t_frames, 'YData', x_pos)
                    set(app.UIAxes5.UserData{1}, 'XData', t_frames, 'YData', y_pos)


                    FrameBeg = str2num(app.StartEditField.Value);
                    FrameEnd = str2num(app.EndEditField.Value);

                    x_pos_selected = x_pos(FrameBeg:FrameEnd);
                    y_pos_selected = y_pos(FrameBeg:FrameEnd);
                    t_frames_selected = t_frames(FrameBeg:FrameEnd);

                    %             cla(app.UIAxes4)

                    if length(app.UIAxes4.UserData)>2
                        set(app.UIAxes4.UserData{3}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                        set(app.UIAxes4.UserData{4}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                        set(app.UIAxes5.UserData{3}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                        set(app.UIAxes5.UserData{4}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                    end;

                    AllChildren = get(app.UIAxes, 'Children');
                    delete(AllChildren(1));
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {750, 750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {247, '1x', 332};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes7 = uiaxes(app.LeftPanel);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.Tag = 'trialsaccumulated';
            app.BodypartsDropDownLabel = uilabel(app.LeftPanel);
            app.BodypartsDropDownLabel.Text = 'Body parts';
            app.BodypartsDropDown = uidropdown(app.LeftPanel);
            app.BodypartsDropDown.Items = {'L_finger5', 'L_finger4', 'L_hand', 'R_finger2', 'R_finger3', 'R_hand', 'Neck', 'Eye', 'EarL', 'EarR', 'Nose', 'LeverBase', 'LeverEnd'};
            app.BodypartsDropDown.Tag = 'BodyParts';
            app.BodypartsDropDown.Value = 'L_finger5';
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.VidnameEditFieldLabel = uilabel(app.LeftPanel);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.VidnameEditField = uieditfield(app.LeftPanel, 'text');
            app.VidnameEditField.Tag = 'vidfilename';
            app.DLCfileEditFieldLabel = uilabel(app.LeftPanel);
            app.DLCfileEditFieldLabel.Text = {'DLC file'; ''};
            app.DLCfileEditField = uieditfield(app.LeftPanel, 'text');
            app.DLCfileEditField.Tag = 'DLCfile';
            app.MetafileEditFieldLabel = uilabel(app.LeftPanel);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.MetafileEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditFieldLabel = uilabel(app.LeftPanel);
            app.FramenumEditFieldLabel.Text = {'Frame num'; ''};
            app.FramenumEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditField.Tag = 'framenum';
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.LeftPanel, 'text');
            app.EndEditFieldLabel = uilabel(app.LeftPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.LeftPanel, 'text');
            app.FillStartButton = uibutton(app.LeftPanel, 'push');
            app.FillStartButton.ButtonPushedFcn = createCallbackFcn(app, @FillStartButtonPushed, true);
            app.FillStartButton.Text = 'Fill Start';
            app.FillEndButton = uibutton(app.LeftPanel, 'push');
            app.FillEndButton.ButtonPushedFcn = createCallbackFcn(app, @FillEndButtonPushed, true);
            app.FillEndButton.Text = 'Fill End';
            app.PlottrajectoryButton = uibutton(app.LeftPanel, 'push');
            app.PlottrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlottrajectoryButtonPushed, true);
            app.PlottrajectoryButton.Text = {'Plot trajectory'; ''};
            app.ManualtrackButton = uibutton(app.LeftPanel, 'push');
            app.ManualtrackButton.ButtonPushedFcn = createCallbackFcn(app, @ManualtrackButtonPushed, true);
            app.ManualtrackButton.Text = 'Manual track';
            app.SavedatatomatButton = uibutton(app.LeftPanel, 'push');
            app.SavedatatomatButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatomatButtonPushed, true);
            app.SavedatatomatButton.Text = {'Save data to mat'; ''};
            app.NextvideoButton = uibutton(app.LeftPanel, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.TrialTrackedLabel = uilabel(app.LeftPanel);
            app.TrialTrackedLabel.Text = {'TrialTracked'; ''};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, {'Tracking quality'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Tag = 'LabelingQuality';
            app.Slider = uislider(app.CenterPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'videoslider';
            app.GoodtrackingratioEditFieldLabel = uilabel(app.CenterPanel);
            app.GoodtrackingratioEditFieldLabel.Text = 'Good tracking ratio';
            app.GoodtrackingratioEditField = uieditfield(app.CenterPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YDir = 'reverse';
            app.UIAxes3.Tag = 'ha_traj';
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Reach trajectory')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLCApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end