classdef DLCApp2025 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        BatchButton                 matlab.ui.control.Button
        PlotSaveMoveonButton        matlab.ui.control.Button
        ListofvideosListBox         matlab.ui.control.ListBox
        LastvideoButton             matlab.ui.control.Button
        SeeButton                   matlab.ui.control.Button
        ChecktrackedButton          matlab.ui.control.Button
        TrackedDropDown             matlab.ui.control.DropDown
        TrackedDropDownLabel        matlab.ui.control.Label
        NextvideoButton             matlab.ui.control.Button
        SavedatatomatButton         matlab.ui.control.Button
        PlottrajectoryButton        matlab.ui.control.Button
        FillEndButton               matlab.ui.control.Button
        FillStartButton             matlab.ui.control.Button
        EndEditField                matlab.ui.control.EditField
        EndEditFieldLabel           matlab.ui.control.Label
        StartEditField              matlab.ui.control.EditField
        StartEditFieldLabel         matlab.ui.control.Label
        FramenumEditField           matlab.ui.control.EditField
        FramenumEditFieldLabel      matlab.ui.control.Label
        MetafileEditField           matlab.ui.control.EditField
        MetafileEditFieldLabel      matlab.ui.control.Label
        DLCfileEditField            matlab.ui.control.EditField
        DLCfileEditFieldLabel       matlab.ui.control.Label
        VidnameEditField            matlab.ui.control.EditField
        VidnameEditFieldLabel       matlab.ui.control.Label
        LoadvideoButton             matlab.ui.control.Button
        BodypartsDropDown           matlab.ui.control.DropDown
        BodypartsDropDownLabel      matlab.ui.control.Label
        UIAxes7                     matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        Slider                      matlab.ui.control.Slider
        GoodtrackingratioEditField  matlab.ui.control.EditField
        GoodtrackingratioEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        currentDLCTableFull % path and table name
        currentDLCTableName % table name
        currentDLCTable
        dlcResults
        tFrames % frames times of current video
        trajOver % overlaid trajactory
        trajCurrentFrame % use a cross to represent body position at current frame
        trajAlone %  trajactory plotted alone
        trajAloneCurrentFrame % use a cross to represent body position at current frame
        trajXT
        trajYT
        trajXTrev % revised 
        trajYTrev % revised
        trajXTCurrent
        trajYTCurrent
        lhood % likelihood
        lhoodCurrent % current likelihood
        allVideoList % a list of all videos
        vidPath % path to all videos
        trajSelected % selected segment of a trajectory
        metaData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)
            
            % open selection
            [vfilename, vpath] = uigetfile('*.avi', 'Select a file') ;
            app.vidPath         =   vpath;
            % read all videos from this folder
            % Define the directory (replace '.' with your folder path if needed)
            folder = vpath;


            % find all avi files in this directory
            fileList = dir('*.avi');

            % Display the matching files
            fprintf('Matching AVI files:\n');
            for i = 1:length(fileList)
                fprintf('%s\n', fileList(i).name);
            end
            app.allVideoList = fileList;

            app.ListofvideosListBox.Items = arrayfun(@(x)x.name, fileList, 'UniformOutput', false);
            app.ListofvideosListBox.Value = vfilename;

            app.VidnameEditField.Value = vfilename;
            VidnameEditFieldValueChanged(app, event)   

     
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            tic
            app.UIAxes.UserData{3}.CData = this_frame;
            toc

            posdata = app.BodypartsDropDown.UserData;

            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh          =   posdata(:, 3);
            t_frames =  app.tFrames;

            try
                app.trajXTCurrent.XData = t_frames(ind_this);
                app.trajXTCurrent.YData =  x_pos(ind_this);
                app.trajYTCurrent.XData = t_frames(ind_this);
                app.trajYTCurrent.YData =  y_pos(ind_this);
            end
            app.trajAloneCurrentFrame.XData = x_pos(ind_this);
            app.trajAloneCurrentFrame.YData = y_pos(ind_this);

            set(app.lhoodCurrent, 'XData', t_frames(ind_this), 'YData', lh(ind_this));
            % change the cross over the image
            app.trajCurrentFrame.XData = x_pos(ind_this);
            app.trajCurrentFrame.YData = y_pos(ind_this);

        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            if false
            verticalScrollCount = event.VerticalScrollCount;
            
            value = app.Slider.Value;
            newvalue = value + verticalScrollCount;
            newvalue = max([1 newvalue]);
            app.Slider.Value = newvalue;
            SliderValueChanged(app, event)
            end
        end

        % Callback function
        function SelectrangeButtonPushed(app, event)
            % select the rannge
            rect = getrect(app.UIAxes2);
            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';
            ind_chosen = find(frs>=rect(1) & frs<=sum(rect([1 3])) & lh>=rect(2) & lh <= sum(rect([2 4])));
            
            if isempty(app.SelectrangeButton.UserData)
                app.SelectrangeButton.UserData{1} = ind_chosen;
                app.SelectrangeButton.UserData{2} = plot(app.UIAxes2, frs(ind_chosen), lh(ind_chosen), 'g.', 'markersize', 5);
                app.UIAxes3.UserData{2} = plot(app.UIAxes3, x_pos(ind_chosen), y_pos(ind_chosen), 'go', 'markersize', 4);
            else
                app.SelectrangeButton.UserData{1} = ind_chosen;
                set(app.SelectrangeButton.UserData{2}, 'XData', frs(ind_chosen), 'YData', lh(ind_chosen));
                set(app.UIAxes3.UserData{2}, 'XData',  x_pos(ind_chosen), 'YData', y_pos(ind_chosen))
            end;
            
            %             %% plot trajectory
            %
            %             t_chosen = 1:length(ind_chosen);
            %             x_chosen = x_pos(ind_chosen);
            %             y_chosen = y_pos(ind_chosen);
            %
            %             tnew = resample(t_chosen, 10, 1);
            %             x_chosennew = interp1(t_chosen, x_chosen, tnew);
            %             y_chosennew = interp1(t_chosen, y_chosen, tnew);
            %             app.UIAxes3.UserData{3} = plot(app.UIAxes3, x_chosennew, y_chosennew, 'color', [1 0.8 0.2]);
            
        end

        % Callback function
        function UndoButtonPushed(app, event)

            posdata = app.BodypartsDropDown.UserData;

            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';

            ind_chosen = [];

            app.SelectrangeButton.UserData{1} = ind_chosen;
            set(app.SelectrangeButton.UserData{2}, 'XData', [], 'YData', []);
            set(app.UIAxes3.UserData{2}, 'XData',  [], 'YData', [])


        end

        % Callback function
        function ManualtrackButtonPushed(app, event)
            % change the location of body part
            value = app.Slider.Value;
            ind_this = round(value);
            
            % draw point on UIAxes
            h = drawpoint(app.UIAxes,  'LabelVisible', 'off'); % user-defined body location
           
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            t_frames =  posdata(:, 4);
            x_pos(ind_this) = h.Position(1);
            y_pos(ind_this) = h.Position(2);
            lh(ind_this)    = 1;
            
            posdata = [x_pos y_pos lh t_frames];
            
            app.BodypartsDropDown.UserData = posdata;
            
            if lh(ind_this) < 0.8
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
            else
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
            end;            
            
            set(app.UIAxes3.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 12, 'linewidth', 2)
            set(app.UIAxes4.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
            set(app.UIAxes5.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))
            
            % update
            set(app.UIAxes2.UserData{1}, 'XData', [1:length(lh)], 'YData', lh);
            
            set(app.UIAxes4.UserData{1}, 'XData', t_frames, 'YData', x_pos)
            set(app.UIAxes5.UserData{1}, 'XData', t_frames, 'YData', y_pos)
            
                       
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);
            
            x_pos_selected = x_pos(FrameBeg:FrameEnd);
            y_pos_selected = y_pos(FrameBeg:FrameEnd);
            t_frames_selected = t_frames(FrameBeg:FrameEnd);
            
            %             cla(app.UIAxes4)
            
            if length(app.UIAxes4.UserData)>2
                set(app.UIAxes4.UserData{3}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                set(app.UIAxes4.UserData{4}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                set(app.UIAxes5.UserData{3}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                set(app.UIAxes5.UserData{4}, 'XData', t_frames_selected, 'YData', y_pos_selected)
            end;
            
            AllChildren = get(app.UIAxes, 'Children');
            delete(AllChildren(1));
        end

        % Button pushed function: FillStartButton
        function FillStartButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.StartEditField.Value = num2str(ind_this);
        end

        % Button pushed function: FillEndButton
        function FillEndButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.EndEditField.Value = num2str(ind_this);
        end

        % Button pushed function: PlottrajectoryButton
        function PlottrajectoryButtonPushed(app, event)
            
            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);
            
            x_pos = posdata(FrameBeg:FrameEnd, 1);
            y_pos = posdata(FrameBeg:FrameEnd, 2);
            like  = posdata(FrameBeg:FrameEnd, 3); % likelihood
            t_frames = app.tFrames;
            t_frames_selected = t_frames(FrameBeg:FrameEnd);

            % remove low-like points, unse interpolation to fill these
            % badly-tracked points
            threshold = 0.9;
            [x_seg,y_seg, ~, t_seg]=Spikes.Videos.clean_data_lever(t_frames_selected, x_pos, y_pos, like, threshold);

            this_frame = app.UIAxes.UserData{1}(:, :, :, FrameEnd);
            %
            cla(app.UIAxes6)
            
            app.UIAxes6.NextPlot = 'add';

            % plot the image first
            app.UIAxes6.UserData{1} = image(app.UIAxes6, this_frame);
            app.UIAxes6.XLim = [0 size(this_frame, 2)];
            app.UIAxes6.YLim = [0 size(this_frame, 1)-200];
            app.UIAxes6.YDir     = 'reverse';

            delete(app.trajSelected)
            app.trajSelected = scatter(app.UIAxes6, x_pos, y_pos, 'Marker', '+', 'linewidth', 1, 'sizedata', 50, 'markeredgecolor', 'c');
   
            delete(app.trajXT)
            delete(app.trajXTCurrent)
            delete(app.trajYT)
            delete(app.trajYTCurrent)

            app.trajXT = plot(app.UIAxes4, t_frames_selected, x_pos,'*', 'color', 'g', 'linewidth', 1, 'markersize', 4);
            app.trajYT = plot(app.UIAxes5, t_frames_selected, y_pos,'*', 'color', 'g', 'linewidth', 1,  'markersize', 4);
                        
            delete(app.trajXTrev)
            delete(app.trajYTrev)

            app.trajXTrev = plot(app.UIAxes4, t_seg, x_seg,'+-', 'color', 'b', 'linewidth', 1, 'markersize', 6);
            app.trajYTrev = plot(app.UIAxes5, t_seg, y_seg,'+-', 'color', 'b', 'linewidth', 1,  'markersize', 6);

            app.UIAxes4.XLabel.String = 'Time from press (ms)';
            app.UIAxes4.YLabel.String = 'X position';
            app.UIAxes4.XLim    = [t_frames_selected(1) t_frames_selected(end)];
            app.UIAxes4.YDir     = 'reverse';

            app.UIAxes5.XLabel.String = 'Time from press (ms)';
            app.UIAxes5.YLabel.String = 'Y position';
            app.UIAxes5.YDir     = 'reverse';
            app.UIAxes5.XLim    = [t_frames_selected(1) t_frames_selected(end)];
 
        end

        % Button pushed function: SavedatatomatButton
        function SavedatatomatButtonPushed(app, event)


            vfilename = app.VidnameEditField.Value;
            vfilename_ = extractBefore(vfilename, '.');
            savedFolderPath = app.vidPath;


            currentBody                         = app.BodypartsDropDown.Value;
            dlcResults                            = app.dlcResults;
            FrameBeg                            = str2num(app.StartEditField.Value);
            FrameEnd                            = str2num(app.EndEditField.Value);
            metaData                            = app.metaData;

            TrajExtracted.DLC                           = dlcResults;
            TrajExtracted.Meta                      = metaData;
            TrajExtracted.indexSelected     = (FrameBeg:FrameEnd);
            TrajExtracted.bodySelected     = currentBody;
            % TrajExtracted.TimeSelected      = TimeSelected;
            new_traj_name = ['TrajExtracted_' vfilename_ '.mat'];
            save(fullfile(savedFolderPath, new_traj_name), 'TrajExtracted');
            disp(['Data saved to: ', fullfile(savedFolderPath, new_traj_name)]);
            app.SavedatatomatButton.Text = {'Saved'};

            ChecktrackedButtonPushed(app, event)
            NextvideoButtonPushed(app, event)
        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)
            
            currentVideo = app.VidnameEditField.Value;
            AllVidFiles = arrayfun(@(x)x.name, app.allVideoList, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles, currentVideo));
  
            if current_indx == length(AllVidFiles)
                return
            end
            
            next_indx = current_indx + 1;
            
            vfilename = AllVidFiles{next_indx};
            app.VidnameEditField.Value = vfilename;
            VidnameEditFieldValueChanged(app, event)
            
            app.SavedatatomatButton.Text = 'Save data to mat';
            app.FramenumEditField.Value = '1';
            app.ListofvideosListBox.Value = vfilename;
            ChecktrackedButtonPushed(app, event)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
           
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
             switch key
                case 'control'
                    % change the location of body part
                    value = app.Slider.Value;
                    ind_this = round(value);

                    % draw point on UIAxes
                    h = drawpoint(app.UIAxes,  'LabelVisible', 'off'); % user-defined body location
                    posdata = app.BodypartsDropDown.UserData;

                    x_pos   =   posdata(:, 1);
                    y_pos   =   posdata(:, 2);
                    lh      =   posdata(:, 3);
                    t_frames =  posdata(:, 4);
                    x_pos(ind_this) = h.Position(1);
                    y_pos(ind_this) = h.Position(2);
                    lh(ind_this)    = 1;

                    posdata = [x_pos y_pos lh t_frames];

                    app.BodypartsDropDown.UserData = posdata;

                    if lh(ind_this) < 0.8
                        set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                        set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
                    else
                        set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                        set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
                    end;

                    set(app.UIAxes3.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 12, 'linewidth', 2)
                    set(app.UIAxes4.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
                    set(app.UIAxes5.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))

                    % update
                    set(app.UIAxes2.UserData{1}, 'XData', [1:length(lh)], 'YData', lh);

                    set(app.UIAxes4.UserData{1}, 'XData', t_frames, 'YData', x_pos)
                    set(app.UIAxes5.UserData{1}, 'XData', t_frames, 'YData', y_pos)


                    FrameBeg = str2num(app.StartEditField.Value);
                    FrameEnd = str2num(app.EndEditField.Value);

                    x_pos_selected = x_pos(FrameBeg:FrameEnd);
                    y_pos_selected = y_pos(FrameBeg:FrameEnd);
                    t_frames_selected = t_frames(FrameBeg:FrameEnd);

                    %             cla(app.UIAxes4)

                    if length(app.UIAxes4.UserData)>2
                        set(app.UIAxes4.UserData{3}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                        set(app.UIAxes4.UserData{4}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                        set(app.UIAxes5.UserData{3}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                        set(app.UIAxes5.UserData{4}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                    end;

                    AllChildren = get(app.UIAxes, 'Children');
                    delete(AllChildren(1));
            end
        end

        % Value changed function: BodypartsDropDown
        function BodypartsDropDownValueChanged(app, event)
            body = app.BodypartsDropDown.Value;
            dlcResults_temp = app.dlcResults;
            ind_this = str2double(app.FramenumEditField.Value);

            % display current body parts
            body =  app.BodypartsDropDown.Value;
            eval(['x_pos = app.dlcResults.' body '.x;']);
            eval(['y_pos = app.dlcResults.' body '.y;']);
            eval(['lh = app.dlcResults.' body '.likelihood;']);
           
            % load video meta data
            t_frames = app.tFrames;
           
            app.BodypartsDropDown.UserData = [x_pos, y_pos, lh, t_frames'];
            n_goodframes = length(find(lh>0.8));
            n_allframes = length(lh);            
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)   

            cla(app.UIAxes2);
            % Plot likelihood
            delete(app.lhood)
            app.lhood                 = plot(app.UIAxes2,t_frames, lh, 'ko-');
            set(app.UIAxes2, 'xlim', [t_frames(1) t_frames(end)], 'ylim', [-0.2 1.2]);
            line(app.UIAxes2, [t_frames(1) t_frames(end)], [0.8 0.8], 'color', 'g', 'linestyle', ':');
            set(app.UIAxes2, 'nextplot', 'add')

            app.UIAxes2.XLabel.String = 'Time (ms)';
            app.UIAxes2.YLabel.String = 'Likelihood';

            % Change trajectory over the image.
            % delete(app.trajOver)
            % app.trajOver= scatter(app.UIAxes, x_pos, y_pos, 15, lh, 'filled'); 
            delete(app.trajCurrentFrame)
            app.trajCurrentFrame = scatter(app.UIAxes, x_pos(ind_this), y_pos(ind_this), '+', 'SizeData', 100, 'MarkerEdgeColor', 'r', 'LineWidth', 1);
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);
            
            % Plot body part location and mark its likelihood.
            delete(app.lhoodCurrent)
            app.lhoodCurrent= plot(app.UIAxes2, t_frames(ind_this), lh(ind_this), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);

            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
            
            % plot pose in ha_traj            
            cla(app.UIAxes3)
            set(app.UIAxes3, 'nextplot', 'add')
            
            delete(app.trajAlone)
            delete(app.trajAloneCurrentFrame)
            app.trajAlone                           =       scatter(app.UIAxes3, x_pos, y_pos, 15, lh, 'filled', 'markeredgecolor', 'k');
            app.trajAloneCurrentFrame       =       scatter(app.UIAxes3, x_pos(ind_this), y_pos(ind_this), 100,'+',  'MarkerEdgeColor','r', 'LineWidth',1);

            delete(app.trajXT)
            delete(app.trajXTCurrent)
            app.trajXT = plot(app.UIAxes4, t_frames, x_pos, 'ko');
            app.trajXTCurrent = plot(app.UIAxes4, t_frames(ind_this), x_pos(ind_this), 'm+', 'markersize', 15, 'linewidth', 2);

            delete(app.trajYT)
            delete(app.trajYTCurrent)
            app.trajYT = plot(app.UIAxes5, t_frames, y_pos, 'ko');
            app.trajYTCurrent = plot(app.UIAxes5, t_frames(ind_this), y_pos(ind_this),  'm+', 'markersize', 15, 'linewidth', 2);

                        
            app.SavedatatomatButton.Text = {'Save data to mat'};
    
        end

        % Value changed function: VidnameEditField
        function VidnameEditFieldValueChanged(app, event)
            
            vfilename = app.VidnameEditField.Value;
            vfilename_ = extractBefore(vfilename, '.');
            vpath = app.vidPath;

            % e.g., Lamar_20210218_Press001.avi
            meta_filename = [vfilename_ '.mat'];
            dlcTable_name = dir(fullfile(vpath, [vfilename_ '*.csv']));

            if isempty(dlcTable_name)
                % Error-style warning
                errordlg('DLC tracking not found!', 'Error');
                return
            end

            dlcTable_name_full = fullfile(vpath, dlcTable_name.name);

            app.currentDLCTableFull         =       dlcTable_name_full;
            app.currentDLCTableName               =       dlcTable_name;
            %            [D, S] = xlsread(dlcTable_name_full); % retired
            dlcTable = readtable(app.currentDLCTableFull);
            app.currentDLCTable = dlcTable;            
            
            app.DLCfileEditField.Value = dlcTable_name.name(1:end-4);
            app.MetafileEditField.Value = meta_filename;

            % start to update video file
            % update bodyparts
            dlcResults_temp = read_dlc_table(dlcTable_name_full);
            app.dlcResults = dlcResults_temp;
            % Assuming dlcResults is already loaded or created
            % Extract field names from dlcResults
            fieldNames = fieldnames(dlcResults_temp);
            % Assign field names to the list box in your app
            app.BodypartsDropDown.Items = fieldNames;

            % display current body parts
            body =  app.BodypartsDropDown.Value;
            eval(['x_pos = app.dlcResults.' body '.x;']);
            eval(['y_pos = app.dlcResults.' body '.y;']);
            eval(['lh = app.dlcResults.' body '.likelihood;']);
           
            % load video meta data
            load(fullfile(vpath, meta_filename));
            app.metaData = VidMeta;

            if isfield(VidMeta, 'FrameTimesB')
                t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            elseif isfield(VidMeta, 'FrameTimesBpod')
                t_frames = VidMeta.FrameTimesBpod - VidMeta.EventTime*1000; % EventTime is in second.
            elseif  isfield(VidMeta, 'SideVideoFrameTimesR')
                t_frames = VidMeta.SideVideoFrameTimesR - VidMeta.tTrigger; % this is to accommodate CJM's two-view tracking results
            else
                error('Check VidMeta, cannot recognize the fields')
            end

            app.BodypartsDropDown.UserData = [x_pos, y_pos, lh, t_frames'];
            app.tFrames = t_frames;


            n_goodframes = length(find(lh>0.8));
            n_allframes = length(lh);
            
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)            
            cla(app.UIAxes2);

            % Plot likelihood
            app.lhood = plot(app.UIAxes2,t_frames, lh, 'ko-');
            set(app.UIAxes2, 'xlim', [t_frames(1) t_frames(end)], 'ylim', [-0.2 1.2]);
            line(app.UIAxes2, [t_frames(1) t_frames(end)], [0.8 0.8], 'color', 'g', 'linestyle', ':')
            set(app.UIAxes2, 'nextplot', 'add')
            
            app.UIAxes2.XLabel.String = 'Time (ms)';
            app.UIAxes2.YLabel.String = 'Likelihood';
            
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);
            
            % plot first frame on the video axis
            cla(app.UIAxes)
            ind_this = 1;
            
            this_video = fullfile(app.vidPath, vfilename);
            vidObj = VideoReader(this_video);
            tic
            disp('##### Read frames from this video #####')
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames;
            disp('##### Completed #####')
            toc
            
            this_frame = AllFrames(:, :, :, ind_this);
            app.FramenumEditField.Value =  num2str(ind_this);            
            
            vidpos = app.UIAxes.Position;
            width = vidpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            vidpos(4) = newheight;
            
            set(app.UIAxes,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 1)], 'ylim', [0 size(this_frame, 2)], 'ydir','reverse')
            
            app.UIAxes.Title.String = vfilename;
            app.UIAxes.Title.Interpreter = 'none';
            app.UIAxes.Title.FontSize = 16; % Set desired font size (e.g., 16 points)
            app.UIAxes.UserData{3} = imagesc(app.UIAxes, this_frame);


            app.UIAxes.XLim = [0 size(this_frame, 2)];
            app.UIAxes.YLim = [0 size(this_frame, 1)];

            app.UIAxes.Position(4) = size(this_frame, 1)*app.UIAxes.Position(3)/size(this_frame, 2);

            %app.trajOver = scatter(app.UIAxes, x_pos, y_pos, 15, lh,
            %'filled'); this blocks the object, preventing quality
            %inspection. 
            app.trajCurrentFrame = scatter(app.UIAxes, x_pos(ind_this), y_pos(ind_this), '+', 'SizeData', 100, 'MarkerEdgeColor', 'r', 'LineWidth',1);

            % Plot body part location and mark its likelihood.
            app.lhoodCurrent = plot(app.UIAxes2, t_frames(ind_this), lh(ind_this), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
            
            % plot pose in ha_traj            
            cla(app.UIAxes3)
            set(app.UIAxes3, 'nextplot', 'add')

            % Plot trajectory on the other axes (without the image)
            app.trajAlone                           =       scatter(app.UIAxes3, x_pos, y_pos, 15, lh, 'filled', 'markeredgecolor', 'k');
            app.trajAloneCurrentFrame       =       scatter(app.UIAxes3, x_pos(ind_this), y_pos(ind_this), 100,'+',  'MarkerEdgeColor','r', 'LineWidth',1);
            trajpos = app.UIAxes3.Position;
            width = trajpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            trajpos(4) = newheight;

            app.UIAxes3.Position = trajpos;
            app.UIAxes3.XLim = [0 size(this_frame, 1)];
            app.UIAxes3.YLim =  [0 size(this_frame, 2)];

            % plot pose  x vs t
            cla(app.UIAxes4)
            app.UIAxes4.UserData = [];
            set(app.UIAxes4, 'nextplot', 'add')

            app.trajXT = plot(app.UIAxes4, t_frames, x_pos, 'ko');
            app.trajXTCurrent = plot(app.UIAxes4, t_frames(ind_this), x_pos(ind_this), 'm+', 'markersize', 15, 'linewidth', 2);
             
            app.UIAxes4.YLabel.String = 'X Position';
            app.UIAxes4.XLabel.String = 'Frames';            
            app.UIAxes4.XLim    = [t_frames(1) t_frames(end)];

            % plot pose  y vs t
            cla(app.UIAxes5)
            app.UIAxes5.UserData = [];
            set(app.UIAxes5, 'nextplot', 'add')
            app.UIAxes5.YLabel.String = 'Y Position';
            app.UIAxes5.XLabel.String = 'Frames';
            app.UIAxes5.XLim    = [t_frames(1) t_frames(end)];

            app.trajYT = plot(app.UIAxes5, t_frames, y_pos, 'ko');
            app.trajYTCurrent = plot(app.UIAxes5, t_frames(ind_this), y_pos(ind_this),  'm+', 'markersize', 15, 'linewidth', 2);

            cla(app.UIAxes6)
            app.UIAxes6.XLim = [100 900];
            app.UIAxes6.YLim = [100 800];
            app.UIAxes6.NextPlot = 'Add';

            app.StartEditField.Value = num2str(1);
            app.EndEditField.Value = num2str(n_allframes);

            app.SavedatatomatButton.Text = {'Save data to mat'};

        end

        % Button pushed function: ChecktrackedButton
        function ChecktrackedButtonPushed(app, event)
            % Check all TrajExtracted_ files in app.vpath
            % Read all these files and extract trajs
            thisFolder = app.vidPath;

            filePatterns = 'TrajExtracted_*.mat';
            allTrajExtractedFiles = dir(fullfile(thisFolder, filePatterns));

            allTrajNames = arrayfun(@(x)x.name, allTrajExtractedFiles, 'UniformOutput', false);

            app.TrackedDropDown.Items = allTrajNames;
            app.TrackedDropDownLabel.Text = sprintf('N=%2.0d', length(allTrajNames));
            
        end

        % Button pushed function: SeeButton
        function SeeButtonPushed(app, event)
            body = app.BodypartsDropDown.Value;
            allItems = app.TrackedDropDown.Items;
            vidPath = app.vidPath;
            cla(app.UIAxes7)
            app.UIAxes7.NextPlot = 'add';
            for i =1:length(allItems)

                load(fullfile(vidPath,allItems{i}));
                x_pos = eval(['TrajExtracted.DLC.' body '.x']);
                x_pos = x_pos(TrajExtracted.indexSelected);
                y_pos = eval(['TrajExtracted.DLC.' body '.y']);
                y_pos = y_pos(TrajExtracted.indexSelected);
                lh_pos = eval(['TrajExtracted.DLC.' body '.likelihood']);
                lh_pos = lh_pos(TrajExtracted.indexSelected);

                tFrames = TrajExtracted.Meta.FrameTimesBpod-TrajExtracted.Meta.EventTime*1000;
                tFrames_pos = tFrames(TrajExtracted.indexSelected);

                threshold = 0.9;
                [x_seg,y_seg]=Spikes.Videos.clean_data_lever(tFrames_pos, x_pos, y_pos, lh_pos, threshold);
             
                scatter(app.UIAxes7, x_seg, y_seg, 'o', 'markerfacecolor', [168, 101, 35]/255, ...
                    'MarkerEdgeColor','none', 'MarkerFaceAlpha',0.75, 'SizeData',12);
            end
            app.UIAxes7.YDir = 'reverse';
            app.UIAxes7.Title.String = body;
        end

        % Button pushed function: LastvideoButton
        function LastvideoButtonPushed(app, event)
            
            currentVideo = app.VidnameEditField.Value;
            AllVidFiles = arrayfun(@(x)x.name, app.allVideoList, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles, currentVideo));

            if current_indx == 1
                return
            end

            next_indx = current_indx - 1;

            vfilename = AllVidFiles{next_indx};
            app.VidnameEditField.Value = vfilename;
            VidnameEditFieldValueChanged(app, event)

            app.SavedatatomatButton.Text = 'Save data to mat';

            app.StartEditField.Value = '';
            app.EndEditField.Value = '';
            app.FramenumEditField.Value = '1';

            app.ListofvideosListBox.Value = vfilename;
        end

        % Value changed function: ListofvideosListBox
        function ListofvideosListBoxValueChanged(app, event)
            value = app.ListofvideosListBox.Value;

            app.VidnameEditField.Value = value;
            VidnameEditFieldValueChanged(app, event)

            app.SavedatatomatButton.Text = 'Save data to mat';

            app.StartEditField.Value = '';
            app.EndEditField.Value = '';
            app.FramenumEditField.Value = '1';


        end

        % Button pushed function: PlotSaveMoveonButton
        function PlotSaveMoveonButtonPushed(app, event)
            PlottrajectoryButtonPushed(app, event)
            SavedatatomatButtonPushed(app, event)
        end

        % Button pushed function: BatchButton
        function BatchButtonPushed(app, event)
            % going through all videos clips automatically
            allVids = app.ListofvideosListBox.Items;
            for i =1:length(allVids)
                app.ListofvideosListBox.Value = allVids{i};
                ListofvideosListBoxValueChanged(app, event);
                VidnameEditFieldValueChanged(app, event);
                PlottrajectoryButtonPushed(app, event)
                SavedatatomatButtonPushed(app, event)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {750, 750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {247, '1x', 332};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes7 = uiaxes(app.LeftPanel);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.Tag = 'trialsaccumulated';
            app.BodypartsDropDownLabel = uilabel(app.LeftPanel);
            app.BodypartsDropDownLabel.Text = 'Body parts';
            app.BodypartsDropDown = uidropdown(app.LeftPanel);
            app.BodypartsDropDown.Items = {'L_finger5', 'L_finger4', 'L_hand', 'R_finger2', 'R_finger3', 'R_hand', 'Neck', 'Eye', 'EarL', 'EarR', 'Nose', 'LeverBase', 'LeverEnd'};
            app.BodypartsDropDown.ValueChangedFcn = createCallbackFcn(app, @BodypartsDropDownValueChanged, true);
            app.BodypartsDropDown.Tag = 'BodyParts';
            app.BodypartsDropDown.Value = 'L_finger5';
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.VidnameEditFieldLabel = uilabel(app.LeftPanel);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.VidnameEditField = uieditfield(app.LeftPanel, 'text');
            app.VidnameEditField.ValueChangedFcn = createCallbackFcn(app, @VidnameEditFieldValueChanged, true);
            app.VidnameEditField.Tag = 'vidfilename';
            app.DLCfileEditFieldLabel = uilabel(app.LeftPanel);
            app.DLCfileEditFieldLabel.Text = {'DLC file'; ''};
            app.DLCfileEditField = uieditfield(app.LeftPanel, 'text');
            app.DLCfileEditField.Tag = 'DLCfile';
            app.MetafileEditFieldLabel = uilabel(app.LeftPanel);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.MetafileEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditFieldLabel = uilabel(app.LeftPanel);
            app.FramenumEditFieldLabel.Text = {'Frame num'; ''};
            app.FramenumEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditField.Tag = 'framenum';
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.LeftPanel, 'text');
            app.EndEditFieldLabel = uilabel(app.LeftPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.LeftPanel, 'text');
            app.FillStartButton = uibutton(app.LeftPanel, 'push');
            app.FillStartButton.ButtonPushedFcn = createCallbackFcn(app, @FillStartButtonPushed, true);
            app.FillStartButton.Text = 'Fill Start';
            app.FillEndButton = uibutton(app.LeftPanel, 'push');
            app.FillEndButton.ButtonPushedFcn = createCallbackFcn(app, @FillEndButtonPushed, true);
            app.FillEndButton.Text = 'Fill End';
            app.PlottrajectoryButton = uibutton(app.LeftPanel, 'push');
            app.PlottrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlottrajectoryButtonPushed, true);
            app.PlottrajectoryButton.Text = {'Plot trajectory'; ''};
            app.SavedatatomatButton = uibutton(app.LeftPanel, 'push');
            app.SavedatatomatButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatomatButtonPushed, true);
            app.SavedatatomatButton.Text = {'Save data to mat'; ''};
            app.NextvideoButton = uibutton(app.LeftPanel, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.TrackedDropDownLabel = uilabel(app.LeftPanel);
            app.TrackedDropDownLabel.Text = 'Tracked';
            app.TrackedDropDown = uidropdown(app.LeftPanel);
            app.ChecktrackedButton = uibutton(app.LeftPanel, 'push');
            app.ChecktrackedButton.ButtonPushedFcn = createCallbackFcn(app, @ChecktrackedButtonPushed, true);
            app.ChecktrackedButton.Text = 'Check tracked';
            app.SeeButton = uibutton(app.LeftPanel, 'push');
            app.SeeButton.ButtonPushedFcn = createCallbackFcn(app, @SeeButtonPushed, true);
            app.SeeButton.Text = 'See';
            app.LastvideoButton = uibutton(app.LeftPanel, 'push');
            app.LastvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LastvideoButtonPushed, true);
            app.LastvideoButton.Text = 'Last video';
            app.ListofvideosListBox = uilistbox(app.LeftPanel);
            app.ListofvideosListBox.ValueChangedFcn = createCallbackFcn(app, @ListofvideosListBoxValueChanged, true);
            app.PlotSaveMoveonButton = uibutton(app.LeftPanel, 'push');
            app.PlotSaveMoveonButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSaveMoveonButtonPushed, true);
            app.PlotSaveMoveonButton.Text = 'Plot, Save, Move on';
            app.BatchButton = uibutton(app.LeftPanel, 'push');
            app.BatchButton.ButtonPushedFcn = createCallbackFcn(app, @BatchButtonPushed, true);
            app.BatchButton.Tooltip = {'Clicking this button will process all videos automatically, taking all frames. '};
            app.BatchButton.Text = 'Batch';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, {'Tracking quality'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Tag = 'LabelingQuality';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GoodtrackingratioEditFieldLabel = uilabel(app.CenterPanel);
            app.GoodtrackingratioEditFieldLabel.Text = 'Good tracking ratio';
            app.GoodtrackingratioEditField = uieditfield(app.CenterPanel, 'text');
            app.Slider = uislider(app.CenterPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'videoslider';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YDir = 'reverse';
            app.UIAxes3.Tag = 'ha_traj';
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Reach trajectory')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLCApp2025

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end