classdef DLCAppLite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Classifier                  matlab.ui.control.Button
        SessionEditField            matlab.ui.control.EditField
        SessionEditFieldLabel       matlab.ui.control.Label
        BackwardButton              matlab.ui.control.Button
        ForwardButton               matlab.ui.control.Button
        PlotfeaturesButton          matlab.ui.control.Button
        LastvideoButton             matlab.ui.control.Button
        SavetableButton             matlab.ui.control.Button
        LoadOptoDLCButton           matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        NextvideoButton             matlab.ui.control.Button
        LabelbadeventsButton        matlab.ui.control.Button
        FrameEditField              matlab.ui.control.EditField
        FrameEditFieldLabel         matlab.ui.control.Label
        MetafileEditField           matlab.ui.control.EditField
        MetafileEditFieldLabel      matlab.ui.control.Label
        VidnameEditField            matlab.ui.control.EditField
        VidnameEditFieldLabel       matlab.ui.control.Label
        LoadvideoButton             matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        ProcessvideodatafromMakeAVIDLCLiveandMakeAVIApproachLabel  matlab.ui.control.Label
        GoodtrackingratioEditField  matlab.ui.control.EditField
        GoodtrackingratioEditFieldLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        UIAxesY                     matlab.ui.control.UIAxes
        UIAxesX                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        WhattrialButton             matlab.ui.control.Button
        UIAxesSpeedxy               matlab.ui.control.UIAxes
        UIAxesTriggerPos            matlab.ui.control.UIAxes
        UIAxesTraj2Video            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)

            % open selection
            [vfilename, vpath] = uigetfile('*.avi', 'Select a file') ;
            % e.g., Lamar_20210218_Press001.avi
            meta_filename = [vfilename(1:end-4) '.mat'];

            app.LoadvideoButton.UserData.vfilename = fullfile(vpath, vfilename);
            app.LoadvideoButton.UserData.metaname = fullfile(vpath, meta_filename);

            session = cellstr(extractBetween(vpath, 'Sessions\', '\DLCVideoClips'));
            session = session{1};
            app.VidnameEditField.Value = [session, '/' vfilename(1:end-4)];
            app.MetafileEditField.Value = meta_filename;

            % load meta data
            load(app.LoadvideoButton.UserData.metaname);

            % Update table
            this_table = app.UITable.UserData{1};
            trial_num =  extractBetween(vfilename, 'Trial_', '.avi');
            trial_num = str2num(trial_num{1});
            app.UITable.Data = this_table(this_table.TrialNums==trial_num, :);              

            % Update Pos information at DLC detection time
            beh_table =  app.UITable.UserData{3};
            EventFrameTime = beh_table.FrameTimeAtDLCTime(beh_table.TrialsBpod == trial_num);

            % find the frame index corresponding to this event:
            % IndDLC is the frame index for the trigger event (ie., DLC) 
             [~, IndDLC] = min(abs(VidMeta.FrameTimes - EventFrameTime));

            % read basic information
            t_frames              =        1000*(VidMeta.FrameTimes - VidMeta.FrameTimes(1));
            ind_frames          =        [1:length(VidMeta.Xpos)];
            OrgSize                 =          VidMeta.FrameSize;
            x_pos                    =          VidMeta.Xpos;
            y_pos                    =          VidMeta.Ypos;

            x_pos_Trigger       =         x_pos(IndDLC);
            y_pos_Trigger       =         y_pos(IndDLC);

            n_goodframes = length(find(~isnan(x_pos)));
            n_allframes = length(x_pos);
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);

            % plot first frame on the video axis

            cla(app.UIAxes)
            ind_this = IndDLC;

            vidObj = VideoReader(app.LoadvideoButton.UserData.vfilename);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames; % all frames
            toc

            ScalingFactor = size(AllFrames, 1)/OrgSize(2);
            x_poshat = x_pos*ScalingFactor;
            y_poshat = y_pos*ScalingFactor;

            x_pos_Trigger       =        x_pos_Trigger*ScalingFactor;
            y_pos_Trigger       =         y_pos_Trigger*ScalingFactor;

            this_frame = AllFrames(:, :, :, ind_this);

            app.UIAxes.UserData{4} = {size(this_frame), ScalingFactor, OrgSize, t_frames, x_pos, y_pos};
            app.FrameEditField.Value =  num2str(ind_this);

            vidpos = app.UIAxes.Position;
            width = vidpos(3);
            newheight = width*size(this_frame, 1)/size(this_frame, 2);

            % let's expand the axis a bit more

            vidpos(4) = newheight;

            set(app.UIAxes,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 2)], 'ylim', [0 size(this_frame, 1)], 'ydir','reverse');
            app.UIAxes.UserData{3} = image(app.UIAxes, this_frame); % a single image

            % if there is a DLC label, plot it
            app.UIAxes.UserData{2} = plot(app.UIAxes, x_poshat(ind_this), y_poshat(ind_this), ...
                'm.', 'markersize', 10, 'linewidth', 1); % a single plot

            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;

            % plot pose  x vs t
            cla(app.UIAxesX)
            app.UIAxesX.UserData = [];
            set(app.UIAxesX, 'nextplot', 'add')

            x_plot = ind_frames(~isnan(x_poshat));
            y_plot = x_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));
            marker_size = 20;

            app.UIAxesX.UserData{1} = scatter(app.UIAxesX, x_plot, y_plot, marker_size, c_plot', 'filled');
            app.UIAxesX.YLabel.String = 'X Position';
            app.UIAxesX.XLabel.String = 'Frames';

            app.UIAxesX.UserData{2} =plot(app.UIAxesX, ind_frames(ind_this), x_poshat(ind_this), 'm.', 'markersize', 8);
            app.UIAxesX.XLim    = [ind_frames(1) ind_frames(end)];

            % add the trigger event
            app.UIAxesX.UserData{3} =plot(app.UIAxesX, IndDLC, x_poshat(IndDLC), 'go', 'markersize', 10, 'linewidth', 1);

            % plot pose  y vs t
            cla(app.UIAxesY)
            app.UIAxesY.UserData = [];
            app.UIAxesY.YDir = 'reverse';
            set(app.UIAxesY, 'nextplot', 'add')

            y_plot = y_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));
            marker_size = 20;

            app.UIAxesY.UserData{1} = scatter(app.UIAxesY, x_plot, y_plot, marker_size, c_plot', 'filled');

            app.UIAxesY.YLabel.String = 'Y Position';
            app.UIAxesY.XLabel.String = 'Frames';

            app.UIAxesY.UserData{2} =plot(app.UIAxesY, ind_frames(ind_this), y_poshat(ind_this), 'm.', 'markersize', 8);
            app.UIAxesY.UserData{3} =plot(app.UIAxesY, IndDLC, y_poshat(IndDLC), 'go', 'markersize', 10, 'linewidth', 1);

            app.UIAxesY.XLim    = [ind_frames(1) ind_frames(end)];

            % plot another image
            cla(app.UIAxesTraj2Video)
            vidpos = app.UIAxesTraj2Video.Position;

            vidpos(2) = 550;
            width = vidpos(3);
            newheight = width*size(this_frame, 1)/size(this_frame, 2);
            vidpos(4) = newheight;

            set(app.UIAxesTraj2Video,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 2)], 'ylim', [0 size(this_frame, 1)], 'ydir','reverse')

            app.UIAxesTraj2Video.UserData{1} = image(app.UIAxesTraj2Video, this_frame);

            % plot all
            x_plot = x_poshat(~isnan(x_poshat));
            y_plot = y_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));
            marker_size = 20;

            app.UIAxesTraj2Video.UserData{2} = scatter(app.UIAxesTraj2Video, ...
                x_plot, y_plot, marker_size, c_plot, 'filled');

            app.UIAxesTraj2Video.UserData{3} =   plot(app.UIAxesTraj2Video, ...
                x_poshat(ind_this), y_poshat(ind_this), ...
                'm.', 'markersize', 15, 'linewidth', 1, 'linestyle', '-');

            app.UIAxesTraj2Video.XLim = [0 size(this_frame, 2)];
            app.UIAxesTraj2Video.YLim = [0 size(this_frame, 1)];
            app.UIAxesTraj2Video.NextPlot = 'Add';

            % update trigger pos plot
            cla(app.UIAxesTriggerPos)
            vidpos = app.UIAxesTriggerPos.Position;

            vidpos(2) = 350;
            width = vidpos(3);
            newheight = width*size(this_frame, 1)/size(this_frame, 2);
            vidpos(4) = newheight;

            set(app.UIAxesTriggerPos,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 2)], 'ylim', [0 size(this_frame, 1)], 'ydir','reverse')

            % add data to UIAxesTriggerPos
            app.UIAxesTriggerPos.XLim =  [0 size(this_frame, 2)];
            app.UIAxesTriggerPos.YLim =  [0 size(this_frame, 1)];
            app.UIAxesTriggerPos.YDir  =  'reverse';

            app.UIAxesTriggerPos.UserData.Plot = plot(app.UIAxesTriggerPos,  x_pos_Trigger, y_pos_Trigger,'marker', 'o', 'color', 'w', 'LineWidth', 1, 'markerfacecolor', [0.2 0.5 1], 'linestyle', 'none');

            % mark 'Tracked'
            this_table = app.UITable.UserData{1};
            this_table.Tracked(this_table.TrialNums == app.UITable.Data.TrialNums) = 1;
            app.UITable.UserData{1} = this_table; % save for further usage

            % check speed
            if ~isempty(app.PlotfeaturesButton.UserData);
                kinematics_feature = app.PlotfeaturesButton.UserData;
                IndThisTrial = kinematics_feature.Trials == trial_num;
                if isempty(app.UIAxesSpeedxy.UserData{2}) || isempty(find(strcmp(arrayfun(@(x)x. Type, app.UIAxesSpeedxy.Children, 'UniformOutput', false), 'line')))
                    app.UIAxesSpeedxy.UserData{2} = plot(app.UIAxesSpeedxy, kinematics_feature.TrajXMedian(IndThisTrial), ...
                        kinematics_feature.TrajYMedian(IndThisTrial),...
                        'color', 'g', 'linewidth', 2, 'marker','o');
                else
                    app.UIAxesSpeedxy.UserData{2}.XData = kinematics_feature.TrajXMedian(IndThisTrial);
                    app.UIAxesSpeedxy.UserData{2}.YData = kinematics_feature.TrajYMedian(IndThisTrial);
                end;
                uistack(app.UIAxesSpeedxy.UserData{2},'top');
            end;

            app.UITable.UserData{4} = trial_num;
            PlotfeaturesButtonPushed(app,  matlab.ui.eventdata.ButtonPushedData)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)

            value = app.Slider.Value;
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            app.FrameEditField.Value =  num2str(ind_this);

            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            tic
            app.UIAxes.UserData{3}.CData = this_frame;
            toc

            x_poshat = app.UIAxes.UserData{4}{5}*app.UIAxes.UserData{4}{2}; % scaled x
            y_poshat = app.UIAxes.UserData{4}{6}*app.UIAxes.UserData{4}{2}; % scaled y

            % update main

            if ~isempty(app.UIAxes.UserData{2})
                set(app.UIAxes.UserData{2}, 'XData', x_poshat(ind_this),'YData', y_poshat(ind_this));
            else
                app.UIAxes.UserData{2} = plot(app.UIAxes, x_poshat(ind_this), y_poshat(ind_this), ...
                    'm.', 'markersize', 10, 'linewidth', 1); % a single plot
            end;

            set(app.UIAxesX.UserData{2}, 'XData', ind_this, 'YData', x_poshat(ind_this));
            set(app.UIAxesY.UserData{2}, 'XData', ind_this, 'YData', y_poshat(ind_this));
%             set(app.UIAxesTraj1.UserData(1), 'XData', x_poshat(ind_this), 'YData', y_poshat(ind_this));
            set(app.UIAxesX.UserData{2}, 'XData',(ind_this), 'YData', x_poshat(ind_this));
            set(app.UIAxesY.UserData{2}, 'XData', (ind_this), 'YData', y_poshat(ind_this));
            set(app.UIAxesTraj2Video.UserData{3}, 'XData', x_poshat(ind_this), 'YData',  y_poshat(ind_this));

            % update image in UIAXexTraj2Video
            app.UIAxesTraj2Video.UserData{1}.CData = this_frame;

        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)

            verticalScrollCount = event.VerticalScrollCount;

            value = app.Slider.Value;
            newvalue = value + verticalScrollCount;
            newvalue = max([1 newvalue]);
            app.Slider.Value = newvalue;
            value = newvalue;

            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            app.FrameEditField.Value =  num2str(ind_this);

            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            tic
            app.UIAxes.UserData{3}.CData = this_frame;
            toc

            x_poshat = app.UIAxes.UserData{4}{5}*app.UIAxes.UserData{4}{2}; % scaled x
            y_poshat = app.UIAxes.UserData{4}{6}*app.UIAxes.UserData{4}{2}; % scaled y

            % update main

            if ~isempty(app.UIAxes.UserData{2})
                set(app.UIAxes.UserData{2}, 'XData', x_poshat(ind_this),'YData', y_poshat(ind_this));
            else
                app.UIAxes.UserData{2} = plot(app.UIAxes, x_poshat(ind_this), y_poshat(ind_this), ...
                    'm.', 'markersize', 10, 'linewidth', 1); % a single plot
            end;

            set(app.UIAxesX.UserData{2}, 'XData', ind_this, 'YData', x_poshat(ind_this));
            set(app.UIAxesY.UserData{2}, 'XData', ind_this, 'YData', y_poshat(ind_this));

            set(app.UIAxesX.UserData{2}, 'XData',(ind_this), 'YData', x_poshat(ind_this));
            set(app.UIAxesY.UserData{2}, 'XData', (ind_this), 'YData', y_poshat(ind_this));
            set(app.UIAxesTraj2Video.UserData{3}, 'XData', x_poshat(ind_this), 'YData',  y_poshat(ind_this));


        end

        % Callback function
        function SelectrangeButtonPushed(app, event)
            % select the rannge
            rect = getrect(app.UIAxes2);

            posdata = app.BodypartsDropDown.UserData;

            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';
            ind_chosen = find(frs>=rect(1) & frs<=sum(rect([1 3])) & lh>=rect(2) & lh <= sum(rect([2 4])));

            if isempty(app.SelectrangeButton.UserData)
                app.SelectrangeButton.UserData{1} = ind_chosen;
                app.SelectrangeButton.UserData{2} = plot(app.UIAxes2, frs(ind_chosen), lh(ind_chosen), 'g.', 'markersize', 5);
%                 app.UIAxesTraj1.UserData{2} = plot(app.UIAxesTraj1, x_pos(ind_chosen), y_pos(ind_chosen), 'go', 'markersize', 4);
            else
                app.SelectrangeButton.UserData{1} = ind_chosen;
                set(app.SelectrangeButton.UserData{2}, 'XData', frs(ind_chosen), 'YData', lh(ind_chosen));
%                 set(app.UIAxesTraj1.UserData{2}, 'XData',  x_pos(ind_chosen), 'YData', y_pos(ind_chosen))
            end;

            %             %% plot trajectory
            %
            %             t_chosen = 1:length(ind_chosen);
            %             x_chosen = x_pos(ind_chosen);
            %             y_chosen = y_pos(ind_chosen);
            %
            %             tnew = resample(t_chosen, 10, 1);
            %             x_chosennew = interp1(t_chosen, x_chosen, tnew);
            %             y_chosennew = interp1(t_chosen, y_chosen, tnew);
            %             app.UIAxesTraj1.UserData{3} = plot(app.UIAxesTraj1, x_chosennew, y_chosennew, 'color', [1 0.8 0.2]);

        end

        % Callback function
        function UndoButtonPushed(app, event)

            posdata = app.BodypartsDropDown.UserData;

            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';

            ind_chosen = [];

            app.SelectrangeButton.UserData{1} = ind_chosen;
            set(app.SelectrangeButton.UserData{2}, 'XData', [], 'YData', []);
%             set(app.UIAxesTraj1.UserData{2}, 'XData',  [], 'YData', [])


        end

        % Button pushed function: LabelbadeventsButton
        function LabelbadeventsButtonPushed(app, event)
            % By clicking this, an event will be marked as bad labeling and
            % won't be counted into futher analysis. 
            % Clicking twice will undo the labeling

            this_table = app.UITable.UserData{1};
            app.UITable.Data.isGood = abs(1-app.UITable.Data.isGood);
            this_table.isGood(this_table.TrialNums == app.UITable.Data.TrialNums) = app.UITable.Data.isGood;
            app.UITable.UserData{1} = this_table; % save for further usage
  
        end

        % Callback function
        function FillStartButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.StartEditField.Value = num2str(ind_this);
        end

        % Callback function
        function FillEndButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.EndEditField.Value = num2str(ind_this);
        end

        % Callback function
        function PlottrajectoryButtonPushed(app, event)

            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);

            x_pos = posdata(FrameBeg:FrameEnd, 1);
            y_pos = posdata(FrameBeg:FrameEnd, 2);

            load(app.LoadvideoButton.UserData.metaname);
            t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            t_frames_selected = t_frames(FrameBeg:FrameEnd);
            this_frame = app.UIAxes.UserData{1}(:, :, :, FrameEnd);
            %
            cla(app.UIAxesTraj2Video)

            app.UIAxesTraj2Video.NextPlot = 'add';

            % plot the image first

            app.UIAxesTraj2Video.UserData{1} = image(app.UIAxesTraj2Video, this_frame);
            app.UIAxesTraj2Video.XLim = [0 800];
            app.UIAxesTraj2Video.YLim = [0 800];
            app.UIAxesTraj2Video.YDir     = 'reverse';

            app.UIAxesTraj2Video.UserData{2} = plot(app.UIAxesTraj2Video, x_pos, y_pos, '.', 'color', 'g', 'linewidth', 1, 'markersize', 6, 'markerfacecolor', 'g');
            app.UIAxesTraj2Video.UserData{3} = plot(app.UIAxesTraj2Video, x_pos, y_pos, '-', 'linewidth', 2, 'color', [1 0.8 0]);

            %             cla(app.UIAxesX)
            app.UIAxesX.UserData{3} =  plot(app.UIAxesX, t_frames_selected, x_pos,'*', 'color', 'g', 'linewidth', 1, 'markersize', 4, 'markerfacecolor', 'g');
            app.UIAxesX.UserData{4} =  plot(app.UIAxesX, t_frames_selected, x_pos,  "Color", [1 0.8 0], 'linewidth', 2);
            app.UIAxesX.XLabel.String = 'Time from press (ms)';
            app.UIAxesX.YLabel.String = 'X position';
            app.UIAxesX.XLim    = [t_frames_selected(1) t_frames_selected(end)];
            app.UIAxesX.YDir     = 'reverse';

            %             cla(app.UIAxesY)
            app.UIAxesY.UserData{3} =  plot(app.UIAxesY, t_frames_selected, y_pos,'*', 'color', 'g', 'linewidth', 1,  'markersize', 4, 'markerfacecolor', 'g');
            app.UIAxesY.UserData{4} =  plot(app.UIAxesY, t_frames_selected, y_pos, "Color", [1 0.8 0], 'linewidth', 2);
            app.UIAxesY.XLabel.String = 'Time from press (ms)';
            app.UIAxesY.YLabel.String = 'Y position';
            app.UIAxesY.YDir     = 'reverse';
            app.UIAxesY.XLim    = [t_frames_selected(1) t_frames_selected(end)];


            chH = get(app.UIAxesX,'Children')
            set(app.UIAxesX,'Children',[chH(end-1); setdiff(chH, chH(end-1))])

            chH = get(app.UIAxesY,'Children')
            set(app.UIAxesY,'Children',[chH(end-1); setdiff(chH, chH(end-1))])

        end

        % Callback function
        function SavedatatomatButtonPushed(app, event)
            %             Session: '2021-02-18-14h19m-Subject Lamar'
            %             Event: 'Press'
            %             EventIndex: 129
            %             Performance: 'Correct'
            %             EventTime: 1787750
            %             FrameTimesB: [1×249 double]
            %             VideoOrg: 'Cam_00D41932971+2021_2_18_14_20_34.5797602++.avi'
            %             FrameIndx: [1×249 double]
            %             Code: 'G:\ZqWork\Code\YUcode\Functions\ExportVideoFiles'
            %             CreatedOn: '01-Jul-2021'
            %             DLCTrackingOut = [];

            meta_filename =   app.LoadvideoButton.UserData.metaname;
            body_part_name = app.BodypartsDropDown.Value;
            load(meta_filename);  % this will launch VidMeta

            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);

            IndxSelected = round(linspace(FrameBeg, FrameEnd, 5));
            %             this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);

            if  ~isempty(dir('DLCTrackingOut.mat'))

                load('DLCTrackingOut.mat')

                % load DLCTracingOut
                % find index of body parts
                ind_body =find(strcmp(DLCTrackingOut.BodyParts, body_part_name));

                % new index
                if isempty(find(DLCTrackingOut.PoseTracking(ind_body).EventIndex(1, :)==VidMeta.EventIndex))

                    DLCTrackingOut.PoseTracking(ind_body).PosData{end+1}        = posdata(FrameBeg:FrameEnd, :);
                    DLCTrackingOut.PoseTracking(ind_body).EventIndex(:, end+1)  = [VidMeta.EventIndex; VidMeta.EventTime];
                    DLCTrackingOut.PoseTracking(ind_body).Performance{end+1}  =  VidMeta.Performance;
                    DLCTrackingOut.PoseTracking(ind_body).Images{end+1} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                    DLCTrackingOut.PoseTracking(ind_body).ImagesIndex{end+1} = IndxSelected-FrameBeg+1;

                else
                    ind_old = find(DLCTrackingOut.PoseTracking(ind_body).EventIndex(1, :)==VidMeta.EventIndex);
                    DLCTrackingOut.PoseTracking(ind_body).PosData{ind_old}        = posdata(FrameBeg:FrameEnd, :);
                    DLCTrackingOut.PoseTracking(ind_body).EventIndex(:, ind_old)  = [VidMeta.EventIndex; VidMeta.EventTime];
                    DLCTrackingOut.PoseTracking(ind_body).Performance{ind_old}  =  VidMeta.Performance;
                    DLCTrackingOut.PoseTracking(ind_body).Images{ind_old} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                    DLCTrackingOut.PoseTracking(ind_body).ImagesIndex{ind_old} = IndxSelected-FrameBeg+1;
                end;

                save DLCTrackingOut DLCTrackingOut

                %% Trials tracked
                app.TrialTrackedLabel.Text = sprintf('tracked: %2.0d', size(DLCTrackingOut.PoseTracking(ind_body).EventIndex, 2));

            else

                DLCTrackingOut = [];
                DLCTrackingOut.Session = VidMeta.Session;
                DLCTrackingOut.Event = VidMeta.Event;
                DLCTrackingOut.BodyParts{1} = body_part_name;
                ind_body = 1;
                DLCTrackingOut.PoseTracking(1).PosInfo = {'x', 'y', 'time(ms)', 'likelihood'};

                DLCTrackingOut.PoseTracking(1).PosData{1} = posdata(FrameBeg:FrameEnd, :);
                DLCTrackingOut.PoseTracking(1).EventIndex(:, 1) = [VidMeta.EventIndex; VidMeta.EventTime];
                DLCTrackingOut.PoseTracking(1).Performance{1} = VidMeta.Performance;
                DLCTrackingOut.PoseTracking(1).Images{1} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                DLCTrackingOut.PoseTracking(1).ImagesIndex{1} = IndxSelected-FrameBeg+1;
                save DLCTrackingOut DLCTrackingOut

                %% Trials tracked
                app.TrialTrackedLabel.Text = sprintf('tracked: %2.0d', size(DLCTrackingOut.PoseTracking(1).EventIndex, 2));
            end;

            app.SavedatatomatButton.Text = {'Saved'};
            app.UIAxes7.XLim = [200 900];
            app.UIAxes7.YLim = [200 800];
            app.UIAxes7.NextPlot = 'Add';
            app.UIAxes7.YDir     = 'reverse';
            delete(get(app.UIAxes7, 'children'));

            % plot an image

            image(app.UIAxes7, DLCTrackingOut.PoseTracking(ind_body).Images{end}(:, :, :, 3));

            % plot all tracked trials
            for i = 1:length(DLCTrackingOut.PoseTracking.PosData)
                tframes = DLCTrackingOut.PoseTracking.PosData{i}(:, 3);
                indframes = find(tframes<=500);
                plot(app.UIAxes7, DLCTrackingOut.PoseTracking.PosData{i}(indframes, 1), DLCTrackingOut.PoseTracking.PosData{i}(indframes, 2), 'color', [0.8 0.8 0.8]);
            end
            plot(app.UIAxes7, DLCTrackingOut.PoseTracking.PosData{end}(indframes, 1), DLCTrackingOut.PoseTracking.PosData{end}(indframes, 2), 'color', [1 0.8 0], 'linewidth', 2);


        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)

            % video directory
            div = strfind(app.LoadvideoButton.UserData.vfilename, '\');
            vpath = app.LoadvideoButton.UserData.vfilename(1:div(end));
            AllFiles = dir(fullfile(vpath, '*Trial_*.avi'));
            AllVidFiles=arrayfun(@(x)fullfile(x.folder, x.name), AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles, app.LoadvideoButton.UserData.vfilename));
            if current_indx == length(AllVidFiles)
                clc
                disp('No more videos')
                return
            end
            next_indx = current_indx + 1;
            vfilename = AllVidFiles{next_indx};
            meta_filename = [extractBefore(vfilename(div(end)+1:end), '.avi') '.mat'];

            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;

            session = cellstr(extractBetween(vpath, 'Sessions\', '\DLCVideoClips'));
            session = session{1};
            app.VidnameEditField.Value = [session, '\' extractAfter(vfilename, vpath)];
            app.MetafileEditField.Value = meta_filename;

            % load meta data
            load(fullfile(vpath, app.LoadvideoButton.UserData.metaname));

            % Update table
            this_table = app.UITable.UserData{1};
            trial_num =  extractBetween(vfilename, 'Trial_', '.avi');
            trial_num = str2num(trial_num{1});
            app.UITable.Data = this_table(this_table.TrialNums==trial_num, :);

            % read basic information
            t_frames         =        1000*(VidMeta.FrameTimes - VidMeta.FrameTimes(1));
            ind_frames     =        [1:length(VidMeta.Xpos)];
            OrgSize          =          VidMeta.FrameSize;
            x_pos             =          VidMeta.Xpos;
            y_pos             =          VidMeta.Ypos;

            n_goodframes = length(find(~isnan(x_pos)));
            n_allframes = length(x_pos);
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);

            this_video = vfilename;
            vidObj = VideoReader(this_video);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames; % all frames
            toc

            ScalingFactor = size(AllFrames, 1)/OrgSize(2);
            x_poshat = x_pos*ScalingFactor;
            y_poshat = y_pos*ScalingFactor;

            % find out trigger  frame
            beh_table =  app.UITable.UserData{3};
            EventFrameTime = beh_table.FrameTimeAtDLCTime(beh_table.TrialsBpod == trial_num);

            % find the frame index corresponding to this event:
            % IndDLC is the frame index for the trigger event (ie., DLC)
            [~, IndDLC] = min(abs(VidMeta.FrameTimes - EventFrameTime));
            x_pos_Trigger       =         x_pos(IndDLC)*ScalingFactor;
            y_pos_Trigger       =         y_pos(IndDLC)*ScalingFactor;

            ind_this = IndDLC;
            this_frame = AllFrames(:, :, :, ind_this);

            app.UIAxes.UserData{4} = {size(this_frame), ScalingFactor, OrgSize, t_frames, x_pos, y_pos};
            app.FrameEditField.Value =  num2str(ind_this);
            
            app.UIAxes.UserData{3}.CData = this_frame; % a single image

            % if there is a DLC label, plot it
            app.UIAxes.UserData{2}.XData =  x_poshat(ind_this);
            app.UIAxes.UserData{2}.YData =  y_poshat(ind_this);
 
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;

            % plot pose  x vs t
            x_plot = ind_frames(~isnan(x_poshat));
            y_plot = x_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));

            app.UIAxesX.UserData{1}.XData =  x_plot;
            app.UIAxesX.UserData{1}.YData  =  y_plot;
            app.UIAxesX.UserData{1}.CData =   c_plot';

            app.UIAxesX.UserData{2}.XData =  ind_frames(ind_this);
            app.UIAxesX.UserData{2}.YData =  x_poshat(ind_this);

            app.UIAxesX.UserData{3}.XData = IndDLC;
            app.UIAxesX.UserData{3}.YData =  x_poshat(IndDLC);

            % plot pose  y vs t
            y_plot = y_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));

            app.UIAxesY.UserData{1}.XData =  x_plot;
            app.UIAxesY.UserData{1}.YData  =  y_plot;
            app.UIAxesY.UserData{1}.CData =   c_plot';

            app.UIAxesY.UserData{2}.XData = ind_frames(ind_this);
            app.UIAxesY.UserData{2}.YData = y_poshat(ind_this);

            app.UIAxesY.UserData{3}.XData = IndDLC;
            app.UIAxesY.UserData{3}.YData =  y_poshat(IndDLC);

            % plot another image
            app.UIAxesTraj2Video.UserData{1}.CData = this_frame;

            x_plot = x_poshat(~isnan(x_poshat));
            y_plot = y_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));
     
            app.UIAxesTraj2Video.UserData{2}.XData =  x_plot;
            app.UIAxesTraj2Video.UserData{2}.YData  =  y_plot;
            app.UIAxesTraj2Video.UserData{2}.CData =   c_plot';

            app.UIAxesTraj2Video.UserData{3}.XData =  x_poshat(ind_this);
            app.UIAxesTraj2Video.UserData{3}.YData =  y_poshat(ind_this);

            if ~isfield(app.UIAxesTriggerPos.UserData, 'Plot')
                app.UIAxesTriggerPos.UserData.Plot = plot(app.UIAxesTriggerPos,  x_pos_Trigger, y_pos_Trigger,'marker', 'o', 'color', 'w', 'LineWidth', 1, 'markerfacecolor', [0.2 0.5 1], 'linestyle', 'none');
            else
                app.UIAxesTriggerPos.UserData.Plot.XData = [app.UIAxesTriggerPos.UserData.Plot.XData x_pos_Trigger];
                app.UIAxesTriggerPos.UserData.Plot.YData = [app.UIAxesTriggerPos.UserData.Plot.YData y_pos_Trigger];
            end;

            % mark 'Tracked'
            this_table = app.UITable.UserData{1};
            this_table.Tracked(this_table.TrialNums == app.UITable.Data.TrialNums) = 1;
            app.UITable.UserData{1} = this_table; % save for further usage


            % check speed
            if ~isempty(app.PlotfeaturesButton.UserData);
                kinematics_feature = app.PlotfeaturesButton.UserData;
                IndThisTrial = kinematics_feature.Trials == trial_num;
                if isempty(app.UIAxesSpeedxy.UserData{2}) || isempty(find(strcmp(arrayfun(@(x)x. Type, app.UIAxesSpeedxy.Children, 'UniformOutput', false), 'line')))
                    app.UIAxesSpeedxy.UserData{2} = plot(app.UIAxesSpeedxy, kinematics_feature.TrajXMedian(IndThisTrial), ...
                        kinematics_feature.TrajYMedian(IndThisTrial),...
                        'color', 'g', 'linewidth', 2, 'marker','o');
                else
                    app.UIAxesSpeedxy.UserData{2}.XData = kinematics_feature.TrajXMedian(IndThisTrial);
                    app.UIAxesSpeedxy.UserData{2}.YData = kinematics_feature.TrajYMedian(IndThisTrial);
                end;
                uistack(app.UIAxesSpeedxy.UserData{2},'top');
            end;

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            dv=0;
            switch key
                case 'rightarrow'
                    clc
                    disp('Loading next video')
                    NextvideoButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
                case 'leftarrow'
                    disp('Loading last video')
                    LastvideoButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
                case 'd'
                    disp('next frame')
                    dv =1;
                case 'a'
                    disp('last frame')
                    dv =-1;
                case 'x'
                    LabelbadeventsButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            end;

            if dv~=0
                value = app.Slider.Value;
                newvalue = value + dv;
                newvalue = max([1 newvalue]);
                if newvalue > app.Slider.Limits(2) || newvalue<1
                    disp('Out of range')
                    return
                end;
                app.Slider.Value = newvalue;
                value = newvalue;

                clc
                ind_this = round(value);
                tic
                sprintf('Plot new frame')

                app.FrameEditField.Value =  num2str(ind_this);

                this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
                tic
                app.UIAxes.UserData{3}.CData = this_frame;
                toc

                x_poshat = app.UIAxes.UserData{4}{5}*app.UIAxes.UserData{4}{2}; % scaled x
                y_poshat = app.UIAxes.UserData{4}{6}*app.UIAxes.UserData{4}{2}; % scaled y

                % update main

                if ~isempty(app.UIAxes.UserData{2})
                    set(app.UIAxes.UserData{2}, 'XData', x_poshat(ind_this),'YData', y_poshat(ind_this));
                else
                    app.UIAxes.UserData{2} = plot(app.UIAxes, x_poshat(ind_this), y_poshat(ind_this), ...
                        'm.', 'markersize', 10, 'linewidth', 1); % a single plot
                end;

                set(app.UIAxesX.UserData{2}, 'XData', ind_this, 'YData', x_poshat(ind_this));
                set(app.UIAxesY.UserData{2}, 'XData', ind_this, 'YData', y_poshat(ind_this));

                set(app.UIAxesX.UserData{2}, 'XData',(ind_this), 'YData', x_poshat(ind_this));
                set(app.UIAxesY.UserData{2}, 'XData', (ind_this), 'YData', y_poshat(ind_this));
                set(app.UIAxesTraj2Video.UserData{3}, 'XData', x_poshat(ind_this), 'YData',  y_poshat(ind_this));
            end;

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'control'
                    % change the location of body part
                    value = app.Slider.Value;
                    ind_this = round(value);

                    % draw point on UIAxes
                    h = drawpoint(app.UIAxes,  'LabelVisible', 'off'); % user-defined body location


                    posdata = app.BodypartsDropDown.UserData;

                    x_pos   =   posdata(:, 1);
                    y_pos   =   posdata(:, 2);
                    lh      =   posdata(:, 3);
                    t_frames =  posdata(:, 4);
                    x_pos(ind_this) = h.Position(1);
                    y_pos(ind_this) = h.Position(2);
                    lh(ind_this)    = 1;

                    posdata = [x_pos y_pos lh t_frames];

                    app.BodypartsDropDown.UserData = posdata;

                    if lh(ind_this) < 0.8
                        set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                        set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
                    else
                        set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                        set(app.UIAxes2.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
                    end;

%                     set(app.UIAxesTraj1.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 12, 'linewidth', 2)
                    set(app.UIAxesX.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
                    set(app.UIAxesY.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))

                    % update
                    set(app.UIAxes2.UserData{1}, 'XData', [1:length(lh)], 'YData', lh);

                    set(app.UIAxesX.UserData{1}, 'XData', t_frames, 'YData', x_pos)
                    set(app.UIAxesY.UserData{1}, 'XData', t_frames, 'YData', y_pos)


                    FrameBeg = str2num(app.StartEditField.Value);
                    FrameEnd = str2num(app.EndEditField.Value);

                    x_pos_selected = x_pos(FrameBeg:FrameEnd);
                    y_pos_selected = y_pos(FrameBeg:FrameEnd);
                    t_frames_selected = t_frames(FrameBeg:FrameEnd);

                    %             cla(app.UIAxesX)

                    if length(app.UIAxesX.UserData)>2
                        set(app.UIAxesX.UserData{3}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                        set(app.UIAxesX.UserData{4}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                        set(app.UIAxesY.UserData{3}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                        set(app.UIAxesY.UserData{4}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                    end;

                    AllChildren = get(app.UIAxes, 'Children');
                    delete(AllChildren(1));
            end
        end

        % Callback function
        function BodypartsDropDownOpening(app, event)
            % read the table to see the body parts tracked.


        end

        % Value changed function: VidnameEditField
        function VidnameEditFieldValueChanged(app, event)
            value = app.VidnameEditField.Value;

        end

        % Button pushed function: LoadOptoDLCButton
        function LoadOptoDLCButtonPushed(app, event)
            % load the table including all video clips

            [tablefilename, tablepath] = uigetfile('*.csv', 'Select table file');
            this_table = readtable(fullfile(tablepath, tablefilename));
            % add new a new column
            thisCol = 'isGood';
            if isempty(find(strcmp(thisCol, this_table.Properties.VariableNames))) % there is no 'isGood'
                this_table.isGood =  ones(size(this_table, 1), 1);
            end;

            % tracking progress
            thisCol = 'Tracked';
            if isempty(find(strcmp(thisCol, this_table.Properties.VariableNames))) % there is no 'isGood'
                this_table.Tracked =  zeros(size(this_table, 1), 1);
            end;

            app.UITable.ColumnName        =        this_table.Properties.VariableNames;
            this_table.Properties.VariableNames;
            app.UITable.Data                        =           this_table(1, :);
            app.UITable.FontSize                =             12;
            app.UITable.ColumnWidth{4} =            90;
             app.UITable.OuterPosition(3) = 390;
            app.UITable.OuterPosition(4) = 80;
          
            app.UITable.UserData{1} = this_table;
            app.UITable.UserData{2} = fullfile(tablepath, tablefilename);

            % also, load and add the behavioral table
            find_behavior_table = dir(fullfile(tablepath, 'BpodMEDTable*.csv'));
            beh_table = readtable(fullfile(tablepath, find_behavior_table.name));            
            app.UITable.UserData{3} = beh_table;

            % read session name
            this_name = extractBetween(find_behavior_table.name, 'BpodMEDTable_', '.csv');
            this_name = this_name{1};
            app.SessionEditField.Value = this_name;

        end

        % Button pushed function: SavetableButton
        function SavetableButtonPushed(app, event)

            this_table = app.UITable.UserData{1};
            aGoodName       =   app.UITable.UserData{2};
            % if this is not the updated table, save it as the updated
            % table
            if ~contains(aGoodName, 'Updated')
                aBetterName     =     insertBefore(aGoodName,'.csv','Updated');
            else
                aBetterName = aGoodName;
            end;
            tic
            writetable(this_table, aBetterName);
            toc

            % save speed feature table
            KinematicsData      =               app.PlotfeaturesButton.UserData;
            isGood                      =               (this_table.('isGood'));

            % Create a table
            ANMSessionName = split(aGoodName, '\');
            % if there is 'UPDATED', get rid of it then
            filepath = aGoodName(1:max(strfind(aGoodName, '\')));

            ANMSessionName = ANMSessionName{end};

            if contains(ANMSessionName, 'Updated')
                ANMSessionName = erase(ANMSessionName, 'Updated');
            end;

            SpeedName = extractAfter(extractBefore(ANMSessionName, '.csv'),  'DLCVideo_');

            TrialNums = KinematicsData.Trials;
            TrajX = KinematicsData.TrajX;
            TrajY = KinematicsData.TrajY;
            isGood = isGood;
            TrajXMed = nanmedian(TrajX, 2);
            TrajYMed = nanmedian(TrajY, 2);

            SpeedTable                     =     table(TrialNums, TrajX, TrajY, TrajXMed, TrajYMed, isGood);
            anotherGoodName       =     fullfile(filepath, ['TrajDLCTable_' SpeedName '.csv']);
            writetable(SpeedTable, anotherGoodName)

        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            
        end

        % Button pushed function: LastvideoButton
        function LastvideoButtonPushed(app, event)
            
            % video directory
            div = strfind(app.LoadvideoButton.UserData.vfilename, '\');
            vpath = app.LoadvideoButton.UserData.vfilename(1:div(end));
            AllFiles = dir(fullfile(vpath, '*Trial_*.avi'));
            AllVidFiles=arrayfun(@(x)fullfile(x.folder, x.name), AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles, app.LoadvideoButton.UserData.vfilename));
            if current_indx == length(AllVidFiles)
                return
            end
            next_indx = current_indx - 1;
            vfilename = AllVidFiles{next_indx};
            meta_filename = [extractBefore(vfilename(div(end)+1:end), '.avi') '.mat'];

            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;

            session = cellstr(extractBetween(vpath, 'Sessions\', '\DLCVideoClips'));
            session = session{1};
            app.VidnameEditField.Value = [session, '\' extractAfter(vfilename, vpath)];
            
            app.MetafileEditField.Value = meta_filename;

            % load meta data
            load(fullfile(vpath, app.LoadvideoButton.UserData.metaname));

            % Update table
            this_table = app.UITable.UserData{1};
            trial_num =  extractBetween(vfilename, 'Trial_', '.avi');
            trial_num = str2num(trial_num{1});
            app.UITable.Data = this_table(this_table.TrialNums==trial_num, :);

            % read basic information
            t_frames         =        1000*(VidMeta.FrameTimes - VidMeta.FrameTimes(1));
            ind_frames     =        [1:length(VidMeta.Xpos)];
            OrgSize          =          VidMeta.FrameSize;
            x_pos             =          VidMeta.Xpos;
            y_pos             =          VidMeta.Ypos;

            n_goodframes = length(find(~isnan(x_pos)));
            n_allframes = length(x_pos);
            sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);

            this_video = vfilename;
            vidObj = VideoReader(this_video);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames; % all frames
            toc

            ScalingFactor = size(AllFrames, 1)/OrgSize(2);
            x_poshat = x_pos*ScalingFactor;
            y_poshat = y_pos*ScalingFactor;

            % find out trigger  frame
            beh_table =  app.UITable.UserData{3};
            EventFrameTime = beh_table.FrameTimeAtDLCTime(beh_table.TrialsBpod == trial_num);

            % find the frame index corresponding to this event:
            % IndDLC is the frame index for the trigger event (ie., DLC)
            [~, IndDLC] = min(abs(VidMeta.FrameTimes - EventFrameTime));
            x_pos_Trigger       =         x_pos(IndDLC)*ScalingFactor;
            y_pos_Trigger       =         y_pos(IndDLC)*ScalingFactor;

            ind_this = IndDLC;
            this_frame = AllFrames(:, :, :, ind_this);

            app.UIAxes.UserData{4} = {size(this_frame), ScalingFactor, OrgSize, t_frames, x_pos, y_pos};
            app.FrameEditField.Value =  num2str(ind_this);
            
            app.UIAxes.UserData{3}.CData = this_frame; % a single image

            % if there is a DLC label, plot it
            app.UIAxes.UserData{2}.XData =  x_poshat(ind_this);
            app.UIAxes.UserData{2}.YData =  y_poshat(ind_this);
 
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;

            % plot pose  x vs t
            x_plot = ind_frames(~isnan(x_poshat));
            y_plot = x_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));

            app.UIAxesX.UserData{1}.XData =  x_plot;
            app.UIAxesX.UserData{1}.YData  =  y_plot;
            app.UIAxesX.UserData{1}.CData =   c_plot';

            app.UIAxesX.UserData{2}.XData =  ind_frames(ind_this);
            app.UIAxesX.UserData{2}.YData =  x_poshat(ind_this);

            app.UIAxesX.UserData{3}.XData = IndDLC;
            app.UIAxesX.UserData{3}.YData =  x_poshat(IndDLC);

            % plot pose  y vs t
            y_plot = y_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));

            app.UIAxesY.UserData{1}.XData =  x_plot;
            app.UIAxesY.UserData{1}.YData  =  y_plot;
            app.UIAxesY.UserData{1}.CData =   c_plot';

            app.UIAxesY.UserData{2}.XData = ind_frames(ind_this);
            app.UIAxesY.UserData{2}.YData = y_poshat(ind_this);

            app.UIAxesY.UserData{3}.XData = IndDLC;
            app.UIAxesY.UserData{3}.YData =  y_poshat(IndDLC);

            % plot another image
            app.UIAxesTraj2Video.UserData{1}.CData = this_frame;

            x_plot = x_poshat(~isnan(x_poshat));
            y_plot = y_poshat(~isnan(x_poshat));
            c_plot = linspace(1, 10, length(x_plot));
     
            app.UIAxesTraj2Video.UserData{2}.XData =  x_plot;
            app.UIAxesTraj2Video.UserData{2}.YData  =  y_plot;
            app.UIAxesTraj2Video.UserData{2}.CData =   c_plot';

            app.UIAxesTraj2Video.UserData{3}.XData =  x_poshat(ind_this);
            app.UIAxesTraj2Video.UserData{3}.YData =  y_poshat(ind_this);

            if ~isfield(app.UIAxesTriggerPos.UserData, 'Plot')
                app.UIAxesTriggerPos.UserData.Plot = plot(app.UIAxesTriggerPos,  x_pos_Trigger, y_pos_Trigger,'marker', 'o', 'color', 'w', 'LineWidth', 1, 'markerfacecolor', [0.2 0.5 1], 'linestyle', 'none');
            else
                app.UIAxesTriggerPos.UserData.Plot.XData = [app.UIAxesTriggerPos.UserData.Plot.XData x_pos_Trigger];
                app.UIAxesTriggerPos.UserData.Plot.YData = [app.UIAxesTriggerPos.UserData.Plot.YData y_pos_Trigger];
            end;

            % mark 'Tracked'
            this_table = app.UITable.UserData{1};
            this_table.Tracked(this_table.TrialNums == app.UITable.Data.TrialNums) = 1;
            app.UITable.UserData{1} = this_table; % save for further usage

            % check speed
            if ~isempty(app.PlotfeaturesButton.UserData);
                kinematics_feature = app.PlotfeaturesButton.UserData;
                IndThisTrial = kinematics_feature.Trials == trial_num;
                if isempty(app.UIAxesSpeedxy.UserData{2}) || isempty(find(strcmp(arrayfun(@(x)x. Type, app.UIAxesSpeedxy.Children, 'UniformOutput', false), 'line')))
                    app.UIAxesSpeedxy.UserData{2} = plot(app.UIAxesSpeedxy, kinematics_feature.TrajXMedian(IndThisTrial), ...
                        kinematics_feature.TrajYMedian(IndThisTrial),...
                        'color', 'g', 'linewidth', 2, 'marker','o');
                else
                    app.UIAxesSpeedxy.UserData{2}.XData = kinematics_feature.TrajXMedian(IndThisTrial);
                    app.UIAxesSpeedxy.UserData{2}.YData = kinematics_feature.TrajYMedian(IndThisTrial);
                end;
                uistack(app.UIAxesSpeedxy.UserData{2},'top');
            end;

        end

        % Button pushed function: PlotfeaturesButton
        function PlotfeaturesButtonPushed(app, event)
            % plot speed x vs y
            %
            % check vx and vy

            % video directory
            div = strfind(app.LoadvideoButton.UserData.vfilename, '\');
            vpath = app.LoadvideoButton.UserData.vfilename(1:div(end));

            AllFiles = dir(fullfile(vpath, '*Trial_*.avi'));
            AllVidFiles=arrayfun(@(x)fullfile(x.folder, x.name), AllFiles, 'UniformOutput', false);

            % Update table
            this_table = app.UITable.UserData{1};

            % find out trigger  frame
            beh_table =  app.UITable.UserData{3};
            % track these frames
            frame_segs = [1:7];

            TrajTrialNums   =       NaN*ones(length(AllVidFiles), 1);
            TrajX                   =       NaN*ones(length(AllVidFiles), length(frame_segs));
            TrajY                   =       NaN*ones(length(AllVidFiles), length(frame_segs));
            TrajXMedian     =       NaN*ones(length(AllVidFiles), 1);
            TrajYMedian      =      NaN*ones(length(AllVidFiles), 1);

            for i =1:length(AllVidFiles)
                vfilename = AllVidFiles{i};
                meta_filename = [extractBefore(vfilename(div(end)+1:end), '.avi') '.mat'];
                trial_num =  extractBetween(vfilename, 'Trial_', '.avi');
                trial_num = str2num(trial_num{1});
                TrajTrialNums(i) = trial_num;

                % load meta data
                load(fullfile(vpath, meta_filename));

                % read basic information
                t_frames         =        1000*(VidMeta.FrameTimes - VidMeta.FrameTimes(1));
                ind_frames     =        [1:length(VidMeta.Xpos)];
                OrgSize          =          VidMeta.FrameSize;
                x_pos             =          VidMeta.Xpos;
                y_pos             =          VidMeta.Ypos;

                % find the frame index corresponding to this event:
                % IndDLC is the frame index for the trigger event (ie., DLC)
                EventFrameTime = beh_table.FrameTimeAtDLCTime(beh_table.TrialsBpod == trial_num);
                [~, IndDLC] = min(abs(VidMeta.FrameTimes - EventFrameTime));

                % track relative position in N successive frames. 
                RelPosXi = NaN*ones(1, length(frame_segs));
                RelPosYi = NaN*ones(1, length(frame_segs));

                x_pos_org       =       x_pos(IndDLC);
                y_pos_org       =       y_pos(IndDLC);
    
                for k = 1:length(frame_segs)                    
                           if ~isnan(x_pos(IndDLC+k))
                            RelPosXi(k) = x_pos(IndDLC+k) - x_pos_org;
                            RelPosYi(k) = y_pos(IndDLC+k) - y_pos_org;
                        end;
                end;

                TrajX(i, :)                             =       RelPosXi;
                TrajY(i, :)                             =       RelPosYi;
                TrajXMedian(i)                  =       nanmedian(RelPosXi);
                TrajYMedian(i)                  =       nanmedian(RelPosYi);

            end;
            toc

            % remove NaN
            nanmean(TrajXMedian) +5*nanstd(TrajXMedian);
            TrajXMedian(isnan(TrajXMedian)) = nanmean(TrajXMedian) +5*nanstd(TrajXMedian)+50*rand(1, length(find(isnan(TrajXMedian))));
            TrajYMedian(isnan(TrajYMedian)) = nanmean(TrajYMedian) +5*nanstd(TrajYMedian)+50*rand(1, length(find(isnan(TrajYMedian))));

            KinematicsFeatures.Trials                               =            TrajTrialNums;
            KinematicsFeatures.TrajX                                =            TrajX;
            KinematicsFeatures.TrajY                                =            TrajY;
            KinematicsFeatures.TrajXMedian                   =            TrajXMedian;
            KinematicsFeatures.TrajYMedian                   =            TrajYMedian;

            app.PlotfeaturesButton.UserData     =             KinematicsFeatures;

            % Plot position after DLC-trig
            cla(app.UIAxesSpeedxy)

            app.UIAxesSpeedxy.XGrid = 'On';
            app.UIAxesSpeedxy.YGrid = 'On';
            app.UIAxesSpeedxy.NextPlot = 'add';
            app.UIAxesSpeedxy.UserData = cell(1, 3);

            this_table = app.UITable.UserData{1};
            isGood                      =               (this_table.('isGood'));
            app.UIAxesSpeedxy.UserData{1} = scatter(app.UIAxesSpeedxy, TrajXMedian(isGood==1), TrajYMedian(isGood==1), 25, 'b', 'filled','markeredgecolor', 'w');
            if ~isempty(find(isGood==0))
                app.UIAxesSpeedxy.UserData{3} = scatter(app.UIAxesSpeedxy, TrajXMedian(isGood==0), TrajYMedian(isGood==0), 25, 'r', 'filled','markeredgecolor', 'w');
            end;

            app.UIAxesSpeedxy.Title.String                     = 'Paw position after trigger';
            app.UIAxesSpeedxy.XLabel.String                = 'Position X';
            app.UIAxesSpeedxy.YLabel.String                 = 'Position y';

            % this_trial
            trial_num = app.UITable.UserData{4};
            % check speed
            if ~isempty(app.PlotfeaturesButton.UserData);
                kinematics_feature = app.PlotfeaturesButton.UserData;
                IndThisTrial = kinematics_feature.Trials == trial_num;
                if isempty(app.UIAxesSpeedxy.UserData{2})|| isempty(find(strcmp(arrayfun(@(x)x. Type, app.UIAxesSpeedxy.Children, 'UniformOutput', false), 'line')))
                    app.UIAxesSpeedxy.UserData{2} = plot(app.UIAxesSpeedxy, kinematics_feature.TrajXMedian(IndThisTrial), ...
                        kinematics_feature.TrajYMedian(IndThisTrial),...
                        'color', 'g', 'linewidth', 2, 'marker','o');
                else
                    app.UIAxesSpeedxy.UserData{2}.XData = kinematics_feature.TrajXMedian(IndThisTrial);
                    app.UIAxesSpeedxy.UserData{2}.YData = kinematics_feature.TrajYMedian(IndThisTrial);
                end;
                uistack(app.UIAxesSpeedxy.UserData{2},'top');
            end;


        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)

            disp('next frame')
            dv =1;
            if dv~=0
                value = app.Slider.Value;
                newvalue = value + dv;
                newvalue = max([1 newvalue]);
                if newvalue > app.Slider.Limits(2) || newvalue<1
                    disp('Out of range')
                    return
                end;
                app.Slider.Value = newvalue;
                value = newvalue;

                clc
                ind_this = round(value);
                tic
                sprintf('Plot new frame')

                app.FrameEditField.Value =  num2str(ind_this);

                this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
                tic
                app.UIAxes.UserData{3}.CData = this_frame;
                toc

                x_poshat = app.UIAxes.UserData{4}{5}*app.UIAxes.UserData{4}{2}; % scaled x
                y_poshat = app.UIAxes.UserData{4}{6}*app.UIAxes.UserData{4}{2}; % scaled y

                % update main

                if ~isempty(app.UIAxes.UserData{2})
                    set(app.UIAxes.UserData{2}, 'XData', x_poshat(ind_this),'YData', y_poshat(ind_this));
                else
                    app.UIAxes.UserData{2} = plot(app.UIAxes, x_poshat(ind_this), y_poshat(ind_this), ...
                        'm.', 'markersize', 10, 'linewidth', 1); % a single plot
                end;

                set(app.UIAxesX.UserData{2}, 'XData', ind_this, 'YData', x_poshat(ind_this));
                set(app.UIAxesY.UserData{2}, 'XData', ind_this, 'YData', y_poshat(ind_this));

                set(app.UIAxesX.UserData{2}, 'XData',(ind_this), 'YData', x_poshat(ind_this));
                set(app.UIAxesY.UserData{2}, 'XData', (ind_this), 'YData', y_poshat(ind_this));
                set(app.UIAxesTraj2Video.UserData{3}, 'XData', x_poshat(ind_this), 'YData',  y_poshat(ind_this));
            end;

        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            
            disp('last frame')
            dv = -1;
            if dv~=0
                value = app.Slider.Value;
                newvalue = value + dv;
                newvalue = max([1 newvalue]);
                if newvalue > app.Slider.Limits(2) || newvalue<1
                    disp('Out of range')
                    return
                end;
                app.Slider.Value = newvalue;
                value = newvalue;

                clc
                ind_this = round(value);
                tic
                sprintf('Plot new frame')

                app.FrameEditField.Value =  num2str(ind_this);

                this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
                tic
                app.UIAxes.UserData{3}.CData = this_frame;
                toc

                x_poshat = app.UIAxes.UserData{4}{5}*app.UIAxes.UserData{4}{2}; % scaled x
                y_poshat = app.UIAxes.UserData{4}{6}*app.UIAxes.UserData{4}{2}; % scaled y

                % update main

                if ~isempty(app.UIAxes.UserData{2})
                    set(app.UIAxes.UserData{2}, 'XData', x_poshat(ind_this),'YData', y_poshat(ind_this));
                else
                    app.UIAxes.UserData{2} = plot(app.UIAxes, x_poshat(ind_this), y_poshat(ind_this), ...
                        'm.', 'markersize', 10, 'linewidth', 1); % a single plot
                end;

                set(app.UIAxesX.UserData{2}, 'XData', ind_this, 'YData', x_poshat(ind_this));
                set(app.UIAxesY.UserData{2}, 'XData', ind_this, 'YData', y_poshat(ind_this));

                set(app.UIAxesX.UserData{2}, 'XData',(ind_this), 'YData', x_poshat(ind_this));
                set(app.UIAxesY.UserData{2}, 'XData', (ind_this), 'YData', y_poshat(ind_this));
                set(app.UIAxesTraj2Video.UserData{3}, 'XData', x_poshat(ind_this), 'YData',  y_poshat(ind_this));
            end;
        end

        % Button down function: LeftPanel
        function LeftPanelButtonDown(app, event)
            
        end

        % Button pushed function: Classifier
        function ClassifierButtonPushed(app, event)
            % load a classifier trained with a machine learning tool, for
            % example, SVM. See trainDLCClassification.mlx for such an
            % example. The output of this script is a model: SVMModel.mat.
            % Load it and use it for prediction.
            [modelname, modelpath] = uigetfile('*.mat', 'Select a classification model');
            thisModel = load(fullfile(modelpath, modelname));
            app.Classifier.UserData{1} = thisModel;
            app.Classifier.UserData{2} = fullfile(modelpath, modelname);

            % now start to use it for classification
            if ~isempty(app.PlotfeaturesButton.UserData)
                TrajXMed =  app.PlotfeaturesButton.UserData.TrajXMedian;
                TrajYMed =  app.PlotfeaturesButton.UserData.TrajYMedian;
                % perform prediction
                mname=fieldnames(thisModel);
                mname = mname{1};
                [Y_predict, score] = predict(thisModel.(mname), [TrajXMed, TrajYMed]);

                % plot score in new figure
                figure(20); clf(20)
                set(20, 'units', 'centimeters', 'position', [5 5 10 10],'Visible','On');

                ha = axes('units', 'centimeters', 'position', [2 2 6 6], 'nextplot', 'add');
                % plot the probability
                hs = scatter(TrajXMed, TrajYMed, 25, score(:, 2), 'filled', 'markeredgecolor', [0.5 0.5 0.5]);
                hc = colorbar('units', 'centimeters', 'position', [8.2 2 0.25 5]);
                colormap(summer)

                % Update table
                this_table = app.UITable.UserData{1};
                this_table.isGood = Y_predict;
                app.UITable.UserData{1} = this_table; % save for further usage

                PlotfeaturesButtonPushed(app,  matlab.ui.eventdata.ButtonPushedData);
                % print out intermediate scores
                ind_intermed_scores = find(score(:, 2)>=0.2 & score(:, 2)<=0.99);
                if ~isempty(ind_intermed_scores)
                    clc
                    sprintf('Check this trial # %2.0f, score %2.5f \n', [this_table.TrialNums(ind_intermed_scores) score(ind_intermed_scores, 2)]')
                end;
            end;
 

        end

        % Button pushed function: WhattrialButton
        function WhattrialButtonPushed(app, event)
            % track the trial number of a few points

            roi = drawpolygon(app.UIAxesSpeedxy);
            this_table = app.UITable.UserData{1};
            datapts=app.PlotfeaturesButton.UserData;
            in = inpolygon(datapts.TrajXMedian, datapts.TrajYMedian, roi.Position(:, 1), roi.Position(:, 2));
            sprintf('Check this trial # %2.0f \n', [this_table.TrialNums(in)]')

            % delete the circle
            pause(3)
            delete(roi)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {856, 856, 856};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {856, 856};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x', 314};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.VidnameEditFieldLabel = uilabel(app.LeftPanel);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.VidnameEditField = uieditfield(app.LeftPanel, 'text');
            app.VidnameEditField.ValueChangedFcn = createCallbackFcn(app, @VidnameEditFieldValueChanged, true);
            app.VidnameEditField.Tag = 'vidfilename';
            app.MetafileEditFieldLabel = uilabel(app.LeftPanel);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.MetafileEditField = uieditfield(app.LeftPanel, 'text');
            app.FrameEditFieldLabel = uilabel(app.LeftPanel);
            app.FrameEditFieldLabel.Text = {'Frame'; ''};
            app.FrameEditField = uieditfield(app.LeftPanel, 'text');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tag = 'framenum';
            app.LabelbadeventsButton = uibutton(app.LeftPanel, 'push');
            app.LabelbadeventsButton.ButtonPushedFcn = createCallbackFcn(app, @LabelbadeventsButtonPushed, true);
            app.LabelbadeventsButton.Text = 'Label bad events';
            app.NextvideoButton = uibutton(app.LeftPanel, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'The main goal is to identify bad labeling events by going through each video clip [produced by MakeAVIDLCLive.]'; ''; 'Click "Remove" to mark bad clips (in the table). These events will be removed from further analysis. '};
            app.LoadOptoDLCButton = uibutton(app.LeftPanel, 'push');
            app.LoadOptoDLCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptoDLCButtonPushed, true);
            app.LoadOptoDLCButton.Text = 'Load OptoDLC';
            app.SavetableButton = uibutton(app.LeftPanel, 'push');
            app.SavetableButton.ButtonPushedFcn = createCallbackFcn(app, @SavetableButtonPushed, true);
            app.SavetableButton.Text = 'Save table';
            app.LastvideoButton = uibutton(app.LeftPanel, 'push');
            app.LastvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LastvideoButtonPushed, true);
            app.LastvideoButton.Text = 'Last video';
            app.PlotfeaturesButton = uibutton(app.LeftPanel, 'push');
            app.PlotfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfeaturesButtonPushed, true);
            app.PlotfeaturesButton.Text = 'Plot features';
            app.ForwardButton = uibutton(app.LeftPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.LeftPanel, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = 'Backward';
            app.SessionEditFieldLabel = uilabel(app.LeftPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LeftPanel, 'text');
            app.Classifier = uibutton(app.LeftPanel, 'push');
            app.Classifier.ButtonPushedFcn = createCallbackFcn(app, @ClassifierButtonPushed, true);
            app.Classifier.Tooltip = {'Load a classifier trained with a ML algorithm, e.g., from SVM. This should be done seperately in a script.'};
            app.Classifier.Text = 'Use classifier';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.UIAxesX = uiaxes(app.CenterPanel);
            title(app.UIAxesX, 'Plot x')
            xlabel(app.UIAxesX, 'X')
            ylabel(app.UIAxesX, 'Y')
            zlabel(app.UIAxesX, 'Z')
            app.UIAxesY = uiaxes(app.CenterPanel);
            title(app.UIAxesY, 'Plot y')
            xlabel(app.UIAxesY, 'X')
            ylabel(app.UIAxesY, 'Y')
            zlabel(app.UIAxesY, 'Z')
            app.Slider = uislider(app.CenterPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'videoslider';
            app.GoodtrackingratioEditFieldLabel = uilabel(app.CenterPanel);
            app.GoodtrackingratioEditFieldLabel.Text = 'Good tracking ratio';
            app.GoodtrackingratioEditField = uieditfield(app.CenterPanel, 'text');
            app.ProcessvideodatafromMakeAVIDLCLiveandMakeAVIApproachLabel = uilabel(app.CenterPanel);
            app.ProcessvideodatafromMakeAVIDLCLiveandMakeAVIApproachLabel.Text = 'Process video data from MakeAVIDLCLive and MakeAVIApproach';
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesTraj2Video = uiaxes(app.RightPanel);
            title(app.UIAxesTraj2Video, 'Reach trajectory')
            xlabel(app.UIAxesTraj2Video, 'X')
            ylabel(app.UIAxesTraj2Video, 'Y')
            zlabel(app.UIAxesTraj2Video, 'Z')
            app.UIAxesTriggerPos = uiaxes(app.RightPanel);
            title(app.UIAxesTriggerPos, 'TriggerPos')
            xlabel(app.UIAxesTriggerPos, 'X')
            ylabel(app.UIAxesTriggerPos, 'Y')
            zlabel(app.UIAxesTriggerPos, 'Z')
            app.UIAxesSpeedxy = uiaxes(app.RightPanel);
            title(app.UIAxesSpeedxy, {'Speed x vs Speed y'; ''})
            xlabel(app.UIAxesSpeedxy, 'X')
            ylabel(app.UIAxesSpeedxy, 'Y')
            zlabel(app.UIAxesSpeedxy, 'Z')
            app.WhattrialButton = uibutton(app.RightPanel, 'push');
            app.WhattrialButton.ButtonPushedFcn = createCallbackFcn(app, @WhattrialButtonPushed, true);
            app.WhattrialButton.Text = 'What trial?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLCAppLite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end