classdef DLCLabelCuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DLConlineoptostimposthocanalysisLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        MakeframevidButton             matlab.ui.control.Button
        MakePosDataButton              matlab.ui.control.Button
        VideofileDropDown              matlab.ui.control.DropDown
        VideofileDropDownLabel         matlab.ui.control.Label
        TriggerfileDropDown            matlab.ui.control.DropDown
        TriggerfileDropDownLabel       matlab.ui.control.Label
        PositionfileDropDown           matlab.ui.control.DropDown
        PositionfileDropDownLabel      matlab.ui.control.Label
        PosDatatableDropDown           matlab.ui.control.DropDown
        PosDatatableDropDownLabel      matlab.ui.control.Label
        DLCframesvidfileDropDown       matlab.ui.control.DropDown
        DLCframesvidfileDropDownLabel  matlab.ui.control.Label
        ANMnameEditField               matlab.ui.control.EditField
        ANMnameEditFieldLabel          matlab.ui.control.Label
        MakenewtriggervideoButton      matlab.ui.control.Button
        UpdatePosDataButton            matlab.ui.control.Button
        UpdatetableButton              matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        LoadvideoButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        MarkbadlabelingButton          matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        FramenumberEditField           matlab.ui.control.NumericEditField
        FramenumberEditFieldLabel      matlab.ui.control.Label
        DLCFramesAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DLCframesvidfileDropDown
        function DLCframesvidfileDropDownOpening(app, event)

            ANM_name = app.ANMnameEditField.Value;
            avi_files = dir(['DLCTriggerFrames_' ANM_name '*.avi'])
            AllFileNames = arrayfun(@(x)x.name, avi_files, 'UniformOutput',false);
            app.DLCframesvidfileDropDown.Items = AllFileNames;
        end

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)

            DLCTableName = app.PosDatatableDropDown.Value;
            [D, S] = xlsread(DLCTableName);

%             app.DLCFramesAxes userdata:
%              1. D
%              2. S (string)
%              3. all frame data
%              4. current frame handle

            app.DLCFramesAxes.UserData{1} = D;
            app.DLCFramesAxes.UserData{2} = S;
            n_allframes = size(D, 1);

            vid_name = app.DLCframesvidfileDropDown.Value;
            % plot first frame on the video axis
            cla(app.DLCFramesAxes)
            ind_this = 1;
            vidObj = VideoReader(vid_name);
            tic

            % Update current table
            app.UITable.FontSize=12;
            app.UITable.Data = D(ind_this, :);
            
            % collect frame data and store it in the userdata of the load
            % button

            AllFrames = read(vidObj, [1, n_allframes]);
            app.DLCFramesAxes.UserData{3} = AllFrames;  
            toc

            this_frame = AllFrames(:, :, :, ind_this);

            app.FramenumberEditField.Value =  ind_this;
                        
            % update axis size
            vidpos = app.DLCFramesAxes.Position;
            width = vidpos(3);
            newheight = width*size(this_frame, 1)/size(this_frame, 2);
            vidpos(4) = newheight;

            set(app.DLCFramesAxes,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 2)], 'ylim', [0 size(this_frame, 1)], 'ydir','reverse')

            % plot current frame
            app.DLCFramesAxes.UserData{4} = image(app.DLCFramesAxes, this_frame);

            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;


        end

        % Drop down opening function: PosDatatableDropDown
        function PosDatatableDropDownOpening(app, event)
            PosDataTables = dir('DLC*.xlsx');
            AllTableNames = arrayfun(@(x)x.name, PosDataTables, 'UniformOutput',false);
            app.PosDatatableDropDown.Items = AllTableNames;

        end

        % Value changed function: DLCframesvidfileDropDown
        function DLCframesvidfileDropDownValueChanged(app, event)
            value = app.DLCframesvidfileDropDown.Value;
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            
            value = app.Slider.Value;
            newvalue = value + verticalScrollCount;
            newvalue = max([1 newvalue]);
            app.Slider.Value = newvalue;
            value = newvalue;
            
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')
            
            % update frame index
            app.FramenumberEditField.Value =  ind_this;            
            this_frame = app.DLCFramesAxes.UserData{3}(:, :, :, ind_this);
            % update frame
            tic
            app.DLCFramesAxes.UserData{4}.CData = this_frame;
            toc

            % update table            
            app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);


        end

        % Button pushed function: MarkbadlabelingButton
        function MarkbadlabelingButtonPushed(app, event)
            % Mark current frame as a bad labeling
            D = app.DLCFramesAxes.UserData{1};
            ind_this = app.FramenumberEditField.Value;
            if D(ind_this, 2)
                D(ind_this, 2) = 0;
            else
                D(ind_this, 2) = 1;
            end;
            app.DLCFramesAxes.UserData{1} = D;

            % update table
            app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Play this vid

            value = app.Slider.Value;
            allframes = size(app.DLCFramesAxes.UserData{1}, 1);

            for i = 1:allframes
                newvalue = i;
                app.Slider.Value = newvalue;
                value = newvalue;

                clc
                ind_this = round(value);
                tic
                sprintf('Plot new frame')

                % update frame index
                app.FramenumberEditField.Value =  ind_this;
                this_frame = app.DLCFramesAxes.UserData{3}(:, :, :, ind_this);
                % update frame
                tic
                app.DLCFramesAxes.UserData{4}.CData = this_frame;
                drawnow
                toc

                % update table
                app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);
            end;


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;

            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            % update frame index
            app.FramenumberEditField.Value =  ind_this;
            this_frame = app.DLCFramesAxes.UserData{3}(:, :, :, ind_this);
            % update frame
            tic
            app.DLCFramesAxes.UserData{4}.CData = this_frame;
            drawnow
            toc

            % update table
            app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);

        end

        % Value changed function: FramenumberEditField
        function FramenumberEditFieldValueChanged(app, event)
            value = app.FramenumberEditField.Value;

            app.Slider.Value = value;

            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            % update frame index
            app.FramenumberEditField.Value =  ind_this;
            this_frame = app.DLCFramesAxes.UserData{3}(:, :, :, ind_this);
            % update frame
            tic
            app.DLCFramesAxes.UserData{4}.CData = this_frame;
            drawnow
            toc

            % update table
            app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);
        end

        % Button pushed function: UpdatetableButton
        function UpdatetableButtonPushed(app, event)
            D =  app.DLCFramesAxes.UserData{1};
            S  =  app.DLCFramesAxes.UserData{2};

            StimIndex       =       D(:, 1);
            GoodTracking =      D(:, 2);
            StimClus          =      D(:, 3);
            StimTime        =       D(:, 4);
            StimPos_x       =       D(:, 5);
            StimPos_y       =       D(:, 6);

            % update current table
            TableEvents     =       table(StimIndex,GoodTracking, StimClus, StimTime, StimPos_x, StimPos_y);
            sheetname      =       app.PosDatatableDropDown.Value;
            writetable(TableEvents, sheetname);
% 
            StimIndex       =       num2cell(StimIndex(GoodTracking~=0));
            StimTime        =       num2cell(StimTime(GoodTracking~=0));
            StimPos_x       =       num2cell(StimPos_x(GoodTracking~=0));
            StimPos_y       =       num2cell(StimPos_y(GoodTracking~=0));
            StimClus          =       num2cell(StimClus(GoodTracking~=0));
            GoodTracking =       num2cell(GoodTracking(GoodTracking~=0));

            TableEvents     =       table(StimIndex,GoodTracking, StimClus, StimTime, StimPos_x, StimPos_y);

            sheetname = app.PosDatatableDropDown.Value;

            newsheetname = insertBefore(sheetname, strfind(sheetname, '.xlsx'), 'Updated');
            writetable(TableEvents, newsheetname);


        end

        % Button pushed function: UpdatePosDataButton
        function UpdatePosDataButtonPushed(app, event)

            % Look for excel table
            [posfile,path] = uigetfile('PosData*.mat',  'Pick the PosData that needs to be updated');

            load(fullfile(path, posfile))

            D =  app.DLCFramesAxes.UserData{1};
            S  =  app.DLCFramesAxes.UserData{2};

            IndGoodStim = D(D(:, 2)==1, 1);
            N_good = length(IndGoodStim);

            NAll = size(D, 1);
            sprintf('Good labeling vs all labeling: %2.0d / %2.0d, %2.2f%%', N_good, NAll, N_good/NAll*100)
  
            for i =1:length(PosData.StimClus)                
                PosData.StimClus{i}(setdiff([1:length(PosData.StimClus{i})], IndGoodStim)) = 0;                
            end;

            app.UpdatePosDataButton.UserData = PosData;

            % save PosData
            newposfile = insertBefore(posfile, strfind(posfile, '.mat'), 'Updated');
            save(newposfile, 'PosData')


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case {'x', 'uparrow'} 
                    % kill current labeling
                    % Mark current frame as a bad labeling
                    D = app.DLCFramesAxes.UserData{1};
                    ind_this = app.FramenumberEditField.Value;
                    if D(ind_this, 2)
                        D(ind_this, 2) = 0;
                    else
                        D(ind_this, 2) = 1;
                    end;
                    app.DLCFramesAxes.UserData{1} = D;

                    % update table
                    app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);

                case   'rightarrow'

                    value = app.Slider.Value;
                    newvalue = value + 1;
                    newvalue = max([1 newvalue]);
                    if newvalue >=1 && newvalue<=app.Slider.Limits(2)
                        app.Slider.Value = newvalue;
                        value = newvalue;

                        clc
                        ind_this = round(value);
                        tic
                        sprintf('Plot new frame')

                        % update frame index
                        app.FramenumberEditField.Value =  ind_this;
                        this_frame = app.DLCFramesAxes.UserData{3}(:, :, :, ind_this);
                        % update frame
                        tic
                        app.DLCFramesAxes.UserData{4}.CData = this_frame;
                        toc

                        % update table
                        app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);
                    else
                        display('Out of range!')
                        return
                    end;

                case  'leftarrow'

                    value = app.Slider.Value;
                    newvalue = value - 1;
                    newvalue = max([1 newvalue]);
                                     
                    app.Slider.Value = newvalue;
                    value = newvalue;

                    clc
                    ind_this = round(value);
                    tic
                    sprintf('Plot new frame')

                    % update frame index
                    app.FramenumberEditField.Value =  ind_this;
                    this_frame = app.DLCFramesAxes.UserData{3}(:, :, :, ind_this);
                    % update frame
                    tic
                    app.DLCFramesAxes.UserData{4}.CData = this_frame;
                    toc

                    % update table
                    app.UITable.Data = app.DLCFramesAxes.UserData{1}(ind_this, :);

            end;


        end

        % Drop down opening function: PositionfileDropDown
        function PositionfileDropDownOpening(app, event)

             name = app.ANMnameEditField.Value;
            CheckPosFile = dir([name '*position*.txt']);

            if ~isempty(CheckPosFile) 
                iPosFile = arrayfun(@(x)x.name, CheckPosFile, 'UniformOutput',false);
            end;

            app.PositionfileDropDown.Items = iPosFile;

        end

        % Value changed function: PositionfileDropDown
        function PositionfileDropDownValueChanged(app, event)
            value = app.PositionfileDropDown.Value;
            
        end

        % Drop down opening function: TriggerfileDropDown
        function TriggerfileDropDownOpening(app, event)

            name = app.ANMnameEditField.Value;
            CheckStimFile = dir([name '*Stim*.txt']);

            if ~isempty(CheckStimFile) 
                iStimFile = arrayfun(@(x)x.name, CheckStimFile, 'UniformOutput',false);
            end;

            app.TriggerfileDropDown.Items = iStimFile;


        end

        % Drop down opening function: VideofileDropDown
        function VideofileDropDownOpening(app, event)

            name = app.ANMnameEditField.Value;
            CheckVidFile = dir([name '*.avi']);
            if ~isempty(CheckVidFile)
                iVidFile = arrayfun(@(x)x.name, CheckVidFile, 'UniformOutput',false);
            end;
            app.VideofileDropDown.Items = iVidFile;

        end

        % Button pushed function: MakePosDataButton
        function MakePosDataButtonPushed(app, event)


            iPosFile = app.PositionfileDropDown.Value;
            iStimFile = app.TriggerfileDropDown.Value;
            iVidFile = app.VideofileDropDown.Value;

            % delete files before generating new ones

            if ~isempty(dir('DLCPos.xlsx'))
                delete 'DLCPos.xlsx'
            end;

            if ~isempty(dir('PosData*.mat'))
                temppos = dir('PosData*.mat');
                for i =1:length(temppos)
                    delete(temppos(i).name)
                end;
            end;

            PosData = ExtractPosData(iPosFile, iStimFile); %
            app.MakePosDataButton.UserData = PosData;

            PosDataTables = dir('DLC*.xlsx');
            AllTableNames = arrayfun(@(x)x.name, PosDataTables, 'UniformOutput',false);
            app.PosDatatableDropDown.Items = AllTableNames;

        end

        % Button pushed function: MakenewtriggervideoButton
        function MakenewtriggervideoButtonPushed(app, event)

            iVidFile = app.VideofileDropDown.Value;
            PosData = app.UpdatePosDataButton.UserData; %
            vidout = ExtractStimFrames(iVidFile, PosData, 'Updated');

            app.DLCframesvidfileDropDown.Items = {vidout};            


        end

        % Button pushed function: MakeframevidButton
        function MakeframevidButtonPushed(app, event)

            if ~isempty(dir('DLCTriggerFrames*.avi'))
                temppos = dir('DLCTriggerFrames*.avi');
                for i =1:length(temppos)
                    delete(temppos(i).name)
                end;
            end;

            iVidFile = app.VideofileDropDown.Value;
            PosData = app.MakePosDataButton.UserData; %
            vidout = ExtractStimFrames(iVidFile, PosData);
            app.DLCframesvidfileDropDown.Items = {vidout};

        end

        % Value changed function: PosDatatableDropDown
        function PosDatatableDropDownValueChanged(app, event)
            value = app.PosDatatableDropDown.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {571, 571};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.UpdatetableButton = uibutton(app.LeftPanel, 'push');
            app.UpdatetableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetableButtonPushed, true);
            app.UpdatetableButton.Text = 'Update table';
            app.UpdatePosDataButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePosDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePosDataButtonPushed, true);
            app.UpdatePosDataButton.Text = 'Update Pos Data';
            app.MakenewtriggervideoButton = uibutton(app.LeftPanel, 'push');
            app.MakenewtriggervideoButton.ButtonPushedFcn = createCallbackFcn(app, @MakenewtriggervideoButtonPushed, true);
            app.MakenewtriggervideoButton.Text = 'Make new trigger video';
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.ANMnameEditFieldLabel = uilabel(app.Panel);
            app.ANMnameEditFieldLabel.Text = 'ANM name';
            app.ANMnameEditField = uieditfield(app.Panel, 'text');
            app.DLCframesvidfileDropDownLabel = uilabel(app.Panel);
            app.DLCframesvidfileDropDownLabel.Text = 'DLC-frames vid file';
            app.DLCframesvidfileDropDown = uidropdown(app.Panel);
            app.DLCframesvidfileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DLCframesvidfileDropDownOpening, true);
            app.DLCframesvidfileDropDown.ValueChangedFcn = createCallbackFcn(app, @DLCframesvidfileDropDownValueChanged, true);
            app.PosDatatableDropDownLabel = uilabel(app.Panel);
            app.PosDatatableDropDownLabel.Text = 'PosData table';
            app.PosDatatableDropDown = uidropdown(app.Panel);
            app.PosDatatableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PosDatatableDropDownOpening, true);
            app.PosDatatableDropDown.ValueChangedFcn = createCallbackFcn(app, @PosDatatableDropDownValueChanged, true);
            app.PositionfileDropDownLabel = uilabel(app.Panel);
            app.PositionfileDropDownLabel.Tooltip = {'This records the time and position of a body part in each frame. NaN means it is not observed in the current frame'};
            app.PositionfileDropDownLabel.Text = 'Position file';
            app.PositionfileDropDown = uidropdown(app.Panel);
            app.PositionfileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PositionfileDropDownOpening, true);
            app.PositionfileDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionfileDropDownValueChanged, true);
            app.TriggerfileDropDownLabel = uilabel(app.Panel);
            app.TriggerfileDropDownLabel.Tooltip = {'This records the time when a body part crosses a pre-defined threshold, which sends a signal to Bpod'; ' '};
            app.TriggerfileDropDownLabel.Text = 'Trigger file';
            app.TriggerfileDropDown = uidropdown(app.Panel);
            app.TriggerfileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TriggerfileDropDownOpening, true);
            app.VideofileDropDownLabel = uilabel(app.Panel);
            app.VideofileDropDownLabel.Text = 'Video file';
            app.VideofileDropDown = uidropdown(app.Panel);
            app.VideofileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VideofileDropDownOpening, true);
            app.VideofileDropDown.Tooltip = {'Video file recorded by Bonsai'};
            app.MakePosDataButton = uibutton(app.Panel, 'push');
            app.MakePosDataButton.ButtonPushedFcn = createCallbackFcn(app, @MakePosDataButtonPushed, true);
            app.MakePosDataButton.Tooltip = {'Extract useful frames, etc.'};
            app.MakePosDataButton.Text = 'Make PosData';
            app.MakeframevidButton = uibutton(app.Panel, 'push');
            app.MakeframevidButton.ButtonPushedFcn = createCallbackFcn(app, @MakeframevidButtonPushed, true);
            app.MakeframevidButton.Tooltip = {'Extract useful frames, etc.'};
            app.MakeframevidButton.Text = 'Make frame vid';
            app.DLConlineoptostimposthocanalysisLabel = uilabel(app.LeftPanel);
            app.DLConlineoptostimposthocanalysisLabel.Text = 'DLC online optostim post hoc analysis';
            app.RightPanel = uipanel(app.GridLayout);
            app.DLCFramesAxes = uiaxes(app.RightPanel);
            title(app.DLCFramesAxes, 'Tracking from DLC')
            app.DLCFramesAxes.XLim = [0 300];
            app.DLCFramesAxes.YLim = [0 500];
            app.FramenumberEditFieldLabel = uilabel(app.RightPanel);
            app.FramenumberEditFieldLabel.Text = 'Frame number';
            app.FramenumberEditField = uieditfield(app.RightPanel, 'numeric');
            app.FramenumberEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumberEditFieldValueChanged, true);
            app.SliderLabel = uilabel(app.RightPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.RightPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Index'; 'Good'; 'Clus'; 'Time'; 'X'; 'Y'};
            app.UITable.RowName = {};
            app.MarkbadlabelingButton = uibutton(app.RightPanel, 'push');
            app.MarkbadlabelingButton.ButtonPushedFcn = createCallbackFcn(app, @MarkbadlabelingButtonPushed, true);
            app.MarkbadlabelingButton.Text = 'Mark bad labeling';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLCLabelCuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end