classdef rVideoViewerSide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutoButton                      matlab.ui.control.Button
        PlotrasteroutsideCheckBox       matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        ROIButton                       matlab.ui.control.Button
        TurningLabel                    matlab.ui.control.Label
        TurningDropDown                 matlab.ui.control.DropDown
        LiftingpawLabel                 matlab.ui.control.Label
        PawDropDown                     matlab.ui.control.DropDown
        LiftTable                       matlab.ui.control.Table
        ExportliftingandturningButton   matlab.ui.control.Button
        SavesideautomaticallyCheckBox   matlab.ui.control.CheckBox
        PlayingbothsidesCheckBox        matlab.ui.control.CheckBox
        Save_Fig10Button                matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        DeleteitButton                  matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        FindSideVideo                   matlab.ui.control.Button
        TrackingData                    matlab.ui.control.Label
        CheckButton                     matlab.ui.control.Button
        ExtractedtrajsListBox           matlab.ui.control.ListBox
        ExtractedtrajsListBoxLabel      matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        BodytriangleCheckBox            matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        saveTraj                        matlab.ui.control.Button
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        EndButton                       matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Snapshotdirectory               matlab.ui.control.Label
        PrintsnapshotButton             matlab.ui.control.Button
        MapspikestotrajectorypopButton  matlab.ui.control.Button
        MapspikestotrajectorysingleButton  matlab.ui.control.Button
        DatadirectoryLabel              matlab.ui.control.Label
        SendmoretrainingdataButton      matlab.ui.control.Button
        CleanDropDown                   matlab.ui.control.DropDown
        CleanDropDownLabel              matlab.ui.control.Label
        BodypartsListBox                matlab.ui.control.ListBox
        BodypartsListBoxLabel           matlab.ui.control.Label
        ListDLC                         matlab.ui.control.ListBox
        LoadDLCtrackingCheckBox         matlab.ui.control.CheckBox
        PlotspikesButton                matlab.ui.control.Button
        SpikeTable                      matlab.ui.control.Table
        rName                           matlab.ui.control.Label
        LoadrButton                     matlab.ui.control.Button
        TimeLabel                       matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        VideoSlider                     matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        ListofvideosListBox             matlab.ui.control.ListBox
        ListofvideosListBoxLabel        matlab.ui.control.Label
        SelectvideosButton              matlab.ui.control.Button
        UIAxesVideoSide                 matlab.ui.control.UIAxes
        UIAxesExtractedSpikes           matlab.ui.control.UIAxes
        UIAxesYT                        matlab.ui.control.UIAxes
        UIAxesXT                        matlab.ui.control.UIAxes
        hbarAxes                        matlab.ui.control.UIAxes
        SpikeAxes                       matlab.ui.control.UIAxes
        FiringRateAxes                  matlab.ui.control.UIAxes
        VideoAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        isPlaying % Description
        VideoMeta % meta data of this video
        rData % Ephys data
        CurrentFrameLine % a line that mark current frame in the spike raster
        BodyScatter % the scatter plot of body tracking results
        FrameImg % the frame image
        FrameImgSide
        FrameImgSideOrg  % in case a ROI is chosen
        dlcResults; % Description
        CurrentFrameTracking;
        folderPath; % where videos and csv files are
        thisVideo;
        thisVideoSide; % side video name
        extractSegment; % time segment to extract for current trial
        extractSegmentIndex; % index of extracted segment
        bodyTriangle; % a body triangle connecting ears and tail
        tPresses; % press times from r
        tReleases; % release times from r
        tTriggers; % trigger times from r
        tPokes; % poke times from r
        SideVideoPath; % path to side videos
        TrajSavedPath; % where we save the TrajExtracted data
        ROI; % region of interests to facilitate fast plotting of the side video
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SelectvideosButton
        function SelectvideosButtonPushed(app, event)
             % Open a dialog box to select a video file
            [fileName, folderPath] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files (*.mp4, *.avi, *.mov)'; '*.*', 'All Files (*.*)'}, 'Select a Video');

            % Check if the user selected a file
            if isequal(fileName, 0)
                disp('No file selected.');
                return;
            end

            % Get a list of all videos in the same folder
            videoFiles = dir(fullfile(folderPath, 'Side*.mp4'));
            videoFiles = [videoFiles; dir(fullfile(folderPath, 'Side*.avi'))];
            videoFiles = [videoFiles; dir(fullfile(folderPath, 'Side*.mov'))];

            videoMetaFileName = [extractBefore(fileName, '.mp4') '.mat'];
            app.VideoMeta = load(fullfile(folderPath, videoMetaFileName));
            app.folderPath = folderPath;
            % Extract the names of the video files
            videoNames = {videoFiles.name};

            % Update the ListBox with video names

            % sort stuff
            % Example list of files in the folder (replace this with actual files list)
             % Extract numeric part of each file name and store in an array
            nums = cellfun(@(x) str2double(regexp(x, '(?<=_)\d+(?=\.mp4)', 'match')), videoNames);           % Sort the files based on the numeric part
            [~, idx] = sort(nums);
            % Reorder the file list according to the sorted order
            sorted_files = videoNames(idx);
            % Update the ListBox with the sorted file list (assumed ListBox name is ListBox)
            app.ListofvideosListBox.Items = sorted_files;
            app.thisVideo = app.ListofvideosListBox.Value;

            % Read the first video (or selected video)
            selectedVideoPath = fullfile(folderPath, fileName);
            app.VideoAxes.UserData = VideoReader(selectedVideoPath);
            app.VideoAxes.Title.String = strrep(fileName, '_', '-');
            % Get total frames
            totalFrames = floor(app.VideoAxes.UserData.Duration * app.VideoAxes.UserData.FrameRate);

            % Update the slider properties
            app.VideoSlider.Limits = [1, totalFrames];
            app.VideoSlider.MajorTicks = 1:round(totalFrames / 10):totalFrames; % Set major ticks
            app.VideoSlider.Value = 1; % Start at the first frame
            if isprop(app, 'Label') % Ensure the label exists
                app.Label.Text = 'Frame: 1';
            end
            if isprop(app, 'TimeLabel') % Ensure the label exists
                app.TimeLabel.Text = 't (ms) = 0';
            end
            % Read the first frame of the video
            firstFrame = readFrame(app.VideoAxes.UserData);

            % Display the first frame in the VideoAxes
            app.FrameImg = imshow(firstFrame, 'Parent', app.VideoAxes);
            app.VideoAxes.XLim = [0 size(firstFrame, 2)];
            app.VideoAxes.YLim = [0 size(firstFrame, 1)];
            app.VideoAxes.Position(4) = (app.VideoAxes.Position(3)/size(firstFrame, 2))* size(firstFrame, 1);

            if app.LoadDLCtrackingCheckBox.Value == 1
                DLCTrackingFiles          = dir(fullfile(folderPath, '*.csv'));
                DLCTrackingFileNames = {DLCTrackingFiles.name};
                nums = cellfun(@(x) str2double(regexp(x, '(?<=Side_Press_)\d+(?=DLC)', 'match', 'once')), DLCTrackingFileNames);
                % Sort the files based on the numeric part
                % Sort the files based on the numeric part
                [~, idx] = sort(nums);
                % Reorder the file list according to the sorted order
                sorted_files = DLCTrackingFileNames(idx);
                % Update the ListBox with the sorted file list (assumed ListBox name is ListBox)

                app.ListDLC.Items                    = sorted_files;
                % make sure the correct item is selected
                selectedDLCFile = dir(fullfile(folderPath, [extractBefore(fileName, '.mp4'),'*.csv']));

                app.ListDLC.Value = selectedDLCFile.name;
                selectedDLCFilePath = fullfile(selectedDLCFile.folder, selectedDLCFile.name);
                dlcResults = read_dlc_table(selectedDLCFilePath);
                    
                  app.dlcResults = dlcResults;
                  % Assuming dlcResults is already loaded or created
                  % Extract field names from dlcResults
                  fieldNames = fieldnames(dlcResults);

                  % Assign field names to the list box in your app
                  app.BodypartsListBox.Items = fieldNames;

                  % Plot the first body part
                  % Extract x, y, and likelihood from dlcResults.EarLTop

                  body =  app.BodypartsListBox.Value;
                  eval(['x = app.dlcResults.' body '.x;']);
                  eval(['y = app.dlcResults.' body '.y;']);
                  eval(['likelihood = app.dlcResults.' body '.likelihood;']);

                  app.VideoAxes.NextPlot = 'add';
                  app.BodyScatter = scatter(app.VideoAxes, x, y, 15, likelihood, 'filled'); % 15 is marker size
                  
                  colormap(app.VideoAxes, 'parula'); % Set colormap to 'hot'
                  % make pseudo colorbar
                  xcolor = ones(1, 100);
                  ycolor = linspace(0, 1, 100);
                  scatter(app.hbarAxes, xcolor, ycolor, 85, ycolor, 'filled', 's');
                  app.hbarAxes.YLim = [0 1];
                  app.hbarAxes.YAxisLocation='right';
                  app.hbarAxes.XLim = [0.9 1.1];
                  app.hbarAxes.Position(2) = 770;
                  app.hbarAxes.Color='none';
                  colormap(app.hbarAxes, 'parula'); % Set colormap to 'hot'

                  % update x, y based on likelihood
                  if strcmp(app.CleanDropDown.Value, 'Yes')
                      [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                      app.BodyScatter.XData = x_cleaned;
                      app.BodyScatter.YData = y_cleaned;
                  end

                  % Current frame
                  app.CurrentFrameTracking = scatter(app.VideoAxes, app.BodyScatter.XData(app.VideoSlider.Value),...
                      app.BodyScatter.YData(app.VideoSlider.Value), 250, '+', ...
                      'MarkerEdgeColor', 'r', 'linewidth', 2);
                   app.VideoAxes.NextPlot = 'replacechildren';                    
                   app.extractSegment = [];

                   % draw a body triangle
                   if app.BodytriangleCheckBox.Value == 1
                
                       body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                       body_1 =  'LeftPaw';
                       eval(['x = app.dlcResults.' body_1 '.x;']);
                       eval(['y = app.dlcResults.' body_1 '.y;']);
                       eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                       if strcmp(app.CleanDropDown.Value, 'Yes')
                           [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                           body_1_coord(1) = x_cleaned(app.VideoSlider.Value);
                           body_1_coord(2) = y_cleaned(app.VideoSlider.Value);
                       else
                           body_1_coord(1) = x(app.VideoSlider.Value);
                           body_1_coord(2) = y(app.VideoSlider.Value);                           
                       end

                       body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                       body_2 =  'RightPaw';
                       eval(['x = app.dlcResults.' body_2 '.x;']);
                       eval(['y = app.dlcResults.' body_2 '.y;']);
                       eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                       if strcmp(app.CleanDropDown.Value, 'Yes')
                           [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                           body_2_coord(1) = x_cleaned(app.VideoSlider.Value);
                           body_2_coord(2) = y_cleaned(app.VideoSlider.Value);
                       else
                           body_2_coord(1) = x(app.VideoSlider.Value);
                           body_2_coord(2) = y(app.VideoSlider.Value);
                       end

                       body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                       body_3 =  'LeftEar';
                       eval(['x = app.dlcResults.' body_3 '.x;']);
                       eval(['y = app.dlcResults.' body_3 '.y;']);
                       eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                       if strcmp(app.CleanDropDown.Value, 'Yes')
                           [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                           body_3_coord(1) = x_cleaned(app.VideoSlider.Value);
                           body_3_coord(2) = y_cleaned(app.VideoSlider.Value);
                       else
                           body_3_coord(1) = x(app.VideoSlider.Value);
                           body_3_coord(2) = y(app.VideoSlider.Value);                           
                       end

                       % draw this triangle
                       app.VideoAxes.NextPlot = 'add';
                       app.bodyTriangle = line(app.VideoAxes, [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)], [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2) ],...
                           'linewidth', 1, 'linestyle', ':', 'color', [0 1 1]);
                       app.VideoAxes.NextPlot = 'replacechildren';
                   end

            end

        end

        % Value changed function: VideoSlider
        function VideoSliderValueChanged(app, event)
            % Check if a video is loaded
            if isempty(app.VideoAxes.UserData) || ~isvalid(app.VideoAxes.UserData)
                disp('No video loaded.');
                return;
            end

            % Get the selected frame index from the slider
            frameIndex = round(app.VideoSlider.Value);

            % Move the video to the correct frame
            app.VideoAxes.UserData.CurrentTime = (frameIndex - 1) / app.VideoAxes.UserData.FrameRate;
            frame = readFrame(app.VideoAxes.UserData);

            % Display the frame in the VideoAxes
            app.FrameImg.CData  = frame;
            % imshow(frame, 'Parent', app.VideoAxes);

            % Update the frame number in the label
            app.Label.Text = sprintf('Frame: %d', frameIndex);

            % Compute time based on video time stamps
            FrameTime =  app.VideoMeta.VideoInfo.VideoTimeStamps(frameIndex)-app.VideoMeta.VideoInfo.VideoTimeStamps(1);
            % Update frame time
            if isprop(app, 'TimeLabel') % Ensure the label exists
                app.TimeLabel.Text = sprintf('t (ms) = %4.0d', FrameTime);
            end

            % Change the CurrentFrameLine position
            if isprop(app, 'CurrentFrameLine') % Ensure the label exists
                videoTimestamps  = app.VideoMeta.VideoInfo.EphysTimeStamps;
                app.CurrentFrameLine.XData = [1 1]*videoTimestamps(frameIndex);
            end

            % Update the current tracking point
            body =  app.BodypartsListBox.Value;
            eval(['x = app.dlcResults.' body '.x;']);
            eval(['y = app.dlcResults.' body '.y;']);
            eval(['likelihood = app.dlcResults.' body '.likelihood;']);

            % Current frame
            app.CurrentFrameTracking.XData = app.BodyScatter.XData(round(app.VideoSlider.Value));
            app.CurrentFrameTracking.YData = app.BodyScatter.YData(round(app.VideoSlider.Value));

            % Update triangle, if there is any
            % draw a body triangle
            if app.BodytriangleCheckBox.Value == 1

                body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                body_1 =  'LeftPaw';
                eval(['x = app.dlcResults.' body_1 '.x;']);
                eval(['y = app.dlcResults.' body_1 '.y;']);
                eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                if strcmp(app.CleanDropDown.Value, 'Yes')
                    [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                    body_1_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                    body_1_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                else
                    body_1_coord(1) = x(round(app.VideoSlider.Value));
                    body_1_coord(2) = y(round(app.VideoSlider.Value));
                end

                body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                body_2 =  'RightPaw';
                eval(['x = app.dlcResults.' body_2 '.x;']);
                eval(['y = app.dlcResults.' body_2 '.y;']);
                eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                if strcmp(app.CleanDropDown.Value, 'Yes')
                    [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                    body_2_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                    body_2_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                else
                    body_2_coord(1) = x(round(app.VideoSlider.Value));
                    body_2_coord(2) = y(round(app.VideoSlider.Value));
                end

                body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                body_3 =  'LeftEar';
                eval(['x = app.dlcResults.' body_3 '.x;']);
                eval(['y = app.dlcResults.' body_3 '.y;']);
                eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                if strcmp(app.CleanDropDown.Value, 'Yes')
                    [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                    body_3_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                    body_3_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                else
                    body_3_coord(1) = x(round(app.VideoSlider.Value));
                    body_3_coord(2) = y(round(app.VideoSlider.Value));
                end

                try
                    app.bodyTriangle.XData = [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)];
                    app.bodyTriangle.YData = [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2)];
                end

                if app.PlayingbothsidesCheckBox.Value == 1
                    FindSideVideoPushed(app)
                end
            end

        end

        % Value changed function: ListofvideosListBox
        function ListofvideosListBoxValueChanged(app, event)
            % Get the selected video name from the ListBox
            selectedVideoName = app.ListofvideosListBox.Value;
            app.thisVideo = app.ListofvideosListBox.Value;
            app.VideoAxes.Title.String = strrep(selectedVideoName, '_', '-');
            app.VideoAxes.Title.FontSize = 15;
            % Check if a video is selected
            if isempty(selectedVideoName)
                disp('No video selected.');
                return;
            end

            app.extractSegment = [];
            app.extractSegmentIndex = [];                
            cla(app.UIAxesYT);
            cla(app.UIAxesXT);
            cla(app.UIAxesExtractedSpikes);

            % Construct the full path of the selected video
            folderPath = (app.VideoAxes.UserData.Path); % Retrieve the folder of the current video
            videoMetaFileName = [extractBefore(selectedVideoName, '.mp4') '.mat'];
            app.VideoMeta = load(fullfile(folderPath, videoMetaFileName));

            selectedVideoPath = fullfile(folderPath, selectedVideoName);
            % Update the VideoReader with the new video
            app.VideoAxes.UserData = VideoReader(selectedVideoPath);
            app.UIAxesVideoSide.UserData = [];

            % Read the first frame of the new video
            firstFrame = readFrame(app.VideoAxes.UserData);
             % Display the first frame in the VideoAxes
            app.FrameImg = imshow(firstFrame, 'Parent', app.VideoAxes);
             app.VideoAxes.XLim = [0 size(firstFrame, 2)];
            app.VideoAxes.YLim = [0 size(firstFrame, 1)];

            % Reset the slider for the new video
            totalFrames = floor(app.VideoAxes.UserData.Duration * app.VideoAxes.UserData.FrameRate);
            app.VideoSlider.Limits = [1, totalFrames];
            app.VideoSlider.MajorTicks = 1:round(totalFrames / 10):totalFrames; % Set major ticks
            app.VideoSlider.Value = 1; % Reset slider to the first frame

            % Optionally, update the frame number label (if applicable)
            if isprop(app, 'Label') % Ensure the label exists
                app.Label.Text = 'Frame: 1';
            end
            cla(app.SpikeAxes); % Clear previous plots
            PlotspikesButtonPushed(app)

            if app.LoadDLCtrackingCheckBox.Value == 1
                
                selectedDLCFile = dir(fullfile(folderPath, [extractBefore(selectedVideoName, '.mp4'),'*.csv']));

                app.ListDLC.Value = selectedDLCFile.name;
                selectedDLCFilePath = fullfile(selectedDLCFile.folder, selectedDLCFile.name);
                dlcResults = read_dlc_table(selectedDLCFilePath);
                app.dlcResults = dlcResults;

                % Assuming dlcResults is already loaded or created
                % Extract field names from dlcResults
                fieldNames = fieldnames(dlcResults);

                % Assign field names to the list box in your app
                app.BodypartsListBox.Items = fieldNames;

                % Plot the first body part
                % Extract x, y, and likelihood from dlcResults.EarLTop
                body =  app.BodypartsListBox.Value;
                eval(['x = app.dlcResults.' body '.x;']);
                eval(['y = app.dlcResults.' body '.y;']);
                eval(['likelihood = app.dlcResults.' body '.likelihood;']);

                app.VideoAxes.NextPlot = 'add';
                app.BodyScatter = scatter(app.VideoAxes, x, y, 15, likelihood, 'filled'); % 15 is marker size
                colormap(app.VideoAxes, 'parula'); % Set colormap to 'hot'
                % hcbar = colorbar; % Add colorbar to show likelihood values
                % caxis([0, 1]); % Ensure color axis matches likelihood range (0 to 1)

                  % update x, y based on likelihood
                  if strcmp(app.CleanDropDown.Value, 'Yes')
                      [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                      app.BodyScatter.XData = x_cleaned;
                      app.BodyScatter.YData = y_cleaned;
                  end

                  % Current frame
                  app.CurrentFrameTracking = scatter(app.VideoAxes, app.BodyScatter.XData(app.VideoSlider.Value),...
                      app.BodyScatter.YData(app.VideoSlider.Value), 250, '+', ...
                      'MarkerEdgeColor', 'r', 'linewidth', 2);
                  app.VideoAxes.NextPlot = 'replacechildren';
                  app.extractSegment = [];

                  % draw a body triangle
                  if app.BodytriangleCheckBox.Value == 1

                      body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                      body_1 =  'LeftPaw';
                      eval(['x = app.dlcResults.' body_1 '.x;']);
                      eval(['y = app.dlcResults.' body_1 '.y;']);
                      eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                      if strcmp(app.CleanDropDown.Value, 'Yes')
                          [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                          body_1_coord(1) = x_cleaned(app.VideoSlider.Value);
                          body_1_coord(2) = y_cleaned(app.VideoSlider.Value);
                      else
                          body_1_coord(1) = x(app.VideoSlider.Value);
                          body_1_coord(2) = y(app.VideoSlider.Value);
                      end

                      body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                      body_2 =  'RightPaw';
                      eval(['x = app.dlcResults.' body_2 '.x;']);
                      eval(['y = app.dlcResults.' body_2 '.y;']);
                      eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                      if strcmp(app.CleanDropDown.Value, 'Yes')
                          [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                          body_2_coord(1) = x_cleaned(app.VideoSlider.Value);
                          body_2_coord(2) = y_cleaned(app.VideoSlider.Value);
                      else
                          body_2_coord(1) = x(app.VideoSlider.Value);
                          body_2_coord(2) = y(app.VideoSlider.Value);
                      end

                      body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                      body_3 =  'LeftEar';
                      eval(['x = app.dlcResults.' body_3 '.x;']);
                      eval(['y = app.dlcResults.' body_3 '.y;']);
                      eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                      if strcmp(app.CleanDropDown.Value, 'Yes')
                          [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                          body_3_coord(1) = x_cleaned(app.VideoSlider.Value);
                          body_3_coord(2) = y_cleaned(app.VideoSlider.Value);
                      else
                          body_3_coord(1) = x(app.VideoSlider.Value);
                          body_3_coord(2) = y(app.VideoSlider.Value);
                      end

                      % draw this triangle
                      app.VideoAxes.NextPlot = 'add';
                      app.bodyTriangle = line(app.VideoAxes, [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)], [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2) ],...
                          'linewidth', 2, 'linestyle', '-.', 'color', [0 1 1]);
                      app.VideoAxes.NextPlot = 'replacechildren';
                  end
            end

            app.Lamp_2.Color = 'b';
            AutoButtonPushed(app, event)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Check if a video is loaded
            if isempty(app.VideoAxes.UserData) || ~isvalid(app.VideoAxes.UserData)
                disp('No video loaded.');
                return;
            end                        
            % Initialize the play flag
            app.isPlaying = true;
            % Get the total number of frames in the video
            totalFrames = floor(app.VideoAxes.UserData.Duration * app.VideoAxes.UserData.FrameRate);
            % Get the current frame index from the slider
            currentFrame = round(app.VideoSlider.Value);
            % Loop through frames starting from the current frame
            for frameIndex = currentFrame:totalFrames
                % Check if playback has been stopped
                if ~app.isPlaying
                    disp('Playback stopped.');
                    break;
                end

                % Update the current time of the video
                app.VideoAxes.UserData.CurrentTime = (frameIndex - 1) / app.VideoAxes.UserData.FrameRate;
                % Read and display the current frame
                frame = readFrame(app.VideoAxes.UserData);
                app.FrameImg.CData  = frame;                %imshow(frame, 'Parent', app.VideoAxes);

                % Compute time based on video time stamps
                FrameTime =  app.VideoMeta.VideoInfo.VideoTimeStamps(frameIndex)-app.VideoMeta.VideoInfo.VideoTimeStamps(1);
                % Update frame time
                if isprop(app, 'TimeLabel') % Ensure the label exists
                    app.TimeLabel.Text = sprintf('t (ms) = %4.0d', FrameTime);
                end

                % Update the slider value
                app.VideoSlider.Value = frameIndex;
                % Change the CurrentFrameLine position
                if isprop(app, 'CurrentFrameLine') % Ensure the label exists
                    videoTimestamps  = app.VideoMeta.VideoInfo.EphysTimeStamps;
                    app.CurrentFrameLine.XData = [1 1]*videoTimestamps(frameIndex);
                end

                % Update the current tracking point
                body =  app.BodypartsListBox.Value;
                eval(['likelihood = app.dlcResults.' body '.likelihood;']);

                % Current frame
                app.CurrentFrameTracking.XData = app.BodyScatter.XData(round(app.VideoSlider.Value));
                app.CurrentFrameTracking.YData = app.BodyScatter.YData(round(app.VideoSlider.Value));

                % Update label
                app.TrackingData.Text = sprintf('x=%2.2f, y=%2.2f, l=%2.2f',app.BodyScatter.XData(round(app.VideoSlider.Value)),...
                    app.BodyScatter.YData(round(app.VideoSlider.Value)), likelihood(round(app.VideoSlider.Value)));

                % draw a body triangle
                if app.BodytriangleCheckBox.Value == 1
                    body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_1 =  'LeftPaw';
                    eval(['x = app.dlcResults.' body_1 '.x;']);
                    eval(['y = app.dlcResults.' body_1 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_1_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_1_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_1_coord(1) = x(round(app.VideoSlider.Value));
                        body_1_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_2 =  'RightPaw';
                    eval(['x = app.dlcResults.' body_2 '.x;']);
                    eval(['y = app.dlcResults.' body_2 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_2_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_2_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_2_coord(1) = x(round(app.VideoSlider.Value));
                        body_2_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_3 =  'LeftEar';
                    eval(['x = app.dlcResults.' body_3 '.x;']);
                    eval(['y = app.dlcResults.' body_3 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_3_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_3_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_3_coord(1) = x(round(app.VideoSlider.Value));
                        body_3_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    try
                        app.bodyTriangle.XData = [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)];
                        app.bodyTriangle.YData = [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2)];
                    end
                end

                    % Update the frame number label
                    app.Label.Text = sprintf('Frame: %d', frameIndex);

                    % % Pause briefly to simulate playback speed
                % pause(1 / app.VideoAxes.UserData.FrameRate);

                % Check if the video has ended
                if ~hasFrame(app.VideoAxes.UserData)
                    break;
                end

                if app.PlayingbothsidesCheckBox.Value == 1
                    FindSideVideoPushed(app)
                end

            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Set the play flag to false
            app.isPlaying = false;
        end

        % Button pushed function: LoadrButton
        function LoadrButtonPushed(app, event)
            % Open a dialog box to select a .mat file
            [fileName, folderPath] = uigetfile('*.mat', 'Select the rArray file');

            % Check if the user selected a file
            if isequal(fileName, 0)
                disp('No file selected.');
                return;
            end

            % Construct the full path of the selected file
            matFilePath = fullfile(folderPath, fileName);
            % Load the .mat file and save its contents to app.rData

            app.rData = load(matFilePath);
            disp(['MAT file loaded successfully: ', matFilePath]);
            app.rName.Text = fileName;

            % tPresses; % press times from r
            % tReleases; % release times from r
            % tTriggers; % trigger times from r
            % tPokes; % poke times from r
            app.tPresses           =       app.rData.r.Behavior.EventTimings(app.rData.r.Behavior.EventMarkers==find(strcmp(app.rData.r.Behavior.Labels, 'LeverPress')));
            app.tReleases         =       app.rData.r.Behavior.EventTimings(app.rData.r.Behavior.EventMarkers==find(strcmp(app.rData.r.Behavior.Labels, 'LeverRelease')));
            app.tTriggers           =       app.rData.r.Behavior.EventTimings(app.rData.r.Behavior.EventMarkers==find(strcmp(app.rData.r.Behavior.Labels, 'Trigger')));
            app.tPokes              =       app.rData.r.Behavior.EventTimings(app.rData.r.Behavior.EventMarkers==find(strcmp(app.rData.r.Behavior.Labels, 'PokeOnset')));

            % Extract the SpikeNotes data from the loaded file
            if isfield(app.rData, 'r') && isfield(app.rData.r, 'Units') && isfield(app.rData.r.Units, 'SpikeNotes')
                spikeNotes = app.rData.r.Units.SpikeNotes;

                % Update the SpikeTable with the SpikeNotes data
                app.SpikeTable.Data = spikeNotes(:, (1:3));
                disp('SpikeNotes data successfully added to the table.');
            else
                disp('SpikeNotes field not found in the loaded .mat file.');
            end
            PlotspikesButtonPushed(app, event)

        end

        % Button pushed function: PlotspikesButton
        function PlotspikesButtonPushed(app, event)
             % Plot spikes
            % Ensure the data is loaded
            if isempty(app.rData) || ~isfield(app.rData, 'r')
                disp('No data loaded. Please load a .mat file first.');
                return;
            end
            % Get the loaded structure
            r = app.rData.r;
            % Get time of the video
            videoTimestamps  = app.VideoMeta.VideoInfo.EphysTimeStamps;
            % Plot based on the selection
            % Example: Plot spike times within the video duration for all units
                cla(app.SpikeAxes); % Clear previous plots
                cla(app.FiringRateAxes);
                app.FiringRateAxes.XLim = [videoTimestamps(1) videoTimestamps(end)];
                app.SpikeAxes.XLim = [videoTimestamps(1) videoTimestamps(end)];

                hold(app.SpikeAxes, 'on');
                hold(app.FiringRateAxes, 'on');

                colors = jet(round(1.5*numel(r.Units.SpikeTimes)));
                firingRateMax = 10;
                unitNames = cell(1, numel(r.Units.SpikeTimes));
                numUnits = numel(r.Units.SpikeTimes);
                for unitIdx = 1:numUnits

                    unitNames{unitIdx} = [num2str(r.Units.SpikeNotes(unitIdx, 1)) '|' num2str(r.Units.SpikeNotes(unitIdx, 2))];
                    % Get spike timings for the unit
                    spikeTimings = r.Units.SpikeTimes(unitIdx).timings;
                    % Filter spike times within the video duration
                    validSpikeTimes = spikeTimings(spikeTimings >= videoTimestamps(1) & spikeTimings <= videoTimestamps(end));

                    if ~isempty(validSpikeTimes)
                        % Plot the valid spike times
                        line(app.SpikeAxes, [validSpikeTimes; validSpikeTimes], [unitIdx * ones(size(validSpikeTimes)); (unitIdx+1) * ones(size(validSpikeTimes))],...
                            'linewidth', 1, 'color', colors(unitIdx, :));
                                 sdfout =sdf_spktimes(validSpikeTimes, [videoTimestamps(1) videoTimestamps(end)], 10, 10);
                        % Plot the valid spike times
                        firingRateMax = max(max(sdfout(:, 2)), firingRateMax);
                        plot(app.FiringRateAxes, sdfout(:, 1), sdfout(:, 2), 'color', colors(unitIdx, :),'linewidth', 1)
                    end
                end

                % add the time of current frame
                frameIndex = round(app.VideoSlider.Value);
                app.CurrentFrameLine = line(app.SpikeAxes, [1 1]*videoTimestamps(frameIndex),[0 unitIdx+1], 'color', 'm','linewidth', 2, 'linestyle', ':');
                app.SpikeAxes.YTick = (1:numUnits)+0.5;
                app.SpikeAxes.YTickLabel = unitNames;
                app.SpikeAxes.YLim = [0 numUnits+1];
                % turn labels to frame number
                IndTicks = 1:100:length(videoTimestamps);
                TimeStampTicks = videoTimestamps(IndTicks);
                app.SpikeAxes.XTick = TimeStampTicks;
                app.SpikeAxes.XTickLabel = num2cell(IndTicks);

                hold(app.SpikeAxes, 'off');
                title(app.SpikeAxes, '');
                xlabel(app.SpikeAxes, '');
                ylabel(app.SpikeAxes, 'Unit Index');

                firingRateMax = round(firingRateMax*1.1);
                % add the time of current frame
                ylabel(app.SpikeAxes, 'Unit Index');
                  firingRateMax = round(firingRateMax*1.1);
                % add the time of current frame
                app.FiringRateAxes.YLim = [0 firingRateMax];
                app.FiringRateAxes.YTick = [0 firingRateMax];
                app.FiringRateAxes.YTickLabel=num2cell([0 firingRateMax]);

                tCurrentVideo = app.ListofvideosListBox.Value;
                tPress_current = sscanf(tCurrentVideo, '%*[^0-9]%d');
                tPress_current=tPress_current(1);

                app.FiringRateAxes.XTick = TimeStampTicks;
                app.FiringRateAxes.XTickLabel = num2cell(round(TimeStampTicks-tPress_current));
                
                hold(app.FiringRateAxes, 'off');
                title(app.FiringRateAxes, '');
                xlabel(app.FiringRateAxes, 'Time (ms)');
                ylabel(app.FiringRateAxes, 'Firing rate (Hz)');

                app.SpikeAxes.Units = 'normalized';
                app.SpikeAxes.InnerPosition = [0.225, 0.31, 0.5, 0.1];
                app.FiringRateAxes.Units = 'normalized';
                app.FiringRateAxes.InnerPosition = [0.225, 0.15, 0.5, 0.15];

                % Let's mark behavior events
                % Check if press and releases are there
                app.SpikeAxes.NextPlot = 'add';
                ind_presses = find(app.tPresses>=videoTimestamps(1) & app.tPresses<=videoTimestamps(end));
                % Plot these presses and releases
                hold(app.SpikeAxes, 'on')
                   if  ~isempty(ind_presses)
                    for kk =1:length(ind_presses)
                        kk_tPress         =       app.tPresses(ind_presses(kk));
                        kk_tRelease     =       app.tReleases(find(app.tReleases>=kk_tPress, 1, 'first'));
                        axes(app.SpikeAxes)
                        % Rectangle coordinates
                        x = [kk_tPress, kk_tRelease, kk_tRelease, kk_tPress]; % x-coordinates
                        y = [0, 0, numUnits + 1, numUnits + 1]; % y-coordinates
                        % Create the patch
                        hold(app.SpikeAxes, 'on'); % Retain existing plots
                        p = patch(app.SpikeAxes, x, y, 'k'); % Create a gray patch ('k' for black)
                        p.FaceAlpha = 0.1; % Set transparency
                        p.EdgeColor = 'none'; % Remove edge color
                    end
                    pressEnd = kk_tPress;
                    % Add 'poke' text in the top right corner of the poke patch
                    text(app.SpikeAxes, pressEnd, numUnits + 1, 'press', ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 10, ...
                        'Color', 'k'); % Text color matches the patch
                end
                hold(app.SpikeAxes, 'off'); % Release hold

                ind_triggers = find(app.tTriggers>=videoTimestamps(1) & app.tTriggers<=videoTimestamps(end));
                hold(app.SpikeAxes, 'on'); % Retain existing plots
                if ~isempty(ind_triggers)
                    triggerTimes = app.tTriggers(ind_triggers); % Trigger times
                    duration = 250; % ms, duration of tone
                    for it = 1:length(triggerTimes)
                        t=triggerTimes(it);
                        % Rectangle coordinates
                        x = [t, t + duration, t + duration, t]; % x-coordinates
                        y = [0, 0, numUnits + 1, numUnits + 1]; % y-coordinates
                        % Create the patch
                        p = patch(app.SpikeAxes, x, y, 'r'); % Red patch for trigger
                        p.FaceAlpha = 0.2; % Set transparency
                        p.EdgeColor = 'none'; % Remove edge color
                    end
                    triggerEnd = t;
                    % Add 'poke' text in the top right corner of the poke patch
                    text(app.SpikeAxes, triggerEnd, numUnits + 1, 'trigger', ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 10, ...
                        'Color', 'k'); % Text color matches the patch
                end
                hold(app.SpikeAxes, 'off'); % Retain existing plots

                ind_pokes = find(app.tPokes>=videoTimestamps(1) & app.tPokes<=videoTimestamps(end));
                hold(app.SpikeAxes, 'on'); % Retain existing plots
                if ~isempty(ind_pokes)
                    pokeTimes = app.tPokes(ind_pokes); % Trigger times
                    duration = 250; % pseudo duration
                    for it = 1:length(pokeTimes)
                        t=pokeTimes(it);
                         % Rectangle coordinates
                        x = [t, t + duration, t + duration, t]; % x-coordinates
                        y = [0, 0, numUnits + 1, numUnits + 1]; % y-coordinates
                        % Create the patch
                        p = patch(app.SpikeAxes, x, y, 'g'); % blue patch for poke
                        p.FaceAlpha = 0.1; % Set transparency
                        p.EdgeColor = 'none'; % Remove edge color
                    end
                    pokeEnd = t;
                    % Add 'poke' text in the top right corner of the poke patch
                    text(app.SpikeAxes, pokeEnd, numUnits + 1, 'poke', ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 10, ...
                        'Color', 'k'); % Text color matches the patch
                end
                hold(app.SpikeAxes, 'off'); % Retain existing plots

                % for better visualization, plot all data in a new figure
                if app.PlotrasteroutsideCheckBox.Value==1
                figure(10); clf(10)
                set(10, 'InnerPosition', [844 230 560 720])
                ha1 = subplot(2, 1 ,1);
                ha2 = subplot(2, 1, 2);

                ha1.NextPlot = 'add';
                ha1.XLim = [videoTimestamps(1) videoTimestamps(end)];
                ha2.NextPlot = 'add';
                ha2.XLim = [videoTimestamps(1) videoTimestamps(end)];

                for unitIdx = 1:numUnits

                    unitNames{unitIdx} = [num2str(r.Units.SpikeNotes(unitIdx, 1)) '|' num2str(r.Units.SpikeNotes(unitIdx, 2))];
                    % Get spike timings for the unit
                    spikeTimings = r.Units.SpikeTimes(unitIdx).timings;
                    % Filter spike times within the video duration
                    validSpikeTimes = spikeTimings(spikeTimings >= videoTimestamps(1) & spikeTimings <= videoTimestamps(end));
                    if ~isempty(validSpikeTimes)
                        % Plot the valid spike times
                        line(ha1, [validSpikeTimes; validSpikeTimes], [unitIdx * ones(size(validSpikeTimes)); (unitIdx+1) * ones(size(validSpikeTimes))],...
                            'linewidth', 1, 'color', colors(unitIdx, :));
                                 sdfout =sdf_spktimes(validSpikeTimes, [videoTimestamps(1) videoTimestamps(end)], 10, 10);
                        % Plot the valid spike times
                        firingRateMax = max(max(sdfout(:, 2)), firingRateMax);
                        plot(ha2, sdfout(:, 1), sdfout(:, 2), 'color', colors(unitIdx, :),'linewidth', 1)
                    end
                end

                % add the time of current frame
                line(ha1, [1 1]*videoTimestamps(frameIndex),[0 unitIdx+1], 'color', 'm','linewidth', 2, 'linestyle', ':');
                ha1.YTick = (1:numUnits)+0.5;
                ha1.YTickLabel = unitNames;
                ha1.YLim = [0 numUnits+1];
                ha1.XTick = TimeStampTicks;
                ha1.XTickLabel = num2cell(IndTicks); 
                xlabel(ha1, '');
                ylabel(ha1, 'Unit Index');

                 % add the time of current frame
                ylabel(ha1, 'Unit Index');
                 % add the time of current frame
                ha2.YLim = [0 firingRateMax];
                ha2.YTick = [0 firingRateMax];
                ha2.YTickLabel=num2cell([0 firingRateMax]);
                ha2.XTick = TimeStampTicks;
                ha2.XTickLabel = num2cell(round(TimeStampTicks-TimeStampTicks(1)));

                hold(ha2, 'off');
                title(ha2, '');
                xlabel(ha2, 'Time (ms)');
                ylabel(ha2, 'Firing rate (Hz)');

                ha1.Units = 'normalized';
                ha1.InnerPosition = [0.1, 0.35, 0.4, 0.60];
                ha2.Units = 'normalized';
                ha2.InnerPosition = [0.1, 0.15, 0.4, 0.15];

                % Let's mark behavior events
                % Check if press and releases are there
                if  ~isempty(ind_presses)
                    for kk =1:length(ind_presses)
                        kk_tPress         =       app.tPresses(ind_presses(kk));
                        kk_tRelease     =       app.tReleases(find(app.tReleases>=kk_tPress, 1, 'first'));
                        axes(ha1)
                        % Rectangle coordinates
                        x = [kk_tPress, kk_tRelease, kk_tRelease, kk_tPress]; % x-coordinates
                        y = [0, 0, numUnits + 1, numUnits + 1]; % y-coordinates
                        % Create the patch
                        hold(ha1, 'on'); % Retain existing plots
                        p = patch(ha1, x, y, 'k'); % Create a gray patch ('k' for black)
                        p.FaceAlpha = 0.1; % Set transparency
                        p.EdgeColor = 'none'; % Remove edge color
                    end
                    pressEnd = kk_tPress;
                    % Add 'poke' text in the top right corner of the poke patch
                    text(ha1, pressEnd, numUnits + 1, 'press', ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 10, ...
                        'Color', 'k'); % Text color matches the patch
                end
                hold(ha1, 'off'); % Release hold

                ind_triggers = find(app.tTriggers>=videoTimestamps(1) & app.tTriggers<=videoTimestamps(end));
                hold(ha1, 'on'); % Retain existing plots
                if ~isempty(ind_triggers)
                    triggerTimes = app.tTriggers(ind_triggers); % Trigger times
                    duration = 250; % ms, duration of tone
                    for it = 1:length(triggerTimes)
                        t=triggerTimes(it);
                        % Rectangle coordinates
                        x = [t, t + duration, t + duration, t]; % x-coordinates
                        y = [0, 0, numUnits + 1, numUnits + 1]; % y-coordinates
                        % Create the patch
                        p = patch(ha1, x, y, 'r'); % Red patch for trigger
                        p.FaceAlpha = 0.2; % Set transparency
                        p.EdgeColor = 'none'; % Remove edge color
                    end

                    triggerEnd = t;
                    % Add 'poke' text in the top right corner of the poke patch
                    text(ha1, triggerEnd, numUnits + 1, 'trigger', ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 10, ...
                        'Color', 'k'); % Text color matches the patch
                end
                hold(ha1, 'off'); % Retain existing plots

                ind_pokes = find(app.tPokes>=videoTimestamps(1) & app.tPokes<=videoTimestamps(end));
                hold(ha1, 'on'); % Retain existing plots
                if ~isempty(ind_pokes)
                    pokeTimes = app.tPokes(ind_pokes); % Trigger times
                    duration = 250; % pseudo duration
                    for it = 1:length(pokeTimes)
                        t=pokeTimes(it);
                         % Rectangle coordinates
                        x = [t, t + duration, t + duration, t]; % x-coordinates
                        y = [0, 0, numUnits + 1, numUnits + 1]; % y-coordinates
                        % Create the patch
                        p = patch(ha1, x, y, 'g'); % blue patch for poke
                        p.FaceAlpha = 0.1; % Set transparency
                        p.EdgeColor = 'none'; % Remove edge color
                    end
                    pokeEnd = t;
                    % Add 'poke' text in the top right corner of the poke patch
                    text(ha1, pokeEnd, numUnits + 1, 'poke', ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 10, ...
                        'Color', 'k'); % Text color matches the patch
                end
                hold(ha1, 'off'); % Retain existing plots

                videoName =  extractBefore(app.ListofvideosListBox.Value, '.mp4');
                thisR = extractBefore(app.rName.Text, '.mat');

                annotation('textbox', ...
                    'String', strrep([thisR ' | ' videoName], '_', '-'), ...
                    'Units', 'normalized', ... % Use normalized units for the position
                    'Position', [0.2 0.95 0.8 0.05], ... % Normalized position for the annotation
                    'HorizontalAlignment', 'left', ... % Align text to the left
                    'FontSize', 10, ... % Set font size
                    'FontWeight', 'bold', ... % Set font weight
                    'EdgeColor', 'none'); % Remove the box around the text
                end

        end

        % Button pushed function: SendmoretrainingdataButton
        function SendmoretrainingdataButtonPushed(app, event)
            % extract current frame and save it to a designated folder for
            % future training
            this_frame = app.FrameImg.CData;
            if ~isempty(app.FrameImgSide)
                this_frame_side = app.FrameImgSide.CData;
            else
                this_frame_side = [];
            end
            button = event.Source; % Reference to the button
            % Check if the folder path is already set
            if isempty(button.UserData)
                % Prompt the user to select a folder
                folder_path = fullfile(pwd, 'NewTrainingFrames');
                if ~exist(folder_path,'dir')
                    mkdir(folder_path);
                end  
                % Save the folder path in UserData of the button
                button.UserData = folder_path;
                % Update app.DatadirectoryLabel to display the selected folder path
                app.DatadirectoryLabel.Text = sprintf('Data Directory: %s', folder_path);
            else
                folder_path = button.UserData; % Retrieve the saved folder path
            end
            % Get the video name from the ListBox and strip the .mp4 extension
            video_name = app.ListofvideosListBox.Value; % Get the selected video name
            [~, video_name_no_ext, ~] = fileparts(video_name); % Strip file extension

            % Generate a unique file name for the image
            timestamp = datestr(now, 'yyyymmdd_HHMMSSFFF'); % Timestamp for uniqueness
            file_name = sprintf('%s_frame_%s.png', video_name_no_ext, timestamp);
            file_path = fullfile(folder_path, file_name);
            % Save the current frame as an image file
            imwrite(this_frame, file_path);

            if ~isempty(this_frame_side)
                [~, fileName, ~] = fileparts(app.thisVideoSide);
                file_name = sprintf('%s_frame_%s.png', fileName, timestamp);
                file_path = fullfile(folder_path, file_name);
                if ~isempty(this_frame_side)
                    imwrite(this_frame_side, file_path);
                end
            end
            % Display confirmation message
            uialert(app.UIFigure, sprintf('Frame saved to %s', file_path), 'Save Successful');
        end

        % Value changed function: CleanDropDown
        function CleanDropDownValueChanged(app, event)
            value = app.CleanDropDown.Value;

            body =  app.BodypartsListBox.Value;
             eval(['x = app.dlcResults.' body '.x;']);
            eval(['y = app.dlcResults.' body '.y;']);
            eval(['likelihood = app.dlcResults.' body '.likelihood;']);

            % update x, y based on likelihood
            if strcmp(app.CleanDropDown.Value, 'Yes')
                [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                app.BodyScatter.XData = x_cleaned;
                app.BodyScatter.YData = y_cleaned;
            else
                app.BodyScatter.XData = x;
                app.BodyScatter.YData = y;
            end
            % Current frame
            app.CurrentFrameTracking.XData = app.BodyScatter.XData(round(app.VideoSlider.Value));
            app.CurrentFrameTracking.YData = app.BodyScatter.YData(round(app.VideoSlider.Value));

        end

        % Value changed function: BodypartsListBox
        function BodypartsListBoxValueChanged(app, event)

            body =  app.BodypartsListBox.Value;
            eval(['x = app.dlcResults.' body '.x']);
            eval(['y = app.dlcResults.' body '.y']);
            eval(['likelihood = app.dlcResults.' body '.likelihood']);

            % update x, y based on likelihood
            if strcmp(app.CleanDropDown.Value, 'Yes')
                [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                app.BodyScatter.XData = x_cleaned;
                app.BodyScatter.YData = y_cleaned;
            else
                app.BodyScatter.XData = x;
                app.BodyScatter.YData = y;
            end
            % Current frame
            app.CurrentFrameTracking.XData = app.BodyScatter.XData(round(app.VideoSlider.Value));
            app.CurrentFrameTracking.YData = app.BodyScatter.YData(round(app.VideoSlider.Value));
        end

        % Value changed function: LoadDLCtrackingCheckBox
        function LoadDLCtrackingCheckBoxValueChanged(app, event)

            if app.LoadDLCtrackingCheckBox.Value == 1 && ~isempty(app.VideoMeta)

                DLCTrackingFiles          = dir(fullfile(app.folderPath, '*.csv'));
                DLCTrackingFileNames = {DLCTrackingFiles.name};


                nums = cellfun(@(x) str2double(regexp(x, '\d+', 'match')), DLCTrackingFileNames);
                % Sort the files based on the numeric part
                [~, idx] = sort(nums);
                % Reorder the file list according to the sorted order
                sorted_files = DLCTrackingFileNames(idx);
                % Update the ListBox with the sorted file list (assumed ListBox name is ListBox)

                app.ListDLC.Items                    = sorted_files;
                
                % make sure the correct item is selected
                fileName = app.ListofvideosListBox.Value;
                selectedDLCFile = dir(fullfile(app.folderPath, [extractBefore(fileName, '.mp4'),'*.csv']));
                app.ListDLC.Value = selectedDLCFile.name;
                selectedDLCFilePath = fullfile(selectedDLCFile.folder, selectedDLCFile.name);
                dlcResults = read_dlc_table(selectedDLCFilePath);

                app.dlcResults = dlcResults;
                % Assuming dlcResults is already loaded or created
                % Extract field names from dlcResults
                fieldNames = fieldnames(dlcResults);

                % Assign field names to the list box in your app
                app.BodypartsListBox.Items = fieldNames;

                % Plot the first body part
                % Extract x, y, and likelihood from dlcResults.EarLTop

                body =  app.BodypartsListBox.Value;
                eval(['x = app.dlcResults.' body '.x;']);
                eval(['y = app.dlcResults.' body '.y;']);
                eval(['likelihood = app.dlcResults.' body '.likelihood;']);

                app.VideoAxes.NextPlot = 'add';
                app.BodyScatter = scatter(app.VideoAxes, x, y, 15, likelihood, 'filled'); % 15 is marker size

                colormap(app.VideoAxes, 'parula'); % Set colormap to 'hot'
                % make pseudo colorbar
                xcolor = ones(1, 100);
                ycolor = linspace(0, 1, 100);
                scatter(app.hbarAxes, xcolor, ycolor, 85, ycolor, 'filled', 's');
                app.hbarAxes.YLim = [0 1];
                app.hbarAxes.YAxisLocation='right';
                app.hbarAxes.XLim = [0.9 1.1];
                app.hbarAxes.Position(2) = 770;
                app.hbarAxes.Color='none';
                colormap(app.hbarAxes, 'parula'); % Set colormap to 'hot'

                % update x, y based on likelihood
                if strcmp(app.CleanDropDown.Value, 'Yes')
                    [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                    app.BodyScatter.XData = x_cleaned;
                    app.BodyScatter.YData = y_cleaned;
                end

                % Current frame
                app.CurrentFrameTracking = scatter(app.VideoAxes, app.BodyScatter.XData(app.VideoSlider.Value),...
                    app.BodyScatter.YData(app.VideoSlider.Value), 250, '+', ...
                    'MarkerEdgeColor', 'r', 'linewidth', 2);
                app.VideoAxes.NextPlot = 'replacechildren';
                app.extractSegment = [];

                % draw a body triangle
                if app.BodytriangleCheckBox.Value == 1

                    body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_1 =  'EarLTop';
                    eval(['x = app.dlcResults.' body_1 '.x;']);
                    eval(['y = app.dlcResults.' body_1 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_1_coord(1) = x_cleaned(app.VideoSlider.Value);
                        body_1_coord(2) = y_cleaned(app.VideoSlider.Value);
                    else
                        body_1_coord(1) = x(app.VideoSlider.Value);
                        body_1_coord(2) = y(app.VideoSlider.Value);
                    end

                    body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_2 =  'EarRTop';
                    eval(['x = app.dlcResults.' body_2 '.x;']);
                    eval(['y = app.dlcResults.' body_2 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_2_coord(1) = x_cleaned(app.VideoSlider.Value);
                        body_2_coord(2) = y_cleaned(app.VideoSlider.Value);
                    else
                        body_2_coord(1) = x(app.VideoSlider.Value);
                        body_2_coord(2) = y(app.VideoSlider.Value);
                    end

                    body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_3 =  'Tail';
                    eval(['x = app.dlcResults.' body_3 '.x;']);
                    eval(['y = app.dlcResults.' body_3 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_3_coord(1) = x_cleaned(app.VideoSlider.Value);
                        body_3_coord(2) = y_cleaned(app.VideoSlider.Value);
                    else
                        body_3_coord(1) = x(app.VideoSlider.Value);
                        body_3_coord(2) = y(app.VideoSlider.Value);
                    end

                    % draw this triangle
                    app.VideoAxes.NextPlot = 'add';
                    app.bodyTriangle = line(app.VideoAxes, [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)], [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2) ],...
                        'linewidth', 1, 'linestyle', ':', 'color', [0 1 1]);
                    app.VideoAxes.NextPlot = 'replacechildren';
                end
            end
        end

        % Button pushed function: MapspikestotrajectorysingleButton
        function MapspikestotrajectorysingleButtonPushed(app, event)
            []

            map_spikes(spike_times, coordinates);
            
        end

        % Button pushed function: MapspikestotrajectorypopButton
        function MapspikestotrajectorypopButtonPushed(app, event)
            
        end

        % Button pushed function: PrintsnapshotButton
        function PrintsnapshotButtonPushed(app, event)
            button = event.Source; % Reference to the button

            % Check if the folder path is already set
            if isempty(app.PrintsnapshotButton.UserData)
                selectedFolder = fullfile(pwd, 'EphysVideoSnapShots');
                if ~exist(selectedFolder, 'dir')
                    mkdir(selectedFolder);
                end
                savedFolderPath = selectedFolder; % Save the new folder path
                app.PrintsnapshotButton.UserData = savedFolderPath;
            end
            savedFolderPath = app.PrintsnapshotButton.UserData;

            % Update app.DatadirectoryLabel to display the selected folder path
            app.Snapshotdirectory.Text = sprintf('Data Directory: %s', savedFolderPath);
            % Generate a timestamped filename
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            filename = fullfile(savedFolderPath, ['snapshot_' timestamp '.png']);

                        % Capture the app window content
                        frame = getframe(app.UIFigure); % Capture the entire app window

                        % Save the snapshot as a PNG image
                        imwrite(frame.cdata, filename);

                        % Display a confirmation message
                        uialert(app.UIFigure, ['Snapshot saved to: ' filename], 'Snapshot Saved');

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             app.StartEditField.Value =  round(app.VideoSlider.Value);
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
              app.EndEditField.Value =  round(app.VideoSlider.Value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            body =  app.BodypartsListBox.Value;
            eval(['x = app.dlcResults.' body '.x;']);
            eval(['y = app.dlcResults.' body '.y;']);
            eval(['likelihood = app.dlcResults.' body '.likelihood;']);

            % update x, y based on likelihood
            if strcmp(app.CleanDropDown.Value, 'Yes')
                [x, y] = clean_coordinates(x, y, likelihood);
            end

            vinfo = app.VideoMeta.VideoInfo;
            tExtract = app.extractSegment;
            indExtract =  app.extractSegmentIndex;            
            app.UIAxesXT.NextPlot = 'replacechildren';
            scatter(app.UIAxesXT, tExtract, x(indExtract),35, 'filled');
            tBeg = tExtract(1);
            tEnd = tExtract(end);
            app.UIAxesXT.XLim = [tBeg, tEnd];
            app.UIAxesYT.NextPlot = 'replacechildren';
            scatter(app.UIAxesYT, tExtract, y(indExtract),35, 'filled');
            app.UIAxesYT.XLim = [tBeg, tEnd];

            tCurrentVideo = app.ListofvideosListBox.Value;
            tPress_current = sscanf(tCurrentVideo, '%*[^0-9]%d');
            tPress_current=tPress_current(1);
            app.UIAxesXT.XTickLabel = num2cell(round(app.UIAxesXT.XTick-tPress_current));
            app.UIAxesYT.XTickLabel = num2cell(round(app.UIAxesYT.XTick-tPress_current));

            % plot spikes
            if ~isempty(app.rData)
                % Get the loaded structure
                r = app.rData.r;
                cla(app.UIAxesExtractedSpikes);
                app.UIAxesExtractedSpikes.NextPlot = 'add';
                app.UIAxesExtractedSpikes.XLim = [tBeg, tEnd];
                hold(app.UIAxesExtractedSpikes, 'on');

                colors = jet(round(1.5*numel(r.Units.SpikeTimes)));
                unitNames = cell(1, numel(r.Units.SpikeTimes));
                for unitIdx = 1:numel(r.Units.SpikeTimes)

                    unitNames{unitIdx} = [num2str(r.Units.SpikeNotes(unitIdx, 1)) '|' num2str(r.Units.SpikeNotes(unitIdx, 2))];
                    % Get spike timings for the unit
                    spikeTimings = r.Units.SpikeTimes(unitIdx).timings;
                    % Filter spike times within the video duration
                    validSpikeTimes = spikeTimings(spikeTimings >= tBeg & spikeTimings <= tEnd);

                    if ~isempty(validSpikeTimes)
                        % Plot the valid spike times
                        line(app.UIAxesExtractedSpikes, [validSpikeTimes; validSpikeTimes], [unitIdx * ones(size(validSpikeTimes)); (unitIdx+1) * ones(size(validSpikeTimes))],...
                            'linewidth', 1, 'color', colors(unitIdx, :));
                    end
                    app.UIAxesExtractedSpikes.YLim = [0 numel(r.Units.SpikeTimes)+1];
                end
            end
            
            % Plot the pattern outside
                         % Save trajectories
            dlc_Results = app.dlcResults;
            TimeSelected = app.extractSegment;
            IndexTimeSelected = app.extractSegmentIndex;

            TrajExtracted = dlc_Results;
            TrajExtracted.IndexSelected = IndexTimeSelected;
            TrajExtracted.TimeSelected = TimeSelected;
            
            file_name = extractBefore(app.ListofvideosListBox.Value, '.mp4');
            new_traj_name = ['TrajExtracted_' file_name '.mat'];
            % Spikes.Videos.extract_trajectory(TrajExtracted,
            % app.BodypartsListBox.Value, 1, new_traj_name); old version
            body = app.BodypartsListBox.Value;
            Spikes.Videos.extract_traj_lift(TrajExtracted, 1, new_traj_name, body);

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
                       
            app.Lamp.Color = 'r';
            vinfo = app.VideoMeta.VideoInfo;
            tBeg = vinfo.EphysTimeStamps(app.StartEditField.Value);
            app.StartEditField.UserData = tBeg;

            tEnd = vinfo.EphysTimeStamps(app.EndEditField.Value);
            app.EndEditField.UserData = tEnd;

            indExtract = (app.StartEditField.Value:app.EndEditField.Value);
            tExtract = vinfo.EphysTimeStamps(indExtract);

            app.extractSegment = [app.extractSegment tExtract];
            app.extractSegment = sort(unique(app.extractSegment));
            [~, indExtract] = intersect(vinfo.EphysTimeStamps, tExtract);
            app.extractSegmentIndex = [app.extractSegmentIndex; indExtract];
            app.extractSegmentIndex = sort(unique(app.extractSegmentIndex)); 
            drawnow;
            pause(1)
            app.Lamp.Color = 'g';
            PlotButtonPushed(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.extractSegment = [];
            app.extractSegmentIndex = [];
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            % Callback for the new pushbutton
            % Ask the user to select a folde

            if isempty(app.TrajSavedPath)
                selectedFolder = fullfile(pwd, 'SideTrajExtracted');
                if ~exist(selectedFolder, 'dir')
                    mkdir(selectedFolder);
                    disp(['Created folder: ', selectedFolder]);
                end
                app.TrajSavedPath = selectedFolder; % Save the new folder path
            end
            selectedFolder = app.TrajSavedPath;

            % Look for files with prefix 'TrajExtracted_####.mat' in the folder
            filePattern = fullfile(selectedFolder, 'TrajExtracted_*.mat');
            files = dir(filePattern);

            % Clear the list box before adding new names
            app.ExtractedtrajsListBox.Items = {};

            % Process each file and add names to the list box
            cleanedNames = cell(1, length(files));
            for i = 1:length(files)
                % Get the file name without 'TrajExtracted' prefix and '.mat' suffix
                fileName = files(i).name;
                cleanedName = extractBetween(fileName, 'TrajExtracted_', '.mat');

                % Add cleaned name to the list box
                if ~isempty(cleanedName)
                    cleanedNames{i} = cleanedName{1};
                    % app.ExtractedtrajsListBox.Items{end+1} = cleanedName{1};
                end
            end
            nums = cellfun(@(x) str2double(regexp(x, '(?<=Top_Press_)\d+', 'match', 'once')), cleanedNames);
            [~, idx] = sort(nums);
            % Reorder the file list according to the sorted order
            sorted_files = cleanedNames(idx);
            % Update the ListBox with the sorted file list (assumed ListBox name is ListBox)
            app.ExtractedtrajsListBox.Items = sorted_files;

            % Display message based on results
            if isempty(files)
                disp('No files with the specified prefix were found in the selected folder.');
            else
                disp(['Loaded ', num2str(length(files)), ' files into the list box.']);
            end

            n_trajs = length(sorted_files);
            app.ExtractedtrajsListBoxLabel.Text = sprintf('N = %2.0d trajs', n_trajs);
            app.ExtractedtrajsListBoxLabel.HorizontalAlignment = 'left';
        end

        % Button pushed function: FindSideVideo
        function FindSideVideoPushed(app, event)
            % Ensure a video is selected in app.ListofvideosListBox
            tic
            if isempty(app.ListofvideosListBox.Value)
                uialert(app.UIFigure, 'Please select a video from the list box.', 'Selection Error');
                return;
            end

            % Extract the selected Side video index (######)
            selectedVideo = app.ListofvideosListBox.Value; % Example: 'Top_Press_000123.mp4'
            pattern = 'Side_Press_(\d+)\.mp4';
            tokens = regexp(selectedVideo, pattern, 'tokens');

            if isempty(tokens)
                uialert(app.UIFigure, 'Invalid file name format selected in the list box.', 'File Error');
                return;
            end

            selectedIndex = tokens{1}{1}; % Extract the ######
            % Ask user to select the Side video folder
            if isempty(app.SideVideoPath)
                % Ask user to select the Side video folder
                sideVideoFolder = uigetdir(pwd, 'Select the folder containing Side videos');

                if sideVideoFolder == 0
                    disp('Folder selection cancelled.');
                    return;
                end
                % Save the selected folder to app.SideVideoPath
                app.SideVideoPath = sideVideoFolder;
            else
                sideVideoFolder = app.SideVideoPath; % Use the saved folder
            end
            % Look for the Top video and meta file in the selected folder
            sideVideoName = fullfile(sideVideoFolder, ['Top_Press_', selectedIndex, '.mp4']);
            sideMetaName = fullfile(sideVideoFolder, ['Top_Press_', selectedIndex, '.mat']);
            app.thisVideoSide=sideVideoName;
            if ~exist(sideVideoName, 'file') || ~exist(sideMetaName, 'file')
                uialert(app.UIFigure, 'Corresponding Side video or meta file not found.', 'File Missing');
                app.SideVideoPath = [];
                return;
            end

            % Load Side meta file
            sideMetaData = load(sideMetaName, 'VideoInfo');
            if ~isfield(sideMetaData, 'VideoInfo')
                uialert(app.UIFigure, 'Invalid or corrupt meta file.', 'File Error');
                return;
            end

            % Extract the target timestamp from Top video
            targetTimestamp = app.VideoMeta.VideoInfo.EphysTimeStamps(round(app.VideoSlider.Value)); % Ensure this is a valid field
            % Find the closest frame in the Side video
            sideTimestamps = sideMetaData.VideoInfo.EphysTimeStamps;
            [t_dist, closestFrameIdx] = min(abs(sideTimestamps - targetTimestamp));

            if t_dist>10 % more than 10 ms is not acceptable
                clc
                disp('Cannot find corresponding frame-Side video is too short')
                return
            end
            
            % Display results and optionally play the side video at the matched frame
            disp(['Matched frame index in Top video: ', num2str(closestFrameIdx)]);
            disp(['Matched timestamp: ', num2str(sideTimestamps(closestFrameIdx)), ' seconds']);
            toc            
            % Example: Play the side video at the matched frame
            if isempty(app.UIAxesVideoSide.UserData)
                sideVideo = VideoReader(sideVideoName);
            else
                sideVideo = app.UIAxesVideoSide.UserData;
            end
            sideVideo.CurrentTime = (closestFrameIdx - 1) / sideVideo.FrameRate;
            while hasFrame(sideVideo)
                tic
                frame = readFrame(sideVideo);
                app.FrameImgSideOrg = frame;
                toc

                if ~isempty(app.ROI)
                    x_ = app.ROI(1);
                    y_ = app.ROI(2);
                    width = app.ROI(3);
                    height = app.ROI(4);
                    frame = frame(y_:y_+height-1, x_:x_+width-1, :); % Crop the ROI
                end

                if isempty(app.FrameImgSide)
                    app.FrameImgSide=imshow(frame, 'Parent', app.UIAxesVideoSide);
                else
                    app.FrameImgSide.CData  = frame;
                end
                app.UIAxesVideoSide.XLim = [0 size(frame, 2)];
                app.UIAxesVideoSide.YLim = [0 size(frame, 1)];

                drawnow;
                break; % Display only the matched frame
            end
      
            % Check if figure 10 exisit
            figHandle = 10;
            if ishandle(figHandle) && strcmp(get(figHandle, 'Type'), 'figure')
                tic
                disp('Figure exists.');
                ha3 = axes('Parent', 10);
                ha3.NextPlot = 'add';
                ha3.Units = 'normalized';
                ha3.InnerPosition = [0.55, 0.1, 0.45, 0.45];
                imshow(frame, 'Parent', ha3);
                % adjust plot size
                frame_height = size(frame, 1);
                frame_width = size(frame, 2);
                ha3.XLim = [0 frame_width];
                ha3.YLim = [0 frame_height];
                ha3.InnerPosition = [0.53, 0.05, 0.45, 0.45*frame_height/frame_width];

                % also show top frame
                frame = app.FrameImg.CData;
                ha4 = axes('Parent', 10);
                ha4.NextPlot = 'add';
                ha4.Units = 'normalized';
                ha4.InnerPosition = [0.55, 0.55, 0.45, 0.45];
                imshow(frame, 'Parent', ha4);

                % adjust plot size
                frame_height = size(frame, 1);
                frame_width = size(frame, 2);
                ha4.XLim = [0 frame_width];
                ha4.YLim = [0 frame_height];
                ha4.InnerPosition = [0.53, 0.55, 0.45, 0.45*frame_height/frame_width];

                if app.SavesideautomaticallyCheckBox.Value == 1
                    Save_Fig10ButtonPushed(app)
                end
                toc
            else
                disp('Figure does not exist.');
            end
        end

        % Button down function: SpikeAxes
        function SpikeAxesButtonDown(app, event)
            
        end

        % Button pushed function: saveTraj
        function saveTrajButtonPushed(app, event)
             % Save trajectories
            dlc_Results = app.dlcResults;
            TimeSelected = app.extractSegment;
            IndexTimeSelected = app.extractSegmentIndex;

            TrajExtracted = dlc_Results;
            TrajExtracted.IndexSelected = IndexTimeSelected;
            TrajExtracted.TimeSelected = TimeSelected;
            % If no folder path is saved, ask user to select one
            if isempty(app.TrajSavedPath)
                selectedFolder = fullfile(pwd, 'SideTrajExtracted');
                if ~exist(selectedFolder, 'dir')
                    mkdir(selectedFolder);
                    disp(['Created folder: ', selectedFolder]);
                end
                app.TrajSavedPath = selectedFolder; % Save the new folder path
            end
            savedFolderPath = app.TrajSavedPath;

            % Display the saved folder path
            file_name = extractBefore(app.ListofvideosListBox.Value, '.mp4');
            new_traj_name = ['TrajExtracted_' file_name '.mat'];
            save(fullfile(savedFolderPath, new_traj_name), 'TrajExtracted');
            disp(['Data will be saved to: ', fullfile(savedFolderPath, new_traj_name)]);
           % Spikes.Videos.extract_trajectory(TrajExtracted, app.BodypartsListBox.Value, 1, new_traj_name)

            CheckButtonPushed(app)
            % % Put the name to the listbox
            % if sum(ismember(app.ExtractedtrajsListBox.Items, 'Item 1'))>0
            %     app.ExtractedtrajsListBox.Items = {};
            %     app.ExtractedtrajsListBox.Items{1} = extractAfter(new_traj_name, 'TrajExtracted_');
            % else
            %     if sum(ismember(app.ExtractedtrajsListBox.Items, extractAfter(new_traj_name, 'TrajExtracted_')))==0
            %         app.ExtractedtrajsListBox.Items = [app.ExtractedtrajsListBox.Items extractAfter(new_traj_name, 'TrajExtracted_')];
            %     end
            % end
            app.Lamp_2.Color = 'g';
            % move item in the list box
            % Get the current value and list of items in the ListBox
            currentValue = app.ListofvideosListBox.Value;
            allItems = app.ListofvideosListBox.Items;

            % Find the index of the current value
            currentIndex = find(strcmp(allItems, currentValue), 1);

            % Ensure the current index is valid and move to the next item
            if ~isempty(currentIndex) && currentIndex < numel(allItems)
                nextIndex = currentIndex + 1; % Move to the next item
                app.ListofvideosListBox.Value = allItems{nextIndex}; % Update the selected value
                % Manually trigger the ValueChanged callback
                app.ListofvideosListBoxValueChanged(app); % Call the associated callback function
            else
                % Handle case when already at the last item
                uialert(app.UIFigure, 'You are already at the last item.', 'End of List');
            end
            CheckButtonPushed(app)
        end

        % Button pushed function: DeleteitButton
        function DeleteitButtonPushed(app, event)
            % Use this to delete the file in
            savedFolderPath = app.TrajSavedPath;
            % Display the saved folder path
            app.TrajSavedPath = savedFolderPath;
            file_name = extractBefore(app.ListofvideosListBox.Value, '.mp4');
            new_traj_name = ['TrajExtracted_' file_name '.mat'];
            disp(['Data will be saved to: ', fullfile(savedFolderPath, new_traj_name)]);
            % Check if the file already exists, and if so, delete it
            existing_file = fullfile(savedFolderPath, new_traj_name);
            if exist(existing_file, 'file')
                delete(existing_file);
            end
            CheckButtonPushed(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Check if a video is loaded
            if isempty(app.VideoAxes.UserData) || ~isvalid(app.VideoAxes.UserData)
                disp('No video loaded.');
                return;
            end

            % Get the total number of frames in the video
            totalFrames = floor(app.VideoAxes.UserData.Duration * app.VideoAxes.UserData.FrameRate);
            % Get the current frame index from the slider
            currentFrame = round(app.VideoSlider.Value);
            % Loop through frames starting from the current frame
            if currentFrame+1<totalFrames
                frameIndex = currentFrame+1;
                % Update the current time of the video
                app.VideoAxes.UserData.CurrentTime = (frameIndex - 1) / app.VideoAxes.UserData.FrameRate;
                % Read and display the current frame
                frame = readFrame(app.VideoAxes.UserData);
                app.FrameImg.CData  = frame;                %imshow(frame, 'Parent', app.VideoAxes);

                % Compute time based on video time stamps
                FrameTime =  app.VideoMeta.VideoInfo.VideoTimeStamps(frameIndex)-app.VideoMeta.VideoInfo.VideoTimeStamps(1);
                % Update frame time
                if isprop(app, 'TimeLabel') % Ensure the label exists
                    app.TimeLabel.Text = sprintf('t (ms) = %4.0d', FrameTime);
                end

                % Update the slider value
                app.VideoSlider.Value = frameIndex;
                % Change the CurrentFrameLine position
                if isprop(app, 'CurrentFrameLine') % Ensure the label exists
                    videoTimestamps  = app.VideoMeta.VideoInfo.EphysTimeStamps;
                    app.CurrentFrameLine.XData = [1 1]*videoTimestamps(frameIndex);
                end

                % Update the current tracking point
                body =  app.BodypartsListBox.Value;
                eval(['likelihood = app.dlcResults.' body '.likelihood;']);

                % Current frame
                app.CurrentFrameTracking.XData = app.BodyScatter.XData(round(app.VideoSlider.Value));
                app.CurrentFrameTracking.YData = app.BodyScatter.YData(round(app.VideoSlider.Value));

                % Update label
                app.TrackingData.Text = sprintf('x=%2.2f, y=%2.2f, l=%2.2f',app.BodyScatter.XData(round(app.VideoSlider.Value)),...
                    app.BodyScatter.YData(round(app.VideoSlider.Value)), likelihood(round(app.VideoSlider.Value)));

                % draw a body triangle
                if app.BodytriangleCheckBox.Value == 1
                    body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_1 =  'LeftPaw';
                    eval(['x = app.dlcResults.' body_1 '.x;']);
                    eval(['y = app.dlcResults.' body_1 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_1_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_1_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_1_coord(1) = x(round(app.VideoSlider.Value));
                        body_1_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_2 =  'RightPaw';
                    eval(['x = app.dlcResults.' body_2 '.x;']);
                    eval(['y = app.dlcResults.' body_2 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_2_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_2_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_2_coord(1) = x(round(app.VideoSlider.Value));
                        body_2_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_3 =  'LeftEar';
                    eval(['x = app.dlcResults.' body_3 '.x;']);
                    eval(['y = app.dlcResults.' body_3 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_3_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_3_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_3_coord(1) = x(round(app.VideoSlider.Value));
                        body_3_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    try
                        app.bodyTriangle.XData = [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)];
                        app.bodyTriangle.YData = [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2)];
                    end
                end

                % Update the frame number label
                app.Label.Text = sprintf('Frame: %d', frameIndex);

                if app.PlayingbothsidesCheckBox.Value == 1
                    FindSideVideoPushed(app)
                end

            end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
              % Check if a video is loaded
            if isempty(app.VideoAxes.UserData) || ~isvalid(app.VideoAxes.UserData)
                disp('No video loaded.');
                return;
            end

            % Get the total number of frames in the video
            % Get the current frame index from the slider
            currentFrame = round(app.VideoSlider.Value);
            % Loop through frames starting from the current frame
            if currentFrame-1>0
                frameIndex = currentFrame-1;
                % Update the current time of the video
                app.VideoAxes.UserData.CurrentTime = (frameIndex - 1) / app.VideoAxes.UserData.FrameRate;
                % Read and display the current frame
                frame = readFrame(app.VideoAxes.UserData);
                app.FrameImg.CData  = frame;                %imshow(frame, 'Parent', app.VideoAxes);

                % Compute time based on video time stamps
                FrameTime =  app.VideoMeta.VideoInfo.VideoTimeStamps(frameIndex)-app.VideoMeta.VideoInfo.VideoTimeStamps(1);
                % Update frame time
                if isprop(app, 'TimeLabel') % Ensure the label exists
                    app.TimeLabel.Text = sprintf('t (ms) = %4.0d', FrameTime);
                end

                % Update the slider value
                app.VideoSlider.Value = frameIndex;
                % Change the CurrentFrameLine position
                if isprop(app, 'CurrentFrameLine') % Ensure the label exists
                    videoTimestamps  = app.VideoMeta.VideoInfo.EphysTimeStamps;
                    app.CurrentFrameLine.XData = [1 1]*videoTimestamps(frameIndex);
                end

                % Update the current tracking point
                body =  app.BodypartsListBox.Value;
                eval(['likelihood = app.dlcResults.' body '.likelihood;']);

                % Current frame
                app.CurrentFrameTracking.XData = app.BodyScatter.XData(round(app.VideoSlider.Value));
                app.CurrentFrameTracking.YData = app.BodyScatter.YData(round(app.VideoSlider.Value));

                % Update label
                app.TrackingData.Text = sprintf('x=%2.2f, y=%2.2f, l=%2.2f',app.BodyScatter.XData(round(app.VideoSlider.Value)),...
                    app.BodyScatter.YData(round(app.VideoSlider.Value)), likelihood(round(app.VideoSlider.Value)));

                % draw a body triangle
                if app.BodytriangleCheckBox.Value == 1
                    body_1_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_1 =  'LeftPaw';
                    eval(['x = app.dlcResults.' body_1 '.x;']);
                    eval(['y = app.dlcResults.' body_1 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_1 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_1_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_1_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_1_coord(1) = x(round(app.VideoSlider.Value));
                        body_1_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    body_2_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_2 =  'RightPaw';
                    eval(['x = app.dlcResults.' body_2 '.x;']);
                    eval(['y = app.dlcResults.' body_2 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_2 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_2_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_2_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_2_coord(1) = x(round(app.VideoSlider.Value));
                        body_2_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    body_3_coord = zeros(1, 2); % x, y of first body part in current frame
                    body_3 =  'LeftEar';
                    eval(['x = app.dlcResults.' body_3 '.x;']);
                    eval(['y = app.dlcResults.' body_3 '.y;']);
                    eval(['likelihood = app.dlcResults.' body_3 '.likelihood;']);
                    if strcmp(app.CleanDropDown.Value, 'Yes')
                        [x_cleaned, y_cleaned] = clean_coordinates(x, y, likelihood);
                        body_3_coord(1) = x_cleaned(round(app.VideoSlider.Value));
                        body_3_coord(2) = y_cleaned(round(app.VideoSlider.Value));
                    else
                        body_3_coord(1) = x(round(app.VideoSlider.Value));
                        body_3_coord(2) = y(round(app.VideoSlider.Value));
                    end

                    try
                        app.bodyTriangle.XData = [body_1_coord(1) body_2_coord(1) body_3_coord(1) body_1_coord(1)];
                        app.bodyTriangle.YData = [body_1_coord(2) body_2_coord(2) body_3_coord(2) body_1_coord(2)];
                    end
                end

                % Update the frame number label
                app.Label.Text = sprintf('Frame: %d', frameIndex);

                if app.PlayingbothsidesCheckBox.Value == 1
                    FindSideVideoPushed(app)
                end

            end
        end

        % Callback function
        function ExpandButtonPushed(app, event)
       
        end

        % Button pushed function: Save_Fig10Button
        function Save_Fig10ButtonPushed(app, event)

            videoName =  extractBefore(app.ListofvideosListBox.Value, '.mp4');
            thisR = extractBefore(app.rName.Text, '.mat');
            % Define the frame name (can be customized further)
            FrameName = [videoName '_' thisR];
            if ishandle(10) && strcmp(get(10, 'Type'), 'figure')
                % Get saved directory from the button's UserData
                saveDir = get(app.Save_Fig10Button, 'UserData');
                % If saveDir is empty, ask the user for the directory
                if isempty(saveDir)
                    saveDir = fullfile(pwd,  'FrameSaved');
                    if ~exist(saveDir, 'dir')
                        mkdir(saveDir);
                    end                   
                    % Save the directory in the button's UserData for future use
                    set(app.Save_Fig10Button, 'UserData', saveDir);
                end

                % % Check for existing files and create a unique file name
                % baseName = FrameName;
                % counter = 1;
                % while exist(fullfile(saveDir, [FrameName, '.png']), 'file')
                %     FrameName = sprintf('%s_%d', baseName, counter);
                %     counter = counter + 1;
                % end

                % Save the figure to the directory
                savePath = fullfile(saveDir, [FrameName, '.png']);
                print(10, savePath, '-dpng'); % Export the figure as an image
                disp(['Figure 10 saved to: ', savePath]);

                % Save the figure to the directory
                savePath = fullfile(saveDir, FrameName);
                print(10, savePath, '-dpng'); % Export the figure as an image
                % print(fig,'MySavedPlot','-dpng')
                disp(['Figure 10 saved to: ', savePath]);
            else
                disp('Figure 10 does not exist.');
            end
        end

        % Button pushed function: ExportliftingandturningButton
        function ExportliftingandturningButtonPushed(app, event)
            % Read current time

            videoTimestamps  = app.VideoMeta.VideoInfo.EphysTimeStamps;
            % add the time of current frame
            frameIndex = round(app.VideoSlider.Value);
            tLift_current = videoTimestamps(frameIndex);
            % Read press time
            tCurrentVideo = app.ListofvideosListBox.Value;
            tPress_current = sscanf(tCurrentVideo, '%*[^0-9]%d');
            tPress_current=tPress_current(1);
            % Read paw preference
            paw_pref = app.PawDropDown.Value;
                     % Read turning preference
            turn_pref = app.TurningDropDown.Value;

            current_table = table({tCurrentVideo}, tLift_current, tPress_current, {paw_pref}, {turn_pref},...
                'VariableNames', {'vidName', 'tLift', 'tPress', 'Paw', 'Turn'});

            rName_ = extractBefore(app.rName.Text, '.mat');
            % Define the filename pattern
            pattern = ['LiftTime_' rName_ '.csv'];

            % Search for matching files in the current directory
            files = dir(pattern);

            if isempty(files)
                % No matching file found, create a new one
                filename = pattern; % Default name or your desired logic for new file
                writetable(current_table, filename);
                disp(['File created: ', filename]);
            else
                % At least one matching file found, load the first one
                filename = files(1).name; % Use the first match (or apply custom logic if needed)
                loaded_table = readtable(filename);
                current_table = [loaded_table; current_table];
                % [~, uniqueIdx] = unique(current_table.vidName, 'stable'); % 'stable' preserves original order
                % current_table = current_table(uniqueIdx, :);
                % i want to keep the last occurence of each unique vidName
                % Reverse the table order to prioritize the last occurrences
                reversed_table = flipud(current_table);
                % Find unique entries based on 'vidName'
                [~, uniqueIdx] = unique(reversed_table.vidName, 'stable');
                % Select the rows corresponding to unique indices
                unique_table = reversed_table(uniqueIdx, :);
                % Reverse the order back to restore original sequence (last occurrences kept)
                current_table = flipud(unique_table);

                % Display the result
                disp(current_table);
                app.LiftTable.Data = current_table;
                writetable(current_table, filename);
                disp(['File loaded: ', filename]);
            end

        end

        % Value changed function: TurningDropDown
        function TurningDropDownValueChanged(app, event)
            value = app.TurningDropDown.Value;
            
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)

            if ~isempty(app.ROI)
                app.ROI = [];
                app.FrameImgSide.CData =app.FrameImgSideOrg;
                return
            end
            % Display the image
            img = app.FrameImgSide.CData; % Assuming the image is stored in FrameImgSide
            figure;
            imshow(img);
            title('Click two points to define the ROI');

            % Let the user select two points
            [x, y] = ginput(2); % User clicks two points

            % Compute the rectangle's position [x, y, width, height]
            xMin = round(min(x));
            yMin = round(min(y));
            width = round(abs(x(2) - x(1)));
            height = round(abs(y(2) - y(1)));
            app.ROI = [xMin, yMin, width, height]; % Save to app.ROI

            % Display the ROI rectangle on the figure
            hold on;
            rectangle('Position', app.ROI, 'EdgeColor', 'r', 'LineWidth', 2);
            hold off;

            % Close the temporary figure
            pause(1); % Pause to show the rectangle briefly
            close(gcf);

            disp(['ROI saved: [', num2str(app.ROI), ']']);


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % load trajectory trajectory
            if isempty(app.TrajSavedPath)
                selectedFolder = fullfile(pwd, 'TrajExtracted');
                if ~exist(selectedFolder, 'dir')
                    mkdir(selectedFolder);
                    disp(['Created folder: ', selectedFolder]);
                end
                app.TrajSavedPath = selectedFolder; % Save the new folder path
            end
            traj_path = app.TrajSavedPath;

            % next, we need to check if 'TrajExtracted_..._###.mat' exist
            file_name = extractBefore(app.ListofvideosListBox.Value, '.mp4');
            traj_name = ['TrajExtracted_' file_name '.mat'];
            % now start to look for this trajectory
            file_lookfor = dir(fullfile(traj_path, traj_name));

            if ~isempty(file_lookfor)

                this_traj = load(fullfile(traj_path, traj_name));
                index_selected = this_traj.TrajExtracted.IndexSelected;
                app.extractSegmentIndex = index_selected;
                app.extractSegment = this_traj.TrajExtracted.TimeSelected;

                PlotButtonPushed(app)
            end

        end

        % Value changed function: ExtractedtrajsListBox
        function ExtractedtrajsListBoxValueChanged(app, event)
            value = app.ExtractedtrajsListBox.Value;
            app.ListofvideosListBox.Value = [value '.mp4'];
            ListofvideosListBoxValueChanged(app);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
            % Find out the press time. 
            tCurrentVideo = app.ListofvideosListBox.Value;
            tPress_current = sscanf(tCurrentVideo, '%*[^0-9]%d');
            tPress_current=tPress_current(1);
            % get the body trajectory.
            body =  app.BodypartsListBox.Value;
            eval(['x = app.dlcResults.' body '.x;']);
            eval(['y = app.dlcResults.' body '.y;']);
            eval(['like = app.dlcResults.' body '.likelihood;']);

            vinfo = app.VideoMeta.VideoInfo;
            tVid = vinfo.EphysTimeStamps;

            [~, t] = min(abs(tVid - tPress_current));                  % known good time point
            d_max = 10.0;              % initial distance threshold (in pixels, adjust as needed)
            like_threshold = 0.9;     % likelihood threshold

            % Function to compute Euclidean distance
            compute_distance = @(x1, y1, x2, y2) sqrt((x1 - x2)^2 + (y1 - y2)^2);

            % Initialize array to store good time points
            good_points = t;

            % Trace backward (before t)
            t_current = t;
            recent_distances = [];
            while t_current > 1
                t_next = t_current - 1;
                if like(t_next) <= like_threshold
                    break;
                end
                d = compute_distance(x(t_current), y(t_current), x(t_next), y(t_next));
                if d > d_max
                    break;
                end
                good_points = [good_points, t_next];
                recent_distances = [recent_distances, d];
                if length(recent_distances) > 5  % Update threshold dynamically
                    d_max = max(100, mean(recent_distances(end-4:end)) + 10*std(recent_distances(end-4:end)));
                    disp(d_max)
                end
                t_current = t_next;
            end

            % Trace forward (after t)
            t_current = t;
            recent_distances = [];
            while t_current < length(x)
                t_next = t_current + 1;
                if like(t_next) <= like_threshold
                    break;
                end
                d = compute_distance(x(t_current), y(t_current), x(t_next), y(t_next));
                if d > d_max
                    break;
                end
                good_points = [good_points, t_next];
                recent_distances = [recent_distances, d];
                if length(recent_distances) > 5  % Update threshold dynamically
                    d_max = max(100, mean(recent_distances(end-4:end)) + 10*std(recent_distances(end-4:end)));
                end
                t_current = t_next;
            end

            % Sort and display good time points
            good_points = sort(good_points);
            disp('Good time points:');
            disp(good_points);

            if length(good_points)>10
                app.StartEditField.Value = good_points(1);
                app.EndEditField.Value = good_points(end);
                app.VideoSlider.Value=good_points(1);
                VideoSliderValueChanged(app, event);
                AddButtonPushed(app, event);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VideoAxes = uiaxes(app.UIFigure);
            title(app.VideoAxes, 'Video')
            zlabel(app.VideoAxes, 'Z')
            app.FiringRateAxes = uiaxes(app.UIFigure);
            title(app.FiringRateAxes, 'Firing rate')
            xlabel(app.FiringRateAxes, 'X')
            ylabel(app.FiringRateAxes, 'Y')
            zlabel(app.FiringRateAxes, 'Z')
            app.SpikeAxes = uiaxes(app.UIFigure);
            title(app.SpikeAxes, 'Spike raster')
            ylabel(app.SpikeAxes, 'Y')
            zlabel(app.SpikeAxes, 'Z')
            app.SpikeAxes.XTick = [];
            app.SpikeAxes.ButtonDownFcn = createCallbackFcn(app, @SpikeAxesButtonDown, true);
            app.hbarAxes = uiaxes(app.UIFigure);
            ylabel(app.hbarAxes, 'likelihood')
            zlabel(app.hbarAxes, 'Z')
            app.hbarAxes.XTick = [];
            app.hbarAxes.YTick = [0 1];
            app.hbarAxes.YTickLabel = '';
            app.UIAxesXT = uiaxes(app.UIFigure);
            ylabel(app.UIAxesXT, 'x(t)')
            zlabel(app.UIAxesXT, 'Z')
            app.UIAxesYT = uiaxes(app.UIFigure);
            ylabel(app.UIAxesYT, 't(t)')
            zlabel(app.UIAxesYT, 'Z')
            app.UIAxesExtractedSpikes = uiaxes(app.UIFigure);
            ylabel(app.UIAxesExtractedSpikes, 'spikes')
            zlabel(app.UIAxesExtractedSpikes, 'Z')
            app.UIAxesVideoSide = uiaxes(app.UIFigure);
            zlabel(app.UIAxesVideoSide, 'Z')
            app.UIAxesVideoSide.XTick = [];
            app.UIAxesVideoSide.YTick = [];
            app.SelectvideosButton = uibutton(app.UIFigure, 'push');
            app.SelectvideosButton.ButtonPushedFcn = createCallbackFcn(app, @SelectvideosButtonPushed, true);
            app.SelectvideosButton.Tooltip = {'Choose one video, but the program will load all videos in the same folder with common tags'};
            app.SelectvideosButton.Text = 'Select videos';
            app.ListofvideosListBoxLabel = uilabel(app.UIFigure);
            app.ListofvideosListBoxLabel.Text = 'List of videos';
            app.ListofvideosListBox = uilistbox(app.UIFigure);
            app.ListofvideosListBox.ValueChangedFcn = createCallbackFcn(app, @ListofvideosListBoxValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.VideoSlider = uislider(app.UIFigure);
            app.VideoSlider.ValueChangedFcn = createCallbackFcn(app, @VideoSliderValueChanged, true);
            app.VideoSlider.Tag = 'VideoSlider';
            app.VideoSlider.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = 'FrameNumber';
            app.Label.Text = '0';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = '';
            app.LoadrButton = uibutton(app.UIFigure, 'push');
            app.LoadrButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrButtonPushed, true);
            app.LoadrButton.Text = 'Load r';
            app.rName = uilabel(app.UIFigure);
            app.SpikeTable = uitable(app.UIFigure);
            app.SpikeTable.ColumnName = {'Ch'; 'Unit'; 'Quality'};
            app.SpikeTable.RowName = {};
            app.PlotspikesButton = uibutton(app.UIFigure, 'push');
            app.PlotspikesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotspikesButtonPushed, true);
            app.PlotspikesButton.Text = 'Plot spikes';
            app.LoadDLCtrackingCheckBox = uicheckbox(app.UIFigure);
            app.LoadDLCtrackingCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadDLCtrackingCheckBoxValueChanged, true);
            app.LoadDLCtrackingCheckBox.Text = 'Load DLC tracking';
            app.LoadDLCtrackingCheckBox.Value = true;
            app.ListDLC = uilistbox(app.UIFigure);
            app.BodypartsListBoxLabel = uilabel(app.UIFigure);
            app.BodypartsListBoxLabel.Text = 'Body parts';
            app.BodypartsListBox = uilistbox(app.UIFigure);
            app.BodypartsListBox.ValueChangedFcn = createCallbackFcn(app, @BodypartsListBoxValueChanged, true);
            app.CleanDropDownLabel = uilabel(app.UIFigure);
            app.CleanDropDownLabel.Text = 'Clean';
            app.CleanDropDown = uidropdown(app.UIFigure);
            app.CleanDropDown.Items = {'No', 'Yes'};
            app.CleanDropDown.ValueChangedFcn = createCallbackFcn(app, @CleanDropDownValueChanged, true);
            app.CleanDropDown.Value = 'No';
            app.SendmoretrainingdataButton = uibutton(app.UIFigure, 'push');
            app.SendmoretrainingdataButton.ButtonPushedFcn = createCallbackFcn(app, @SendmoretrainingdataButtonPushed, true);
            app.SendmoretrainingdataButton.Text = 'Send more training data';
            app.DatadirectoryLabel = uilabel(app.UIFigure);
            app.DatadirectoryLabel.Text = 'Data directory';
            app.MapspikestotrajectorysingleButton = uibutton(app.UIFigure, 'push');
            app.MapspikestotrajectorysingleButton.ButtonPushedFcn = createCallbackFcn(app, @MapspikestotrajectorysingleButtonPushed, true);
            app.MapspikestotrajectorysingleButton.Text = 'Map spikes to trajectory (single)';
            app.MapspikestotrajectorypopButton = uibutton(app.UIFigure, 'push');
            app.MapspikestotrajectorypopButton.ButtonPushedFcn = createCallbackFcn(app, @MapspikestotrajectorypopButtonPushed, true);
            app.MapspikestotrajectorypopButton.Text = 'Map spikes to trajectory (pop)';
            app.PrintsnapshotButton = uibutton(app.UIFigure, 'push');
            app.PrintsnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @PrintsnapshotButtonPushed, true);
            app.PrintsnapshotButton.Text = 'Print snapshot';
            app.Snapshotdirectory = uilabel(app.UIFigure);
            app.Snapshotdirectory.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.UIFigure, 'numeric');
            app.saveTraj = uibutton(app.UIFigure, 'push');
            app.saveTraj.ButtonPushedFcn = createCallbackFcn(app, @saveTrajButtonPushed, true);
            app.saveTraj.Text = 'Extract trajectory vs spikes here';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.BodytriangleCheckBox = uicheckbox(app.UIFigure);
            app.BodytriangleCheckBox.Text = 'Body triangle';
            app.BodytriangleCheckBox.Value = true;
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExtractedtrajsListBoxLabel = uilabel(app.UIFigure);
            app.ExtractedtrajsListBoxLabel.Text = 'Extracted trajs';
            app.ExtractedtrajsListBox = uilistbox(app.UIFigure);
            app.ExtractedtrajsListBox.ValueChangedFcn = createCallbackFcn(app, @ExtractedtrajsListBoxValueChanged, true);
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.TrackingData = uilabel(app.UIFigure);
            app.TrackingData.Text = 'Label2';
            app.FindSideVideo = uibutton(app.UIFigure, 'push');
            app.FindSideVideo.ButtonPushedFcn = createCallbackFcn(app, @FindSideVideoPushed, true);
            app.FindSideVideo.Text = 'Top';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.DeleteitButton = uibutton(app.UIFigure, 'push');
            app.DeleteitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteitButtonPushed, true);
            app.DeleteitButton.Text = 'Delete it';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.Save_Fig10Button = uibutton(app.UIFigure, 'push');
            app.Save_Fig10Button.ButtonPushedFcn = createCallbackFcn(app, @Save_Fig10ButtonPushed, true);
            app.Save_Fig10Button.Text = 'Save_Fig10';
            app.PlayingbothsidesCheckBox = uicheckbox(app.UIFigure);
            app.PlayingbothsidesCheckBox.Text = 'Playing both sides';
            app.SavesideautomaticallyCheckBox = uicheckbox(app.UIFigure);
            app.SavesideautomaticallyCheckBox.Text = 'Save side automatically';
            app.ExportliftingandturningButton = uibutton(app.UIFigure, 'push');
            app.ExportliftingandturningButton.ButtonPushedFcn = createCallbackFcn(app, @ExportliftingandturningButtonPushed, true);
            app.ExportliftingandturningButton.Text = 'Export lifting and turning';
            app.LiftTable = uitable(app.UIFigure);
            app.LiftTable.ColumnName = {'vidName'; 'tLift'; 'tPress'; 'Paw'; 'Turning'};
            app.LiftTable.RowName = {};
            app.PawDropDown = uidropdown(app.UIFigure);
            app.PawDropDown.Items = {'Left', 'Right'};
            app.PawDropDown.Tag = 'Lift';
            app.PawDropDown.Value = 'Left';
            app.LiftingpawLabel = uilabel(app.UIFigure);
            app.LiftingpawLabel.Text = 'Lifting paw';
            app.TurningDropDown = uidropdown(app.UIFigure);
            app.TurningDropDown.Items = {'Left', 'Right'};
            app.TurningDropDown.ValueChangedFcn = createCallbackFcn(app, @TurningDropDownValueChanged, true);
            app.TurningDropDown.Tag = 'Lift';
            app.TurningDropDown.Value = 'Left';
            app.TurningLabel = uilabel(app.UIFigure);
            app.TurningLabel.Text = 'Turning';
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'load existing extracted trajectory'};
            app.LoadButton.Text = ' Load';
            app.PlotrasteroutsideCheckBox = uicheckbox(app.UIFigure);
            app.PlotrasteroutsideCheckBox.Text = 'Plot raster outside';
            app.AutoButton = uibutton(app.UIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Tooltip = {'Automatically determine good tracking'};
            app.AutoButton.Text = 'Auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rVideoViewerSide

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end