classdef DLCAppMS_0929 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        Panel_4                     matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        ReleaseOnsetTextArea        matlab.ui.control.TextArea
        ReleaseonsetButton          matlab.ui.control.Button
        ReleaseDropDown             matlab.ui.control.DropDown
        ReleaseDropDownLabel        matlab.ui.control.Label
        TouchTextArea               matlab.ui.control.TextArea
        TouchButton                 matlab.ui.control.Button
        HoldDropDown                matlab.ui.control.DropDown
        HoldDropDownLabel           matlab.ui.control.Label
        FlexTextArea                matlab.ui.control.TextArea
        FlexButton                  matlab.ui.control.Button
        PressDropDown               matlab.ui.control.DropDown
        PressDropDownLabel          matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        TrialTrackedLabel           matlab.ui.control.Label
        PlottrajectoryButton        matlab.ui.control.Button
        EndEditField                matlab.ui.control.EditField
        EndButton                   matlab.ui.control.Button
        ManualtrackmButton          matlab.ui.control.Button
        StartEditField              matlab.ui.control.EditField
        StartButton                 matlab.ui.control.Button
        ExtractnButton              matlab.ui.control.Button
        Panel_7                     matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        FrameEditField              matlab.ui.control.EditField
        FrameEditFieldLabel         matlab.ui.control.Label
        LinkStartFlexCheckBox       matlab.ui.control.CheckBox
        ExtractFolderEditField      matlab.ui.control.EditField
        ExtractfolderButton         matlab.ui.control.Button
        MetafileEditField           matlab.ui.control.EditField
        MetafileEditFieldLabel      matlab.ui.control.Label
        DLCfileEditField            matlab.ui.control.EditField
        DLCfileEditFieldLabel       matlab.ui.control.Label
        VidnameEditField            matlab.ui.control.EditField
        VidnameEditFieldLabel       matlab.ui.control.Label
        BodypartsDropDown           matlab.ui.control.DropDown
        BodypartsDropDownLabel      matlab.ui.control.Label
        LastvidButton               matlab.ui.control.Button
        ContinueButton              matlab.ui.control.Button
        LoadvideoButton             matlab.ui.control.Button
        UIAxesTrajAll               matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        Panel_3                     matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        GoodtrackingratioEditField  matlab.ui.control.EditField
        GoodtrackingratioEditFieldLabel  matlab.ui.control.Label
        NextvideoButton             matlab.ui.control.Button
        SaveMSButton                matlab.ui.control.Button
        SaveDLCButton               matlab.ui.control.Button
        Slider                      matlab.ui.control.Slider
        Panel_6                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxesQuality               matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        UIAxesTraj                  matlab.ui.control.UIAxes
        UIAxesYT                    matlab.ui.control.UIAxes
        UIAxesXT                    matlab.ui.control.UIAxes
        UIAxesLoc                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        DLCTrackingOut;
        saveindex;
        sizeframe;
        b
        vpath
        VidMeta
        mfile
    end
    
    methods (Access = private)
        
        function getBehaviorData(app,videodate)
            [filem,pathm] = uigetfile({'B_*.mat;bmixed*.mat','ProcessedData';...
                '*Subject*.txt;*DSRT*.mat','UnprocessedData'},...
                ['Select ',videodate,' behavior data'],...
                fullfile(app.vpath,['..',filesep],['..',filesep]),'MultiSelect','on');% med or bpod data
            if ~iscell(filem)
                [~,expName,dataext] = fileparts(filem);
            else
                [~,expName,dataext] = fileparts(filem{1});
            end
            switch dataext
                case '.txt' % Med unprocessed data
                    app.b=track_training_progress_advanced(fullfile(pathm,filem));
%                     app.RTTextArea.Value={num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)-...
%                         app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex))};
                case '.mat'
                    bData = load(fullfile(pathm,[expName,dataext]));
                    if contains(expName,'B_') && ~isfield(bData,'bt') % Med processed data
                        app.b = bData.b;
%                         app.RTTextArea.Value={num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)-...
%                             app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex))};
                    else % Bpod data
                        cfilem = cellstr(filem);
                        btAll = cell(1,length(cfilem));
                        if ~contains(expName,'B_') && ~contains(expName,'bmixed') % Bpod unprocessed data
                            for i=1:length(cfilem)
                                btAll{i} = DSRT_DataExtract_Block(fullfile(pathm,cfilem{i}),false);
                            end
                        else % Bpod processed data
                            for i=1:length(cfilem)
                                bData = load(fullfile(pathm,cfilem{i}));
                                btAll{i} = bData.bt;
                            end
                        end
                        btAll = DSRT_DataMerge_Block(btAll,2); % if choose multiple files, merge it
                        app.b = btAll{1};
%                         app.RTTextArea.Value={num2str(app.b.HT(app.VidMeta.EventIndex).*1000-app.b.FP(app.VidMeta.EventIndex).*1000)};
                    end
            end
        end
        
        function refreshUIAxes(app,this_frame,mode)
            switch nargin
                case 2
                    mode = 'refresh';
                case 3
                    mode = lower(mode);
            end
            axepos = app.UIAxes.Position;
            panpos = app.Panel_6.Position-[0 0 20 20];
            ax_WdivH = panpos(3)/panpos(4);
            vid_WdivH = size(this_frame, 2)/size(this_frame, 1);
            if abs(ax_WdivH-vid_WdivH)<0.01
                newWidth = panpos(3);
                newHeight = panpos(4);
            elseif ax_WdivH>vid_WdivH
                newWidth = panpos(4)*vid_WdivH; % confused, forget why it's okay
                newHeight = panpos(4);
            elseif ax_WdivH<vid_WdivH
                newWidth = panpos(3);
                newHeight = panpos(3)/vid_WdivH;
            end
            axepos(3) = newWidth;
            axepos(4) = newHeight;
            axepos(1) = (panpos(3)-axepos(3))/2;

            set(app.UIAxes, 'position', axepos, 'nextplot', 'add',...
                'xlim',[0 size(this_frame, 2)], 'ylim', [0 size(this_frame, 1)], 'ydir','reverse');
            if strcmp(mode,'init')
                app.UIAxes.UserData{3} = image(app.UIAxes, this_frame);
            else
                app.UIAxes.UserData{3}.CData = this_frame;
            end
        end
        
        function refreshFrame(app,ind_this)
            if isempty(app.UIAxes.UserData)
                return; % not load video yet
            end
            app.FrameEditField.Value =  num2str(ind_this);
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
%             clf
%             sprintf('Plot new frame')
            refreshUIAxes(app,this_frame);

            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            t_frames =  posdata(:, 4);
            
            if lh(ind_this) < 0.8
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                set(app.UIAxesQuality.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
            else
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                set(app.UIAxesQuality.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
            end
            
            set(app.UIAxesLoc.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 15, 'linewidth', 2)
            set(app.UIAxesXT.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
            set(app.UIAxesYT.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))
        end
        
        function videoInit(app,vfilename)
            meta_filename = [vfilename(1:end-4) '.mat'];
            dlcTable_name = dir([vfilename(1:end-4) '*.csv']);
            dlcTable_name = dlcTable_name.name;
            
            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;
            app.LoadvideoButton.UserData.dlcTable = dlcTable_name;
            
            app.VidnameEditField.Value = vfilename(1:end-4);
            app.DLCfileEditField.Value = dlcTable_name(1:end-4);
            app.MetafileEditField.Value = meta_filename;
            
            [D, S] = xlsread(dlcTable_name);
            bodies = S(2, :);
            bodies = setdiff(unique(bodies), {'bodyparts'});
            app.BodypartsDropDown.Items = bodies; 
            % start to update video file
            ind_body = find(strcmp(S(2, :), app.BodypartsDropDown.Value));
            nframes = D(:, 1);
            x_pos   = D(:, ind_body(1));
            y_pos   = D(:, ind_body(2));
            lh      = D(:, ind_body(3));
            
            load(fullfile(app.LoadvideoButton.UserData.vfilepath, app.LoadvideoButton.UserData.metaname));
            app.VidMeta = VidMeta;
            
            t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            app.BodypartsDropDown.UserData = [x_pos, y_pos, lh, t_frames'];
            
            n_goodframes = length(find(lh>0.8));
            n_allframes = length(lh);
            
%             sprintf('Percentage of good frams is %2.1f %%', 100*n_goodframes/n_allframes)
            
            cla(app.UIAxesQuality)
            app.UIAxesQuality.UserData{1} = plot(app.UIAxesQuality,nframes+1, lh, 'ko-');
            set(app.UIAxesQuality, 'xlim', [0 nframes(end)+1], 'ylim', [-0.2 1.2])
            line(app.UIAxesQuality, [0 nframes(end)+1], [0.8 0.8], 'color', 'g', 'linestyle', ':')
            set(app.UIAxesQuality, 'nextplot', 'add')
            
            app.UIAxesQuality.XLabel.String = 'Frame #';
            app.UIAxesQuality.YLabel.String = 'Likelihood';
            
            app.GoodtrackingratioEditField.Value = sprintf('%2.1f %%', 100*n_goodframes/n_allframes);
            
            % plot first frame on the video axis
            cla(app.UIAxes)
            ind_this = 1;
            
            this_video = fullfile(app.LoadvideoButton.UserData.vfilepath, vfilename);
            vidObj = VideoReader(this_video);
            
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames;
            
            this_frame = AllFrames(:, :, :, ind_this);
            app.FrameEditField.Value =  num2str(ind_this);
            refreshUIAxes(app,this_frame,'init');
            
            if lh(ind_this) < 0.8
                app.UIAxes.UserData{2} = plot(app.UIAxes, x_pos(ind_this), y_pos(ind_this), 'ro', 'markersize', 6, 'linewidth', 1);
                app.UIAxesQuality.UserData{2} = plot(app.UIAxesQuality, ind_this, lh(ind_this), 'mo', 'markersize', 6, 'markerfacecolor', 'm', 'linewidth', 1);
            else
                app.UIAxes.UserData{2} = plot(app.UIAxes, x_pos(ind_this), y_pos(ind_this), 'co', 'markersize', 6, 'linewidth', 1);
                app.UIAxesQuality.UserData{2} = plot(app.UIAxesQuality, ind_this, lh(ind_this), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            end
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
            
            % plot pose in ha_traj
            cla(app.UIAxesLoc)
            set(app.UIAxesLoc, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxesLoc, x_pos(i), y_pos(i), 'c+', 'linewidth', 1);
                else
                    plot(app.UIAxesLoc, x_pos(i), y_pos(i), 'k+', 'linewidth', 1);
                end
            end
            
            app.UIAxesLoc.UserData{1} = plot(app.UIAxesLoc, x_pos(ind_this), y_pos(ind_this), 'm+', 'markersize', 12,'markerfacecolor', 'm', 'linewidth', 2);
            
            widV = size(this_frame,2);
            heiV = size(this_frame,1);
            app.UIAxesLoc.XLim = [100 min([widV,1000])];
            app.UIAxesLoc.YLim = [100 min([heiV-100,1000])];
            
            % adjust length-width ratio, but it seems unimportant & warning
%             trajpos = app.UIAxesLoc.Position;
%             WdivH_Ax3 = trajpos(3)/trajpos(4);
%             WdivH_fig = abs(diff(app.UIAxesLoc.XLim)/diff(app.UIAxesLoc.YLim));
%             if abs(diff([WdivH_Ax3,WdivH_fig]))<0.01
%                 newwidth = trajpos(3);
%                 newheight = trajpos(4);
%             elseif WdivH_Ax3>WdivH_fig
%                 newwidth = trajpos(3)*WdivH_fig;
%                 newheight = trajpos(4);
%             elseif WdivH_Ax3<WdivH_fig
%                 newwidth = trajpos(3);
%                 newheight = trajpos(4)/WdivH_fig;
%             end
%             trajpos(3) = newwidth;
%             trajpos(4) = newheight;
%             app.UIAxesLoc.Position = trajpos;
            
            % plot pose  x vs t
            cla(app.UIAxesXT)
            set(app.UIAxesXT, 'nextplot', 'add')
            app.UIAxesXT.UserData = [];
            app.UIAxesXT.UserData{1} = plot(app.UIAxesXT, t_frames, x_pos, 'ko');
            app.UIAxesXT.YLabel.String = 'X Position';
            app.UIAxesXT.XLabel.String = 'Time (ms)';
            app.UIAxesXT.UserData{2} = plot(app.UIAxesXT, t_frames(ind_this), x_pos(ind_this), 'm+', 'markersize', 12, 'linewidth', 2);
            app.UIAxesXT.XLim = [t_frames(1) t_frames(end)];

            % plot pose  y vs t
            cla(app.UIAxesYT)
            set(app.UIAxesYT, 'nextplot', 'add')
            app.UIAxesYT.UserData = [];
            app.UIAxesYT.UserData{1} = plot(app.UIAxesYT, t_frames, y_pos, 'ko');
            app.UIAxesYT.YLabel.String = 'Y Position';
            app.UIAxesYT.XLabel.String = 'Time (ms)';
            app.UIAxesYT.UserData{2} = plot(app.UIAxesYT, t_frames(ind_this), y_pos(ind_this),  'm+', 'markersize', 12, 'linewidth', 2);
            app.UIAxesYT.XLim = [t_frames(1) t_frames(end)];

            cla(app.UIAxesTraj)
            app.UIAxesTraj.XLim = [100 min([widV,1000])];
            app.UIAxesTraj.YLim = [100 min([heiV-100,1000])];
            app.UIAxesTraj.NextPlot = 'Add';
            
            app.SaveDLCButton.Text = {'SaveDLC'};
            
            SaveCache(app);
        end
        
        function SaveCache(app)
            AllFiles = dir('*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,app.LoadvideoButton.UserData.vfilename));
            
            if ~isfolder(fullfile(fileparts(app.mfile),'cache'))
                mkdir(fullfile(fileparts(app.mfile),'cache'));
            end
            cache.savetime=datestr(now,'yyyymmdd_HH_MM_SS');
            cache.path=app.vpath;
            
            file=app.LoadvideoButton.UserData.metaname(1:end-4);
            index=find(file=='_',1,'first');
            cache.name=file(1:index-1);
            cache.filename=AllVidFiles(current_indx);
            cache.date=file(index+1:index+8);
            cache.index_current=current_indx;
            save(fullfile(fileparts(app.mfile),'cache','last_cache.mat'),'cache');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mfile = mfilename('fullpath');
            set(app.UIFigure,'renderer','opengl');
            
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxesQuality.Toolbar.Visible = 'off';
            app.UIAxesLoc.Toolbar.Visible = 'off';
            app.UIAxesXT.Toolbar.Visible = 'off';
            app.UIAxesYT.Toolbar.Visible = 'off';
            app.UIAxesTraj.Toolbar.Visible = 'off';
            app.UIAxesTrajAll.Toolbar.Visible = 'off';
        end

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)
            % open selection
            [vfilename,app.vpath,indx] = uigetfile('*.avi', 'Select a file') ;
            if ~indx
                return;
            end
            app.LoadvideoButton.UserData.vfilepath = app.vpath;
            
            % e.g., Lamar_20210218_Press001.avi
            meta_filename = [vfilename(1:end-4) '.mat'];
            index=find(meta_filename=='_',1,'first');
            videodate=meta_filename(index+1:index+8);
            
            cd(app.vpath);
            videoInit(app,vfilename);
            app.saveindex=1;
            
            getBehaviorData(app,videodate);
%             [filem,pathm] = uigetfile('*.txt',...
%                 [videodate,'med data']);% med txt
%             app.b=my_track_training_progress_advanced([pathm,filem]);
        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)
            AllFiles = dir('*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,app.LoadvideoButton.UserData.vfilename));
            
            if current_indx == length(AllVidFiles)
                return
            end
            
            next_indx = current_indx + 1;
            vfilename = AllVidFiles{next_indx};
            
            videoInit(app,vfilename);
        end

        % Button pushed function: LastvidButton
        function LastvidButtonPushed(app, event)
            AllFiles = dir('*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,app.LoadvideoButton.UserData.vfilename));
            
            if current_indx == 1
                return
            end
            
            next_indx = current_indx - 1;
            vfilename = AllVidFiles{next_indx};
            
            videoInit(app,vfilename);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            if ~isfile(fullfile(fileparts(app.mfile),'cache','last_cache.mat'))
                uialert(app.UIFigure,'Cache not found','Invalid File');
                return;
            end
            load(fullfile(fileparts(app.mfile),'cache','last_cache.mat'),'cache');
            vfilename=cell2mat(cache.filename);
            app.vpath=cache.path;
            app.LoadvideoButton.UserData.vfilepath = app.vpath;
            
            % e.g., Lamar_20210218_Press001.avi
            meta_filename = [vfilename(1:end-4) '.mat'];
            index=find(meta_filename=='_',1,'first');
            videodate=meta_filename(index+1:index+8);
            
            cd(app.vpath);
            videoInit(app,vfilename);
            app.saveindex=1;
            
            getBehaviorData(app,videodate);
%             [filem,pathm] = uigetfile('*.txt',...
%                 [videodate,'med data']);% med txt
%             app.b=my_track_training_progress_advanced([pathm,filem]);
        end

        % Value changed function: BodypartsDropDown
        function BodypartsDropDownValueChanged(app, event)
            if isempty(app.LoadvideoButton.UserData)
                return;
            end
            vfilename = app.LoadvideoButton.UserData.vfilename;
            videoInit(app,vfilename);
        end

        % Button pushed function: ManualtrackmButton
        function ManualtrackmButtonPushed(app, event)
            % change the location of body part
            value = app.Slider.Value;
            ind_this = round(value);
            
            % draw point on UIAxes
            h = drawpoint(app.UIAxes,'LabelVisible', 'off'); % user-defined body location
            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            t_frames =  posdata(:, 4);
            x_pos(ind_this) = h.Position(1);
            y_pos(ind_this) = h.Position(2);
            lh(ind_this)    = 1;
            
            posdata = [x_pos y_pos lh t_frames];
            
            app.BodypartsDropDown.UserData = posdata;
            
            if lh(ind_this) < 0.8
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'r' )
                set(app.UIAxesQuality.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'r')
            else
                set(app.UIAxes.UserData{2}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'color', 'c' )
                set(app.UIAxesQuality.UserData{2}, 'XData', ind_this, 'YData', lh(ind_this), 'color', 'c')
            end
            
            set(app.UIAxesLoc.UserData{1}, 'XData', x_pos(ind_this), 'YData', y_pos(ind_this), 'marker', '+', 'markersize', 12, 'linewidth', 2)
            set(app.UIAxesXT.UserData{2}, 'XData', t_frames(ind_this), 'YData', x_pos(ind_this))
            set(app.UIAxesYT.UserData{2}, 'XData', t_frames(ind_this), 'YData', y_pos(ind_this))
            
            % update
            set(app.UIAxesQuality.UserData{1}, 'XData', 1:length(lh), 'YData', lh);
            
            FrameBeg = str2double(app.StartEditField.Value);
            FrameEnd = str2double(app.EndEditField.Value);
            
            if ~isnan(FrameBeg) && ~isnan(FrameEnd)
                x_pos_selected = x_pos(FrameBeg:FrameEnd);
                y_pos_selected = y_pos(FrameBeg:FrameEnd);
                t_frames_selected = t_frames(FrameBeg:FrameEnd);
            end
            
            if length(app.UIAxesXT.UserData{1}.XData)==length(t_frames)
                set(app.UIAxesXT.UserData{1}, 'XData', t_frames, 'YData', x_pos);
                set(app.UIAxesYT.UserData{1}, 'XData', t_frames, 'YData', y_pos);
            else
                set(app.UIAxesXT.UserData{1}, 'XData', t_frames_selected, 'YData', x_pos_selected);
                set(app.UIAxesYT.UserData{1}, 'XData', t_frames_selected, 'YData', y_pos_selected);
            end
            
            if length(app.UIAxesXT.UserData)>2
                set(app.UIAxesXT.UserData{3}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                set(app.UIAxesXT.UserData{4}, 'XData', t_frames_selected, 'YData', x_pos_selected)
                set(app.UIAxesYT.UserData{3}, 'XData', t_frames_selected, 'YData', y_pos_selected)
                set(app.UIAxesYT.UserData{4}, 'XData', t_frames_selected, 'YData', y_pos_selected)
            end
            
            AllChildren = get(app.UIAxes, 'Children');
            delete(AllChildren(1));
        end

        % Button pushed function: PlottrajectoryButton
        function PlottrajectoryButtonPushed(app, event)
            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);

            x_pos_full = posdata(:, 1);
            y_pos_full = posdata(:, 2);

            x_pos = posdata(FrameBeg:FrameEnd, 1);
            y_pos = posdata(FrameBeg:FrameEnd, 2);
            
            load(fullfile(app.LoadvideoButton.UserData.vfilepath,app.LoadvideoButton.UserData.metaname));
            t_frames = VidMeta.FrameTimesB - VidMeta.EventTime;
            t_frames_selected = t_frames(FrameBeg:FrameEnd);

            ind_this  = round(app.Slider.Value);
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            %
            cla(app.UIAxesTraj)
            
            app.UIAxesTraj.NextPlot = 'add';            
            % plot the image first            
            app.UIAxesTraj.UserData{1} = image(app.UIAxesTraj, this_frame);
            app.sizeframe=size(this_frame);
            app.UIAxesTraj.XLim = [100 min([app.sizeframe(2),1000])];
            app.UIAxesTraj.YLim = [100 min([app.sizeframe(1)-100,1000])];
            app.UIAxesTraj.YDir = 'reverse';

%             trajpos6 = app.UIAxesTraj.Position;
%             width = trajpos6(3);
%             newheight = width*diff(app.UIAxesTraj.YLim)/diff(app.UIAxesTraj.XLim);
%             trajpos6(4) = newheight;
%             app.UIAxesTraj.Position = trajpos6;
            
            app.UIAxesTraj.UserData{2} = scatter( app.UIAxesTraj, x_pos, y_pos, 30, t_frames_selected,'filled', 'markeredgecolor', 'w','linewidth', 0.25);

            %  cla(app.UIAxesXT)
            app.UIAxesXT.XLim = [t_frames_selected(1) t_frames_selected(end)];
            app.UIAxesYT.XLim = [t_frames_selected(1) t_frames_selected(end)];

            cla(app.UIAxesXT)
            app.UIAxesXT.XDir = 'reverse';
            app.UIAxesXT.YDir = 'reverse';

            app.UIAxesXT.UserData{1} = scatter(app.UIAxesXT, t_frames_selected, x_pos, 30, t_frames_selected,'filled', 'markeredgecolor', 'w','linewidth', 0.25);
            app.UIAxesXT.UserData{2} = plot(app.UIAxesXT, t_frames(ind_this), x_pos_full(ind_this), 'm+', 'markersize', 15, 'linewidth', 2);
            cla(app.UIAxesYT)
            app.UIAxesYT.XDir = 'reverse';
            app.UIAxesYT.YDir = 'reverse';
            app.UIAxesYT.UserData{1} = scatter(app.UIAxesYT, t_frames_selected, y_pos, 30, t_frames_selected,'filled', 'markeredgecolor', 'w','linewidth', 0.25);
            app.UIAxesYT.UserData{2} = plot(app.UIAxesYT, t_frames(ind_this), y_pos_full(ind_this), 'm+', 'markersize', 15, 'linewidth', 2);

            app.UIAxesXT.YLim = [100 min([app.sizeframe(2)-100,1000])];
            app.UIAxesYT.YLim = [100 min([app.sizeframe(1)-100,1000])];

        end

        % Button pushed function: SaveDLCButton
        function SaveDLCButtonPushed(app, event)
            %             Session: '2021-02-18-14h19m-Subject Lamar'
            %             Event: 'Press'
            %             EventIndex: 129
            %             Performance: 'Correct'
            %             EventTime: 1787750
            %             FrameTimesB: [1×249 double]
            %             VideoOrg: 'Cam_00D41932971+2021_2_18_14_20_34.5797602++.avi'
            %             FrameIndx: [1×249 double]
            %             Code: 'G:\ZqWork\Code\YUcode\Functions\ExportVideoFiles'
            %             CreatedOn: '01-Jul-2021'
            %             DLCTrackingOut = [];
            
            meta_filename = app.LoadvideoButton.UserData.metaname;
            body_part_name = app.BodypartsDropDown.Value;
            load(meta_filename);  % this will launch VidMeta
            
            posdata = app.BodypartsDropDown.UserData;
            FrameBeg = str2num(app.StartEditField.Value);
            FrameEnd = str2num(app.EndEditField.Value);
            
            IndxSelected = round(linspace(FrameBeg, FrameEnd, 5));
            %             this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            if ~isfolder([app.vpath,'VideoData\'])
                mkdir([app.vpath,'VideoData\']);
            end

            
            if  ~isempty(dir([app.vpath,'\VideoData\','*DLC.mat']))
                if app.saveindex==1
                    filepath = [app.vpath,'\VideoData\'];
                    filename=dir([filepath ['*DLC.mat']]);
                    for i =1:length(filename)
                        file=filename(i).name;
                        load(fullfile(filepath,file));
                        if i==1
                            app.DLCTrackingOut = DLCSave;
                        else
                            app.DLCTrackingOut.PoseTracking.PosData(end+1)        = DLCSave.PoseTracking.PosData;
                            app.DLCTrackingOut.PoseTracking.EventIndex(:, end+1)  = DLCSave.PoseTracking.EventIndex;
                            app.DLCTrackingOut.PoseTracking.Performance(end+1)    = DLCSave.PoseTracking.Performance;
                            app.DLCTrackingOut.PoseTracking.Images(end+1)         = DLCSave.PoseTracking.Images;
                            app.DLCTrackingOut.PoseTracking.ImagesIndex(end+1)    = DLCSave.PoseTracking.ImagesIndex;
                        end
                    end
                    app.saveindex=0;
                end
                % load DLCTracingOut
                % find index of body parts
                ind_body =find(strcmp(app.DLCTrackingOut.BodyParts, body_part_name));

                % new index
                if isempty(find(app.DLCTrackingOut.PoseTracking(ind_body).EventIndex(1, :)==VidMeta.EventIndex, 1))
                    app.DLCTrackingOut.PoseTracking(ind_body).PosData{end+1}       = posdata(FrameBeg:FrameEnd, :);
                    app.DLCTrackingOut.PoseTracking(ind_body).EventIndex(:, end+1) = [VidMeta.EventIndex; VidMeta.EventTime];
                    app.DLCTrackingOut.PoseTracking(ind_body).Performance{end+1}   = VidMeta.Performance;
                    app.DLCTrackingOut.PoseTracking(ind_body).Images{end+1}        = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                    app.DLCTrackingOut.PoseTracking(ind_body).ImagesIndex{end+1}   = IndxSelected-FrameBeg+1;

                else
                    ind_old = find(app.DLCTrackingOut.PoseTracking(ind_body).EventIndex(1, :)==VidMeta.EventIndex);
                    app.DLCTrackingOut.PoseTracking(ind_body).PosData{ind_old}       = posdata(FrameBeg:FrameEnd, :);
                    app.DLCTrackingOut.PoseTracking(ind_body).EventIndex(:, ind_old) = [VidMeta.EventIndex; VidMeta.EventTime];
                    app.DLCTrackingOut.PoseTracking(ind_body).Performance{ind_old}   = VidMeta.Performance;
                    app.DLCTrackingOut.PoseTracking(ind_body).Images{ind_old}        = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                    app.DLCTrackingOut.PoseTracking(ind_body).ImagesIndex{ind_old}   = IndxSelected-FrameBeg+1;
                end
                DLCSave = [];
                DLCSave.Session = VidMeta.Session;
                DLCSave.Event = VidMeta.Event;
                DLCSave.BodyParts{1} = body_part_name;
                DLCSave.PoseTracking(1).PosInfo = {'x', 'y', 'time(ms)', 'likelihood'};

                DLCSave.PoseTracking(1).PosData{1} = posdata(FrameBeg:FrameEnd, :);
                DLCSave.PoseTracking(1).EventIndex(:, 1) = [VidMeta.EventIndex; VidMeta.EventTime];
                DLCSave.PoseTracking(1).Performance{1} = VidMeta.Performance;
                DLCSave.PoseTracking(1).Images{1} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                DLCSave.PoseTracking(1).ImagesIndex{1} = IndxSelected-FrameBeg+1;
                savename=[app.vpath,'VideoData\',meta_filename(1:end-4),'DLC','.mat'];
                save(savename,'DLCSave')
                DLCSave = [];
                %% Trials tracked
                app.TrialTrackedLabel.Text = sprintf('tracked: %2.0d', size(app.DLCTrackingOut.PoseTracking(ind_body).EventIndex, 2));
            else
                app.DLCTrackingOut = [];
                app.DLCTrackingOut.Session = VidMeta.Session;
                app.DLCTrackingOut.Event = VidMeta.Event;
                app.DLCTrackingOut.BodyParts{1} = body_part_name;
                ind_body = 1;
                app.DLCTrackingOut.PoseTracking(1).PosInfo = {'x', 'y', 'time(ms)', 'likelihood'};

                app.DLCTrackingOut.PoseTracking(1).PosData{1} = posdata(FrameBeg:FrameEnd, :);
                app.DLCTrackingOut.PoseTracking(1).EventIndex(:, 1) = [VidMeta.EventIndex; VidMeta.EventTime];
                app.DLCTrackingOut.PoseTracking(1).Performance{1} = VidMeta.Performance;
                app.DLCTrackingOut.PoseTracking(1).Images{1} = app.UIAxes.UserData{1}(:, :, :,IndxSelected);
                app.DLCTrackingOut.PoseTracking(1).ImagesIndex{1} = IndxSelected-FrameBeg+1;
                DLCSave=app.DLCTrackingOut;
                savename=[app.vpath,'VideoData\',meta_filename(1:end-4),'DLC','.mat'];
                save(savename,'DLCSave')
                DLCSave = [];
                %% Trials tracked
                app.TrialTrackedLabel.Text = sprintf('tracked: %2.0d', size(app.DLCTrackingOut.PoseTracking(1).EventIndex, 2));
            end
            
            app.SaveDLCButton.Text = {'DLC Saved'};

            app.UIAxesTrajAll.XLim = [100 min(app.sizeframe(2),1000)];
            app.UIAxesTrajAll.YLim = [100 min(app.sizeframe(1)-100,1000)];
            app.UIAxesTrajAll.NextPlot = 'Add';
            app.UIAxesTrajAll.YDir = 'reverse';
            delete(get(app.UIAxesTrajAll, 'children'));

            % plot an image
            image(app.UIAxesTrajAll, app.DLCTrackingOut.PoseTracking(ind_body).Images{end}(:, :, :, 3));

            % plot all tracked trials
            for i = 1:length(app.DLCTrackingOut.PoseTracking.PosData)
                tframes = app.DLCTrackingOut.PoseTracking.PosData{i}(:, 3);
                indframes = find(tframes<=500);
                plot(app.UIAxesTrajAll, app.DLCTrackingOut.PoseTracking.PosData{i}(indframes, 1), app.DLCTrackingOut.PoseTracking.PosData{i}(indframes, 2), 'color', [0.8 0.8 0.8]);
            end
            plot(app.UIAxesTrajAll, app.DLCTrackingOut.PoseTracking.PosData{end}(indframes, 1), app.DLCTrackingOut.PoseTracking.PosData{end}(indframes, 2), 'color', [1 0.8 0], 'linewidth', 2);
        end

        % Button pushed function: SaveMSButton
        function SaveMSButtonPushed(app, event)
            %save
            VidAnno=struct;
            file=app.LoadvideoButton.UserData.metaname(1:end-4);
            index=find(file=='_',1,'first');
            VidAnno.RatName=file(1:index-1);
            VidAnno.SessionDate=file(index+1:index+8);
            VidAnno.PressIndex=app.VidMeta.EventIndex;
            VidAnno.PressTime=app.VidMeta.EventTime;
%             if isempty(app.StartEditField.Value)
%                 VidAnno.Flex=str2double(app.StartEditField.Value);
%             else
%                 VidAnno.Flex=app.VidMeta.FrameTimesB(str2double(app.StartEditField.Value))-app.VidMeta.EventTime;
%             end
            VidAnno.Press=app.PressDropDown.Value;
            VidAnno.Hold=app.HoldDropDown.Value;
            VidAnno.Release=app.ReleaseDropDown.Value;
            VidAnno.Performance=app.VidMeta.Performance;
%             VidAnno.RT=num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)-app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex));
            switch class(app.b)
                case 'struct'
                    VidAnno.RT = num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)-app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex));
                    VidAnno.FP=app.b.FPs(app.VidMeta.EventIndex);
                case 'table'
                    VidAnno.RT = num2str(app.b.HT(app.VidMeta.EventIndex).*1000-app.b.FP(app.VidMeta.EventIndex).*1000);
                    VidAnno.FP=app.b.FP(app.VidMeta.EventIndex);
            end
            VidAnno.Flex=str2double(app.FlexTextArea.Value);
            VidAnno.Touch=str2double(app.TouchTextArea.Value);
            VidAnno.ReleaseOnset=str2double(app.ReleaseOnsetTextArea.Value);
            if ~isfolder([app.vpath,'VideoData\'])
                mkdir([app.vpath,'VideoData\']);
            end
            save([app.vpath,'VideoData\',app.LoadvideoButton.UserData.metaname(1:end-4),'Annotated'],'VidAnno')
            
%             SaveCache(app);
        end

        % Button pushed function: ExtractfolderButton
        function ExtractfolderButtonPushed(app, event)
           Epath = uigetdir('*.*','Choose a folder to save bad frames') ;
           app.ExtractFolderEditField.Value=Epath;
        end

        % Button pushed function: ExtractnButton
        function ExtractnButtonPushed(app, event)
            if ~isempty(app.ExtractFolderEditField.Value)
                imwrite(app.UIAxes.UserData{1}(:, :, :,str2num(app.FrameEditField.Value)) ,strcat(app.ExtractFolderEditField.Value,'\',app.VidnameEditField.Value,'_',app.FrameEditField.Value,'.jpg'),'jpg');
            else
                imwrite(app.UIAxes.UserData{1}(:, :, :,str2num(app.FrameEditField.Value)) ,strcat(app.VidnameEditField.Value,'_',app.FrameEditField.Value,'.jpg'),'jpg');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            if app.LinkStartFlexCheckBox.Value
                app.StartEditField.Value = num2str(ind_this);
                app.FlexTextArea.Value=num2str(app.VidMeta.FrameTimesB(ind_this)-app.VidMeta.EventTime);
            else
                app.StartEditField.Value = num2str(ind_this);
            end
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.EndEditField.Value = num2str(ind_this);
        end

        % Button pushed function: FlexButton
        function FlexButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            if app.LinkStartFlexCheckBox.Value
                app.StartEditField.Value = num2str(ind_this);
                app.FlexTextArea.Value=num2str(app.VidMeta.FrameTimesB(ind_this)-app.VidMeta.EventTime);
            else
                app.FlexTextArea.Value=num2str(app.VidMeta.FrameTimesB(ind_this)-app.VidMeta.EventTime);
            end
        end

        % Button pushed function: TouchButton
        function TouchButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.TouchTextArea.Value=num2str(app.VidMeta.FrameTimesB(ind_this)-app.VidMeta.EventTime);
        end

        % Button pushed function: ReleaseonsetButton
        function ReleaseonsetButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.ReleaseOnsetTextArea.Value=num2str(app.VidMeta.FrameTimesB(ind_this)-app.VidMeta.EventTime);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            if isempty(app.UIAxes.UserData)
                return;
            end
            
            newvalue = str2double(app.FrameEditField.Value);
            newvalue = min([max([1 newvalue]),size(app.UIAxes.UserData{1},4)]);
            app.Slider.Value = newvalue;
            value = newvalue;
            
            ind_this = round(value);
            refreshFrame(app,ind_this)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value; % double
            ind_this = round(value);

            refreshFrame(app,ind_this)
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            if isempty(app.UIAxes.UserData)
                return;
            end
            
            verticalScrollCount = event.VerticalScrollCount;
            
            value = app.Slider.Value;
            newvalue = value + verticalScrollCount;
            newvalue = min([max([1 newvalue]),size(app.UIAxes.UserData{1},4)]);
            app.Slider.Value = newvalue;
            value = newvalue;
            
            ind_this = round(value);
            refreshFrame(app,ind_this)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;

            value = app.Slider.Value;
            switch key
                case 'rightarrow'
                    newvalue = value + 1;
                    newvalue = min([newvalue,size(app.UIAxes.UserData{1},4)]);
                    app.Slider.Value = newvalue;
                    value = newvalue;
                    ind_this = round(value);
                    refreshFrame(app,ind_this)
                case 'leftarrow'
                    newvalue = value-1;
                    newvalue = max([1,newvalue]);
                    app.Slider.Value = newvalue;
                    value = newvalue;
                    ind_this = round(value);
                    refreshFrame(app,ind_this)
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'm' % manual track
                    ManualtrackmButtonPushed(app);
                case 'n'
                    ExtractnButtonPushed(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {750, 750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x', 300};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.UIAxesTrajAll = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesTrajAll, 'X')
            ylabel(app.UIAxesTrajAll, 'Y')
            app.UIAxesTrajAll.Tag = 'trialsaccumulated';
            app.Panel_7 = uipanel(app.GridLayout4);
            app.GridLayout8 = uigridlayout(app.Panel_7);
            app.LoadvideoButton = uibutton(app.GridLayout8, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.ContinueButton = uibutton(app.GridLayout8, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.LastvidButton = uibutton(app.GridLayout8, 'push');
            app.LastvidButton.ButtonPushedFcn = createCallbackFcn(app, @LastvidButtonPushed, true);
            app.LastvidButton.Text = 'Last vid';
            app.BodypartsDropDownLabel = uilabel(app.GridLayout8);
            app.BodypartsDropDownLabel.Text = 'Body parts';
            app.BodypartsDropDown = uidropdown(app.GridLayout8);
            app.BodypartsDropDown.Items = {'L_finger5', 'L_finger4', 'L_hand', 'R_finger2', 'R_finger3', 'R_hand', 'Neck', 'Eye', 'EarL', 'EarR', 'Nose', 'LeverBase', 'LeverEnd'};
            app.BodypartsDropDown.ValueChangedFcn = createCallbackFcn(app, @BodypartsDropDownValueChanged, true);
            app.BodypartsDropDown.Tag = 'BodyParts';
            app.BodypartsDropDown.Value = 'L_finger5';
            app.VidnameEditFieldLabel = uilabel(app.GridLayout8);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.VidnameEditField = uieditfield(app.GridLayout8, 'text');
            app.VidnameEditField.Tag = 'vidfilename';
            app.DLCfileEditFieldLabel = uilabel(app.GridLayout8);
            app.DLCfileEditFieldLabel.Text = {'DLC file'; ''};
            app.DLCfileEditField = uieditfield(app.GridLayout8, 'text');
            app.DLCfileEditField.Tag = 'DLCfile';
            app.MetafileEditFieldLabel = uilabel(app.GridLayout8);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.MetafileEditField = uieditfield(app.GridLayout8, 'text');
            app.ExtractfolderButton = uibutton(app.GridLayout8, 'push');
            app.ExtractfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractfolderButtonPushed, true);
            app.ExtractfolderButton.Text = 'Extract folder';
            app.ExtractFolderEditField = uieditfield(app.GridLayout8, 'text');
            app.ExtractFolderEditField.Tag = 'DLCfile';
            app.LinkStartFlexCheckBox = uicheckbox(app.GridLayout8);
            app.LinkStartFlexCheckBox.Text = 'Link Start & Flex';
            app.LinkStartFlexCheckBox.Value = true;
            app.FrameEditFieldLabel = uilabel(app.GridLayout8);
            app.FrameEditFieldLabel.Text = 'Frame#';
            app.FrameEditField = uieditfield(app.GridLayout8, 'text');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tag = 'framenum';
            app.Panel = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.ExtractnButton = uibutton(app.GridLayout5, 'push');
            app.ExtractnButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractnButtonPushed, true);
            app.ExtractnButton.Text = {'Extract (n)'; ''};
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StartEditField = uieditfield(app.GridLayout5, 'text');
            app.ManualtrackmButton = uibutton(app.GridLayout5, 'push');
            app.ManualtrackmButton.ButtonPushedFcn = createCallbackFcn(app, @ManualtrackmButtonPushed, true);
            app.ManualtrackmButton.Text = 'Manual track (m)';
            app.EndButton = uibutton(app.GridLayout5, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.EndEditField = uieditfield(app.GridLayout5, 'text');
            app.PlottrajectoryButton = uibutton(app.GridLayout5, 'push');
            app.PlottrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlottrajectoryButtonPushed, true);
            app.PlottrajectoryButton.Text = {'Plot trajectory'; ''};
            app.TrialTrackedLabel = uilabel(app.GridLayout5);
            app.TrialTrackedLabel.Text = {'TrialTracked'; ''};
            app.Panel_4 = uipanel(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.Panel_4);
            app.PressDropDownLabel = uilabel(app.GridLayout6);
            app.PressDropDownLabel.Text = 'Press';
            app.PressDropDown = uidropdown(app.GridLayout6);
            app.PressDropDown.Items = {'nan', 'L', 'R', 'B'};
            app.PressDropDown.Value = 'nan';
            app.FlexButton = uibutton(app.GridLayout6, 'push');
            app.FlexButton.ButtonPushedFcn = createCallbackFcn(app, @FlexButtonPushed, true);
            app.FlexButton.Text = 'Flex';
            app.FlexTextArea = uitextarea(app.GridLayout6);
            app.HoldDropDownLabel = uilabel(app.GridLayout6);
            app.HoldDropDownLabel.Text = 'Hold';
            app.HoldDropDown = uidropdown(app.GridLayout6);
            app.HoldDropDown.Items = {'nan', 'L', 'R', 'B'};
            app.HoldDropDown.Value = 'nan';
            app.TouchButton = uibutton(app.GridLayout6, 'push');
            app.TouchButton.ButtonPushedFcn = createCallbackFcn(app, @TouchButtonPushed, true);
            app.TouchButton.Text = 'Touch';
            app.TouchTextArea = uitextarea(app.GridLayout6);
            app.ReleaseDropDownLabel = uilabel(app.GridLayout6);
            app.ReleaseDropDownLabel.Text = 'Release';
            app.ReleaseDropDown = uidropdown(app.GridLayout6);
            app.ReleaseDropDown.Items = {'nan', 'L', 'R', 'B'};
            app.ReleaseDropDown.Value = 'nan';
            app.ReleaseonsetButton = uibutton(app.GridLayout6, 'push');
            app.ReleaseonsetButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseonsetButtonPushed, true);
            app.ReleaseonsetButton.Text = {'Release'; 'onset'};
            app.ReleaseOnsetTextArea = uitextarea(app.GridLayout6);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.CenterPanel);
            app.Panel_6 = uipanel(app.GridLayout7);
            app.UIAxes = uiaxes(app.Panel_6);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YDir = 'reverse';
            app.Panel_3 = uipanel(app.GridLayout7);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.UIAxesQuality = uiaxes(app.GridLayout3);
            title(app.UIAxesQuality, {'Tracking quality'; ''})
            xlabel(app.UIAxesQuality, 'X')
            ylabel(app.UIAxesQuality, 'Y')
            app.UIAxesQuality.Tag = 'LabelingQuality';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [1 101];
            app.Slider.MajorTicks = [1 21 41 61 81 101 121 141 161 181 201 221 241 261 281 301 321 341 361 381 401 421 441 461 481 501 521 541 561 581 601 621 641 661 681 701 721 741 761 781 801 821 841 861 881 901 921 941 961 981];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 6 11 16 21 26 31 36 41 46 51 56 61 66 71 76 81 86 91 96 101 106 111 116 121 126 131 136 141 146];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
            app.Slider.Tag = 'videoslider';
            app.Slider.Value = 1;
            app.SaveDLCButton = uibutton(app.GridLayout3, 'push');
            app.SaveDLCButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDLCButtonPushed, true);
            app.SaveDLCButton.Text = {'SaveDLC'; ''};
            app.SaveMSButton = uibutton(app.GridLayout3, 'push');
            app.SaveMSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMSButtonPushed, true);
            app.SaveMSButton.Text = 'SaveMS';
            app.NextvideoButton = uibutton(app.GridLayout3, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.GoodtrackingratioEditFieldLabel = uilabel(app.GridLayout3);
            app.GoodtrackingratioEditFieldLabel.Text = 'Good tracking ratio';
            app.GoodtrackingratioEditField = uieditfield(app.GridLayout3, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxesLoc = uiaxes(app.GridLayout2);
            title(app.UIAxesLoc, 'Marker location')
            xlabel(app.UIAxesLoc, 'X')
            ylabel(app.UIAxesLoc, 'Y')
            app.UIAxesLoc.YDir = 'reverse';
            app.UIAxesLoc.Tag = 'ha_traj';
            app.UIAxesXT = uiaxes(app.GridLayout2);
            xlabel(app.UIAxesXT, 'Time')
            ylabel(app.UIAxesXT, 'X position')
            app.UIAxesYT = uiaxes(app.GridLayout2);
            xlabel(app.UIAxesYT, 'Time')
            ylabel(app.UIAxesYT, 'Y position')
            app.UIAxesTraj = uiaxes(app.GridLayout2);
            title(app.UIAxesTraj, 'Reach trajectory')
            xlabel(app.UIAxesTraj, 'X')
            ylabel(app.UIAxesTraj, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLCAppMS_0929

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end